classdef LCconverter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LabCharttoMatlabFileConverterUIFigure  matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        OpenMenu                      matlab.ui.container.Menu
        PlotLengthsec3EditField       matlab.ui.control.NumericEditField
        PlotLengthsec3EditFieldLabel  matlab.ui.control.Label
        ChanFsHz3EditField            matlab.ui.control.NumericEditField
        ChanFsHz3EditFieldLabel       matlab.ui.control.Label
        ComChan3DropDown              matlab.ui.control.DropDown
        ComChan3DropDownLabel         matlab.ui.control.Label
        ComID3DropDown                matlab.ui.control.DropDown
        ComID3DropDownLabel           matlab.ui.control.Label
        ExportButton3                 matlab.ui.control.Button
        DisplayButton3                matlab.ui.control.Button
        NoteTextArea                  matlab.ui.control.TextArea
        NoteTextAreaLabel             matlab.ui.control.Label
        ExportButton2                 matlab.ui.control.Button
        ExportButton1                 matlab.ui.control.Button
        MarkerSizeDropDown            matlab.ui.control.DropDown
        MarkerSizeDropDownLabel       matlab.ui.control.Label
        MarkerDropDown                matlab.ui.control.DropDown
        MarkerDropDownLabel           matlab.ui.control.Label
        LineWidthDropDown             matlab.ui.control.DropDown
        LineWidthDropDownLabel        matlab.ui.control.Label
        PlotLengthsec2EditField       matlab.ui.control.NumericEditField
        PlotLengthsec2EditFieldLabel  matlab.ui.control.Label
        ChanFsHz2EditField            matlab.ui.control.NumericEditField
        ChanFsHz2EditFieldLabel       matlab.ui.control.Label
        ComChan2DropDown              matlab.ui.control.DropDown
        ComChan2DropDownLabel         matlab.ui.control.Label
        ComID2DropDown                matlab.ui.control.DropDown
        ComID2DropDownLabel           matlab.ui.control.Label
        DisplayButton2                matlab.ui.control.Button
        TabGroup                      matlab.ui.container.TabGroup
        DataInformationTab            matlab.ui.container.Tab
        DataTable                     matlab.ui.control.Table
        ChannelInformationTab         matlab.ui.container.Tab
        ChannelTable                  matlab.ui.control.Table
        DisplayButton1                matlab.ui.control.Button
        PlotLengthsec1EditField       matlab.ui.control.NumericEditField
        PlotLengthsec1EditFieldLabel  matlab.ui.control.Label
        ChanFsHz1EditField            matlab.ui.control.NumericEditField
        ChanFsHz1EditFieldLabel       matlab.ui.control.Label
        ComChan1DropDown              matlab.ui.control.DropDown
        ComChan1DropDownLabel         matlab.ui.control.Label
        ComID1DropDown                matlab.ui.control.DropDown
        ComID1DropDownLabel           matlab.ui.control.Label
        FileNameEditField             matlab.ui.control.EditField
        FileNameEditFieldLabel        matlab.ui.control.Label
        DirectoryEditField            matlab.ui.control.EditField
        DirectoryEditFieldLabel       matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        
        channelNum % The number of channel
        blockNum % The number of block
        samplerate % A column varialbe - sample rate for each channel
        
        dataCell
        
        commentCell
        commentNum
        
        currentComID1 % ID of the current selected comment - plot 1
        currentComID2
        currentComID3
        
        plotObject1 % The object of plot 1
        plotObject2
        plotObject3
        
        sigDisp1 % The signal 1 for plot 1
        sigDisp2
        sigDisp3
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            progressBar = uiprogressdlg(app.LabCharttoMatlabFileConverterUIFigure, 'Title','Initialising', 'Message','Configuring files', 'Indeterminate','on');
            drawnow
            
            clc
            
            close(progressBar)
            
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            
            [file,path] = uigetfile('*.mat');
            
            if ~ischar(file)
                
                msg = 'Please only choose one ".mat" file';
                uialert(app.LabCharttoMatlabFileConverterUIFigure, msg, 'Warning','Icon','error')
                figure(app.LabCharttoMatlabFileConverterUIFigure)
                return
                
            else
                
                figure(app.LabCharttoMatlabFileConverterUIFigure)
                msg = 'Loading the data';
                progressBar = uiprogressdlg(app.LabCharttoMatlabFileConverterUIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');
                drawnow
                
                app.DirectoryEditField.Value = path;
                app.FileNameEditField.Value = file;
                
                
                % Load original variables in the LabChart-Matlab file ---------------
                DataPack = load( [path,file] ); % 'DataPack' is a structure
                
                blocktimes = DataPack.blocktimes;
                com = DataPack.com;
                comtext = DataPack.comtext;
                data = DataPack.data;
                dataend = DataPack.dataend;
                datastart = DataPack.datastart;
                firstsampleoffset = DataPack.firstsampleoffset;
                rangemax = DataPack.rangemax;
                rangemin = DataPack.rangemin;
                samplerate = DataPack.samplerate;
                tickrate = DataPack.tickrate;
                titles = DataPack.titles;
                unittext = DataPack.unittext;
                unittextmap = DataPack.unittextmap;
                
                
                % Organise data structure ---------------
                progressBar.Message = 'Organising the data';
                drawnow
                
                % Generate 'dataCell'
                [ channelNum, blockNum ] = size(datastart);
                
                dataCell = cell( channelNum+1, blockNum+1 );
                
                for ID = 1 : channelNum
                    dataCell{ ID+1, 1 } = titles(ID, :);
                end
                
                for ID = 1 : blockNum
                    dataCell{ 1, ID+1 } = [ 'Block ', num2str(ID) ];
                end
                
                for channelID = 1 : channelNum
                    for blockID = 1 : blockNum
                        dataCell{ channelID+1, blockID+1 } = data( datastart(channelID, blockID) : dataend(channelID, blockID) );
                    end
                end
                
                
                % Generate 'commnetCell'
                commentNum = size(com,1);
                explainNum = 5;
                explainTitle = { 'ComChan', 'ComBlock', 'ComTickPos', 'ComType (User Comments = 1, Event Markers = 2)', 'ComText' };
                
                commentCell = cell( commentNum+1, explainNum+1 );
                
                for ID = 1 : explainNum
                    commentCell{ 1, ID+1 } = explainTitle{ID};
                end
                
                for ID = 1 : commentNum
                    commentCell{ ID+1 , 1 } = [ 'Comment ', num2str(ID) ];
                    
                    for explainID = 1 : explainNum
                        commentCell{ ID+1 , explainID+1 } = com( ID, explainID );
                        
                        if explainID == 1
                            if isequal( commentCell{ ID+1 , explainID+1 }, -1 )
                                commentCell{ ID+1 , explainID+1 } = 'All';
                            end
                        end
                        
                        if explainID == 5
                            commentCell{ ID+1 , explainID+1 } = comtext( commentCell{ ID+1 , explainID+1 }, : );
                        end
                    end
                end
                
                
                % Save the organised data ---------------
                progressBar.Message = 'Saving the organised data';
                drawnow
                
                newFileName = [path,file];
                save(newFileName, 'blocktimes', 'com', 'comtext', 'data', 'dataend', 'datastart', 'firstsampleoffset', ...
                    'rangemax', 'rangemin', 'samplerate', 'tickrate', 'titles', 'unittext', 'unittextmap', ...
                    'dataCell', 'commentCell')
                
                
                % Display information in the table ---------------
                progressBar.Message = 'Updating the table';
                drawnow
                
                app.channelNum = channelNum;
                app.blockNum = blockNum;
                app.samplerate = samplerate;
                app.dataCell = dataCell;
                app.commentCell = commentCell;
                app.commentNum = commentNum;
                
                % Update the table
                ComID = commentCell( 2:end, 1 );
                ComChan = commentCell( 2:end, 2 );
                ComBlock = commentCell( 2:end, 3 );
                ComTickPos = commentCell( 2:end, 4 );
                ComText = commentCell( 2:end, 6 );
                
                app.DataTable.Data = table(ComID, ComChan, ComBlock, ComTickPos, ComText);
                
                
                ChanID = cell( app.channelNum, 1 );
                for i = 1 : app.channelNum
                    ChanID{i} = num2str(i);
                end
                
                ChanName = cellstr(titles);
                app.ChannelTable.Data = table(ChanID, ChanName);
                
                
                % Update the comment ID dropdown
                itemCell = ComID';
                itemCell( 2 : end+1 ) = itemCell;
                itemCell{ 1 } = 'None';
                
                app.ComID1DropDown.Items = itemCell;
                app.ComID1DropDown.Value = 'None';
                
                app.ComID2DropDown.Items = itemCell;
                app.ComID2DropDown.Value = 'None';
                
                app.ComID3DropDown.Items = itemCell;
                app.ComID3DropDown.Value = 'None';
                
                
                ComID1DropDownValueChanged(app, event)
                ComID2DropDownValueChanged(app, event)
                ComID3DropDownValueChanged(app, event)
                
                
                % Clear variables
                clear channelNum blockNum ID channelID blockID
                clear commentNum explainNum explainTitle ID explainID
                
                
                close(progressBar);
            end
            
        end

        % Value changed function: LineWidthDropDown
        function LineWidthDropDownValueChanged(app, event)
            
            % Plot 1
            if isequal( app.DisplayButton1.Enable, 'off' )  &&  ~isempty( app.plotObject1 )
                
                app.plotObject1.LineWidth = str2double( app.LineWidthDropDown.Value );
            end
            
            % Plot 2
            if isequal( app.DisplayButton2.Enable, 'off' )  &&  ~isempty( app.plotObject2 )
                
                app.plotObject2.LineWidth = str2double( app.LineWidthDropDown.Value );
            end
            
            % Plot 3
            if isequal( app.DisplayButton3.Enable, 'off' )  &&  ~isempty( app.plotObject3 )
                
                app.plotObject3.LineWidth = str2double( app.LineWidthDropDown.Value );
            end
            
        end

        % Value changed function: MarkerDropDown
        function MarkerDropDownValueChanged(app, event)
            
            % Plot 1
            if isequal( app.DisplayButton1.Enable, 'off' )  &&  ~isempty( app.plotObject1 )
                
                app.plotObject1.Marker = app.MarkerDropDown.Value;
            end
            
            % Plot 2
            if isequal( app.DisplayButton2.Enable, 'off' )  &&  ~isempty( app.plotObject2 )
                
                app.plotObject2.Marker = app.MarkerDropDown.Value;
            end
            
            % Plot 3
            if isequal( app.DisplayButton3.Enable, 'off' )  &&  ~isempty( app.plotObject3 )
                
                app.plotObject3.Marker = app.MarkerDropDown.Value;
            end
            
        end

        % Value changed function: MarkerSizeDropDown
        function MarkerSizeDropDownValueChanged(app, event)
            
            % Plot 1
            if isequal( app.DisplayButton1.Enable, 'off' )  &&  ~isempty( app.plotObject1 )
                
                app.plotObject1.MarkerSize = str2double( app.MarkerSizeDropDown.Value );
            end
            
            % Plot 2
            if isequal( app.DisplayButton2.Enable, 'off' )  &&  ~isempty( app.plotObject2 )
                
                app.plotObject2.MarkerSize = str2double( app.MarkerSizeDropDown.Value );
            end
            
            % Plot 3
            if isequal( app.DisplayButton3.Enable, 'off' )  &&  ~isempty( app.plotObject3 )
                
                app.plotObject3.MarkerSize = str2double( app.MarkerSizeDropDown.Value );
            end
            
        end

        % Value changed function: ComID1DropDown
        function ComID1DropDownValueChanged(app, event)
            
            app.currentComID1 = [];
            
            app.ComChan1DropDown.Items = { 'None' };
            app.ComChan1DropDown.Value = 'None';
            
            app.ChanFsHz1EditField.Value = 0;
            
            delete( app.plotObject1 )
            app.plotObject1 = [];
            app.sigDisp1 = [];
            app.DisplayButton1.Enable = 'off';
            
            
            if ~isequal( app.ComID1DropDown.Value, 'None' )
                
                app.DisplayButton1.Enable = 'on';
                
                % Update ComID dropdown
                for ID = 1 : app.commentNum
                    
                    if isequal( app.ComID1DropDown.Value, app.DataTable.Data{ID,1}{:} )
                        
                        app.currentComID1 = ID;
                        
                        % Update ComChan dropdown
                        if isequal( app.DataTable.Data{ID,2}{:}, 'All' )
                            
                            itemCell = cell( 1, app.channelNum );
                            for i = 1 : app.channelNum
                                itemCell{i} = num2str(i);
                            end
                            app.ComChan1DropDown.Items = itemCell;
                            app.ComChan1DropDown.Value = itemCell{1};
                            
                        else
                            app.ComChan1DropDown.Items = app.DataTable.Data{ID,2};
                            app.ComChan1DropDown.Value = app.DataTable.Data{ID,1}{:};
                        end
                        
                        ComChan1DropDownValueChanged(app, event)
                    end
                end
            end
            
        end

        % Value changed function: ComChan1DropDown
        function ComChan1DropDownValueChanged(app, event)
            
            app.ChanFsHz1EditField.Value = app.samplerate( str2double(app.ComChan1DropDown.Value) );
            
            if isequal( app.DisplayButton1.Enable, 'off' )  &&  ~isempty( app.plotObject1 )
                
                DisplayButton1Pushed(app, event)
            end
            
        end

        % Value changed function: PlotLengthsec1EditField
        function PlotLengthsec1EditFieldValueChanged(app, event)
            
            if isequal( app.DisplayButton1.Enable, 'off' )  &&  ~isempty( app.plotObject1 )
                
                DisplayButton1Pushed(app, event)
            end
            
        end

        % Button pushed function: DisplayButton1
        function DisplayButton1Pushed(app, event)
            
            if isequal( app.ComID1DropDown.Value, 'None' )
                
                msg = 'Select the comment and channel before ploting';
                uialert(app.LabCharttoMatlabFileConverterUIFigure, msg,'Warning','Icon','error')
                
                return
            end
            
            
            channelID = str2double( app.ComChan1DropDown.Value );
            blockID = app.DataTable.Data{app.currentComID1,3}{:};
            comPos = app.DataTable.Data{app.currentComID1,4}{:}; % comment position in terms of data points
            
            pointNum = round( app.PlotLengthsec1EditField.Value * app.ChanFsHz1EditField.Value );
            
            app.sigDisp1 = app.dataCell{ channelID+1, blockID+1 }( comPos : comPos+pointNum );
            sig = rescale(app.sigDisp1);
            
            delete( app.plotObject1 )
            hold( app.UIAxes, 'on' )
            app.plotObject1 = plot( app.UIAxes, sig);
            hold( app.UIAxes, 'off' )
            app.plotObject1.Color = [0.00, 0.45, 0.74]; % Blue
            app.plotObject1.LineWidth = str2double( app.LineWidthDropDown.Value );
            app.plotObject1.Marker = app.MarkerDropDown.Value;
            app.plotObject1.MarkerSize = str2double( app.MarkerSizeDropDown.Value );
            
            app.DisplayButton1.Enable = 'off';
            
        end

        % Button pushed function: ExportButton1
        function ExportButton1Pushed(app, event)
            
            if isequal( app.DisplayButton1.Enable, 'off' )  &&  ~isempty( app.plotObject1 )  &&  ~isempty( app.sigDisp1 )
                
                sig = app.sigDisp1;
                sigPoints = 1 : length(sig);
                
                if isrow(sig)
                    sig = sig';
                end
                if isrow(sigPoints)
                    sigPoints = sigPoints';
                end
                
                
                saveDir = uigetdir;
                
                % Set this uifigure at the top level
                figure(app.LabCharttoMatlabFileConverterUIFigure)
                
                if saveDir ~= 0 % User has selected file directory.
                    
                    prompt = {'Enter filename to save (without extension)...   Please do not use \/:*?"<>|'};
                    dlgtitle = 'Save to an excel file';
                    dfinput = { 'Signal_1' };
                    dims = [1,100];
                    filename = inputdlg(prompt,dlgtitle,dims,dfinput);
                    
                    if ~isempty(filename) % User has entered a name
                        
                        msg = 'Exporting the map data to a .mat file';
                        progressBar = uiprogressdlg(app.LabCharttoMatlabFileConverterUIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');
                        
                        fullSaveName = strcat(saveDir, '/', filename, '.xlsx'); % At this moment, 'fullSaveName' is a 'cell'.
                        fullSaveName = fullSaveName{1}; % Now 'fullSaveName' is a 'character'
                        
                        T = table(sigPoints, sig);
                        SheetName = [ 'Signal - Fs (Hz) = ', num2str( app.ChanFsHz1EditField.Value ) ];
                        writetable( T, fullSaveName, 'Sheet', SheetName )
                        
                        close(progressBar)
                    end
                end
                
                
            else
                msg = 'Plot the signal before exporting';
                uialert(app.LabCharttoMatlabFileConverterUIFigure, msg,'Warning','Icon','error')
            end
            
        end

        % Value changed function: ComID2DropDown
        function ComID2DropDownValueChanged(app, event)
            
            app.currentComID2 = [];
            
            app.ComChan2DropDown.Items = { 'None' };
            app.ComChan2DropDown.Value = 'None';
            
            app.ChanFsHz2EditField.Value = 0;
            
            delete( app.plotObject2 )
            app.plotObject2 = [];
            app.sigDisp2 = [];
            app.DisplayButton2.Enable = 'off';
            
            
            if ~isequal( app.ComID2DropDown.Value, 'None' )
                
                app.DisplayButton2.Enable = 'on';
                
                % Update ComID dropdown
                for ID = 1 : app.commentNum
                    
                    if isequal( app.ComID2DropDown.Value, app.DataTable.Data{ID,1}{:} )
                        
                        app.currentComID2 = ID;
                        
                        % Update ComChan dropdown
                        if isequal( app.DataTable.Data{ID,2}{:}, 'All' )
                            
                            itemCell = cell( 1, app.channelNum );
                            for i = 1 : app.channelNum
                                itemCell{i} = num2str(i);
                            end
                            app.ComChan2DropDown.Items = itemCell;
                            app.ComChan2DropDown.Value = itemCell{1};
                            
                        else
                            app.ComChan2DropDown.Items = app.DataTable.Data{ID,2};
                            app.ComChan2DropDown.Value = app.DataTable.Data{ID,1}{:};
                        end
                        
                        ComChan2DropDownValueChanged(app, event)
                    end
                end
            end
            
            
        end

        % Value changed function: ComChan2DropDown
        function ComChan2DropDownValueChanged(app, event)
            
            app.ChanFsHz2EditField.Value = app.samplerate( str2double(app.ComChan2DropDown.Value) );
            
            if isequal( app.DisplayButton2.Enable, 'off' )  &&  ~isempty( app.plotObject2 )
                
                DisplayButton2Pushed(app, event)
            end
            
        end

        % Value changed function: PlotLengthsec2EditField
        function PlotLengthsec2EditFieldValueChanged(app, event)
            
            if isequal( app.DisplayButton2.Enable, 'off' )  &&  ~isempty( app.plotObject2 )
                
                DisplayButton2Pushed(app, event)
            end
            
        end

        % Button pushed function: DisplayButton2
        function DisplayButton2Pushed(app, event)
            
            if isequal( app.ComID2DropDown.Value, 'None' )
                
                msg = 'Select the comment and channel before ploting';
                uialert(app.LabCharttoMatlabFileConverterUIFigure, msg,'Warning','Icon','error')
                
                return
            end
            
            
            channelID = str2double( app.ComChan2DropDown.Value );
            blockID = app.DataTable.Data{app.currentComID2,3}{:};
            comPos = app.DataTable.Data{app.currentComID2,4}{:}; % comment position in terms of data points
            
            pointNum = round( app.PlotLengthsec2EditField.Value * app.ChanFsHz2EditField.Value );
            
            app.sigDisp2 = app.dataCell{ channelID+1, blockID+1 }( comPos : comPos+pointNum );
            sig = rescale(app.sigDisp2);
            
            delete( app.plotObject2 )
            hold( app.UIAxes, 'on' )
            app.plotObject2 = plot( app.UIAxes, sig);
            hold( app.UIAxes, 'on' )
            app.plotObject2.Color = [0.64, 0.08, 0.18]; % Red
            app.plotObject2.LineWidth = str2double( app.LineWidthDropDown.Value );
            app.plotObject2.Marker = app.MarkerDropDown.Value;
            app.plotObject2.MarkerSize = str2double( app.MarkerSizeDropDown.Value );
            
            app.DisplayButton2.Enable = 'off';
            
        end

        % Button pushed function: ExportButton2
        function ExportButton2Pushed(app, event)
            
            if isequal( app.DisplayButton2.Enable, 'off' )  &&  ~isempty( app.plotObject2 )  &&  ~isempty( app.sigDisp2 )
                
                sig = app.sigDisp2;
                sigPoints = 1 : length(sig);
                
                if isrow(sig)
                    sig = sig';
                end
                if isrow(sigPoints)
                    sigPoints = sigPoints';
                end
                
                
                saveDir = uigetdir;
                
                % Set this uifigure at the top level
                figure(app.LabCharttoMatlabFileConverterUIFigure)
                
                if saveDir ~= 0 % User has selected file directory.
                    
                    prompt = {'Enter filename to save (without extension)...   Please do not use \/:*?"<>|'};
                    dlgtitle = 'Save to an excel file';
                    dfinput = { 'Signal_2' };
                    dims = [1,100];
                    filename = inputdlg(prompt,dlgtitle,dims,dfinput);
                    
                    if ~isempty(filename) % User has entered a name
                        
                        msg = 'Exporting the map data to a .mat file';
                        progressBar = uiprogressdlg(app.LabCharttoMatlabFileConverterUIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');
                        
                        fullSaveName = strcat(saveDir, '/', filename, '.xlsx'); % At this moment, 'fullSaveName' is a 'cell'.
                        fullSaveName = fullSaveName{1}; % Now 'fullSaveName' is a 'character'
                        
                        T = table(sigPoints, sig);
                        SheetName = [ 'Signal - Fs (Hz) = ', num2str( app.ChanFsHz1EditField.Value ) ];
                        writetable( T, fullSaveName, 'Sheet', SheetName )
                        
                        close(progressBar)
                    end
                end
                
                
            else
                msg = 'Plot the signal before exporting';
                uialert(app.LabCharttoMatlabFileConverterUIFigure, msg,'Warning','Icon','error')
            end
            
        end

        % Value changed function: ComID3DropDown
        function ComID3DropDownValueChanged(app, event)
            
            app.currentComID3 = [];
            
            app.ComChan3DropDown.Items = { 'None' };
            app.ComChan3DropDown.Value = 'None';
            
            app.ChanFsHz3EditField.Value = 0;
            
            delete( app.plotObject3 )
            app.plotObject3 = [];
            app.sigDisp3 = [];
            app.DisplayButton3.Enable = 'off';
            
            
            if ~isequal( app.ComID3DropDown.Value, 'None' )
                
                app.DisplayButton3.Enable = 'on';
                
                % Update ComID dropdown
                for ID = 1 : app.commentNum
                    
                    if isequal( app.ComID3DropDown.Value, app.DataTable.Data{ID,1}{:} )
                        
                        app.currentComID3 = ID;
                        
                        % Update ComChan dropdown
                        if isequal( app.DataTable.Data{ID,2}{:}, 'All' )
                            
                            itemCell = cell( 1, app.channelNum );
                            for i = 1 : app.channelNum
                                itemCell{i} = num2str(i);
                            end
                            app.ComChan3DropDown.Items = itemCell;
                            app.ComChan3DropDown.Value = itemCell{1};
                            
                        else
                            app.ComChan3DropDown.Items = app.DataTable.Data{ID,2};
                            app.ComChan3DropDown.Value = app.DataTable.Data{ID,1}{:};
                        end
                        
                        ComChan3DropDownValueChanged(app, event)
                    end
                end
            end
            
        end

        % Value changed function: ComChan3DropDown
        function ComChan3DropDownValueChanged(app, event)
            
            app.ChanFsHz3EditField.Value = app.samplerate( str2double(app.ComChan3DropDown.Value) );
            
            if isequal( app.DisplayButton3.Enable, 'off' )  &&  ~isempty( app.plotObject3 )
                
                DisplayButton3Pushed(app, event)
            end
            
        end

        % Value changed function: PlotLengthsec3EditField
        function PlotLengthsec3EditFieldValueChanged(app, event)
            
            if isequal( app.DisplayButton3.Enable, 'off' )  &&  ~isempty( app.plotObject3 )
                
                DisplayButton3Pushed(app, event)
            end
            
        end

        % Button pushed function: DisplayButton3
        function DisplayButton3Pushed(app, event)
            
            if isequal( app.ComID3DropDown.Value, 'None' )
                
                msg = 'Select the comment and channel before ploting';
                uialert(app.LabCharttoMatlabFileConverterUIFigure, msg,'Warning','Icon','error')
                
                return
            end
            
            
            channelID = str2double( app.ComChan3DropDown.Value );
            blockID = app.DataTable.Data{app.currentComID3,3}{:};
            comPos = app.DataTable.Data{app.currentComID3,4}{:}; % comment position in terms of data points
            
            pointNum = round( app.PlotLengthsec3EditField.Value * app.ChanFsHz3EditField.Value );
            
            app.sigDisp3 = app.dataCell{ channelID+1, blockID+1 }( comPos : comPos+pointNum );
            sig = rescale(app.sigDisp3);
            
            delete( app.plotObject3 )
            hold( app.UIAxes, 'on' )
            app.plotObject3 = plot( app.UIAxes, sig);
            hold( app.UIAxes, 'on' )
            app.plotObject3.Color = [0.72, 0.27, 1.00]; % Purple
            app.plotObject3.LineWidth = str2double( app.LineWidthDropDown.Value );
            app.plotObject3.Marker = app.MarkerDropDown.Value;
            app.plotObject3.MarkerSize = str2double( app.MarkerSizeDropDown.Value );
            
            app.DisplayButton3.Enable = 'off';
            
        end

        % Button pushed function: ExportButton3
        function ExportButton3Pushed(app, event)
            
            if isequal( app.DisplayButton3.Enable, 'off' )  &&  ~isempty( app.plotObject3 )  &&  ~isempty( app.sigDisp3 )
                
                sig = app.sigDisp3;
                sigPoints = 1 : length(sig);
                
                if isrow(sig)
                    sig = sig';
                end
                if isrow(sigPoints)
                    sigPoints = sigPoints';
                end
                
                
                saveDir = uigetdir;
                
                % Set this uifigure at the top level
                figure(app.LabCharttoMatlabFileConverterUIFigure)
                
                if saveDir ~= 0 % User has selected file directory.
                    
                    prompt = {'Enter filename to save (without extension)...   Please do not use \/:*?"<>|'};
                    dlgtitle = 'Save to an excel file';
                    dfinput = { 'Signal_3' };
                    dims = [1,100];
                    filename = inputdlg(prompt,dlgtitle,dims,dfinput);
                    
                    if ~isempty(filename) % User has entered a name
                        
                        msg = 'Exporting the map data to a .mat file';
                        progressBar = uiprogressdlg(app.LabCharttoMatlabFileConverterUIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');
                        
                        fullSaveName = strcat(saveDir, '/', filename, '.xlsx'); % At this moment, 'fullSaveName' is a 'cell'.
                        fullSaveName = fullSaveName{1}; % Now 'fullSaveName' is a 'character'
                        
                        T = table(sigPoints, sig);
                        SheetName = [ 'Signal - Fs (Hz) = ', num2str( app.ChanFsHz3EditField.Value ) ];
                        writetable( T, fullSaveName, 'Sheet', SheetName )
                        
                        close(progressBar)
                    end
                end
                
                
            else
                msg = 'Plot the signal before exporting';
                uialert(app.LabCharttoMatlabFileConverterUIFigure, msg,'Warning','Icon','error')
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LabCharttoMatlabFileConverterUIFigure = uifigure('Visible', 'off');
            app.LabCharttoMatlabFileConverterUIFigure.Name = 'LabChart to Matlab File Converter';
            app.FileMenu = uimenu(app.LabCharttoMatlabFileConverterUIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Accelerator = 'O';
            app.OpenMenu.Text = 'Open...';
            app.UIAxes = uiaxes(app.LabCharttoMatlabFileConverterUIFigure);
            xlabel(app.UIAxes, 'X - Data Points')
            ylabel(app.UIAxes, 'Y - Normalised Value')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.SubtitleFontWeight = 'bold';
            app.DirectoryEditFieldLabel = uilabel(app.LabCharttoMatlabFileConverterUIFigure);
            app.DirectoryEditFieldLabel.Text = 'Directory';
            app.DirectoryEditField = uieditfield(app.LabCharttoMatlabFileConverterUIFigure, 'text');
            app.DirectoryEditField.Editable = 'off';
            app.FileNameEditFieldLabel = uilabel(app.LabCharttoMatlabFileConverterUIFigure);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.LabCharttoMatlabFileConverterUIFigure, 'text');
            app.FileNameEditField.Editable = 'off';
            app.ComID1DropDownLabel = uilabel(app.LabCharttoMatlabFileConverterUIFigure);
            app.ComID1DropDownLabel.Text = 'ComID - 1';
            app.ComID1DropDown = uidropdown(app.LabCharttoMatlabFileConverterUIFigure);
            app.ComID1DropDown.Items = {'None'};
            app.ComID1DropDown.ValueChangedFcn = createCallbackFcn(app, @ComID1DropDownValueChanged, true);
            app.ComID1DropDown.Value = 'None';
            app.ComChan1DropDownLabel = uilabel(app.LabCharttoMatlabFileConverterUIFigure);
            app.ComChan1DropDownLabel.Text = 'ComChan - 1';
            app.ComChan1DropDown = uidropdown(app.LabCharttoMatlabFileConverterUIFigure);
            app.ComChan1DropDown.Items = {'None'};
            app.ComChan1DropDown.ValueChangedFcn = createCallbackFcn(app, @ComChan1DropDownValueChanged, true);
            app.ComChan1DropDown.Value = 'None';
            app.ChanFsHz1EditFieldLabel = uilabel(app.LabCharttoMatlabFileConverterUIFigure);
            app.ChanFsHz1EditFieldLabel.Text = 'Chan Fs (Hz) - 1';
            app.ChanFsHz1EditField = uieditfield(app.LabCharttoMatlabFileConverterUIFigure, 'numeric');
            app.ChanFsHz1EditField.Limits = [0 Inf];
            app.ChanFsHz1EditField.ValueDisplayFormat = '%.3f';
            app.ChanFsHz1EditField.Editable = 'off';
            app.PlotLengthsec1EditFieldLabel = uilabel(app.LabCharttoMatlabFileConverterUIFigure);
            app.PlotLengthsec1EditFieldLabel.Text = 'Plot Length (sec) - 1';
            app.PlotLengthsec1EditField = uieditfield(app.LabCharttoMatlabFileConverterUIFigure, 'numeric');
            app.PlotLengthsec1EditField.Limits = [1 Inf];
            app.PlotLengthsec1EditField.ValueDisplayFormat = '%.3f';
            app.PlotLengthsec1EditField.ValueChangedFcn = createCallbackFcn(app, @PlotLengthsec1EditFieldValueChanged, true);
            app.PlotLengthsec1EditField.Value = 1;
            app.DisplayButton1 = uibutton(app.LabCharttoMatlabFileConverterUIFigure, 'push');
            app.DisplayButton1.ButtonPushedFcn = createCallbackFcn(app, @DisplayButton1Pushed, true);
            app.DisplayButton1.Text = 'Display';
            app.TabGroup = uitabgroup(app.LabCharttoMatlabFileConverterUIFigure);
            app.DataInformationTab = uitab(app.TabGroup);
            app.DataInformationTab.Title = 'Data Information';
            app.DataTable = uitable(app.DataInformationTab);
            app.DataTable.ColumnName = {'ComID'; 'ComChan'; 'ComBlock'; 'ComTickPos'; 'ComText'};
            app.DataTable.RowName = {};
            app.ChannelInformationTab = uitab(app.TabGroup);
            app.ChannelInformationTab.Title = 'Channel Information';
            app.ChannelTable = uitable(app.ChannelInformationTab);
            app.ChannelTable.ColumnName = {'ChanID'; 'ChanName'};
            app.ChannelTable.RowName = {};
            app.DisplayButton2 = uibutton(app.LabCharttoMatlabFileConverterUIFigure, 'push');
            app.DisplayButton2.ButtonPushedFcn = createCallbackFcn(app, @DisplayButton2Pushed, true);
            app.DisplayButton2.Text = 'Display';
            app.ComID2DropDownLabel = uilabel(app.LabCharttoMatlabFileConverterUIFigure);
            app.ComID2DropDownLabel.Text = 'ComID - 2';
            app.ComID2DropDown = uidropdown(app.LabCharttoMatlabFileConverterUIFigure);
            app.ComID2DropDown.Items = {'None'};
            app.ComID2DropDown.ValueChangedFcn = createCallbackFcn(app, @ComID2DropDownValueChanged, true);
            app.ComID2DropDown.Value = 'None';
            app.ComChan2DropDownLabel = uilabel(app.LabCharttoMatlabFileConverterUIFigure);
            app.ComChan2DropDownLabel.Text = 'ComChan - 2';
            app.ComChan2DropDown = uidropdown(app.LabCharttoMatlabFileConverterUIFigure);
            app.ComChan2DropDown.Items = {'None'};
            app.ComChan2DropDown.ValueChangedFcn = createCallbackFcn(app, @ComChan2DropDownValueChanged, true);
            app.ComChan2DropDown.Value = 'None';
            app.ChanFsHz2EditFieldLabel = uilabel(app.LabCharttoMatlabFileConverterUIFigure);
            app.ChanFsHz2EditFieldLabel.Text = 'Chan Fs (Hz) - 2';
            app.ChanFsHz2EditField = uieditfield(app.LabCharttoMatlabFileConverterUIFigure, 'numeric');
            app.ChanFsHz2EditField.Limits = [0 Inf];
            app.ChanFsHz2EditField.ValueDisplayFormat = '%.3f';
            app.ChanFsHz2EditField.Editable = 'off';
            app.PlotLengthsec2EditFieldLabel = uilabel(app.LabCharttoMatlabFileConverterUIFigure);
            app.PlotLengthsec2EditFieldLabel.Text = 'Plot Length (sec) - 2';
            app.PlotLengthsec2EditField = uieditfield(app.LabCharttoMatlabFileConverterUIFigure, 'numeric');
            app.PlotLengthsec2EditField.Limits = [1 Inf];
            app.PlotLengthsec2EditField.ValueDisplayFormat = '%.3f';
            app.PlotLengthsec2EditField.ValueChangedFcn = createCallbackFcn(app, @PlotLengthsec2EditFieldValueChanged, true);
            app.PlotLengthsec2EditField.Value = 1;
            app.LineWidthDropDownLabel = uilabel(app.LabCharttoMatlabFileConverterUIFigure);
            app.LineWidthDropDownLabel.Text = 'Line Width';
            app.LineWidthDropDown = uidropdown(app.LabCharttoMatlabFileConverterUIFigure);
            app.LineWidthDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.LineWidthDropDown.ValueChangedFcn = createCallbackFcn(app, @LineWidthDropDownValueChanged, true);
            app.LineWidthDropDown.Value = '3';
            app.MarkerDropDownLabel = uilabel(app.LabCharttoMatlabFileConverterUIFigure);
            app.MarkerDropDownLabel.Text = 'Marker';
            app.MarkerDropDown = uidropdown(app.LabCharttoMatlabFileConverterUIFigure);
            app.MarkerDropDown.Items = {'None', 'o', '+', '*', '.', 'x', 'square', 'diamond', '^'};
            app.MarkerDropDown.ValueChangedFcn = createCallbackFcn(app, @MarkerDropDownValueChanged, true);
            app.MarkerDropDown.Value = 'o';
            app.MarkerSizeDropDownLabel = uilabel(app.LabCharttoMatlabFileConverterUIFigure);
            app.MarkerSizeDropDownLabel.Text = 'Marker Size';
            app.MarkerSizeDropDown = uidropdown(app.LabCharttoMatlabFileConverterUIFigure);
            app.MarkerSizeDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.MarkerSizeDropDown.ValueChangedFcn = createCallbackFcn(app, @MarkerSizeDropDownValueChanged, true);
            app.MarkerSizeDropDown.Value = '3';
            app.ExportButton1 = uibutton(app.LabCharttoMatlabFileConverterUIFigure, 'push');
            app.ExportButton1.ButtonPushedFcn = createCallbackFcn(app, @ExportButton1Pushed, true);
            app.ExportButton1.Text = 'Export';
            app.ExportButton2 = uibutton(app.LabCharttoMatlabFileConverterUIFigure, 'push');
            app.ExportButton2.ButtonPushedFcn = createCallbackFcn(app, @ExportButton2Pushed, true);
            app.ExportButton2.Text = 'Export';
            app.NoteTextAreaLabel = uilabel(app.LabCharttoMatlabFileConverterUIFigure);
            app.NoteTextAreaLabel.Text = 'Note';
            app.NoteTextArea = uitextarea(app.LabCharttoMatlabFileConverterUIFigure);
            app.NoteTextArea.Editable = 'off';
            app.NoteTextArea.Value = {'Normalisation (Y-axis) is ONLY for data visualisation purpose.    The ''Export Button'' will export the raw data that is being visualised. Therefore, ''Plot Length (sec)'' decides how many data points will be exported.'};
            app.DisplayButton3 = uibutton(app.LabCharttoMatlabFileConverterUIFigure, 'push');
            app.DisplayButton3.ButtonPushedFcn = createCallbackFcn(app, @DisplayButton3Pushed, true);
            app.DisplayButton3.Text = 'Display';
            app.ExportButton3 = uibutton(app.LabCharttoMatlabFileConverterUIFigure, 'push');
            app.ExportButton3.ButtonPushedFcn = createCallbackFcn(app, @ExportButton3Pushed, true);
            app.ExportButton3.Text = 'Export';
            app.ComID3DropDownLabel = uilabel(app.LabCharttoMatlabFileConverterUIFigure);
            app.ComID3DropDownLabel.Text = 'ComID - 3';
            app.ComID3DropDown = uidropdown(app.LabCharttoMatlabFileConverterUIFigure);
            app.ComID3DropDown.Items = {'None'};
            app.ComID3DropDown.ValueChangedFcn = createCallbackFcn(app, @ComID3DropDownValueChanged, true);
            app.ComID3DropDown.Value = 'None';
            app.ComChan3DropDownLabel = uilabel(app.LabCharttoMatlabFileConverterUIFigure);
            app.ComChan3DropDownLabel.Text = 'ComChan - 3';
            app.ComChan3DropDown = uidropdown(app.LabCharttoMatlabFileConverterUIFigure);
            app.ComChan3DropDown.Items = {'None'};
            app.ComChan3DropDown.ValueChangedFcn = createCallbackFcn(app, @ComChan3DropDownValueChanged, true);
            app.ComChan3DropDown.Value = 'None';
            app.ChanFsHz3EditFieldLabel = uilabel(app.LabCharttoMatlabFileConverterUIFigure);
            app.ChanFsHz3EditFieldLabel.Text = 'Chan Fs (Hz) - 3';
            app.ChanFsHz3EditField = uieditfield(app.LabCharttoMatlabFileConverterUIFigure, 'numeric');
            app.ChanFsHz3EditField.Limits = [0 Inf];
            app.ChanFsHz3EditField.ValueDisplayFormat = '%.3f';
            app.ChanFsHz3EditField.Editable = 'off';
            app.PlotLengthsec3EditFieldLabel = uilabel(app.LabCharttoMatlabFileConverterUIFigure);
            app.PlotLengthsec3EditFieldLabel.Text = 'Plot Length (sec) - 3';
            app.PlotLengthsec3EditField = uieditfield(app.LabCharttoMatlabFileConverterUIFigure, 'numeric');
            app.PlotLengthsec3EditField.Limits = [1 Inf];
            app.PlotLengthsec3EditField.ValueDisplayFormat = '%.3f';
            app.PlotLengthsec3EditField.ValueChangedFcn = createCallbackFcn(app, @PlotLengthsec3EditFieldValueChanged, true);
            app.PlotLengthsec3EditField.Value = 1;
            % Show the figure after all components are created
            app.LabCharttoMatlabFileConverterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LCconverter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LabCharttoMatlabFileConverterUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LabCharttoMatlabFileConverterUIFigure)
        end
    end
end