classdef OMZer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OMZer_UIFigure             matlab.ui.Figure
        MoviesecSpinner            matlab.ui.control.Spinner
        MoviesecSpinnerLabel       matlab.ui.control.Label
        CameraNALabel              matlab.ui.control.Label
        Image_ActivatedCamera      matlab.ui.control.Image
        ResetLabel                 matlab.ui.control.Label
        Image_Reset                matlab.ui.control.Image
        ApplyLabel                 matlab.ui.control.Label
        Image_Apply                matlab.ui.control.Image
        NormaliseLamp              matlab.ui.control.Lamp
        TopHatDedriftLamp          matlab.ui.control.Lamp
        NormaliseCheckBox          matlab.ui.control.CheckBox
        TopHatDedriftbetaCheckBox  matlab.ui.control.CheckBox
        PolyDedriftLamp            matlab.ui.control.Lamp
        PolyDriftDropDown          matlab.ui.control.DropDown
        PolyfitDedriftCheckBox     matlab.ui.control.CheckBox
        SGLamp                     matlab.ui.control.Lamp
        ButterworthLamp            matlab.ui.control.Lamp
        DenoiseLamp                matlab.ui.control.Lamp
        ButterworthDropDown        matlab.ui.control.DropDown
        SGCheckBox                 matlab.ui.control.CheckBox
        ButterworthFilterCheckBox  matlab.ui.control.CheckBox
        Denoise60HzCheckBox        matlab.ui.control.CheckBox
        BoxFilterLamp              matlab.ui.control.Lamp
        BoxFilterDropDown          matlab.ui.control.DropDown
        BoxFilterCheckBox          matlab.ui.control.CheckBox
        InvertLamp                 matlab.ui.control.Lamp
        InvertCheckBox             matlab.ui.control.CheckBox
        X_scrn6                    matlab.ui.control.NumericEditField
        Y_scrn6                    matlab.ui.control.NumericEditField
        X_scrn5                    matlab.ui.control.NumericEditField
        Y_scrn5                    matlab.ui.control.NumericEditField
        X_scrn4                    matlab.ui.control.NumericEditField
        Y_scrn4                    matlab.ui.control.NumericEditField
        X_scrn3                    matlab.ui.control.NumericEditField
        Y_scrn3                    matlab.ui.control.NumericEditField
        X_scrn2                    matlab.ui.control.NumericEditField
        Y_scrn2                    matlab.ui.control.NumericEditField
        X_scrn1                    matlab.ui.control.NumericEditField
        Y_scrn1                    matlab.ui.control.NumericEditField
        ExportMaskLabel            matlab.ui.control.Label
        Image_ExportMask           matlab.ui.control.Image
        EraseMaskLabel             matlab.ui.control.Label
        Image_EraseMask            matlab.ui.control.Image
        Camera2MaskCheckBox        matlab.ui.control.CheckBox
        Camera1MaskCheckBox        matlab.ui.control.CheckBox
        RestoreViewLabel           matlab.ui.control.Label
        Image_RestoreView          matlab.ui.control.Image
        SectionLabel               matlab.ui.control.Label
        Image_Section              matlab.ui.control.Image
        ViewEndsecSpinner          matlab.ui.control.Spinner
        ViewEndsecSpinnerLabel     matlab.ui.control.Label
        ViewStartsecSpinner        matlab.ui.control.Spinner
        ViewStartsecSpinnerLabel   matlab.ui.control.Label
        LinkCameras                matlab.ui.control.CheckBox
        pacing2CheckBox            matlab.ui.control.CheckBox
        XYLabel                    matlab.ui.control.Label
        MovieSlider                matlab.ui.control.Slider
        pacing1CheckBox            matlab.ui.control.CheckBox
        TabGroup                   matlab.ui.container.TabGroup
        FileTab                    matlab.ui.container.Tab
        fileExtensionLabel         matlab.ui.control.Label
        DirectoryEditField         matlab.ui.control.EditField
        DirectoryEditFieldLabel    matlab.ui.control.Label
        FileInformationLabel       matlab.ui.control.Label
        Image_FileInformation      matlab.ui.control.Image
        RenameLabel                matlab.ui.control.Label
        Image_Rename               matlab.ui.control.Image
        FileNameEditField          matlab.ui.control.EditField
        FileNameEditFieldLabel     matlab.ui.control.Label
        SaveLabel                  matlab.ui.control.Label
        Image_Save                 matlab.ui.control.Image
        OpenFolderLabel            matlab.ui.control.Label
        Image_OpenFolder           matlab.ui.control.Image
        ToolsTab                   matlab.ui.container.Tab
        ExportFigureLabel          matlab.ui.control.Label
        Image_ExportFigure         matlab.ui.control.Image
        ExportDataLabel            matlab.ui.control.Label
        Image_ExportData           matlab.ui.control.Image
        MaskSetupLabel             matlab.ui.control.Label
        Image_MaskSetup            matlab.ui.control.Image
        AnalysisTab                matlab.ui.container.Tab
        VmDominantFrequencyAnalysisLabel  matlab.ui.control.Label
        Image_VmDominantFrequencyAnalysis  matlab.ui.control.Image
        VmPhaseAnalysisLabel       matlab.ui.control.Label
        Image_VmPhaseAnalysis      matlab.ui.control.Image
        VmCaLinkedAnalysisLabel    matlab.ui.control.Label
        Image_VmCaLinkedAnalysis   matlab.ui.control.Image
        CaAnalysisLabel            matlab.ui.control.Label
        Image_CaAnalysis           matlab.ui.control.Image
        VmAnalysisLabel            matlab.ui.control.Label
        Image_VmAnalysis           matlab.ui.control.Image
        PreferenceTab              matlab.ui.container.Tab
        SetupLabel                 matlab.ui.control.Label
        Image_Setup                matlab.ui.control.Image
        scrn6                      matlab.ui.control.UIAxes
        scrn5                      matlab.ui.control.UIAxes
        scrn4                      matlab.ui.control.UIAxes
        scrn3                      matlab.ui.control.UIAxes
        scrn2                      matlab.ui.control.UIAxes
        scrn1                      matlab.ui.control.UIAxes
        imageAxis1                 matlab.ui.control.UIAxes
        imageAxis2                 matlab.ui.control.UIAxes
    end


    properties (Access = public)

        % The Children App --------------------
        OMZer_FileInformation
        OMZer_Mask
        OMZer_Vm
        OMZer_Ca
        OMZer_VmCaLinked
        OMZer_Vm_Phase
        OMZer_ExportDisplayPixel
        OMZer_ExportFigure


        % The Current App --------------------
        setup % User preference

        imageIconActColor = [0.80, 0.80, 0.80]; % The activation color when an image is clicked
        colorGroup_Gray_Orange = { [0.90, 0.90, 0.90], [0.93, 0.69, 0.13] }; % Gray and Orange
        colorGroup_Gray_Green = { [0.90, 0.90, 0.90], [0.00,1.00,0.00] }; % Gray and Green
        enableGroup_On_Off = { 'on', 'off' };

        dirName % File directory
        fileNameWithExtension
        fileName % Filename without path and extension
        fileExt % File Extension

        rshFileList
        matFileList
        gshFileList
        gshFileStructure % A cell (n x 3) - stores organized GSH file list based on single/dual cameras
        allFileList = [ {'--- --- --- --- --- --- --- --- --- --- RSH Files --- --- --- --- --- --- --- --- --- ---'}; ...
            {}; ...
            {'--- --- --- --- --- --- --- --- --- --- GSH Files --- --- --- --- --- --- --- --- --- ---'}; ...
            {}; ...
            {'--- --- --- --- --- --- --- --- --- --- MAT Files --- --- --- --- --- --- --- --- --- ---'}; ...
            {} ]; % The collection of all different types of files

        batchProcessingFileList
        batchProcessingLampOnOff = num2cell( zeros(2,8) ); % 'Row 1 (or 2)' - camera 1 (or 2)
        batchProcessingBoxFilterDropDownValue = {'3';'3'}; % 'Row 1 (or 2)' - camera 1 (or 2)
        batchProcessingButterworthDropDownValue = {'100';'100'}; % 'Row 1 (or 2)' - camera 1 (or 2)
        batchProcessingPolyDriftDropDownValue = {'1';'1'}; % 'Row 1 (or 2)' - camera 1 (or 2)

        data % All the data from the converted .mat file
        acqFreq % Camera acquisition frequency (Hz)
        pcl % Pacing cycle length
        camTF % 0 (single camera) or 1 (dual camera)
        cmosDataRawMaskFreeGroup % Un-filtered, mask-free
        cmosDataMaskFreeGroup % Filtered, mask-free
        cmosDataGroup % Filtered, masked
        maskGroup % User mask
        bgImageGroup
        bgImageNomAdjustGroup % Pixel intensity ranges in [0,255]
        bgImageNomGroup % Gray colour RGB version of background image
        cmosDataRowNum % The number of rows of the cmosData matrix (i.e., Y max)
        cmosDataColNum % The number of columns of the cmosData matrix (i.e., X max)
        signalTime
        pacing1 % Pacing signal recorded by channel 1
        pacing2 % Pacing signal recorded by channel 2
        pacingTime % Time points to plot pacing signal
        userComment

        clickCount = 1; % Record how many times the mouse double-click in imageAxis1 and imageAxis2
        mouseMotion
        mouseDragID
        mouseXY
        markerXY
        scrnObject % To store 6 scrn axis objects
        Xs % The x coordinate edit box
        Ys % The y coordinate edit box

        imageMaskComponentsObject

        imageAxisObject
        imageAxis1Locs % The position information about imageAxis1, that is [left bottom width height]
        imageAxis2Locs % The position information about imageAxis2, that is [left bottom width height]

        markerPlotObject = cell(2,6); % 'Row 1 (or 2)' - camera 1 (or 2)
        signalPlotObject = cell(2,6); % 'Row 1 (or 2)' - camera 1 (or 2)
        pacingPlotObject = cell(2,6); % 'Row 1 (or 2)' - pacing 1 (or 2)
        maskPlotObject = cell(2,1);

        ColorID
        Color1 = [0.00,0.45,0.74];
        Color2 = [0.93,0.69,0.13];
        Color3 = [0.64,0.08,0.18];
        Color4 = [0.72,0.27,1.00];
        Color5 = [0.47,0.67,0.19];
        Color6 = [0.89,0.47,0.58];

        lWidth = 2; % Line width

        activeCamera = 1; % 1 (or 2) - camera 1 (or 2) active
        bgFrame = cell(2,1); % 'background fram' - store background image for camera 1 and camera 2
        imFrame = cell(2,1); % 'image Frame' - store current movie frame for camera 1 and camera 2
        imFrameAlpha = cell(2,1); % The transparency of imFrame for camera 1 and camera 2
        timeIndicators = cell(6,1); % The plot of movie playing sweep bar
        currentFrameID % The current frame

        conditionLampOnOff = num2cell( zeros(2,8) ); % 'Row 1 (or 2)' - camera 1 (or 2)
        conditionLampObject = cell(1,8);
        conditionBoxObject = cell(1,8);
        BoxFilterDropDownValue = {'3';'3'}; % 'Row 1 (or 2)' - camera 1 (or 2)
        ButterworthDropDownValue = {'100';'100'}; % 'Row 1 (or 2)' - camera 1 (or 2)
        PolyDriftDropDownValue = {'1';'1'}; % 'Row 1 (or 2)' - camera 1 (or 2)

        sectionStartIndex
        sectionEndIndex

        topHatWindowStartIndex % The window start index for topHat
        topHatWindowEndIndex % The window end index for topHat

        % For save files
        signalCondition_Yes_No = 0; % 1 (or 0) means does (not) need to be saved
        signalSection_Yes_No = 0;
        signalMask_Yes_No = 0;

        % For window key press and release callback
        keyPressHistory
        keyPressID = 1;
    end


    methods (Access = public)

        function showImageIconAct(app, imageIconName)

            imageIconName.BackgroundColor = app.imageIconActColor; %[0.80, 0.80, 0.80];
            pause(0.05)
            imageIconName.BackgroundColor = 'none';
        end


        function closeAllChildrenApp(app, exceptAppName)

            if isobject(app.OMZer_FileInformation) && ( sum( contains(exceptAppName, 'OMZer_FileInformation') ) == 0 )
                delete(app.OMZer_FileInformation)
            end

            if isobject(app.OMZer_Mask) && ( sum( contains(exceptAppName, 'OMZer_Mask') ) == 0 )
                delete(app.OMZer_Mask)
            end

            if isobject(app.OMZer_Vm) && ( sum( contains(exceptAppName, 'OMZer_Vm') ) == 0 )
                callByMainApp_OMZer_Vm_UIFigureCloseRequest(app.OMZer_Vm)
                delete(app.OMZer_Vm)
            end

            if isobject(app.OMZer_Ca) && ( sum( contains(exceptAppName, 'OMZer_Ca') ) == 0 )
                callByMainApp_OMZer_Ca_UIFigureCloseRequest(app.OMZer_Ca)
                delete(app.OMZer_Ca)
            end

            if isobject(app.OMZer_VmCaLinked) && ( sum( contains(exceptAppName, 'OMZer_VmCaLinked') ) == 0 )
                callByMainApp_OMZer_VmCaLinked_UIFigureCloseRequest(app.OMZer_VmCaLinked)
                delete(app.OMZer_VmCaLinked)
            end
            
            if isobject(app.OMZer_Vm_Phase) && ( sum( contains(exceptAppName, 'OMZer_Vm_Phase') ) == 0 )
                callByMainApp_OMZer_Vm_Phase_UIFigureCloseRequest(app.OMZer_Vm_Phase)
                delete(app.OMZer_Vm_Phase)
            end
            
            if isobject(app.OMZer_ExportDisplayPixel) && ( sum( contains(exceptAppName, 'OMZer_ExportDisplayPixel') ) == 0 )
                delete(app.OMZer_ExportDisplayPixel)
            end

            if isobject(app.OMZer_ExportFigure) && ( sum( contains(exceptAppName, 'OMZer_ExportFigure') ) == 0 )
                delete(app.OMZer_ExportFigure)
            end
        end


        function setColourMap(app,name)

            colormap(app.imageAxis1, name);
            colormap(app.imageAxis2, name);
        end


        function setScrnLimit(app, LB, UB, state)

            app.Image_RestoreView.Enable = state;

            app.ViewStartsecSpinner.Enable = state;
            app.ViewEndsecSpinner.Enable = state;

            app.ViewStartsecSpinner.Limits = [ LB, UB ];
            app.ViewEndsecSpinner.Limits = [ LB, UB ];

            app.ViewStartsecSpinner.Value = LB;
            app.ViewEndsecSpinner.Value = UB;

            for ID = 1:6
                xlim( app.scrnObject{ID}, [ app.ViewStartsecSpinner.Value, app.ViewEndsecSpinner.Value ] )
            end
        end


        function setAxisLimit(app, xMaxLimit, yMaxLimit) % Set the x-y limit of all axes

            app.imageAxis1.XLim = [1, xMaxLimit];
            app.imageAxis1.YLim = [1, yMaxLimit];

            app.imageAxis2.XLim = [1, xMaxLimit];
            app.imageAxis2.YLim = [1, yMaxLimit];

            for ID = 1:6
                app.Xs{ID}.Value = 1;
                app.Ys{ID}.Value = 1;
                app.Xs{ID}.Limits = [1, xMaxLimit];
                app.Ys{ID}.Limits = [1, yMaxLimit];
            end
        end


        function updateConditionComponents(app, newActiveCamera)

            if app.activeCamera ~= newActiveCamera

                % Condition check box
                for ID = 1 : 8
                    app.conditionBoxObject{ID}.Value = app.conditionLampOnOff{newActiveCamera,ID};
                end

                if app.conditionLampOnOff{newActiveCamera,4} == 1 % 'Butterworth' is checked
                    app.SGCheckBox.Enable = 'off';
                else
                    app.SGCheckBox.Enable = 'on';
                end
                if app.conditionLampOnOff{newActiveCamera,5} == 1 % 'Savitzky-Golay' is checked
                    app.ButterworthFilterCheckBox.Enable = 'off';
                else
                    app.ButterworthFilterCheckBox.Enable = 'on';
                end

                if app.conditionLampOnOff{newActiveCamera,6} == 1 % 'Polynomial dedrift' is checked
                    app.TopHatDedriftbetaCheckBox.Enable = 'off';
                else
                    app.TopHatDedriftbetaCheckBox.Enable = 'on';
                end
                if app.conditionLampOnOff{newActiveCamera,7} == 1 % 'TopHat dedrift' is checked
                    app.PolyfitDedriftCheckBox.Enable = 'off';
                else
                    app.PolyfitDedriftCheckBox.Enable = 'on';
                end


                % Condition drop down
                app.BoxFilterDropDown.Value = app.BoxFilterDropDownValue{newActiveCamera,1};
                app.ButterworthDropDown.Value = app.ButterworthDropDownValue{newActiveCamera,1};
                app.PolyDriftDropDown.Value = app.PolyDriftDropDownValue{newActiveCamera,1};

                if app.conditionLampOnOff{newActiveCamera,5} == 1 % 'Savitzky-Golay' is checked
                    app.ButterworthDropDown.Enable = 'off';
                else
                    app.ButterworthDropDown.Enable = 'on';
                end

                if app.conditionLampOnOff{newActiveCamera,7} == 1 % 'TopHat dedrift' is checked
                    app.PolyDriftDropDown.Enable = 'off';
                else
                    app.PolyDriftDropDown.Enable = 'on';
                end


                % Condition lamps
                color = { [0.90,0.90,0.90], [0.00,1.00,0.00] }; % Gray and Green
                for ID = 1 : 8
                    colorID = app.conditionLampOnOff{newActiveCamera,ID} + 1;
                    app.conditionLampObject{ID}.Color = color{colorID};
                end
            end
        end


        function loadUserConditionParameters(app)

            if ~isempty(app.data.signalConditioningInfo)

                % Condition lamp
                for ID = 3:10
                    app.conditionLampOnOff{ 1, ID-2 } = app.data.signalConditioningInfo{ ID, 2 };

                    if app.camTF ~= 0 % Dual cameras
                        app.conditionLampOnOff{ 2, ID-2 } = app.data.signalConditioningInfo{ ID, 4 };
                    end
                end

                % Condition drop down
                app.BoxFilterDropDownValue{ 1, 1 } = app.data.signalConditioningInfo{ 4, 3 };
                app.ButterworthDropDownValue{ 1, 1 } = app.data.signalConditioningInfo{ 6, 3 };
                app.PolyDriftDropDownValue{ 1, 1 } = app.data.signalConditioningInfo{ 8, 3 };

                if app.camTF ~= 0 % Dual cameras
                    app.BoxFilterDropDownValue{ 2, 1 } = app.data.signalConditioningInfo{ 4, 5 };
                    app.ButterworthDropDownValue{ 2, 1 } = app.data.signalConditioningInfo{ 6, 5 };
                    app.PolyDriftDropDownValue{ 2, 1 } = app.data.signalConditioningInfo{ 8, 5 };
                end

                % Mask
                if app.data.signalConditioningInfo{2,2} == 1 % The user created a mask for camera 1

                    app.Camera1MaskCheckBox.Value = 0;
                    app.Camera1MaskCheckBox.Enable = 'on';
                    app.cmosDataGroup{ 1 } = app.cmosDataMaskFreeGroup{ 1 } .* app.maskGroup{ 1 };
                end

                if app.camTF ~= 0 % Dual cameras
                    if app.data.signalConditioningInfo{2,4} == 1 % The user created a mask for camera 2

                        app.Camera2MaskCheckBox.Value = 0;
                        app.Camera2MaskCheckBox.Enable = 'on';
                        app.cmosDataGroup{ 2 } = app.cmosDataMaskFreeGroup{ 2 } .* app.maskGroup{ 2 };
                    end
                end

                if isequal(app.Camera1MaskCheckBox.Enable, 'on')  || isequal(app.Camera2MaskCheckBox.Enable, 'on')

                    app.Image_EraseMask.Enable = 'on';
                    app.Image_ExportMask.Enable = 'on';
                end

                app.activeCamera = 404; % NOTE: because I need to let the following function work, here I temporarily set "app.activeCamera = 404". 404 will only appear at here.
                updateConditionComponents(app, 1)
                app.activeCamera = 1;
            end
        end


        function changeActiveCamera(app, newActiveCamera)

            if ( app.camTF == 1 )  &&  ( app.activeCamera ~= newActiveCamera ) % Dual camera

                app.CameraNALabel.Text = [ 'Camera ', num2str(newActiveCamera) ];

                % Update Signal
                if  ~isempty(app.markerXY)

                    for ID = 1 : size(app.markerXY,1)

                        delete( app.signalPlotObject{newActiveCamera,ID} )

                        row = app.markerXY(ID,2);
                        col = app.markerXY(ID,1);
                        sig = squeeze( app.cmosDataGroup{newActiveCamera}(row,col,:) );
                        app.signalPlotObject{newActiveCamera,ID} = line( app.scrnObject{ID}, app.signalTime, sig, 'LineWidth',app.lWidth, 'color',app.ColorID{ID} );


                        if app.LinkCameras.Value == 1
                            app.signalPlotObject{ 2, ID }.LineStyle = '-.';
                        end


                        if ( newActiveCamera == 1 )  &&  ( app.LinkCameras.Value == 0 )
                            delete( app.signalPlotObject{2,ID} ) % Delete camera 2 signal

                        elseif ( newActiveCamera == 2 )  &&  ( app.LinkCameras.Value == 0 )
                            delete( app.signalPlotObject{1,ID} ) % Delete camera 1 signal
                        end
                    end
                end
            end
        end


        function updateMouseXY(app)

            if app.activeCamera == 1 % Camera 1 is now active
                % app.mouseXY(1,1) = app.imageAxis1.CurrentPoint(1,1); % The CurrentPoint property contains the (x,y,z) coordinates of the mouse pointer with respect to the axes
                app.mouseXY(1,1) = ( app.mouseXY(1,1) - app.imageAxis1Locs(1,1) ) * app.cmosDataColNum / app.imageAxis1Locs(1,3);
                app.mouseXY(1,1) = round( app.mouseXY(1,1) );
                if app.mouseXY(1,1) < 1
                    app.mouseXY(1,1) = 1; % As a result, there won't appear (0,y) element requirement in a 100x100 matrix
                end
                if app.mouseXY(1,1) > app.cmosDataColNum
                    app.mouseXY(1,1) = app.cmosDataColNum;
                end

                % app.mouseXY(1,2) = app.imageAxis1.CurrentPoint(1,2); % The CurrentPoint property contains the (x,y,z) coordinates of the mouse pointer with respect to the axes
                app.mouseXY(1,2) = ( (app.imageAxis1Locs(1,2) + app.imageAxis1Locs(1,4)) - app.mouseXY(1,2) ) * app.cmosDataRowNum / app.imageAxis1Locs(1,4);
                app.mouseXY(1,2) = round( app.mouseXY(1,2) ); % Transfer relative mouse location inside imageAxis1 to absolute location inside a 100x100 matrix
                if app.mouseXY(1,2) < 1
                    app.mouseXY(1,2) = 1; % As a result, there won't appear (x,0) element requirement in a 100x100 matrix
                end
                if app.mouseXY(1,2) > app.cmosDataRowNum
                    app.mouseXY(1,2) = app.cmosDataRowNum;
                end


            elseif app.activeCamera == 2 % Camera 2 is now active
                % app.mouseXY(1,1) = app.imageAxis2.CurrentPoint(1,1); % The CurrentPoint property contains the (x,y,z) coordinates of the mouse pointer with respect to the axes
                app.mouseXY(1,1) = ( app.mouseXY(1,1) - app.imageAxis2Locs(1,1) ) * app.cmosDataColNum / app.imageAxis2Locs(1,3);
                app.mouseXY(1,1) = round( app.mouseXY(1,1) );
                if app.mouseXY(1,1) < 1
                    app.mouseXY(1,1) = 1; % As a result, there won't appear (0,y) element requirement in a 100x100 matrix
                end
                if app.mouseXY(1,1) > app.cmosDataColNum
                    app.mouseXY(1,1) = app.cmosDataColNum;
                end

                % app.mouseXY(1,2) = app.imageAxis2.CurrentPoint(1,2); % The CurrentPoint property contains the (x,y,z) coordinates of the mouse pointer with respect to the axes
                app.mouseXY(1,2) = ( (app.imageAxis2Locs(1,2) + app.imageAxis2Locs(1,4)) - app.mouseXY(1,2) ) * app.cmosDataRowNum / app.imageAxis2Locs(1,4);
                app.mouseXY(1,2) = round( app.mouseXY(1,2) ); % Transfer relative mouse location inside imageAxis1 to absolute location inside a 100x100 matrix
                if app.mouseXY(1,2) < 1
                    app.mouseXY(1,2) = 1; % As a result, there won't appear (x,0) element requirement in a 100x100 matrix
                end
                if app.mouseXY(1,2) > app.cmosDataRowNum
                    app.mouseXY(1,2) = app.cmosDataRowNum;
                end
            end
        end


        function updateMarker(app)

            if ~isempty(app.mouseDragID) % Single click

                X = app.markerXY( app.mouseDragID, 1 );
                Y = app.markerXY( app.mouseDragID, 2 );

                app.markerPlotObject{ 1, app.mouseDragID }.XData = X;
                app.markerPlotObject{ 1, app.mouseDragID }.YData = Y;

                if app.camTF ~= 0 % Dual camera
                    app.markerPlotObject{ 2, app.mouseDragID }.XData = X;
                    app.markerPlotObject{ 2, app.mouseDragID }.YData = Y;
                end


            else % Double click

                X = app.markerXY( app.clickCount, 1 );
                Y = app.markerXY( app.clickCount, 2 );

                delete( app.markerPlotObject{ 1, app.clickCount } )
                app.markerPlotObject{ 1, app.clickCount } = line( app.imageAxis1, X,Y, 'Marker','square' );
                app.markerPlotObject{ 1, app.clickCount }.MarkerSize = 10;
                app.markerPlotObject{ 1, app.clickCount }.MarkerEdgeColor = 'White';
                app.markerPlotObject{ 1, app.clickCount }.MarkerFaceColor = app.ColorID{app.clickCount};

                if app.camTF ~= 0 % Dual camera
                    delete( app.markerPlotObject{ 2, app.clickCount } )
                    app.markerPlotObject{ 2, app.clickCount } = line( app.imageAxis2, X,Y, 'Marker','square' );
                    app.markerPlotObject{ 2, app.clickCount }.MarkerSize = 10;
                    app.markerPlotObject{ 2, app.clickCount }.MarkerEdgeColor = 'White';
                    app.markerPlotObject{ 2, app.clickCount }.MarkerFaceColor = app.ColorID{app.clickCount};
                end
            end
        end


        function updateSignal(app)

            ID = (app.activeCamera == 1) + 1; % When app.activeCamera is 1 (or 2), ID is 2 (or 1)

            if ~isempty(app.mouseDragID) % Single click

                row = app.markerXY( app.mouseDragID, 2 );
                col = app.markerXY( app.mouseDragID, 1 );

                app.signalPlotObject{ app.activeCamera, app.mouseDragID }.YData = squeeze( app.cmosDataGroup{app.activeCamera}(row,col,:) );

                if app.LinkCameras.Value == 0
                    delete( app.signalPlotObject{ ID, app.mouseDragID } )
                else
                    app.signalPlotObject{ ID, app.mouseDragID }.YData = squeeze( app.cmosDataGroup{ID}(row,col,:) );
                end


            else % Double click

                row = app.markerXY( app.clickCount, 2 );
                col = app.markerXY( app.clickCount, 1 );

                delete( app.signalPlotObject{ app.activeCamera, app.clickCount } )
                sig = squeeze( app.cmosDataGroup{app.activeCamera}(row,col,:) );
                app.signalPlotObject{ app.activeCamera, app.clickCount } = line( app.scrnObject{app.clickCount}, app.signalTime, sig );
                app.signalPlotObject{ app.activeCamera, app.clickCount }.LineWidth = app.lWidth;
                app.signalPlotObject{ app.activeCamera, app.clickCount }.Color = app.ColorID{app.clickCount};

                delete( app.signalPlotObject{ ID, app.clickCount } )
                if app.LinkCameras.Value == 1
                    sig = squeeze( app.cmosDataGroup{ID}(row,col,:) );
                    app.signalPlotObject{ ID, app.clickCount } = line( app.scrnObject{app.clickCount}, app.signalTime, sig );
                    app.signalPlotObject{ ID, app.clickCount }.LineWidth = app.lWidth;
                    app.signalPlotObject{ ID, app.clickCount }.Color = app.ColorID{app.clickCount};


                    app.signalPlotObject{ 2, app.clickCount }.LineStyle = '-.';
                end
            end
        end


        function updateAllSignals(app)

            ID = (app.activeCamera == 1) + 1; % When app.activeCamera is 1 (or 2), ID is 2 (or 1)

            for index = 1 : size(app.markerXY,1)

                delete( app.signalPlotObject{app.activeCamera,index} )
                row = app.markerXY(index,2); % Y
                col = app.markerXY(index,1); % X
                sig = squeeze( app.cmosDataGroup{app.activeCamera}(row,col,:) );
                app.signalPlotObject{app.activeCamera,index} = line( app.scrnObject{index}, app.signalTime, sig, 'LineWidth',app.lWidth, 'color',app.ColorID{index} );

                delete( app.signalPlotObject{ID,index} )
                if app.LinkCameras.Value == 1
                    sig = squeeze( app.cmosDataGroup{ID}(row,col,:) );
                    app.signalPlotObject{ID,index} = line ( app.scrnObject{index}, app.signalTime, sig, 'LineWidth',app.lWidth, 'color',app.ColorID{index} );


                    app.signalPlotObject{2,index}.LineStyle = '-.';
                end
            end
        end


        function updateVideoFrame(app) % Todo

            if app.currentFrameID ~= 0 % Display colourful image

                if app.camTF == 0 % Single camera
                    app.imFrame{1,1}.CData = app.cmosDataGroup{1}(:,:,app.currentFrameID);
                    app.imFrame{1,1}.AlphaData = app.imFrameAlpha{1,1};
                    drawnow

                else % Dual camers
                    app.imFrame{1,1}.CData = app.cmosDataGroup{1}(:,:,app.currentFrameID);
                    app.imFrame{2,1}.CData = app.cmosDataGroup{2}(:,:,app.currentFrameID);
                    app.imFrame{1,1}.AlphaData = app.imFrameAlpha{1,1};
                    app.imFrame{2,1}.AlphaData = app.imFrameAlpha{2,1};
                    drawnow
                end

            else % Display background image

                if app.camTF == 0 % Single camera
                    app.imFrame{1,1}.CData = app.bgImageNomGroup{1};
                    drawnow

                else % Dual camers
                    app.imFrame{1,1}.CData = app.bgImageNomGroup{1};
                    app.imFrame{2,1}.CData = app.bgImageNomGroup{2};
                    drawnow
                end
            end
        end


        function updateSweepBar(app) % If the current frame is a "bgimage" frame, then it will delete all the sweepbar previously plotted.

            if app.currentFrameID ~= 0 % Display colourful image
                for ID = 1 : size(app.markerXY)
                    app.timeIndicators{ID,1}.Value = app.currentFrameID / app.acqFreq;
                end

            else % Display background image
                for ID = 1 : size(app.markerXY)
                    app.timeIndicators{ID,1}.Value = -1;
                end
            end
        end


        function setupCameraFrameInfo(app)

            app.currentFrameID = 0;

            % Set camera1 frame
            hold(app.imageAxis1,'on')
            app.bgFrame{1,1} = imagesc( app.imageAxis1, app.bgImageNomGroup{1} );
            app.imFrame{1,1} = imagesc( app.imageAxis1, app.bgImageNomGroup{1} );

            %             app.imFrame{1,1} = pcolor( app.imageAxis1, app.bgImageNomAdjustGroup{1} );
            %             app.imFrame{1,1}.FaceColor = 'interp';
            %             app.imFrame{1,1}.EdgeColor = 'none';
            %             app.imFrame{1,1}.CData = app.bgImageNomGroup{1};

            hold(app.imageAxis1,'off')
            app.imFrameAlpha{1,1} = ~isnan( app.cmosDataGroup{1}(:,:,1) ); % The transparency of imFrame for camera 1 and camera 2

            if app.camTF ~= 0 % Dual cameras

                % Set camera2 frame information
                hold(app.imageAxis2,'on')
                app.bgFrame{2,1} = imagesc( app.imageAxis2, app.bgImageNomGroup{2} ); % For single camera, plot background image on the first background image axis
                app.imFrame{2,1} = imagesc( app.imageAxis2, app.bgImageNomGroup{2} );
                hold(app.imageAxis2,'off')
                app.imFrameAlpha{2,1} = ~isnan( app.cmosDataGroup{2}(:,:,1) ); % The transparency of imFrame for camera 1 and camera 2
            end
        end


        function organizeGSHFiles(app)

            fileListCopy = app.gshFileList; % 'app.gshFileList' is a cell column
            rowNum = size(fileListCopy, 1);
            gshFileStruct = cell( rowNum,3 ); % One row is: [{displayName}, {cam1 file}, {cam2 file}]. If single camera then cam2 file is {}.

            gshFileStruct(:) = {''};


            numUniqueFiles = 1; % Index for gshFileStruct

            while ~isempty(fileListCopy)
                rowNum = size(fileListCopy, 1);
                filename = fileListCopy{1};
                fileID = fopen([app.dirName, '/', filename],'r'); % Open the specified file for reading (i.e., 'r').

                textInfo = textscan( fileID, '%s', 'Delimiter','');
                textInfo = textInfo{1}; % 'textInfo' is a column cell. Each row is one text line in the loaded .rsh file

                fclose(fileID);

                acqVersion = ~contains(textInfo{1}, 'DataName'); % 0 if MICAM, 1 if BV Workbench. MiCAM generated file's first line has 'DataName'

                if acqVersion == 1 % BV Workbench
                    if size(fileListCopy, 1) == 1 % Only 1 file left -> has to be single camera
                        gshFileStruct{numUniqueFiles,1} = filename; % Display name
                        gshFileStruct{numUniqueFiles,2} = filename; % Camera 1 file
                        gshFileStruct{numUniqueFiles,3} = {};

                        %                         fileListCopy = {}; % Remove other file from list
                    else
                        % Get the CAM #
                        charIndex = strfind( filename, 'CAM'); % 'charIndex' the index of the character we are searching for
                        camNum = str2double(filename(charIndex+3)); % charIndex gave the position of 'C' in CAM, so add 3 positions to get number

                        % Probably not needed since files are in order, but in case
                        otherPossibleCamNum = double(camNum == 1) + 1; % If camNum is 1, logical expression becomes 1 and 1 is added to get 2. If canNum is 2, logical expression is 0 and 1 is added to get 1.

                        otherPossibleFilename = filename;
                        otherPossibleFilename(charIndex+3) = num2str(otherPossibleCamNum);

                        for i = 2 : rowNum % Iterate through other files to check if complementary camera file exists
                            if strcmp(fileListCopy{i}, otherPossibleFilename) == 1
                                %if fileListCopy{i} == otherPossibleFilename before % Found matching file
                                % Get display name
                                actualFilename = split(filename, '_'); % Split filename into substrings separated by '_'.
                                actualFilename = join(actualFilename(1:end-1),'_'); % If any '_' are in the actual filename, keep them.
                                actualFilename = actualFilename{1}; % Above line resulted in 1x1 cell array, so get the string from that.

                                gshFileStruct{numUniqueFiles,1} = [actualFilename, '.gsh']; % Display name
                                gshFileStruct{numUniqueFiles,2} = filename; % Camera 1 file
                                gshFileStruct{numUniqueFiles,3} = otherPossibleFilename; % Camera 2 file

                                fileListCopy(i) = []; % Remove other file from list
                                break;

                            elseif  i == rowNum % Single camera
                                gshFileStruct{numUniqueFiles,1} = filename; % Display name
                                gshFileStruct{numUniqueFiles,2} = filename; % Camera 1 file
                                gshFileStruct{numUniqueFiles,3} = {};

                                %                                 fileListCopy(i) = []; % Remove other file from list
                            end
                        end
                    end

                else % MiCAM generated file
                    % Figure out whether there is a dual camera or not. This is not
                    % done for the BV Workbench file because there is an additional
                    % text file that would have to be opened to figure it out... too
                    % much work.
                    dualCamLine = find(contains(textInfo(:), 'dual_cam'));
                    isDualCam = ~any( [contains(textInfo{dualCamLine},'dual_cam=0'), contains(textInfo{dualCamLine},'dual_cam=1')] ); % True (i.e., dual cameras) if answer is 1. False (i.e., single camera) if answer is 0.

                    if isDualCam == 1 % Dual camera
                        charIndex = strfind( filename, '.gsh'); % 'charIndex' the index of the character we are searching for
                        camLetter = filename(charIndex-1); % charIndex gave the position of '.' in .gsh, so subtract 1 position to get letter

                        otherPossibleFilename = filename;
                        otherPossibleFilename(charIndex-1) = 'B';

                        for i=2:size(fileListCopy, 1) % Iterate through other files to check if complementary camera file exists
                            if strcmp(fileListCopy{i}, otherPossibleFilename) == 1  % fileListCopy{i} == otherPossibleFilename % Found matching file
                                % Get display name
                                actualFilename = filename(1:charIndex-2); % charIndex give position of the '.' in '.gsh', the character before that is the camera letter, so 2 needs to be subtracted.

                                gshFileStruct{numUniqueFiles,1} = [actualFilename, '.gsh']; % Display name
                                gshFileStruct{numUniqueFiles,2} = filename; % Camera 1 file
                                gshFileStruct{numUniqueFiles,3} = otherPossibleFilename; % Camera 2 file

                                fileListCopy(i) = []; % Remove other file from list
                                break;
                            end
                        end

                        if isequal( gshFileStruct{numUniqueFiles, 1}, '' ) % it could not find the other file, so treat as single file
                            gshFileStruct{numUniqueFiles,1} = filename; % Display name
                            gshFileStruct{numUniqueFiles,2} = filename; % Camera 1 file
                            gshFileStruct{numUniqueFiles,3} = {};
                        end

                    else % Single camera
                        gshFileStruct{numUniqueFiles,1} = filename; % Display name
                        gshFileStruct{numUniqueFiles,2} = filename; % Camera 1 file
                        gshFileStruct{numUniqueFiles,3} = {};


                    end
                end
                fileListCopy(1) = []; % Remove current file from list
                numUniqueFiles = numUniqueFiles + 1;


            end
            % Get rid of empty lines
            app.gshFileStructure = gshFileStruct(1:numUniqueFiles-1, 1:3);
            %             app.gshFileStructure = gshFileStruct(1:numUniqueFiles, 1:3);
        end


        function resetVariables(app)

            app.OMZer_FileInformation.UserCommentTextArea.Value = {''};

            app.batchProcessingFileList = {};

            app.data = [];
            app.acqFreq = [];
            app.pcl = [];
            app.camTF = [];
            app.cmosDataRawMaskFreeGroup = [];
            app.cmosDataMaskFreeGroup = [];
            app.cmosDataGroup = [];
            app.maskGroup = [];
            app.bgImageNomGroup = [];
            app.cmosDataRowNum = [];
            app.cmosDataColNum = [];
            app.signalTime = [];
            app.pacing1 = [];
            app.pacing2 = [];
            app.pacingTime = [];
            app.userComment = {''};

            app.clickCount = 1; % Record how many times the mouse double-click in imageAxis1 and imageAxis2
            app.mouseMotion = [];
            app.mouseDragID = [];
            app.mouseXY = [];
            app.markerXY = [];

            app.imageAxis1Locs = [];
            app.imageAxis2Locs = [];

            app.markerPlotObject = cell(2,6); % 'Row 1 (or 2)' - camera 1 (or 2)
            app.signalPlotObject = cell(2,6); % 'Row 1 (or 2)' - camera 1 (or 2)
            app.pacingPlotObject = cell(2,6); % 'Row 1 (or 2)' - pacing 1 (or 2)
            app.maskPlotObject = cell(2,1);

            app.activeCamera = 1; % 1 (or 2) - camera 1 (or 2) active
            app.CameraNALabel.Text = 'Camera N/A';
            app.bgFrame = cell(2,1); % 'background fram' - store background image for camera 1 and camera 2
            app.imFrame = cell(2,1); % 'image Frame' - store current movie frame for camera 1 and camera 2
            app.imFrameAlpha = cell(2,1); % The transparency of imFrame for camera 1 and camera 2
            app.timeIndicators = cell(6,1);
            app.currentFrameID = [];

            app.conditionLampOnOff = num2cell( zeros(2,8) ); % 'Row 1 (or 2)' - camera 1 (or 2)
            app.BoxFilterDropDownValue = {'3';'3'}; % 'Row 1 (or 2)' - camera 1 (or 2)
            app.ButterworthDropDownValue = {'100';'100'}; % 'Row 1 (or 2)' - camera 1 (or 2)
            app.PolyDriftDropDownValue = {'1';'1'}; % 'Row 1 (or 2)' - camera 1 (or 2)

            app.sectionStartIndex = [];
            app.sectionEndIndex = [];

            app.topHatWindowStartIndex = [];
            app.topHatWindowEndIndex = [];
        end


        function enableMenuComponents(app,state)

            app.Image_Save.Enable = state;

            app.Image_VmAnalysis.Enable = state;
            app.Image_CaAnalysis.Enable = state;

            app.Image_VmCaLinkedAnalysis.Enable = state;
            if isequal(app.camTF, 0)  &&  isequal(state, 'on') % camTF = 0 (or 1) means single (or dual)
                app.Image_VmCaLinkedAnalysis.Enable = 'off';
            end

            app.Image_VmPhaseAnalysis.Enable = state;
            app.Image_VmDominantFrequencyAnalysis.Enable = state;
            
            app.Image_MaskSetup.Enable = state;
            app.Image_ExportData.Enable = state;
            app.Image_ExportFigure.Enable = state;
        end


        function enableConditionComponents(app,state)

            % Condition check box
            app.InvertCheckBox.Enable = state;
            app.BoxFilterCheckBox.Enable = state;
            app.Denoise60HzCheckBox.Enable = state;
            app.ButterworthFilterCheckBox.Enable = state;
            app.SGCheckBox.Enable = state;
            app.PolyfitDedriftCheckBox.Enable = state;
            app.TopHatDedriftbetaCheckBox.Enable = state;
            app.NormaliseCheckBox.Enable = state;

            % Condition drop down
            app.BoxFilterDropDown.Enable = state;
            app.ButterworthDropDown.Enable = state;
            app.PolyDriftDropDown.Enable = state;

            % Button
            app.Image_Apply.Enable = state;
            app.Image_Reset.Enable = state;
        end


        function resetConditionComponents(app)

            % Condition check box
            app.InvertCheckBox.Value = 0;
            app.BoxFilterCheckBox.Value = 0;
            app.Denoise60HzCheckBox.Value = 0;
            app.ButterworthFilterCheckBox.Value = 0;
            app.SGCheckBox.Value = 0;
            app.PolyfitDedriftCheckBox.Value = 0;
            app.TopHatDedriftbetaCheckBox.Value = 0;
            app.NormaliseCheckBox.Value = 0;

            % Condition drop down
            app.BoxFilterDropDown.Value = '3';
            app.ButterworthDropDown.Value = '100';
            app.PolyDriftDropDown.Value = '1';

            app.BoxFilterDropDownValue = {'3';'3'}; % 'Row 1 (or 2)' - camera 1 (or 2)
            app.ButterworthDropDownValue = {'100';'100'}; % 'Row 1 (or 2)' - camera 1 (or 2)
            app.PolyDriftDropDownValue = {'1';'1'}; % 'Row 1 (or 2)' - camera 1 (or 2)

            % Condition lamp
            app.InvertLamp.Color = [0.90, 0.90, 0.90]; % Gray
            app.BoxFilterLamp.Color = [0.90, 0.90, 0.90];
            app.DenoiseLamp.Color = [0.90, 0.90, 0.90];
            app.ButterworthLamp.Color = [0.90, 0.90, 0.90];
            app.SGLamp.Color = [0.90, 0.90, 0.90];
            app.PolyDedriftLamp.Color = [0.90, 0.90, 0.90];
            app.TopHatDedriftLamp.Color = [0.90, 0.90, 0.90];
            app.NormaliseLamp.Color = [0.90, 0.90, 0.90];

            app.conditionLampOnOff = num2cell( zeros(2,8) );
        end


        function enableImageAxisComponents(app,state)

            % File directory and file name
            app.DirectoryEditField.Value = '';

            app.FileNameEditField.Value = '';
            app.FileNameEditField.Enable = state;
            app.fileExtensionLabel.Text = '.Ext';
            app.Image_Rename.Enable = state;

            % Camera mask
            app.Camera1MaskCheckBox.Value = 0;
            app.Camera1MaskCheckBox.Enable = 'off';

            app.Camera2MaskCheckBox.Value = 0;
            app.Camera2MaskCheckBox.Enable = 'off';

            app.Image_EraseMask.Enable = 'off';
            app.Image_ExportMask.Enable = 'off';

            cla(app.imageAxis1)
            cla(app.imageAxis2)

            % Movie
            app.MovieSlider.Enable = state;
            app.MovieSlider.Value = 0;
            app.MoviesecSpinner.Enable = state;
            app.MoviesecSpinner.Value = 0;

            app.bgFrame = cell(2,1); % 'background fram' - store background image for camera 1 and camera 2
            app.imFrame = cell(2,1); % 'image Frame' - store current movie frame for camera 1 and camera 2
            app.imFrameAlpha = cell(2,1); % The transparency of imFrame for camera 1 and camera 2
            app.timeIndicators = cell(6,1);
            app.currentFrameID = 0;
        end


        function resetSignalDisplayCompontnes(app)

            for ID = 1 : 6
                cla( app.scrnObject{ID} );
                app.Xs{ID}.Limits = [-Inf, Inf];
                app.Ys{ID}.Limits = [-Inf, Inf];
                app.Xs{ID}.Value = 0;
                app.Ys{ID}.Value = 0;
                app.Xs{ID}.Editable = 'off';
                app.Ys{ID}.Editable = 'off';
                app.Xs{ID}.BackgroundColor = [1.00, 1.00, 1.00]; % White
                app.Ys{ID}.BackgroundColor = [1.00, 1.00, 1.00];
            end

            app.pacing1CheckBox.Value = 0;
            app.pacing1CheckBox.Enable = 'off';
            app.pacing2CheckBox.Value = 0;
            app.pacing2CheckBox.Enable = 'off';

            app.LinkCameras.Value = 0;
            app.LinkCameras.Enable = 'off';

            LB = 0;
            UB = Inf;
            setScrnLimit(app, LB, UB, 'off')

            app.Image_Section.Enable = 'off';
            app.sectionStartIndex = [];
            app.sectionEndIndex = [];
        end


        function prepareBatchProcessing(app)

            app.OMZer_FileInformation.BatchProcessingCheckBox.Enable = 'on';
            app.OMZer_FileInformation.BatchProcessingCheckBox.Value = 0;
            app.OMZer_FileInformation.Image_ConfirmSelection.Enable = 'off';
            app.OMZer_FileInformation.Image_UndoSelection.Enable = 'off';
        end


        function enableBatchProcessingComponents(app, state)

            app.OMZer_FileInformation.BatchProcessingProgressEditField.Value = 0;

            app.batchProcessingLampOnOff = num2cell( zeros(2,8) ); % 'Row 1 (or 2)' - camera 1 (or 2)
            app.batchProcessingBoxFilterDropDownValue = {'3';'3'}; % 'Row 1 (or 2)' - camera 1 (or 2)
            app.batchProcessingButterworthDropDownValue = {'100';'100'}; % 'Row 1 (or 2)' - camera 1 (or 2)
            app.batchProcessingPolyDriftDropDownValue = {'1';'1'}; % 'Row 1 (or 2)' - camera 1 (or 2)

            app.OMZer_FileInformation.BatchProcessingCamera1Button.Value = 1;
            app.OMZer_FileInformation.BatchProcessingCamera1Button.Enable = state;
            app.OMZer_FileInformation.BatchProcessingCamera2Button.Enable = state;

            app.OMZer_FileInformation.Image_Apply.Enable = state;
            app.OMZer_FileInformation.Image_Start.Enable = state;
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            %% Icon URL
            % https://icons8.com/icon/set/center/color-glass--static

            %% MATLAB Plot Gallery
            % https://www.mathworks.com/products/matlab/plot-gallery.html

            %% Creating Multiwindow Apps in App Designer
            % https://www.mathworks.com/help/matlab/creating_guis/creating-multiwindow-apps-in-app-designer.html#mw_b4d7e3b6-7641-40a6-8dd3-148c03c77298

            %% Share Data Within App Designer Apps
            % https://www.mathworks.com/help/matlab/creating_guis/share-data-across-callbacks-in-app-designer.html#responsive_offcanvas


            msg = 'Configuring files';
            progressBar = uiprogressdlg(app.OMZer_UIFigure, 'Title','Initialising', 'Message',msg, 'Indeterminate','on');
            drawnow

            clc

            % (1) User setup
            app.setup = load([pwd,'/OMZer_Setup']);
            setColourMap(app, app.setup.userColourMap);

            % (2) Create components collection
            app.scrnObject = {app.scrn1, app.scrn2, app.scrn3, app.scrn4, app.scrn5, app.scrn6};
            app.Xs = {app.X_scrn1, app.X_scrn2, app.X_scrn3, app.X_scrn4, app.X_scrn5, app.X_scrn6};
            app.Ys = {app.Y_scrn1, app.Y_scrn2, app.Y_scrn3, app.Y_scrn4, app.Y_scrn5, app.Y_scrn6};
            app.ColorID = {app.Color1, app.Color2, app.Color3, app.Color4, app.Color5, app.Color6};

            app.imageMaskComponentsObject = { app.Camera1MaskCheckBox; app.Camera2MaskCheckBox };

            app.imageAxisObject = { app.imageAxis1; app.imageAxis2 };

            app.conditionLampObject = {app.InvertLamp, app.BoxFilterLamp, app.DenoiseLamp, app.ButterworthLamp, app.SGLamp, app.PolyDedriftLamp, app.TopHatDedriftLamp, app.NormaliseLamp};
            app.conditionBoxObject = {app.InvertCheckBox, app.BoxFilterCheckBox, app.Denoise60HzCheckBox, app.ButterworthFilterCheckBox, app.SGCheckBox, app.PolyfitDedriftCheckBox, app.TopHatDedriftbetaCheckBox, app.NormaliseCheckBox};

            % (3) Diable Toolbar
            % https://www.mathworks.com/help/matlab/ref/axtoolbar.html?searchHighlight=axtoolbar&s_tid=doc_srchtitle
            try
                disableDefaultInteractivity(app.imageAxis1);
                disableDefaultInteractivity(app.imageAxis2);
                axtoolbar(app.imageAxis1);
                axtoolbar(app.imageAxis2);

                for ID = 1 : 6
                    disableDefaultInteractivity( app.scrnObject{ID} );
                    axtoolbar( app.scrnObject{ID} );
                end

            catch
                % Do nothing
            end

            close(progressBar)

        end

        % Image clicked function: Image_OpenFolder
        function Image_OpenFolderClicked(app, event)

            showImageIconAct(app, app.Image_OpenFolder)

            app.dirName = uigetdir;

            if ( ~ischar(app.dirName) )  &&  ( app.dirName == 0 ) % The user 'Cancel' directory selection

                if isempty(app.DirectoryEditField.Value) % No previous directory at all

                    msg = 'No new file directory is set';
                    uialert(app.OMZer_UIFigure, msg,'Warning','Icon','error')

                elseif ~isempty(app.DirectoryEditField.Value) % A previous directory exists

                    app.dirName = app.DirectoryEditField.Value;
                end

                figure(app.OMZer_UIFigure) % Set this uifigure at the top level


            else % The user chose directory selection

                % Reset the tool --------------------
                % (1) Reset to default value
                resetVariables(app)
                enableMenuComponents(app,'off')

                enableConditionComponents(app,'off')
                resetConditionComponents(app)

                enableImageAxisComponents(app,'off')
                resetSignalDisplayCompontnes(app)

                closeAllChildrenApp(app, {})

                app.fileName = [];
                app.fileNameWithExtension = [];
                app.DirectoryEditField.Value = app.dirName;

                % (2) Prepare 'rsh' file list
                rshFileName = [app.dirName,'/*.rsh']; % Find all the'.rsh' files.
                rshFileName = dir(rshFileName); % "dir" lists folder contents. Now 'rshFileName' is a structure.
                rshFileName = struct2cell(rshFileName); % "struct2cell" turns the struct to a cell. Now 'rshFileName' is a cell.
                app.rshFileList = rshFileName(1,:)'; % Creat a cell column list for .rsh files.
                if isempty(app.rshFileList)
                    app.rshFileList = {};
                end

                % (3) Prepare 'mat' file list
                matFileName = [app.dirName,'/*.mat'];
                matFileName = dir(matFileName);
                matFileName = struct2cell(matFileName);
                app.matFileList = matFileName(1,:)';
                if isempty(app.matFileList)
                    app.matFileList = {};
                end

                % (4) Prepare 'gsh' file list
                gshFileName = [app.dirName,'/*.gsh'];
                gshFileName = dir(gshFileName);
                gshFileName = struct2cell(gshFileName);
                app.gshFileList = gshFileName(1,:)';
                if isempty(app.gshFileList)
                    app.gshFileList = {};
                end
                organizeGSHFiles(app); % Creates the 'app.gshFileStructure'

                % (5) List all files ('allFileList' is a cell column)
                app.OMZer_FileInformation = OMZer_FileInformation(app); % Open 'OMZer_FileInformation.mlapp'

                app.allFileList = [ {'--- --- --- --- --- --- --- --- --- --- RSH Files --- --- --- --- --- --- --- --- --- ---'}; ...
                    app.rshFileList; ...
                    {'--- --- --- --- --- --- --- --- --- --- GSH Files --- --- --- --- --- --- --- --- --- ---'}; ...
                    app.gshFileStructure(:,1); ...
                    {'--- --- --- --- --- --- --- --- --- --- MAT Files --- --- --- --- --- --- --- --- --- ---'}; ...
                    app.matFileList ]; % Concatenate cell arrays with the array concatenation operator, [].  'https://www.mathworks.com/help/matlab/matlab_prog/combine-cell-arrays.html'

                app.OMZer_FileInformation.fileListBox.Items = app.allFileList;
                app.OMZer_FileInformation.fileListBox.Value = {}; % 'Value = {}' means there is no default selection of a file

                prepareBatchProcessing(app)
                enableBatchProcessingComponents(app,'off')

                if isempty(app.rshFileList)  &&  isempty(app.matFileList)  &&  isempty(app.gshFileList)

                    msg = 'No ".rsh", ".gsh", or ".mat" files can be found.';
                    uialert(app.OMZer_UIFigure, msg,'Warning','Icon','error')
                    uialert(app.OMZer_FileInformation.OMZer_FileInformation_UIFigure, msg,'Warning','Icon','error')

                    app.OMZer_FileInformation.BatchProcessingCheckBox.Enable = 'off';

                else
                    app.OMZer_FileInformation.BatchProcessingCheckBox.Enable = 'on';
                end

                figure(app.OMZer_UIFigure) % Set this uifigure at the top level
                figure(app.OMZer_FileInformation.OMZer_FileInformation_UIFigure)
            end

        end

        % Image clicked function: Image_Save
        function Image_SaveClicked(app, event)

            showImageIconAct(app, app.Image_Save)

            msg = 'Save data to the file';
            progressBar = uiprogressdlg(app.OMZer_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');

            newFileName = [app.dirName, '/', app.fileName, '.mat'];

            if app.camTF == 0 % Single camera

                cmosData1Raw = app.cmosDataRawMaskFreeGroup{1};
                cmosData1 = app.cmosDataMaskFreeGroup{1};
                userMaskMatrix_cam1 = app.maskGroup{1};
                analog1 = app.pacing1;
                analog2 = app.pacing2;
                analogTime = app.pacingTime;
                signalTime = app.signalTime;
                bgImageNom1 = app.bgImageNomAdjustGroup{1};

                signalConditioningInfo(1,:) = {'Conditioning Name', 'Camera 1 - Condition On (1) / Off (0)', 'Camera 1 - Other Info'};
                signalConditioningInfo(2,:) = {'maskOnOff',         double(~isempty(app.maskGroup{1})),    'N/A'};
                signalConditioningInfo(3,:) = {'InvertOnOff',       app.conditionLampOnOff{1,1},    'N/A'};
                signalConditioningInfo(4,:) = {'BoxFilterOnOff',    app.conditionLampOnOff{1,2},    app.BoxFilterDropDownValue{1,1}};
                signalConditioningInfo(5,:) = {'DenoiseOnOff',      app.conditionLampOnOff{1,3},    'N/A'};
                signalConditioningInfo(6,:) = {'ButterworthOnOff',  app.conditionLampOnOff{1,4},    app.ButterworthDropDownValue{1,1}};
                signalConditioningInfo(7,:) = {'SGOnOff',           app.conditionLampOnOff{1,5},    'N/A'};
                signalConditioningInfo(8,:) = {'PolyDedriftOnOff',  app.conditionLampOnOff{1,6},    app.PolyDriftDropDownValue{1,1}};
                signalConditioningInfo(9,:) = {'TopHatDedriftOnOff',app.conditionLampOnOff{1,7},    'N/A'};
                signalConditioningInfo(10,:) = {'NormaliseOnOff',   app.conditionLampOnOff{1,8},    'N/A'};

                comment = app.userComment;

                systemSetupComment = app.data.systemSetupComment;

                save(newFileName, 'bgImageNom1', 'signalConditioningInfo', 'comment', 'systemSetupComment', '-append')

                if app.signalMask_Yes_No == 1

                    save(newFileName, 'userMaskMatrix_cam1', '-append')
                end
                app.signalMask_Yes_No = 0;

                if app.signalCondition_Yes_No == 1

                    save(newFileName, 'cmosData1', '-append')
                end
                app.signalCondition_Yes_No = 0;

                if app.signalSection_Yes_No == 1

                    save(newFileName, 'cmosData1Raw', 'cmosData1', 'analog1', 'analog2', 'analogTime', 'signalTime', '-append')
                end
                app.signalSection_Yes_No = 0;


            elseif app.camTF == 1 % Dual cameras

                cmosData1Raw = app.cmosDataRawMaskFreeGroup{1};
                cmosData2Raw = app.cmosDataRawMaskFreeGroup{2};
                cmosData1 = app.cmosDataMaskFreeGroup{1};
                cmosData2 = app.cmosDataMaskFreeGroup{2};
                userMaskMatrix_cam1 = app.maskGroup{1};
                userMaskMatrix_cam2 = app.maskGroup{2};
                analog1 = app.pacing1;
                analog2 = app.pacing2;
                analogTime = app.pacingTime;
                signalTime = app.signalTime;
                bgImageNom1 = app.bgImageNomAdjustGroup{1};
                bgImageNom2 = app.bgImageNomAdjustGroup{2};

                signalConditioningInfo(1,:) = {'Conditioning Name', 'Camera 1 - Condition On (1) / Off (0)', 'Camera 1 - Other Info', 'Camera 2 - Condition On (1) / Off (0)', 'Camera 2 - Other Info'};
                signalConditioningInfo(2,:) = {'maskOnOff',         double(~isempty(app.maskGroup{1})),    'N/A',       double(~isempty(app.maskGroup{2})),    'N/A'};
                signalConditioningInfo(3,:) = {'InvertOnOff',       app.conditionLampOnOff{1,1},    'N/A',                              app.conditionLampOnOff{2,1},    'N/A'};
                signalConditioningInfo(4,:) = {'BoxFilterOnOff',    app.conditionLampOnOff{1,2},    app.BoxFilterDropDownValue{1,1},     app.conditionLampOnOff{2,2},    app.BoxFilterDropDownValue{2,1}};
                signalConditioningInfo(5,:) = {'DenoiseOnOff',      app.conditionLampOnOff{1,3},    'N/A',                              app.conditionLampOnOff{2,3},    'N/A'};
                signalConditioningInfo(6,:) = {'ButterworthOnOff',  app.conditionLampOnOff{1,4},    app.ButterworthDropDownValue{1,1},   app.conditionLampOnOff{2,4},    app.ButterworthDropDownValue{2,1}};
                signalConditioningInfo(7,:) = {'SGOnOff',           app.conditionLampOnOff{1,5},    'N/A',                              app.conditionLampOnOff{2,5},    'N/A'};
                signalConditioningInfo(8,:) = {'PolyDedriftOnOff',  app.conditionLampOnOff{1,6},    app.PolyDriftDropDownValue{1,1},     app.conditionLampOnOff{2,6},    app.PolyDriftDropDownValue{2,1}};
                signalConditioningInfo(9,:) = {'TopHatDedriftOnOff',app.conditionLampOnOff{1,7},    'N/A',                              app.conditionLampOnOff{2,7},    'N/A'};
                signalConditioningInfo(10,:) = {'NormaliseOnOff',   app.conditionLampOnOff{1,8},    'N/A',                              app.conditionLampOnOff{2,8},    'N/A'};

                comment = app.userComment;

                systemSetupComment = app.data.systemSetupComment;

                save(newFileName, 'bgImageNom1', 'bgImageNom2', 'signalConditioningInfo', 'comment', 'systemSetupComment', '-append')

                if app.signalMask_Yes_No == 1

                    save(newFileName, 'userMaskMatrix_cam1', 'userMaskMatrix_cam2', '-append')
                end
                app.signalMask_Yes_No = 0;

                if app.signalCondition_Yes_No == 1

                    save(newFileName, 'cmosData1', 'cmosData2', '-append')
                end
                app.signalCondition_Yes_No = 0;

                if app.signalSection_Yes_No == 1

                    save(newFileName, 'cmosData1Raw', 'cmosData2Raw', 'cmosData1', 'cmosData2', 'analog1', 'analog2', 'analogTime', 'signalTime', '-append')
                end
                app.signalSection_Yes_No = 0;

            end

            close(progressBar)

        end

        % Image clicked function: Image_FileInformation
        function Image_FileInformationClicked(app, event)

            showImageIconAct(app, app.Image_FileInformation)

            app.OMZer_FileInformation = OMZer_FileInformation(app);

            app.OMZer_FileInformation.fileListBox.Items = app.allFileList;
            if isempty(app.FileNameEditField.Value)

                app.OMZer_FileInformation.fileListBox.Value = {}; % 'Value = {}' means there is no default selection of a file

            else
                app.OMZer_FileInformation.fileListBox.Value = [app.fileName, app.fileExt]; % app.fileNameWithExtension;
            end

            prepareBatchProcessing(app)
            enableBatchProcessingComponents(app,'off')

            if isempty(app.rshFileList)  &&  isempty(app.matFileList)  &&  isempty(app.gshFileList)

                msg = 'No ".rsh", ".gsh", or ".mat" files can be found.';
                uialert(app.OMZer_UIFigure, msg,'Warning','Icon','error')


                uialert(app.OMZer_FileInformation.OMZer_FileInformation_UIFigure, msg,'Warning','Icon','error')

                app.OMZer_FileInformation.BatchProcessingCheckBox.Enable = 'off';

            else
                app.OMZer_FileInformation.BatchProcessingCheckBox.Enable = 'on';
            end

        end

        % Image clicked function: Image_Rename
        function Image_RenameClicked(app, event)

            showImageIconAct(app, app.Image_Rename)

            newFileName = app.FileNameEditField.Value;

            if isequal(newFileName, app.fileName)

                msg = 'You actually have not changed the file name, no rename will be excuted';
                uialert(app.OMZer_UIFigure, msg,'Warning','Icon','error')

            else
                msg = 'Saving the file';
                progressBar = uiprogressdlg(app.OMZer_UIFigure, 'Title','Renaming','Message',msg, 'Indeterminate','on');

                Image_FileInformationClicked(app, event)

                if isequal(app.fileExtensionLabel.Text, '.rsh') % User loads a '.rsh' file - update '.rsh', '.rsd', '.mat' file name

                    % Load RSH file to rename filenames
                    fileID = fopen([app.dirName, '/', app.fileName, '.rsh'],'r','b'); % Open the specified file in binary (i.e., 'b') for reading (i.e., 'r').
                    fileStr = fread(fileID,'int8=>char')';% Convert binary date to letters and store them in file string.
                    fclose all; % Close all open files.

                    % Find where filename is written in file
                    filenameInds = strfind(fileStr, app.fileName);
                    %dataFileListIndex = strfind(fileStr,'Data-File-List'); % Find the starting indices of "Data-File-List".

                    % Change RSM filename line
                    newfileStr = fileStr(1:filenameInds(1)-1);
                    for line = 1 : length(filenameInds)-1
                        newfileStr = [newfileStr newFileName fileStr(filenameInds(line) + length(app.fileName):filenameInds(line+1)-1)];
                        movefile([app.dirName, '/', app.fileName, '(', num2str(line-1), ').rsd'],[app.dirName, '/', newFileName, '(', num2str(line-1), ').rsd']);
                    end
                    newfileStr = [newfileStr newFileName fileStr(filenameInds(length(filenameInds)) + length(app.fileName):end)];

                    fileID = fopen([app.dirName, '/', app.fileName, '.rsh'],'w','b'); % Open the specified file in binary (i.e., 'b') for reading (i.e., 'r').
                    fprintf(fileID, newfileStr);
                    fclose all;

                    movefile([app.dirName, '/', app.fileName, '.rsh'],[app.dirName, '/', newFileName, '.rsh'],'f');
                    movefile([app.dirName, '/', app.fileName, '.mat'],[app.dirName, '/', newFileName, '.mat'],'f');
                    movefile([app.dirName, '/', app.fileName, '.rsm'],[app.dirName, '/', newFileName, '.rsm'],'f');

                    app.fileName = newFileName;

                    callByMainApp_Image_RefreshClicked(app.OMZer_FileInformation)


                elseif isequal(app.fileExtensionLabel.Text, '.mat') % User loads a '.mat' file - update '.mat' file name only

                    movefile([app.dirName, '/', app.fileName, '.mat'],[app.dirName, '/', newFileName, '.mat'],'f');

                    app.fileName = newFileName;

                    callByMainApp_Image_RefreshClicked(app.OMZer_FileInformation)
                end

                close(progressBar)
            end

        end

        % Image clicked function: Image_VmAnalysis
        function Image_VmAnalysisClicked(app, event)

            showImageIconAct(app, app.Image_VmAnalysis)

            if ( isempty(app.markerXY) )  ||  ( isnan( app.cmosDataGroup{app.activeCamera}(app.markerXY(1,2), app.markerXY(1,1), 1) ) )

                msg = 'Choose a pixel and plot its signal before continuing';
                uialert(app.OMZer_UIFigure, msg,'Warning','Icon','error')

            else
                app.OMZer_Vm = OMZer_Vm(app);
            end

        end

        % Image clicked function: Image_CaAnalysis
        function Image_CaAnalysisClicked(app, event)

            showImageIconAct(app, app.Image_CaAnalysis)

            if ( isempty(app.markerXY) )  ||  ( isnan( app.cmosDataGroup{app.activeCamera}(app.markerXY(1,2), app.markerXY(1,1), 1) ) )

                msg = 'Choose a pixel and plot its signal before continuing';
                uialert(app.OMZer_UIFigure, msg,'Warning','Icon','error')

            else
                app.OMZer_Ca = OMZer_Ca(app);
            end

        end

        % Image clicked function: Image_VmCaLinkedAnalysis
        function Image_VmCaLinkedAnalysisClicked(app, event)

            showImageIconAct(app, app.Image_VmCaLinkedAnalysis)

            if isequal(app.camTF, 0) % camTF = 0 (or 1) means single (or dual)

                msg = 'This function is only available when there are 2 cameras';
                uialert(app.OMZer_UIFigure, msg,'Warning','Icon','error')

            elseif isequal(app.camTF, 1)

                if isempty(app.markerXY)  ||  ...
                        isnan( app.cmosDataGroup{1}(app.markerXY(1,2), app.markerXY(1,1), 1) )  ||  ...
                        isnan( app.cmosDataGroup{2}(app.markerXY(1,2), app.markerXY(1,1), 1) )

                    msg = 'Choose a pixel and plot its signal from both cameras before continuing';
                    uialert(app.OMZer_UIFigure, msg,'Warning','Icon','error')

                else
                    app.OMZer_VmCaLinked = OMZer_VmCaLinked(app);
                end
            end

        end

        % Image clicked function: Image_VmPhaseAnalysis
        function Image_VmPhaseAnalysisClicked(app, event)
            
            showImageIconAct(app, app.Image_VmPhaseAnalysis)

            app.OMZer_Vm_Phase = OMZer_Vm_Phase(app);
            
        end

        % Image clicked function: Image_VmDominantFrequencyAnalysis
        function Image_VmDominantFrequencyAnalysisClicked(app, event)
            
            showImageIconAct(app, app.Image_VmDominantFrequencyAnalysis)

        end

        % Image clicked function: Image_MaskSetup
        function Image_MaskSetupClicked(app, event)

            showImageIconAct(app, app.Image_MaskSetup)

            app.OMZer_Mask = OMZer_Mask(app);

        end

        % Image clicked function: Image_ExportData
        function Image_ExportDataClicked(app, event)

            showImageIconAct(app, app.Image_ExportData)

            msg = 'Choose the data you want to export';
            msgTitle = 'Warning';
            optionItem = { 'Export Display Pixel (.mat File)', 'Export Current Frame (.mat File)', 'Cancel' };
            selection = uiconfirm( app.OMZer_UIFigure, msg, msgTitle, ...
                'Options', optionItem, ...
                'DefaultOption', 1, ...
                'CancelOption', 3 ); % uiconfirm returns the selection as a character vector

            switch selection

                case optionItem{1} % Export Display Pixel (.mat File)

                    app.OMZer_ExportDisplayPixel = OMZer_ExportDisplayPixel(app);

                case optionItem{2} % Export Current Frame (.mat File)

                    % Get valid saving directory and file name
                    saveDir = uigetdir;

                    if saveDir == 0 % User has NOT selected file directory.

                        msg = 'Please select a valid saving directory';
                        uialert(app.OMZer_UIFigure,msg,'Warning','Icon','error')

                    else

                        prompt = {'Enter filename to save (without extension)...   Please do not use \/:*?"<>|'};
                        dlgtitle = 'Save to a .mat file';
                        dfinput = { [ app.fileName, '_Current_Frame_Data' ] };
                        dims = [1,100];
                        filename = inputdlg(prompt,dlgtitle,dims,dfinput);

                        if isempty(filename) % User has NOT entered a name

                            msg = 'Please enter a valid file name';
                            uialert(app.OMZer_UIFigure,msg,'Warning','Icon','error')

                        else

                            % Export map data
                            currentFrameIndex = round(app.MoviesecSpinner.Value * app.acqFreq);


                            currentFrameIndex = currentFrameIndex + 1;

                            if currentFrameIndex > length( app.signalTime )
                                currentFrameIndex = length( app.signalTime );
                            end


                            currentFrameTime_sec_Unit = app.signalTime( currentFrameIndex );

                            timeAxis_sec_Unit = app.signalTime;

                            backgroundImage_Camera1 = app.bgImageNomGroup{1};
                            currentFrame_Camera1 = app.cmosDataGroup{1}( :, :, currentFrameIndex ); % Filtered, masked

                            msg = [ 'Export the Current Frame' ];
                            progressBar = uiprogressdlg(app.OMZer_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');

                            fullSaveName = strcat(saveDir, '/', filename, '.mat'); % At this moment, 'fullSaveName' is a 'cell'.
                            fullSaveName = fullSaveName{1}; % Now 'fullSaveName' is a 'character
                            save(fullSaveName, ...
                                'backgroundImage_Camera1', ...
                                'currentFrame_Camera1', ...
                                'currentFrameIndex', ...
                                'currentFrameTime_sec_Unit', ...
                                'timeAxis_sec_Unit');

                            if app.camTF ~= 0 % Dual camera

                                backgroundImage_Camera2 = app.bgImageNomGroup{2};
                                currentFrame_Camera2 = app.cmosDataGroup{2}( :, :, currentFrameIndex ); % Filtered, masked

                                save(fullSaveName, ...
                                    'backgroundImage_Camera2', ...
                                    'currentFrame_Camera2', ...
                                    '-append');
                            end

                            close(progressBar)
                        end
                    end

                    figure(app.OMZer_UIFigure) % Set this uifigure at the top level
            end

        end

        % Image clicked function: Image_ExportFigure
        function Image_ExportFigureClicked(app, event)

            showImageIconAct(app, app.Image_ExportFigure)

            app.OMZer_ExportFigure = OMZer_ExportFigure(app);

        end

        % Image clicked function: Image_Setup
        function Image_SetupClicked(app, event)

            showImageIconAct(app, app.Image_Setup)

            field = { 'VmCaLinkedMeasurement' };
            TF = prod( isfield( app.data, field ) ); % 1 (or 0) - all exist (or at least one does not exist)

            if TF == 1
                disp('Has such an variable')
            else
                disp('No such an variable')
            end

        end

        % Value changed function: pacing1CheckBox
        function pacing1CheckBoxValueChanged(app, event)

            for ID = 1 : size(app.markerXY,1)
                delete(app.pacingPlotObject{1,ID})
            end

            if app.pacing1CheckBox.Value == 1

                for ID = 1 : size(app.markerXY,1)

                    hold(app.scrnObject{ID},'on')
                    app.pacingPlotObject{1,ID} = stem( app.scrnObject{ID}, app.pacingTime, app.pacing1, 'LineWidth',2, 'color','black', 'Marker','none');
                    hold(app.scrnObject{ID},'off')
                end
            end

        end

        % Value changed function: pacing2CheckBox
        function pacing2CheckBoxValueChanged(app, event)

            for ID = 1 : size(app.markerXY,1)
                delete(app.pacingPlotObject{2,ID})
            end

            if app.pacing2CheckBox.Value == 1

                for ID = 1 : size(app.markerXY,1)

                    hold(app.scrnObject{ID},'on')
                    app.pacingPlotObject{2,ID} = stem( app.scrnObject{ID}, app.pacingTime, app.pacing2, 'LineWidth',2, 'color','black', 'Marker','none');
                    hold(app.scrnObject{ID},'off')
                end
            end

        end

        % Value changed function: LinkCameras
        function LinkCamerasValueChanged(app, event)

            if app.LinkCameras.Value == 1

                msg = { 'Camera 1 - solid line'; ''; 'Camera 2 - Dash-dotted line' };
                uialert(app.OMZer_UIFigure,msg,'Warning','Icon','error')

                app.CameraNALabel.Text = 'Camera 1 & 2';

            else

                app.CameraNALabel.Text = [ 'Camera ', num2str(app.activeCamera) ];
            end

            updateAllSignals(app)

        end

        % Value changed function: MoviesecSpinner
        function MoviesecSpinnerValueChanged(app, event)

            app.currentFrameID = round(app.MoviesecSpinner.Value * app.acqFreq);
            app.MovieSlider.Value = app.currentFrameID;

            updateVideoFrame(app);
            updateSweepBar(app);

        end

        % Value changing function: MoviesecSpinner
        function MoviesecSpinnerValueChanging(app, event)

            if ischar(event.Value)
                app.MoviesecSpinner.Value = str2double(event.Value);
            else
                app.MoviesecSpinner.Value = event.Value;
            end

            MoviesecSpinnerValueChanged(app, event)

        end

        % Value changed function: MovieSlider
        function MovieSliderValueChanged(app, event)

            app.currentFrameID = round(app.MovieSlider.Value);

            app.MoviesecSpinner.Value = app.currentFrameID / app.acqFreq;

            updateVideoFrame(app);
            updateSweepBar(app);

        end

        % Value changing function: MovieSlider
        function MovieSliderValueChanging(app, event)

            app.currentFrameID = round(event.Value);

            app.MoviesecSpinner.Value = app.currentFrameID / app.acqFreq;

            updateVideoFrame(app);
            updateSweepBar(app);

        end

        % Value changed function: ViewStartsecSpinner
        function ViewStartsecSpinnerValueChanged(app, event)

            if app.ViewStartsecSpinner.Value >= app.ViewEndsecSpinner.Value

                msg = 'Start time must be eariler than end time';
                uialert(app.OMZer_UIFigure,msg,'Warning','Icon','error')

                app.ViewStartsecSpinner.Value = 0;
                app.ViewEndsecSpinner.Value = app.signalTime(end);
            end

            for ID = 1:6
                xlim( app.scrnObject{ID}, [ app.ViewStartsecSpinner.Value, app.ViewEndsecSpinner.Value ] )
            end

        end

        % Value changing function: ViewStartsecSpinner
        function ViewStartsecSpinnerValueChanging(app, event)

            if ischar(event.Value)
                app.ViewStartsecSpinner.Value = str2double(event.Value);
            else
                app.ViewStartsecSpinner.Value = event.Value;
            end

            ViewStartsecSpinnerValueChanged(app, event)

        end

        % Value changed function: ViewEndsecSpinner
        function ViewEndsecSpinnerValueChanged(app, event)

            if app.ViewStartsecSpinner.Value >= app.ViewEndsecSpinner.Value

                msg = 'Start time must be eariler than end time';
                uialert(app.OMZer_UIFigure,msg,'Warning','Icon','error')

                app.ViewStartsecSpinner.Value = 0;
                app.ViewEndsecSpinner.Value = app.signalTime(end);
            end

            for ID = 1:6
                xlim( app.scrnObject{ID}, [ app.ViewStartsecSpinner.Value, app.ViewEndsecSpinner.Value ] )
            end

        end

        % Value changing function: ViewEndsecSpinner
        function ViewEndsecSpinnerValueChanging(app, event)

            if ischar(event.Value)
                app.ViewEndsecSpinner.Value = str2double(event.Value);
            else
                app.ViewEndsecSpinner.Value = event.Value;
            end

            ViewEndsecSpinnerValueChanged(app, event)

        end

        % Image clicked function: Image_Section
        function Image_SectionClicked(app, event)

            showImageIconAct(app, app.Image_Section)

            msg = { 'By clicking "Yes" you decide to keep ONLY the section you specified while data outside the section will be deleted. Are you sure?' ; ''; ...
                'You can retrieve your data by clicking "load" button again.'; ''; ...
                'Please include at least FOUR beats inside the section if your data is long enough' };
            msgTitle = 'Warning';
            selection = uiconfirm(app.OMZer_UIFigure,msg,msgTitle, 'Options',{'Yes','Cancel'},'DefaultOption',2, 'CancelOption',2);

            if isequal(selection,'Yes')

                msg = 'Sectioning data';
                progressBar = uiprogressdlg(app.OMZer_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');

                % (1) User has chosen a data section, find the "start index" and "end index" in the signalTime
                app.sectionStartIndex = find( app.signalTime >= app.ViewStartsecSpinner.Value, 1,'first' );
                app.sectionEndIndex = find( app.signalTime <= app.ViewEndsecSpinner.Value, 1,'last' );


                % (2) Truncate analog signal, analog time, and signal time based on the index of the section
                app.pacing1 = app.pacing1(app.sectionStartIndex : app.sectionEndIndex);
                app.pacing2 = app.pacing2(app.sectionStartIndex : app.sectionEndIndex);

                app.pacingTime = app.pacingTime(app.sectionStartIndex : app.sectionEndIndex);
                app.pacingTime = app.pacingTime - app.pacingTime(1); % Shift the time so that the new section starts at 0

                app.signalTime = app.signalTime(app.sectionStartIndex : app.sectionEndIndex);
                app.signalTime = app.signalTime - app.signalTime(1); % Shift the time so that the new section starts at 0


                % (3) Update plots, sectionStartIndex, andsectionEndIndex
                app.ViewStartsecSpinner.Limits = [ 0, app.signalTime(end) ];
                app.ViewEndsecSpinner.Limits = [ 0, app.signalTime(end) ];
                app.ViewStartsecSpinner.Value = 0;
                app.ViewEndsecSpinner.Value = app.signalTime(end);
                for ID = 1:6
                    xlim(app.scrnObject{ID}, [app.ViewStartsecSpinner.Value, app.ViewEndsecSpinner.Value])
                end


                % (4) After data sectioning, re-apply signal conditioning (if the user has conditioned the signal before)
                % updateAllSignals(app);
                pacing1CheckBoxValueChanged(app, event)
                pacing2CheckBoxValueChanged(app, event)

                if app.camTF == 0 % Single camera.

                    Image_ApplyClicked(app, event)% Re-condition signal

                else % Dual cameras
                    activeCameraTemporary = app.activeCamera;

                    linkCameraTemporary = app.LinkCameras.Value;
                    app.LinkCameras.Value = 0;

                    % Condition camera 1 signal
                    updateConditionComponents(app, 1)
                    app.activeCamera = 1;
                    Image_ApplyClicked(app, event)

                    % Condition camera 2 signal
                    updateConditionComponents(app, 2)
                    app.activeCamera = 2;
                    Image_ApplyClicked(app, event)


                    if activeCameraTemporary == 1 % 'activeCameraTemporary' can only be '1' or '2'
                        changeActiveCamera(app,1);
                        updateConditionComponents(app, 1)
                    end

                    if linkCameraTemporary == 1
                        app.LinkCameras.Value = linkCameraTemporary;
                        LinkCamerasValueChanged(app, event)
                    end

                    app.activeCamera = activeCameraTemporary;
                end


                % (5) Truncate 'cmosData1Raw' and 'cmosData2Raw'
                if app.camTF == 0 % Single camera.
                    app.cmosDataRawMaskFreeGroup{1} = app.cmosDataRawMaskFreeGroup{1}(:,:, app.sectionStartIndex : app.sectionEndIndex);

                else % Dual cameras
                    app.cmosDataRawMaskFreeGroup{1} = app.cmosDataRawMaskFreeGroup{1}(:,:, app.sectionStartIndex : app.sectionEndIndex);
                    app.cmosDataRawMaskFreeGroup{2} = app.cmosDataRawMaskFreeGroup{2}(:,:, app.sectionStartIndex : app.sectionEndIndex);
                end


                % (6) Reset index
                app.sectionStartIndex = 1;
                app.sectionEndIndex = length(app.signalTime);

                app.MoviesecSpinner.Value = 0;
                app.MoviesecSpinner.Limits = [ 0, app.signalTime(end) ];

                app.MovieSlider.Limits = [ 0, length(app.signalTime)-1 ];
                app.MovieSlider.Value = 0;

                app.currentFrameID = 0;
                updateVideoFrame(app);
                updateSweepBar(app);

                close(progressBar)

                app.signalSection_Yes_No = 1;
            end

        end

        % Image clicked function: Image_RestoreView
        function Image_RestoreViewClicked(app, event)

            showImageIconAct(app, app.Image_RestoreView)

            app.ViewStartsecSpinner.Value = 0;
            app.ViewEndsecSpinner.Value = app.signalTime(end);

            for ID = 1:6
                xlim( app.scrnObject{ID}, [ min(app.ViewStartsecSpinner.Limits), max(app.ViewEndsecSpinner.Limits) ] )
            end

        end

        % Value changed function: Camera1MaskCheckBox
        function Camera1MaskCheckBoxValueChanged(app, event)

            if app.Camera1MaskCheckBox.Value == 1

                hold(app.imageAxis1,'on')
                app.maskPlotObject{1} = imagesc( app.imageAxis1, app.maskGroup{1}, 'AlphaData', 0.3 * ~isnan(app.maskGroup{1}) );
                hold(app.imageAxis1,'off')

                if app.LinkCameras.Value == 1

                    if isempty( app.maskGroup{2} )

                        msg = 'You have mask for camera 1 but no mask for camera 2. Since you link cameras, mask 2 will be equal to mask 1.';
                        uialert(app.OMZer_UIFigure, msg,'Warning','Icon','error')

                        app.maskGroup{2} = app.maskGroup{1};
                        app.Camera2MaskCheckBox.Enable = 'on';
                    end
                    app.Camera2MaskCheckBox.Value = 1;

                    hold(app.imageAxis2,'on')
                    app.maskPlotObject{2} = imagesc( app.imageAxis2, app.maskGroup{2}, 'AlphaData', 0.3 * ~isnan(app.maskGroup{2}) );
                    hold(app.imageAxis2,'off')
                end


            else
                delete( app.maskPlotObject{1} );

                if app.LinkCameras.Value == 1
                    delete( app.maskPlotObject{2} );
                    app.Camera2MaskCheckBox.Value = 0;
                end
            end

        end

        % Value changed function: Camera2MaskCheckBox
        function Camera2MaskCheckBoxValueChanged(app, event)

            if app.Camera2MaskCheckBox.Value == 1 % '1 - checked'

                hold(app.imageAxis2,'on')
                app.maskPlotObject{2} = imagesc( app.imageAxis2, app.maskGroup{2}, 'AlphaData', 0.3 * ~isnan(app.maskGroup{2}) );
                hold(app.imageAxis2,'off')

                if app.LinkCameras.Value == 1

                    if isempty( app.maskGroup{1} )

                        msg = 'You have mask for camera 2 but no mask for camera 1. Since you link cameras, mask 1 will be equal to mask 2.';
                        uialert(app.OMZer_UIFigure,msg,'Warning','Icon','error')

                        app.maskGroup{1} = app.maskGroup{2};
                        app.Camera1MaskCheckBox.Enable = 'on';
                    end
                    app.Camera1MaskCheckBox.Value = 1;

                    hold(app.imageAxis1,'on')
                    app.maskPlotObject{1} = imagesc( app.imageAxis1, app.maskGroup{1}, 'AlphaData', 0.3 * ~isnan(app.maskGroup{1}) );
                    hold(app.imageAxis1,'off')
                end


            else
                delete( app.maskPlotObject{2} );

                if app.LinkCameras.Value == 1
                    delete( app.maskPlotObject{1} );
                    app.Camera1MaskCheckBox.Value = 0;
                end
            end

        end

        % Image clicked function: Image_EraseMask
        function Image_EraseMaskClicked(app, event)

            showImageIconAct(app, app.Image_EraseMask)

            if ( app.Camera1MaskCheckBox.Value == 0 )  &&  ( app.Camera2MaskCheckBox.Value == 0 )

                msg = 'Choose the mask you want to erase';
                uialert(app.OMZer_UIFigure,msg,'Warning','Icon','error')

                return
            end


            if app.Camera1MaskCheckBox.Value == 1

                % Delete mask of camera 1
                app.maskGroup{1} = [];
                app.cmosDataGroup{1} = app.cmosDataMaskFreeGroup{1};
                delete( app.maskPlotObject{1} );

                % Update video frame in camera 1
                app.imFrameAlpha{1,1} = ones(app.cmosDataRowNum, app.cmosDataColNum);
                if app.currentFrameID ~= 0
                    app.imFrame{1,1}.CData = app.cmosDataGroup{1}(:,:,app.currentFrameID);
                    set(app.imFrame{1,1},'AlphaData', app.imFrameAlpha{1,1});
                end

                app.Camera1MaskCheckBox.Value = 0;
                app.Camera1MaskCheckBox.Enable = 'off';
            end


            if app.Camera2MaskCheckBox.Value == 1

                % Delete mask of camera 2
                app.maskGroup{2} = [];
                app.cmosDataGroup{2} = app.cmosDataMaskFreeGroup{2};
                delete( app.maskPlotObject{2} );

                % Update video frame in camera 2
                app.imFrameAlpha{2,1} = ones(app.cmosDataRowNum, app.cmosDataColNum);
                if app.currentFrameID ~= 0
                    app.imFrame{2,1}.CData = app.cmosDataGroup{2}(:,:,app.currentFrameID);
                    set(app.imFrame{2,1},'AlphaData', app.imFrameAlpha{2,1});
                end

                app.Camera2MaskCheckBox.Value = 0;
                app.Camera2MaskCheckBox.Enable = 'off';
            end


            if isequal( app.Camera1MaskCheckBox.Enable, 'off' )  &&  isequal( app.Camera2MaskCheckBox.Enable, 'off' )

                app.Image_EraseMask.Enable = 'off';
                app.Image_ExportMask.Enable = 'off';
            end

            updateAllSignals(app)

            app.signalMask_Yes_No = 1;

        end

        % Image clicked function: Image_ExportMask
        function Image_ExportMaskClicked(app, event)

            showImageIconAct(app, app.Image_ExportMask)

            if ( app.Camera1MaskCheckBox.Value == 0 )  &&  ( app.Camera2MaskCheckBox.Value == 0 )

                msg = 'Choose the mask you want to export';
                uialert(app.OMZer_UIFigure,msg,'Warning','Icon','error')

                return
            end


            % Save mask 1
            if app.Camera1MaskCheckBox.Value == 1

                saveDir = uigetdir;

                if saveDir == 0 % User has NOT selected file directory.

                    msg = 'Please select a valid saving directory to save mask 1';
                    uialert(app.OMZer_UIFigure,msg,'Warning','Icon','error')

                else
                    prompt = {'Enter filename to save (without extension)...   Please do not use \/:*?"<>|'};
                    dlgtitle = 'Save to a mat file';
                    dfinput = {[app.fileName, '_Mask_1']};
                    dims = [1,100];
                    filename = inputdlg(prompt,dlgtitle,dims,dfinput);

                    if isempty(filename) % User has NOT entered a name

                        msg = 'Please enter a valid file name.';
                        uialert(app.OMZer_UIFigure,msg,'Warning','Icon','error')

                    else
                        fullSaveName = strcat(saveDir, '/', filename, '.mat'); % At this moment, 'fullSaveName' is a 'cell'.
                        fullSaveName = fullSaveName{1}; % Now 'fullSaveName' is a 'character

                        msg = 'Export the mask';
                        progressBar = uiprogressdlg(app.OMZer_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');

                        backgroundImage = app.bgImageNomGroup{1}; % Gray colour RGB version of background image
                        mask = app.maskGroup{1};
                        save(fullSaveName, 'mask', 'backgroundImage')

                        close(progressBar);
                    end
                end
            end


            % Save mask 2
            if app.Camera2MaskCheckBox.Value == 1

                saveDir = uigetdir;

                if saveDir == 0 % User has NOT selected file directory.

                    msg = 'Please select a valid saving directory to save mask 2';
                    uialert(app.OMZer_UIFigure,msg,'Warning','Icon','error')

                else
                    prompt = {'Enter filename to save (without extension)...   Please do not use \/:*?"<>|'};
                    dlgtitle = 'Save to a mat file';
                    dfinput = {[app.fileName, '_Mask_2']};
                    dims = [1,100];
                    filename = inputdlg(prompt,dlgtitle,dims,dfinput);

                    if isempty(filename) % User has NOT entered a name

                        msg = 'Please enter a valid file name.';
                        uialert(app.OMZer_UIFigure,msg,'Warning','Icon','error')

                    else
                        fullSaveName = strcat(saveDir, '/', filename, '.mat'); % At this moment, 'fullSaveName' is a 'cell'.
                        fullSaveName = fullSaveName{1}; % Now 'fullSaveName' is a 'character

                        msg = 'Export the mask';
                        progressBar = uiprogressdlg(app.OMZer_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');

                        backgroundImage = app.bgImageNomGroup{2}; % Gray colour RGB version of background image
                        mask = app.maskGroup{2};
                        save(fullSaveName, 'mask', 'backgroundImage')

                        close(progressBar);
                    end
                end
            end

            figure( app.OMZer_UIFigure )

        end

        % Value changed function: InvertCheckBox
        function InvertCheckBoxValueChanged(app, event)

            app.InvertLamp.Color = app.colorGroup_Gray_Orange{ app.InvertCheckBox.Value + 1 }; % Value == 0 (or 1), Color == Gray (or Orange)

        end

        % Value changed function: BoxFilterCheckBox
        function BoxFilterCheckBoxValueChanged(app, event)

            app.BoxFilterLamp.Color = app.colorGroup_Gray_Orange{ app.BoxFilterCheckBox.Value + 1 }; % Value == 0 (or 1), Color == Gray (or Orange)

        end

        % Value changed function: BoxFilterDropDown
        function BoxFilterDropDownValueChanged(app, event)

            if app.BoxFilterCheckBox.Value == 1

                app.BoxFilterLamp.Color = [0.93, 0.69, 0.13]; % Orange
            end

        end

        % Value changed function: Denoise60HzCheckBox
        function Denoise60HzCheckBoxValueChanged(app, event)

            app.DenoiseLamp.Color = app.colorGroup_Gray_Orange{ app.Denoise60HzCheckBox.Value + 1 }; % Value == 0 (or 1), Color == Gray (or Orange)

        end

        % Value changed function: ButterworthFilterCheckBox
        function ButterworthFilterCheckBoxValueChanged(app, event)

            app.ButterworthLamp.Color = app.colorGroup_Gray_Orange{ app.ButterworthFilterCheckBox.Value + 1 }; % Value == 0 (or 1), Color == Gray (or Orange)

            app.SGCheckBox.Enable = app.enableGroup_On_Off{ app.ButterworthFilterCheckBox.Value + 1 }; % Value == 0 (or 1), Enable == on (or off)

        end

        % Value changed function: ButterworthDropDown
        function ButterworthDropDownValueChanged(app, event)

            if app.ButterworthFilterCheckBox.Value == 1

                app.ButterworthLamp.Color = [0.93, 0.69, 0.13]; % Orange
            end

        end

        % Value changed function: X_scrn1, X_scrn2, X_scrn3, 
        % X_scrn4, X_scrn5, X_scrn6, Y_scrn1, Y_scrn2, Y_scrn3, 
        % Y_scrn4, Y_scrn5, Y_scrn6
        function XY_scrnValueChanged(app, event)

            for ID = 1 : 6

                if ( event.Source == app.Xs{ID} )  ||  ( event.Source == app.Ys{ID} )

                    xValue = app.Xs{ID}.Value;
                    yValue = app.Ys{ID}.Value;

                    app.mouseDragID = ID;

                    app.markerXY(app.mouseDragID,1:2) = [ xValue, yValue ]; % CAUTION: X is the column in matlab matrix. Y is the row in matlab matrix.

                    updateMarker(app)
                    updateSignal(app)

                    app.mouseDragID = [];

                    break
                end
            end

        end

        % Value changed function: SGCheckBox
        function SGCheckBoxValueChanged(app, event)

            app.SGLamp.Color = app.colorGroup_Gray_Orange{ app.SGCheckBox.Value + 1 }; % Value == 0 (or 1), Color == Gray (or Orange)

            app.ButterworthFilterCheckBox.Enable = app.enableGroup_On_Off{ app.SGCheckBox.Value + 1 }; % Value == 0 (or 1), Enable == on (or off)
            app.ButterworthDropDown.Enable = app.enableGroup_On_Off{ app.SGCheckBox.Value + 1 }; % Value == 0 (or 1), Enable == on (or off)

        end

        % Value changed function: PolyfitDedriftCheckBox
        function PolyfitDedriftCheckBoxValueChanged(app, event)

            app.PolyDedriftLamp.Color = app.colorGroup_Gray_Orange{ app.PolyfitDedriftCheckBox.Value + 1 }; % Value == 0 (or 1), Color == Gray (or Orange)

            app.TopHatDedriftbetaCheckBox.Enable = app.enableGroup_On_Off{ app.PolyfitDedriftCheckBox.Value + 1 }; % Value == 0 (or 1), Enable == on (or off)

        end

        % Value changed function: PolyDriftDropDown
        function PolyDriftDropDownValueChanged(app, event)

            if app.PolyfitDedriftCheckBox.Value == 1

                app.PolyDedriftLamp.Color = [0.93, 0.69, 0.13]; % Orange
            end

        end

        % Value changed function: TopHatDedriftbetaCheckBox
        function TopHatDedriftbetaCheckBoxValueChanged(app, event)

            msg = 'Sorry. This function can not be used. Please use "Polyfit Dedrift" to remove drift';
            uialert(app.OMZer_UIFigure, msg,'Warning','Icon','error')
            app.TopHatDedriftbetaCheckBox.Value = 0;


            app.TopHatDedriftLamp.Color = app.colorGroup_Gray_Orange{ app.TopHatDedriftbetaCheckBox.Value + 1 }; % Value == 0 (or 1), Color == Gray (or Orange)

            app.PolyfitDedriftCheckBox.Enable = app.enableGroup_On_Off{ app.TopHatDedriftbetaCheckBox.Value + 1 }; % Value == 0 (or 1), Enable == on (or off)
            app.PolyDriftDropDown.Enable = app.enableGroup_On_Off{ app.TopHatDedriftbetaCheckBox.Value + 1 }; % Value == 0 (or 1), Enable == on (or off)

        end

        % Value changed function: NormaliseCheckBox
        function NormaliseCheckBoxValueChanged(app, event)

            app.NormaliseLamp.Color = app.colorGroup_Gray_Orange{ app.NormaliseCheckBox.Value + 1 }; % Value == 0 (or 1), Color == Gray (or Orange)

        end

        % Image clicked function: Image_Apply
        function Image_ApplyClicked(app, event)

            showImageIconAct(app, app.Image_Apply)

            %% Passband Ripple and Stopband Attenuation
            % http://zone.ni.com/reference/en-XX/help/371361R-01/lvanlsconcepts/lvac_passband_rip_stopban_atten/

            %% Practical Introduction to Digital Filter Design
            % https://www.mathworks.com/help/signal/examples/practical-introduction-to-digital-filter-design.html

            %% Practical Introduction to Digital Filtering
            % https://www.mathworks.com/help/signal/examples/practical-introduction-to-digital-filtering.html

            %% Remove the 60 Hz Hum from a Signal
            % https://www.mathworks.com/help/signal/ug/remove-the-60-hz-hum-from-a-signal.html

            %% Savitzky-Golay filtering
            % https://www.mathworks.com/help/signal/ref/sgolayfilt.html

            %% Data Smoothing
            % https://www.mathworks.com/help/matlab/data_analysis/data-smoothing-and-outlier-detection.html
            % https://www.mathworks.com/help/matlab/ref/smoothdata.html#bvhejau-method
            % https://www.mathworks.com/help/signal/examples/signal-smoothing.html
            % https://www.mathworks.com/help/curvefit/smoothing-data.html

            %% Vector Filed
            % https://www.khanacademy.org/math/multivariable-calculus/thinking-about-multivariable-function/visualizing-vector-valued-functions/v/3d-vector-field-example

            %% Gradient
            % https://www.khanacademy.org/math/multivariable-calculus/multivariable-derivatives/gradient-and-directional-derivatives/v/why-the-gradient-is-the-direction-of-steepest-ascent

            %% Digital Filter Design
            % https://www.mathworks.com/help/signal/filter-design.html
            % https://dspguru.com/dsp/reference/filter-terminology/
            % https://allsignalprocessing.com/selecting-a-filter-which-one/

            % ---------------

            % digitalFilter
            % https://www.mathworks.com/help/signal/ref/digitalfilter.html

            % isstable
            % https://www.mathworks.com/help/signal/ref/isstable.html

            % islinphase
            % https://www.mathworks.com/help/signal/ref/islinphase.html

            % FVTool
            % https://www.mathworks.com/help/signal/ref/fvtool.html

            % What is windowing
            % https://www.tek.com/blog/window-functions-spectrum-analyzers

            % ---------------

            % Gaussian Filter vs Box Filter
            % https://stackoverflow.com/questions/31131672/difference-between-mean-and-gaussian-filter-in-result

            % Gaussian filter
            % https://cs.nyu.edu/~fergus/teaching/vision/3_filtering.pdf
            % http://www.cse.psu.edu/~rtc12/CSE486/lecture04.pdf
            % https://www.crisluengo.net/archives/150
            % https://www.cs.cornell.edu/courses/cs6670/2011sp/lectures/lec02_filter.pdf

            % Gaussian kernal calculator
            % http://dev.theomader.com/gaussian-kernel-calculator/

            % ----------------

            % Types of Morphological Operations
            % https://www.mathworks.com/help/images/morphological-dilation-and-erosion.html

            % Customize an Application
            % https://www.mathworks.com/help/mps/ml_code/customizing-the-installer.html

            % -----------------
            % Edge Detection
            % https://www.olympus-lifescience.com/en/microscope-resource/primer/java/digitalimaging/processing/derivativefilters/

            % Filter Introduction and Edge Detection
            % https://www.swarthmore.edu/NatSci/mzucker1/e27_s2016/filter-slides.pdf


            % (1) Whenever "Apply" button is clicked, recover all the data back to raw data
            app.cmosDataGroup{ app.activeCamera } = app.cmosDataRawMaskFreeGroup{ app.activeCamera }(:,:, app.sectionStartIndex : app.sectionEndIndex);

            % (2) - Apply Signal Conditioning
            % (2.1) Invert
            app.conditionLampOnOff{ app.activeCamera, 1 } = app.InvertCheckBox.Value;
            app.InvertLamp.Color = app.colorGroup_Gray_Green{ app.InvertCheckBox.Value + 1 }; % Value == 0 (or 1), Color == Gray (or Green)
            if app.InvertCheckBox.Value == 1
                msg = 'Invert signal';
                progressBar = uiprogressdlg(app.OMZer_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');
                app.cmosDataGroup{ app.activeCamera } = invertSignal( app.cmosDataGroup{ app.activeCamera } );
                close(progressBar)
            end

            % (2.2) Box filter
            app.conditionLampOnOff{ app.activeCamera, 2 } = app.BoxFilterCheckBox.Value;
            app.BoxFilterLamp.Color = app.colorGroup_Gray_Green{ app.BoxFilterCheckBox.Value + 1 }; % Value == 0 (or 1), Color == Gray (or Green)
            app.BoxFilterDropDownValue{ app.activeCamera, 1 } = app.BoxFilterDropDown.Value;
            if app.BoxFilterCheckBox.Value == 1
                msg = 'Bin signal';
                progressBar = uiprogressdlg(app.OMZer_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');
                app.cmosDataGroup{ app.activeCamera } = binnigSignal( app.cmosDataGroup{ app.activeCamera }, str2double( app.BoxFilterDropDown.Value ) );
                close(progressBar)
            end

            % (2.3) Denoise 60 Hz
            app.conditionLampOnOff{ app.activeCamera, 3 } = app.Denoise60HzCheckBox.Value;
            app.DenoiseLamp.Color = app.colorGroup_Gray_Green{ app.Denoise60HzCheckBox.Value + 1 }; % Value == 0 (or 1), Color == Gray (or Green)
            if app.Denoise60HzCheckBox.Value == 1
                msg = 'Remove the 60Hz noise';
                progressBar = uiprogressdlg(app.OMZer_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');
                app.cmosDataGroup{ app.activeCamera } = denoise60Hz( app.cmosDataGroup{ app.activeCamera }, app.acqFreq );
                close(progressBar)
            end

            % (2.4) Butterworth
            app.conditionLampOnOff{ app.activeCamera, 4 } = app.ButterworthFilterCheckBox.Value;
            app.ButterworthLamp.Color = app.colorGroup_Gray_Green{ app.ButterworthFilterCheckBox.Value + 1 }; % Value == 0 (or 1), Color == Gray (or Green)
            app.ButterworthDropDownValue{ app.activeCamera, 1 } = app.ButterworthDropDown.Value;
            if app.ButterworthFilterCheckBox.Value == 1
                msg = 'Low-pass filter signal';
                progressBar = uiprogressdlg(app.OMZer_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');
                app.cmosDataGroup{ app.activeCamera } = lowPassFilterSignal( app.cmosDataGroup{ app.activeCamera }, str2double( app.ButterworthDropDown.Value ), app.acqFreq);
                close(progressBar)
            end

            % (2.5) Savitzky-Golay Filter
            app.conditionLampOnOff{ app.activeCamera, 5 } = app.SGCheckBox.Value;
            app.SGLamp.Color = app.colorGroup_Gray_Green{ app.SGCheckBox.Value + 1 }; % Value == 0 (or 1), Color == Gray (or Green)
            if app.SGCheckBox.Value == 1
                msg = 'Savitzky-Golay filter signal';
                progressBar = uiprogressdlg(app.OMZer_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');
                app.cmosDataGroup{ app.activeCamera } = SGFilterSignal( app.cmosDataGroup{ app.activeCamera } );
                close(progressBar)
            end

            % (2.6) Polynomial curve fitting dedrift
            app.conditionLampOnOff{ app.activeCamera, 6 } = app.PolyfitDedriftCheckBox.Value;
            app.PolyDedriftLamp.Color = app.colorGroup_Gray_Green{ app.PolyfitDedriftCheckBox.Value + 1 }; % Value == 0 (or 1), Color == Gray (or Green)
            app.PolyDriftDropDownValue{ app.activeCamera, 1 } = app.PolyDriftDropDown.Value;
            if app.PolyfitDedriftCheckBox.Value == 1
                msg = 'Polynomial curve fitting dedrift signal';
                progressBar = uiprogressdlg(app.OMZer_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');
                app.cmosDataGroup{ app.activeCamera } = polynomialDedriftSignal( app.cmosDataGroup{ app.activeCamera }, str2double( app.PolyDriftDropDown.Value ) );
                close(progressBar)
            end

            % (2.7) Tophat dedrift signal - This function is disabled
            app.conditionLampOnOff{ app.activeCamera, 7 } = app.TopHatDedriftbetaCheckBox.Value;
            app.TopHatDedriftLamp.Color = app.colorGroup_Gray_Green{ app.TopHatDedriftbetaCheckBox.Value + 1 }; % Value == 0 (or 1), Color == Gray (or Green)
            if app.TopHatDedriftbetaCheckBox.Value == 1
                %{
                msg = 'TopHat drift correction';
                progressBar = uiprogressdlg(app.OMZer_UIFigure, 'Title','Computing', 'Message',msg, 'Indeterminate','on');

                [ app.cmosDataGroup{ app.activeCamera }, ~ ] = D_TopHat_SNR( app.cmosDataGroup{ app.activeCamera }, app.topHatWindowEndIndex - app.topHatWindowStartIndex + 1); % De-drift signal

                close(progressBar)
                %}
            end

            % (2.8) Normalise signal
            app.conditionLampOnOff{ app.activeCamera, 8 } = app.NormaliseCheckBox.Value;
            app.NormaliseLamp.Color = app.colorGroup_Gray_Green{ app.NormaliseCheckBox.Value + 1 }; % Value == 0 (or 1), Color == Gray (or Green)
            if app.NormaliseCheckBox.Value == 1
                msg = 'Normalise signal';
                progressBar = uiprogressdlg(app.OMZer_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');
                app.cmosDataGroup{ app.activeCamera } = normaliseSignal( app.cmosDataGroup{ app.activeCamera } );
                close(progressBar)
            end

            % (3) Round data
            % After signal conditioning, there will be lots of digits after the decimal which can result in big file size
            % if the user save these data. Here we round the data to its 2nd digits after the decimal; this can
            % reduce the file size while keep the data still precise enough.
            app.cmosDataGroup{ app.activeCamera } = round( app.cmosDataGroup{ app.activeCamera }, 2 );

            % (4) Re-apply the mask if it exists
            app.cmosDataMaskFreeGroup{ app.activeCamera } = app.cmosDataGroup{ app.activeCamera };
            if ~isempty( app.maskGroup{ app.activeCamera } )
                app.cmosDataGroup{ app.activeCamera } = app.cmosDataMaskFreeGroup{ app.activeCamera } .* app.maskGroup{ app.activeCamera };
            end

            % (5) - Update signal display. Reset the movieslider back to start point.
            app.MoviesecSpinner.Value = 0;
            app.MovieSlider.Value = 0;
            app.currentFrameID = 0;
            MovieSliderValueChanged(app, event)

            updateAllSignals(app)

            app.signalCondition_Yes_No = 1;

        end

        % Image clicked function: Image_Reset
        function Image_ResetClicked(app, event)

            showImageIconAct(app, app.Image_Reset)

            % (1) Reset condition check box
            for ID = 1 : 8
                app.conditionBoxObject{ID}.Value = 0;
            end

            % (2) Reset condition drop down
            app.BoxFilterDropDown.Value = '3';
            app.ButterworthDropDown.Value = '100';
            app.PolyDriftDropDown.Value = '1';

            app.BoxFilterDropDownValue{ app.activeCamera, 1 } = '3';
            app.ButterworthDropDownValue{ app.activeCamera, 1 } = '100';
            app.PolyDriftDropDownValue{ app.activeCamera, 1 } = '1';

            % (3) Reset condition lamp
            for ID = 1 : 8
                app.conditionLampObject{ID}.Color = [0.90,0.90,0.90]; % Gray
            end

            % (4) Enable all components
            enableConditionComponents(app,'on')

            for ID = 1:8
                app.conditionLampOnOff{ app.activeCamera, ID } = 0;
            end

            app.cmosDataGroup{ app.activeCamera } = app.cmosDataRawMaskFreeGroup{ app.activeCamera }(:,:, app.sectionStartIndex : app.sectionEndIndex); % Unfiltered, mask-free

            if ~isempty( app.maskGroup{ app.activeCamera } )

                app.cmosDataGroup{ app.activeCamera } = app.cmosDataGroup{ app.activeCamera } .* app.maskGroup{ app.activeCamera }; % Unfiltered, masked
            end

            % (5) - Update signal display. Reset the movieslider back to start point.
            app.MoviesecSpinner.Value = 0;
            app.MovieSlider.Value = 0;
            app.currentFrameID = 0;
            MovieSliderValueChanged(app, event)

            updateAllSignals(app)

            app.signalCondition_Yes_No = 1;

        end

        % Window button down function: OMZer_UIFigure
        function OMZer_UIFigureWindowButtonDown(app, event)

            if ~isempty(app.data)

                app.imageAxis1Locs = app.imageAxis1.InnerPosition; % "app.imageAxis1Locs" = [left bottom width height].
                app.imageAxis2Locs = app.imageAxis2.InnerPosition;

                app.mouseXY = app.OMZer_UIFigure.CurrentPoint; % Get the xy coordinate of mouse cursor in terms of the uifigure
                app.mouseMotion = app.OMZer_UIFigure.SelectionType; % The result can be 'normal', 'extend', 'alt', 'open'


                %% Mouse inside imageAxis1
                if ( app.mouseXY(1,1) >= app.imageAxis1Locs(1,1) ) ...
                        &&  ( app.mouseXY(1,1) <= app.imageAxis1Locs(1,1) + app.imageAxis1Locs(1,3) ) ...
                        &&  ( app.mouseXY(1,2) >= app.imageAxis1Locs(1,2) ) ...
                        &&  ( app.mouseXY(1,2) <= app.imageAxis1Locs(1,2) + app.imageAxis1Locs(1,4) )

                    changeActiveCamera(app,1)
                    updateConditionComponents(app, 1)
                    app.activeCamera = 1;
                    updateMouseXY(app)


                    if isequal(app.mouseMotion,'open') % Double-click any mouse button

                        app.markerXY(app.clickCount, 1:2) = [ app.mouseXY(1,1), app.mouseXY(1,2) ]; % CAUTION: X is the column in matlab matrix. Y is the row in matlab matrix.
                        app.Xs{app.clickCount}.Value = app.markerXY(app.clickCount,1);
                        app.Ys{app.clickCount}.Value = app.markerXY(app.clickCount,2);
                        app.Xs{app.clickCount}.Editable = 'on';
                        app.Ys{app.clickCount}.Editable = 'on';
                        app.Xs{app.clickCount}.BackgroundColor = [1.00, 1.00, 0.00]; % Yellow
                        app.Ys{app.clickCount}.BackgroundColor = [1.00, 1.00, 0.00]; % Yellow

                        updateMarker(app)
                        updateSignal(app)

                        %  Display sweep bar in a new scrn
                        if app.currentFrameID ~= 0
                            app.timeIndicators{app.clickCount,1}.Value = app.currentFrameID/app.acqFreq;
                        end

                        % Plot pacing1
                        if ( app.pacing1CheckBox.Value == 1 )  &&  isempty( app.pacingPlotObject{1,app.clickCount} ) % The new screen has signal but not pacing1

                            hold(app.scrnObject{app.clickCount},'on')
                            app.pacingPlotObject{1,app.clickCount} = stem( app.scrnObject{app.clickCount}, app.pacingTime, app.pacing1 );
                            app.pacingPlotObject{1,app.clickCount}.LineWidth = app.lWidth;
                            app.pacingPlotObject{1,app.clickCount}.Color = 'black';
                            app.pacingPlotObject{1,app.clickCount}.Marker = 'none';
                            hold(app.scrnObject{app.clickCount},'off')
                        end

                        % Plot pacing2
                        if ( app.pacing2CheckBox.Value == 1 )  &&  isempty( app.pacingPlotObject{2,app.clickCount} ) % The new screen has signal but not pacing2

                            hold(app.scrnObject{app.clickCount},'on')
                            app.pacingPlotObject{2,app.clickCount} = stem( app.scrnObject{app.clickCount}, app.pacingTime, app.pacing2 );
                            app.pacingPlotObject{2,app.clickCount}.LineWidth = app.lWidth;
                            app.pacingPlotObject{2,app.clickCount}.Color = 'black';
                            app.pacingPlotObject{2,app.clickCount}.Marker = 'none';
                            hold(app.scrnObject{app.clickCount},'off')
                        end

                        app.clickCount = app.clickCount + 1;
                        if app.clickCount == 7
                            app.clickCount = 1;
                        end


                    elseif isequal(app.mouseMotion,'normal') % Click the left mouse button

                        for ID = 1 : size(app.markerXY,1)
                            if abs( app.mouseXY(1,1) - app.markerXY(ID,1) ) <= 5  &&  abs( app.mouseXY(1,2) - app.markerXY(ID,2) ) <= 5 % Find which marker is within 5 pixels of the mouse location
                                app.mouseDragID = ID; % Find which marker the user is pointing to
                                break
                            end
                        end
                    end

                    return
                end


                if app.camTF == 1 % Dual camera

                    %% Mouse inside imageAxis2
                    if ( app.mouseXY(1,1) >= app.imageAxis2Locs(1,1) ) ...
                            &&  ( app.mouseXY(1,1) <= app.imageAxis2Locs(1,1) + app.imageAxis2Locs(1,3) ) ...
                            &&  ( app.mouseXY(1,2) >= app.imageAxis2Locs(1,2) ) ...
                            &&  ( app.mouseXY(1,2) <= app.imageAxis2Locs(1,2) + app.imageAxis2Locs(1,4) )

                        changeActiveCamera(app,2)
                        updateConditionComponents(app, 2)
                        app.activeCamera = 2;
                        updateMouseXY(app)


                        if isequal(app.mouseMotion,'open') % Double-click any mouse button

                            app.markerXY(app.clickCount,1:2) = [ app.mouseXY(1,1), app.mouseXY(1,2) ]; % CAUTION: X is the column in matlab matrix. Y is the row in matlab matrix.
                            app.Xs{app.clickCount}.Value = app.markerXY(app.clickCount,1);
                            app.Ys{app.clickCount}.Value = app.markerXY(app.clickCount,2);
                            app.Xs{app.clickCount}.Editable = 'on';
                            app.Ys{app.clickCount}.Editable = 'on';
                            app.Xs{app.clickCount}.BackgroundColor = [1.00, 1.00, 0.00]; % Yellow
                            app.Ys{app.clickCount}.BackgroundColor = [1.00, 1.00, 0.00]; % Yellow

                            updateMarker(app)
                            updateSignal(app)


                            %  Display sweep bar in a new scrn
                            if app.currentFrameID ~= 0
                                app.timeIndicators{app.clickCount,1}.Value = app.currentFrameID/app.acqFreq;
                            end

                            % Plot pacing1
                            if ( app.pacing1CheckBox.Value == 1 )  &&  isempty( app.pacingPlotObject{1,app.clickCount} ) % The new screen has signal but not pacing1

                                hold(app.scrnObject{app.clickCount},'on')
                                app.pacingPlotObject{1,app.clickCount} = stem( app.scrnObject{app.clickCount}, app.pacingTime, app.pacing1 );
                                app.pacingPlotObject{1,app.clickCount}.LineWidth = app.lWidth;
                                app.pacingPlotObject{1,app.clickCount}.Color = 'black';
                                app.pacingPlotObject{1,app.clickCount}.Marker = 'none';
                                hold(app.scrnObject{app.clickCount},'off')
                            end

                            % Plot pacing2
                            if ( app.pacing2CheckBox.Value == 1 )  &&  isempty( app.pacingPlotObject{2,app.clickCount} ) == 1 % The new screen has signal but does not have pacing2

                                hold(app.scrnObject{app.clickCount},'on')
                                app.pacingPlotObject{2,app.clickCount} = stem( app.scrnObject{app.clickCount}, app.pacingTime, app.pacing2 );
                                app.pacingPlotObject{2,app.clickCount}.LineWidth = app.lWidth;
                                app.pacingPlotObject{2,app.clickCount}.Color = 'black';
                                app.pacingPlotObject{2,app.clickCount}.Marker = 'none';
                                hold(app.scrnObject{app.clickCount},'off')
                            end

                            app.clickCount = app.clickCount + 1;
                            if app.clickCount == 7 % If all the six scrns have been used, start plotting again from the 1st scrn to the last scrn
                                app.clickCount = 1;
                            end


                        elseif isequal(app.mouseMotion,'normal') % Click the left mouse button

                            for ID = 1 : size(app.markerXY,1)
                                if abs( app.mouseXY(1,1) - app.markerXY(ID,1) ) <= 5  &&  abs( app.mouseXY(1,2) - app.markerXY(ID,2) ) <= 5 % Find which marker is within 5 pixels of the mouse location
                                    app.mouseDragID = ID; % Find which marker the user is pointing to
                                    break
                                end
                            end
                        end

                        return
                    end
                end
            end

        end

        % Window button motion function: OMZer_UIFigure
        function OMZer_UIFigureWindowButtonMotion(app, event)

            if ~isempty(app.mouseDragID)

                app.imageAxis1Locs = app.imageAxis1.InnerPosition; % "app.imageAxis1Locs" = [left bottom width height].
                app.imageAxis2Locs = app.imageAxis2.InnerPosition;

                app.mouseXY = app.OMZer_UIFigure.CurrentPoint; % Get the xy coordinate of mouse cursor in terms of the uifigure
                app.mouseMotion = app.OMZer_UIFigure.SelectionType; % The result can be 'normal', 'extend', 'alt', 'open'


                % Mouse inside imageAxis1
                if ( app.mouseXY(1,1) >= app.imageAxis1Locs(1,1) ) ...
                        &&  ( app.mouseXY(1,1) <= app.imageAxis1Locs(1,1) + app.imageAxis1Locs(1,3) ) ...
                        &&  ( app.mouseXY(1,2) >= app.imageAxis1Locs(1,2) ) ...
                        &&  ( app.mouseXY(1,2) <= app.imageAxis1Locs(1,2) + app.imageAxis1Locs(1,4) )

                    updateMouseXY(app)

                    app.markerXY(app.mouseDragID,1:2) = [ app.mouseXY(1,1), app.mouseXY(1,2) ]; % CAUTION: X is the column in matlab matrix. Y is the row in matlab matrix.
                    app.Xs{app.mouseDragID}.Value = app.markerXY(app.mouseDragID,1);
                    app.Ys{app.mouseDragID}.Value = app.markerXY(app.mouseDragID,2);

                    updateMarker(app)
                    updateSignal(app)


                    % Mouse inside imageAxis2
                elseif ( app.mouseXY(1,1) >= app.imageAxis2Locs(1,1) ) ...
                        &&  ( app.mouseXY(1,1) <= app.imageAxis2Locs(1,1) + app.imageAxis2Locs(1,3) ) ...
                        &&  ( app.mouseXY(1,2) >= app.imageAxis2Locs(1,2) ) ...
                        &&  ( app.mouseXY(1,2) <= app.imageAxis2Locs(1,2) + app.imageAxis2Locs(1,4) )

                    updateMouseXY(app)

                    app.markerXY(app.mouseDragID,1:2) = [ app.mouseXY(1,1), app.mouseXY(1,2) ]; % CAUTION: X is the column in matlab matrix. Y is the row in matlab matrix.
                    app.Xs{app.mouseDragID}.Value = app.markerXY(app.mouseDragID,1);
                    app.Ys{app.mouseDragID}.Value = app.markerXY(app.mouseDragID,2);

                    updateMarker(app)
                    updateSignal(app)

                else
                    app.mouseDragID = [];
                end
            end

        end

        % Window button up function: OMZer_UIFigure
        function OMZer_UIFigureWindowButtonUp(app, event)

            app.mouseDragID = [];

        end

        % Window key press function: OMZer_UIFigure
        function OMZer_UIFigureWindowKeyPress(app, event)

            app.keyPressHistory{app.keyPressID} = event.Key;
            app.keyPressID = app.keyPressID + 1;

            % (1) Save file callback
            if length( app.keyPressHistory ) == 2

                if ( ismac == 1 )  &&  isequal( app.keyPressHistory{1}, 'meta' )  &&  isequal( app.keyPressHistory{2}, 's' )  &&  ( isequal( app.Image_Save.Enable, 'on' ) )

                    Image_SaveClicked(app, 'clicked')

                elseif ( ispc == 1 )  &&  isequal( app.keyPressHistory{1}, 'control' )  &&  isequal( app.keyPressHistory{2}, 's' )  &&  ( isequal( app.Image_Save.Enable, 'on' ) )

                    Image_SaveClicked(app, 'clicked')
                end
            end

            % (2) Open folder callback
            if length( app.keyPressHistory ) == 2

                if ( ismac == 1 )  &&  isequal( app.keyPressHistory{1}, 'meta' )  &&  isequal( app.keyPressHistory{2}, 'o' )

                    Image_OpenFolderClicked(app, 'clicked')

                elseif ( ispc == 1 )  &&  isequal( app.keyPressHistory{1}, 'control' )  &&  isequal( app.keyPressHistory{2}, 'o' )

                    Image_OpenFolderClicked(app, 'clicked')
                end
            end

            % (3) File information callback
            if length( app.keyPressHistory ) == 2

                if ( ismac == 1 )  &&  isequal( app.keyPressHistory{1}, 'meta' )  &&  isequal( app.keyPressHistory{2}, 'i' )

                    Image_FileInformationClicked(app, 'clicked')

                elseif ( ispc == 1 )  &&  isequal( app.keyPressHistory{1}, 'control' )  &&  isequal( app.keyPressHistory{2}, 'i' )

                    Image_FileInformationClicked(app, 'clicked')
                end
            end

        end

        % Window key release function: OMZer_UIFigure
        function OMZer_UIFigureWindowKeyRelease(app, event)

            % key = event.Key

            app.keyPressID = 1;
            app.keyPressHistory = {};

        end

        % Close request function: OMZer_UIFigure
        function OMZer_UIFigureCloseRequest(app, event)

            closeAllChildrenApp(app, {})

            delete(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OMZer_UIFigure = uifigure('Visible', 'off');
            app.OMZer_UIFigure.Name = 'OMZer';
            app.OMZer_UIFigure.CloseRequestFcn = createCallbackFcn(app, @OMZer_UIFigureCloseRequest, true);
            app.OMZer_UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @OMZer_UIFigureWindowButtonDown, true);
            app.OMZer_UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @OMZer_UIFigureWindowButtonUp, true);
            app.OMZer_UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @OMZer_UIFigureWindowButtonMotion, true);
            app.OMZer_UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @OMZer_UIFigureWindowKeyPress, true);
            app.OMZer_UIFigure.WindowKeyReleaseFcn = createCallbackFcn(app, @OMZer_UIFigureWindowKeyRelease, true);
            app.imageAxis2 = uiaxes(app.OMZer_UIFigure);
            app.imageAxis2.XLim = [1 100];
            app.imageAxis2.YLim = [1 100];
            app.imageAxis2.YDir = 'reverse';
            app.imageAxis2.XTick = [];
            app.imageAxis2.YTick = [];
            app.imageAxis2.ZTick = [];
            app.imageAxis1 = uiaxes(app.OMZer_UIFigure);
            app.imageAxis1.XLim = [1 100];
            app.imageAxis1.YLim = [1 100];
            app.imageAxis1.YDir = 'reverse';
            app.imageAxis1.XTick = [];
            app.imageAxis1.YTick = [];
            app.imageAxis1.ZTick = [];
            app.scrn1 = uiaxes(app.OMZer_UIFigure);
            app.scrn2 = uiaxes(app.OMZer_UIFigure);
            app.scrn3 = uiaxes(app.OMZer_UIFigure);
            app.scrn4 = uiaxes(app.OMZer_UIFigure);
            app.scrn5 = uiaxes(app.OMZer_UIFigure);
            app.scrn6 = uiaxes(app.OMZer_UIFigure);
            app.TabGroup = uitabgroup(app.OMZer_UIFigure);
            app.FileTab = uitab(app.TabGroup);
            app.FileTab.Title = 'File';
            app.Image_OpenFolder = uiimage(app.FileTab);
            app.Image_OpenFolder.ImageClickedFcn = createCallbackFcn(app, @Image_OpenFolderClicked, true);
            app.Image_OpenFolder.ImageSource = 'Icon Open.png';
            app.OpenFolderLabel = uilabel(app.FileTab);
            app.OpenFolderLabel.Text = 'Open Folder';
            app.Image_Save = uiimage(app.FileTab);
            app.Image_Save.ImageClickedFcn = createCallbackFcn(app, @Image_SaveClicked, true);
            app.Image_Save.Enable = 'off';
            app.Image_Save.ImageSource = 'Icon Save.png';
            app.SaveLabel = uilabel(app.FileTab);
            app.SaveLabel.Text = 'Save';
            app.FileNameEditFieldLabel = uilabel(app.FileTab);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.FileTab, 'text');
            app.FileNameEditField.Enable = 'off';
            app.Image_Rename = uiimage(app.FileTab);
            app.Image_Rename.ImageClickedFcn = createCallbackFcn(app, @Image_RenameClicked, true);
            app.Image_Rename.Enable = 'off';
            app.Image_Rename.ImageSource = 'Icon Rename.png';
            app.RenameLabel = uilabel(app.FileTab);
            app.RenameLabel.Text = 'Rename';
            app.Image_FileInformation = uiimage(app.FileTab);
            app.Image_FileInformation.ImageClickedFcn = createCallbackFcn(app, @Image_FileInformationClicked, true);
            app.Image_FileInformation.ImageSource = 'Icon File Infomation.png';
            app.FileInformationLabel = uilabel(app.FileTab);
            app.FileInformationLabel.Text = 'File Information';
            app.DirectoryEditFieldLabel = uilabel(app.FileTab);
            app.DirectoryEditFieldLabel.Text = 'Directory';
            app.DirectoryEditField = uieditfield(app.FileTab, 'text');
            app.DirectoryEditField.Editable = 'off';
            app.fileExtensionLabel = uilabel(app.FileTab);
            app.fileExtensionLabel.Tooltip = {'The file extension. This can be ''.rsh'''; ' ''.mat''.'};
            app.fileExtensionLabel.Text = '.Ext';
            app.ToolsTab = uitab(app.TabGroup);
            app.ToolsTab.Title = 'Tools';
            app.Image_MaskSetup = uiimage(app.ToolsTab);
            app.Image_MaskSetup.ImageClickedFcn = createCallbackFcn(app, @Image_MaskSetupClicked, true);
            app.Image_MaskSetup.Enable = 'off';
            app.Image_MaskSetup.ImageSource = 'Icon Mask.png';
            app.MaskSetupLabel = uilabel(app.ToolsTab);
            app.MaskSetupLabel.Text = 'Mask Setup';
            app.Image_ExportData = uiimage(app.ToolsTab);
            app.Image_ExportData.ImageClickedFcn = createCallbackFcn(app, @Image_ExportDataClicked, true);
            app.Image_ExportData.Enable = 'off';
            app.Image_ExportData.ImageSource = 'Icon Export.png';
            app.ExportDataLabel = uilabel(app.ToolsTab);
            app.ExportDataLabel.Text = 'Export Data';
            app.Image_ExportFigure = uiimage(app.ToolsTab);
            app.Image_ExportFigure.ImageClickedFcn = createCallbackFcn(app, @Image_ExportFigureClicked, true);
            app.Image_ExportFigure.Enable = 'off';
            app.Image_ExportFigure.ImageSource = 'Icon Export Figure.png';
            app.ExportFigureLabel = uilabel(app.ToolsTab);
            app.ExportFigureLabel.Text = 'Export Figure';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.Image_VmAnalysis = uiimage(app.AnalysisTab);
            app.Image_VmAnalysis.ImageClickedFcn = createCallbackFcn(app, @Image_VmAnalysisClicked, true);
            app.Image_VmAnalysis.Enable = 'off';
            app.Image_VmAnalysis.ImageSource = 'Icon V.png';
            app.VmAnalysisLabel = uilabel(app.AnalysisTab);
            app.VmAnalysisLabel.Text = 'Vm Analysis';
            app.Image_CaAnalysis = uiimage(app.AnalysisTab);
            app.Image_CaAnalysis.ImageClickedFcn = createCallbackFcn(app, @Image_CaAnalysisClicked, true);
            app.Image_CaAnalysis.Enable = 'off';
            app.Image_CaAnalysis.ImageSource = 'Icon C.png';
            app.CaAnalysisLabel = uilabel(app.AnalysisTab);
            app.CaAnalysisLabel.Text = 'Ca Analysis';
            app.Image_VmCaLinkedAnalysis = uiimage(app.AnalysisTab);
            app.Image_VmCaLinkedAnalysis.ImageClickedFcn = createCallbackFcn(app, @Image_VmCaLinkedAnalysisClicked, true);
            app.Image_VmCaLinkedAnalysis.Enable = 'off';
            app.Image_VmCaLinkedAnalysis.ImageSource = 'Icons Wedding Ring.png';
            app.VmCaLinkedAnalysisLabel = uilabel(app.AnalysisTab);
            app.VmCaLinkedAnalysisLabel.Text = 'Vm-Ca Linked Analysis';
            app.Image_VmPhaseAnalysis = uiimage(app.AnalysisTab);
            app.Image_VmPhaseAnalysis.ImageClickedFcn = createCallbackFcn(app, @Image_VmPhaseAnalysisClicked, true);
            app.Image_VmPhaseAnalysis.Enable = 'off';
            app.Image_VmPhaseAnalysis.ImageSource = 'Icons Sine Wave.png';
            app.VmPhaseAnalysisLabel = uilabel(app.AnalysisTab);
            app.VmPhaseAnalysisLabel.Text = 'Vm Phase Analysis';
            app.Image_VmDominantFrequencyAnalysis = uiimage(app.AnalysisTab);
            app.Image_VmDominantFrequencyAnalysis.ImageClickedFcn = createCallbackFcn(app, @Image_VmDominantFrequencyAnalysisClicked, true);
            app.Image_VmDominantFrequencyAnalysis.Enable = 'off';
            app.Image_VmDominantFrequencyAnalysis.ImageSource = 'Icons Sonometer.png';
            app.VmDominantFrequencyAnalysisLabel = uilabel(app.AnalysisTab);
            app.VmDominantFrequencyAnalysisLabel.Text = 'Vm Dominant Frequency Analysis';
            app.PreferenceTab = uitab(app.TabGroup);
            app.PreferenceTab.Title = 'Preference';
            app.Image_Setup = uiimage(app.PreferenceTab);
            app.Image_Setup.ImageClickedFcn = createCallbackFcn(app, @Image_SetupClicked, true);
            app.Image_Setup.ImageSource = 'Icon Setup.png';
            app.SetupLabel = uilabel(app.PreferenceTab);
            app.SetupLabel.Text = 'Setup';
            app.pacing1CheckBox = uicheckbox(app.OMZer_UIFigure);
            app.pacing1CheckBox.ValueChangedFcn = createCallbackFcn(app, @pacing1CheckBoxValueChanged, true);
            app.pacing1CheckBox.Enable = 'off';
            app.pacing1CheckBox.Tooltip = {'Pacing signal stored in analog 1 channel'};
            app.pacing1CheckBox.Text = 'Pacing 1';
            app.MovieSlider = uislider(app.OMZer_UIFigure);
            app.MovieSlider.MajorTicks = [];
            app.MovieSlider.MajorTickLabels = {''};
            app.MovieSlider.ValueChangedFcn = createCallbackFcn(app, @MovieSliderValueChanged, true);
            app.MovieSlider.ValueChangingFcn = createCallbackFcn(app, @MovieSliderValueChanging, true);
            app.MovieSlider.MinorTicks = [];
            app.MovieSlider.Enable = 'off';
            app.XYLabel = uilabel(app.OMZer_UIFigure);
            app.XYLabel.Tooltip = {'X (red) and Y (blue) coordinate of the marker. Please be noted that X is column and Y is row to locate an element in a matrix in MATLAB.'};
            app.XYLabel.Text = 'X,Y';
            app.pacing2CheckBox = uicheckbox(app.OMZer_UIFigure);
            app.pacing2CheckBox.ValueChangedFcn = createCallbackFcn(app, @pacing2CheckBoxValueChanged, true);
            app.pacing2CheckBox.Enable = 'off';
            app.pacing2CheckBox.Tooltip = {'Pacing signal stored in analog 2 channel'};
            app.pacing2CheckBox.Text = 'Pacing 2';
            app.LinkCameras = uicheckbox(app.OMZer_UIFigure);
            app.LinkCameras.ValueChangedFcn = createCallbackFcn(app, @LinkCamerasValueChanged, true);
            app.LinkCameras.Enable = 'off';
            app.LinkCameras.Text = 'Link Cameras';
            app.ViewStartsecSpinnerLabel = uilabel(app.OMZer_UIFigure);
            app.ViewStartsecSpinnerLabel.Text = 'View Start (sec)';
            app.ViewStartsecSpinner = uispinner(app.OMZer_UIFigure);
            app.ViewStartsecSpinner.Step = 0.05;
            app.ViewStartsecSpinner.ValueChangingFcn = createCallbackFcn(app, @ViewStartsecSpinnerValueChanging, true);
            app.ViewStartsecSpinner.Limits = [0 Inf];
            app.ViewStartsecSpinner.ValueChangedFcn = createCallbackFcn(app, @ViewStartsecSpinnerValueChanged, true);
            app.ViewStartsecSpinner.Enable = 'off';
            app.ViewEndsecSpinnerLabel = uilabel(app.OMZer_UIFigure);
            app.ViewEndsecSpinnerLabel.Text = 'View End (sec)';
            app.ViewEndsecSpinner = uispinner(app.OMZer_UIFigure);
            app.ViewEndsecSpinner.Step = 0.05;
            app.ViewEndsecSpinner.ValueChangingFcn = createCallbackFcn(app, @ViewEndsecSpinnerValueChanging, true);
            app.ViewEndsecSpinner.Limits = [0 Inf];
            app.ViewEndsecSpinner.ValueChangedFcn = createCallbackFcn(app, @ViewEndsecSpinnerValueChanged, true);
            app.ViewEndsecSpinner.Enable = 'off';
            app.ViewEndsecSpinner.Value = Inf;
            app.Image_Section = uiimage(app.OMZer_UIFigure);
            app.Image_Section.ImageClickedFcn = createCallbackFcn(app, @Image_SectionClicked, true);
            app.Image_Section.Enable = 'off';
            app.Image_Section.ImageSource = 'Icon Section.png';
            app.SectionLabel = uilabel(app.OMZer_UIFigure);
            app.SectionLabel.Text = 'Section';
            app.Image_RestoreView = uiimage(app.OMZer_UIFigure);
            app.Image_RestoreView.ImageClickedFcn = createCallbackFcn(app, @Image_RestoreViewClicked, true);
            app.Image_RestoreView.Enable = 'off';
            app.Image_RestoreView.ImageSource = 'Icon Restore View.png';
            app.RestoreViewLabel = uilabel(app.OMZer_UIFigure);
            app.RestoreViewLabel.Text = 'Restore View';
            app.Camera1MaskCheckBox = uicheckbox(app.OMZer_UIFigure);
            app.Camera1MaskCheckBox.ValueChangedFcn = createCallbackFcn(app, @Camera1MaskCheckBoxValueChanged, true);
            app.Camera1MaskCheckBox.Enable = 'off';
            app.Camera1MaskCheckBox.Text = 'Camera 1 Mask';
            app.Camera2MaskCheckBox = uicheckbox(app.OMZer_UIFigure);
            app.Camera2MaskCheckBox.ValueChangedFcn = createCallbackFcn(app, @Camera2MaskCheckBoxValueChanged, true);
            app.Camera2MaskCheckBox.Enable = 'off';
            app.Camera2MaskCheckBox.Text = 'Camera 2 Mask';
            app.Image_EraseMask = uiimage(app.OMZer_UIFigure);
            app.Image_EraseMask.ImageClickedFcn = createCallbackFcn(app, @Image_EraseMaskClicked, true);
            app.Image_EraseMask.Enable = 'off';
            app.Image_EraseMask.ImageSource = 'Icon Erase Mask.png';
            app.EraseMaskLabel = uilabel(app.OMZer_UIFigure);
            app.EraseMaskLabel.Text = 'Erase Mask';
            app.Image_ExportMask = uiimage(app.OMZer_UIFigure);
            app.Image_ExportMask.ImageClickedFcn = createCallbackFcn(app, @Image_ExportMaskClicked, true);
            app.Image_ExportMask.Enable = 'off';
            app.Image_ExportMask.ImageSource = 'Icon Export.png';
            app.ExportMaskLabel = uilabel(app.OMZer_UIFigure);
            app.ExportMaskLabel.Text = 'Export Mask';
            app.Y_scrn1 = uieditfield(app.OMZer_UIFigure, 'numeric');
            app.Y_scrn1.Limits = [0 100];
            app.Y_scrn1.ValueDisplayFormat = '%.0f';
            app.Y_scrn1.ValueChangedFcn = createCallbackFcn(app, @XY_scrnValueChanged, true);
            app.Y_scrn1.Editable = 'off';
            app.Y_scrn1.Tooltip = {'Y coordinate of the 1st marker (Note: row in a matlab matrix)'};
            app.X_scrn1 = uieditfield(app.OMZer_UIFigure, 'numeric');
            app.X_scrn1.Limits = [0 100];
            app.X_scrn1.ValueDisplayFormat = '%.0f';
            app.X_scrn1.ValueChangedFcn = createCallbackFcn(app, @XY_scrnValueChanged, true);
            app.X_scrn1.Editable = 'off';
            app.X_scrn1.Tooltip = {'X coordinate of the 1st marker (Note: column in a matlab matrix)'};
            app.Y_scrn2 = uieditfield(app.OMZer_UIFigure, 'numeric');
            app.Y_scrn2.Limits = [0 100];
            app.Y_scrn2.ValueDisplayFormat = '%.0f';
            app.Y_scrn2.ValueChangedFcn = createCallbackFcn(app, @XY_scrnValueChanged, true);
            app.Y_scrn2.Editable = 'off';
            app.Y_scrn2.Tooltip = {'Y coordinate of the 1st marker (Note: row in a matlab matrix)'};
            app.X_scrn2 = uieditfield(app.OMZer_UIFigure, 'numeric');
            app.X_scrn2.Limits = [0 100];
            app.X_scrn2.ValueDisplayFormat = '%.0f';
            app.X_scrn2.ValueChangedFcn = createCallbackFcn(app, @XY_scrnValueChanged, true);
            app.X_scrn2.Editable = 'off';
            app.X_scrn2.Tooltip = {'X coordinate of the 1st marker (Note: column in a matlab matrix)'};
            app.Y_scrn3 = uieditfield(app.OMZer_UIFigure, 'numeric');
            app.Y_scrn3.Limits = [0 100];
            app.Y_scrn3.ValueDisplayFormat = '%.0f';
            app.Y_scrn3.ValueChangedFcn = createCallbackFcn(app, @XY_scrnValueChanged, true);
            app.Y_scrn3.Editable = 'off';
            app.Y_scrn3.Tooltip = {'Y coordinate of the 1st marker (Note: row in a matlab matrix)'};
            app.X_scrn3 = uieditfield(app.OMZer_UIFigure, 'numeric');
            app.X_scrn3.Limits = [0 100];
            app.X_scrn3.ValueDisplayFormat = '%.0f';
            app.X_scrn3.ValueChangedFcn = createCallbackFcn(app, @XY_scrnValueChanged, true);
            app.X_scrn3.Editable = 'off';
            app.X_scrn3.Tooltip = {'X coordinate of the 1st marker (Note: column in a matlab matrix)'};
            app.Y_scrn4 = uieditfield(app.OMZer_UIFigure, 'numeric');
            app.Y_scrn4.Limits = [0 100];
            app.Y_scrn4.ValueDisplayFormat = '%.0f';
            app.Y_scrn4.ValueChangedFcn = createCallbackFcn(app, @XY_scrnValueChanged, true);
            app.Y_scrn4.Editable = 'off';
            app.Y_scrn4.Tooltip = {'Y coordinate of the 1st marker (Note: row in a matlab matrix)'};
            app.X_scrn4 = uieditfield(app.OMZer_UIFigure, 'numeric');
            app.X_scrn4.Limits = [0 100];
            app.X_scrn4.ValueDisplayFormat = '%.0f';
            app.X_scrn4.ValueChangedFcn = createCallbackFcn(app, @XY_scrnValueChanged, true);
            app.X_scrn4.Editable = 'off';
            app.X_scrn4.Tooltip = {'X coordinate of the 1st marker (Note: column in a matlab matrix)'};
            app.Y_scrn5 = uieditfield(app.OMZer_UIFigure, 'numeric');
            app.Y_scrn5.Limits = [0 100];
            app.Y_scrn5.ValueDisplayFormat = '%.0f';
            app.Y_scrn5.ValueChangedFcn = createCallbackFcn(app, @XY_scrnValueChanged, true);
            app.Y_scrn5.Editable = 'off';
            app.Y_scrn5.Tooltip = {'Y coordinate of the 1st marker (Note: row in a matlab matrix)'};
            app.X_scrn5 = uieditfield(app.OMZer_UIFigure, 'numeric');
            app.X_scrn5.Limits = [0 100];
            app.X_scrn5.ValueDisplayFormat = '%.0f';
            app.X_scrn5.ValueChangedFcn = createCallbackFcn(app, @XY_scrnValueChanged, true);
            app.X_scrn5.Editable = 'off';
            app.X_scrn5.Tooltip = {'X coordinate of the 1st marker (Note: column in a matlab matrix)'};
            app.Y_scrn6 = uieditfield(app.OMZer_UIFigure, 'numeric');
            app.Y_scrn6.Limits = [0 100];
            app.Y_scrn6.ValueDisplayFormat = '%.0f';
            app.Y_scrn6.ValueChangedFcn = createCallbackFcn(app, @XY_scrnValueChanged, true);
            app.Y_scrn6.Editable = 'off';
            app.Y_scrn6.Tooltip = {'Y coordinate of the 1st marker (Note: row in a matlab matrix)'};
            app.X_scrn6 = uieditfield(app.OMZer_UIFigure, 'numeric');
            app.X_scrn6.Limits = [0 100];
            app.X_scrn6.ValueDisplayFormat = '%.0f';
            app.X_scrn6.ValueChangedFcn = createCallbackFcn(app, @XY_scrnValueChanged, true);
            app.X_scrn6.Editable = 'off';
            app.X_scrn6.Tooltip = {'X coordinate of the 1st marker (Note: column in a matlab matrix)'};
            app.InvertCheckBox = uicheckbox(app.OMZer_UIFigure);
            app.InvertCheckBox.ValueChangedFcn = createCallbackFcn(app, @InvertCheckBoxValueChanged, true);
            app.InvertCheckBox.Enable = 'off';
            app.InvertCheckBox.Text = 'Invert';
            app.InvertLamp = uilamp(app.OMZer_UIFigure);
            app.BoxFilterCheckBox = uicheckbox(app.OMZer_UIFigure);
            app.BoxFilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @BoxFilterCheckBoxValueChanged, true);
            app.BoxFilterCheckBox.Enable = 'off';
            app.BoxFilterCheckBox.Tooltip = {'This is a spatial filter that reduce random noise in an image via box blurring method.'};
            app.BoxFilterCheckBox.Text = 'Box Filter';
            app.BoxFilterDropDown = uidropdown(app.OMZer_UIFigure);
            app.BoxFilterDropDown.Items = {'3', '5', '7'};
            app.BoxFilterDropDown.ValueChangedFcn = createCallbackFcn(app, @BoxFilterDropDownValueChanged, true);
            app.BoxFilterDropDown.Enable = 'off';
            app.BoxFilterDropDown.Tooltip = {'Select the kernal size of the box filter. (3x3'; ' 5x5'; ' 7x7)'};
            app.BoxFilterDropDown.Value = '3';
            app.BoxFilterLamp = uilamp(app.OMZer_UIFigure);
            app.Denoise60HzCheckBox = uicheckbox(app.OMZer_UIFigure);
            app.Denoise60HzCheckBox.ValueChangedFcn = createCallbackFcn(app, @Denoise60HzCheckBoxValueChanged, true);
            app.Denoise60HzCheckBox.Enable = 'off';
            app.Denoise60HzCheckBox.Text = 'Denoise (60Hz)';
            app.ButterworthFilterCheckBox = uicheckbox(app.OMZer_UIFigure);
            app.ButterworthFilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @ButterworthFilterCheckBoxValueChanged, true);
            app.ButterworthFilterCheckBox.Enable = 'off';
            app.ButterworthFilterCheckBox.Tooltip = {'This is a butterworth low pass filter. On the right is its stopband cutoff frequency.'};
            app.ButterworthFilterCheckBox.Text = 'Butterworth Filter';
            app.SGCheckBox = uicheckbox(app.OMZer_UIFigure);
            app.SGCheckBox.ValueChangedFcn = createCallbackFcn(app, @SGCheckBoxValueChanged, true);
            app.SGCheckBox.Enable = 'off';
            app.SGCheckBox.Tooltip = {'This is a low pass filter. 5th order (13 points window length).'};
            app.SGCheckBox.Text = 'Savitzky-Golay Smooth';
            app.ButterworthDropDown = uidropdown(app.OMZer_UIFigure);
            app.ButterworthDropDown.Items = {'40', '50', '60', '70', '80', '90', '100', '150', '200'};
            app.ButterworthDropDown.ValueChangedFcn = createCallbackFcn(app, @ButterworthDropDownValueChanged, true);
            app.ButterworthDropDown.Enable = 'off';
            app.ButterworthDropDown.Tooltip = {'This is a low pass filter. Select the cutoff frequency (Hz).'};
            app.ButterworthDropDown.Value = '100';
            app.DenoiseLamp = uilamp(app.OMZer_UIFigure);
            app.ButterworthLamp = uilamp(app.OMZer_UIFigure);
            app.SGLamp = uilamp(app.OMZer_UIFigure);
            app.PolyfitDedriftCheckBox = uicheckbox(app.OMZer_UIFigure);
            app.PolyfitDedriftCheckBox.ValueChangedFcn = createCallbackFcn(app, @PolyfitDedriftCheckBoxValueChanged, true);
            app.PolyfitDedriftCheckBox.Enable = 'off';
            app.PolyfitDedriftCheckBox.Tooltip = {'This is the baseline drift correction via polynomial method.'};
            app.PolyfitDedriftCheckBox.Text = 'Polyfit Dedrift';
            app.PolyDriftDropDown = uidropdown(app.OMZer_UIFigure);
            app.PolyDriftDropDown.Items = {'1', '2', '3', '4', '5', '6'};
            app.PolyDriftDropDown.ValueChangedFcn = createCallbackFcn(app, @PolyDriftDropDownValueChanged, true);
            app.PolyDriftDropDown.Enable = 'off';
            app.PolyDriftDropDown.Tooltip = {'Select the order of the polynominal curve for baseline drift correction.'};
            app.PolyDriftDropDown.Value = '1';
            app.PolyDedriftLamp = uilamp(app.OMZer_UIFigure);
            app.TopHatDedriftbetaCheckBox = uicheckbox(app.OMZer_UIFigure);
            app.TopHatDedriftbetaCheckBox.ValueChangedFcn = createCallbackFcn(app, @TopHatDedriftbetaCheckBoxValueChanged, true);
            app.TopHatDedriftbetaCheckBox.Enable = 'off';
            app.TopHatDedriftbetaCheckBox.Text = 'TopHat Dedrift (beta)';
            app.NormaliseCheckBox = uicheckbox(app.OMZer_UIFigure);
            app.NormaliseCheckBox.ValueChangedFcn = createCallbackFcn(app, @NormaliseCheckBoxValueChanged, true);
            app.NormaliseCheckBox.Enable = 'off';
            app.NormaliseCheckBox.Tooltip = {'To normalise signal such that the value ranges from 0 to 1.'};
            app.NormaliseCheckBox.Text = 'Normalise';
            app.TopHatDedriftLamp = uilamp(app.OMZer_UIFigure);
            app.NormaliseLamp = uilamp(app.OMZer_UIFigure);
            app.Image_Apply = uiimage(app.OMZer_UIFigure);
            app.Image_Apply.ImageClickedFcn = createCallbackFcn(app, @Image_ApplyClicked, true);
            app.Image_Apply.Enable = 'off';
            app.Image_Apply.ImageSource = 'Icon Do.png';
            app.ApplyLabel = uilabel(app.OMZer_UIFigure);
            app.ApplyLabel.Text = 'Apply';
            app.Image_Reset = uiimage(app.OMZer_UIFigure);
            app.Image_Reset.ImageClickedFcn = createCallbackFcn(app, @Image_ResetClicked, true);
            app.Image_Reset.Enable = 'off';
            app.Image_Reset.ImageSource = 'Icon Undo.png';
            app.ResetLabel = uilabel(app.OMZer_UIFigure);
            app.ResetLabel.Text = 'Reset';
            app.Image_ActivatedCamera = uiimage(app.OMZer_UIFigure);
            app.Image_ActivatedCamera.Tooltip = {'(This is NOT a button) Tell the user from which camera are the signals being displayed now'};
            app.Image_ActivatedCamera.ImageSource = 'Icon Activated Camera.png';
            app.CameraNALabel = uilabel(app.OMZer_UIFigure);
            app.CameraNALabel.Text = 'Camera N/A';
            app.MoviesecSpinnerLabel = uilabel(app.OMZer_UIFigure);
            app.MoviesecSpinnerLabel.Text = 'Movie (sec)';
            app.MoviesecSpinner = uispinner(app.OMZer_UIFigure);
            app.MoviesecSpinner.Step = 0.0005;
            app.MoviesecSpinner.ValueChangingFcn = createCallbackFcn(app, @MoviesecSpinnerValueChanging, true);
            app.MoviesecSpinner.Limits = [0 Inf];
            app.MoviesecSpinner.ValueDisplayFormat = '%.4f';
            app.MoviesecSpinner.ValueChangedFcn = createCallbackFcn(app, @MoviesecSpinnerValueChanged, true);
            app.MoviesecSpinner.Enable = 'off';
            % Show the figure after all components are created
            app.OMZer_UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OMZer

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.OMZer_UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.OMZer_UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OMZer_UIFigure)
        end
    end
end