classdef OMZer_BatchMasking < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OMZer_BatchMasking_UIFigure    matlab.ui.Figure
        DirectoryEditField             matlab.ui.control.EditField
        DirectoryEditFieldLabel        matlab.ui.control.Label
        BatchProcessingProgressEditField  matlab.ui.control.NumericEditField
        ProgressEditFieldLabel         matlab.ui.control.Label
        StartLabel                     matlab.ui.control.Label
        Image_Start                    matlab.ui.control.Image
        ConfirmFileswithCamera2Label   matlab.ui.control.Label
        Image_ConfirmFileswithCamera2  matlab.ui.control.Image
        ConfirmFileswithCamera1Label   matlab.ui.control.Label
        Image_ConfirmFileswithCamera1  matlab.ui.control.Image
        fileListBox_Camera2            matlab.ui.control.ListBox
        fileListBox_Camera1            matlab.ui.control.ListBox
        LoadAMaskforCamera2Label       matlab.ui.control.Label
        Image_LoadAMaskforCamera2      matlab.ui.control.Image
        LoadAMaskforCamera1Label       matlab.ui.control.Label
        Image_LoadAMaskforCamera1      matlab.ui.control.Image
        OpenFolderLabel                matlab.ui.control.Label
        Image_OpenFolder               matlab.ui.control.Image
        fileListBox                    matlab.ui.control.ListBox
        imageAxis1                     matlab.ui.control.UIAxes
        imageAxis2                     matlab.ui.control.UIAxes
    end


    properties (Access = public)

        % The Current App --------------------
        setup % User preference

        imageIconActColor = [0.80, 0.80, 0.80]; % The activation color when an image is clicked

        dirName % File directory
        fileNameWithExtension
        fileName % Filename without path and extension
        fileExt % File Extension

        matFileList
        allFileList = [ {'--- --- --- --- --- --- --- --- --- --- MAT Files --- --- --- --- --- --- --- --- --- ---'}; ...
            {} ]; % The collection of all different types of files

        imageAxisObject

        bgFrame = cell(1,2);

        maskPlotObject = cell(1,2); % Column 1 (or 2) for camera 1 (or 2)
        maskGroup = cell(1,2); % Column 1 (or 2) for camera 1 (or 2)
        bgImageGroup = cell(1,2); % Column 1 (or 2) for camera 1 (or 2). 1 (or NaN) - inside (or outside) the mask
    end


    methods (Access = public)

        function showImageIconAct(app, imageIconName)

            imageIconName.BackgroundColor = app.imageIconActColor; %[0.80, 0.80, 0.80];
            pause(0.05)
            imageIconName.BackgroundColor = 'none';
        end


        function setColourMap(app,name)

            colormap(app.imageAxis1, name);
            colormap(app.imageAxis2, name);
        end


        function setAxisLimit(app, xMaxLimit, yMaxLimit) % Set the x-y limit of all axes

            app.imageAxis1.XLim = [1, xMaxLimit];
            app.imageAxis1.YLim = [1, yMaxLimit];

            app.imageAxis2.XLim = [1, xMaxLimit];
            app.imageAxis2.YLim = [1, yMaxLimit];
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            msg = 'Configuring files';
            progressBar = uiprogressdlg(app.OMZer_BatchMasking_UIFigure, 'Title','Initialising', 'Message',msg, 'Indeterminate','on');
            drawnow

            clc

            % (1) User setup
            app.setup = load([pwd,'/OMZer_Setup']);
            setColourMap(app, app.setup.userColourMap);

            % (2) Create components collection
            app.imageAxisObject = { app.imageAxis1; app.imageAxis2 };

            % (3) Diable Toolbar
            % https://www.mathworks.com/help/matlab/ref/axtoolbar.html?searchHighlight=axtoolbar&s_tid=doc_srchtitle
            try
                disableDefaultInteractivity(app.imageAxis1);
                disableDefaultInteractivity(app.imageAxis2);
                axtoolbar(app.imageAxis1);
                axtoolbar(app.imageAxis2);

            catch
                % Do nothing
            end

            close(progressBar)

        end

        % Image clicked function: Image_OpenFolder
        function Image_OpenFolderClicked(app, event)

            showImageIconAct(app, app.Image_OpenFolder)

            app.dirName = uigetdir;

            if ( ~ischar(app.dirName) )  &&  ( app.dirName == 0 ) % The user 'Cancel' directory selection

                if isempty(app.DirectoryEditField.Value) % No previous directory at all

                    msg = 'No new file directory is set';
                    uialert(app.OMZer_BatchMasking_UIFigure, msg,'Warning','Icon','error')

                elseif ~isempty(app.DirectoryEditField.Value) % A previous directory exists

                    app.dirName = app.DirectoryEditField.Value;
                end

                figure(app.OMZer_BatchMasking_UIFigure) % Set this uifigure at the top level

            else % The user choose directory selection

                % (1) Reset
                app.fileName = [];
                app.fileNameWithExtension = [];
                app.DirectoryEditField.Value = app.dirName;

                app.BatchProcessingProgressEditField.Value = 0;

                app.fileListBox_Camera1.Items = {};
                app.fileListBox_Camera2.Items = {};

                for ID = 1 : 2
                    cla( app.imageAxisObject{ID} )
                end

                % (2) Prepare 'mat' file list
                matFileName = [app.dirName,'/*.mat'];
                matFileName = dir(matFileName);
                matFileName = struct2cell(matFileName);
                app.matFileList = matFileName(1,:)';
                if isempty(app.matFileList)
                    app.matFileList = {};
                end

                % (3) List all files ('allFileList' is a cell column)
                app.allFileList = [ {'--- --- --- --- --- --- --- --- --- --- MAT Files --- --- --- --- --- --- --- --- --- ---'}; ...
                    app.matFileList ]; % Concatenate cell arrays with the array concatenation operator, [].  'https://www.mathworks.com/help/matlab/matlab_prog/combine-cell-arrays.html'

                app.fileListBox.Items = app.allFileList;
                app.fileListBox.Value = {}; % 'Value = {}' means there is no default selection of a file

                if isempty(app.matFileList)

                    msg = 'No ".mat" files can be found.';
                    uialert(app.OMZer_BatchMasking_UIFigure, msg,'Warning','Icon','error')
                end

                figure(app.OMZer_BatchMasking_UIFigure) % Set this uifigure at the top level
            end

        end

        % Image clicked function: Image_Start
        function Image_StartClicked(app, event)

            showImageIconAct(app, app.Image_Start)

            app.BatchProcessingProgressEditField.Value = 0;
            completedFileNum = 0;

            % (1) Check
            if isempty( app.fileListBox_Camera1.Items )  &&  isempty( app.fileListBox_Camera2.Items )

                msg = 'You have not confirmed any files to be masked.';
                uialert(app.OMZer_BatchMasking_UIFigure, msg ,'Warning','Icon','error')

                return

            elseif isempty( app.maskGroup{1} )  &&  isempty( app.maskGroup{2} )

                msg = 'You have not loaded any masks.';
                uialert(app.OMZer_BatchMasking_UIFigure, msg ,'Warning','Icon','error')

                return
            end

            totalFileNum = length( app.fileListBox_Camera1.Items ) + length( app.fileListBox_Camera2.Items );

            % (2) Apply mask for camera 1
            if ~isempty( app.fileListBox_Camera1.Items )

                msg = 'Applying the mask to camera 1';
                progressBar = uiprogressdlg(app.OMZer_BatchMasking_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');

                userMaskMatrix_cam1 = app.maskGroup{1}; % 1 (or NaN) - inside (or outside) the mask

                for fileID = 1 : length( app.fileListBox_Camera1.Items )

                    data = load( [app.dirName, '/', app.fileListBox_Camera1.Items{fileID}] ); % 'data' is a structure

                    signalConditioningInfo = data.signalConditioningInfo;
                    signalConditioningInfo{2,2} = 1;

                    save( [app.dirName, '/', app.fileListBox_Camera1.Items{fileID}], 'userMaskMatrix_cam1', 'signalConditioningInfo', '-append' )

                    completedFileNum = completedFileNum + 1;
                    app.BatchProcessingProgressEditField.Value = round( (completedFileNum / totalFileNum) * 100 );
                    drawnow
                end

                close(progressBar)
            end

            % (3) Apply mask for camera 2
            if ~isempty( app.fileListBox_Camera2.Items )

                msg = 'Applying the mask to camera 2';
                progressBar = uiprogressdlg(app.OMZer_BatchMasking_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');

                userMaskMatrix_cam2 = app.maskGroup{2}; % 1 (or NaN) - inside (or outside) the mask

                for fileID = 1 : length( app.fileListBox_Camera2.Items )

                    data = load( [app.dirName, '/', app.fileListBox_Camera2.Items{fileID}] ); % 'data' is a structure

                    % Need to check if the file has dual cameras
                    if data.camTF == 1 % Dual cameras

                        signalConditioningInfo = data.signalConditioningInfo;
                        signalConditioningInfo{2,4} = 1;
                        
                        save( [app.dirName, '/', app.fileListBox_Camera2.Items{fileID}], 'userMaskMatrix_cam2', 'signalConditioningInfo', '-append' )

                    else

                        msg = [ app.fileListBox_Camera2.Items{fileID}, ' - only has a single camera but you want a mask to be applied to camera 2. The app ignores such a request.' ];
                        warning( msg );
                        fprintf('\n');
                    end

                    completedFileNum = completedFileNum + 1;
                    app.BatchProcessingProgressEditField.Value = round( (completedFileNum / totalFileNum) * 100 );
                    drawnow
                end

                close(progressBar)
            end

            msg = 'Completed. Click the "Refresh" button in the "OMZer File Information" app to have the lasted version of files.';
            uialert(app.OMZer_BatchMasking_UIFigure, msg ,'Warning','Icon','error')

        end

        % Image clicked function: Image_ConfirmFileswithCamera1
        function Image_ConfirmFileswithCamera1Clicked(app, event)

            showImageIconAct(app, app.Image_ConfirmFileswithCamera1)

            app.BatchProcessingProgressEditField.Value = 0;
            app.fileListBox_Camera1.Items = {};

            if isempty( app.fileListBox.Value )

                msg = 'Select files from the file list first.';
                uialert(app.OMZer_BatchMasking_UIFigure, msg,'Warning','Icon','error')

            else

                app.fileListBox_Camera1.Items = app.fileListBox.Value;
            end

        end

        % Image clicked function: Image_ConfirmFileswithCamera2
        function Image_ConfirmFileswithCamera2Clicked(app, event)

            showImageIconAct(app, app.Image_ConfirmFileswithCamera2)

            app.BatchProcessingProgressEditField.Value = 0;
            app.fileListBox_Camera2.Items = {};

            if isempty( app.fileListBox.Value )

                msg = 'Select files from the file list first.';
                uialert(app.OMZer_BatchMasking_UIFigure, msg,'Warning','Icon','error')

            else

                app.fileListBox_Camera2.Items = app.fileListBox.Value;
            end

        end

        % Image clicked function: Image_LoadAMaskforCamera1
        function Image_LoadAMaskforCamera1Clicked(app, event)

            showImageIconAct(app, app.Image_LoadAMaskforCamera1)

            % (1) Reset
            app.BatchProcessingProgressEditField.Value = 0;

            cla( app.imageAxisObject{1} )
            app.maskGroup{1} = [];
            app.bgImageGroup{1} = [];

            % (2) Get a new mask
            [file, path] = uigetfile('*.mat');

            if ~ischar(file) % MATLAB: If the user clicks the Cancel button or the window close button (X), then MATLAB returns the file value as 0.

                msg = 'Please only choose one mask file with ".mat" extension.';
                uialert(app.OMZer_BatchMasking_UIFigure, msg,'Warning','Icon','error')

            else

                msg = 'Load the mask';
                progressBar = uiprogressdlg(app.OMZer_BatchMasking_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');

                data = load( [path,file] ); % 'Data' is a structure

                field = { 'mask', 'backgroundImage' };
                TF = prod( isfield( data, field ) ); % 1 (or 0) - all exist (or at least one does not exist)

                if TF == 1

                    app.maskGroup{1} = data.mask; % 1 (or NaN) - inside (or outside) the mask
                    app.bgImageGroup{1} = data.backgroundImage;

                    hold(app.imageAxis1,'on')
                    app.bgFrame{1} = imagesc( app.imageAxis1, app.bgImageGroup{1} );
                    app.maskPlotObject{1} = imagesc( app.imageAxis1, app.maskGroup{1}, 'AlphaData', 0.3 * ~isnan(app.maskGroup{1}) );
                    hold(app.imageAxis1,'off')
                else

                    msg = 'Please only choose one mask file with ".mat" extension.';
                    uialert(app.OMZer_BatchMasking_UIFigure, msg,'Warning','Icon','error')
                end

                close(progressBar);
            end

            figure(app.OMZer_BatchMasking_UIFigure)

        end

        % Image clicked function: Image_LoadAMaskforCamera2
        function Image_LoadAMaskforCamera2Clicked(app, event)

            showImageIconAct(app, app.Image_LoadAMaskforCamera2)

            % (1) Reset
            app.BatchProcessingProgressEditField.Value = 0;

            cla( app.imageAxisObject{2} )
            app.maskGroup{2} = [];
            app.bgImageGroup{2} = [];

            % (2) Get a new mask
            [file, path] = uigetfile('*.mat');

            if ~ischar(file) % MATLAB: If the user clicks the Cancel button or the window close button (X), then MATLAB returns the file value as 0.

                msg = 'Please only choose one mask file with ".mat" extension.';
                uialert(app.OMZer_BatchMasking_UIFigure, msg,'Warning','Icon','error')

            else

                msg = 'Load the mask';
                progressBar = uiprogressdlg(app.OMZer_BatchMasking_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');

                data = load( [path,file] ); % 'Data' is a structure

                field = { 'mask', 'backgroundImage' };
                TF = prod( isfield( data, field ) ); % 1 (or 0) - all exist (or at least one does not exist)

                if TF == 1

                    app.maskGroup{2} = data.mask; % 1 (or NaN) - inside (or outside) the mask
                    app.bgImageGroup{2} = data.backgroundImage;

                    hold(app.imageAxis2,'on')
                    app.bgFrame{2} = imagesc( app.imageAxis2, app.bgImageGroup{2} );
                    app.maskPlotObject{2} = imagesc( app.imageAxis2, app.maskGroup{2}, 'AlphaData', 0.3 * ~isnan(app.maskGroup{2}) );
                    hold(app.imageAxis2,'off')
                else

                    msg = 'Please only choose one mask file with ".mat" extension.';
                    uialert(app.OMZer_BatchMasking_UIFigure, msg,'Warning','Icon','error')
                end

                close(progressBar);
            end

            figure(app.OMZer_BatchMasking_UIFigure)

        end

        % Value changed function: fileListBox
        function fileListBoxValueChanged(app, event)

            fileList = app.fileListBox.Value'; % 'fileList' is a cell column
            fileList( contains( fileList, '--- --- --- --- --- --- --- --- --- --- MAT Files --- --- --- --- --- --- --- --- --- ---' ) ) = [];

            app.fileListBox.Value = fileList;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OMZer_BatchMasking_UIFigure = uifigure('Visible', 'off');
            app.OMZer_BatchMasking_UIFigure.Name = 'OMZer Batch Masking';
            app.imageAxis2 = uiaxes(app.OMZer_BatchMasking_UIFigure);
            app.imageAxis2.XLim = [1 100];
            app.imageAxis2.YLim = [1 100];
            app.imageAxis2.YDir = 'reverse';
            app.imageAxis2.XTick = [];
            app.imageAxis2.YTick = [];
            app.imageAxis2.ZTick = [];
            app.imageAxis1 = uiaxes(app.OMZer_BatchMasking_UIFigure);
            app.imageAxis1.XLim = [1 100];
            app.imageAxis1.YLim = [1 100];
            app.imageAxis1.YDir = 'reverse';
            app.imageAxis1.XTick = [];
            app.imageAxis1.YTick = [];
            app.imageAxis1.ZTick = [];
            app.fileListBox = uilistbox(app.OMZer_BatchMasking_UIFigure);
            app.fileListBox.Items = {};
            app.fileListBox.Multiselect = 'on';
            app.fileListBox.ValueChangedFcn = createCallbackFcn(app, @fileListBoxValueChanged, true);
            app.fileListBox.Value = {};
            app.Image_OpenFolder = uiimage(app.OMZer_BatchMasking_UIFigure);
            app.Image_OpenFolder.ImageClickedFcn = createCallbackFcn(app, @Image_OpenFolderClicked, true);
            app.Image_OpenFolder.ImageSource = 'Icon Open.png';
            app.OpenFolderLabel = uilabel(app.OMZer_BatchMasking_UIFigure);
            app.OpenFolderLabel.Text = 'Open Folder';
            app.Image_LoadAMaskforCamera1 = uiimage(app.OMZer_BatchMasking_UIFigure);
            app.Image_LoadAMaskforCamera1.ImageClickedFcn = createCallbackFcn(app, @Image_LoadAMaskforCamera1Clicked, true);
            app.Image_LoadAMaskforCamera1.ImageSource = 'Icon Open.png';
            app.LoadAMaskforCamera1Label = uilabel(app.OMZer_BatchMasking_UIFigure);
            app.LoadAMaskforCamera1Label.Text = 'Load A Mask for Camera 1';
            app.Image_LoadAMaskforCamera2 = uiimage(app.OMZer_BatchMasking_UIFigure);
            app.Image_LoadAMaskforCamera2.ImageClickedFcn = createCallbackFcn(app, @Image_LoadAMaskforCamera2Clicked, true);
            app.Image_LoadAMaskforCamera2.ImageSource = 'Icon Open.png';
            app.LoadAMaskforCamera2Label = uilabel(app.OMZer_BatchMasking_UIFigure);
            app.LoadAMaskforCamera2Label.Text = 'Load A Mask for Camera 2';
            app.fileListBox_Camera1 = uilistbox(app.OMZer_BatchMasking_UIFigure);
            app.fileListBox_Camera1.Items = {};
            app.fileListBox_Camera1.Enable = 'off';
            app.fileListBox_Camera1.Value = {};
            app.fileListBox_Camera2 = uilistbox(app.OMZer_BatchMasking_UIFigure);
            app.fileListBox_Camera2.Items = {};
            app.fileListBox_Camera2.Enable = 'off';
            app.fileListBox_Camera2.Value = {};
            app.Image_ConfirmFileswithCamera1 = uiimage(app.OMZer_BatchMasking_UIFigure);
            app.Image_ConfirmFileswithCamera1.ImageClickedFcn = createCallbackFcn(app, @Image_ConfirmFileswithCamera1Clicked, true);
            app.Image_ConfirmFileswithCamera1.ImageSource = 'Icon Select.png';
            app.ConfirmFileswithCamera1Label = uilabel(app.OMZer_BatchMasking_UIFigure);
            app.ConfirmFileswithCamera1Label.Text = 'Confirm Files with Camera 1';
            app.Image_ConfirmFileswithCamera2 = uiimage(app.OMZer_BatchMasking_UIFigure);
            app.Image_ConfirmFileswithCamera2.ImageClickedFcn = createCallbackFcn(app, @Image_ConfirmFileswithCamera2Clicked, true);
            app.Image_ConfirmFileswithCamera2.ImageSource = 'Icon Select.png';
            app.ConfirmFileswithCamera2Label = uilabel(app.OMZer_BatchMasking_UIFigure);
            app.ConfirmFileswithCamera2Label.Text = 'Confirm Files with Camera 2';
            app.Image_Start = uiimage(app.OMZer_BatchMasking_UIFigure);
            app.Image_Start.ImageClickedFcn = createCallbackFcn(app, @Image_StartClicked, true);
            app.Image_Start.ImageSource = 'Icon Start.png';
            app.StartLabel = uilabel(app.OMZer_BatchMasking_UIFigure);
            app.StartLabel.Text = 'Start';
            app.ProgressEditFieldLabel = uilabel(app.OMZer_BatchMasking_UIFigure);
            app.ProgressEditFieldLabel.Text = 'Progress (%)';
            app.BatchProcessingProgressEditField = uieditfield(app.OMZer_BatchMasking_UIFigure, 'numeric');
            app.BatchProcessingProgressEditField.Limits = [0 Inf];
            app.BatchProcessingProgressEditField.ValueDisplayFormat = '%.0f';
            app.BatchProcessingProgressEditField.Editable = 'off';
            app.DirectoryEditFieldLabel = uilabel(app.OMZer_BatchMasking_UIFigure);
            app.DirectoryEditFieldLabel.Text = 'Directory';
            app.DirectoryEditField = uieditfield(app.OMZer_BatchMasking_UIFigure, 'text');
            app.DirectoryEditField.Editable = 'off';
            % Show the figure after all components are created
            app.OMZer_BatchMasking_UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OMZer_BatchMasking

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.OMZer_BatchMasking_UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.OMZer_BatchMasking_UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OMZer_BatchMasking_UIFigure)
        end
    end
end