classdef OMZer_Ca_ExportDisplayPixel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OMZer_Ca_ExportDisplayPixel_UIFigure  matlab.ui.Figure
        MapTitleLabel          matlab.ui.control.Label
        ExportLabel            matlab.ui.control.Label
        Image_Export           matlab.ui.control.Image
        AddIntoPixelLabel      matlab.ui.control.Label
        Image_AddIntoPixel     matlab.ui.control.Image
        DeletefromPixelLabel   matlab.ui.control.Label
        Image_DeletefromPixel  matlab.ui.control.Image
        PixelListBox           matlab.ui.control.ListBox
        PixelListBoxLabel      matlab.ui.control.Label
        CheckSigX_scrn         matlab.ui.control.NumericEditField
        CheckSigY_scrn         matlab.ui.control.NumericEditField
        CheckSigXYLabel        matlab.ui.control.Label
        MapHistAxis            matlab.ui.control.UIAxes
        MapAxis                matlab.ui.control.UIAxes
        colorBarAxes           matlab.ui.control.UIAxes
        checkSigAxis           matlab.ui.control.UIAxes
    end


    properties (Access = public)

        % The Parent App --------------------
        mainApp
        mainAppUIFigure


        % The Current App --------------------
        dirName % File directory
        fileName % Filename without path and extension

        setup % User preference
        userAlphaValue

        imageIconActColor = [0.80, 0.80, 0.80]; % The activation color when an image is clicked

        lWidth = 2; % Line width

        data
        acqFreq
        markerXY
        signalTime
        cmosDataGroup
        bgImageNomGroup % Gray colour RGB version of background image
        cameraSource % '1 (or 2)' - data from camera 1 (or 2)

        mapAxisLocs

        mouseXY = []; % This is a 1-by-2 row vector that stores mouse "X" and "Y" coordinate
        mouseMotion = []; % The result can be 'normal', 'extend', 'alt', 'open'

        cmosDataRowNum % The numer of rows in cmosData
        cmosDataColNum % The numer of columns in cmosData

        % (0)
        exportMapName
        exportMapDataTitle
        exportMapData

        % (1) For Act
        actMapPlot
        actHistPlot
        actColorBarPlot

        % (2) For Rep
        repMapPlot
        repHistPlot
        repColorBarPlot

        % (3) For CaTD
        CaTDMapPlot
        CaTDHistPlot
        CaTDColorBarPlot

        % (4) For RT
        RTMapPlot
        RTHistPlot
        RTColorBarPlot

        % (5) For DT
        DTMapPlot
        DTHistPlot
        DTColorBarPlot

        % (6) For DTau
        DTauMapPlot
        DTauHistPlot
        DTauColorBarPlot

        % (7) For Check Signal
        checkSigMarkerXY
        checkSigMouseDragID

        checkSigDataSectionTime
        checkSigDataSection
        checkSigLocsAct
        checkSigLocsRep
        checkSigLocsStart
        checkSigLocsEnd
        checkSigDTauFitDataSection

        checkSigMapData
        checkSigLB
        checkSigUB

        checkSigMarkerPlot
        checkSigMarkerHighlightPlot
        checkSigPlot
        checkSigActPointPlot
        checkSigRepPointPlot
        checkSigStartPointPlot
        checkSigEndPointPlot
        checkSigHistIndicatingLinePlot
        checkSigDTauFitPlot
        
        % (8) For export pixel
        pixelSaveToID = 1;
        exportPixelX
        exportPixelY
    end


    methods (Access = public)

        function showImageIconAct(app, imageIconName)

            imageIconName.BackgroundColor = app.imageIconActColor; %[0.80, 0.80, 0.80];
            pause(0.05)
            imageIconName.BackgroundColor = 'none';
        end


        function setColourMap(app,name)

            colormap(app.MapAxis, name);
            colormap(app.colorBarAxes, name);
        end


        function setAxisLimit(app, xMaxLimit, yMaxLimit) % Set the x-y limit of all axes

            app.MapAxis.XLim = [1, xMaxLimit];
            app.MapAxis.YLim = [1, yMaxLimit];

            app.CheckSigX_scrn.Value = 1;
            app.CheckSigX_scrn.Limits = [1, xMaxLimit];
            app.CheckSigY_scrn.Value = 1;
            app.CheckSigY_scrn.Limits = [1, yMaxLimit];
        end


        function updateMouseXY(app, axisLosInfo)

            %             app.mouseXY(1,1) = ( app.mouseXY(1,1) - axisLosInfo(1,1) ) * app.cmosDataColNum / axisLosInfo(1,3);
            %             app.mouseXY(1,1) = round( app.mouseXY(1,1) ); % Transfer relative mouse location inside bgImageAxis1 to absolute location inside a 100x100 matrix
            %             if app.mouseXY(1,1) < 1
            %                 app.mouseXY(1,1) = 1; % As a result, there won't appear (0,y) element requirement in a 100x100 matrix
            %             end
            %             if app.mouseXY(1,1) > app.cmosDataColNum
            %                 app.mouseXY(1,1) = app.cmosDataColNum;
            %             end
            %
            %
            %             app.mouseXY(1,2) = axisLosInfo.CurrentPoint(1,2);

            %             app.mouseXY(1,2) = ( (axisLosInfo(1,2) + axisLosInfo(1,4)) - app.mouseXY(1,2) ) * app.cmosDataRowNum / axisLosInfo(1,4);
            %             app.mouseXY(1,2) = round( app.mouseXY(1,2) ); % Transfer relative mouse location inside bgImageAxis1 to absolute location inside a 100x100 matrix
            %             if app.mouseXY(1,2) < 1
            %                 app.mouseXY(1,2) = 1; % As a result, there won't appear (x,0) element requirement in a 100x100 matrix
            %             end
            %             if app.mouseXY(1,2) > app.cmosDataRowNum
            %                 app.mouseXY(1,2) = app.cmosDataRowNum;
            %             end


            app.mouseXY(1,1) = app.MapAxis.CurrentPoint(1,1);
            app.mouseXY(1,2) = app.MapAxis.CurrentPoint(1,2);


            app.mouseXY(1,1) = round( app.mouseXY(1,1) ); % Transfer relative mouse location inside bgImageAxis1 to absolute location inside a 100x100 matrix
            if app.mouseXY(1,1) < 1
                app.mouseXY(1,1) = 1; % As a result, there won't appear (0,y) element requirement in a 100x100 matrix
            end
            if app.mouseXY(1,1) > app.cmosDataColNum
                app.mouseXY(1,1) = app.cmosDataColNum;
            end

            app.mouseXY(1,2) = round( app.mouseXY(1,2) ); % Transfer relative mouse location inside bgImageAxis1 to absolute location inside a 100x100 matrix
            if app.mouseXY(1,2) < 1
                app.mouseXY(1,2) = 1; % As a result, there won't appear (x,0) element requirement in a 100x100 matrix
            end
            if app.mouseXY(1,2) > app.cmosDataRowNum
                app.mouseXY(1,2) = app.cmosDataRowNum;
            end
        end


        function updatePixelListBox(app)

            pixelNum = length( app.exportPixelX );

            app.PixelListBox.Items = {};
            if pixelNum ~= 0
                for ID = 1 : pixelNum
                    app.PixelListBox.Items{ID} = num2str(ID);
                end
            end
        end


        function newPlotObject = plotMap(app, plotObject, matrix, LB, UB)

            matrix( matrix < LB  |  matrix > UB ) = NaN;

            if isequal( app.mainApp.ActLabel.FontColor, [ 1.00, 0.00, 0.00 ] )  ...
                    ||  isequal( app.mainApp.RepLabel.FontColor, [ 1.00, 0.00, 0.00 ] )

                matrix = matrix - nanmin( matrix(:) ); % Shift the time bar such that Act (or Rep) Map always starts from 0
            end

            hold(app.MapAxis,'on')
            delete(plotObject)

            % newPlotObject = imagesc(app.MapAxis, matrix);
            % newPlotObject.AlphaData = ~isnan(matrix) * app.userAlphaValue; % Make the pixel with NaN transparent

            newPlotObject = pcolor( app.MapAxis, matrix );
            newPlotObject.FaceColor = 'interp';
            newPlotObject.EdgeColor = 'none';
            newPlotObject.AlphaData = ~isnan(matrix) * app.userAlphaValue; % Make the pixel with NaN transparent

            if nanmin(matrix(:)) == nanmax(matrix(:))
                caxis(app.MapAxis, [ nanmin(matrix(:)), nanmax(matrix(:))+1 ]);
            else
                caxis(app.MapAxis, [ nanmin(matrix(:)), nanmax(matrix(:)) ]);
            end
            hold(app.MapAxis,'off')
        end


        function newPlotObject = plotMapColorBar(app, plotObject, matrix, LB, UB)

            matrix( matrix < LB  |  matrix > UB ) = NaN;

            if isequal( app.mainApp.ActLabel.FontColor, [ 1.00, 0.00, 0.00 ] )  ...
                    ||  isequal( app.mainApp.RepLabel.FontColor, [ 1.00, 0.00, 0.00 ] )

                matrix = matrix - nanmin( matrix(:) ); % Shift the time bar such that Act (or Rep) Map always starts from 0
            end

            matrixColumnVersion = reshape( matrix, [],1 );
            matrixColumnVersion( isnan(matrixColumnVersion) ) = [];
            matrixColumnVersion = unique(matrixColumnVersion);

            % (1) Plot the color bar
            delete(plotObject)
            newPlotObject = imagesc(app.colorBarAxes, matrixColumnVersion);
            yticks(app.colorBarAxes, 'auto')
            app.colorBarAxes.YLim = [ 1, length(matrixColumnVersion) ];

            % (2) Manually create y tick label
            if length(app.colorBarAxes.YTick) <= length(matrixColumnVersion)

                app.colorBarAxes.YTick( mod(app.colorBarAxes.YTick , 1) ~= 0  ) = []; % Only keep integer tick

            else
                app.colorBarAxes.YTick = [ 1 : length(matrixColumnVersion) ];
            end

            colorBarLabel = cell( 1, length(app.colorBarAxes.YTick) );
            for ID = 1 : length(app.colorBarAxes.YTick)
                colorBarLabel{ID} = num2str( matrixColumnVersion(app.colorBarAxes.YTick(ID)) );
            end
            app.colorBarAxes.YTickLabel = colorBarLabel;
        end


        function newPlotObject = plotMapHist(app, plotObject, matrix, LB, UB)

            matrix( matrix < LB  |  matrix > UB ) = NaN;

            histColumnVersion = reshape(matrix, [],1);
            histColumnVersion( isnan(histColumnVersion) ) = []; % Delete NaN because it will also be counted into 'data length'
            binNum = round( sqrt( length(histColumnVersion) ) ); % Specifies the number of bins (= square root of the number of observations)

            hold(app.MapHistAxis,'on')
            delete(plotObject)

            %            newPlotObject = histogram(app.MapHistAxis, histColumnVersion, binNum, 'FaceColor',[0.00, 0.45, 0.74], 'FaceAlpha',1);

            newPlotObject = histogram(app.MapHistAxis, histColumnVersion, 'FaceColor',[0.00, 0.45, 0.74], 'FaceAlpha',1);


            %             % Colourful Histgtam (Nedd code update)
            %             if isequal( app.Setup.VmMapCalSetup{13,2}, 'Multiple' )
            %
            %                 newPlotObject.FaceAlpha = 0;
            %
            %                 histBinEdge = newPlotObject.BinEdges;
            %                 eval( [ 'colorLevel = ', app.userColourMap, '(', num2str(binNum), ');' ] )
            %                 app.colorHistPlot = cell(binNum,1);
            %
            %                 for ID = 1 : binNum
            %                     app.colorHistPlot{ID} = histogram( app.MapHistAxis, HistCol( HistCol >= histBinEdge(ID) & HistCol < histBinEdge(ID+1) ), histBinEdge, 'FaceColor',colorLevel(ID,:), 'FaceAlpha',1 );
            %                 end
            %             end

            hold(app.MapHistAxis,'off')

            if min(histColumnVersion) == max(histColumnVersion)
                xlim( app.MapHistAxis, [min(histColumnVersion), max(histColumnVersion)+1] );
            else
                xlim( app.MapHistAxis, [min(histColumnVersion), max(histColumnVersion)] );
            end

            app.MapHistAxis.YLim = [ 0, max(newPlotObject.BinCounts) ];
        end


        function plotObject = updateCheckSigMarker(app, markerColor)

            X = app.checkSigMarkerXY(1,1);
            Y = app.checkSigMarkerXY(1,2);

            plotObject = line(app.MapAxis, X, Y, 'Marker','square', 'MarkerSize',10, 'MarkerEdgeColor','white', 'MarkerFaceColor', markerColor);
        end


        function plotObject = updateCheckSigMarkerHighlight(app)

            X = app.checkSigMarkerXY(1,1);
            Y = app.checkSigMarkerXY(1,2);

            plotObject = line( app.MapAxis, X, Y, 'Marker','o', 'LineWidth',4, 'MarkerSize',30, 'MarkerEdgeColor','white', 'MarkerFaceColor', 'none');
        end


        function plotObject = updateCheckSig(app, dataTime, data, lineColor)

            row = app.checkSigMarkerXY(1,2);
            col = app.checkSigMarkerXY(1,1);

            plotObject = line( app.checkSigAxis, dataTime, squeeze(data(row,col,:)), 'LineWidth',app.lWidth, 'color', lineColor );
            xlim( app.checkSigAxis, [ dataTime(1), dataTime(end) ] );
        end


        function plotObject = updatecheckSigHistIndicatingLine(app, dataMap)

            row = app.checkSigMarkerXY(1,2);
            col = app.checkSigMarkerXY(1,1);

            if ~isnan( dataMap(row,col) )

                valueLabel = num2str(dataMap(row,col));

                plotObject = xline( app.MapHistAxis, dataMap(row,col), 'LineWidth',app.lWidth, 'color','Red' );
                plotObject.Label = valueLabel;
                plotObject.FontSize = 20;
                plotObject.FontWeight = 'bold';

                delta = app.MapHistAxis.XLim(2) - app.MapHistAxis.XLim(1);
                if dataMap(row,col) <= app.MapHistAxis.XLim(1) + 0.1*delta
                    plotObject.LabelHorizontalAlignment = 'right';

                elseif dataMap(row,col) >= app.MapHistAxis.XLim(2) - 0.1*delta
                    plotObject.LabelHorizontalAlignment = 'left';

                else
                    plotObject.LabelHorizontalAlignment = 'center';
                end

            else
                plotObject = [];
            end
        end


        function plotObject = updateCheckSigActPoints(app, dataTime, data)

            row = app.checkSigMarkerXY(1,2);
            col = app.checkSigMarkerXY(1,1);

            ID = app.checkSigLocsAct( row, col );
            if ~isnan(ID)
                plotObject = line( app.checkSigAxis, dataTime(ID), data(row,col,ID), 'Marker','o', 'MarkerSize',10, 'MarkerEdgeColor','black', 'MarkerFaceColor','black' );
            else
                plotObject = [];
            end
        end


        function plotObject = updateCheckSigRepPoints(app, dataTime, data)

            row = app.checkSigMarkerXY(1,2);
            col = app.checkSigMarkerXY(1,1);

            ID = app.checkSigLocsRep( row, col );
            if ~isnan(ID)
                plotObject = line( app.checkSigAxis, dataTime(ID), data(row,col,ID), 'Marker','d', 'MarkerSize',10, 'MarkerEdgeColor','black', 'MarkerFaceColor','black' );
            else
                plotObject = [];
            end
        end


        function plotObject = updateCheckSigStartPoints(app, dataTime, data)

            row = app.checkSigMarkerXY(1,2);
            col = app.checkSigMarkerXY(1,1);

            ID = app.checkSigLocsStart( row, col );
            if ~isnan(ID)
                plotObject = line( app.checkSigAxis, dataTime(ID), data(row,col,ID), 'Marker','o', 'MarkerSize',10, 'MarkerEdgeColor','black', 'MarkerFaceColor','black' );
            else
                plotObject = [];
            end
        end


        function plotObject = updateCheckSigEndPoints(app, dataTime, data)

            row = app.checkSigMarkerXY(1,2);
            col = app.checkSigMarkerXY(1,1);

            ID = app.checkSigLocsEnd( row, col );
            if ~isnan(ID)
                plotObject = line( app.checkSigAxis, dataTime(ID), data(row,col,ID), 'Marker','d', 'MarkerSize',10, 'MarkerEdgeColor','black', 'MarkerFaceColor','black' );
            else
                plotObject = [];
            end
        end


        function plotObject = updateCheckSigDTauFit(app, dataTime, data, lineColor)

            row = app.checkSigMarkerXY(1,2);
            col = app.checkSigMarkerXY(1,1);

            plotObject = line( app.checkSigAxis, dataTime, squeeze(data(row,col,:)), 'LineWidth',app.lWidth, 'color', lineColor );
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, OMZer_Ca)

            msg = 'Configuring files';
            progressBar = uiprogressdlg(app.OMZer_Ca_ExportDisplayPixel_UIFigure, 'Title','Initialising', 'Message',msg, 'Indeterminate','on');
            drawnow

            % (1) User set up
            app.setup = load([pwd,'/OMZer_Setup']);
            setColourMap(app, app.setup.userColourMap);
            app.userAlphaValue = 1.0; % The default alpha value

            % (2) Get information from the mainApp
            app.mainApp = OMZer_Ca;
            app.mainAppUIFigure = app.mainApp.OMZer_Ca_UIFigure;

            app.dirName = app.mainApp.dirName;
            app.fileName = app.mainApp.fileName;

            app.data = app.mainApp.data;
            app.acqFreq = app.mainApp.acqFreq;
            app.markerXY = app.mainApp.markerXY;
            app.signalTime = app.mainApp.signalTime;
            app.cmosDataGroup = app.mainApp.cmosDataGroup;
            app.bgImageNomGroup = app.mainApp.bgImageNomGroup;
            app.cameraSource = app.mainApp.cameraSource;

            app.exportMapName = app.mainApp.exportMapName;
            app.exportMapDataTitle = app.mainApp.exportMapDataTitle;
            app.exportMapData = app.mainApp.exportMapData;

            % (3) Diable Toolbar
            % https://www.mathworks.com/help/matlab/ref/axtoolbar.html?searchHighlight=axtoolbar&s_tid=doc_srchtitle
            try
                disableDefaultInteractivity(app.colorBarAxes);
                disableDefaultInteractivity(app.MapAxis);
                disableDefaultInteractivity(app.MapHistAxis);
                disableDefaultInteractivity(app.checkSigAxis);

                axtoolbar(app.colorBarAxes);
                axtoolbar(app.MapAxis);
                axtoolbar(app.MapHistAxis);
                axtoolbar(app.checkSigAxis);

            catch
                % Do nothing
            end

            % (4.1) Plot background image
            [app.cmosDataRowNum, app.cmosDataColNum, ~] = size( app.cmosDataGroup{app.cameraSource} );
            setAxisLimit(app, app.cmosDataColNum, app.cmosDataRowNum);
            imagesc(  app.MapAxis, app.bgImageNomGroup{app.cameraSource} );

            % (4.2) Plot map
            app.MapTitleLabel.Text = app.mainApp.MapTitleLabel.Text;

            if isequal( app.exportMapName, app.mainApp.ActLabel.Text ) % Act map

                app.checkSigDataSectionTime = app.exportMapData{7};
                app.checkSigDataSection = app.exportMapData{8};
                app.checkSigLocsAct = app.exportMapData{9};

                app.checkSigMapData = app.exportMapData{10};
                app.checkSigLB = app.exportMapData{5};
                app.checkSigUB = app.exportMapData{6};
                app.checkSigMapData( app.checkSigMapData < app.checkSigLB  |  app.checkSigMapData > app.checkSigUB ) = NaN;

                app.actMapPlot = plotMap(app, app.actMapPlot, app.checkSigMapData, app.checkSigLB, app.checkSigUB);
                app.actColorBarPlot = plotMapColorBar(app, app.actColorBarPlot, app.checkSigMapData, app.checkSigLB, app.checkSigUB);
                app.actHistPlot = plotMapHist(app, app.actHistPlot, app.checkSigMapData, app.checkSigLB, app.checkSigUB);

            elseif isequal( app.exportMapName, app.mainApp.RepLabel.Text ) % Rep map

                app.checkSigDataSectionTime = app.exportMapData{8};
                app.checkSigDataSection = app.exportMapData{9};
                app.checkSigLocsRep = app.exportMapData{10};

                app.checkSigMapData = app.exportMapData{11};
                app.checkSigLB = app.exportMapData{6};
                app.checkSigUB = app.exportMapData{7};
                app.checkSigMapData( app.checkSigMapData < app.checkSigLB  |  app.checkSigMapData > app.checkSigUB ) = NaN;

                app.repMapPlot = plotMap(app, app.repMapPlot, app.checkSigMapData, app.checkSigLB, app.checkSigUB);
                app.repColorBarPlot = plotMapColorBar(app, app.repColorBarPlot, app.checkSigMapData, app.checkSigLB, app.checkSigUB);
                app.repHistPlot = plotMapHist(app, app.repHistPlot, app.checkSigMapData, app.checkSigLB, app.checkSigUB);

            elseif isequal( app.exportMapName, app.mainApp.CaTDLabel.Text ) % CaTD map

                app.checkSigDataSectionTime = app.exportMapData{8};
                app.checkSigDataSection = app.exportMapData{9};
                app.checkSigLocsAct = app.exportMapData{10};
                app.checkSigLocsRep = app.exportMapData{11};

                app.checkSigMapData = app.exportMapData{12};
                app.checkSigLB = app.exportMapData{6};
                app.checkSigUB = app.exportMapData{7};
                app.checkSigMapData( app.checkSigMapData < app.checkSigLB  |  app.checkSigMapData > app.checkSigUB ) = NaN;

                app.CaTDMapPlot = plotMap(app, app.CaTDMapPlot, app.checkSigMapData, app.checkSigLB, app.checkSigUB);
                app.CaTDColorBarPlot = plotMapColorBar(app, app.CaTDColorBarPlot, app.checkSigMapData, app.checkSigLB, app.checkSigUB);
                app.CaTDHistPlot = plotMapHist(app, app.CaTDHistPlot, app.checkSigMapData, app.checkSigLB, app.checkSigUB);

            elseif isequal( app.exportMapName, app.mainApp.RTLabel.Text ) % RT map

                app.checkSigDataSectionTime = app.exportMapData{9};
                app.checkSigDataSection = app.exportMapData{10};
                app.checkSigLocsStart = app.exportMapData{11};
                app.checkSigLocsEnd = app.exportMapData{12};

                app.checkSigMapData = app.exportMapData{13};
                app.checkSigLB = app.exportMapData{7};
                app.checkSigUB = app.exportMapData{8};
                app.checkSigMapData( app.checkSigMapData < app.checkSigLB  |  app.checkSigMapData > app.checkSigUB ) = NaN;

                app.RTMapPlot = plotMap(app, app.RTMapPlot, app.checkSigMapData, app.checkSigLB, app.checkSigUB);
                app.RTColorBarPlot = plotMapColorBar(app, app.RTColorBarPlot, app.checkSigMapData, app.checkSigLB, app.checkSigUB);
                app.RTHistPlot = plotMapHist(app, app.RTHistPlot, app.checkSigMapData, app.checkSigLB, app.checkSigUB);

            elseif isequal( app.exportMapName, app.mainApp.DTLabel.Text ) % DT map

                app.checkSigDataSectionTime = app.exportMapData{9};
                app.checkSigDataSection = app.exportMapData{10};
                app.checkSigLocsStart = app.exportMapData{11};
                app.checkSigLocsEnd = app.exportMapData{12};

                app.checkSigMapData = app.exportMapData{13};
                app.checkSigLB = app.exportMapData{7};
                app.checkSigUB = app.exportMapData{8};
                app.checkSigMapData( app.checkSigMapData < app.checkSigLB  |  app.checkSigMapData > app.checkSigUB ) = NaN;

                app.DTMapPlot = plotMap(app, app.DTMapPlot, app.checkSigMapData, app.checkSigLB, app.checkSigUB);
                app.DTColorBarPlot = plotMapColorBar(app, app.DTColorBarPlot, app.checkSigMapData, app.checkSigLB, app.checkSigUB);
                app.DTHistPlot = plotMapHist(app, app.DTHistPlot, app.checkSigMapData, app.checkSigLB, app.checkSigUB);

            elseif isequal( app.exportMapName, app.mainApp.DTauLabel.Text ) % DTau map

                app.checkSigDataSectionTime = app.exportMapData{9};
                app.checkSigDataSection = app.exportMapData{10};
                app.checkSigDTauFitDataSection = app.exportMapData{11};
                app.checkSigLocsStart = app.exportMapData{12};
                app.checkSigLocsEnd = app.exportMapData{13};

                app.checkSigMapData = app.exportMapData{14};
                app.checkSigLB = app.exportMapData{7};
                app.checkSigUB = app.exportMapData{8};
                app.checkSigMapData( app.checkSigMapData < app.checkSigLB  |  app.checkSigMapData > app.checkSigUB ) = NaN;

                app.DTauMapPlot = plotMap(app, app.DTauMapPlot, app.checkSigMapData, app.checkSigLB, app.checkSigUB);
                app.DTauColorBarPlot = plotMapColorBar(app, app.DTauColorBarPlot, app.checkSigMapData, app.checkSigLB, app.checkSigUB);
                app.DTauHistPlot = plotMapHist(app, app.DTauHistPlot, app.checkSigMapData, app.checkSigLB, app.checkSigUB);
            end

            % (5) Plot initial marker
            app.checkSigMarkerXY = [ round(0.5*app.cmosDataRowNum), round(0.5*app.cmosDataColNum) ];
            app.CheckSigX_scrn.Value = app.checkSigMarkerXY(1,1);
            app.CheckSigY_scrn.Value = app.checkSigMarkerXY(1,2);

            CheckSigXY_scrnValueChanged(app)

            close(progressBar)

        end

        % Value changed function: PixelListBox
        function PixelListBoxValueChanged(app, event)

            app.CheckSigX_scrn.Value = app.exportPixelX( str2double(app.PixelListBox.Value) );
            app.CheckSigY_scrn.Value = app.exportPixelY( str2double(app.PixelListBox.Value) );

            CheckSigXY_scrnValueChanged(app, event)

        end

        % Image clicked function: Image_AddIntoPixel
        function Image_AddIntoPixelClicked(app, event)

            showImageIconAct(app, app.Image_AddIntoPixel)

            app.exportPixelX(app.pixelSaveToID) = app.CheckSigX_scrn.Value;
            app.exportPixelY(app.pixelSaveToID) = app.CheckSigY_scrn.Value;

            app.PixelListBox.Value = {};
            updatePixelListBox(app)

            app.pixelSaveToID = app.pixelSaveToID + 1;

        end

        % Image clicked function: Image_DeletefromPixel
        function Image_DeletefromPixelClicked(app, event)

            showImageIconAct(app, app.Image_DeletefromPixel)

            if isempty( app.PixelListBox.Value )

                msg = 'Choose one pixel from the "export list" to delete';
                uialert(app.OMZer_Ca_ExportDisplayPixel_UIFigure, msg,'Warning','Icon','error')

            else

                app.exportPixelX( str2double(app.PixelListBox.Value) ) = [];
                app.exportPixelY( str2double(app.PixelListBox.Value) ) = [];

                app.PixelListBox.Value = {};
                updatePixelListBox(app)

                app.pixelSaveToID = app.pixelSaveToID - 1;

                app.checkSigMarkerXY = [ round(0.5*app.cmosDataRowNum), round(0.5*app.cmosDataColNum) ];
                app.CheckSigX_scrn.Value = app.checkSigMarkerXY(1,1);
                app.CheckSigY_scrn.Value = app.checkSigMarkerXY(1,2);

                CheckSigXY_scrnValueChanged(app, event)
            end

        end

        % Image clicked function: Image_Export
        function Image_ExportClicked(app, event)

            showImageIconAct(app, app.Image_Export)

            if isempty( app.exportPixelX )

                msg = 'There is no pixel to be exported';
                uialert(app.OMZer_Ca_ExportDisplayPixel_UIFigure, msg,'Warning','Icon','error')

            else

                % Get valid saving directory and file name
                saveDir = uigetdir;

                if saveDir == 0 % User has NOT selected file directory.

                    msg = 'Please select a valid saving directory';
                    uialert(app.OMZer_Ca_ExportDisplayPixel_UIFigure,msg,'Warning','Icon','error')

                else

                    prompt = {'Enter filename to save (without extension)...   Please do not use \/:*?"<>|'};
                    dlgtitle = 'Save to a .mat file';
                    dfinput = { [ app.fileName, '_Ca_', app.exportMapName, '_Pixel_Data' ] };
                    dims = [1,100];
                    filename = inputdlg(prompt,dlgtitle,dims,dfinput);

                    if isempty(filename) % User has NOT entered a name

                        msg = 'Please enter a valid file name';
                        uialert(app.OMZer_Ca_ExportDisplayPixel_UIFigure,msg,'Warning','Icon','error')

                    else

                        mapPlotTitle = app.MapTitleLabel.Text;

                        acquisitionFrequency_Hz_Unit = app.acqFreq;

                        pixelTime_sec_Unit = app.checkSigDataSectionTime;
                        if isrow(pixelTime_sec_Unit)
                            pixelTime_sec_Unit = pixelTime_sec_Unit';
                        end

                        pixelX = app.exportPixelX;
                        if isrow(pixelX)
                            pixelX = pixelX';
                        end

                        pixelY = app.exportPixelY;
                        if isrow(pixelY)
                            pixelY = pixelY';
                        end

                        pixelSignal = NaN( length(app.checkSigDataSectionTime), length(app.exportPixelX) );
                        for ID = 1 : length(app.exportPixelX)

                            rowID = app.exportPixelY(ID);
                            colID = app.exportPixelX(ID);
                            sig = squeeze( app.checkSigDataSection( rowID, colID, : ) );

                            if isrow(sig)
                                sig = sig';
                            end
                            pixelSignal(:,ID) = sig; % Each column is a pixel
                        end
                        
                        backgroundImage = app.bgImageNomGroup{app.cameraSource};

                        msg = [ 'Export the ', app.exportMapName, ' Pixel Data' ];
                        progressBar = uiprogressdlg(app.OMZer_Ca_ExportDisplayPixel_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');

                        fullSaveName = strcat(saveDir, '/', filename, '.mat'); % At this moment, 'fullSaveName' is a 'cell'.
                        fullSaveName = fullSaveName{1}; % Now 'fullSaveName' is a 'character
                        save(fullSaveName, ...
                            'mapPlotTitle', ...
                            'acquisitionFrequency_Hz_Unit', ...
                            'pixelTime_sec_Unit', ...
                            'pixelX', ...
                            'pixelY', ...
                            'pixelSignal', ...
                            'backgroundImage');

                        if isequal( app.exportMapName, app.mainApp.ActLabel.Text ) % Act map

                            locsAct = NaN( length(app.exportPixelX), 1 );
                            for ID = 1 : length(app.exportPixelX)

                                rowID = app.exportPixelY(ID);
                                colID = app.exportPixelX(ID);
                                locsAct(ID,1) = app.exportMapData{9}( rowID, colID );
                            end

                            LB = app.exportMapData{5};
                            UB = app.exportMapData{6};
                            actMap = app.exportMapData{10};
                            actMap( actMap < LB  |  actMap > UB ) = NaN;
                            actMap = actMap - nanmin( actMap(:) );

                            mapUserComment = app.exportMapData{12};

                            save(fullSaveName, ...
                                'locsAct', ...
                                'actMap', ...
                                'mapUserComment', ...
                                '-append');

                        elseif isequal( app.exportMapName, app.mainApp.RepLabel.Text ) % Rep map

                            locsRep = NaN( length(app.exportPixelX), 1 );
                            for ID = 1 : length(app.exportPixelX)

                                rowID = app.exportPixelY(ID);
                                colID = app.exportPixelX(ID);
                                locsRep(ID,1) = app.exportMapData{10}( rowID, colID );
                            end

                            LB = app.exportMapData{6};
                            UB = app.exportMapData{7};
                            repMap = app.exportMapData{11};
                            repMap( repMap < LB  |  repMap > UB ) = NaN;
                            repMap = repMap - nanmin( repMap(:) );

                            mapUserComment = app.exportMapData{13};

                            save(fullSaveName, ...
                                'locsRep', ...
                                'repMap', ...
                                'mapUserComment', ...
                                '-append');

                        elseif isequal( app.exportMapName, app.mainApp.CaTDLabel.Text ) % CaTD map

                            locsAct = NaN( length(app.exportPixelX), 1 );
                            locsRep = NaN( length(app.exportPixelX), 1 );
                            CaTD_ms_Unit = NaN( length(app.exportPixelX), 1 );
                            for ID = 1 : length(app.exportPixelX)

                                rowID = app.exportPixelY(ID);
                                colID = app.exportPixelX(ID);
                                locsAct(ID,1) = app.exportMapData{10}( rowID, colID );
                                locsRep(ID,1) = app.exportMapData{11}( rowID, colID );
                                CaTD_ms_Unit(ID, 1) = app.exportMapData{12}( rowID, colID );
                            end

                            LB = app.exportMapData{6};
                            UB = app.exportMapData{7};
                            CaTDMap = app.exportMapData{12};
                            CaTDMap( CaTDMap < LB  |  CaTDMap > UB ) = NaN;

                            mapUserComment = app.exportMapData{14};

                            save(fullSaveName, ...
                                'locsAct', ...
                                'locsRep', ...
                                'CaTD_ms_Unit', ...
                                'CaTDMap', ...
                                'mapUserComment', ...
                                '-append');

                        elseif isequal( app.exportMapName, app.mainApp.RTLabel.Text ) % RT map

                            locsStart = NaN( length(app.exportPixelX), 1 );
                            locsEnd = NaN( length(app.exportPixelX), 1 );
                            RiseTime_ms_Unit = NaN( length(app.exportPixelX), 1 );
                            for ID = 1 : length(app.exportPixelX)

                                rowID = app.exportPixelY(ID);
                                colID = app.exportPixelX(ID);
                                locsStart(ID,1) = app.exportMapData{11}( rowID, colID );
                                locsEnd(ID,1) = app.exportMapData{12}( rowID, colID );
                                RiseTime_ms_Unit(ID,1) = app.exportMapData{13}( rowID, colID );
                            end

                            LB = app.exportMapData{7};
                            UB = app.exportMapData{8};
                            RTMap = app.exportMapData{13};
                            RTMap( RTMap < LB  |  RTMap > UB ) = NaN;

                            mapUserComment = app.exportMapData{15};

                            save(fullSaveName, ...
                                'locsStart', ...
                                'locsEnd', ...
                                'RiseTime_ms_Unit', ...
                                'RTMap', ...
                                'mapUserComment', ...
                                '-append');

                        elseif isequal( app.exportMapName, app.mainApp.DTLabel.Text ) % DT map

                            locsStart = NaN( length(app.exportPixelX), 1 );
                            locsEnd = NaN( length(app.exportPixelX), 1 );
                            DecayTime_ms_Unit = NaN( length(app.exportPixelX), 1 );
                            for ID = 1 : length(app.exportPixelX)

                                rowID = app.exportPixelY(ID);
                                colID = app.exportPixelX(ID);
                                locsStart(ID,1) = app.exportMapData{11}( rowID, colID );
                                locsEnd(ID,1) = app.exportMapData{12}( rowID, colID );
                                DecayTime_ms_Unit(ID,1) = app.exportMapData{13}( rowID, colID );
                            end

                            LB = app.exportMapData{7};
                            UB = app.exportMapData{8};
                            DTMap = app.exportMapData{13};
                            DTMap( DTMap < LB  |  DTMap > UB ) = NaN;

                            mapUserComment = app.exportMapData{15};

                            save(fullSaveName, ...
                                'locsStart', ...
                                'locsEnd', ...
                                'DecayTime_ms_Unit', ...
                                'DTMap', ...
                                'mapUserComment', ...
                                '-append');

                        elseif isequal( app.exportMapName, app.mainApp.DTauLabel.Text ) % DTau map

                            pixelFitData = NaN( length(app.checkSigDataSectionTime), length(app.exportPixelX) );
                            for ID = 1 : length(app.exportPixelX)

                                rowID = app.exportPixelY(ID);
                                colID = app.exportPixelX(ID);
                                sig = squeeze( app.checkSigDTauFitDataSection( rowID, colID, : ) );

                                if isrow(sig)
                                    sig = sig';
                                end
                                pixelFitData(:,ID) = sig; % Each column is a pixel
                            end

                            locsStart = NaN( length(app.exportPixelX), 1 );
                            locsEnd = NaN( length(app.exportPixelX), 1 );
                            DecayTimeTau_ms_Unit = NaN( length(app.exportPixelX), 1 );
                            for ID = 1 : length(app.exportPixelX)

                                rowID = app.exportPixelY(ID);
                                colID = app.exportPixelX(ID);
                                locsStart(ID,1) = app.exportMapData{12}( rowID, colID );
                                locsEnd(ID,1) = app.exportMapData{13}( rowID, colID );
                                DecayTimeTau_ms_Unit(ID,1) = app.exportMapData{14}( rowID, colID );
                            end

                            LB = app.exportMapData{7};
                            UB = app.exportMapData{8};
                            DTauMap = app.exportMapData{14};
                            DTauMap( DTauMap < LB  |  DTauMap > UB ) = NaN;

                            mapUserComment = app.exportMapData{16};

                            save(fullSaveName, ...
                                'pixelFitData', ...
                                'locsStart', ...
                                'locsEnd', ...
                                'DecayTimeTau_ms_Unit', ...
                                'DTauMap', ...
                                'mapUserComment', ...
                                '-append');
                        end

                        close(progressBar)
                    end
                end
            end

            figure(app.OMZer_Ca_ExportDisplayPixel_UIFigure)

        end

        % Value changed function: CheckSigX_scrn, CheckSigY_scrn
        function CheckSigXY_scrnValueChanged(app, event)

            app.checkSigMarkerXY(1,1) = app.CheckSigX_scrn.Value;
            app.checkSigMarkerXY(1,2) = app.CheckSigY_scrn.Value;

            checkSigColor = [0.00, 0.45, 0.74];

            % (1.1) Marker plot
            delete(app.checkSigMarkerPlot)
            app.checkSigMarkerPlot = updateCheckSigMarker(app, checkSigColor);

            % (1.2) Marker Highlight Plot
            delete(app.checkSigMarkerHighlightPlot)
            app.checkSigMarkerHighlightPlot = updateCheckSigMarkerHighlight(app);

            % (2) Update the signal
            if isequal( app.exportMapName, app.mainApp.ActLabel.Text ) % Act map

                delete(app.checkSigPlot)
                app.checkSigPlot = updateCheckSig(app, app.checkSigDataSectionTime, app.checkSigDataSection, checkSigColor);

                delete(app.checkSigActPointPlot)
                app.checkSigActPointPlot = updateCheckSigActPoints(app, app.checkSigDataSectionTime, app.checkSigDataSection);

                delete(app.checkSigHistIndicatingLinePlot)
                app.checkSigHistIndicatingLinePlot = updatecheckSigHistIndicatingLine(app, app.checkSigMapData);

            elseif isequal( app.exportMapName, app.mainApp.RepLabel.Text ) % Rep map

                delete(app.checkSigPlot)
                app.checkSigPlot = updateCheckSig(app, app.checkSigDataSectionTime, app.checkSigDataSection, checkSigColor);

                delete(app.checkSigRepPointPlot)
                app.checkSigRepPointPlot = updateCheckSigRepPoints(app, app.checkSigDataSectionTime, app.checkSigDataSection);

                delete(app.checkSigHistIndicatingLinePlot)
                app.checkSigHistIndicatingLinePlot = updatecheckSigHistIndicatingLine(app, app.checkSigMapData);

            elseif isequal( app.exportMapName, app.mainApp.CaTDLabel.Text ) % CaTD map

                delete(app.checkSigPlot)
                app.checkSigPlot = updateCheckSig(app, app.checkSigDataSectionTime, app.checkSigDataSection, checkSigColor);

                delete(app.checkSigActPointPlot)
                app.checkSigActPointPlot = updateCheckSigActPoints(app, app.checkSigDataSectionTime, app.checkSigDataSection);

                delete(app.checkSigRepPointPlot)
                app.checkSigRepPointPlot = updateCheckSigRepPoints(app, app.checkSigDataSectionTime, app.checkSigDataSection);

                delete(app.checkSigHistIndicatingLinePlot)
                app.checkSigHistIndicatingLinePlot = updatecheckSigHistIndicatingLine(app, app.checkSigMapData);

            elseif isequal( app.exportMapName, app.mainApp.RTLabel.Text ) % RT map

                delete(app.checkSigPlot)
                app.checkSigPlot = updateCheckSig(app, app.checkSigDataSectionTime, app.checkSigDataSection, checkSigColor);

                delete(app.checkSigStartPointPlot)
                app.checkSigStartPointPlot = updateCheckSigStartPoints(app, app.checkSigDataSectionTime, app.checkSigDataSection);

                delete(app.checkSigEndPointPlot)
                app.checkSigEndPointPlot = updateCheckSigEndPoints(app, app.checkSigDataSectionTime, app.checkSigDataSection);

                delete(app.checkSigHistIndicatingLinePlot)
                app.checkSigHistIndicatingLinePlot = updatecheckSigHistIndicatingLine(app, app.checkSigMapData);

            elseif isequal( app.exportMapName, app.mainApp.DTLabel.Text ) % DT map

                delete(app.checkSigPlot)
                app.checkSigPlot = updateCheckSig(app, app.checkSigDataSectionTime, app.checkSigDataSection, checkSigColor);

                delete(app.checkSigStartPointPlot)
                app.checkSigStartPointPlot = updateCheckSigStartPoints(app, app.checkSigDataSectionTime, app.checkSigDataSection);

                delete(app.checkSigEndPointPlot)
                app.checkSigEndPointPlot = updateCheckSigEndPoints(app, app.checkSigDataSectionTime, app.checkSigDataSection);

                delete(app.checkSigHistIndicatingLinePlot)
                app.checkSigHistIndicatingLinePlot = updatecheckSigHistIndicatingLine(app, app.checkSigMapData);

            elseif isequal( app.exportMapName, app.mainApp.DTauLabel.Text ) % DTau map

                delete(app.checkSigPlot)
                app.checkSigPlot = updateCheckSig(app, app.checkSigDataSectionTime, app.checkSigDataSection, checkSigColor);

                delete(app.checkSigDTauFitPlot)
                app.checkSigDTauFitPlot = updateCheckSigDTauFit(app, app.checkSigDataSectionTime, app.checkSigDTauFitDataSection, [1, 0, 0]);

                delete(app.checkSigStartPointPlot)
                app.checkSigStartPointPlot = updateCheckSigStartPoints(app, app.checkSigDataSectionTime, app.checkSigDataSection);

                delete(app.checkSigEndPointPlot)
                app.checkSigEndPointPlot = updateCheckSigEndPoints(app, app.checkSigDataSectionTime, app.checkSigDataSection);

                delete(app.checkSigHistIndicatingLinePlot)
                app.checkSigHistIndicatingLinePlot = updatecheckSigHistIndicatingLine(app, app.checkSigMapData);
            end

        end

        % Window button down function: 
        % OMZer_Ca_ExportDisplayPixel_UIFigure
        function OMZer_Ca_ExportDisplayPixel_UIFigureWindowButtonDown(app, event)

            app.mouseMotion = app.OMZer_Ca_ExportDisplayPixel_UIFigure.SelectionType; % The result can be 'normal', 'extend', 'alt', 'open'

            if isequal(app.mouseMotion,'normal') % 'normal' means single-click any mouse button.

                app.mouseXY = app.OMZer_Ca_ExportDisplayPixel_UIFigure.CurrentPoint; % Returns (x, y) coordinates of the mouse pointer, measured from the lower-left corner of the figure.
                app.mapAxisLocs = app.MapAxis.InnerPosition; % Returns a 1-by-4 row vector -  [left bottom width height].

                if ( app.mouseXY(1,1) >= app.mapAxisLocs(1,1) )  ...
                        &&  ( app.mouseXY(1,1) <= app.mapAxisLocs(1,1) + app.mapAxisLocs(1,3) )  ...
                        &&  ( app.mouseXY(1,2) >= app.mapAxisLocs(1,2) )  ...
                        &&  ( app.mouseXY(1,2) <= app.mapAxisLocs(1,2) + app.mapAxisLocs(1,4) )

                    updateMouseXY(app, app.mapAxisLocs);

                    if ( abs(app.mouseXY(1,1) - app.checkSigMarkerXY(1,1)) <= 5 )  &&  ( abs(app.mouseXY(1,2) - app.checkSigMarkerXY(1,2)) <= 5 ) % Find which marker is within 5 pixels of the mouse location

                        app.checkSigMouseDragID = 1;
                    end
                end
            end

        end

        % Window button motion function: 
        % OMZer_Ca_ExportDisplayPixel_UIFigure
        function OMZer_Ca_ExportDisplayPixel_UIFigureWindowButtonMotion(app, event)

            if ~isempty(app.checkSigMouseDragID)

                app.mouseXY = app.OMZer_Ca_ExportDisplayPixel_UIFigure.CurrentPoint; % Returns (x, y) coordinates of the mouse pointer, measured from the lower-left corner of the figure.
                app.mapAxisLocs = app.MapAxis.InnerPosition; % Returns a 1-by-4 row vector -  [left bottom width height].

                if ( app.mouseXY(1,1) >= app.mapAxisLocs(1,1) )  ...
                        &&  ( app.mouseXY(1,1) <= app.mapAxisLocs(1,1) + app.mapAxisLocs(1,3) )  ...
                        &&  ( app.mouseXY(1,2) >= app.mapAxisLocs(1,2) )  ...
                        &&  ( app.mouseXY(1,2) <= app.mapAxisLocs(1,2) + app.mapAxisLocs(1,4) )

                    updateMouseXY(app, app.mapAxisLocs);

                    app.checkSigMarkerXY(1,1) = app.mouseXY(1,1);
                    app.checkSigMarkerXY(1,2) = app.mouseXY(1,2);

                    app.CheckSigX_scrn.Value = app.checkSigMarkerXY(1,1);
                    app.CheckSigY_scrn.Value = app.checkSigMarkerXY(1,2);

                    CheckSigXY_scrnValueChanged(app, event)
                end
            end

        end

        % Window button up function: 
        % OMZer_Ca_ExportDisplayPixel_UIFigure
        function OMZer_Ca_ExportDisplayPixel_UIFigureWindowButtonUp(app, event)

            app.checkSigMouseDragID = [];

        end

        % Close request function: 
        % OMZer_Ca_ExportDisplayPixel_UIFigure
        function OMZer_Ca_ExportDisplayPixel_UIFigureCloseRequest(app, event)

            % (1) Set mainApp at the top level
            figure(app.mainAppUIFigure)

            % (2) Update the mainApp
            app.mainApp.OMZer_Ca_ExportDisplayPixel = [];

            delete(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OMZer_Ca_ExportDisplayPixel_UIFigure = uifigure('Visible', 'off');
            app.OMZer_Ca_ExportDisplayPixel_UIFigure.Name = 'OMZer Ca Export Display Pixel';
            app.OMZer_Ca_ExportDisplayPixel_UIFigure.Icon = 'Icon V.png';
            app.OMZer_Ca_ExportDisplayPixel_UIFigure.CloseRequestFcn = createCallbackFcn(app, @OMZer_Ca_ExportDisplayPixel_UIFigureCloseRequest, true);
            app.OMZer_Ca_ExportDisplayPixel_UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @OMZer_Ca_ExportDisplayPixel_UIFigureWindowButtonDown, true);
            app.OMZer_Ca_ExportDisplayPixel_UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @OMZer_Ca_ExportDisplayPixel_UIFigureWindowButtonUp, true);
            app.OMZer_Ca_ExportDisplayPixel_UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @OMZer_Ca_ExportDisplayPixel_UIFigureWindowButtonMotion, true);
            app.checkSigAxis = uiaxes(app.OMZer_Ca_ExportDisplayPixel_UIFigure);
            xlabel(app.checkSigAxis, 'Time (sec)')
            app.colorBarAxes = uiaxes(app.OMZer_Ca_ExportDisplayPixel_UIFigure);
            title(app.colorBarAxes, 'ms')
            app.colorBarAxes.XTick = [];
            app.MapAxis = uiaxes(app.OMZer_Ca_ExportDisplayPixel_UIFigure);
            app.MapAxis.XLim = [1 100];
            app.MapAxis.YLim = [1 100];
            app.MapAxis.YDir = 'reverse';
            app.MapAxis.XTick = [];
            app.MapAxis.YTick = [];
            app.MapAxis.ZTick = [];
            app.MapHistAxis = uiaxes(app.OMZer_Ca_ExportDisplayPixel_UIFigure);
            xlabel(app.MapHistAxis, 'Time (ms)')
            ylabel(app.MapHistAxis, 'Counts')
            app.MapHistAxis.ZTick = [];
            app.CheckSigXYLabel = uilabel(app.OMZer_Ca_ExportDisplayPixel_UIFigure);
            app.CheckSigXYLabel.Tooltip = {'X (red) and Y (blue) coordinate of the marker. Please be noted that X is column and Y is row to locate an element in a matrix in MATLAB.'};
            app.CheckSigXYLabel.Text = 'X,Y';
            app.CheckSigY_scrn = uieditfield(app.OMZer_Ca_ExportDisplayPixel_UIFigure, 'numeric');
            app.CheckSigY_scrn.Limits = [0 100];
            app.CheckSigY_scrn.ValueDisplayFormat = '%.0f';
            app.CheckSigY_scrn.ValueChangedFcn = createCallbackFcn(app, @CheckSigXY_scrnValueChanged, true);
            app.CheckSigY_scrn.Tooltip = {'Y coordinate of the 1st marker (Note: row in a matlab matrix)'};
            app.CheckSigX_scrn = uieditfield(app.OMZer_Ca_ExportDisplayPixel_UIFigure, 'numeric');
            app.CheckSigX_scrn.Limits = [0 100];
            app.CheckSigX_scrn.ValueDisplayFormat = '%.0f';
            app.CheckSigX_scrn.ValueChangedFcn = createCallbackFcn(app, @CheckSigXY_scrnValueChanged, true);
            app.CheckSigX_scrn.Tooltip = {'X coordinate of the 1st marker (Note: column in a matlab matrix)'};
            app.PixelListBoxLabel = uilabel(app.OMZer_Ca_ExportDisplayPixel_UIFigure);
            app.PixelListBoxLabel.Text = 'Pixel';
            app.PixelListBox = uilistbox(app.OMZer_Ca_ExportDisplayPixel_UIFigure);
            app.PixelListBox.Items = {};
            app.PixelListBox.ValueChangedFcn = createCallbackFcn(app, @PixelListBoxValueChanged, true);
            app.PixelListBox.Value = {};
            app.Image_DeletefromPixel = uiimage(app.OMZer_Ca_ExportDisplayPixel_UIFigure);
            app.Image_DeletefromPixel.ImageClickedFcn = createCallbackFcn(app, @Image_DeletefromPixelClicked, true);
            app.Image_DeletefromPixel.ImageSource = 'Icon Delete.png';
            app.DeletefromPixelLabel = uilabel(app.OMZer_Ca_ExportDisplayPixel_UIFigure);
            app.DeletefromPixelLabel.Text = 'Delete from Pixel';
            app.Image_AddIntoPixel = uiimage(app.OMZer_Ca_ExportDisplayPixel_UIFigure);
            app.Image_AddIntoPixel.ImageClickedFcn = createCallbackFcn(app, @Image_AddIntoPixelClicked, true);
            app.Image_AddIntoPixel.ImageSource = 'Icon Plus.png';
            app.AddIntoPixelLabel = uilabel(app.OMZer_Ca_ExportDisplayPixel_UIFigure);
            app.AddIntoPixelLabel.Text = 'Add Into Pixel';
            app.Image_Export = uiimage(app.OMZer_Ca_ExportDisplayPixel_UIFigure);
            app.Image_Export.ImageClickedFcn = createCallbackFcn(app, @Image_ExportClicked, true);
            app.Image_Export.ImageSource = 'Icon Export.png';
            app.ExportLabel = uilabel(app.OMZer_Ca_ExportDisplayPixel_UIFigure);
            app.ExportLabel.Text = 'Export';
            app.MapTitleLabel = uilabel(app.OMZer_Ca_ExportDisplayPixel_UIFigure);
            app.MapTitleLabel.Text = 'Map Title';
            % Show the figure after all components are created
            app.OMZer_Ca_ExportDisplayPixel_UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OMZer_Ca_ExportDisplayPixel(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.OMZer_Ca_ExportDisplayPixel_UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.OMZer_Ca_ExportDisplayPixel_UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OMZer_Ca_ExportDisplayPixel_UIFigure)
        end
    end
end