classdef OMZer_ExportDisplayPixel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OMZer_ExportDisplayPixel_UIFigure  matlab.ui.Figure
        CautionExportsignallengthisthesameasviewlengthLabel  matlab.ui.control.Label
        Camera2SignalRedColorLabel   matlab.ui.control.Label
        Camera1SignalBlueColorLabel  matlab.ui.control.Label
        RestoreViewLabel             matlab.ui.control.Label
        Image_RestoreView            matlab.ui.control.Image
        ViewEndsecSpinner            matlab.ui.control.Spinner
        ViewEndsecSpinnerLabel       matlab.ui.control.Label
        ViewStartsecSpinner          matlab.ui.control.Spinner
        ViewStartsecSpinnerLabel     matlab.ui.control.Label
        CheckSigX_scrn               matlab.ui.control.NumericEditField
        CheckSigY_scrn               matlab.ui.control.NumericEditField
        CheckSigXYLabel              matlab.ui.control.Label
        PixelListBox                 matlab.ui.control.ListBox
        PixelListBoxLabel            matlab.ui.control.Label
        ExportLabel                  matlab.ui.control.Label
        Image_Export                 matlab.ui.control.Image
        AddIntoPixelLabel            matlab.ui.control.Label
        Image_AddIntoPixel           matlab.ui.control.Image
        DeletefromPixelLabel         matlab.ui.control.Label
        Image_DeletefromPixel        matlab.ui.control.Image
        checkSigAxis                 matlab.ui.control.UIAxes
        imageAxis2                   matlab.ui.control.UIAxes
        imageAxis1                   matlab.ui.control.UIAxes
    end


    properties (Access = public)

        % The Parent App --------------------
        mainApp
        mainAppUIFigure


        % The Current App --------------------
        dirName % File directory
        fileName % Filename without path and extension

        setup % User preference
        userAlphaValue

        imageIconActColor = [0.80, 0.80, 0.80]; % The activation color when an image is clicked

        lWidth = 2; % Line width

        activeCamera = 1; % 1 (or 2) - camera 1 (or 2) active

        data
        acqFreq
        pcl % Pacing cycle length
        camTF % 0 (single camera) or 1 (dual camera)
        maskGroup % User mask
        signalTime
        cmosDataGroup
        bgImageNomGroup % Gray colour RGB version of background image
        pacing1 % Pacing signal recorded by channel 1
        pacing2 % Pacing signal recorded by channel 2
        pacingTime % Time points to plot pacing signal

        cmosDataRowNum % The numer of rows in cmosData
        cmosDataColNum % The numer of columns in cmosData

        imageAxisObject
        imageAxis1Locs % The position information about imageAxis1, that is [left bottom width height]
        imageAxis2Locs % The position information about imageAxis2, that is [left bottom width height]

        mouseMotion = []; % The result can be 'normal', 'extend', 'alt', 'open'
        mouseDragID
        mouseXY
        markerXY = []; % This is a 1-by-2 row vector that stores mouse "X" and "Y" coordinate

        markerPlotObject = cell(2,1); % 'Row 1 (or 2)' - camera 1 (or 2)
        markerHighlightPlotObject = cell(2,1); % 'Row 1 (or 2)' - camera 1 (or 2)
        signalPlotObject = cell(2,1); % 'Row 1 (or 2)' - camera 1 (or 2)
        maskPlotObject = cell(2,1);

        ColorID
        Color1 = [0.00,0.45,0.74]; % Blue
        Color2 = [0.64,0.08,0.18]; % Red

        % For export pixel
        pixelSaveToID = 1;
        exportPixelX
        exportPixelY
    end


    methods (Access = public)


        function showImageIconAct(app, imageIconName)

            imageIconName.BackgroundColor = app.imageIconActColor; %[0.80, 0.80, 0.80];
            pause(0.05)
            imageIconName.BackgroundColor = 'none';
        end


        function setColourMap(app,name)

            colormap(app.imageAxis1, name);
            colormap(app.imageAxis2, name);
        end


        function setScrnLimit(app, LB, UB)

            app.ViewStartsecSpinner.Limits = [ LB, UB ];
            app.ViewEndsecSpinner.Limits = [ LB, UB ];

            app.ViewStartsecSpinner.Value = LB;
            app.ViewEndsecSpinner.Value = UB;

            xlim( app.checkSigAxis, [ app.ViewStartsecSpinner.Value, app.ViewEndsecSpinner.Value ] )
        end


        function setAxisLimit(app, xMaxLimit, yMaxLimit) % Set the x-y limit of all axes

            app.imageAxis1.XLim = [1, xMaxLimit];
            app.imageAxis1.YLim = [1, yMaxLimit];

            app.imageAxis2.XLim = [1, xMaxLimit];
            app.imageAxis2.YLim = [1, yMaxLimit];

            app.CheckSigX_scrn.Value = 1;
            app.CheckSigX_scrn.Limits = [1, xMaxLimit];
            app.CheckSigY_scrn.Value = 1;
            app.CheckSigY_scrn.Limits = [1, yMaxLimit];
        end


        function updateMouseXY(app)

            if app.activeCamera == 1 % Camera 1 is now active
                % app.mouseXY(1,1) = app.imageAxis1.CurrentPoint(1,1); % The CurrentPoint property contains the (x,y,z) coordinates of the mouse pointer with respect to the axes
                app.mouseXY(1,1) = ( app.mouseXY(1,1) - app.imageAxis1Locs(1,1) ) * app.cmosDataColNum / app.imageAxis1Locs(1,3);
                app.mouseXY(1,1) = round( app.mouseXY(1,1) );
                if app.mouseXY(1,1) < 1
                    app.mouseXY(1,1) = 1; % As a result, there won't appear (0,y) element requirement in a 100x100 matrix
                end
                if app.mouseXY(1,1) > app.cmosDataColNum
                    app.mouseXY(1,1) = app.cmosDataColNum;
                end

                % app.mouseXY(1,2) = app.imageAxis1.CurrentPoint(1,2); % The CurrentPoint property contains the (x,y,z) coordinates of the mouse pointer with respect to the axes
                app.mouseXY(1,2) = ( (app.imageAxis1Locs(1,2) + app.imageAxis1Locs(1,4)) - app.mouseXY(1,2) ) * app.cmosDataRowNum / app.imageAxis1Locs(1,4);
                app.mouseXY(1,2) = round( app.mouseXY(1,2) ); % Transfer relative mouse location inside imageAxis1 to absolute location inside a 100x100 matrix
                if app.mouseXY(1,2) < 1
                    app.mouseXY(1,2) = 1; % As a result, there won't appear (x,0) element requirement in a 100x100 matrix
                end
                if app.mouseXY(1,2) > app.cmosDataRowNum
                    app.mouseXY(1,2) = app.cmosDataRowNum;
                end


            elseif app.activeCamera == 2 % Camera 2 is now active
                % app.mouseXY(1,1) = app.imageAxis2.CurrentPoint(1,1); % The CurrentPoint property contains the (x,y,z) coordinates of the mouse pointer with respect to the axes
                app.mouseXY(1,1) = ( app.mouseXY(1,1) - app.imageAxis2Locs(1,1) ) * app.cmosDataColNum / app.imageAxis2Locs(1,3);
                app.mouseXY(1,1) = round( app.mouseXY(1,1) );
                if app.mouseXY(1,1) < 1
                    app.mouseXY(1,1) = 1; % As a result, there won't appear (0,y) element requirement in a 100x100 matrix
                end
                if app.mouseXY(1,1) > app.cmosDataColNum
                    app.mouseXY(1,1) = app.cmosDataColNum;
                end

                % app.mouseXY(1,2) = app.imageAxis2.CurrentPoint(1,2); % The CurrentPoint property contains the (x,y,z) coordinates of the mouse pointer with respect to the axes
                app.mouseXY(1,2) = ( (app.imageAxis2Locs(1,2) + app.imageAxis2Locs(1,4)) - app.mouseXY(1,2) ) * app.cmosDataRowNum / app.imageAxis2Locs(1,4);
                app.mouseXY(1,2) = round( app.mouseXY(1,2) ); % Transfer relative mouse location inside imageAxis1 to absolute location inside a 100x100 matrix
                if app.mouseXY(1,2) < 1
                    app.mouseXY(1,2) = 1; % As a result, there won't appear (x,0) element requirement in a 100x100 matrix
                end
                if app.mouseXY(1,2) > app.cmosDataRowNum
                    app.mouseXY(1,2) = app.cmosDataRowNum;
                end
            end
        end


        function updateMarker(app)

            X = app.markerXY( app.mouseDragID, 1 );
            Y = app.markerXY( app.mouseDragID, 2 );

            app.markerPlotObject{ 1, app.mouseDragID }.XData = X;
            app.markerPlotObject{ 1, app.mouseDragID }.YData = Y;

            if app.camTF ~= 0 % Dual camera
                app.markerPlotObject{ 2, app.mouseDragID }.XData = X;
                app.markerPlotObject{ 2, app.mouseDragID }.YData = Y;
            end
        end


        function updateMarkerHighlight(app)

            X = app.markerXY( app.mouseDragID, 1 );
            Y = app.markerXY( app.mouseDragID, 2 );

            app.markerHighlightPlotObject{ 1, app.mouseDragID }.XData = X;
            app.markerHighlightPlotObject{ 1, app.mouseDragID }.YData = Y;


            if app.camTF ~= 0 % Dual camera
                app.markerHighlightPlotObject{ 2, app.mouseDragID }.XData = X;
                app.markerHighlightPlotObject{ 2, app.mouseDragID }.YData = Y;
            end
        end


        function updateSignal(app)

            ID = (app.activeCamera == 1) + 1; % When app.activeCamera is 1 (or 2), ID is 2 (or 1)

            row = app.markerXY( app.mouseDragID, 2 );
            col = app.markerXY( app.mouseDragID, 1 );

            app.signalPlotObject{ app.activeCamera, app.mouseDragID }.YData = squeeze( app.cmosDataGroup{app.activeCamera}(row,col,:) );

            if app.camTF ~= 0 % Dual camera
                app.signalPlotObject{ ID, app.mouseDragID }.YData = squeeze( app.cmosDataGroup{ID}(row,col,:) );
            end
        end


        function updatePixelListBox(app)

            pixelNum = length( app.exportPixelX );

            app.PixelListBox.Items = {};
            if pixelNum ~= 0
                for ID = 1 : pixelNum
                    app.PixelListBox.Items{ID} = num2str(ID);
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, OMZer)

            msg = 'Configuring files';
            progressBar = uiprogressdlg(app.OMZer_ExportDisplayPixel_UIFigure, 'Title','Initialising', 'Message',msg, 'Indeterminate','on');
            drawnow

            % (1) User set up
            app.setup = load([pwd,'/OMZer_Setup']);
            setColourMap(app, app.setup.userColourMap);
            app.userAlphaValue = 1.0; % The default alpha value

            % (2) Create components collection
            app.ColorID = {app.Color1, app.Color2};

            % (3) Get information from the mainApp
            app.mainApp = OMZer;
            app.mainAppUIFigure = app.mainApp.OMZer_UIFigure;

            app.dirName = app.mainApp.dirName;
            app.fileName = app.mainApp.fileName;

            app.data = app.mainApp.data;
            app.acqFreq = app.mainApp.acqFreq;
            app.pcl = app.mainApp.pcl;
            app.camTF = app.mainApp.camTF;
            app.maskGroup = app.mainApp.maskGroup;
            app.signalTime = app.mainApp.signalTime;
            app.cmosDataGroup = app.mainApp.cmosDataGroup;
            app.bgImageNomGroup = app.mainApp.bgImageNomGroup;
            app.pacing1 = app.mainApp.pacing1;
            app.pacing2 = app.mainApp.pacing2;
            app.pacingTime = app.mainApp.pacingTime;

            % (4) Diable Toolbar
            % https://www.mathworks.com/help/matlab/ref/axtoolbar.html?searchHighlight=axtoolbar&s_tid=doc_srchtitle
            try
                disableDefaultInteractivity(app.imageAxis1);
                disableDefaultInteractivity(app.imageAxis2);
                disableDefaultInteractivity(app.checkSigAxis);

                axtoolbar(app.imageAxis1);
                axtoolbar(app.imageAxis2);
                axtoolbar(app.checkSigAxis);

            catch
                % Do nothing
            end

            % (5) Plot background image
            [app.cmosDataRowNum, app.cmosDataColNum, ~] = size( app.cmosDataGroup{1} );
            setAxisLimit(app, app.cmosDataColNum, app.cmosDataRowNum);
            LB = 0;
            UB = app.signalTime(end);
            setScrnLimit(app, LB, UB)
            imagesc( app.imageAxis1, app.bgImageNomGroup{1} );
            if app.camTF ~= 0 % Dual cameras
                imagesc( app.imageAxis2, app.bgImageNomGroup{2} );
            end

            % (6) Plot mask
            if ~isempty( app.maskGroup{1} )

                hold(app.imageAxis1,'on')
                app.maskPlotObject{1} = imagesc( app.imageAxis1, app.maskGroup{1}, 'AlphaData', 0.3 * ~isnan(app.maskGroup{1}) );
                hold(app.imageAxis1,'off')
            end

            if app.camTF ~= 0 % Dual camera
                if ~isempty( app.maskGroup{2} )

                    hold(app.imageAxis2,'on')
                    app.maskPlotObject{2} = imagesc( app.imageAxis2, app.maskGroup{2}, 'AlphaData', 0.3 * ~isnan(app.maskGroup{2}) );
                    hold(app.imageAxis2,'off')
                end
            end

            % (7) Plot initial marker and signal
            app.markerXY = [ round(0.5*app.cmosDataRowNum), round(0.5*app.cmosDataColNum) ];
            app.CheckSigX_scrn.Value = app.markerXY(1,1);
            app.CheckSigY_scrn.Value = app.markerXY(1,2);

            X = app.markerXY(1,1);
            Y = app.markerXY(1,2);

            row = app.markerXY( 1, 2 );
            col = app.markerXY( 1, 1 );

            % Camera 1 marker
            app.markerPlotObject{ 1, 1 } = line( app.imageAxis1, X, Y, 'Marker','square' );
            app.markerPlotObject{ 1, 1 }.MarkerSize = 10;
            app.markerPlotObject{ 1, 1 }.MarkerEdgeColor = 'White';
            app.markerPlotObject{ 1, 1 }.MarkerFaceColor = app.ColorID{1};

            % Camera 1 marker highlight
            app.markerHighlightPlotObject{ 1, 1 } = line( app.imageAxis1, X, Y, 'Marker','o' );
            app.markerHighlightPlotObject{ 1, 1 }.LineWidth = 4;
            app.markerHighlightPlotObject{ 1, 1 }.MarkerSize = 30;
            app.markerHighlightPlotObject{ 1, 1 }.MarkerEdgeColor = 'White';
            app.markerHighlightPlotObject{ 1, 1 }.MarkerFaceColor = 'none';

            % Camera 1 signal
            sig = squeeze( app.cmosDataGroup{1}(row,col,:) );
            app.signalPlotObject{ 1, 1 } = line( app.checkSigAxis, app.signalTime, sig );
            app.signalPlotObject{ 1, 1 }.LineWidth = app.lWidth;
            app.signalPlotObject{ 1, 1 }.Color = app.ColorID{1};

            if app.camTF ~= 0 % Dual camera

                % Camera 2 marker
                app.markerPlotObject{ 2, 1 } = line( app.imageAxis2, X, Y, 'Marker','square' );
                app.markerPlotObject{ 2, 1 }.MarkerSize = 10;
                app.markerPlotObject{ 2, 1 }.MarkerEdgeColor = 'White';
                app.markerPlotObject{ 2, 1 }.MarkerFaceColor = app.ColorID{2};

                % Camera 2 marker highlight
                app.markerHighlightPlotObject{ 2, 1 } = line( app.imageAxis2, X, Y, 'Marker','o' );
                app.markerHighlightPlotObject{ 2, 1 }.LineWidth = 4;
                app.markerHighlightPlotObject{ 2, 1 }.MarkerSize = 30;
                app.markerHighlightPlotObject{ 2, 1 }.MarkerEdgeColor = 'White';
                app.markerHighlightPlotObject{ 2, 1 }.MarkerFaceColor = 'none';

                % Camera 2 signal
                sig = squeeze( app.cmosDataGroup{2}(row,col,:) );
                app.signalPlotObject{ 2, 1 } = line( app.checkSigAxis, app.signalTime, sig );
                app.signalPlotObject{ 2, 1 }.LineWidth = app.lWidth;
                app.signalPlotObject{ 2, 1 }.Color = app.ColorID{2};
            end

            close (progressBar)

        end

        % Value changed function: PixelListBox
        function PixelListBoxValueChanged(app, event)

            app.CheckSigX_scrn.Value = app.exportPixelX( str2double(app.PixelListBox.Value) );
            app.CheckSigY_scrn.Value = app.exportPixelY( str2double(app.PixelListBox.Value) );

            CheckSigXY_scrnValueChanged(app, event)

        end

        % Image clicked function: Image_AddIntoPixel
        function Image_AddIntoPixelClicked(app, event)

            showImageIconAct(app, app.Image_AddIntoPixel)

            app.exportPixelX(app.pixelSaveToID) = app.CheckSigX_scrn.Value;
            app.exportPixelY(app.pixelSaveToID) = app.CheckSigY_scrn.Value;

            app.PixelListBox.Value = {};
            updatePixelListBox(app)

            app.pixelSaveToID = app.pixelSaveToID + 1;

        end

        % Image clicked function: Image_DeletefromPixel
        function Image_DeletefromPixelClicked(app, event)

            showImageIconAct(app, app.Image_DeletefromPixel)

            if isempty( app.PixelListBox.Value )

                msg = 'Choose one pixel from the "export list" to delete';
                uialert(app.OMZer_ExportDisplayPixel_UIFigure, msg,'Warning','Icon','error')

            else

                app.exportPixelX( str2double(app.PixelListBox.Value) ) = [];
                app.exportPixelY( str2double(app.PixelListBox.Value) ) = [];

                app.PixelListBox.Value = {};
                updatePixelListBox(app)

                app.pixelSaveToID = app.pixelSaveToID - 1;

                app.markerXY = [ round(0.5*app.cmosDataRowNum), round(0.5*app.cmosDataColNum) ];
                app.CheckSigX_scrn.Value = app.markerXY(1,1);
                app.CheckSigY_scrn.Value = app.markerXY(1,2);

                CheckSigXY_scrnValueChanged(app, event)
            end

        end

        % Image clicked function: Image_Export
        function Image_ExportClicked(app, event)

            showImageIconAct(app, app.Image_Export)

            if isempty( app.exportPixelX )

                msg = 'There is no pixel to be exported';
                uialert(app.OMZer_ExportDisplayPixel_UIFigure, msg,'Warning','Icon','error')

            else

                % Get valid saving directory and file name
                saveDir = uigetdir;

                if saveDir == 0 % User has NOT selected file directory.

                    msg = 'Please select a valid saving directory';
                    uialert(app.OMZer_ExportDisplayPixel_UIFigure,msg,'Warning','Icon','error')

                else

                    prompt = {'Enter filename to save (without extension)...   Please do not use \/:*?"<>|'};
                    dlgtitle = 'Save to a .mat file';
                    dfinput = { [ app.fileName, '_Pixel_Data' ] };
                    dims = [1,100];
                    filename = inputdlg(prompt,dlgtitle,dims,dfinput);

                    if isempty(filename) % User has NOT entered a name

                        msg = 'Please enter a valid file name';
                        uialert(app.OMZer_ExportDisplayPixel_UIFigure,msg,'Warning','Icon','error')

                    else

                        acquisitionFrequency_Hz_Unit = app.acqFreq;

                        pixelX = app.exportPixelX;
                        if isrow(pixelX)
                            pixelX = pixelX';
                        end

                        pixelY = app.exportPixelY;
                        if isrow(pixelY)
                            pixelY = pixelY';
                        end

                        viewWindowStartIndex = find( app.signalTime >= app.ViewStartsecSpinner.Value, 1,'first' );
                        viewWindowEndIndex = find( app.signalTime <= app.ViewEndsecSpinner.Value, 1,'last' );

                        pacing1_marker = app.pacing1( viewWindowStartIndex : viewWindowEndIndex );
                        pacing2_marker = app.pacing2( viewWindowStartIndex : viewWindowEndIndex );
                        pacingTime_sec_Unit = app.pacingTime( viewWindowStartIndex : viewWindowEndIndex );
                        pacingTime_sec_Unit = pacingTime_sec_Unit - pacingTime_sec_Unit(1);% Time start at 0
                        if isrow(pacing1_marker)
                            pacing1_marker = pacing1_marker';
                        end
                        if isrow(pacing2_marker)
                            pacing2_marker = pacing2_marker';
                        end
                        if isrow(pacingTime_sec_Unit)
                            pacingTime_sec_Unit = pacingTime_sec_Unit';
                        end
                        
                        pixelTime_sec_Unit = app.signalTime( viewWindowStartIndex : viewWindowEndIndex );
                        pixelTime_sec_Unit = pixelTime_sec_Unit - pixelTime_sec_Unit(1); % Time start at 0
                        if isrow(pixelTime_sec_Unit)
                            pixelTime_sec_Unit = pixelTime_sec_Unit';
                        end

                        pixelSignal_Camera1 = NaN( length(pixelTime_sec_Unit), length(app.exportPixelX) );
                        for ID = 1 : length(app.exportPixelX)

                            rowID = app.exportPixelY(ID);
                            colID = app.exportPixelX(ID);
                            sig = squeeze( app.cmosDataGroup{1}( rowID, colID, viewWindowStartIndex:viewWindowEndIndex ) );
                            sig = rescale(sig);

                            if isrow(sig)
                                sig = sig';
                            end
                            pixelSignal_Camera1(:,ID) = sig; % Each column is a pixel
                        end

                        backgroundImage_Camera1 = app.bgImageNomGroup{1};

                        if ~isempty( app.maskGroup{1} )
                            mask_Camera1 = app.maskGroup{1};
                        else
                            mask_Camera1 = ones( app.cmosDataRowNum, app.cmosDataColNum );
                        end

                        msg = 'Export the Pixel Data';
                        progressBar = uiprogressdlg(app.OMZer_ExportDisplayPixel_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');

                        fullSaveName = strcat(saveDir, '/', filename, '.mat'); % At this moment, 'fullSaveName' is a 'cell'.
                        fullSaveName = fullSaveName{1}; % Now 'fullSaveName' is a 'character
                        save(fullSaveName, ...
                            'acquisitionFrequency_Hz_Unit', ...
                            'pacing1_marker', ...
                            'pacing2_marker', ...
                            'pacingTime_sec_Unit', ...
                            'pixelTime_sec_Unit', ...
                            'pixelX', ...
                            'pixelY', ...
                            'pixelSignal_Camera1', ...
                            'backgroundImage_Camera1', ...
                            'mask_Camera1');

                        if app.camTF ~= 0 % Dual camera

                            pixelSignal_Camera2 = NaN( length(pixelTime_sec_Unit), length(app.exportPixelX) );
                            for ID = 1 : length(app.exportPixelX)

                                rowID = app.exportPixelY(ID);
                                colID = app.exportPixelX(ID);
                                sig = squeeze( app.cmosDataGroup{2}( rowID, colID, viewWindowStartIndex:viewWindowEndIndex ) );
                                sig = rescale(sig);

                                if isrow(sig)
                                    sig = sig';
                                end
                                pixelSignal_Camera2(:,ID) = sig; % Each column is a pixel
                            end

                            backgroundImage_Camera2 = app.bgImageNomGroup{2};

                            if ~isempty( app.maskGroup{2} )
                                mask_Camera2 = app.maskGroup{2};
                            else
                                mask_Camera2 = ones( app.cmosDataRowNum, app.cmosDataColNum );
                            end

                            save(fullSaveName, ...
                                'pixelSignal_Camera2', ...
                                'backgroundImage_Camera2', ...
                                'mask_Camera2', ...
                                '-append');
                        end

                        close(progressBar)
                    end
                end
            end

            figure(app.OMZer_ExportDisplayPixel_UIFigure)

        end

        % Value changed function: ViewStartsecSpinner
        function ViewStartsecSpinnerValueChanged(app, event)

            if app.ViewStartsecSpinner.Value >= app.ViewEndsecSpinner.Value

                msg = 'Start time must be eariler than end time';
                uialert(app.OMZer_ExportDisplayPixel_UIFigure,msg,'Warning','Icon','error')

                app.ViewStartsecSpinner.Value = 0;
                app.ViewEndsecSpinner.Value = app.signalTime(end);
            end

            xlim( app.checkSigAxis, [ app.ViewStartsecSpinner.Value, app.ViewEndsecSpinner.Value ] )

        end

        % Value changing function: ViewStartsecSpinner
        function ViewStartsecSpinnerValueChanging(app, event)

            if ischar(event.Value)
                app.ViewStartsecSpinner.Value = str2double(event.Value);
            else
                app.ViewStartsecSpinner.Value = event.Value;
            end

            ViewStartsecSpinnerValueChanged(app, event)

        end

        % Value changed function: ViewEndsecSpinner
        function ViewEndsecSpinnerValueChanged(app, event)

            if app.ViewStartsecSpinner.Value >= app.ViewEndsecSpinner.Value

                msg = 'Start time must be eariler than end time';
                uialert(app.OMZer_ExportDisplayPixel_UIFigure,msg,'Warning','Icon','error')

                app.ViewStartsecSpinner.Value = 0;
                app.ViewEndsecSpinner.Value = app.signalTime(end);
            end

            xlim( app.checkSigAxis, [ app.ViewStartsecSpinner.Value, app.ViewEndsecSpinner.Value ] )

        end

        % Value changing function: ViewEndsecSpinner
        function ViewEndsecSpinnerValueChanging(app, event)

            if ischar(event.Value)
                app.ViewEndsecSpinner.Value = str2double(event.Value);
            else
                app.ViewEndsecSpinner.Value = event.Value;
            end

            ViewEndsecSpinnerValueChanged(app, event)

        end

        % Image clicked function: Image_RestoreView
        function Image_RestoreViewClicked(app, event)

            showImageIconAct(app, app.Image_RestoreView)

            app.ViewStartsecSpinner.Value = 0;
            app.ViewEndsecSpinner.Value = app.signalTime(end);

            xlim( app.checkSigAxis, [ app.ViewStartsecSpinner.Value, app.ViewEndsecSpinner.Value ] )

        end

        % Value changed function: CheckSigX_scrn, CheckSigY_scrn
        function CheckSigXY_scrnValueChanged(app, event)

            xValue = app.CheckSigX_scrn.Value;
            yValue = app.CheckSigY_scrn.Value;

            app.mouseDragID = 1;

            app.markerXY(app.mouseDragID,1:2) = [ xValue, yValue ]; % CAUTION: X is the column in matlab matrix. Y is the row in matlab matrix.

            updateMarker(app)
            updateMarkerHighlight(app)
            updateSignal(app)

            app.mouseDragID = [];

        end

        % Window button down function: 
        % OMZer_ExportDisplayPixel_UIFigure
        function OMZer_ExportDisplayPixel_UIFigureWindowButtonDown(app, event)

            app.imageAxis1Locs = app.imageAxis1.InnerPosition; % "app.imageAxis1Locs" = [left bottom width height].
            app.imageAxis2Locs = app.imageAxis2.InnerPosition;

            app.mouseXY = app.OMZer_ExportDisplayPixel_UIFigure.CurrentPoint; % Get the xy coordinate of mouse cursor in terms of the uifigure
            app.mouseMotion = app.OMZer_ExportDisplayPixel_UIFigure.SelectionType; % The result can be 'normal', 'extend', 'alt', 'open'


            %% Mouse inside imageAxis1
            if ( app.mouseXY(1,1) >= app.imageAxis1Locs(1,1) ) ...
                    &&  ( app.mouseXY(1,1) <= app.imageAxis1Locs(1,1) + app.imageAxis1Locs(1,3) ) ...
                    &&  ( app.mouseXY(1,2) >= app.imageAxis1Locs(1,2) ) ...
                    &&  ( app.mouseXY(1,2) <= app.imageAxis1Locs(1,2) + app.imageAxis1Locs(1,4) )

                app.activeCamera = 1;
                updateMouseXY(app)

                if isequal(app.mouseMotion,'normal') % Click the left mouse button

                    if abs( app.mouseXY(1,1) - app.markerXY(1,1) ) <= 5  &&  abs( app.mouseXY(1,2) - app.markerXY(1,2) ) <= 5 % Find which marker is within 5 pixels of the mouse location
                        app.mouseDragID = 1; % Find which marker the user is pointing to
                    end
                end

                return
            end


            if app.camTF == 1 % Dual camera

                %% Mouse inside imageAxis2
                if ( app.mouseXY(1,1) >= app.imageAxis2Locs(1,1) ) ...
                        &&  ( app.mouseXY(1,1) <= app.imageAxis2Locs(1,1) + app.imageAxis2Locs(1,3) ) ...
                        &&  ( app.mouseXY(1,2) >= app.imageAxis2Locs(1,2) ) ...
                        &&  ( app.mouseXY(1,2) <= app.imageAxis2Locs(1,2) + app.imageAxis2Locs(1,4) )

                    app.activeCamera = 2;
                    updateMouseXY(app)

                    if isequal(app.mouseMotion,'normal') % Click the left mouse button

                        if abs( app.mouseXY(1,1) - app.markerXY(1,1) ) <= 5  &&  abs( app.mouseXY(1,2) - app.markerXY(1,2) ) <= 5 % Find which marker is within 5 pixels of the mouse location
                            app.mouseDragID = 1; % Find which marker the user is pointing to
                        end
                    end

                    return
                end
            end

        end

        % Window button motion function: 
        % OMZer_ExportDisplayPixel_UIFigure
        function OMZer_ExportDisplayPixel_UIFigureWindowButtonMotion(app, event)

            if ~isempty(app.mouseDragID)

                app.imageAxis1Locs = app.imageAxis1.InnerPosition; % "app.imageAxis1Locs" = [left bottom width height].
                app.imageAxis2Locs = app.imageAxis2.InnerPosition;

                app.mouseXY = app.OMZer_ExportDisplayPixel_UIFigure.CurrentPoint; % Get the xy coordinate of mouse cursor in terms of the uifigure
                app.mouseMotion = app.OMZer_ExportDisplayPixel_UIFigure.SelectionType; % The result can be 'normal', 'extend', 'alt', 'open'


                % Mouse inside imageAxis1
                if ( app.mouseXY(1,1) >= app.imageAxis1Locs(1,1) ) ...
                        &&  ( app.mouseXY(1,1) <= app.imageAxis1Locs(1,1) + app.imageAxis1Locs(1,3) ) ...
                        &&  ( app.mouseXY(1,2) >= app.imageAxis1Locs(1,2) ) ...
                        &&  ( app.mouseXY(1,2) <= app.imageAxis1Locs(1,2) + app.imageAxis1Locs(1,4) )

                    updateMouseXY(app)

                    app.markerXY(app.mouseDragID,1:2) = [ app.mouseXY(1,1), app.mouseXY(1,2) ]; % CAUTION: X is the column in matlab matrix. Y is the row in matlab matrix.
                    app.CheckSigX_scrn.Value = app.markerXY(app.mouseDragID,1);
                    app.CheckSigY_scrn.Value = app.markerXY(app.mouseDragID,2);

                    updateMarker(app)
                    updateMarkerHighlight(app)
                    updateSignal(app)


                    % Mouse inside imageAxis2
                elseif ( app.mouseXY(1,1) >= app.imageAxis2Locs(1,1) ) ...
                        &&  ( app.mouseXY(1,1) <= app.imageAxis2Locs(1,1) + app.imageAxis2Locs(1,3) ) ...
                        &&  ( app.mouseXY(1,2) >= app.imageAxis2Locs(1,2) ) ...
                        &&  ( app.mouseXY(1,2) <= app.imageAxis2Locs(1,2) + app.imageAxis2Locs(1,4) )

                    updateMouseXY(app)

                    app.markerXY(app.mouseDragID,1:2) = [ app.mouseXY(1,1), app.mouseXY(1,2) ]; % CAUTION: X is the column in matlab matrix. Y is the row in matlab matrix.
                    app.CheckSigX_scrn.Value = app.markerXY(app.mouseDragID,1);
                    app.CheckSigY_scrn.Value = app.markerXY(app.mouseDragID,2);

                    updateMarker(app)
                    updateMarkerHighlight(app)
                    updateSignal(app)

                else
                    app.mouseDragID = [];
                end
            end

        end

        % Window button up function: 
        % OMZer_ExportDisplayPixel_UIFigure
        function OMZer_ExportDisplayPixel_UIFigureWindowButtonUp(app, event)

            app.mouseDragID = [];

        end

        % Close request function: OMZer_ExportDisplayPixel_UIFigure
        function OMZer_ExportDisplayPixel_UIFigureCloseRequest(app, event)

            figure(app.mainAppUIFigure) % Set mainApp at the top level

            app.mainApp.OMZer_ExportDisplayPixel = [];

            delete(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OMZer_ExportDisplayPixel_UIFigure = uifigure('Visible', 'off');
            app.OMZer_ExportDisplayPixel_UIFigure.Name = 'OMZer Export Display Pixel';
            app.OMZer_ExportDisplayPixel_UIFigure.CloseRequestFcn = createCallbackFcn(app, @OMZer_ExportDisplayPixel_UIFigureCloseRequest, true);
            app.OMZer_ExportDisplayPixel_UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @OMZer_ExportDisplayPixel_UIFigureWindowButtonDown, true);
            app.OMZer_ExportDisplayPixel_UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @OMZer_ExportDisplayPixel_UIFigureWindowButtonUp, true);
            app.OMZer_ExportDisplayPixel_UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @OMZer_ExportDisplayPixel_UIFigureWindowButtonMotion, true);
            app.imageAxis1 = uiaxes(app.OMZer_ExportDisplayPixel_UIFigure);
            app.imageAxis1.XLim = [1 100];
            app.imageAxis1.YLim = [1 100];
            app.imageAxis1.YDir = 'reverse';
            app.imageAxis1.XTick = [];
            app.imageAxis1.YTick = [];
            app.imageAxis1.ZTick = [];
            app.imageAxis2 = uiaxes(app.OMZer_ExportDisplayPixel_UIFigure);
            app.imageAxis2.XLim = [1 100];
            app.imageAxis2.YLim = [1 100];
            app.imageAxis2.YDir = 'reverse';
            app.imageAxis2.XTick = [];
            app.imageAxis2.YTick = [];
            app.imageAxis2.ZTick = [];
            app.checkSigAxis = uiaxes(app.OMZer_ExportDisplayPixel_UIFigure);
            app.Image_DeletefromPixel = uiimage(app.OMZer_ExportDisplayPixel_UIFigure);
            app.Image_DeletefromPixel.ImageClickedFcn = createCallbackFcn(app, @Image_DeletefromPixelClicked, true);
            app.Image_DeletefromPixel.ImageSource = 'Icon Delete.png';
            app.DeletefromPixelLabel = uilabel(app.OMZer_ExportDisplayPixel_UIFigure);
            app.DeletefromPixelLabel.Text = 'Delete from Pixel';
            app.Image_AddIntoPixel = uiimage(app.OMZer_ExportDisplayPixel_UIFigure);
            app.Image_AddIntoPixel.ImageClickedFcn = createCallbackFcn(app, @Image_AddIntoPixelClicked, true);
            app.Image_AddIntoPixel.ImageSource = 'Icon Plus.png';
            app.AddIntoPixelLabel = uilabel(app.OMZer_ExportDisplayPixel_UIFigure);
            app.AddIntoPixelLabel.Text = 'Add Into Pixel';
            app.Image_Export = uiimage(app.OMZer_ExportDisplayPixel_UIFigure);
            app.Image_Export.ImageClickedFcn = createCallbackFcn(app, @Image_ExportClicked, true);
            app.Image_Export.ImageSource = 'Icon Export.png';
            app.ExportLabel = uilabel(app.OMZer_ExportDisplayPixel_UIFigure);
            app.ExportLabel.Text = 'Export';
            app.PixelListBoxLabel = uilabel(app.OMZer_ExportDisplayPixel_UIFigure);
            app.PixelListBoxLabel.Text = 'Pixel';
            app.PixelListBox = uilistbox(app.OMZer_ExportDisplayPixel_UIFigure);
            app.PixelListBox.Items = {};
            app.PixelListBox.ValueChangedFcn = createCallbackFcn(app, @PixelListBoxValueChanged, true);
            app.PixelListBox.Value = {};
            app.CheckSigXYLabel = uilabel(app.OMZer_ExportDisplayPixel_UIFigure);
            app.CheckSigXYLabel.Tooltip = {'X (red) and Y (blue) coordinate of the marker. Please be noted that X is column and Y is row to locate an element in a matrix in MATLAB.'};
            app.CheckSigXYLabel.Text = 'X,Y';
            app.CheckSigY_scrn = uieditfield(app.OMZer_ExportDisplayPixel_UIFigure, 'numeric');
            app.CheckSigY_scrn.Limits = [0 100];
            app.CheckSigY_scrn.ValueDisplayFormat = '%.0f';
            app.CheckSigY_scrn.ValueChangedFcn = createCallbackFcn(app, @CheckSigXY_scrnValueChanged, true);
            app.CheckSigY_scrn.Tooltip = {'Y coordinate of the 1st marker (Note: row in a matlab matrix)'};
            app.CheckSigX_scrn = uieditfield(app.OMZer_ExportDisplayPixel_UIFigure, 'numeric');
            app.CheckSigX_scrn.Limits = [0 100];
            app.CheckSigX_scrn.ValueDisplayFormat = '%.0f';
            app.CheckSigX_scrn.ValueChangedFcn = createCallbackFcn(app, @CheckSigXY_scrnValueChanged, true);
            app.CheckSigX_scrn.Tooltip = {'X coordinate of the 1st marker (Note: column in a matlab matrix)'};
            app.ViewStartsecSpinnerLabel = uilabel(app.OMZer_ExportDisplayPixel_UIFigure);
            app.ViewStartsecSpinnerLabel.Text = 'View Start (sec)';
            app.ViewStartsecSpinner = uispinner(app.OMZer_ExportDisplayPixel_UIFigure);
            app.ViewStartsecSpinner.Step = 0.05;
            app.ViewStartsecSpinner.ValueChangingFcn = createCallbackFcn(app, @ViewStartsecSpinnerValueChanging, true);
            app.ViewStartsecSpinner.Limits = [0 Inf];
            app.ViewStartsecSpinner.ValueChangedFcn = createCallbackFcn(app, @ViewStartsecSpinnerValueChanged, true);
            app.ViewEndsecSpinnerLabel = uilabel(app.OMZer_ExportDisplayPixel_UIFigure);
            app.ViewEndsecSpinnerLabel.Text = 'View End (sec)';
            app.ViewEndsecSpinner = uispinner(app.OMZer_ExportDisplayPixel_UIFigure);
            app.ViewEndsecSpinner.Step = 0.05;
            app.ViewEndsecSpinner.ValueChangingFcn = createCallbackFcn(app, @ViewEndsecSpinnerValueChanging, true);
            app.ViewEndsecSpinner.Limits = [0 Inf];
            app.ViewEndsecSpinner.ValueChangedFcn = createCallbackFcn(app, @ViewEndsecSpinnerValueChanged, true);
            app.ViewEndsecSpinner.Value = Inf;
            app.Image_RestoreView = uiimage(app.OMZer_ExportDisplayPixel_UIFigure);
            app.Image_RestoreView.ImageClickedFcn = createCallbackFcn(app, @Image_RestoreViewClicked, true);
            app.Image_RestoreView.ImageSource = 'Icon Restore View.png';
            app.RestoreViewLabel = uilabel(app.OMZer_ExportDisplayPixel_UIFigure);
            app.RestoreViewLabel.Text = 'Restore View';
            app.Camera1SignalBlueColorLabel = uilabel(app.OMZer_ExportDisplayPixel_UIFigure);
            app.Camera1SignalBlueColorLabel.Text = 'Camera 1 Signal - Blue Color';
            app.Camera2SignalRedColorLabel = uilabel(app.OMZer_ExportDisplayPixel_UIFigure);
            app.Camera2SignalRedColorLabel.Text = 'Camera 2 Signal - Red Color';
            app.CautionExportsignallengthisthesameasviewlengthLabel = uilabel(app.OMZer_ExportDisplayPixel_UIFigure);
            app.CautionExportsignallengthisthesameasviewlengthLabel.Text = 'Caution: Export signal length is the same as view length';
            % Show the figure after all components are created
            app.OMZer_ExportDisplayPixel_UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OMZer_ExportDisplayPixel(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.OMZer_ExportDisplayPixel_UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.OMZer_ExportDisplayPixel_UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OMZer_ExportDisplayPixel_UIFigure)
        end
    end
end