classdef OMZer_ExportFigure < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OMZer_ExportFigure_UIFigure  matlab.ui.Figure
        RestoreViewLabel             matlab.ui.control.Label
        Image_RestoreView            matlab.ui.control.Image
        ViewEndsecSpinner            matlab.ui.control.Spinner
        ViewEndsecSpinnerLabel       matlab.ui.control.Label
        ViewStartsecSpinner          matlab.ui.control.Spinner
        ViewStartsecSpinnerLabel     matlab.ui.control.Label
        X_scrn6                      matlab.ui.control.NumericEditField
        Y_scrn6                      matlab.ui.control.NumericEditField
        X_scrn5                      matlab.ui.control.NumericEditField
        Y_scrn5                      matlab.ui.control.NumericEditField
        X_scrn4                      matlab.ui.control.NumericEditField
        Y_scrn4                      matlab.ui.control.NumericEditField
        X_scrn3                      matlab.ui.control.NumericEditField
        Y_scrn3                      matlab.ui.control.NumericEditField
        X_scrn2                      matlab.ui.control.NumericEditField
        Y_scrn2                      matlab.ui.control.NumericEditField
        X_scrn1                      matlab.ui.control.NumericEditField
        Y_scrn1                      matlab.ui.control.NumericEditField
        XYLabel                      matlab.ui.control.Label
        TabGroup                     matlab.ui.container.TabGroup
        ToolsTab                     matlab.ui.container.Tab
        CautionScalebarlengthis1mmLabel_2  matlab.ui.control.Label
        ExportLabel                  matlab.ui.control.Label
        Image_Export                 matlab.ui.control.Image
        ScaleBarTab                  matlab.ui.container.Tab
        CautionScalebarlengthis1mmLabel  matlab.ui.control.Label
        ScaleBarSizeSpinner          matlab.ui.control.Spinner
        ScaleBarSizeSpinnerLabel     matlab.ui.control.Label
        FieldofViewmmSpinner         matlab.ui.control.Spinner
        FieldofViewmmSpinnerLabel    matlab.ui.control.Label
        ScaleBarColorLabel           matlab.ui.control.Label
        Image_ScaleBarColor          matlab.ui.control.Image
        ScaleBarCheckBox             matlab.ui.control.CheckBox
        MarkerTab                    matlab.ui.container.Tab
        DeleteAMarkerLabel           matlab.ui.control.Label
        AddAMarkerLabel              matlab.ui.control.Label
        Image_DeleteAMarker          matlab.ui.control.Image
        Image_AddAMarker             matlab.ui.control.Image
        CautionExportsignallengthisthesameasviewlengthLabel  matlab.ui.control.Label
        MarkerSizeSpinner            matlab.ui.control.Spinner
        MarkerSizeSpinnerLabel       matlab.ui.control.Label
        FigureTab                    matlab.ui.container.Tab
        CautionBoxLineWidthonlyworkswhenFigureBoxischeckedLabel  matlab.ui.control.Label
        BoxLineWidthSpinner          matlab.ui.control.Spinner
        BoxLineWidthSpinnerLabel     matlab.ui.control.Label
        FigureBoxCheckBox            matlab.ui.control.CheckBox
        OnlyShowForegroundCheckBox   matlab.ui.control.CheckBox
        scrn6                        matlab.ui.control.UIAxes
        scrn5                        matlab.ui.control.UIAxes
        scrn4                        matlab.ui.control.UIAxes
        scrn3                        matlab.ui.control.UIAxes
        scrn2                        matlab.ui.control.UIAxes
        scrn1                        matlab.ui.control.UIAxes
        MapAxis                      matlab.ui.control.UIAxes
    end


    properties (Access = public)

        % The Parent App --------------------
        mainApp
        mainAppUIFigure


        % The Current App --------------------
        dirName % File directory
        fileName % Filename without path and extension

        setup % User preference
        userAlphaValue

        imageIconActColor = [0.80, 0.80, 0.80]; % The activation color when an image is clicked

        lWidth = 2; % Line width

        data
        acqFreq
        markerXY
        signalTime
        cmosDataGroup
        bgImageNomGroup % Gray colour RGB version of background image
        pacing1 % Pacing signal recorded by channel 1
        pacing2 % Pacing signal recorded by channel 2
        pacingTime % Time points to plot pacing signal

        cameraSource % '1 (or 2)' - data from camera 1 (or 2)
        maskGroup

        mapAxisLocs

        maxMarkerNumber = 6;

        clickCount = 1; % Record how many times the mouse double-click in imageAxis1 and imageAxis2
        mouseMotion
        mouseDragID
        scaleBarDragID
        mouseXY
        scrnObject % To store 6 scrn axis objects
        Xs % The x coordinate edit box
        Ys % The y coordinate edit box

        cmosDataRowNum % The numer of rows in cmosData
        cmosDataColNum % The numer of columns in cmosData

        markerPlotObject = cell(1,6);
        signalPlotObject = cell(1,6);

        maskPlotObject

        scaleBarPlotObject
        scaleBarPixelNum
        scaleBarColor = [1, 1, 1]; % White

        ColorID
        Color1 = [0.00,0.45,0.74];
        Color2 = [0.93,0.69,0.13];
        Color3 = [0.64,0.08,0.18];
        Color4 = [0.72,0.27,1.00];
        Color5 = [0.47,0.67,0.19];
        Color6 = [0.89,0.47,0.58];

        exportBgImage

        % For export pixel
        pixelSaveToID = 1;
        exportPixelX
        exportPixelY
    end


    methods (Access = public)


        function showImageIconAct(app, imageIconName)

            imageIconName.BackgroundColor = app.imageIconActColor; %[0.80, 0.80, 0.80];
            pause(0.05)
            imageIconName.BackgroundColor = 'none';
        end


        function setColourMap(app,name)

            colormap(app.MapAxis, name);
        end


        function setAxisLimit(app, xMaxLimit, yMaxLimit) % Set the x-y limit of all axes

            app.MapAxis.XLim = [1, xMaxLimit];
            app.MapAxis.YLim = [1, yMaxLimit];
        end


        function setScrnLimit(app, LB, UB)

            app.ViewStartsecSpinner.Limits = [ LB, UB ];
            app.ViewEndsecSpinner.Limits = [ LB, UB ];

            app.ViewStartsecSpinner.Value = LB;
            app.ViewEndsecSpinner.Value = UB;

            for ID = 1:6
                xlim( app.scrnObject{ID}, [ app.ViewStartsecSpinner.Value, app.ViewEndsecSpinner.Value ] )
            end
        end


        function updateMouseXY(app, axisLosInfo)

            %             app.mouseXY(1,1) = ( app.mouseXY(1,1) - axisLosInfo(1,1) ) * app.cmosDataColNum / axisLosInfo(1,3);
            %             app.mouseXY(1,1) = round( app.mouseXY(1,1) ); % Transfer relative mouse location inside bgImageAxis1 to absolute location inside a 100x100 matrix
            %             if app.mouseXY(1,1) < 1
            %                 app.mouseXY(1,1) = 1; % As a result, there won't appear (0,y) element requirement in a 100x100 matrix
            %             end
            %             if app.mouseXY(1,1) > app.cmosDataColNum
            %                 app.mouseXY(1,1) = app.cmosDataColNum;
            %             end
            %
            %
            %             app.mouseXY(1,2) = axisLosInfo.CurrentPoint(1,2);

            %             app.mouseXY(1,2) = ( (axisLosInfo(1,2) + axisLosInfo(1,4)) - app.mouseXY(1,2) ) * app.cmosDataRowNum / axisLosInfo(1,4);
            %             app.mouseXY(1,2) = round( app.mouseXY(1,2) ); % Transfer relative mouse location inside bgImageAxis1 to absolute location inside a 100x100 matrix
            %             if app.mouseXY(1,2) < 1
            %                 app.mouseXY(1,2) = 1; % As a result, there won't appear (x,0) element requirement in a 100x100 matrix
            %             end
            %             if app.mouseXY(1,2) > app.cmosDataRowNum
            %                 app.mouseXY(1,2) = app.cmosDataRowNum;
            %             end


            app.mouseXY(1,1) = app.MapAxis.CurrentPoint(1,1);
            app.mouseXY(1,2) = app.MapAxis.CurrentPoint(1,2);


            app.mouseXY(1,1) = round( app.mouseXY(1,1) ); % Transfer relative mouse location inside bgImageAxis1 to absolute location inside a 100x100 matrix
            if app.mouseXY(1,1) < 1
                app.mouseXY(1,1) = 1; % As a result, there won't appear (0,y) element requirement in a 100x100 matrix
            end
            if app.mouseXY(1,1) > app.cmosDataColNum
                app.mouseXY(1,1) = app.cmosDataColNum;
            end

            app.mouseXY(1,2) = round( app.mouseXY(1,2) ); % Transfer relative mouse location inside bgImageAxis1 to absolute location inside a 100x100 matrix
            if app.mouseXY(1,2) < 1
                app.mouseXY(1,2) = 1; % As a result, there won't appear (x,0) element requirement in a 100x100 matrix
            end
            if app.mouseXY(1,2) > app.cmosDataRowNum
                app.mouseXY(1,2) = app.cmosDataRowNum;
            end
        end


        function updateMarker(app)

            if ~isempty(app.mouseDragID) % Single click

                X = app.markerXY( app.mouseDragID, 1 );
                Y = app.markerXY( app.mouseDragID, 2 );

                app.markerPlotObject{ 1, app.mouseDragID }.XData = X;
                app.markerPlotObject{ 1, app.mouseDragID }.YData = Y;


            else % Double click

                X = app.markerXY( app.clickCount, 1 );
                Y = app.markerXY( app.clickCount, 2 );

                delete( app.markerPlotObject{ 1, app.clickCount } )
                app.markerPlotObject{ 1, app.clickCount } = line( app.MapAxis, X,Y, 'Marker','square' );
                app.markerPlotObject{ 1, app.clickCount }.MarkerSize = app.MarkerSizeSpinner.Value;
                app.markerPlotObject{ 1, app.clickCount }.MarkerEdgeColor = 'White';
                app.markerPlotObject{ 1, app.clickCount }.MarkerFaceColor = app.ColorID{app.clickCount};
            end
        end


        function updateSignal(app)

            if ~isempty(app.mouseDragID) % Single click

                row = app.markerXY( app.mouseDragID, 2 );
                col = app.markerXY( app.mouseDragID, 1 );

                app.signalPlotObject{ 1, app.mouseDragID }.YData = squeeze( app.cmosDataGroup{app.cameraSource}(row,col,:) );

            else % Double click

                row = app.markerXY( app.clickCount, 2 );
                col = app.markerXY( app.clickCount, 1 );

                delete( app.signalPlotObject{ 1, app.clickCount } )
                sig = squeeze( app.cmosDataGroup{app.cameraSource}(row,col,:) );
                app.signalPlotObject{ 1, app.clickCount } = line( app.scrnObject{app.clickCount}, app.signalTime, sig );
                app.signalPlotObject{ 1, app.clickCount }.LineWidth = app.lWidth;
                app.signalPlotObject{ 1, app.clickCount }.Color = app.ColorID{app.clickCount};
            end
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, OMZer)

            msg = 'Configuring files';
            progressBar = uiprogressdlg(app.OMZer_ExportFigure_UIFigure, 'Title','Initialising', 'Message',msg, 'Indeterminate','on');
            drawnow

            % (1) User set up
            app.setup = load([pwd,'/OMZer_Setup']);
            setColourMap(app, app.setup.userColourMap);
            app.userAlphaValue = 1.0; % The default alpha value

            % (2) Get information from the mainApp
            app.mainApp = OMZer;
            app.mainAppUIFigure = app.mainApp.OMZer_UIFigure;

            app.dirName = app.mainApp.dirName;
            app.fileName = app.mainApp.fileName;

            app.data = app.mainApp.data;
            app.acqFreq = app.mainApp.acqFreq;
            % app.markerXY = app.mainApp.markerXY;
            app.signalTime = app.mainApp.signalTime;
            app.cmosDataGroup = app.mainApp.cmosDataGroup;
            app.bgImageNomGroup = app.mainApp.bgImageNomGroup;
            app.pacing1 = app.mainApp.pacing1;
            app.pacing2 = app.mainApp.pacing2;
            app.pacingTime = app.mainApp.pacingTime;
            app.cameraSource = app.mainApp.activeCamera;
            app.maskGroup = app.mainApp.maskGroup;

            % (3) Create components collection
            app.scrnObject = {app.scrn1, app.scrn2, app.scrn3, app.scrn4, app.scrn5, app.scrn6};
            app.Xs = {app.X_scrn1, app.X_scrn2, app.X_scrn3, app.X_scrn4, app.X_scrn5, app.X_scrn6};
            app.Ys = {app.Y_scrn1, app.Y_scrn2, app.Y_scrn3, app.Y_scrn4, app.Y_scrn5, app.Y_scrn6};
            app.ColorID = {app.Color1, app.Color2, app.Color3, app.Color4, app.Color5, app.Color6};

            % (4) Diable Toolbar
            % https://www.mathworks.com/help/matlab/ref/axtoolbar.html?searchHighlight=axtoolbar&s_tid=doc_srchtitle
            try
                disableDefaultInteractivity(app.MapAxis);
                axtoolbar(app.MapAxis);

                for ID = 1 : 6
                    disableDefaultInteractivity( app.scrnObject{ID} );
                    axtoolbar( app.scrnObject{ID} );
                end

            catch
                % Do nothing
            end

            % (5) Plot background image
            [app.cmosDataRowNum, app.cmosDataColNum, ~] = size( app.cmosDataGroup{app.cameraSource} );
            setAxisLimit(app, app.cmosDataColNum, app.cmosDataRowNum);
            LB = 0;
            UB = app.signalTime(end);
            setScrnLimit(app, LB, UB)
            imagesc( app.MapAxis, app.bgImageNomGroup{app.cameraSource} );

            app.exportBgImage = app.bgImageNomGroup{app.cameraSource};

            close(progressBar)

        end

        % Image clicked function: Image_Export
        function Image_ExportClicked(app, event)

            showImageIconAct(app, app.Image_Export)

            % (1) Set up the figure canvas
            fObject = figure;
            screenSize = get( groot, 'ScreenSize' );
            fObject.Position = [ 1, 20, 0.9*screenSize(4), 0.9*screenSize(4) ];

            % (2) Set up the axis
            MapAxisExport = axes('OuterPosition', [0, 0, 1, 1]);
            MapAxisExport.YDir = 'Reverse';
            MapAxisExport.XLim = [ 1, app.cmosDataColNum ];
            MapAxisExport.YLim = [ 1, app.cmosDataRowNum ];

            % (3) Plot the background image
            bgImagePlotExport = imagesc( MapAxisExport, app.exportBgImage );

            % (Below) Interpolation
            % https://www.mathworks.com/help/matlab/interpolation.html
            % https://www.mathworks.com/help/matlab/math/interpolating-gridded-data.html
            %{
            [X_Old,Y_Old] = meshgrid(1:1:100);
            [X_New,Y_New] = meshgrid(1:0.05:100);
            
            R_Old = app.exportBgImage(:,:,1);
            G_Old = app.exportBgImage(:,:,2);
            B_Old = app.exportBgImage(:,:,3);
            
            R_New = interp2( X_Old, Y_Old, R_Old, X_New, Y_New);
            G_New = interp2( X_Old, Y_Old, G_Old, X_New, Y_New);
            B_New = interp2( X_Old, Y_Old, B_Old, X_New, Y_New);

            exportBgImage_New(:,:,1) = R_New;
            exportBgImage_New(:,:,2) = G_New;
            exportBgImage_New(:,:,3) = B_New;

            bgImagePlotExport = imagesc( MapAxisExport, exportBgImage_New );
            %}
            % (Above) Interpolation

            TF = ~isnan( bgImagePlotExport.CData(:,:,1) );
            bgImagePlotExport.AlphaData = TF;

            % (4) Plot scale bar
            if app.ScaleBarCheckBox.Value == 1

                xx = app.scaleBarPlotObject.XData;
                yy = app.scaleBarPlotObject.YData;
                scaleBarPlotObjectExport = line( MapAxisExport, xx, yy );
                scaleBarPlotObjectExport.LineWidth = app.ScaleBarSizeSpinner.Value;
                scaleBarPlotObjectExport.Color = app.scaleBarColor;
            end

            % (5) Plot markers
            if ~isempty( app.markerXY )

                [ markerNumber, ~ ] = size(app.markerXY);

                for ID = 1 : markerNumber

                    X = app.markerXY( ID, 1 );
                    Y = app.markerXY( ID, 2 );

                    markerPlotObjectExport = line( MapAxisExport, X,Y, 'Marker','square' );
                    markerPlotObjectExport.MarkerSize = app.MarkerSizeSpinner.Value;
                    markerPlotObjectExport.MarkerEdgeColor = 'White';
                    markerPlotObjectExport.MarkerFaceColor = app.ColorID{ID};
                end
            end

            % (6) Figure setup
            MapAxisExport.PlotBoxAspectRatio = [ 1, 1, 1 ];

            MapAxisExport.XTickLabel = {};
            MapAxisExport.YTickLabel = {};

            MapAxisExport.Box = 'on';
            MapAxisExport.XTick = [];
            MapAxisExport.YTick = [];
            MapAxisExport.LineWidth = app.BoxLineWidthSpinner.Value;

            if app.FigureBoxCheckBox.Value == 1
                
                MapAxisExport.Box = 'on';
                
            else
                
                MapAxisExport.Box = 'off';
                MapAxisExport.XAxis.Color = 'none';
                MapAxisExport.YAxis.Color = 'none';
            end

            % (7) Export pixel (i.e., marker) signals
            if ~isempty( app.markerXY )

                [ markerNumber, ~ ] = size(app.markerXY);

                saveDir = uigetdir; % Get valid saving directory and file name

                if saveDir == 0 % User has NOT selected file directory.

                    msg = 'Please select a valid saving directory';
                    uialert(app.OMZer_ExportFigure_UIFigure,msg,'Warning','Icon','error')

                else

                    prompt = {'Enter filename to save (without extension)...   Please do not use \/:*?"<>|'};
                    dlgtitle = 'Save to a .mat file';
                    dfinput = { [ app.fileName, '_Pixel_Data' ] };
                    dims = [1,100];
                    filename = inputdlg(prompt,dlgtitle,dims,dfinput);

                    if isempty(filename) % User has NOT entered a name

                        msg = 'Please enter a valid file name';
                        uialert(app.OMZer_ExportFigure_UIFigure,msg,'Warning','Icon','error')

                    else

                        acquisitionFrequency_Hz_Unit = app.acqFreq;

                        pixelX = app.markerXY(:,1);
                        if isrow(pixelX)
                            pixelX = pixelX';
                        end

                        pixelY = app.markerXY(:,2);
                        if isrow(pixelY)
                            pixelY = pixelY';
                        end

                        viewWindowStartIndex = find( app.signalTime >= app.ViewStartsecSpinner.Value, 1,'first' );
                        viewWindowEndIndex = find( app.signalTime <= app.ViewEndsecSpinner.Value, 1,'last' );

                        pacing1_marker = app.pacing1( viewWindowStartIndex : viewWindowEndIndex );
                        pacing2_marker = app.pacing2( viewWindowStartIndex : viewWindowEndIndex );
                        pacingTime_sec_Unit = app.pacingTime( viewWindowStartIndex : viewWindowEndIndex );
                        pacingTime_sec_Unit = pacingTime_sec_Unit - pacingTime_sec_Unit(1);% Time start at 0
                        if isrow(pacing1_marker)
                            pacing1_marker = pacing1_marker';
                        end
                        if isrow(pacing2_marker)
                            pacing2_marker = pacing2_marker';
                        end
                        if isrow(pacingTime_sec_Unit)
                            pacingTime_sec_Unit = pacingTime_sec_Unit';
                        end

                        pixelTime_sec_Unit = app.signalTime( viewWindowStartIndex : viewWindowEndIndex );
                        pixelTime_sec_Unit = pixelTime_sec_Unit - pixelTime_sec_Unit(1); % Time start at 0
                        if isrow(pixelTime_sec_Unit)
                            pixelTime_sec_Unit = pixelTime_sec_Unit';
                        end

                        pixelColor = app.ColorID( 1 : markerNumber );
                        
                        pixelSignal = NaN( length(pixelTime_sec_Unit), markerNumber );
                        for ID = 1 : markerNumber

                            rowID = pixelY(ID);
                            colID = pixelX(ID);
                            sig = squeeze( app.cmosDataGroup{app.cameraSource}( rowID, colID, viewWindowStartIndex:viewWindowEndIndex ) );
                            sig = rescale(sig);

                            if isrow(sig)
                                sig = sig';
                            end
                            pixelSignal(:,ID) = sig; % Each column is a pixel
                        end

                        backgroundImage = app.bgImageNomGroup{app.cameraSource};

                        if ~isempty( app.maskGroup{app.cameraSource} )
                            mask = app.maskGroup{app.cameraSource};
                        else
                            mask = ones( app.cmosDataRowNum, app.cmosDataColNum );
                        end

                        cameraID = app.cameraSource;

                        msg = 'Export the Pixel Data';
                        progressBar = uiprogressdlg(app.OMZer_ExportFigure_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');

                        fullSaveName = strcat(saveDir, '/', filename, '.mat'); % At this moment, 'fullSaveName' is a 'cell'.
                        fullSaveName = fullSaveName{1}; % Now 'fullSaveName' is a 'character
                        save(fullSaveName, ...
                            'acquisitionFrequency_Hz_Unit', ...
                            'pacing1_marker', ...
                            'pacing2_marker', ...
                            'pacingTime_sec_Unit', ...
                            'pixelTime_sec_Unit', ...
                            'pixelX', ...
                            'pixelY', ...
                            'pixelSignal', ...
                            'pixelColor', ...
                            'backgroundImage', ...
                            'mask', ...
                            'cameraID');

                        close(progressBar)
                    end
                end
            end

        end

        % Image clicked function: Image_ScaleBarColor
        function Image_ScaleBarColorClicked(app, event)

            showImageIconAct(app, app.Image_ScaleBarColor)

            app.scaleBarColor = uisetcolor('Select a scale bar color');

            if app.scaleBarColor == 0

                msg = 'You have not selected a color. By default, the color will be white.';
                uialert(app.OMZer_ExportFigure_UIFigure, msg,'Warning','Icon','error')

                app.scaleBarColor = [1, 1, 1]; % White
            end

            if ~isempty( app.scaleBarPlotObject )

                app.scaleBarPlotObject.Color = app.scaleBarColor;
            end

            figure(app.OMZer_ExportFigure_UIFigure)

        end

        % Value changed function: ScaleBarCheckBox
        function ScaleBarCheckBoxValueChanged(app, event)

            delete(app.scaleBarPlotObject)
            app.scaleBarPlotObject = [];

            if app.ScaleBarCheckBox.Value == 1

                pixelSize = app.FieldofViewmmSpinner.Value / app.cmosDataColNum; % Unit - mm/pixel
                scaleBarLength = 1; % Unit - mm
                app.scaleBarPixelNum = round( scaleBarLength / pixelSize );

                xx = [ (1/4) * app.cmosDataColNum, (1/4) * app.cmosDataColNum + app.scaleBarPixelNum ];
                yy = [ (1/4) * app.cmosDataRowNum, (1/4) * app.cmosDataRowNum ];

                app.scaleBarPlotObject = line( app.MapAxis, xx, yy );
                app.scaleBarPlotObject.LineWidth = app.ScaleBarSizeSpinner.Value;
                app.scaleBarPlotObject.Color = app.scaleBarColor;
            end

        end

        % Value changed function: FieldofViewmmSpinner
        function FieldofViewmmSpinnerValueChanged(app, event)

            if ~isempty( app.scaleBarPlotObject )

                xx = app.scaleBarPlotObject.XData;
                yy = app.scaleBarPlotObject.YData;

                pixelSize = app.FieldofViewmmSpinner.Value / app.cmosDataColNum; % Unit - mm/pixel
                scaleBarLength = 1; % Unit - mm
                app.scaleBarPixelNum = round( scaleBarLength / pixelSize );

                xxNew = [ xx(1), xx(1) + app.scaleBarPixelNum ];
                yyNew = yy;

                app.scaleBarPlotObject.XData = xxNew;
                app.scaleBarPlotObject.YData = yyNew;
            end

        end

        % Value changing function: FieldofViewmmSpinner
        function FieldofViewmmSpinnerValueChanging(app, event)

            if ischar(event.Value)
                app.FieldofViewmmSpinner.Value = str2double(event.Value);
            else
                app.FieldofViewmmSpinner.Value = event.Value;
            end

            FieldofViewmmSpinnerValueChanged(app, event)

        end

        % Value changed function: ScaleBarSizeSpinner
        function ScaleBarSizeSpinnerValueChanged(app, event)

            if ~isempty( app.scaleBarPlotObject )

                app.scaleBarPlotObject.LineWidth = app.ScaleBarSizeSpinner.Value;
            end

        end

        % Value changing function: ScaleBarSizeSpinner
        function ScaleBarSizeSpinnerValueChanging(app, event)

            if ischar(event.Value)
                app.ScaleBarSizeSpinner.Value = str2double(event.Value);
            else
                app.ScaleBarSizeSpinner.Value = event.Value;
            end

            ScaleBarSizeSpinnerValueChanged(app, event)

        end

        % Value changed function: MarkerSizeSpinner
        function MarkerSizeSpinnerValueChanged(app, event)

            if ~isempty( app.markerXY )

                [ markerNumber, ~ ] = size(app.markerXY);

                for ID = 1 : markerNumber

                    if ~isempty( app.markerPlotObject )

                        app.markerPlotObject{ 1, ID }.MarkerSize = app.MarkerSizeSpinner.Value;
                    end
                end
            end

        end

        % Value changing function: MarkerSizeSpinner
        function MarkerSizeSpinnerValueChanging(app, event)

            if ischar(event.Value)
                app.MarkerSizeSpinner.Value = str2double(event.Value);
            else
                app.MarkerSizeSpinner.Value = event.Value;
            end

            MarkerSizeSpinnerValueChanged(app, event)

        end

        % Image clicked function: Image_AddAMarker
        function Image_AddAMarkerClicked(app, event)

            showImageIconAct(app, app.Image_AddAMarker)

            if app.clickCount > 6

                msg = [ 'You can have up to ', num2str(app.maxMarkerNumber), ' markers. Delete one or more markers first.' ];
                uialert(app.OMZer_ExportFigure_UIFigure, msg,'Warning','Icon','error')

            else

                app.markerXY(app.clickCount, 1:2) = [ round(0.5*app.cmosDataRowNum), round(0.5*app.cmosDataColNum) ]; % CAUTION: X is the column in matlab matrix. Y is the row in matlab matrix.
                app.Xs{app.clickCount}.Value = app.markerXY(app.clickCount,1);
                app.Ys{app.clickCount}.Value = app.markerXY(app.clickCount,2);
                app.Xs{app.clickCount}.Editable = 'on';
                app.Ys{app.clickCount}.Editable = 'on';
                app.Xs{app.clickCount}.BackgroundColor = [1.00, 1.00, 0.00]; % Yellow
                app.Ys{app.clickCount}.BackgroundColor = [1.00, 1.00, 0.00]; % Yellow

                updateMarker(app)
                updateSignal(app)

                app.clickCount = app.clickCount + 1;
            end

        end

        % Image clicked function: Image_DeleteAMarker
        function Image_DeleteAMarkerClicked(app, event)

            showImageIconAct(app, app.Image_DeleteAMarker)

            if ~isempty( app.markerXY )

                [ markerNumber, ~ ] = size(app.markerXY);

                app.markerXY(markerNumber, :) = []; % CAUTION: X is the column in matlab matrix. Y is the row in matlab matrix.
                app.Xs{markerNumber}.Value = 0;
                app.Ys{markerNumber}.Value = 0;
                app.Xs{markerNumber}.Editable = 'off';
                app.Ys{markerNumber}.Editable = 'off';
                app.Xs{markerNumber}.BackgroundColor = [1.00, 1.00, 1.00]; % White
                app.Ys{markerNumber}.BackgroundColor = [1.00, 1.00, 1.00]; % White

                delete( app.markerPlotObject{ 1, markerNumber } )
                app.markerPlotObject{ 1, markerNumber } = [];

                delete( app.signalPlotObject{ 1, markerNumber } )
                app.signalPlotObject{ 1, markerNumber } = [];

                app.clickCount = app.clickCount - 1;

            else

                msg = 'There is no marker';
                uialert(app.OMZer_ExportFigure_UIFigure, msg,'Warning','Icon','error')
            end

        end

        % Value changed function: OnlyShowForegroundCheckBox
        function OnlyShowForegroundCheckBoxValueChanged(app, event)

            delete(app.maskPlotObject)
            app.maskPlotObject = [];

            app.exportBgImage = app.bgImageNomGroup{app.cameraSource};

            if isempty( app.maskGroup( app.cameraSource ) )

                msg = 'There is no mask so there is no foreground';
                uialert(app.OMZer_ExportFigure_UIFigure, msg,'Warning','Icon','error')

                app.OnlyShowForegroundCheckBox.Value = 0;

            else

                if app.OnlyShowForegroundCheckBox.Value == 1

                    hold(app.MapAxis,'on')
                    app.maskPlotObject = imagesc( app.MapAxis, app.maskGroup{app.cameraSource}, 'AlphaData', 0.3 * ~isnan(app.maskGroup{app.cameraSource}) );
                    hold(app.MapAxis,'off')

                    app.exportBgImage = app.bgImageNomGroup{app.cameraSource} .* app.maskGroup{app.cameraSource};
                end
            end

        end

        % Value changed function: X_scrn1, X_scrn2, X_scrn3, 
        % X_scrn4, X_scrn5, X_scrn6, Y_scrn1, Y_scrn2, Y_scrn3, 
        % Y_scrn4, Y_scrn5, Y_scrn6
        function XY_scrnValueChanged(app, event)

            for ID = 1 : 6

                if ( event.Source == app.Xs{ID} )  ||  ( event.Source == app.Ys{ID} )

                    xValue = app.Xs{ID}.Value;
                    yValue = app.Ys{ID}.Value;

                    app.mouseDragID = ID;

                    app.markerXY(app.mouseDragID,1:2) = [ xValue, yValue ]; % CAUTION: X is the column in matlab matrix. Y is the row in matlab matrix.

                    updateMarker(app)
                    updateSignal(app)

                    app.mouseDragID = [];

                    break
                end
            end

        end

        % Value changed function: ViewStartsecSpinner
        function ViewStartsecSpinnerValueChanged(app, event)

            if app.ViewStartsecSpinner.Value >= app.ViewEndsecSpinner.Value

                msg = 'Start time must be eariler than end time';
                uialert(app.OMZer_ExportFigure_UIFigure,msg,'Warning','Icon','error')

                app.ViewStartsecSpinner.Value = 0;
                app.ViewEndsecSpinner.Value = app.signalTime(end);
            end

            for ID = 1:6
                xlim( app.scrnObject{ID}, [ app.ViewStartsecSpinner.Value, app.ViewEndsecSpinner.Value ] )
            end

        end

        % Value changing function: ViewStartsecSpinner
        function ViewStartsecSpinnerValueChanging(app, event)

            if ischar(event.Value)
                app.ViewStartsecSpinner.Value = str2double(event.Value);
            else
                app.ViewStartsecSpinner.Value = event.Value;
            end

            ViewStartsecSpinnerValueChanged(app, event)

        end

        % Value changed function: ViewEndsecSpinner
        function ViewEndsecSpinnerValueChanged(app, event)

            if app.ViewStartsecSpinner.Value >= app.ViewEndsecSpinner.Value

                msg = 'Start time must be eariler than end time';
                uialert(app.OMZer_ExportFigure_UIFigure,msg,'Warning','Icon','error')

                app.ViewStartsecSpinner.Value = 0;
                app.ViewEndsecSpinner.Value = app.signalTime(end);
            end

            for ID = 1:6
                xlim( app.scrnObject{ID}, [ app.ViewStartsecSpinner.Value, app.ViewEndsecSpinner.Value ] )
            end

        end

        % Value changing function: ViewEndsecSpinner
        function ViewEndsecSpinnerValueChanging(app, event)

            if ischar(event.Value)
                app.ViewEndsecSpinner.Value = str2double(event.Value);
            else
                app.ViewEndsecSpinner.Value = event.Value;
            end

            ViewEndsecSpinnerValueChanged(app, event)

        end

        % Image clicked function: Image_RestoreView
        function Image_RestoreViewClicked(app, event)

            showImageIconAct(app, app.Image_RestoreView)

            app.ViewStartsecSpinner.Value = 0;
            app.ViewEndsecSpinner.Value = app.signalTime(end);

            for ID = 1:6
                xlim( app.scrnObject{ID}, [ min(app.ViewStartsecSpinner.Limits), max(app.ViewEndsecSpinner.Limits) ] )
            end

        end

        % Window button down function: OMZer_ExportFigure_UIFigure
        function OMZer_ExportFigure_UIFigureWindowButtonDown(app, event)

            app.mouseMotion = app.OMZer_ExportFigure_UIFigure.SelectionType; % The result can be 'normal', 'extend', 'alt', 'open'

            if isequal(app.mouseMotion,'normal') % 'normal' means single-click any mouse button.

                app.mouseXY = app.OMZer_ExportFigure_UIFigure.CurrentPoint; % Returns (x, y) coordinates of the mouse pointer, measured from the lower-left corner of the figure.
                app.mapAxisLocs = app.MapAxis.InnerPosition; % Returns a 1-by-4 row vector -  [left bottom width height].

                if ( app.mouseXY(1,1) >= app.mapAxisLocs(1,1) )  ...
                        &&  ( app.mouseXY(1,1) <= app.mapAxisLocs(1,1) + app.mapAxisLocs(1,3) )  ...
                        &&  ( app.mouseXY(1,2) >= app.mapAxisLocs(1,2) )  ...
                        &&  ( app.mouseXY(1,2) <= app.mapAxisLocs(1,2) + app.mapAxisLocs(1,4) )

                    updateMouseXY(app, app.mapAxisLocs);

                    % Check marker
                    for ID = 1 : size(app.markerXY,1)
                        if abs( app.mouseXY(1,1) - app.markerXY(ID,1) ) <= 5  &&  abs( app.mouseXY(1,2) - app.markerXY(ID,2) ) <= 5 % Find which marker is within 5 pixels of the mouse location
                            app.mouseDragID = ID; % Find which marker the user is pointing to
                            break
                        end
                    end

                    % Check scale bar
                    if ~isempty(app.scaleBarPlotObject)  &&  isempty(app.mouseDragID)

                        if app.mouseXY(1,1) >= app.scaleBarPlotObject.XData(1) - 5  ...
                                &&  app.mouseXY(1,1) <= app.scaleBarPlotObject.XData(2) + 5  ...
                                &&  app.mouseXY(1,2) >= app.scaleBarPlotObject.YData(1) - 5  ...
                                &&  app.mouseXY(1,2) <= app.scaleBarPlotObject.YData(2) + 5

                            app.scaleBarDragID = 1;
                        end
                    end
                end
            end

        end

        % Window button motion function: OMZer_ExportFigure_UIFigure
        function OMZer_ExportFigure_UIFigureWindowButtonMotion(app, event)

            if ~isempty(app.mouseDragID)  ||  ~isempty(app.scaleBarDragID)

                app.mouseXY = app.OMZer_ExportFigure_UIFigure.CurrentPoint; % Returns (x, y) coordinates of the mouse pointer, measured from the lower-left corner of the figure.
                app.mapAxisLocs = app.MapAxis.InnerPosition; % Returns a 1-by-4 row vector -  [left bottom width height].

                if ( app.mouseXY(1,1) >= app.mapAxisLocs(1,1) )  ...
                        &&  ( app.mouseXY(1,1) <= app.mapAxisLocs(1,1) + app.mapAxisLocs(1,3) )  ...
                        &&  ( app.mouseXY(1,2) >= app.mapAxisLocs(1,2) )  ...
                        &&  ( app.mouseXY(1,2) <= app.mapAxisLocs(1,2) + app.mapAxisLocs(1,4) )

                    updateMouseXY(app, app.mapAxisLocs);

                    % Update marker
                    if ~isempty(app.mouseDragID)

                        app.markerXY(app.mouseDragID,1:2) = [ app.mouseXY(1,1), app.mouseXY(1,2) ]; % CAUTION: X is the column in matlab matrix. Y is the row in matlab matrix.
                        app.Xs{app.mouseDragID}.Value = app.markerXY(app.mouseDragID,1);
                        app.Ys{app.mouseDragID}.Value = app.markerXY(app.mouseDragID,2);

                        updateMarker(app)
                        updateSignal(app)

                        % Update scale bar
                    elseif ~isempty(app.scaleBarDragID)

                        app.scaleBarPlotObject.XData = [ app.mouseXY(1,1), app.mouseXY(1,1) + app.scaleBarPixelNum ];
                        app.scaleBarPlotObject.YData = [ app.mouseXY(1,2), app.mouseXY(1,2) ];
                    end
                end
            end

        end

        % Window button up function: OMZer_ExportFigure_UIFigure
        function OMZer_ExportFigure_UIFigureWindowButtonUp(app, event)

            app.mouseDragID = [];
            app.scaleBarDragID = [];

        end

        % Close request function: OMZer_ExportFigure_UIFigure
        function OMZer_ExportFigure_UIFigureCloseRequest(app, event)

            % (1) Set mainApp at the top level
            figure(app.mainAppUIFigure)

            % (2) Update the mainApp
            app.mainApp.OMZer_ExportFigure = [];

            delete(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OMZer_ExportFigure_UIFigure = uifigure('Visible', 'off');
            app.OMZer_ExportFigure_UIFigure.Name = 'OMZer Export Figure';
            app.OMZer_ExportFigure_UIFigure.CloseRequestFcn = createCallbackFcn(app, @OMZer_ExportFigure_UIFigureCloseRequest, true);
            app.OMZer_ExportFigure_UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @OMZer_ExportFigure_UIFigureWindowButtonDown, true);
            app.OMZer_ExportFigure_UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @OMZer_ExportFigure_UIFigureWindowButtonUp, true);
            app.OMZer_ExportFigure_UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @OMZer_ExportFigure_UIFigureWindowButtonMotion, true);
            app.MapAxis = uiaxes(app.OMZer_ExportFigure_UIFigure);
            app.MapAxis.XLim = [1 100];
            app.MapAxis.YLim = [1 100];
            app.MapAxis.YDir = 'reverse';
            app.MapAxis.XTick = [];
            app.MapAxis.YTick = [];
            app.MapAxis.ZTick = [];
            app.scrn1 = uiaxes(app.OMZer_ExportFigure_UIFigure);
            app.scrn2 = uiaxes(app.OMZer_ExportFigure_UIFigure);
            app.scrn3 = uiaxes(app.OMZer_ExportFigure_UIFigure);
            app.scrn4 = uiaxes(app.OMZer_ExportFigure_UIFigure);
            app.scrn5 = uiaxes(app.OMZer_ExportFigure_UIFigure);
            app.scrn6 = uiaxes(app.OMZer_ExportFigure_UIFigure);
            app.TabGroup = uitabgroup(app.OMZer_ExportFigure_UIFigure);
            app.ToolsTab = uitab(app.TabGroup);
            app.ToolsTab.Title = 'Tools';
            app.Image_Export = uiimage(app.ToolsTab);
            app.Image_Export.ImageClickedFcn = createCallbackFcn(app, @Image_ExportClicked, true);
            app.Image_Export.ImageSource = 'Icon Export.png';
            app.ExportLabel = uilabel(app.ToolsTab);
            app.ExportLabel.Text = 'Export';
            app.CautionScalebarlengthis1mmLabel_2 = uilabel(app.ToolsTab);
            app.CautionScalebarlengthis1mmLabel_2.Text = 'Caution: If signals are plotted, signals data will be exported as a .mat file';
            app.ScaleBarTab = uitab(app.TabGroup);
            app.ScaleBarTab.Title = 'Scale Bar';
            app.ScaleBarCheckBox = uicheckbox(app.ScaleBarTab);
            app.ScaleBarCheckBox.ValueChangedFcn = createCallbackFcn(app, @ScaleBarCheckBoxValueChanged, true);
            app.ScaleBarCheckBox.Text = 'Scale Bar';
            app.Image_ScaleBarColor = uiimage(app.ScaleBarTab);
            app.Image_ScaleBarColor.ImageClickedFcn = createCallbackFcn(app, @Image_ScaleBarColorClicked, true);
            app.Image_ScaleBarColor.ImageSource = 'Icon Color.png';
            app.ScaleBarColorLabel = uilabel(app.ScaleBarTab);
            app.ScaleBarColorLabel.Text = 'Scale Bar Color';
            app.FieldofViewmmSpinnerLabel = uilabel(app.ScaleBarTab);
            app.FieldofViewmmSpinnerLabel.Text = 'Field of View (mm)';
            app.FieldofViewmmSpinner = uispinner(app.ScaleBarTab);
            app.FieldofViewmmSpinner.ValueChangingFcn = createCallbackFcn(app, @FieldofViewmmSpinnerValueChanging, true);
            app.FieldofViewmmSpinner.Limits = [1 Inf];
            app.FieldofViewmmSpinner.ValueDisplayFormat = '%.0f';
            app.FieldofViewmmSpinner.ValueChangedFcn = createCallbackFcn(app, @FieldofViewmmSpinnerValueChanged, true);
            app.FieldofViewmmSpinner.Value = 15;
            app.ScaleBarSizeSpinnerLabel = uilabel(app.ScaleBarTab);
            app.ScaleBarSizeSpinnerLabel.Text = 'Scale Bar Size';
            app.ScaleBarSizeSpinner = uispinner(app.ScaleBarTab);
            app.ScaleBarSizeSpinner.ValueChangingFcn = createCallbackFcn(app, @ScaleBarSizeSpinnerValueChanging, true);
            app.ScaleBarSizeSpinner.Limits = [1 Inf];
            app.ScaleBarSizeSpinner.ValueDisplayFormat = '%.0f';
            app.ScaleBarSizeSpinner.ValueChangedFcn = createCallbackFcn(app, @ScaleBarSizeSpinnerValueChanged, true);
            app.ScaleBarSizeSpinner.Value = 10;
            app.CautionScalebarlengthis1mmLabel = uilabel(app.ScaleBarTab);
            app.CautionScalebarlengthis1mmLabel.Text = 'Caution: Scale bar length is 1 mm';
            app.MarkerTab = uitab(app.TabGroup);
            app.MarkerTab.Title = 'Marker';
            app.MarkerSizeSpinnerLabel = uilabel(app.MarkerTab);
            app.MarkerSizeSpinnerLabel.Text = 'Marker Size';
            app.MarkerSizeSpinner = uispinner(app.MarkerTab);
            app.MarkerSizeSpinner.ValueChangingFcn = createCallbackFcn(app, @MarkerSizeSpinnerValueChanging, true);
            app.MarkerSizeSpinner.Limits = [10 Inf];
            app.MarkerSizeSpinner.ValueDisplayFormat = '%.0f';
            app.MarkerSizeSpinner.ValueChangedFcn = createCallbackFcn(app, @MarkerSizeSpinnerValueChanged, true);
            app.MarkerSizeSpinner.Value = 10;
            app.CautionExportsignallengthisthesameasviewlengthLabel = uilabel(app.MarkerTab);
            app.CautionExportsignallengthisthesameasviewlengthLabel.Text = 'Caution: Export signal length is the same as view length';
            app.Image_AddAMarker = uiimage(app.MarkerTab);
            app.Image_AddAMarker.ImageClickedFcn = createCallbackFcn(app, @Image_AddAMarkerClicked, true);
            app.Image_AddAMarker.ImageSource = 'Icon Plus.png';
            app.Image_DeleteAMarker = uiimage(app.MarkerTab);
            app.Image_DeleteAMarker.ImageClickedFcn = createCallbackFcn(app, @Image_DeleteAMarkerClicked, true);
            app.Image_DeleteAMarker.ImageSource = 'Icon Delete.png';
            app.AddAMarkerLabel = uilabel(app.MarkerTab);
            app.AddAMarkerLabel.Text = 'Add A Marker';
            app.DeleteAMarkerLabel = uilabel(app.MarkerTab);
            app.DeleteAMarkerLabel.Text = 'Delete A Marker';
            app.FigureTab = uitab(app.TabGroup);
            app.FigureTab.Title = 'Figure';
            app.OnlyShowForegroundCheckBox = uicheckbox(app.FigureTab);
            app.OnlyShowForegroundCheckBox.ValueChangedFcn = createCallbackFcn(app, @OnlyShowForegroundCheckBoxValueChanged, true);
            app.OnlyShowForegroundCheckBox.Text = 'Only Show Foreground';
            app.FigureBoxCheckBox = uicheckbox(app.FigureTab);
            app.FigureBoxCheckBox.Text = 'Figure Box';
            app.BoxLineWidthSpinnerLabel = uilabel(app.FigureTab);
            app.BoxLineWidthSpinnerLabel.Text = 'Box LineWidth';
            app.BoxLineWidthSpinner = uispinner(app.FigureTab);
            app.BoxLineWidthSpinner.Limits = [1 Inf];
            app.BoxLineWidthSpinner.ValueDisplayFormat = '%.0f';
            app.BoxLineWidthSpinner.Value = 1;
            app.CautionBoxLineWidthonlyworkswhenFigureBoxischeckedLabel = uilabel(app.FigureTab);
            app.CautionBoxLineWidthonlyworkswhenFigureBoxischeckedLabel.Text = 'Caution: "Box LineWidth" only works when "Figure Box" is checked; click "Export" to see the effect';
            app.XYLabel = uilabel(app.OMZer_ExportFigure_UIFigure);
            app.XYLabel.Tooltip = {'X (red) and Y (blue) coordinate of the marker. Please be noted that X is column and Y is row to locate an element in a matrix in MATLAB.'};
            app.XYLabel.Text = 'X,Y';
            app.Y_scrn1 = uieditfield(app.OMZer_ExportFigure_UIFigure, 'numeric');
            app.Y_scrn1.Limits = [0 100];
            app.Y_scrn1.ValueDisplayFormat = '%.0f';
            app.Y_scrn1.ValueChangedFcn = createCallbackFcn(app, @XY_scrnValueChanged, true);
            app.Y_scrn1.Editable = 'off';
            app.Y_scrn1.Tooltip = {'Y coordinate of the 1st marker (Note: row in a matlab matrix)'};
            app.X_scrn1 = uieditfield(app.OMZer_ExportFigure_UIFigure, 'numeric');
            app.X_scrn1.Limits = [0 100];
            app.X_scrn1.ValueDisplayFormat = '%.0f';
            app.X_scrn1.ValueChangedFcn = createCallbackFcn(app, @XY_scrnValueChanged, true);
            app.X_scrn1.Editable = 'off';
            app.X_scrn1.Tooltip = {'X coordinate of the 1st marker (Note: column in a matlab matrix)'};
            app.Y_scrn2 = uieditfield(app.OMZer_ExportFigure_UIFigure, 'numeric');
            app.Y_scrn2.Limits = [0 100];
            app.Y_scrn2.ValueDisplayFormat = '%.0f';
            app.Y_scrn2.ValueChangedFcn = createCallbackFcn(app, @XY_scrnValueChanged, true);
            app.Y_scrn2.Editable = 'off';
            app.Y_scrn2.Tooltip = {'Y coordinate of the 1st marker (Note: row in a matlab matrix)'};
            app.X_scrn2 = uieditfield(app.OMZer_ExportFigure_UIFigure, 'numeric');
            app.X_scrn2.Limits = [0 100];
            app.X_scrn2.ValueDisplayFormat = '%.0f';
            app.X_scrn2.ValueChangedFcn = createCallbackFcn(app, @XY_scrnValueChanged, true);
            app.X_scrn2.Editable = 'off';
            app.X_scrn2.Tooltip = {'X coordinate of the 1st marker (Note: column in a matlab matrix)'};
            app.Y_scrn3 = uieditfield(app.OMZer_ExportFigure_UIFigure, 'numeric');
            app.Y_scrn3.Limits = [0 100];
            app.Y_scrn3.ValueDisplayFormat = '%.0f';
            app.Y_scrn3.ValueChangedFcn = createCallbackFcn(app, @XY_scrnValueChanged, true);
            app.Y_scrn3.Editable = 'off';
            app.Y_scrn3.Tooltip = {'Y coordinate of the 1st marker (Note: row in a matlab matrix)'};
            app.X_scrn3 = uieditfield(app.OMZer_ExportFigure_UIFigure, 'numeric');
            app.X_scrn3.Limits = [0 100];
            app.X_scrn3.ValueDisplayFormat = '%.0f';
            app.X_scrn3.ValueChangedFcn = createCallbackFcn(app, @XY_scrnValueChanged, true);
            app.X_scrn3.Editable = 'off';
            app.X_scrn3.Tooltip = {'X coordinate of the 1st marker (Note: column in a matlab matrix)'};
            app.Y_scrn4 = uieditfield(app.OMZer_ExportFigure_UIFigure, 'numeric');
            app.Y_scrn4.Limits = [0 100];
            app.Y_scrn4.ValueDisplayFormat = '%.0f';
            app.Y_scrn4.ValueChangedFcn = createCallbackFcn(app, @XY_scrnValueChanged, true);
            app.Y_scrn4.Editable = 'off';
            app.Y_scrn4.Tooltip = {'Y coordinate of the 1st marker (Note: row in a matlab matrix)'};
            app.X_scrn4 = uieditfield(app.OMZer_ExportFigure_UIFigure, 'numeric');
            app.X_scrn4.Limits = [0 100];
            app.X_scrn4.ValueDisplayFormat = '%.0f';
            app.X_scrn4.ValueChangedFcn = createCallbackFcn(app, @XY_scrnValueChanged, true);
            app.X_scrn4.Editable = 'off';
            app.X_scrn4.Tooltip = {'X coordinate of the 1st marker (Note: column in a matlab matrix)'};
            app.Y_scrn5 = uieditfield(app.OMZer_ExportFigure_UIFigure, 'numeric');
            app.Y_scrn5.Limits = [0 100];
            app.Y_scrn5.ValueDisplayFormat = '%.0f';
            app.Y_scrn5.ValueChangedFcn = createCallbackFcn(app, @XY_scrnValueChanged, true);
            app.Y_scrn5.Editable = 'off';
            app.Y_scrn5.Tooltip = {'Y coordinate of the 1st marker (Note: row in a matlab matrix)'};
            app.X_scrn5 = uieditfield(app.OMZer_ExportFigure_UIFigure, 'numeric');
            app.X_scrn5.Limits = [0 100];
            app.X_scrn5.ValueDisplayFormat = '%.0f';
            app.X_scrn5.ValueChangedFcn = createCallbackFcn(app, @XY_scrnValueChanged, true);
            app.X_scrn5.Editable = 'off';
            app.X_scrn5.Tooltip = {'X coordinate of the 1st marker (Note: column in a matlab matrix)'};
            app.Y_scrn6 = uieditfield(app.OMZer_ExportFigure_UIFigure, 'numeric');
            app.Y_scrn6.Limits = [0 100];
            app.Y_scrn6.ValueDisplayFormat = '%.0f';
            app.Y_scrn6.ValueChangedFcn = createCallbackFcn(app, @XY_scrnValueChanged, true);
            app.Y_scrn6.Editable = 'off';
            app.Y_scrn6.Tooltip = {'Y coordinate of the 1st marker (Note: row in a matlab matrix)'};
            app.X_scrn6 = uieditfield(app.OMZer_ExportFigure_UIFigure, 'numeric');
            app.X_scrn6.Limits = [0 100];
            app.X_scrn6.ValueDisplayFormat = '%.0f';
            app.X_scrn6.ValueChangedFcn = createCallbackFcn(app, @XY_scrnValueChanged, true);
            app.X_scrn6.Editable = 'off';
            app.X_scrn6.Tooltip = {'X coordinate of the 1st marker (Note: column in a matlab matrix)'};
            app.ViewStartsecSpinnerLabel = uilabel(app.OMZer_ExportFigure_UIFigure);
            app.ViewStartsecSpinnerLabel.Text = 'View Start (sec)';
            app.ViewStartsecSpinner = uispinner(app.OMZer_ExportFigure_UIFigure);
            app.ViewStartsecSpinner.Step = 0.05;
            app.ViewStartsecSpinner.ValueChangingFcn = createCallbackFcn(app, @ViewStartsecSpinnerValueChanging, true);
            app.ViewStartsecSpinner.Limits = [0 Inf];
            app.ViewStartsecSpinner.ValueChangedFcn = createCallbackFcn(app, @ViewStartsecSpinnerValueChanged, true);
            app.ViewEndsecSpinnerLabel = uilabel(app.OMZer_ExportFigure_UIFigure);
            app.ViewEndsecSpinnerLabel.Text = 'View End (sec)';
            app.ViewEndsecSpinner = uispinner(app.OMZer_ExportFigure_UIFigure);
            app.ViewEndsecSpinner.Step = 0.05;
            app.ViewEndsecSpinner.ValueChangingFcn = createCallbackFcn(app, @ViewEndsecSpinnerValueChanging, true);
            app.ViewEndsecSpinner.Limits = [0 Inf];
            app.ViewEndsecSpinner.ValueChangedFcn = createCallbackFcn(app, @ViewEndsecSpinnerValueChanged, true);
            app.ViewEndsecSpinner.Value = Inf;
            app.Image_RestoreView = uiimage(app.OMZer_ExportFigure_UIFigure);
            app.Image_RestoreView.ImageClickedFcn = createCallbackFcn(app, @Image_RestoreViewClicked, true);
            app.Image_RestoreView.ImageSource = 'Icon Restore View.png';
            app.RestoreViewLabel = uilabel(app.OMZer_ExportFigure_UIFigure);
            app.RestoreViewLabel.Text = 'Restore View';
            % Show the figure after all components are created
            app.OMZer_ExportFigure_UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OMZer_ExportFigure(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.OMZer_ExportFigure_UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.OMZer_ExportFigure_UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OMZer_ExportFigure_UIFigure)
        end
    end
end