classdef OMZer_FileInformation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OMZer_FileInformation_UIFigure  matlab.ui.Figure
        BatchMaskingLabel               matlab.ui.control.Label
        Image_BatchMasking              matlab.ui.control.Image
        StartLabel                      matlab.ui.control.Label
        Image_Start                     matlab.ui.control.Image
        ConfirmSelectionLabel           matlab.ui.control.Label
        Image_ConfirmSelection          matlab.ui.control.Image
        UndoSelectionLabel              matlab.ui.control.Label
        Image_UndoSelection             matlab.ui.control.Image
        ApplyLabel                      matlab.ui.control.Label
        Image_Apply                     matlab.ui.control.Image
        NormaliseLamp                   matlab.ui.control.Lamp
        TopHatDedriftLamp               matlab.ui.control.Lamp
        NormaliseCheckBox               matlab.ui.control.CheckBox
        TopHatDedriftbetaCheckBox       matlab.ui.control.CheckBox
        PolyDedriftLamp                 matlab.ui.control.Lamp
        PolyDriftDropDown               matlab.ui.control.DropDown
        PolyfitDedriftCheckBox          matlab.ui.control.CheckBox
        SGLamp                          matlab.ui.control.Lamp
        ButterworthLamp                 matlab.ui.control.Lamp
        DenoiseLamp                     matlab.ui.control.Lamp
        ButterworthDropDown             matlab.ui.control.DropDown
        SGCheckBox                      matlab.ui.control.CheckBox
        ButterworthFilterCheckBox       matlab.ui.control.CheckBox
        Denoise60HzCheckBox             matlab.ui.control.CheckBox
        BoxFilterLamp                   matlab.ui.control.Lamp
        BoxFilterDropDown               matlab.ui.control.DropDown
        BoxFilterCheckBox               matlab.ui.control.CheckBox
        InvertLamp                      matlab.ui.control.Lamp
        InvertCheckBox                  matlab.ui.control.CheckBox
        BatchProcessingProgressEditField  matlab.ui.control.NumericEditField
        ProgressEditFieldLabel          matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        BatchProcessingCamera2Button    matlab.ui.control.RadioButton
        BatchProcessingCamera1Button    matlab.ui.control.RadioButton
        BatchProcessingCheckBox         matlab.ui.control.CheckBox
        UserCommentTextArea             matlab.ui.control.TextArea
        UserCommentTextAreaLabel        matlab.ui.control.Label
        RefreshLabel                    matlab.ui.control.Label
        Image_Refresh                   matlab.ui.control.Image
        LoadLabel                       matlab.ui.control.Label
        Image_Load                      matlab.ui.control.Image
        fileListBox                     matlab.ui.control.ListBox
    end

    
    properties (Access = public)
        
        % The Parent App --------------------
        mainApp
        mainAppUIFigure


        % The Current App --------------------
        imageIconActColor = [0.80, 0.80, 0.80]; % The activation color when an image is clicked
        
        colorGroup_Gray_Orange = { [0.90, 0.90, 0.90], [0.93, 0.69, 0.13] }; % Gray and Orange
        colorGroup_Gray_Green = { [0.90, 0.90, 0.90], [0.00,1.00,0.00] }; % Gray and Green
        enableGroup_On_Off = { 'on', 'off' };
        
        conditionLampObject = cell(1,8);
        conditionBoxObject = cell(1,8);
        
        dirName % File directory
        fileNameWithExtension
        fileName % Filename without path and extension
        fileExt % File Extension
        
        gshFileStructure % A cell (n x 3) - stores organized GSH file list based on single/dual cameras
        
        data % All the data from the converted .mat file
        
        % (1) For window key press and release callback
        keyPressHistory
        keyPressID = 1;
    end


    methods (Access = public)
        
        function showImageIconAct(app, imageIconName)
            
            imageIconName.BackgroundColor = app.imageIconActColor; %[0.80, 0.80, 0.80];
            pause(0.05)
            imageIconName.BackgroundColor = 'none';
        end


        function resetBatchProcessingConditionComponents(app)
            
            % Condition check box
            app.InvertCheckBox.Value = 0;
            app.BoxFilterCheckBox.Value = 0;
            app.Denoise60HzCheckBox.Value = 0;
            app.ButterworthFilterCheckBox.Value = 0;
            app.SGCheckBox.Value = 0;
            app.PolyfitDedriftCheckBox.Value = 0;
            app.TopHatDedriftbetaCheckBox.Value = 0;
            app.NormaliseCheckBox.Value = 0;
            
            % Condition drop down
            app.BoxFilterDropDown.Value = '3';
            app.ButterworthDropDown.Value = '100';
            app.PolyDriftDropDown.Value = '1';
            
            % Condition lamp
            app.InvertLamp.Color = [0.90, 0.90, 0.90]; % Gray
            app.BoxFilterLamp.Color = [0.90, 0.90, 0.90];
            app.DenoiseLamp.Color = [0.90, 0.90, 0.90];
            app.ButterworthLamp.Color = [0.90, 0.90, 0.90];
            app.SGLamp.Color = [0.90, 0.90, 0.90];
            app.PolyDedriftLamp.Color = [0.90, 0.90, 0.90];
            app.TopHatDedriftLamp.Color = [0.90, 0.90, 0.90];
            app.NormaliseLamp.Color = [0.90, 0.90, 0.90];
        end


        function enableBatchProcessingConditionComponents(app,state)
            
            % Condition check box
            app.InvertCheckBox.Enable = state;
            app.BoxFilterCheckBox.Enable = state;
            app.Denoise60HzCheckBox.Enable = state;
            app.ButterworthFilterCheckBox.Enable = state;
            app.SGCheckBox.Enable = state;
            app.PolyfitDedriftCheckBox.Enable = state;
            app.TopHatDedriftbetaCheckBox.Enable = state;
            app.NormaliseCheckBox.Enable = state;
            
            % Condition drop down
            app.BoxFilterDropDown.Enable = state;
            app.ButterworthDropDown.Enable = state;
            app.PolyDriftDropDown.Enable = state;
        end


        function outputSig = batchConditionSignal(app, inputSig)
            
            sigTemporary = inputSig;
            
            % (1) Inver signal
            if app.InvertCheckBox.Value == 1
                
                msg = 'Invert signal';
                progressBar = uiprogressdlg(app.OMZer_FileInformation_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');
                sigTemporary = invertSignal( sigTemporary );
                close(progressBar)
            end
            
            % (2) Binning signal
            if app.BoxFilterCheckBox.Value == 1
                
                msg = 'Bin signal';
                progressBar = uiprogressdlg(app.OMZer_FileInformation_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');
                sigTemporary = binnigSignal( sigTemporary, str2double( app.BoxFilterDropDown.Value ) );
                close(progressBar)
            end
            
            % (3) Denoise signal
            if app.Denoise60HzCheckBox.Value == 1
                
                msg = 'Remove the 60Hz noise';
                progressBar = uiprogressdlg(app.OMZer_FileInformation_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');
                sigTemporary = denoise60Hz( sigTemporary, app.data.acqFreq );
                close(progressBar)
            end
            
            % (4) Low-pass filter signal
            if app.ButterworthFilterCheckBox.Value == 1
                
                msg = 'Low-pass filter signal';
                progressBar = uiprogressdlg(app.OMZer_FileInformation_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');
                sigTemporary = lowPassFilterSignal( sigTemporary, str2double( app.ButterworthDropDown.Value ), app.data.acqFreq);
                close(progressBar)
            end
            
            % (5) SG filter signal
            if app.SGCheckBox.Value == 1
                
                msg = 'Savitzky-Golay filter signal';
                progressBar = uiprogressdlg(app.OMZer_FileInformation_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');
                sigTemporary = SGFilterSignal( sigTemporary );
                close(progressBar)
            end
            
            % (6) De-drift signal
            if app.PolyfitDedriftCheckBox.Value == 1
                
                msg = 'Polynomial curve fitting dedrift signal';
                progressBar = uiprogressdlg(app.OMZer_FileInformation_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');
                sigTemporary = polynomialDedriftSignal( sigTemporary, str2double( app.PolyDriftDropDown.Value ) );
                close(progressBar)
            end
            
            % (7) Normalise signal
            if app.NormaliseCheckBox.Value == 1
                
                msg = 'Normalise signal';
                progressBar = uiprogressdlg(app.OMZer_FileInformation_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');
                sigTemporary = normaliseSignal( sigTemporary );
                close(progressBar)
            end
            
            % Round data
            % After signal conditioning, there will be lots digits after the decimal which can result in big file size
            % if the user save these data. Here we round the data to its 2nd digits after the decimal; this can
            % reduce the file size while keep the data still precise enough.
            outputSig = round(sigTemporary,2);
        end


        function callByMainApp_Image_RefreshClicked(app)
            
            Image_RefreshClicked(app, [])
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, OMZer)
            
            msg = 'Configuring files';
            progressBar = uiprogressdlg(app.OMZer_FileInformation_UIFigure, 'Title','Initialising', 'Message',msg, 'Indeterminate','on');
            drawnow
            
            app.mainApp = OMZer;
            app.mainAppUIFigure = app.mainApp.OMZer_UIFigure;
            
            app.dirName = app.mainApp.dirName;
            app.gshFileStructure = app.mainApp.gshFileStructure;
            
            app.conditionLampObject = {app.InvertLamp, app.BoxFilterLamp, app.DenoiseLamp, app.ButterworthLamp, app.SGLamp, app.PolyDedriftLamp, app.TopHatDedriftLamp, app.NormaliseLamp};
            app.conditionBoxObject = {app.InvertCheckBox, app.BoxFilterCheckBox, app.Denoise60HzCheckBox, app.ButterworthFilterCheckBox, app.SGCheckBox, app.PolyfitDedriftCheckBox, app.TopHatDedriftbetaCheckBox, app.NormaliseCheckBox};
            
            close(progressBar)
            
        end

        % Value changed function: fileListBox
        function fileListBoxValueChanged(app, event)
            
            if isequal( app.fileListBox.Multiselect, 'off' ) % Single selection
                
                % 'app.fileListBox.Value' is a character
                if isequal( app.fileListBox.Value, '--- --- --- --- --- --- --- --- --- --- RSH Files --- --- --- --- --- --- --- --- --- ---' )  ||  ...
                        isequal( app.fileListBox.Value, '--- --- --- --- --- --- --- --- --- --- GSH Files --- --- --- --- --- --- --- --- --- ---' )  ||  ...
                        isequal( app.fileListBox.Value, '--- --- --- --- --- --- --- --- --- --- MAT Files --- --- --- --- --- --- --- --- --- ---' )
                
                app.fileListBox.Value = {};
                
                app.fileNameWithExtension = [];
                app.fileName = [];
                app.fileExt = [];
                
                else
                    [~, name, ext] = fileparts(app.fileListBox.Value); % Returns the path name, file name, and extension for the specified file.
                    
                    app.fileNameWithExtension = app.fileListBox.Value; % Get the selected file name. 'app.fileNameWithExtension' is a character.
                    app.fileName = name;
                    app.fileExt = ext;
                    
                    if isempty(app.mainApp.FileNameEditField.Value) % Previously no file has been loaded
                        
                        enableMenuComponents(app.mainApp,'off');
                        
                    else
                        if isequal( [app.fileName, app.fileExt], [app.mainApp.FileNameEditField.Value, app.mainApp.fileExtensionLabel.Text] ) % The loaded file is the same as the one the user selected
                            
                            enableMenuComponents(app.mainApp,'on');
                            
                        else
                            enableMenuComponents(app.mainApp,'off'); % A file has been loaded while the user is selecting another one
                        end
                    end
                end


            elseif isequal( app.fileListBox.Multiselect, 'on' ) % Multi Selection
                
                fileList = app.fileListBox.Value'; % 'fileList' is a cell column
                fileList( contains( fileList, '--- --- --- --- --- --- --- --- --- --- RSH Files --- --- --- --- --- --- --- --- --- ---' ) ) = [];
                fileList( contains( fileList, '--- --- --- --- --- --- --- --- --- --- GSH Files --- --- --- --- --- --- --- --- --- ---' ) ) = [];
                fileList( contains( fileList, '--- --- --- --- --- --- --- --- --- --- MAT Files --- --- --- --- --- --- --- --- --- ---' ) ) = [];
                
                app.fileListBox.Value = fileList;
                
                if app.BatchProcessingCheckBox.Value == 1
                    
                    app.mainApp.batchProcessingFileList = fileList;
                end
            end
            
        end

        % Image clicked function: Image_Load
        function Image_LoadClicked(app, event)
            
            showImageIconAct(app, app.Image_Load)
            
            if isempty(app.fileName)
                
                msg = 'Please select a file to load';
                uialert(app.OMZer_FileInformation_UIFigure, msg,'Warning','Icon','error')
                
            else
                
                % (1) Reset to default value
                resetVariables(app.mainApp)
                enableMenuComponents(app.mainApp,'off')
                
                enableConditionComponents(app.mainApp,'on')
                resetConditionComponents(app.mainApp)
                
                enableImageAxisComponents(app.mainApp,'on')
                resetSignalDisplayCompontnes(app.mainApp)
                
                prepareBatchProcessing(app.mainApp)
                enableBatchProcessingComponents(app.mainApp,'off')
                
                closeAllChildrenApp(app.mainApp, {'OMZer_FileInformation'})
                
                % (2) Check if file format conversion is required
                if ~exist( [app.dirName, '/', app.fileName, '.mat'], 'file' )
                    
                    msg = 'Convert the file';
                    progressBar1 = uiprogressdlg(app.OMZer_FileInformation_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');
                    progressBar2 = uiprogressdlg(app.mainAppUIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');
                    
                    if isequal(app.fileExt,'.rsh')
                        
                        CMOSconverter( [app.dirName, '/', app.fileName, '.rsh'] );
                        
                    else
                        index = find( strcmp(app.gshFileStructure(:), app.fileListBox.Value) == 1 );
                        GSDconverter( app.dirName, app.gshFileStructure(index(1), :) );
                    end
                    
                    close(progressBar1)
                    close(progressBar2)
                    figure(app.mainAppUIFigure)
                end
                
                % (3) Load file name information
                app.mainApp.DirectoryEditField.Value = app.mainApp.dirName;
                
                app.mainApp.fileNameWithExtension = app.fileNameWithExtension;
                app.mainApp.fileName = app.fileName;
                app.mainApp.fileExt = app.fileExt;
                
                app.mainApp.FileNameEditField.Value = app.fileName;
                app.mainApp.fileExtensionLabel.Text = app.fileExt;
                
                % (4) Load Data
                msg = 'Loading data';
                progressBar1 = uiprogressdlg(app.OMZer_FileInformation_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');
                progressBar2 = uiprogressdlg(app.mainAppUIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');
                
                app.mainApp.data = load( [app.dirName, '/', app.fileName, '.mat'] );
                
                
                % Check if version migration is required (Part A)
                % oldVersionTF = D_SliceZerMigration( [app.dirName, '/', app.fileName, '.mat'], app.mainApp.data );
                % if oldVersionTF == 1
                %     app.mainApp.data = load( [app.dirName, '/', app.fileName, '.mat'] );
                % end
                
                % Check if version update is required (Part B)
                oldVersionTF = versionConverter( [app.dirName, '/', app.fileName, '.mat'], app.mainApp.data );
                if oldVersionTF == 1
                    app.mainApp.data = load( [app.dirName, '/', app.fileName, '.mat'] );
                end
                
                
                app.data = app.mainApp.data;
                
                app.UserCommentTextArea.Value = app.data.comment;
                app.UserCommentTextArea.Enable = 'on';
                app.mainApp.userComment = app.UserCommentTextArea.Value;
                
                app.mainApp.camTF = app.data.camTF; % camTF = 0 (or 1) means single (or dual)
                app.mainApp.CameraNALabel.Text = [ 'Camera ', num2str(app.mainApp.activeCamera) ];
                
                app.mainApp.signalTime = app.data.signalTime; % Get coms data time series
                app.mainApp.acqFreq = app.data.acqFreq;
                app.mainApp.pcl = app.data.pcl;
                app.mainApp.pacing1 = app.data.analog1;
                app.mainApp.pacing2 = app.data.analog2;
                app.mainApp.pacingTime = app.data.analogTime;
                
                if any(app.mainApp.pacing1) == 1 % pacing signal is stored in channel 1
                    app.mainApp.pacing1CheckBox.Enable = 'on';
                    
                elseif any(app.mainApp.pacing2) == 1 % pacing signal is stored in channel 2
                    app.mainApp.pacing2CheckBox.Enable = 'on';
                end
                
                if app.mainApp.camTF == 0 % Single camera
                    
                    app.mainApp.cmosDataRawMaskFreeGroup = { app.data.cmosData1Raw }; % Unfiltered, mask-free
                    app.mainApp.cmosDataMaskFreeGroup = { app.data.cmosData1 }; % Filtered, mask-free
                    app.mainApp.cmosDataGroup = { app.data.cmosData1 }; % Filtered, masked
                    app.mainApp.bgImageGroup = { app.data.bgImage1 };
                    app.mainApp.bgImageNomAdjustGroup = { app.data.bgImageNom1 }; % Pixel intensity ranges in [0,255]
                    app.mainApp.bgImageNomGroup = { real2rgb(app.data.bgImageNom1,'gray') }; % Gray colour RGB version of background image
                    app.mainApp.maskGroup = { app.data.userMaskMatrix_cam1 };
                    
                else % Dual cameras
                    app.mainApp.LinkCameras.Enable = 'on';
                    app.mainApp.LinkCameras.Value = 0;
                    
                    app.mainApp.cmosDataRawMaskFreeGroup = { app.data.cmosData1Raw, app.data.cmosData2Raw }; % Unfiltered, mask-free
                    app.mainApp.cmosDataMaskFreeGroup = { app.data.cmosData1, app.data.cmosData2 }; % Filtered, mask-free
                    app.mainApp.cmosDataGroup = { app.data.cmosData1, app.data.cmosData2 }; % Filtered, masked
                    app.mainApp.bgImageGroup = { app.data.bgImage1, app.data.bgImage2 };
                    app.mainApp.bgImageNomAdjustGroup = { app.data.bgImageNom1, app.data.bgImageNom2 }; % Pixel intensity ranges in [0,255]
                    app.mainApp.bgImageNomGroup = { real2rgb(app.data.bgImageNom1,'gray'), real2rgb(app.data.bgImageNom2,'gray') }; % Gray colour RGB version of background image
                    app.mainApp.maskGroup = { app.data.userMaskMatrix_cam1, app.data.userMaskMatrix_cam2 };
                end
                
                [app.mainApp.cmosDataRowNum, app.mainApp.cmosDataColNum, ~] = size( app.mainApp.cmosDataGroup{1} ); % Even with dual cameras, both camera will have the same size
                setAxisLimit(app.mainApp, app.mainApp.cmosDataColNum, app.mainApp.cmosDataRowNum);
                
                LB = 0;
                UB = app.mainApp.signalTime(end);
                setScrnLimit(app.mainApp, LB, UB, 'on')
                
                loadUserConditionParameters(app.mainApp)
                setupCameraFrameInfo(app.mainApp)
                
                % Data sectioning information
                app.mainApp.Image_Section.Enable = 'on';
                app.mainApp.sectionStartIndex = 1;
                app.mainApp.sectionEndIndex = length(app.mainApp.signalTime);
                
                % Update active camera
                app.mainApp.activeCamera = 404; % NOTE: because I need to let the following function work, here I temporarily set "app.activeCamera = 404". 404 will only appear at here.
                changeActiveCamera(app.mainApp, 1);
                app.mainApp.activeCamera = 1;
                
                % Set movie slider bar and time indicator sweep bar
                app.mainApp.MoviesecSpinner.Limits = [ 0, app.mainApp.signalTime(end) ];
                app.mainApp.MoviesecSpinner.Value = 0;
                app.mainApp.MoviesecSpinner.Step = 1 / app.mainApp.acqFreq;
                digitsNumberAfterDecimalPoint = num2str( round( abs( log10( 1 / app.mainApp.acqFreq ) ) ) );
                app.mainApp.MoviesecSpinner.ValueDisplayFormat = [ '%.', digitsNumberAfterDecimalPoint, 'f' ];
                
                app.mainApp.MovieSlider.Limits = [ 0, length(app.mainApp.signalTime)-1 ];
                app.mainApp.MovieSlider.Value = 0;
                
                for ID = 1:6
                    app.mainApp.timeIndicators{ID,1} = xline(app.mainApp.scrnObject{ID}, -1, 'Color','red', 'LineWidth',app.mainApp.lWidth);
                end
                
                
                % Automatic plot (depends on setup file)
                for ID = 1:6
                    
                    if app.mainApp.setup.scrnPlotOnOff{ID+1,1} == 1 % '1' means automatic plot 'on'; '0' means 'off'
                        
                        X = app.mainApp.setup.scrnPlotOnOff{ID+1,2};
                        Y = app.mainApp.setup.scrnPlotOnOff{ID+1,3};
                        
                        app.mainApp.markerXY(ID,1:2) = [ X, Y ]; % CAUTION: X is the column in matlab matrix. Y is the row in matlab matrix.
                        app.mainApp.Xs{ID}.Value = X;
                        app.mainApp.Ys{ID}.Value = Y;
                        
                        app.mainApp.Xs{ID}.Editable = 'on';
                        app.mainApp.Ys{ID}.Editable = 'on';
                        
                        app.mainApp.Xs{ID}.BackgroundColor = [1.00, 1.00, 0.00]; % Yellow
                        app.mainApp.Ys{ID}.BackgroundColor = [1.00, 1.00, 0.00];
                        
                        app.mainApp.clickCount = ID;
                        updateMarker(app.mainApp); % Plot a marker where the mouse click.
                        updateSignal(app.mainApp); % Update signal in ONE scrn axis.
                        
                        app.mainApp.clickCount = app.mainApp.clickCount + 1;
                        if app.mainApp.clickCount == 7
                            app.mainApp.clickCount = 1;
                        end
                    end
                end
                
                enableMenuComponents(app.mainApp,'on')
                
                close(progressBar1)
                close(progressBar2)
                
                figure(app.mainAppUIFigure)
            end
            
        end

        % Image clicked function: Image_Refresh
        function Image_RefreshClicked(app, event)
            
            showImageIconAct(app, app.Image_Refresh)
            
            if isempty(app.dirName)
                
                msg = 'Choose a directory first';
                uialert(app.mainAppUIFigure, msg,'Warning','Icon','error')
                uialert(app.OMZer_FileInformation_UIFigure, msg,'Warning','Icon','error')
                
                return
            end
            
            % (1) Prepare 'rsh' file list
            rshFileName = [app.dirName,'/*.rsh']; % Find all the '.rsh' files.
            rshFileName = dir(rshFileName); % "dir" lists folder contents. Now 'rshFileName' is a structure.
            rshFileName = struct2cell(rshFileName); % "struct2cell" turns the struct to a cell. Now 'rshFileName' is a cell.
            app.mainApp.rshFileList = rshFileName(1,:)'; % Create a cell column list for .rsh files.
            if isempty(app.mainApp.rshFileList)
                app.mainApp.rshFileList = {};
            end
            
            % (2) Prepare 'mat' file list
            matFileName = [app.dirName,'/*.mat']; % Find all the '.mat' files.
            matFileName = dir(matFileName);
            matFileName = struct2cell(matFileName);
            app.mainApp.matFileList = matFileName(1,:)'; % Create a cell column list for .mat files.
            if isempty(app.mainApp.matFileList)
                app.mainApp.matFileList = {};
            end
            
            % (3) Prepare 'gsh' file list
            gshFileName = [app.dirName,'/*.gsh'];
            gshFileName = dir(gshFileName);
            gshFileName = struct2cell(gshFileName);
            app.mainApp.gshFileList = gshFileName(1,:)'; % Create a cell column list for .gsh files.
            if isempty(app.mainApp.gshFileList)
                app.mainApp.gshFileList = {};
            end
            organizeGSHFiles(app.mainApp); % Creates the 'gshFileStructure
            
            % (4) List all files ('allFileList' is a cell column)
            app.mainApp.allFileList = [ {'--- --- --- --- --- --- --- --- --- --- RSH Files --- --- --- --- --- --- --- --- --- ---'}; ...
                app.mainApp.rshFileList; ...
                {'--- --- --- --- --- --- --- --- --- --- GSH Files --- --- --- --- --- --- --- --- --- ---'}; ...
                app.mainApp.gshFileStructure(:,1); ...
                {'--- --- --- --- --- --- --- --- --- --- MAT Files --- --- --- --- --- --- --- --- --- ---'}; ...
                app.mainApp.matFileList ]; % Concatenate cell arrays with the array concatenation operator, [].  'https://www.mathworks.com/help/matlab/matlab_prog/combine-cell-arrays.html'
            
            if isempty(app.mainApp.allFileList)
                app.mainApp.allFileList = {};
            end
            app.fileListBox.Items = app.mainApp.allFileList; % 'Items' shows what files there are in the user-selected folder
            app.fileListBox.Value = {}; % 'Value = {}' means there is no default selection of a file
            
            prepareBatchProcessing(app.mainApp)
            enableBatchProcessingComponents(app.mainApp,'off')
            
            % （5）Check if reset OMZer is required
            if isempty(app.mainApp.rshFileList)  &&  isempty(app.mainApp.matFileList)  &&  isempty(app.mainApp.gshFileList)
                
                app.BatchProcessingCheckBox.Enable = 'off';
                
                msg = 'No .rsh, .gsh, and/or .mat files are found!';
                uialert(app.mainAppUIFigure, msg,'Warning','Icon','error')
                uialert(app.OMZer_FileInformation_UIFigure, msg,'Warning','Icon','error')
                
                % Reset to default value
                resetVariables(app.mainApp)
                enableMenuComponents(app.mainApp,'off')
                
                enableConditionComponents(app.mainApp,'off')
                resetConditionComponents(app.mainApp)
                
                enableImageAxisComponents(app.mainApp,'off')
                resetSignalDisplayCompontnes(app.mainApp)
                
                closeAllChildrenApp(app.mainApp, {'OMZer_FileInformation'})
                
                app.fileName = [];
                app.fileNameWithExtension = [];
                
                app.mainApp.fileName = [];
                app.mainApp.fileNameWithExtension = [];
                app.mainApp.DirectoryEditField.Value = app.dirName;
                
                
            else
                
                app.BatchProcessingCheckBox.Enable = 'on';
                
                if ~isempty( app.mainApp.FileNameEditField.Value ) % Before 'refresh', user has loaded a file
                    
                    if any( contains( app.mainApp.allFileList, [app.mainApp.FileNameEditField.Value, app.mainApp.fileExtensionLabel.Text] ) ) == 1 % Check if the previously loaded file exsits in the new file list after 'refresh'. '1' means do exist.
                        
                        app.fileListBox.Value = [app.mainApp.FileNameEditField.Value, app.mainApp.fileExtensionLabel.Text]; % Highlight the file previously loaded before 'refresh'.
                        enableMenuComponents(app.mainApp,'on')
                        
                    else
                        % User delet the previously loaded file from the directory. Reset OMZer to default value
                        resetVariables(app.mainApp)
                        enableMenuComponents(app.mainApp,'off')
                        
                        enableConditionComponents(app.mainApp,'off')
                        resetConditionComponents(app.mainApp)
                        
                        enableImageAxisComponents(app.mainApp,'off')
                        resetSignalDisplayCompontnes(app.mainApp)
                        
                        closeAllChildrenApp(app.mainApp, {'OMZer_FileInformation'})
                        
                        app.fileName = [];
                        app.fileNameWithExtension = [];
                        
                        app.mainApp.fileName = [];
                        app.mainApp.fileNameWithExtension = [];
                        app.mainApp.DirectoryEditField.Value = app.dirName;
                    end
                    
                    
                else % Before 'refresh', there is no loaded file. Reset OMZer to default value
                    resetVariables(app.mainApp)
                    enableMenuComponents(app.mainApp,'off')
                    
                    enableConditionComponents(app.mainApp,'off')
                    resetConditionComponents(app.mainApp)
                    
                    enableImageAxisComponents(app.mainApp,'off')
                    resetSignalDisplayCompontnes(app.mainApp)
                    
                    closeAllChildrenApp(app.mainApp, {'OMZer_FileInformation'})
                    
                    app.fileName = [];
                    app.fileNameWithExtension = [];
                    
                    app.mainApp.fileName = [];
                    app.mainApp.fileNameWithExtension = [];
                    app.mainApp.DirectoryEditField.Value = app.dirName;
                end
            end
            
        end

        % Image clicked function: Image_BatchMasking
        function Image_BatchMaskingClicked(app, event)
            
            showImageIconAct(app, app.Image_BatchMasking)
            
            OMZer_BatchMasking
            
        end

        % Value changed function: UserCommentTextArea
        function UserCommentTextAreaValueChanged(app, event)
            
            app.mainApp.userComment = app.UserCommentTextArea.Value;
            
        end

        % Value changed function: BatchProcessingCheckBox
        function BatchProcessingCheckBoxValueChanged(app, event)
            
            if app.BatchProcessingCheckBox.Value == 1
                
                if isempty( app.fileListBox.Items )
                    
                    msg = 'No ".rsh" or ".gsh" files can be found.';
                    uialert(app.OMZer_FileInformation_UIFigure, msg,'Warning','Icon','error')
                    
                    app.BatchProcessingCheckBox.Value = 0;
                    
                else
                    
                    if ( isempty(app.mainApp.rshFileList) )  &&  ( isempty(app.mainApp.gshFileList) ) % Although there are files, there are no '.rsh' and '.gsh' files
                        
                        msg = 'No ".rsh" or ".gsh" files can be found.';
                        uialert(app.OMZer_FileInformation_UIFigure, msg,'Warning','Icon','error')
                        
                        app.BatchProcessingCheckBox.Value = 0;
                        
                    else
                        % There are '.rsh' or 'gsh' files
                        
                        msg = 'This will reset the app to its original state. Do you want to continue?';
                        msgTitle = 'Warning';
                        selection = uiconfirm(app.OMZer_FileInformation_UIFigure,msg,msgTitle, 'Options',{'Continue','Cancel'},'DefaultOption',2, 'CancelOption',2);
                        
                        if isequal( selection, 'Cancel' )
                            
                            app.BatchProcessingCheckBox.Value = 0;
                            
                        else
                            
                            % (1) Reset to default value
                            resetVariables(app.mainApp)
                            enableMenuComponents(app.mainApp,'off')
                            
                            enableConditionComponents(app.mainApp,'off')
                            resetConditionComponents(app.mainApp)
                            
                            enableImageAxisComponents(app.mainApp,'off')
                            resetSignalDisplayCompontnes(app.mainApp)
                            
                            app.Image_Load.Enable = 'off';
                            app.Image_Refresh.Enable = 'off';
                            
                            closeAllChildrenApp(app.mainApp, {'OMZer_FileInformation'})
                            
                            app.fileName = [];
                            app.fileNameWithExtension = [];
                            
                            app.mainApp.fileName = [];
                            app.mainApp.fileNameWithExtension = [];
                            app.mainApp.DirectoryEditField.Value = app.dirName;
                            
                            % (2) Prepare for batch processing
                            app.fileListBox.BackgroundColor = [ 0.98, 0.91, 0.69 ]; % Yellowish
                            app.fileListBox.Multiselect = 'on';
                            app.fileListBox.Value = {}; % Don't have any default selection
                            
                            app.Image_ConfirmSelection.Enable = 'on';
                            app.Image_UndoSelection.Enable = 'off';
                            
                            resetBatchProcessingConditionComponents(app)
                            enableBatchProcessingConditionComponents(app,'off')
                            enableBatchProcessingComponents(app.mainApp, 'off')
                        end
                    end
                end


            else
                
                % (1) Reset to default value
                resetVariables(app.mainApp)
                enableMenuComponents(app.mainApp,'off')
                
                enableConditionComponents(app.mainApp,'off')
                resetConditionComponents(app.mainApp)
                
                enableImageAxisComponents(app.mainApp,'off')
                resetSignalDisplayCompontnes(app.mainApp)
                
                closeAllChildrenApp(app.mainApp, {'OMZer_FileInformation'})
                
                app.fileName = [];
                app.fileNameWithExtension = [];
                
                app.mainApp.fileName = [];
                app.mainApp.fileNameWithExtension = [];
                app.mainApp.DirectoryEditField.Value = app.dirName;
                
                app.Image_Load.Enable = 'on';
                app.Image_Refresh.Enable = 'on';
                
                closeAllChildrenApp(app.mainApp, {'OMZer_FileInformation'})
                
                app.fileListBox.BackgroundColor = [ 1.00, 1.00, 1.00 ]; % White
                app.fileListBox.Multiselect = 'off';
                app.fileListBox.Enable = 'on';
                app.fileListBox.Value = {};
                
                app.mainApp.batchProcessingFileList = {};
                
                app.Image_ConfirmSelection.Enable = 'off';
                app.Image_UndoSelection.Enable = 'off';
                
                resetBatchProcessingConditionComponents(app)
                enableBatchProcessingConditionComponents(app,'off')
                enableBatchProcessingComponents(app.mainApp, 'off')
            end
            
        end

        % Image clicked function: Image_ConfirmSelection
        function Image_ConfirmSelectionClicked(app, event)
            
            showImageIconAct(app, app.Image_ConfirmSelection)
            
            if isempty( app.mainApp.batchProcessingFileList )
                
                msg = 'No ".rsh" or ".gsh" files have been selected.';
                uialert(app.OMZer_FileInformation_UIFigure, msg, 'Warning','Icon','error')
                
                
            else
                
                if isrow( app.mainApp.batchProcessingFileList ) % 'app.mainApp.batchProcessingFileList' is a cell
                    app.mainApp.batchProcessingFileList = app.mainApp.batchProcessingFileList'; % We need a column list
                end
                
                
                % The user only selected '.mat' files
                if any( contains(app.mainApp.batchProcessingFileList, '.mat') ) == 1  &&  ...
                        any( contains(app.mainApp.batchProcessingFileList, '.rsh') ) == 0  &&  ...
                        any( contains(app.mainApp.batchProcessingFileList, '.gsh') ) == 0
                
                msg = 'Please only select ".rsh" or ".gsh" files.';
                uialert(app.OMZer_FileInformation_UIFigure, msg, 'Warning','Icon','error')
                
                
                else
                    
                    app.mainApp.batchProcessingFileList( contains(app.mainApp.batchProcessingFileList, '.mat') ) = [];
                    app.fileListBox.Value = app.mainApp.batchProcessingFileList;
                    app.fileListBox.Enable = 'off';
                    
                    app.Image_ConfirmSelection.Enable = 'off';
                    app.Image_UndoSelection.Enable = 'on';
                    
                    resetBatchProcessingConditionComponents(app)
                    enableBatchProcessingConditionComponents(app,'on')
                    app.TopHatDedriftbetaCheckBox.Enable = 'off'; % Don't let user use this function
                    enableBatchProcessingComponents(app.mainApp, 'on')
                end
            end
            
        end

        % Image clicked function: Image_UndoSelection
        function Image_UndoSelectionClicked(app, event)
            
            showImageIconAct(app, app.Image_UndoSelection)
            
            app.fileListBox.Enable = 'on';
            app.fileListBox.Value = {};
            
            app.mainApp.batchProcessingFileList = {};
            
            app.Image_ConfirmSelection.Enable = 'on';
            app.Image_UndoSelection.Enable = 'off';
            
            resetBatchProcessingConditionComponents(app)
            enableBatchProcessingConditionComponents(app,'off')
            enableBatchProcessingComponents(app.mainApp, 'off')
            
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            
            if app.ButtonGroup.SelectedObject == app.BatchProcessingCamera1Button
                cameraID = 1;
            else
                cameraID = 2;
            end
            
            for ID = 1 : 8
                app.conditionLampObject{ID}.Color = app.colorGroup_Gray_Green{ app.mainApp.batchProcessingLampOnOff{ cameraID, ID } + 1 }; % Value == 0 (or 1), Color == Gray (or Green)
                app.conditionBoxObject{ID}.Value = app.mainApp.batchProcessingLampOnOff{ cameraID, ID };
            end
            
            % Binnig
            app.BoxFilterDropDown.Value = app.mainApp.batchProcessingBoxFilterDropDownValue{cameraID,1};
            
            % Butterworth Filter
            app.ButterworthDropDown.Value = app.mainApp.batchProcessingButterworthDropDownValue{cameraID,1};
            
            % Polyfit Dedrift
            app.PolyDriftDropDown.Value = app.mainApp.batchProcessingPolyDriftDropDownValue{cameraID,1};
            
            
            if app.mainApp.batchProcessingLampOnOff{cameraID,4} == 1 % 'Butterworth' is checked
                app.SGCheckBox.Enable = 'off';
            else
                app.SGCheckBox.Enable = 'on';
            end
            if app.mainApp.batchProcessingLampOnOff{cameraID,5} == 1 % 'Savitzky-Golay' is checked
                app.ButterworthFilterCheckBox.Enable = 'off';
                app.ButterworthDropDown.Enable = 'off';
            else
                app.ButterworthFilterCheckBox.Enable = 'on';
                app.ButterworthDropDown.Enable = 'on';
            end
            
        end

        % Image clicked function: Image_Apply
        function Image_ApplyClicked(app, event)
            
            showImageIconAct(app, app.Image_Apply)
            
            if app.ButtonGroup.SelectedObject == app.BatchProcessingCamera1Button
                cameraID = 1;
            else
                cameraID = 2;
            end
            
            for ID = 1 : 8
                app.conditionLampObject{ID}.Color = app.colorGroup_Gray_Green{ app.conditionBoxObject{ID}.Value + 1 }; % Value == 0 (or 1), Color == Gray (or Green)
                app.mainApp.batchProcessingLampOnOff{ cameraID, ID } = app.conditionBoxObject{ID}.Value;
            end
            
            
            % Binnig
            app.mainApp.batchProcessingBoxFilterDropDownValue{cameraID,1} = app.BoxFilterDropDown.Value;
            
            % Butterworth Filter
            app.mainApp.batchProcessingButterworthDropDownValue{cameraID,1} = app.ButterworthDropDown.Value;
            
            % Polyfit Dedrift
            app.mainApp.batchProcessingPolyDriftDropDownValue{cameraID,1} = app.PolyDriftDropDown.Value;
            
        end

        % Image clicked function: Image_Start
        function Image_StartClicked(app, event)
            
            showImageIconAct(app, app.Image_Start)
            
            msg = 'Are you ready to start batch processing?';
            msgTitle = 'Warning';
            selection = uiconfirm(app.OMZer_FileInformation_UIFigure,msg,msgTitle, 'Options',{'Yes','No'},'DefaultOption',2, 'CancelOption',2);
            
            if isequal( selection, 'Yes' )
                
                app.BatchProcessingCheckBox.Value = 0;
                app.BatchProcessingCheckBox.Enable = 'off';
                app.Image_ConfirmSelection.Enable = 'off';
                app.Image_UndoSelection.Enable = 'off';
                app.BatchProcessingCamera1Button.Enable = 'off';
                app.BatchProcessingCamera2Button.Enable = 'off';
                app.Image_Apply.Enable = 'off';
                app.Image_Start.Enable = 'off';
                
                totalFileNum = length(app.mainApp.batchProcessingFileList);
                
                for fileID = 1 : totalFileNum
                    
                    fileNameWithExt = app.mainApp.batchProcessingFileList{fileID}; % 'fileNameWithExt' is a character (file name + extension)
                    [~, fileNameOnly, fileFormat] = fileparts(fileNameWithExt);
                    
                    % (1) Convert the file
                    msg = 'Convert the file';
                    progressBar = uiprogressdlg(app.OMZer_FileInformation_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');
                    
                    if isequal(fileFormat, '.rsh')
                        CMOSconverter( [app.dirName, '/', fileNameOnly, '.rsh'] );
                    else
                        index = find( strcmp(app.gshFileStructure(:), fileNameWithExt) == 1 );
                        GSDconverter( app.dirName, app.gshFileStructure(index(1), :) ); % Convert '.gsh to '.mat' files
                    end
                    close(progressBar)
                    
                    % (2) Load the file
                    msg = 'Load the file';
                    progressBar = uiprogressdlg(app.OMZer_FileInformation_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');
                    
                    app.data = load([app.dirName, '/', fileNameOnly, '.mat']); % Load converted data to SliceZer System
                    signalConditioningInfo = {};
                    
                    close(progressBar)
                    
                    % (3) Signal condition
                    if app.data.camTF == 0 % Single camera
                        
                        % Signal conditioning for camera 1
                        app.BatchProcessingCamera1Button.Value = 1;
                        ButtonGroupSelectionChanged(app, event)
                        
                        if (app.InvertCheckBox.Value == 1)  ...
                                ||  (app.BoxFilterCheckBox.Value == 1)  ...
                                ||  (app.Denoise60HzCheckBox.Value == 1)  ...
                                ||  (app.ButterworthFilterCheckBox.Value == 1)  ...
                                ||  (app.SGCheckBox.Value == 1)  ...
                                ||  (app.PolyfitDedriftCheckBox.Value == 1)  ...
                                ||  (app.NormaliseCheckBox.Value == 1)
                        
                        needToSave_Cam1 = 1;
                        else
                            needToSave_Cam1 = 0;
                        end
                        
                        % Signal conditioning information
                        signalConditioningInfo(1,:) = {'Conditioning Name',     'Camera 1 - Condition On (1) / Off (0)',        'Camera 1 - Other Info'};
                        signalConditioningInfo(2,:) = {'maskOnOff',             0,                                              'N/A'};
                        signalConditioningInfo(3,:) = {'InvertOnOff',           app.mainApp.batchProcessingLampOnOff{1,1},      'N/A'};
                        signalConditioningInfo(4,:) = {'BoxFilterOnOff',        app.mainApp.batchProcessingLampOnOff{1,2},      app.mainApp.batchProcessingBoxFilterDropDownValue{1,1}};
                        signalConditioningInfo(5,:) = {'DenoiseOnOff',          app.mainApp.batchProcessingLampOnOff{1,3},      'N/A'};
                        signalConditioningInfo(6,:) = {'ButterworthOnOff',      app.mainApp.batchProcessingLampOnOff{1,4},      app.mainApp.batchProcessingButterworthDropDownValue{1,1}};
                        signalConditioningInfo(7,:) = {'SGOnOff',               app.mainApp.batchProcessingLampOnOff{1,5},      'N/A'};
                        signalConditioningInfo(8,:) = {'PolyDedriftOnOff',      app.mainApp.batchProcessingLampOnOff{1,6},      app.mainApp.batchProcessingPolyDriftDropDownValue{1,1}};
                        signalConditioningInfo(9,:) = {'TopHatDedriftOnOff',    0,                                              'N/A'};
                        signalConditioningInfo(10,:) = {'NormaliseOnOff',       app.mainApp.batchProcessingLampOnOff{1,8},      'N/A'};
                        
                        % Save conditioned data to the file
                        if needToSave_Cam1 == 1
                            
                            cmosData1 = app.data.cmosData1Raw; % Unfiltered, mask-free
                            cmosData1 = batchConditionSignal(app, cmosData1); % Condition signals
                            
                            msg = 'Save the conditioned data';
                            progressBar = uiprogressdlg(app.OMZer_FileInformation_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');
                            
                            save( [app.dirName, '/', fileNameOnly, '.mat'], 'cmosData1', 'signalConditioningInfo', '-append' )
                            
                            close(progressBar)
                        end


                    else % Dual cameras
                        
                        % Signal conditioning for camera 1
                        app.BatchProcessingCamera1Button.Value = 1;
                        ButtonGroupSelectionChanged(app, event)
                        
                        if (app.InvertCheckBox.Value == 1)  ...
                                ||  (app.BoxFilterCheckBox.Value == 1)  ...
                                ||  (app.Denoise60HzCheckBox.Value == 1)  ...
                                ||  (app.ButterworthFilterCheckBox.Value == 1)  ...
                                ||  (app.SGCheckBox.Value == 1)  ...
                                ||  (app.PolyfitDedriftCheckBox.Value == 1)  ...
                                ||  (app.NormaliseCheckBox.Value == 1)
                        
                        needToSave_Cam1 = 1;
                        
                        cmosData1 = app.data.cmosData1Raw; % Unfiltered, mask-free
                        cmosData1 = batchConditionSignal(app, cmosData1); % Condition signals
                        
                        else
                            needToSave_Cam1 = 0;
                        end
                        
                        % Signal conditioning for camera 2
                        app.BatchProcessingCamera2Button.Value = 1;
                        ButtonGroupSelectionChanged(app, event)
                        
                        if (app.InvertCheckBox.Value == 1)  ...
                                ||  (app.BoxFilterCheckBox.Value == 1)  ...
                                ||  (app.Denoise60HzCheckBox.Value == 1)  ...
                                ||  (app.ButterworthFilterCheckBox.Value == 1)  ...
                                ||  (app.SGCheckBox.Value == 1)  ...
                                ||  (app.PolyfitDedriftCheckBox.Value == 1)  ...
                                ||  (app.NormaliseCheckBox.Value == 1)
                        
                        needToSave_Cam2 = 1;
                        
                        cmosData2 = app.data.cmosData2Raw; % Unfiltered, mask-free
                        cmosData2 = batchConditionSignal(app, cmosData2); % Condition signals
                        
                        else
                            needToSave_Cam2 = 0;
                        end
                        
                        % Signal conditioning information
                        signalConditioningInfo(1,:) = {'Conditioning Name',     'Camera 1 - Condition On (1) / Off (0)',        'Camera 1 - Other Info',                                    'Camera 2 - Condition On (1) / Off (0)',        'Camera 2 - Other Info'};
                        signalConditioningInfo(2,:) = {'maskOnOff',             0,                                              'N/A',                                                      0,                                              'N/A'};
                        signalConditioningInfo(3,:) = {'InvertOnOff',           app.mainApp.batchProcessingLampOnOff{1,1},      'N/A',                                                      app.mainApp.batchProcessingLampOnOff{2,1},      'N/A'};
                        signalConditioningInfo(4,:) = {'BoxFilterOnOff',        app.mainApp.batchProcessingLampOnOff{1,2},      app.mainApp.batchProcessingBoxFilterDropDownValue{1,1},     app.mainApp.batchProcessingLampOnOff{2,2},      app.mainApp.batchProcessingBoxFilterDropDownValue{2,1}};
                        signalConditioningInfo(5,:) = {'DenoiseOnOff',          app.mainApp.batchProcessingLampOnOff{1,3},      'N/A',                                                      app.mainApp.batchProcessingLampOnOff{2,3},      'N/A'};
                        signalConditioningInfo(6,:) = {'ButterworthOnOff',      app.mainApp.batchProcessingLampOnOff{1,4},      app.mainApp.batchProcessingButterworthDropDownValue{1,1},   app.mainApp.batchProcessingLampOnOff{2,4},      app.mainApp.batchProcessingButterworthDropDownValue{2,1}};
                        signalConditioningInfo(7,:) = {'SGOnOff',               app.mainApp.batchProcessingLampOnOff{1,5},      'N/A',                                                      app.mainApp.batchProcessingLampOnOff{2,5},      'N/A'};
                        signalConditioningInfo(8,:) = {'PolyDedriftOnOff',      app.mainApp.batchProcessingLampOnOff{1,6},      app.mainApp.batchProcessingPolyDriftDropDownValue{1,1},     app.mainApp.batchProcessingLampOnOff{2,6},      app.mainApp.batchProcessingPolyDriftDropDownValue{2,1}};
                        signalConditioningInfo(9,:) = {'TopHatDedriftOnOff',    0,                                              'N/A',                                                      0,                                              'N/A'};
                        signalConditioningInfo(10,:) = {'NormaliseOnOff',       app.mainApp.batchProcessingLampOnOff{1,8},      'N/A',                                                      app.mainApp.batchProcessingLampOnOff{2,8},      'N/A'};
                        
                        % Save conditioned data to the file
                        if ( needToSave_Cam1 == 1 )  &&  ( needToSave_Cam2 == 0 )
                            
                            msg = 'Save the conditioned data';
                            progressBar = uiprogressdlg(app.OMZer_FileInformation_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');
                            
                            save( [app.dirName, '/', fileNameOnly, '.mat'], 'cmosData1', 'signalConditioningInfo', '-append' )
                            
                            close(progressBar)
                            
                        elseif ( needToSave_Cam1 == 0 )  &&  ( needToSave_Cam2 == 1 )
                            
                            msg = 'Save the conditioned data';
                            progressBar = uiprogressdlg(app.OMZer_FileInformation_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');
                            
                            save( [app.dirName, '/', fileNameOnly, '.mat'], 'cmosData2', 'signalConditioningInfo', '-append' )
                            
                            close(progressBar)
                            
                        elseif ( needToSave_Cam1 == 1 )  &&  ( needToSave_Cam2 == 1 )
                            
                            msg = 'Save the conditioned data';
                            progressBar = uiprogressdlg(app.OMZer_FileInformation_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');
                            
                            save( [app.dirName, '/', fileNameOnly, '.mat'], 'cmosData1', 'cmosData2', 'signalConditioningInfo', '-append' )
                            
                            close(progressBar)
                        end
                    end
                    
                    app.BatchProcessingProgressEditField.Value = round( 100 * (fileID/totalFileNum) );
                end
                
                
                % (4) Completed
                app.BatchProcessingCheckBox.Value = 0;
                app.BatchProcessingCheckBox.Enable = 'on';
                BatchProcessingCheckBoxValueChanged(app, event)
                
                Image_RefreshClicked(app, event)
                
                app.BatchProcessingProgressEditField.Value = 0;
                
                msg = 'Batch processing completed';
                uialert(app.OMZer_FileInformation_UIFigure, msg,'Warning','Icon','error')
                
                figure(app.OMZer_FileInformation_UIFigure)
            end
            
        end

        % Value changed function: InvertCheckBox
        function InvertCheckBoxValueChanged(app, event)
            
            app.InvertLamp.Color = app.colorGroup_Gray_Orange{ app.InvertCheckBox.Value + 1 }; % Value == 0 (or 1), Color == Gray (or Orange)
            
        end

        % Value changed function: BoxFilterCheckBox
        function BoxFilterCheckBoxValueChanged(app, event)
            
            app.BoxFilterLamp.Color = app.colorGroup_Gray_Orange{ app.BoxFilterCheckBox.Value + 1 }; % Value == 0 (or 1), Color == Gray (or Orange)
            
        end

        % Value changed function: BoxFilterDropDown
        function BoxFilterDropDownValueChanged(app, event)
            
            if app.BoxFilterCheckBox.Value == 1
                
                app.BoxFilterLamp.Color = [0.93, 0.69, 0.13]; % Orange
            end
            
        end

        % Value changed function: Denoise60HzCheckBox
        function Denoise60HzCheckBoxValueChanged(app, event)
            
            app.DenoiseLamp.Color = app.colorGroup_Gray_Orange{ app.Denoise60HzCheckBox.Value + 1 }; % Value == 0 (or 1), Color == Gray (or Orange)
            
        end

        % Value changed function: ButterworthFilterCheckBox
        function ButterworthFilterCheckBoxValueChanged(app, event)
            
            app.ButterworthLamp.Color = app.colorGroup_Gray_Orange{ app.ButterworthFilterCheckBox.Value + 1 }; % Value == 0 (or 1), Color == Gray (or Orange)
            
            app.SGCheckBox.Enable = app.enableGroup_On_Off{ app.ButterworthFilterCheckBox.Value + 1 }; % Value == 0 (or 1), Enable == on (or off)
            
        end

        % Value changed function: ButterworthDropDown
        function ButterworthDropDownValueChanged(app, event)
            
            if app.ButterworthFilterCheckBox.Value == 1
                
                app.ButterworthLamp.Color = [0.93, 0.69, 0.13]; % Orange
            end
            
        end

        % Value changed function: SGCheckBox
        function SGCheckBoxValueChanged(app, event)
            
            app.SGLamp.Color = app.colorGroup_Gray_Orange{ app.SGCheckBox.Value + 1 }; % Value == 0 (or 1), Color == Gray (or Orange)
            
            app.ButterworthFilterCheckBox.Enable = app.enableGroup_On_Off{ app.SGCheckBox.Value + 1 }; % Value == 0 (or 1), Enable == on (or off)
            app.ButterworthDropDown.Enable = app.enableGroup_On_Off{ app.SGCheckBox.Value + 1 }; % Value == 0 (or 1), Enable == on (or off)
            
        end

        % Value changed function: PolyfitDedriftCheckBox
        function PolyfitDedriftCheckBoxValueChanged(app, event)
            
            app.PolyDedriftLamp.Color = app.colorGroup_Gray_Orange{ app.PolyfitDedriftCheckBox.Value + 1 }; % Value == 0 (or 1), Color == Gray (or Orange)
            
            if app.BatchProcessingCheckBox.Value == 0
                app.TopHatDedriftbetaCheckBox.Enable = app.enableGroup_On_Off{ app.PolyfitDedriftCheckBox.Value + 1 }; % Value == 0 (or 1), Enable == on (or off)
            end
            
        end

        % Value changed function: PolyDriftDropDown
        function PolyDriftDropDownValueChanged(app, event)
            
            if app.PolyfitDedriftCheckBox.Value == 1
                
                app.PolyDedriftLamp.Color = [0.93, 0.69, 0.13]; % Orange
            end
            
        end

        % Value changed function: TopHatDedriftbetaCheckBox
        function TopHatDedriftbetaCheckBoxValueChanged(app, event)
            
            msg = 'Sorry. This function can not be used. Please use "Polyfit Dedrift" to remove drift';
            uialert(app.OMZer_FileInformation_UIFigure, msg,'Warning','Icon','error')
            app.TopHatDedriftbetaCheckBox.Value = 0;
            
            
            app.TopHatDedriftLamp.Color = app.colorGroup_Gray_Orange{ app.TopHatDedriftbetaCheckBox.Value + 1 }; % Value == 0 (or 1), Color == Gray (or Orange)
            
            app.PolyfitDedriftCheckBox.Enable = app.enableGroup_On_Off{ app.TopHatDedriftbetaCheckBox.Value + 1 }; % Value == 0 (or 1), Enable == on (or off)
            app.PolyDriftDropDown.Enable = app.enableGroup_On_Off{ app.TopHatDedriftbetaCheckBox.Value + 1 }; % Value == 0 (or 1), Enable == on (or off)
            
        end

        % Value changed function: NormaliseCheckBox
        function NormaliseCheckBoxValueChanged(app, event)
            
            app.NormaliseLamp.Color = app.colorGroup_Gray_Orange{ app.NormaliseCheckBox.Value + 1 }; % Value == 0 (or 1), Color == Gray (or Orange)
            
        end

        % Window key press function: OMZer_FileInformation_UIFigure
        function OMZer_FileInformation_UIFigureWindowKeyPress(app, event)
            
            % key = event.Key;
            
            app.keyPressHistory{app.keyPressID} = event.Key;
            app.keyPressID = app.keyPressID + 1;

            % (1) Save file callback
            if length( app.keyPressHistory ) == 2

                if ( ismac == 1 )  &&  isequal( app.keyPressHistory{1}, 'meta' )  &&  isequal( app.keyPressHistory{2}, 'l' )  &&  isequal( app.fileListBox.Multiselect, 'off' )

                    Image_LoadClicked(app, 'clicked')

                elseif ( ispc == 1 )  &&  isequal( app.keyPressHistory{1}, 'control' )  &&  isequal( app.keyPressHistory{2}, 'l' )  &&  isequal( app.fileListBox.Multiselect, 'off' )

                    Image_LoadClicked(app, 'clicked')
                end
            end

        end

        % Window key release function: 
        % OMZer_FileInformation_UIFigure
        function OMZer_FileInformation_UIFigureWindowKeyRelease(app, event)
            
            % key = event.Key;
            
            app.keyPressID = 1;
            app.keyPressHistory = {};

        end

        % Close request function: OMZer_FileInformation_UIFigure
        function OMZer_FileInformation_UIFigureCloseRequest(app, event)
            
            figure(app.mainAppUIFigure) % Set mainApp at the top level
            
            app.mainApp.OMZer_FileInformation = [];
            
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OMZer_FileInformation_UIFigure = uifigure('Visible', 'off');
            app.OMZer_FileInformation_UIFigure.Name = 'OMZer File Information';
            app.OMZer_FileInformation_UIFigure.CloseRequestFcn = createCallbackFcn(app, @OMZer_FileInformation_UIFigureCloseRequest, true);
            app.OMZer_FileInformation_UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @OMZer_FileInformation_UIFigureWindowKeyPress, true);
            app.OMZer_FileInformation_UIFigure.WindowKeyReleaseFcn = createCallbackFcn(app, @OMZer_FileInformation_UIFigureWindowKeyRelease, true);
            app.fileListBox = uilistbox(app.OMZer_FileInformation_UIFigure);
            app.fileListBox.Items = {};
            app.fileListBox.ValueChangedFcn = createCallbackFcn(app, @fileListBoxValueChanged, true);
            app.fileListBox.Value = {};
            app.Image_Load = uiimage(app.OMZer_FileInformation_UIFigure);
            app.Image_Load.ImageClickedFcn = createCallbackFcn(app, @Image_LoadClicked, true);
            app.Image_Load.ImageSource = 'Icon Load.png';
            app.LoadLabel = uilabel(app.OMZer_FileInformation_UIFigure);
            app.LoadLabel.Text = 'Load';
            app.Image_Refresh = uiimage(app.OMZer_FileInformation_UIFigure);
            app.Image_Refresh.ImageClickedFcn = createCallbackFcn(app, @Image_RefreshClicked, true);
            app.Image_Refresh.ImageSource = 'Icon Refresh.png';
            app.RefreshLabel = uilabel(app.OMZer_FileInformation_UIFigure);
            app.RefreshLabel.Text = 'Refresh';
            app.UserCommentTextAreaLabel = uilabel(app.OMZer_FileInformation_UIFigure);
            app.UserCommentTextAreaLabel.Text = 'User Comment';
            app.UserCommentTextArea = uitextarea(app.OMZer_FileInformation_UIFigure);
            app.UserCommentTextArea.ValueChangedFcn = createCallbackFcn(app, @UserCommentTextAreaValueChanged, true);
            app.UserCommentTextArea.Enable = 'off';
            app.UserCommentTextArea.Tooltip = {''};
            app.BatchProcessingCheckBox = uicheckbox(app.OMZer_FileInformation_UIFigure);
            app.BatchProcessingCheckBox.ValueChangedFcn = createCallbackFcn(app, @BatchProcessingCheckBoxValueChanged, true);
            app.BatchProcessingCheckBox.Enable = 'off';
            app.BatchProcessingCheckBox.Tooltip = {'Multi-files converting and conditioning'};
            app.BatchProcessingCheckBox.Text = 'Batch Processing';
            app.ButtonGroup = uibuttongroup(app.OMZer_FileInformation_UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.BatchProcessingCamera1Button = uiradiobutton(app.ButtonGroup);
            app.BatchProcessingCamera1Button.Enable = 'off';
            app.BatchProcessingCamera1Button.Text = 'Camera 1';
            app.BatchProcessingCamera1Button.Value = true;
            app.BatchProcessingCamera2Button = uiradiobutton(app.ButtonGroup);
            app.BatchProcessingCamera2Button.Enable = 'off';
            app.BatchProcessingCamera2Button.Text = 'Camera 2';
            app.ProgressEditFieldLabel = uilabel(app.OMZer_FileInformation_UIFigure);
            app.ProgressEditFieldLabel.Text = 'Progress (%)';
            app.BatchProcessingProgressEditField = uieditfield(app.OMZer_FileInformation_UIFigure, 'numeric');
            app.BatchProcessingProgressEditField.Limits = [0 Inf];
            app.BatchProcessingProgressEditField.ValueDisplayFormat = '%.0f';
            app.BatchProcessingProgressEditField.Editable = 'off';
            app.InvertCheckBox = uicheckbox(app.OMZer_FileInformation_UIFigure);
            app.InvertCheckBox.ValueChangedFcn = createCallbackFcn(app, @InvertCheckBoxValueChanged, true);
            app.InvertCheckBox.Enable = 'off';
            app.InvertCheckBox.Text = 'Invert';
            app.InvertLamp = uilamp(app.OMZer_FileInformation_UIFigure);
            app.BoxFilterCheckBox = uicheckbox(app.OMZer_FileInformation_UIFigure);
            app.BoxFilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @BoxFilterCheckBoxValueChanged, true);
            app.BoxFilterCheckBox.Enable = 'off';
            app.BoxFilterCheckBox.Tooltip = {'This is a spatial filter that reduce random noise in an image via box blurring method.'};
            app.BoxFilterCheckBox.Text = 'Box Filter';
            app.BoxFilterDropDown = uidropdown(app.OMZer_FileInformation_UIFigure);
            app.BoxFilterDropDown.Items = {'3', '5', '7'};
            app.BoxFilterDropDown.ValueChangedFcn = createCallbackFcn(app, @BoxFilterDropDownValueChanged, true);
            app.BoxFilterDropDown.Enable = 'off';
            app.BoxFilterDropDown.Tooltip = {'Select the kernal size of the box filter. (3x3'; ' 5x5'; ' 7x7)'};
            app.BoxFilterDropDown.Value = '3';
            app.BoxFilterLamp = uilamp(app.OMZer_FileInformation_UIFigure);
            app.Denoise60HzCheckBox = uicheckbox(app.OMZer_FileInformation_UIFigure);
            app.Denoise60HzCheckBox.ValueChangedFcn = createCallbackFcn(app, @Denoise60HzCheckBoxValueChanged, true);
            app.Denoise60HzCheckBox.Enable = 'off';
            app.Denoise60HzCheckBox.Text = 'Denoise (60Hz)';
            app.ButterworthFilterCheckBox = uicheckbox(app.OMZer_FileInformation_UIFigure);
            app.ButterworthFilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @ButterworthFilterCheckBoxValueChanged, true);
            app.ButterworthFilterCheckBox.Enable = 'off';
            app.ButterworthFilterCheckBox.Tooltip = {'This is a butterworth low pass filter. On the right is its stopband cutoff frequency.'};
            app.ButterworthFilterCheckBox.Text = 'Butterworth Filter';
            app.SGCheckBox = uicheckbox(app.OMZer_FileInformation_UIFigure);
            app.SGCheckBox.ValueChangedFcn = createCallbackFcn(app, @SGCheckBoxValueChanged, true);
            app.SGCheckBox.Enable = 'off';
            app.SGCheckBox.Tooltip = {'This is a low pass filter. 5th order (13 points window length).'};
            app.SGCheckBox.Text = 'Savitzky-Golay Smooth';
            app.ButterworthDropDown = uidropdown(app.OMZer_FileInformation_UIFigure);
            app.ButterworthDropDown.Items = {'40', '50', '60', '70', '80', '90', '100', '150', '200'};
            app.ButterworthDropDown.ValueChangedFcn = createCallbackFcn(app, @ButterworthDropDownValueChanged, true);
            app.ButterworthDropDown.Enable = 'off';
            app.ButterworthDropDown.Tooltip = {'This is a low pass filter. Select the cutoff frequency (Hz).'};
            app.ButterworthDropDown.Value = '100';
            app.DenoiseLamp = uilamp(app.OMZer_FileInformation_UIFigure);
            app.ButterworthLamp = uilamp(app.OMZer_FileInformation_UIFigure);
            app.SGLamp = uilamp(app.OMZer_FileInformation_UIFigure);
            app.PolyfitDedriftCheckBox = uicheckbox(app.OMZer_FileInformation_UIFigure);
            app.PolyfitDedriftCheckBox.ValueChangedFcn = createCallbackFcn(app, @PolyfitDedriftCheckBoxValueChanged, true);
            app.PolyfitDedriftCheckBox.Enable = 'off';
            app.PolyfitDedriftCheckBox.Tooltip = {'This is the baseline drift correction via polynomial method.'};
            app.PolyfitDedriftCheckBox.Text = 'Polyfit Dedrift';
            app.PolyDriftDropDown = uidropdown(app.OMZer_FileInformation_UIFigure);
            app.PolyDriftDropDown.Items = {'1', '2', '3', '4', '5', '6'};
            app.PolyDriftDropDown.ValueChangedFcn = createCallbackFcn(app, @PolyDriftDropDownValueChanged, true);
            app.PolyDriftDropDown.Enable = 'off';
            app.PolyDriftDropDown.Tooltip = {'Select the order of the polynominal curve for baseline drift correction.'};
            app.PolyDriftDropDown.Value = '1';
            app.PolyDedriftLamp = uilamp(app.OMZer_FileInformation_UIFigure);
            app.TopHatDedriftbetaCheckBox = uicheckbox(app.OMZer_FileInformation_UIFigure);
            app.TopHatDedriftbetaCheckBox.ValueChangedFcn = createCallbackFcn(app, @TopHatDedriftbetaCheckBoxValueChanged, true);
            app.TopHatDedriftbetaCheckBox.Enable = 'off';
            app.TopHatDedriftbetaCheckBox.Text = 'TopHat Dedrift (beta)';
            app.NormaliseCheckBox = uicheckbox(app.OMZer_FileInformation_UIFigure);
            app.NormaliseCheckBox.ValueChangedFcn = createCallbackFcn(app, @NormaliseCheckBoxValueChanged, true);
            app.NormaliseCheckBox.Enable = 'off';
            app.NormaliseCheckBox.Tooltip = {'To normalise signal such that the value ranges from 0 to 1.'};
            app.NormaliseCheckBox.Text = 'Normalise';
            app.TopHatDedriftLamp = uilamp(app.OMZer_FileInformation_UIFigure);
            app.NormaliseLamp = uilamp(app.OMZer_FileInformation_UIFigure);
            app.Image_Apply = uiimage(app.OMZer_FileInformation_UIFigure);
            app.Image_Apply.ImageClickedFcn = createCallbackFcn(app, @Image_ApplyClicked, true);
            app.Image_Apply.Enable = 'off';
            app.Image_Apply.ImageSource = 'Icon Do.png';
            app.ApplyLabel = uilabel(app.OMZer_FileInformation_UIFigure);
            app.ApplyLabel.Text = 'Apply';
            app.Image_UndoSelection = uiimage(app.OMZer_FileInformation_UIFigure);
            app.Image_UndoSelection.ImageClickedFcn = createCallbackFcn(app, @Image_UndoSelectionClicked, true);
            app.Image_UndoSelection.Enable = 'off';
            app.Image_UndoSelection.ImageSource = 'Icon Undo.png';
            app.UndoSelectionLabel = uilabel(app.OMZer_FileInformation_UIFigure);
            app.UndoSelectionLabel.Text = 'Undo Selection';
            app.Image_ConfirmSelection = uiimage(app.OMZer_FileInformation_UIFigure);
            app.Image_ConfirmSelection.ImageClickedFcn = createCallbackFcn(app, @Image_ConfirmSelectionClicked, true);
            app.Image_ConfirmSelection.Enable = 'off';
            app.Image_ConfirmSelection.ImageSource = 'Icon Select.png';
            app.ConfirmSelectionLabel = uilabel(app.OMZer_FileInformation_UIFigure);
            app.ConfirmSelectionLabel.Text = 'Confirm Selection';
            app.Image_Start = uiimage(app.OMZer_FileInformation_UIFigure);
            app.Image_Start.ImageClickedFcn = createCallbackFcn(app, @Image_StartClicked, true);
            app.Image_Start.Enable = 'off';
            app.Image_Start.ImageSource = 'Icon Start.png';
            app.StartLabel = uilabel(app.OMZer_FileInformation_UIFigure);
            app.StartLabel.Text = 'Start';
            app.Image_BatchMasking = uiimage(app.OMZer_FileInformation_UIFigure);
            app.Image_BatchMasking.ImageClickedFcn = createCallbackFcn(app, @Image_BatchMaskingClicked, true);
            app.Image_BatchMasking.ImageSource = 'Icon Mask.png';
            app.BatchMaskingLabel = uilabel(app.OMZer_FileInformation_UIFigure);
            app.BatchMaskingLabel.Text = 'Batch Masking';
            % Show the figure after all components are created
            app.OMZer_FileInformation_UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OMZer_FileInformation(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.OMZer_FileInformation_UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.OMZer_FileInformation_UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OMZer_FileInformation_UIFigure)
        end
    end
end