classdef OMZer_Mask < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OMZer_Mask_UIFigure          matlab.ui.Figure
        MaskSetForCameraNALabel      matlab.ui.control.Label
        Image_MaskSetForCamera       matlab.ui.control.Image
        CoarseAdjustmentButtonGroup  matlab.ui.container.ButtonGroup
        DrawAROILabel                matlab.ui.control.Label
        Image_DrawAROI               matlab.ui.control.Image
        UseROIDrawingMethodButton    matlab.ui.control.RadioButton
        LoadAMaskLabel               matlab.ui.control.Label
        Image_LoadAMask              matlab.ui.control.Image
        IntensityUBSpinner           matlab.ui.control.Spinner
        IntensityUBSpinnerLabel      matlab.ui.control.Label
        IntensityLBSpinner           matlab.ui.control.Spinner
        IntensityLBSpinnerLabel      matlab.ui.control.Label
        UseASavedMaskButton          matlab.ui.control.RadioButton
        UseIntensityMethodButton     matlab.ui.control.RadioButton
        RedoMaskLabel                matlab.ui.control.Label
        Image_RedoMask               matlab.ui.control.Image
        ApplyAndExitLabel            matlab.ui.control.Label
        Image_ApplyAndExit           matlab.ui.control.Image
        FineAdjustmentPanel          matlab.ui.container.Panel
        DeleteTheHistoryLabel        matlab.ui.control.Label
        Image_DeleteTheHistory       matlab.ui.control.Image
        HistoryListBox               matlab.ui.control.ListBox
        HistoryListBoxLabel          matlab.ui.control.Label
        DeleteFromMaskLabel          matlab.ui.control.Label
        Image_DeleteFromMask         matlab.ui.control.Image
        AddIntoMaskLabel             matlab.ui.control.Label
        Image_AddIntoMask            matlab.ui.control.Image
        finalMaskAxis                matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        
        % The Parent App --------------------
        mainApp
        mainAppUIFigure


        % The Current App --------------------
        imageIconActColor = [0.80, 0.80, 0.80]; % The activation color when an image is clicked
        
        roiRegion % For 'Use ROI Drawing Method'
        
        coarseMask
        maxFineMaskNumber = 10; % The max number of fine mask that can be saved
        fineMaskGroup
        finalMask
        
        finalMaskPlot
        
        saveToHistoryID = 1;
        
        signalTime
        markerXY
        
        cameraSource % 1 (or 2) - data from camera 1 (or 2)
        cmosDataGroup % For cmosData1 and cmosData2
        cmosDataRowNum % The numer of rows in cmosData
        cmosDataColNum % The numer of columns in cmosData
        bgImageNomAdjustGroup % Pixel intensity ranges in [0,255]
        bgImageNomGroup % Gray colour RGB version of background image
    end


    methods (Access = public)
        
        function showImageIconAct(app, imageIconName)
            
            imageIconName.BackgroundColor = app.imageIconActColor; %[0.80, 0.80, 0.80];
            pause(0.05)
            imageIconName.BackgroundColor = 'none';
        end


        function setImageAxisLimit(app, xMaxLimit, yMaxLimit) % Set the x-y limit of all axes
            
            app.finalMaskAxis.XLim = [1, xMaxLimit];
            app.finalMaskAxis.YLim = [1, yMaxLimit];
        end


        function updateCoarseMask(app, method)
            
            if isequal( method, 'Use Intensity Method' )
                
                app.coarseMask = ( app.bgImageNomAdjustGroup{app.cameraSource} <= app.IntensityUBSpinner.Value )   &   ( app.bgImageNomAdjustGroup{app.cameraSource} >= app.IntensityLBSpinner.Value ); % 1 (or 0) - inside (or outside) the range
                app.coarseMask = double(app.coarseMask);
                app.coarseMask( app.coarseMask == 0 ) = NaN; % 1 (or NaN) - inside (or outside) the mask
                
            elseif isequal( method, 'Use ROI Drawing Method' )
                
                app.coarseMask = app.roiRegion;
                
            elseif isequal( method, 'Use A Saved Mask' )
                
            end
        end


        function updateFineMask(app, currentFineROI)
            
            app.fineMaskGroup{ app.saveToHistoryID } = currentFineROI;
            
            for ID = 1 : app.saveToHistoryID
                app.HistoryListBox.Items{ID} = num2str(ID);
            end
            app.HistoryListBox.Value = {};
            
            app.saveToHistoryID = app.saveToHistoryID + 1;
        end


        function updateFinalMaskPlot(app)
            
            hold(app.finalMaskAxis,'on')
            delete(app.finalMaskPlot)
            app.finalMaskPlot = imagesc( app.finalMaskAxis, app.finalMask, 'AlphaData', ~isnan(app.finalMask)*0.3 );
            hold(app.finalMaskAxis,'off')
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, OMZer)
            
            msg = 'Configuring files';
            progressBar = uiprogressdlg(app.OMZer_Mask_UIFigure, 'Title','Initialising', 'Message',msg, 'Indeterminate','on');
            drawnow
            
            app.mainApp = OMZer;
            app.mainAppUIFigure = app.mainApp.OMZer_UIFigure;
            
            app.cameraSource = app.mainApp.activeCamera;
            app.signalTime = app.mainApp.signalTime;
            app.markerXY = app.mainApp.markerXY;
            app.cmosDataGroup = app.mainApp.cmosDataGroup;
            app.bgImageNomAdjustGroup = app.mainApp.bgImageNomAdjustGroup; % Pixel intensity ranges in [0,255]
            app.bgImageNomGroup = app.mainApp.bgImageNomGroup; % Gray colour RGB version of background image
            
            [app.cmosDataRowNum, app.cmosDataColNum, ~] = size( app.cmosDataGroup{app.cameraSource} );
            
            % (1) Set axis limit
            setImageAxisLimit(app, app.cmosDataColNum, app.cmosDataRowNum);
            
            % (2) Set axis colourmap
            colormap(app.finalMaskAxis, 'jet');
            
            % (3) Disable figure toolbox
            try
                disableDefaultInteractivity(app.finalMaskAxis);
                
                % https://www.mathworks.com/help/matlab/ref/axtoolbar.html?searchHighlight=axtoolbar&s_tid=doc_srchtitle
                axtoolbar(app.finalMaskAxis); % Set the axes toolbar be empty
            catch
                % Do nothing
            end
            
            % (4) Generate default mask
            app.coarseMask = ones( app.cmosDataRowNum, app.cmosDataColNum ); % By default, all the pixels will be kept inside the mask
            app.fineMaskGroup = cell( app.maxFineMaskNumber, 1 );
            app.finalMask = ones( app.cmosDataRowNum, app.cmosDataColNum );
            
            % (5) Plot background image and the default mask
            hold(app.finalMaskAxis,'on')
            imagesc( app.finalMaskAxis, app.bgImageNomGroup{app.cameraSource} )
            app.finalMaskPlot = imagesc( app.finalMaskAxis, app.finalMask, 'AlphaData', ~isnan(app.finalMask)*0.3 );
            hold(app.finalMaskAxis,'off')
            
            % (6) Set default value of the intensity LB and UB
            app.IntensityLBSpinner.Value = min( min( app.bgImageNomAdjustGroup{app.cameraSource} ) );
            app.IntensityUBSpinner.Value = max( max( app.bgImageNomAdjustGroup{app.cameraSource} ) );
            
            app.IntensityLBSpinner.Limits = [ app.IntensityLBSpinner.Value, app.IntensityUBSpinner.Value ];
            app.IntensityUBSpinner.Limits = [ app.IntensityLBSpinner.Value, app.IntensityUBSpinner.Value ];
            
            % (7) Update other information
            if app.mainApp.LinkCameras.Value == 0
                app.MaskSetForCameraNALabel.Text = [ 'Mask Set For Camera ', num2str(app.cameraSource), ];
            else
                app.MaskSetForCameraNALabel.Text = 'Mask Set For Camera 1 & 2';
            end
            
            close(progressBar)
            
        end

        % Selection changed function: CoarseAdjustmentButtonGroup
        function CoarseAdjustmentButtonGroupSelectionChanged(app, event)
            
            if app.CoarseAdjustmentButtonGroup.SelectedObject == app.UseIntensityMethodButton
                
                app.IntensityLBSpinner.Enable = 'on';
                app.IntensityUBSpinner.Enable = 'on';
                
                app.Image_DrawAROI.Enable = 'off';
                
                app.Image_LoadAMask.Enable = 'off';
                
                
            elseif app.CoarseAdjustmentButtonGroup.SelectedObject == app.UseROIDrawingMethodButton
                
                app.IntensityLBSpinner.Enable = 'off';
                app.IntensityUBSpinner.Enable = 'off';
                
                app.Image_DrawAROI.Enable = 'on';
                
                app.Image_LoadAMask.Enable = 'off';
                
            elseif app.CoarseAdjustmentButtonGroup.SelectedObject == app.UseASavedMaskButton
                
                app.IntensityLBSpinner.Enable = 'off';
                app.IntensityUBSpinner.Enable = 'off';
                
                app.Image_DrawAROI.Enable = 'off';
                
                app.Image_LoadAMask.Enable = 'on';
            end
            
            % Reset all the masks (i.e., coarseMask, fineMaskGroup, finalMask)
            app.roiRegion = [];
            
            app.coarseMask = ones( app.cmosDataRowNum, app.cmosDataColNum ); % By default, all the pixels will be kept inside the mask
            app.fineMaskGroup = cell( app.maxFineMaskNumber, 1 );
            app.finalMask = ones( app.cmosDataRowNum, app.cmosDataColNum );
            
            app.saveToHistoryID = 1;
            
            app.IntensityLBSpinner.Value = min( app.IntensityLBSpinner.Limits );
            app.IntensityUBSpinner.Value = max( app.IntensityUBSpinner.Limits );
            
            app.HistoryListBox.Items = {};
            app.HistoryListBox.Value = {};
            
            updateFinalMaskPlot(app)
            
        end

        % Value changed function: IntensityLBSpinner
        function IntensityLBSpinnerValueChanged(app, event)
            
            if app.IntensityLBSpinner.Value >= app.IntensityUBSpinner.Value
                
                msg = 'Lower bound value must be smaller than upper bound value';
                uialert(app.OMZer_Mask_UIFigure,msg,'Warning','Icon','error')
                
                app.IntensityLBSpinner.Value = min( app.IntensityLBSpinner.Limits );
                app.IntensityUBSpinner.Value = max( app.IntensityUBSpinner.Limits );
            end
            
            updateCoarseMask(app, 'Use Intensity Method')
            app.finalMask = app.coarseMask; % 1 (or NaN) - inside (or outside) the mask
            updateFinalMaskPlot(app)
            
        end

        % Value changing function: IntensityLBSpinner
        function IntensityLBSpinnerValueChanging(app, event)
            
            if ischar(event.Value) == 1
                app.IntensityLBSpinner.Value = str2double(event.Value);
            else
                app.IntensityLBSpinner.Value = event.Value;
            end
            
            IntensityLBSpinnerValueChanged(app, event)
            
        end

        % Value changed function: IntensityUBSpinner
        function IntensityUBSpinnerValueChanged(app, event)
            
            if app.IntensityLBSpinner.Value >= app.IntensityUBSpinner.Value
                
                msg = 'Lower bound value must be smaller than upper bound value';
                uialert(app.OMZer_Mask_UIFigure,msg,'Warning','Icon','error')
                
                app.IntensityLBSpinner.Value = min( app.IntensityLBSpinner.Limits );
                app.IntensityUBSpinner.Value = max( app.IntensityUBSpinner.Limits );
            end
            
            updateCoarseMask(app, 'Use Intensity Method')
            app.finalMask = app.coarseMask; % 1 (or NaN) - inside (or outside) the mask
            updateFinalMaskPlot(app)
            
        end

        % Value changing function: IntensityUBSpinner
        function IntensityUBSpinnerValueChanging(app, event)
            
            if ischar(event.Value) == 1
                app.IntensityUBSpinner.Value = str2double(event.Value);
            else
                app.IntensityUBSpinner.Value = event.Value;
            end
            
            IntensityUBSpinnerValueChanged(app, event)
            
        end

        % Image clicked function: Image_DrawAROI
        function Image_DrawAROIClicked(app, event)
            
            showImageIconAct(app, app.Image_DrawAROI)
            
            % (1) Get screen size
            screenSize = get( groot, 'ScreenSize' );
            
            % (2 - A) Prepare Background Axis
            fObject_axMap = figure;
            fObject_axMap.Position = [ 1, 20, 0.9*screenSize(4), 0.9*screenSize(4) ];
            axMap = axes( 'Parent', fObject_axMap );
            axMap.OuterPosition = [ 0, 0, 1, 1 ];
            axMap.Box = 'on';
            axMap.YDir = 'Reverse';
            axMap.XLim = [ 1, app.cmosDataColNum ];
            axMap.YLim = [ 1, app.cmosDataRowNum ];
            axMap.PlotBoxAspectRatio = [ 1, 1, 1 ];
            
            % (2 - B) Prepare Signal Axis
            fObject_axSigObject = figure;
            fObject_axSigObject.Position = [ 100, 20, 0.9*screenSize(4), 0.9*screenSize(4) ];
            axSigObject = cell( size(app.markerXY,1), 1 );
            for ID = 1 : size(app.markerXY,1)
                axSigObject{ID} = subplot( size(app.markerXY,1), 1, ID );
            end
            
            % (3 - A) Plot Background
            figurePlot = imagesc( axMap, app.bgImageNomGroup{app.cameraSource} );
            
            % (3 - B) Plot Marker And Signal
            for ID = 1 : size(app.markerXY,1)
                
                row = app.markerXY(ID,2); % Y
                col = app.markerXY(ID,1); % X
                
                line( axMap, col,row, 'Marker','square', 'MarkerSize',10, 'MarkerEdgeColor','White', 'MarkerFaceColor',app.mainApp.ColorID{ID} ); % Marker
                
                sig = squeeze( app.cmosDataGroup{app.cameraSource}(row,col,:) );
                line( axSigObject{ID}, app.signalTime, sig, 'LineWidth',app.mainApp.lWidth, 'color',app.mainApp.ColorID{ID} ); % Signal
            end
            
            % (4) Get the User Defined ROI
            roiObject = drawpolygon(axMap);
            
            if ~ishandle(roiObject) % The user close the figure directly withou drawing any roi
                
                msg = 'You have not set the region of interest (ROI). Draw a region and double click on it to set the ROI.';
                uialert(app.OMZer_Mask_UIFigure, msg,'Warning','Icon','error')
                
            else
                
                wait(roiObject); % If the user draws a roi, he should double click on the roi to truly set it up
                
                if ~ishandle(roiObject) % Although the user draws a roi, he does not double click on the roi to truly set it up
                    
                    msg = 'You have not set the region of interest (ROI). Draw a region and double click on it to set the ROI.';
                    uialert(app.OMZer_Mask_UIFigure, msg,'Warning','Icon','error')
                    
                else
                    app.roiRegion = createMask(roiObject, figurePlot); % The user has set a region of interest
                    app.roiRegion = double(app.roiRegion);
                    app.roiRegion( app.roiRegion == 0 ) = NaN; % 1 (or NaN) - inside (or outside) the mask
                    
                    updateCoarseMask(app, 'Use ROI Drawing Method')
                    app.finalMask = app.coarseMask; % 1 (or NaN) - inside (or outside) the mask
                    
                    updateFinalMaskPlot(app)
                end
            end
            
            if ishandle(fObject_axMap)
                close(fObject_axMap)
            end
            if ishandle(fObject_axSigObject)
                close(fObject_axSigObject)
            end
            
            figure(app.OMZer_Mask_UIFigure)
            
        end

        % Image clicked function: Image_LoadAMask
        function Image_LoadAMaskClicked(app, event)
            
            showImageIconAct(app, app.Image_LoadAMask)
            
            [file, path] = uigetfile('*.mat');
            
            if ~ischar(file) % MATLAB: If the user clicks the Cancel button or the window close button (X), then MATLAB returns the file value as 0.
                
                msg = 'Please only choose one mask file with ".mat" extension.';
                uialert(app.OMZer_Mask_UIFigure, msg,'Warning','Icon','error')
                
            else
                
                msg = 'Load the mask';
                progressBar = uiprogressdlg(app.OMZer_Mask_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');
                
                data = load( [path,file] ); % 'Data' is a structure
                app.coarseMask = data.mask;
                app.finalMask = app.coarseMask; % 1 (or NaN) - inside (or outside) the mask
                updateFinalMaskPlot(app)
                
                close(progressBar);
            end
            
            figure(app.OMZer_Mask_UIFigure)
            
        end

        % Image clicked function: Image_AddIntoMask
        function Image_AddIntoMaskClicked(app, event)
            
            showImageIconAct(app, app.Image_AddIntoMask)
            
            if app.saveToHistoryID >= (app.maxFineMaskNumber + 1)
                
                msg = [ 'You can have up to ', num2str(app.maxFineMaskNumber), ' history steps for fine adjustment. Delete one or more history steps first.' ];
                uialert(app.OMZer_Mask_UIFigure, msg,'Warning','Icon','error')
                
            else
                % (1) Get screen size
                screenSize = get( groot, 'ScreenSize' );
                
                % (2) Prepare Background Axis
                fObject_axMap = figure;
                fObject_axMap.Position = [ 1, 20, 0.9*screenSize(4), 0.9*screenSize(4) ];
                axMap = axes( 'Parent', fObject_axMap );
                axMap.OuterPosition = [ 0, 0, 1, 1 ];
                axMap.Box = 'on';
                axMap.YDir = 'Reverse';
                axMap.XLim = [ 1, app.cmosDataColNum ];
                axMap.YLim = [ 1, app.cmosDataRowNum ];
                axMap.PlotBoxAspectRatio = [ 1, 1, 1 ];
                colormap( axMap, 'jet' )
                
                % (3) Plot Background and Current Mask
                hold( axMap, 'on' )
                figurePlot = imagesc( axMap, app.bgImageNomGroup{app.cameraSource} );
                currentMaskPlot = imagesc( axMap, app.finalMask, 'AlphaData', ~isnan(app.finalMask)*0.3 );
                hold( axMap, 'off' )
                title( axMap, 'Add Into Mask' )
                
                % (4) Get the User Defined ROI
                fineROIRegion = drawpolygon(axMap);
                
                if ~ishandle(fineROIRegion) % The user close the figure directly withou drawing any roi
                    
                    msg = 'You have not set the region of interest (ROI). Draw a region and double click on it to set the ROI.';
                    uialert(app.OMZer_Mask_UIFigure, msg,'Warning','Icon','error')
                    
                else
                    
                    wait(fineROIRegion); % If the user draws a roi, he should double click on the roi to truly set it up
                    
                    if ~ishandle(fineROIRegion) % Although the user draws a roi, he does not double click on the roi to truly set it up
                        
                        msg = 'You have not set the region of interest (ROI). Draw a region and double click on it to set the ROI.';
                        uialert(app.OMZer_Mask_UIFigure, msg,'Warning','Icon','error')
                        
                    else
                        fineROIRegion = createMask(fineROIRegion, figurePlot); % The user has set a region of interest
                        fineROIRegion = double(fineROIRegion); % 1 (or 0) - add into (or no change of) the mask
                        updateFineMask(app, fineROIRegion)
                        
                        app.finalMask( isnan(app.finalMask) ) = 0; % 1 (or 0) - inside (or outside) the mask
                        app.finalMask = app.finalMask + fineROIRegion;
                        app.finalMask( app.finalMask >= 1 ) = 1; % 1 (or 0) - inside (or outside) the mask
                        app.finalMask( app.finalMask == 0 ) = NaN; % 1 (or NaN) - inside (or outside) the mask
                        
                        updateFinalMaskPlot(app)
                    end
                end
                
                if ishandle(fObject_axMap)
                    close(fObject_axMap)
                end
                
                figure(app.OMZer_Mask_UIFigure)
            end
            
        end

        % Image clicked function: Image_DeleteFromMask
        function Image_DeleteFromMaskClicked(app, event)
            
            showImageIconAct(app, app.Image_DeleteFromMask)
            
            if app.saveToHistoryID >= (app.maxFineMaskNumber + 1)
                
                msg = [ 'You can have up to ', num2str(app.maxFineMaskNumber), ' history steps for fine adjustment. Delete one or more history steps first.' ];
                uialert(app.OMZer_Mask_UIFigure, msg,'Warning','Icon','error')
                
            else
                % (1) Get screen size
                screenSize = get( groot, 'ScreenSize' );
                
                % (2) Prepare Background Axis
                fObject_axMap = figure;
                fObject_axMap.Position = [ 1, 20, 0.9*screenSize(4), 0.9*screenSize(4) ];
                axMap = axes( 'Parent', fObject_axMap );
                axMap.OuterPosition = [ 0, 0, 1, 1 ];
                axMap.Box = 'on';
                axMap.YDir = 'Reverse';
                axMap.XLim = [ 1, app.cmosDataColNum ];
                axMap.YLim = [ 1, app.cmosDataRowNum ];
                axMap.PlotBoxAspectRatio = [ 1, 1, 1 ];
                colormap( axMap, 'jet' )
                
                % (3) Plot Background and Current Mask
                hold( axMap, 'on' )
                figurePlot = imagesc( axMap, app.bgImageNomGroup{app.cameraSource} );
                currentMaskPlot = imagesc( axMap, app.finalMask, 'AlphaData', ~isnan(app.finalMask)*0.3 );
                hold( axMap, 'off' )
                title( axMap, 'Add Into Mask' )
                
                % (4) Get the User Defined ROI
                fineROIRegion = drawpolygon(axMap);
                
                if ~ishandle(fineROIRegion) % The user close the figure directly withou drawing any roi
                    
                    msg = 'You have not set the region of interest (ROI). Draw a region and double click on it to set the ROI.';
                    uialert(app.OMZer_Mask_UIFigure, msg,'Warning','Icon','error')
                    
                else
                    
                    wait(fineROIRegion); % If the user draws a roi, he should double click on the roi to truly set it up
                    
                    if ~ishandle(fineROIRegion) % Although the user draws a roi, he does not double click on the roi to truly set it up
                        
                        msg = 'You have not set the region of interest (ROI). Draw a region and double click on it to set the ROI.';
                        uialert(app.OMZer_Mask_UIFigure, msg,'Warning','Icon','error')
                        
                    else
                        fineROIRegion = createMask(fineROIRegion, figurePlot); % The user has set a region of interest
                        fineROIRegion = double(fineROIRegion); % 1 (or 0) - remove from (or no change of) the mask
                        fineROIRegion( fineROIRegion == 1 ) = -1; % -1 (or 0) - remove from (or no change of) the mask
                        updateFineMask(app, fineROIRegion)
                        
                        app.finalMask( isnan(app.finalMask) ) = 0; % 1 (or 0) - inside (or outside) the mask
                        app.finalMask = app.finalMask + fineROIRegion;
                        app.finalMask( app.finalMask <= 0 ) = 0; % 1 (or 0) - inside (or outside) the mask
                        app.finalMask( app.finalMask == 0 ) = NaN; % 1 (or NaN) - inside (or outside) the mask
                        
                        updateFinalMaskPlot(app)
                    end
                end
                
                if ishandle(fObject_axMap)
                    close(fObject_axMap)
                end
                
                figure(app.OMZer_Mask_UIFigure)
            end
            
        end

        % Image clicked function: Image_DeleteTheHistory
        function Image_DeleteTheHistoryClicked(app, event)
            
            showImageIconAct(app, app.Image_DeleteTheHistory)
            
            if isempty( app.HistoryListBox.Value )
                
                msg = 'Choose one history step from the "History" to delete';
                uialert(app.OMZer_Mask_UIFigure, msg,'Warning','Icon','error')
                
            else
                
                fineMaskGroupTemporary = cell(app.maxFineMaskNumber,1);
                app.fineMaskGroup( str2double(app.HistoryListBox.Value) ) = [];
                fineMaskGroupTemporary(1 : app.maxFineMaskNumber-1) = app.fineMaskGroup;
                app.fineMaskGroup = fineMaskGroupTemporary;
                
                
                app.HistoryListBox.Items = {};
                app.HistoryListBox.Value = {};
                
                totalHistoryStep = 0;
                for ID = 1 : app.maxFineMaskNumber
                    totalHistoryStep = totalHistoryStep + ~isempty( app.fineMaskGroup{ID} );
                end
                
                if totalHistoryStep ~= 0
                    
                    app.saveToHistoryID = totalHistoryStep + 1;
                    
                    app.finalMask = app.coarseMask; % 1 (or NaN) - inside (or outside) the mask
                    app.finalMask( isnan(app.finalMask) ) = 0; % 1 (or 0) - inside (or outside) the mask
                    
                    for ID = 1 : totalHistoryStep
                        app.HistoryListBox.Items{ID} = num2str(ID);
                        
                        app.finalMask = app.finalMask + app.fineMaskGroup{ID};
                        app.finalMask( app.finalMask >= 1 ) = 1; % For 'Add Into Mask' - 1 (or 0) - inside (or outside) the mask
                        app.finalMask( app.finalMask <= 0 ) = 0; % For 'Delete From Mask' - 1 (or 0) - inside (or outside) the mask
                    end
                    
                    app.finalMask( app.finalMask == 0 ) = NaN; % 1 (or NaN) - inside (or outside) the mask
                    
                elseif totalHistoryStep == 0
                    
                    app.saveToHistoryID = 1;
                    
                    app.finalMask = app.coarseMask; % 1 (or NaN) - inside (or outside) the mask
                end
                
                updateFinalMaskPlot(app)
            end
            
        end

        % Image clicked function: Image_ApplyAndExit
        function Image_ApplyAndExitClicked(app, event)
            
            showImageIconAct(app, app.Image_ApplyAndExit)
            
            % (1) Ask whether to export the mask
            msg = 'Do you want to export the mask (".mat" file) for future use? ';
            msgTitle = 'Warning';
            selection = uiconfirm(app.OMZer_Mask_UIFigure,msg,msgTitle, 'Options',{'Yes','No'},'DefaultOption',2, 'CancelOption',2); % When the user selects an option, uiconfirm returns their selection as a character vector.
            
            if isequal(selection,'Yes')
                
                saveDir = uigetdir; % Pop up a window so that the user can decide where to store the new .mat file.
                
                if saveDir == 0 % User has NOT selected file directory.
                    
                    msg = 'Please select a valid saving directory.';
                    uialert(app.OMZer_Mask_UIFigure,msg, 'Warning','Icon','error')
                    return
                    
                else
                    % User has selected file directory.
                    prompt = {'Enter filename to save (without extension)...   Please do not use \/:*?"<>|'};
                    dlgtitle = 'Save to a new mat file'; % Dialog box title
                    dfinput = { [app.mainApp.FileNameEditField.Value, '_Mask'] }; % Default new file name
                    dims = [1,100];
                    newFileName = inputdlg(prompt,dlgtitle,dims,dfinput); % 'newFileName' is a 'cell' that stores a 'character'. The user can type in the name for the .mat file.
                    
                    if ~isempty(newFileName) % User has entered a file name
                        
                        newFileName = strcat(saveDir, '/', newFileName, '.mat'); % At this moment, 'newFileName' is a 'cell'.
                        newFileName = newFileName{1}; % Now 'newFileName' is a character.
                        
                        msg = 'Export the mask';
                        progressBar = uiprogressdlg(app.OMZer_Mask_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');
                        
                        backgroundImage = app.bgImageNomGroup{app.cameraSource}; % Gray colour RGB version of background image
                        mask = app.finalMask; % 1 (or NaN) - inside (or outside) the mask
                        save(newFileName, 'mask', 'backgroundImage')
                        
                        close(progressBar)
                    end
                end
            end
            
            %% Update information in the mainApp
            ID = (app.cameraSource == 1) + 1; % When app.cameraSource is 1 (or 2), ID is 2 (or 1)
            
            % (2 - A) Update cmosData, user mask
            app.mainApp.maskGroup{app.cameraSource} = app.finalMask;
            app.mainApp.cmosDataGroup{app.cameraSource} = app.mainApp.cmosDataMaskFreeGroup{app.cameraSource} .* app.mainApp.maskGroup{app.cameraSource};
            
            if app.mainApp.LinkCameras.Value == 1
                app.mainApp.maskGroup{ID} = app.finalMask;
                app.mainApp.cmosDataGroup{ID} = app.mainApp.cmosDataMaskFreeGroup{ID} .* app.mainApp.maskGroup{ID};
            end
            
            % (2 - B) Update video frame
            app.mainApp.imFrameAlpha{app.cameraSource,1} = ~isnan( app.finalMask );
            if app.mainApp.currentFrameID ~= 0
                app.mainApp.imFrame{app.cameraSource,1}.CData = app.mainApp.cmosDataGroup{ app.cameraSource }(:,:,app.mainApp.currentFrameID);
                set( app.mainApp.imFrame{app.cameraSource,1},'AlphaData', app.mainApp.imFrameAlpha{app.cameraSource,1} );
            end
            
            if app.mainApp.LinkCameras.Value == 1
                app.mainApp.imFrameAlpha{ID,1} = ~isnan( app.finalMask );
                if app.mainApp.currentFrameID ~= 0
                    app.mainApp.imFrame{ID,1}.CData = app.mainApp.cmosDataGroup{ ID }(:,:,app.mainApp.currentFrameID);
                    set( app.mainApp.imFrame{ID,1},'AlphaData', app.mainApp.imFrameAlpha{ID,1} );
                end
            end
            
            % (2 - C) Update mask plot
            app.mainApp.Image_EraseMask.Enable = 'on';
            app.mainApp.Image_ExportMask.Enable = 'on';
            
            app.mainApp.imageMaskComponentsObject{app.cameraSource}.Value = 1;
            app.mainApp.imageMaskComponentsObject{app.cameraSource}.Enable = 'on';
            
            delete( app.mainApp.maskPlotObject{app.cameraSource} )
            hold( app.mainApp.imageAxisObject{app.cameraSource}, 'on' )
            app.mainApp.maskPlotObject{app.cameraSource} = imagesc( app.mainApp.imageAxisObject{app.cameraSource}, app.mainApp.maskGroup{app.cameraSource}, 'AlphaData', 0.3 * ~isnan(app.mainApp.maskGroup{app.cameraSource}) );
            hold( app.mainApp.imageAxisObject{app.cameraSource}, 'off' )
            
            if app.mainApp.LinkCameras.Value == 1
                app.mainApp.imageMaskComponentsObject{ID}.Value = 1;
                app.mainApp.imageMaskComponentsObject{ID}.Enable = 'on';
                
                delete( app.mainApp.maskPlotObject{ID} )
                hold( app.mainApp.imageAxisObject{ID}, 'on' )
                app.mainApp.maskPlotObject{ID} = imagesc( app.mainApp.imageAxisObject{ID}, app.mainApp.maskGroup{ID}, 'AlphaData', 0.3 * ~isnan(app.mainApp.maskGroup{ID}) );
                hold( app.mainApp.imageAxisObject{ID}, 'off' )
            end
            
            % Update signal in the Main App ---------------------------------------------------
            updateAllSignals(app.mainApp)
            
            app.mainApp.signalMask_Yes_No = 1;
            
            OMZer_Mask_UIFigureCloseRequest(app, event)
            
        end

        % Image clicked function: Image_RedoMask
        function Image_RedoMaskClicked(app, event)
            
            showImageIconAct(app, app.Image_RedoMask)
            
            app.CoarseAdjustmentButtonGroup.SelectedObject = app.UseIntensityMethodButton;
            
            CoarseAdjustmentButtonGroupSelectionChanged(app, event)
            
        end

        % Close request function: OMZer_Mask_UIFigure
        function OMZer_Mask_UIFigureCloseRequest(app, event)
            
            figure(app.mainAppUIFigure) % Set mainApp at the top level
            
            app.mainApp.OMZer_Mask = [];
            
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OMZer_Mask_UIFigure = uifigure('Visible', 'off');
            app.OMZer_Mask_UIFigure.Name = 'OMZer Mask';
            app.OMZer_Mask_UIFigure.CloseRequestFcn = createCallbackFcn(app, @OMZer_Mask_UIFigureCloseRequest, true);
            app.finalMaskAxis = uiaxes(app.OMZer_Mask_UIFigure);
            app.finalMaskAxis.XLim = [1 100];
            app.finalMaskAxis.YLim = [1 100];
            app.finalMaskAxis.YDir = 'reverse';
            app.finalMaskAxis.XTick = [];
            app.finalMaskAxis.YTick = [];
            app.finalMaskAxis.ZTick = [];
            app.FineAdjustmentPanel = uipanel(app.OMZer_Mask_UIFigure);
            app.FineAdjustmentPanel.Title = 'Fine Adjustment';
            app.Image_AddIntoMask = uiimage(app.FineAdjustmentPanel);
            app.Image_AddIntoMask.ImageClickedFcn = createCallbackFcn(app, @Image_AddIntoMaskClicked, true);
            app.Image_AddIntoMask.ImageSource = 'Icon Plus.png';
            app.AddIntoMaskLabel = uilabel(app.FineAdjustmentPanel);
            app.AddIntoMaskLabel.Text = 'Add Into Mask';
            app.Image_DeleteFromMask = uiimage(app.FineAdjustmentPanel);
            app.Image_DeleteFromMask.ImageClickedFcn = createCallbackFcn(app, @Image_DeleteFromMaskClicked, true);
            app.Image_DeleteFromMask.ImageSource = 'Icon Minus.png';
            app.DeleteFromMaskLabel = uilabel(app.FineAdjustmentPanel);
            app.DeleteFromMaskLabel.Text = 'Delete From Mask';
            app.HistoryListBoxLabel = uilabel(app.FineAdjustmentPanel);
            app.HistoryListBoxLabel.Text = 'History';
            app.HistoryListBox = uilistbox(app.FineAdjustmentPanel);
            app.HistoryListBox.Items = {};
            app.HistoryListBox.Value = {};
            app.Image_DeleteTheHistory = uiimage(app.FineAdjustmentPanel);
            app.Image_DeleteTheHistory.ImageClickedFcn = createCallbackFcn(app, @Image_DeleteTheHistoryClicked, true);
            app.Image_DeleteTheHistory.ImageSource = 'Icon Delete.png';
            app.DeleteTheHistoryLabel = uilabel(app.FineAdjustmentPanel);
            app.DeleteTheHistoryLabel.Text = 'Delete The History';
            app.Image_ApplyAndExit = uiimage(app.OMZer_Mask_UIFigure);
            app.Image_ApplyAndExit.ImageClickedFcn = createCallbackFcn(app, @Image_ApplyAndExitClicked, true);
            app.Image_ApplyAndExit.ImageSource = 'Icon Save.png';
            app.ApplyAndExitLabel = uilabel(app.OMZer_Mask_UIFigure);
            app.ApplyAndExitLabel.Text = 'Apply And Exit';
            app.Image_RedoMask = uiimage(app.OMZer_Mask_UIFigure);
            app.Image_RedoMask.ImageClickedFcn = createCallbackFcn(app, @Image_RedoMaskClicked, true);
            app.Image_RedoMask.ImageSource = 'Icon Undo.png';
            app.RedoMaskLabel = uilabel(app.OMZer_Mask_UIFigure);
            app.RedoMaskLabel.Text = 'Redo Mask';
            app.CoarseAdjustmentButtonGroup = uibuttongroup(app.OMZer_Mask_UIFigure);
            app.CoarseAdjustmentButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CoarseAdjustmentButtonGroupSelectionChanged, true);
            app.CoarseAdjustmentButtonGroup.Title = 'Coarse Adjustment';
            app.UseIntensityMethodButton = uiradiobutton(app.CoarseAdjustmentButtonGroup);
            app.UseIntensityMethodButton.Text = 'Use Intensity Method';
            app.UseIntensityMethodButton.Value = true;
            app.UseASavedMaskButton = uiradiobutton(app.CoarseAdjustmentButtonGroup);
            app.UseASavedMaskButton.Text = 'Use A Saved Mask';
            app.IntensityLBSpinnerLabel = uilabel(app.CoarseAdjustmentButtonGroup);
            app.IntensityLBSpinnerLabel.Text = 'Intensity LB';
            app.IntensityLBSpinner = uispinner(app.CoarseAdjustmentButtonGroup);
            app.IntensityLBSpinner.ValueChangingFcn = createCallbackFcn(app, @IntensityLBSpinnerValueChanging, true);
            app.IntensityLBSpinner.Limits = [0 Inf];
            app.IntensityLBSpinner.ValueDisplayFormat = '%.0f';
            app.IntensityLBSpinner.ValueChangedFcn = createCallbackFcn(app, @IntensityLBSpinnerValueChanged, true);
            app.IntensityUBSpinnerLabel = uilabel(app.CoarseAdjustmentButtonGroup);
            app.IntensityUBSpinnerLabel.Text = 'Intensity UB';
            app.IntensityUBSpinner = uispinner(app.CoarseAdjustmentButtonGroup);
            app.IntensityUBSpinner.ValueChangingFcn = createCallbackFcn(app, @IntensityUBSpinnerValueChanging, true);
            app.IntensityUBSpinner.Limits = [0 Inf];
            app.IntensityUBSpinner.ValueDisplayFormat = '%.0f';
            app.IntensityUBSpinner.ValueChangedFcn = createCallbackFcn(app, @IntensityUBSpinnerValueChanged, true);
            app.Image_LoadAMask = uiimage(app.CoarseAdjustmentButtonGroup);
            app.Image_LoadAMask.ImageClickedFcn = createCallbackFcn(app, @Image_LoadAMaskClicked, true);
            app.Image_LoadAMask.Enable = 'off';
            app.Image_LoadAMask.ImageSource = 'Icon Open.png';
            app.LoadAMaskLabel = uilabel(app.CoarseAdjustmentButtonGroup);
            app.LoadAMaskLabel.Text = 'Load A Mask';
            app.UseROIDrawingMethodButton = uiradiobutton(app.CoarseAdjustmentButtonGroup);
            app.UseROIDrawingMethodButton.Text = 'Use ROI Drawing Method';
            app.Image_DrawAROI = uiimage(app.CoarseAdjustmentButtonGroup);
            app.Image_DrawAROI.ImageClickedFcn = createCallbackFcn(app, @Image_DrawAROIClicked, true);
            app.Image_DrawAROI.Enable = 'off';
            app.Image_DrawAROI.ImageSource = 'Icon ROI Drawing.png';
            app.DrawAROILabel = uilabel(app.CoarseAdjustmentButtonGroup);
            app.DrawAROILabel.Text = 'Draw A ROI';
            app.Image_MaskSetForCamera = uiimage(app.OMZer_Mask_UIFigure);
            app.Image_MaskSetForCamera.Tooltip = {'(This is NOT a button) Tell the user for which camera will the mask be set'};
            app.Image_MaskSetForCamera.ImageSource = 'Icon Activated Camera.png';
            app.MaskSetForCameraNALabel = uilabel(app.OMZer_Mask_UIFigure);
            app.MaskSetForCameraNALabel.Text = 'Mask Set For Camera N/A';
            % Show the figure after all components are created
            app.OMZer_Mask_UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OMZer_Mask(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.OMZer_Mask_UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.OMZer_Mask_UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OMZer_Mask_UIFigure)
        end
    end
end