classdef OMZer_Vm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OMZer_Vm_UIFigure           matlab.ui.Figure
        ROIDockLabel                matlab.ui.control.Label
        Image_ROIDock               matlab.ui.control.Image
        CameraNALabel               matlab.ui.control.Label
        Image_ActivatedCamera       matlab.ui.control.Image
        UserCommentTextArea         matlab.ui.control.TextArea
        UserCommentTextAreaLabel    matlab.ui.control.Label
        ClearPixelLabel             matlab.ui.control.Label
        Image_ClearPixel            matlab.ui.control.Image
        CheckSigX_scrn              matlab.ui.control.NumericEditField
        CheckSigY_scrn              matlab.ui.control.NumericEditField
        CheckSigXYLabel             matlab.ui.control.Label
        DisplayPixelLabel           matlab.ui.control.Label
        Image_DisplayPixel          matlab.ui.control.Image
        MapTitleLabel               matlab.ui.control.Label
        MinPeakHeightDropDown       matlab.ui.control.DropDown
        MinPeakHeightDropDownLabel  matlab.ui.control.Label
        UnloadMapLabel              matlab.ui.control.Label
        Image_UnloadMap             matlab.ui.control.Image
        DeleteMapLabel              matlab.ui.control.Label
        Image_DeleteMap             matlab.ui.control.Image
        ResetBoundLabel             matlab.ui.control.Label
        Image_ResetBound            matlab.ui.control.Image
        UBmsSpinner                 matlab.ui.control.Spinner
        UBmsSpinnerLabel            matlab.ui.control.Label
        LBmsSpinner                 matlab.ui.control.Spinner
        LBmsSpinnerLabel            matlab.ui.control.Label
        WinEndsecEditField          matlab.ui.control.NumericEditField
        WinEndsecEditFieldLabel     matlab.ui.control.Label
        WinStartsecEditField        matlab.ui.control.NumericEditField
        WinStartsecEditFieldLabel   matlab.ui.control.Label
        SDmsEditField               matlab.ui.control.NumericEditField
        SDmsEditFieldLabel          matlab.ui.control.Label
        MedianmsEditField           matlab.ui.control.NumericEditField
        MedianmsEditFieldLabel      matlab.ui.control.Label
        MeanmsEditField             matlab.ui.control.NumericEditField
        MeanmsEditFieldLabel        matlab.ui.control.Label
        MapListBox                  matlab.ui.control.ListBox
        MapListBoxLabel             matlab.ui.control.Label
        Image_MapMode               matlab.ui.control.Image
        MapModeDropDown             matlab.ui.control.DropDown
        GlobalMapLabel              matlab.ui.control.Label
        Image_GlobalMap             matlab.ui.control.Image
        RegionalMapLabel            matlab.ui.control.Label
        Image_RegionalMap           matlab.ui.control.Image
        RTEndDropDown               matlab.ui.control.DropDown
        RTEndDropDownLabel          matlab.ui.control.Label
        RTStartDropDown             matlab.ui.control.DropDown
        RTStartDropDownLabel        matlab.ui.control.Label
        APDDropDown                 matlab.ui.control.DropDown
        APDDropDownLabel            matlab.ui.control.Label
        RepDropDown                 matlab.ui.control.DropDown
        RepDropDownLabel            matlab.ui.control.Label
        TabGroup                    matlab.ui.container.TabGroup
        AnalysisTab                 matlab.ui.container.Tab
        CVLabel                     matlab.ui.control.Label
        Image_CV                    matlab.ui.control.Image
        SignalLabel                 matlab.ui.control.Label
        Image_Signal                matlab.ui.control.Image
        RTLabel                     matlab.ui.control.Label
        Image_RT                    matlab.ui.control.Image
        APDLabel                    matlab.ui.control.Label
        Image_APD                   matlab.ui.control.Image
        RepLabel                    matlab.ui.control.Label
        Image_Rep                   matlab.ui.control.Image
        ActLabel                    matlab.ui.control.Label
        Image_Act                   matlab.ui.control.Image
        ToolsTab                    matlab.ui.container.Tab
        ExportFigureLabel           matlab.ui.control.Label
        Image_ExportFigure          matlab.ui.control.Image
        ExportDataLabel             matlab.ui.control.Label
        Image_ExportData            matlab.ui.control.Image
        SaveLabel                   matlab.ui.control.Label
        Image_Save                  matlab.ui.control.Image
        PreferenceTab               matlab.ui.container.Tab
        MapHistAxis                 matlab.ui.control.UIAxes
        checkSigAxis                matlab.ui.control.UIAxes
        MapAxis                     matlab.ui.control.UIAxes
        colorBarAxes                matlab.ui.control.UIAxes
    end


    properties (Access = public)

        % The Parent App --------------------
        mainApp
        mainAppUIFigure


        % The Children App --------------------
        OMZer_Vm_Signal
        OMZer_Vm_ExportDisplayPixel
        OMZer_Vm_ExportFigure
        OMZer_Vm_CV


        % The Current App --------------------
        dirName % File directory
        fileName % Filename without path and extension

        setup % User preference
        userAlphaValue

        imageIconActColor = [0.80, 0.80, 0.80]; % The activation color when an image is clicked

        ColorID
        Color1 = [0.00,0.45,0.74];
        Color2 = [0.93,0.69,0.13];
        Color3 = [0.64,0.08,0.18];
        Color4 = [0.72,0.27,1.00];
        Color5 = [0.47,0.67,0.19];
        Color6 = [0.89,0.47,0.58];

        lWidth = 2; % Line width

        maxMapNumber = 10; % The max number of map that can be saved

        data
        acqFreq
        markerXY
        signalTime
        cmosDataGroup
        bgImageNomGroup % Gray colour RGB version of background image
        cameraSource % '1 (or 2)' - data from camera 1 (or 2)

        pacing1 % Pacing signal recorded by channel 1
        pacing2 % Pacing signal recorded by channel 2
        pacingTime % Time points to plot pacing signal
        pacing1Enable
        pacing2Enable

        mapAxisLocs

        mouseXY = []; % This is a 1-by-2 row vector that stores mouse "X" and "Y" coordinate
        mouseMotion = []; % The result can be 'normal', 'extend', 'alt', 'open'

        cmosDataRowNum % The numer of rows in cmosData
        cmosDataColNum % The numer of columns in cmosData

        actDefinition = 'max_dv_dt'; % The definition of activation
        dockMapCalculationROI
        mapCalculationROI
        callFromRegionalButton = 0; % '1 (or 0)' - regional (or global)

        % (0) For signal window selection
        oneBeatWindowStartIndex
        oneBeatWindowEndIndex

        multiBeatsWindowStartIndex
        multiBeatsWindowEndIndex

        % (1) For Act
        actMapPlot
        actHistPlot
        actColorBarPlot

        actSaveToID = 1; % The ID of map to be saved
        actMapDataTitle = { '(1) Win Start Index', '(2) Win End Index', ...
            '(3) Area Mode', '(4) AP Mode', ...
            '(5) LB', '(6) UB', ...
            '(7) Data Section Time', '(8) Data Section', ...
            '(9) Locs Act', '(10) Act Matrix', ...
            '(11) Ensemble Peaks Number', ...
            '(12) Map User Comment'};
        actMapData

        % (2) For Rep
        repMapPlot
        repHistPlot
        repColorBarPlot

        repSaveToID = 1;
        repMapDataTitle = { '(1) Win Start Index', '(2) Win End Index', ...
            '(3) Area Mode', '(4) AP Mode', ...
            '(5) Rep Level', ...
            '(6) LB', '(7) UB', ...
            '(8) Data Section Time', '(9) Data Section', ...
            '(10) Locs Rep', '(11) Rep Matrix', ...
            '(12) Ensemble Peaks Number', ...
            '(13) Map User Comment'};
        repMapData

        % (3) For APD
        APDMapPlot
        APDHistPlot
        APDColorBarPlot

        APDSaveToID = 1;
        APDMapDataTitle = { '(1) Win Start Index', '(2) Win End Index', ...
            '(3) Area Mode', '(4) AP Mode', ...
            '(5) APD Level', ...
            '(6) LB', '(7) UB', ...
            '(8) Data Section Time', '(9) Data Section', ...
            '(10) Locs Act', '(11) Locs Rep', ...
            '(12) APD Matrix', ...
            '(13) Ensemble Peaks Number', ...
            '(14) Map User Comment'};
        APDMapData

        % (4) For RT
        RTMapPlot
        RTHistPlot
        RTColorBarPlot

        RTSaveToID = 1;
        RTMapDataTitle = { '(1) Win Start Index', '(2) Win End Index', ...
            '(3) Area Mode', '(4) AP Mode', ...
            '(5) Start Level', '(6) End Level', ...
            '(7) LB', '(8) UB', ...
            '(9) Data Section Time', '(10) Data Section', ...
            '(11) Locs Start', '(12) Locs End', ...
            '(13) RT Matrix', ...
            '(14) Ensemble Peaks Number', ...
            '(15) Map User Comment'};
        RTMapData

        % (5) For Check Signal
        checkSigMarkerXY
        checkSigMouseDragID

        checkSigDataSectionTime
        checkSigDataSection
        checkSigLocsAct
        checkSigLocsRep
        checkSigLocsStart
        checkSigLocsEnd

        checkSigMapData
        checkSigLB
        checkSigUB

        checkSigMarkerPlot
        checkSigMarkerHighlightPlot
        checkSigPlot
        checkSigActPointPlot
        checkSigRepPointPlot
        checkSigStartPointPlot
        checkSigEndPointPlot
        checkSigHistIndicatingLinePlot

        % (6) For export
        exportMapName
        exportMapDataTitle
        exportMapData
        
        % (7) For window key press and release callback
        keyPressHistory
        keyPressID = 1;
    end


    methods (Access = public)

        function callByMainApp_OMZer_Vm_UIFigureCloseRequest(app)

            OMZer_Vm_UIFigureCloseRequest(app, [])
        end


        function showImageIconAct(app, imageIconName)

            imageIconName.BackgroundColor = app.imageIconActColor; %[0.80, 0.80, 0.80];
            pause(0.05)
            imageIconName.BackgroundColor = 'none';
        end


        function setColourMap(app,name)

            colormap(app.MapAxis, name);
            colormap(app.colorBarAxes, name);
        end


        function setAxisLimit(app, xMaxLimit, yMaxLimit) % Set the x-y limit of all axes

            app.MapAxis.XLim = [1, xMaxLimit];
            app.MapAxis.YLim = [1, yMaxLimit];

            app.CheckSigX_scrn.Value = 1;
            app.CheckSigX_scrn.Limits = [1, xMaxLimit];
            app.CheckSigY_scrn.Value = 1;
            app.CheckSigY_scrn.Limits = [1, yMaxLimit];
        end


        function updateMouseXY(app, axisLosInfo)

            %             app.mouseXY(1,1) = ( app.mouseXY(1,1) - axisLosInfo(1,1) ) * app.cmosDataColNum / axisLosInfo(1,3);
            %             app.mouseXY(1,1) = round( app.mouseXY(1,1) ); % Transfer relative mouse location inside bgImageAxis1 to absolute location inside a 100x100 matrix
            %             if app.mouseXY(1,1) < 1
            %                 app.mouseXY(1,1) = 1; % As a result, there won't appear (0,y) element requirement in a 100x100 matrix
            %             end
            %             if app.mouseXY(1,1) > app.cmosDataColNum
            %                 app.mouseXY(1,1) = app.cmosDataColNum;
            %             end
            %
            %
            %             app.mouseXY(1,2) = axisLosInfo.CurrentPoint(1,2);

            %             app.mouseXY(1,2) = ( (axisLosInfo(1,2) + axisLosInfo(1,4)) - app.mouseXY(1,2) ) * app.cmosDataRowNum / axisLosInfo(1,4);
            %             app.mouseXY(1,2) = round( app.mouseXY(1,2) ); % Transfer relative mouse location inside bgImageAxis1 to absolute location inside a 100x100 matrix
            %             if app.mouseXY(1,2) < 1
            %                 app.mouseXY(1,2) = 1; % As a result, there won't appear (x,0) element requirement in a 100x100 matrix
            %             end
            %             if app.mouseXY(1,2) > app.cmosDataRowNum
            %                 app.mouseXY(1,2) = app.cmosDataRowNum;
            %             end


            app.mouseXY(1,1) = app.MapAxis.CurrentPoint(1,1);
            app.mouseXY(1,2) = app.MapAxis.CurrentPoint(1,2);


            app.mouseXY(1,1) = round( app.mouseXY(1,1) ); % Transfer relative mouse location inside bgImageAxis1 to absolute location inside a 100x100 matrix
            if app.mouseXY(1,1) < 1
                app.mouseXY(1,1) = 1; % As a result, there won't appear (0,y) element requirement in a 100x100 matrix
            end
            if app.mouseXY(1,1) > app.cmosDataColNum
                app.mouseXY(1,1) = app.cmosDataColNum;
            end

            app.mouseXY(1,2) = round( app.mouseXY(1,2) ); % Transfer relative mouse location inside bgImageAxis1 to absolute location inside a 100x100 matrix
            if app.mouseXY(1,2) < 1
                app.mouseXY(1,2) = 1; % As a result, there won't appear (x,0) element requirement in a 100x100 matrix
            end
            if app.mouseXY(1,2) > app.cmosDataRowNum
                app.mouseXY(1,2) = app.cmosDataRowNum;
            end
        end


        function loadSavedMap(app)

            VmMap = app.data.VmMeasurement{ app.cameraSource, 2 };

            % (1) Act
            app.actMapData = VmMap{1,3};
            mapNum = 0;
            for ID = 1 : app.maxMapNumber
                mapNum = mapNum + ( ~isempty( app.actMapData{1,1,ID} ) );
            end
            app.actSaveToID = mapNum + 1;

            % (2) Rep
            app.repMapData = VmMap{2,3};
            mapNum = 0;
            for ID = 1 : app.maxMapNumber
                mapNum = mapNum + ( ~isempty( app.repMapData{1,1,ID} ) );
            end
            app.repSaveToID = mapNum + 1;

            % (3) APD
            app.APDMapData = VmMap{3,3};
            mapNum = 0;
            for ID = 1 : app.maxMapNumber
                mapNum = mapNum + ( ~isempty( app.APDMapData{1,1,ID} ) );
            end
            app.APDSaveToID = mapNum + 1;

            % (4) RT
            app.RTMapData = VmMap{4,3};
            mapNum = 0;
            for ID = 1 : app.maxMapNumber
                mapNum = mapNum + ( ~isempty( app.RTMapData{1,1,ID} ) );
            end
            app.RTSaveToID = mapNum + 1;
        end


        function updateMapListBox(app, mapIconName)

            mapNum = 0;

            if isequal( mapIconName, app.Image_Act )
                for ID = 1 : app.maxMapNumber
                    mapNum = mapNum + ( ~isempty( app.actMapData{1,1,ID} ) );
                end

            elseif isequal( mapIconName, app.Image_Rep )
                for ID = 1 : app.maxMapNumber
                    mapNum = mapNum + ( ~isempty( app.repMapData{1,1,ID} ) );
                end

            elseif isequal( mapIconName, app.Image_APD )
                for ID = 1 : app.maxMapNumber
                    mapNum = mapNum + ( ~isempty( app.APDMapData{1,1,ID} ) );
                end

            elseif isequal( mapIconName, app.Image_RT )
                for ID = 1 : app.maxMapNumber
                    mapNum = mapNum + ( ~isempty( app.RTMapData{1,1,ID} ) );
                end
            end

            % Update MapListBox
            app.MapListBox.Items = {};
            if mapNum ~= 0
                for ID = 1 : mapNum
                    app.MapListBox.Items{ID} = num2str(ID);
                end
            end
        end


        function clearActMapPlot(app)

            delete(app.actMapPlot)
            app.actMapPlot = [];

            delete(app.actColorBarPlot)
            app.actColorBarPlot = [];

            delete(app.actHistPlot)
            app.actHistPlot = [];
        end


        function clearRepMapPlot(app)

            delete(app.repMapPlot)
            app.repMapPlot = [];

            delete(app.repColorBarPlot)
            app.repColorBarPlot = [];

            delete(app.repHistPlot)
            app.repHistPlot = [];
        end


        function clearAPDMapPlot(app)

            delete(app.APDMapPlot)
            app.APDMapPlot = [];

            delete(app.APDColorBarPlot)
            app.APDColorBarPlot = [];

            delete(app.APDHistPlot)
            app.APDHistPlot = [];
        end


        function clearRTMapPlot(app)

            delete(app.RTMapPlot)
            app.RTMapPlot = [];

            delete(app.RTColorBarPlot)
            app.RTColorBarPlot = [];

            delete(app.RTHistPlot)
            app.RTHistPlot = [];
        end


        function clearCheckSigPlot(app)

            app.checkSigMarkerXY = [];
            app.CheckSigX_scrn.Value = 1;
            app.CheckSigY_scrn.Value = 1;

            app.checkSigDataSectionTime = [];
            app.checkSigDataSection = [];
            app.checkSigLocsAct = [];
            app.checkSigLocsRep = [];
            app.checkSigLocsStart = [];
            app.checkSigLocsEnd = [];

            app.checkSigMapData = [];
            app.checkSigLB = [];
            app.checkSigUB = [];

            delete(app.checkSigMarkerPlot)
            delete(app.checkSigMarkerHighlightPlot)
            delete(app.checkSigPlot)
            delete(app.checkSigActPointPlot)
            delete(app.checkSigRepPointPlot)
            delete(app.checkSigStartPointPlot)
            delete(app.checkSigEndPointPlot)
            delete(app.checkSigHistIndicatingLinePlot)

            app.checkSigMarkerPlot = [];
            app.checkSigMarkerHighlightPlot = [];
            app.checkSigPlot = [];
            app.checkSigActPointPlot = [];
            app.checkSigRepPointPlot = [];
            app.checkSigStartPointPlot = [];
            app.checkSigEndPointPlot = [];
            app.checkSigHistIndicatingLinePlot = [];
        end


        function resetStatisticResult(app)

            app.MeanmsEditField.Value = 0;
            app.MedianmsEditField.Value = 0;
            app.SDmsEditField.Value = 0;

            app.LBmsSpinner.Limits = [ -Inf, Inf ];
            app.LBmsSpinner.Value = 0;
            app.UBmsSpinner.Limits = [ -Inf, Inf ];
            app.UBmsSpinner.Value = 0;
        end


        function prepareMapCalculation(app, mapIconName)

            % (1) Update ribbon menu and calculation components
            app.Image_ExportData.Enable = 'off';
            app.Image_ExportFigure.Enable = 'off';

            app.ActLabel.FontColor = [ 0.00, 0.00, 0.00 ]; % Black
            app.RepLabel.FontColor = [ 0.00, 0.00, 0.00 ];
            app.APDLabel.FontColor = [ 0.00, 0.00, 0.00 ];
            app.RTLabel.FontColor = [ 0.00, 0.00, 0.00 ];

            app.RepDropDown.Enable = 'off';
            app.APDDropDown.Enable = 'off';
            app.RTStartDropDown.Enable = 'off';
            app.RTEndDropDown.Enable = 'off';

            if isequal( mapIconName, app.Image_Act )
                app.ActLabel.FontColor = [ 1.00, 0.00, 0.00 ]; % Red

            elseif isequal( mapIconName, app.Image_Rep )
                app.RepLabel.FontColor = [ 1.00, 0.00, 0.00 ];
                app.RepDropDown.Enable = 'on';

            elseif isequal( mapIconName, app.Image_APD )
                app.APDLabel.FontColor = [ 1.00, 0.00, 0.00 ];
                app.APDDropDown.Enable = 'on';

            elseif isequal( mapIconName, app.Image_RT )
                app.RTLabel.FontColor = [ 1.00, 0.00, 0.00 ];
                app.RTStartDropDown.Enable = 'on';
                app.RTEndDropDown.Enable = 'on';
            end

            app.Image_RegionalMap.Enable = 'on';
            app.Image_GlobalMap.Enable = 'on';

            % (2) Unload the previous map
            if ~isempty(app.MapListBox.Value)

                % (2.1) Reset statistic result
                resetStatisticResult(app)
                app.LBmsSpinner.Enable = 'off';
                app.UBmsSpinner.Enable = 'off';

                % (2.2) Reset signal window information
                app.WinStartsecEditField.Value = Inf;
                app.WinEndsecEditField.Value = Inf;

                % (2.3) Clear displayed map and signal plot
                app.MapTitleLabel.Text = 'Map Title';
                cla(app.MapAxis)
                cla(app.colorBarAxes)
                cla(app.MapHistAxis)
                imagesc( app.MapAxis, app.bgImageNomGroup{app.cameraSource} );

                clearActMapPlot(app)
                clearRepMapPlot(app)
                clearAPDMapPlot(app)
                clearRTMapPlot(app)

                % (2.4) Clear check signal
                app.Image_DisplayPixel.Enable = 'off';
                app.Image_ClearPixel.Enable = 'off';
                if ~isempty(app.checkSigMarkerPlot)

                    clearCheckSigPlot(app)

                    app.CheckSigX_scrn.Value = 1;
                    app.CheckSigY_scrn.Value = 1;
                    app.CheckSigX_scrn.Editable = 'off';
                    app.CheckSigY_scrn.Editable = 'off';
                    app.CheckSigX_scrn.BackgroundColor = [1.00, 1.00, 1.00]; % White
                    app.CheckSigY_scrn.BackgroundColor = [1.00, 1.00, 1.00];
                end

                % (2.5) Deselect the 'MapListBox'
                app.MapListBox.Value = {};
                app.Image_ResetBound.Enable = 'off';
                app.Image_UnloadMap.Enable = 'off';
                app.Image_DeleteMap.Enable = 'off';

                % (2.6) Clear map user comment
                app.UserCommentTextArea.Enable = 'off';
                app.UserCommentTextArea.Value = {''};
            end

            % (3) Update 'MapListBox'
            updateMapListBox(app, mapIconName)

            % (4) For export data
            app.exportMapName = [];
            app.exportMapDataTitle = [];
            app.exportMapData = [];
        end


        function checkLBUBAdjustment(app, matrix)

            mapTemporary = matrix;

            mapTemporaryCheck = mapTemporary;
            mapTemporaryCheck( mapTemporaryCheck < app.LBmsSpinner.Value  |  mapTemporaryCheck > app.UBmsSpinner.Value ) = NaN;

            if ~isnan( min(mapTemporaryCheck(:)) )  &&  ~isnan( max(mapTemporaryCheck(:)) )  &&  isequal( min(mapTemporaryCheck(:)), max(mapTemporaryCheck(:)) )

                msg = 'Although you specify different bounds for min and max, the min value and max value in the resulting range are actually the same. Please adjust the range.';
                uialert(app.OMZer_Vm_UIFigure, msg,'Warning','Icon','error')

                app.LBmsSpinner.Value = min(app.LBmsSpinner.Limits);
                app.UBmsSpinner.Value = max(app.UBmsSpinner.Limits);
            end
        end


        function newPlotObject = plotMap(app, plotObject, matrix, LB, UB)

            matrix( matrix < LB  |  matrix > UB ) = NaN;

            if isequal( app.ActLabel.FontColor, [ 1.00, 0.00, 0.00 ] )  ...
                    ||  isequal( app.RepLabel.FontColor, [ 1.00, 0.00, 0.00 ] )

                matrix = matrix - nanmin( matrix(:) ); % Shift the time bar such that Act (or Rep) Map always starts from 0
            end

            hold(app.MapAxis,'on')
            delete(plotObject)

            % newPlotObject = imagesc(app.MapAxis, matrix);
            % newPlotObject.AlphaData = ~isnan(matrix) * app.userAlphaValue; % Make the pixel with NaN transparent

            newPlotObject = pcolor( app.MapAxis, matrix );
            newPlotObject.FaceColor = 'interp';
            newPlotObject.EdgeColor = 'none';
            newPlotObject.AlphaData = ~isnan(matrix) * app.userAlphaValue; % Make the pixel with NaN transparent

            if nanmin(matrix(:)) == nanmax(matrix(:))
                caxis(app.MapAxis, [ nanmin(matrix(:)), nanmax(matrix(:))+1 ]);
            else
                caxis(app.MapAxis, [ nanmin(matrix(:)), nanmax(matrix(:)) ]);
            end
            hold(app.MapAxis,'off')
        end


        function newPlotObject = plotMapColorBar(app, plotObject, matrix, LB, UB)

            matrix( matrix < LB  |  matrix > UB ) = NaN;

            if isequal( app.ActLabel.FontColor, [ 1.00, 0.00, 0.00 ] )  ...
                    ||  isequal( app.RepLabel.FontColor, [ 1.00, 0.00, 0.00 ] )

                matrix = matrix - nanmin( matrix(:) ); % Shift the time bar such that Act (or Rep) Map always starts from 0
            end

            matrixColumnVersion = reshape( matrix, [],1 );
            matrixColumnVersion( isnan(matrixColumnVersion) ) = [];
            matrixColumnVersion = unique(matrixColumnVersion);

            % (1) Plot the color bar
            delete(plotObject)
            newPlotObject = imagesc(app.colorBarAxes, matrixColumnVersion);
            yticks(app.colorBarAxes, 'auto')
            app.colorBarAxes.YLim = [ 1, length(matrixColumnVersion) ];

            % (2) Manually create y tick label
            if length(app.colorBarAxes.YTick) <= length(matrixColumnVersion)

                app.colorBarAxes.YTick( mod(app.colorBarAxes.YTick , 1) ~= 0 ) = []; % Only keep integer tick

            else
                app.colorBarAxes.YTick = [ 1 : length(matrixColumnVersion) ];
            end

            colorBarLabel = cell( 1, length(app.colorBarAxes.YTick) );
            for ID = 1 : length(app.colorBarAxes.YTick)
                colorBarLabel{ID} = num2str( matrixColumnVersion(app.colorBarAxes.YTick(ID)) );
            end
            app.colorBarAxes.YTickLabel = colorBarLabel;
        end


        function newPlotObject = plotMapHist(app, plotObject, matrix, LB, UB)

            matrix( matrix < LB  |  matrix > UB ) = NaN;

            histColumnVersion = reshape(matrix, [],1);
            histColumnVersion( isnan(histColumnVersion) ) = []; % Delete NaN because it will also be counted into 'data length'
            binNum = round( sqrt( length(histColumnVersion) ) ); % Specifies the number of bins (= square root of the number of observations)

            hold(app.MapHistAxis,'on')
            delete(plotObject)

            %            newPlotObject = histogram(app.MapHistAxis, histColumnVersion, binNum, 'FaceColor',[0.00, 0.45, 0.74], 'FaceAlpha',1);

            newPlotObject = histogram(app.MapHistAxis, histColumnVersion, 'FaceColor',[0.00, 0.45, 0.74], 'FaceAlpha',1);


            %             % Colourful Histgtam (Nedd code update)
            %             if isequal( app.Setup.VmMapCalSetup{13,2}, 'Multiple' )
            %
            %                 newPlotObject.FaceAlpha = 0;
            %
            %                 histBinEdge = newPlotObject.BinEdges;
            %                 eval( [ 'colorLevel = ', app.userColourMap, '(', num2str(binNum), ');' ] )
            %                 app.colorHistPlot = cell(binNum,1);
            %
            %                 for ID = 1 : binNum
            %                     app.colorHistPlot{ID} = histogram( app.MapHistAxis, HistCol( HistCol >= histBinEdge(ID) & HistCol < histBinEdge(ID+1) ), histBinEdge, 'FaceColor',colorLevel(ID,:), 'FaceAlpha',1 );
            %                 end
            %             end

            hold(app.MapHistAxis,'off')

            if min(histColumnVersion) == max(histColumnVersion)
                xlim( app.MapHistAxis, [min(histColumnVersion), max(histColumnVersion)+1] );
            else
                xlim( app.MapHistAxis, [min(histColumnVersion), max(histColumnVersion)] );
            end

            app.MapHistAxis.YLim = [ 0, max(newPlotObject.BinCounts) ];
        end


        function updateStatisticResult(app, matrix, LB, UB)

            if nanmin(matrix(:)) == nanmax(matrix(:))

                app.LBmsSpinner.Limits = [ nanmin(matrix(:)), nanmax(matrix(:))+1 ];
                app.UBmsSpinner.Limits = [ nanmin(matrix(:)), nanmax(matrix(:))+1 ];

            else
                app.LBmsSpinner.Limits = [ nanmin(matrix(:)), nanmax(matrix(:)) ];
                app.UBmsSpinner.Limits = [ nanmin(matrix(:)), nanmax(matrix(:)) ];
            end

            app.LBmsSpinner.Value = LB;
            app.UBmsSpinner.Value = UB;

            mapTemporary = matrix;
            mapTemporary( mapTemporary < LB  |  mapTemporary > UB ) = NaN;

            app.MeanmsEditField.Value = nanmean( mapTemporary(:) );
            app.MedianmsEditField.Value = nanmedian( mapTemporary(:) );
            app.SDmsEditField.Value = nanstd( mapTemporary(:) );
        end


        function updateSignalWindowInformation(app, thisWinStartIndex, thisWinEndIndex)

            if ( prod( ~isnan(thisWinStartIndex(:)) ) == 1 )  &&  ( prod( ~isnan(thisWinEndIndex(:)) ) == 1 )

                if length(thisWinStartIndex) == 1  &&  length(thisWinEndIndex) == 1

                    app.WinStartsecEditField.Value = app.signalTime(thisWinStartIndex);
                    app.WinEndsecEditField.Value = app.signalTime(thisWinEndIndex);
                end
            end
        end


        function plotObject = updateCheckSigMarker(app, markerColor)

            X = app.checkSigMarkerXY(1,1);
            Y = app.checkSigMarkerXY(1,2);

            plotObject = line(app.MapAxis, X, Y, 'Marker','square', 'MarkerSize',10, 'MarkerEdgeColor','white', 'MarkerFaceColor', markerColor);
        end


        function plotObject = updateCheckSigMarkerHighlight(app)

            X = app.checkSigMarkerXY(1,1);
            Y = app.checkSigMarkerXY(1,2);

            plotObject = line( app.MapAxis, X, Y, 'Marker','o', 'LineWidth',4, 'MarkerSize',30, 'MarkerEdgeColor','white', 'MarkerFaceColor', 'none');
        end


        function plotObject = updateCheckSig(app, dataTime, data, lineColor)

            row = app.checkSigMarkerXY(1,2);
            col = app.checkSigMarkerXY(1,1);

            plotObject = line( app.checkSigAxis, dataTime, squeeze(data(row,col,:)), 'LineWidth',app.lWidth, 'color', lineColor );
            xlim( app.checkSigAxis, [ dataTime(1), dataTime(end) ] );
        end


        function plotObject = updatecheckSigHistIndicatingLine(app, dataMap)

            row = app.checkSigMarkerXY(1,2);
            col = app.checkSigMarkerXY(1,1);

            if ~isnan( dataMap(row,col) )

                valueLabel = num2str(dataMap(row,col));

                plotObject = xline( app.MapHistAxis, dataMap(row,col), 'LineWidth',app.lWidth, 'color','Red' );
                plotObject.Label = valueLabel;
                plotObject.FontSize = 20;
                plotObject.FontWeight = 'bold';

                delta = app.MapHistAxis.XLim(2) - app.MapHistAxis.XLim(1);
                if dataMap(row,col) <= app.MapHistAxis.XLim(1) + 0.1*delta
                    plotObject.LabelHorizontalAlignment = 'right';

                elseif dataMap(row,col) >= app.MapHistAxis.XLim(2) - 0.1*delta
                    plotObject.LabelHorizontalAlignment = 'left';

                else
                    plotObject.LabelHorizontalAlignment = 'center';
                end

            else
                plotObject = [];
            end
        end


        function plotObject = updateCheckSigActPoints(app, dataTime, data)

            row = app.checkSigMarkerXY(1,2);
            col = app.checkSigMarkerXY(1,1);

            ID = app.checkSigLocsAct( row, col );
            if ~isnan(ID)
                plotObject = line( app.checkSigAxis, dataTime(ID), data(row,col,ID), 'Marker','o', 'MarkerSize',10, 'MarkerEdgeColor','black', 'MarkerFaceColor','black' );
            else
                plotObject = [];
            end
        end


        function plotObject = updateCheckSigRepPoints(app, dataTime, data)

            row = app.checkSigMarkerXY(1,2);
            col = app.checkSigMarkerXY(1,1);

            ID = app.checkSigLocsRep( row, col );
            if ~isnan(ID)
                plotObject = line( app.checkSigAxis, dataTime(ID), data(row,col,ID), 'Marker','d', 'MarkerSize',10, 'MarkerEdgeColor','black', 'MarkerFaceColor','black' );
            else
                plotObject = [];
            end
        end


        function plotObject = updateCheckSigStartPoints(app, dataTime, data)

            row = app.checkSigMarkerXY(1,2);
            col = app.checkSigMarkerXY(1,1);

            ID = app.checkSigLocsStart( row, col );
            if ~isnan(ID)
                plotObject = line( app.checkSigAxis, dataTime(ID), data(row,col,ID), 'Marker','o', 'MarkerSize',10, 'MarkerEdgeColor','black', 'MarkerFaceColor','black' );
            else
                plotObject = [];
            end
        end


        function plotObject = updateCheckSigEndPoints(app, dataTime, data)

            row = app.checkSigMarkerXY(1,2);
            col = app.checkSigMarkerXY(1,1);

            ID = app.checkSigLocsEnd( row, col );
            if ~isnan(ID)
                plotObject = line( app.checkSigAxis, dataTime(ID), data(row,col,ID), 'Marker','d', 'MarkerSize',10, 'MarkerEdgeColor','black', 'MarkerFaceColor','black' );
            else
                plotObject = [];
            end
        end


        function outputUserComment = generateMultiBeatsMapUserComment(app, inputUserComment, windowStartTime, windowEndTime)

            if iscolumn(windowStartTime) % Unit in 'sec'
                windowStartTime_row = windowStartTime';
            else
                windowStartTime_row = windowStartTime;
            end

            if iscolumn(windowEndTime) % Unit in 'sec'
                windowEndTime_row = windowEndTime';
            else
                windowEndTime_row = windowEndTime;
            end

            windowStartTimeText = 'Multi Beats window start (sec) at ';
            for ID = 1 : length(windowStartTime_row)
                windowStartTimeText = [ windowStartTimeText, num2str( windowStartTime_row(ID) ) ];
                if ID ~= length(windowStartTime_row)
                    windowStartTimeText = [windowStartTimeText, ', '];
                end
            end

            windowEndTimeText = 'Multi Beats window end (sec) at ';
            for ID = 1 : length(windowEndTime_row)
                windowEndTimeText = [ windowEndTimeText, num2str( windowEndTime_row(ID) ) ];
                if ID ~= length(windowEndTime_row)
                    windowEndTimeText = [windowEndTimeText, ', '];
                end
            end

            outputUserComment = inputUserComment;
            outputUserComment{ length(outputUserComment)+1, 1 } = windowStartTimeText;
            outputUserComment{ length(outputUserComment)+1, 1 } = windowEndTimeText;
        end


        function saveActMap(app, winStartIndex, winEndIndex, areaLabel, mapMode, dataSectionTime, dataSection, locsAct, actMatrix, pksNum, mapUserComment)

            app.actMapData{1, 1, app.actSaveToID} = winStartIndex;
            app.actMapData{1, 2, app.actSaveToID} = winEndIndex;
            app.actMapData{1, 3, app.actSaveToID} = areaLabel;
            app.actMapData{1, 4, app.actSaveToID} = mapMode;
            app.actMapData{1, 5, app.actSaveToID} = nanmin( actMatrix(:) );
            app.actMapData{1, 6, app.actSaveToID} = nanmax( actMatrix(:) );
            app.actMapData{1, 7, app.actSaveToID} = dataSectionTime;
            app.actMapData{1, 8, app.actSaveToID} = dataSection;
            app.actMapData{1, 9, app.actSaveToID} = locsAct;
            app.actMapData{1, 10, app.actSaveToID} = actMatrix;
            app.actMapData{1, 11, app.actSaveToID} = pksNum;
            app.actMapData{1, 12, app.actSaveToID} = mapUserComment;
        end


        function saveRepMap(app, winStartIndex, winEndIndex, areaLabel, mapMode, dataSectionTime, dataSection, locsRep, repMatrix, pksNum, mapUserComment, repLevel)

            app.repMapData{1, 1, app.repSaveToID} = winStartIndex;
            app.repMapData{1, 2, app.repSaveToID} = winEndIndex;
            app.repMapData{1, 3, app.repSaveToID} = areaLabel;
            app.repMapData{1, 4, app.repSaveToID} = mapMode;
            app.repMapData{1, 5, app.repSaveToID} = repLevel;
            app.repMapData{1, 6, app.repSaveToID} = nanmin( repMatrix(:) );
            app.repMapData{1, 7, app.repSaveToID} = nanmax( repMatrix(:) );
            app.repMapData{1, 8, app.repSaveToID} = dataSectionTime;
            app.repMapData{1, 9, app.repSaveToID} = dataSection;
            app.repMapData{1, 10, app.repSaveToID} = locsRep;
            app.repMapData{1, 11, app.repSaveToID} = repMatrix;
            app.repMapData{1, 12, app.repSaveToID} = pksNum;
            app.repMapData{1, 13, app.repSaveToID} = mapUserComment;
        end


        function saveAPDMap(app, winStartIndex, winEndIndex, areaLabel, mapMode, dataSectionTime, dataSection, locsAct, locsRep, durationMatrix, pksNum, mapUserComment, repLevel)

            app.APDMapData{1, 1, app.APDSaveToID} = winStartIndex;
            app.APDMapData{1, 2, app.APDSaveToID} = winEndIndex;
            app.APDMapData{1, 3, app.APDSaveToID} = areaLabel;
            app.APDMapData{1, 4, app.APDSaveToID} = mapMode;
            app.APDMapData{1, 5, app.APDSaveToID} = repLevel;
            app.APDMapData{1, 6, app.APDSaveToID} = nanmin( durationMatrix(:) );
            app.APDMapData{1, 7, app.APDSaveToID} = nanmax( durationMatrix(:) );

            app.APDMapData{1, 8, app.APDSaveToID} = dataSectionTime;
            app.APDMapData{1, 9, app.APDSaveToID} = dataSection;
            app.APDMapData{1, 10, app.APDSaveToID} = locsAct;
            app.APDMapData{1, 11, app.APDSaveToID} = locsRep;
            app.APDMapData{1, 12, app.APDSaveToID} = durationMatrix;
            app.APDMapData{1, 13, app.APDSaveToID} = pksNum;
            app.APDMapData{1, 14, app.APDSaveToID} = mapUserComment;
        end


        function saveRTMap(app, winStartIndex, winEndIndex, areaLabel, mapMode, dataSectionTime, dataSection, locsStart, locsEnd, durationMatrix, pksNum, mapUserComment, startLevel, endLevel)

            app.RTMapData{1, 1, app.RTSaveToID} = winStartIndex;
            app.RTMapData{1, 2, app.RTSaveToID} = winEndIndex;
            app.RTMapData{1, 3, app.RTSaveToID} = areaLabel;
            app.RTMapData{1, 4, app.RTSaveToID} = mapMode;
            app.RTMapData{1, 5, app.RTSaveToID} = startLevel;
            app.RTMapData{1, 6, app.RTSaveToID} = endLevel;
            app.RTMapData{1, 7, app.RTSaveToID} = nanmin( durationMatrix(:) );
            app.RTMapData{1, 8, app.RTSaveToID} = nanmax( durationMatrix(:) );
            app.RTMapData{1, 9, app.RTSaveToID} = dataSectionTime;
            app.RTMapData{1, 10, app.RTSaveToID} = dataSection;
            app.RTMapData{1, 11, app.RTSaveToID} = locsStart;
            app.RTMapData{1, 12, app.RTSaveToID} = locsEnd;
            app.RTMapData{1, 13, app.RTSaveToID} = durationMatrix;
            app.RTMapData{1, 14, app.RTSaveToID} = pksNum;
            app.RTMapData{1, 15, app.RTSaveToID} = mapUserComment;
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, OMZer)

            msg = 'Configuring files';
            progressBar = uiprogressdlg(app.OMZer_Vm_UIFigure, 'Title','Initialising', 'Message',msg, 'Indeterminate','on');
            drawnow

            % (1) User set up
            app.setup = load([pwd,'/OMZer_Setup']);
            setColourMap(app, app.setup.userColourMap);
            app.userAlphaValue = 1.0; % The default alpha value
            app.ColorID = {app.Color1, app.Color2, app.Color3, app.Color4, app.Color5, app.Color6}; % Store 6 color

            % (2) Get information from the mainApp
            app.mainApp = OMZer;
            app.mainAppUIFigure = app.mainApp.OMZer_UIFigure;

            app.dirName = app.mainApp.dirName;
            app.fileName = app.mainApp.fileName;

            app.data = app.mainApp.data;
            app.acqFreq = app.mainApp.acqFreq;
            app.markerXY = app.mainApp.markerXY;
            app.signalTime = app.mainApp.signalTime;
            app.cmosDataGroup = app.mainApp.cmosDataGroup;
            app.bgImageNomGroup = app.mainApp.bgImageNomGroup;
            app.cameraSource = app.mainApp.activeCamera;

            app.pacing1 = app.mainApp.pacing1;
            app.pacing2 = app.mainApp.pacing2;
            app.pacingTime = app.mainApp.pacingTime;
            app.pacing1Enable = app.mainApp.pacing1CheckBox.Enable;
            app.pacing2Enable = app.mainApp.pacing2CheckBox.Enable;

            app.CameraNALabel.Text = [ 'Camera ', num2str(app.cameraSource) ];

            % (3) Diable Toolbar
            % https://www.mathworks.com/help/matlab/ref/axtoolbar.html?searchHighlight=axtoolbar&s_tid=doc_srchtitle
            try
                disableDefaultInteractivity(app.colorBarAxes);
                disableDefaultInteractivity(app.MapAxis);
                disableDefaultInteractivity(app.MapHistAxis);
                disableDefaultInteractivity(app.checkSigAxis);

                axtoolbar(app.colorBarAxes);
                axtoolbar(app.MapAxis);
                axtoolbar(app.MapHistAxis);
                axtoolbar(app.checkSigAxis);

            catch
                % Do nothing
            end

            % (4) Plot background image
            [app.cmosDataRowNum, app.cmosDataColNum, ~] = size( app.cmosDataGroup{app.cameraSource} );
            setAxisLimit(app, app.cmosDataColNum, app.cmosDataRowNum);
            imagesc( app.MapAxis, app.bgImageNomGroup{app.cameraSource} );

            % (5) Load saved map
            loadSavedMap(app)

            % (6) Other
            Image_ActClicked(app, 'ImageClicked')

            close(progressBar)

        end

        % Image clicked function: Image_Signal
        function Image_SignalClicked(app, event)

            showImageIconAct(app, app.Image_Signal)
            app.OMZer_Vm_Signal = OMZer_Vm_Signal(app);

        end

        % Image clicked function: Image_Act
        function Image_ActClicked(app, event)

            showImageIconAct(app, app.Image_Act)
            prepareMapCalculation(app, app.Image_Act)

        end

        % Image clicked function: Image_Rep
        function Image_RepClicked(app, event)

            showImageIconAct(app, app.Image_Rep)
            prepareMapCalculation(app, app.Image_Rep)

        end

        % Image clicked function: Image_APD
        function Image_APDClicked(app, event)

            showImageIconAct(app, app.Image_APD)
            prepareMapCalculation(app, app.Image_APD)

        end

        % Image clicked function: Image_RT
        function Image_RTClicked(app, event)

            showImageIconAct(app, app.Image_RT)
            prepareMapCalculation(app, app.Image_RT)

        end

        % Image clicked function: Image_CV
        function Image_CVClicked(app, event)

            showImageIconAct(app, app.Image_CV)
            app.OMZer_Vm_CV = OMZer_Vm_CV(app);

        end

        % Image clicked function: Image_Save
        function Image_SaveClicked(app, event)

            showImageIconAct(app, app.Image_Save)

            msg = 'Saving the file';
            progressBar = uiprogressdlg(app.OMZer_Vm_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');

            % (1) Update the data
            VmMeasurement = app.data.VmMeasurement;
            old_VmMap = VmMeasurement{ app.cameraSource, 2 };
            new_VmMap = old_VmMap;

            new_VmMap{1,3} = app.actMapData;
            new_VmMap{2,3} = app.repMapData;
            new_VmMap{3,3} = app.APDMapData;
            new_VmMap{4,3} = app.RTMapData;

            VmMeasurement{ app.cameraSource, 2 } = new_VmMap;

            % (2) Save the data
            newFileName = [app.dirName, '/', app.fileName, '.mat'];

            save(newFileName, 'VmMeasurement', '-append');

            app.mainApp.data = load( newFileName ); % Since data changed, need to re-load and update data in the main app
            app.data = app.mainApp.data;

            close(progressBar)

        end

        % Image clicked function: Image_ExportData
        function Image_ExportDataClicked(app, event)

            showImageIconAct(app, app.Image_ExportData)

            msg = 'Choose the data you want to export';
            msgTitle = 'Warning';
            optionItem = { 'Export Map Data (.mat File)', 'Export Display Pixel (.mat File)', 'Export Map ROI (.mat File)', 'Cancel' };
            selection = uiconfirm( app.OMZer_Vm_UIFigure, msg, msgTitle, ...
                'Options', optionItem, ...
                'DefaultOption', 1, ...
                'CancelOption', 4 ); % uiconfirm returns the selection as a character vector

            switch selection

                case optionItem{1} % Export Map Data (.mat File)

                    % Get valid saving directory and file name
                    saveDir = uigetdir;

                    if saveDir == 0 % User has NOT selected file directory.

                        msg = 'Please select a valid saving directory';
                        uialert(app.OMZer_Vm_UIFigure,msg,'Warning','Icon','error')

                    else

                        prompt = {'Enter filename to save (without extension)...   Please do not use \/:*?"<>|'};
                        dlgtitle = 'Save to a .mat file';
                        dfinput = { [ app.fileName, '_Vm_', app.exportMapName, '_Data' ] };
                        dims = [1,100];
                        filename = inputdlg(prompt,dlgtitle,dims,dfinput);

                        if isempty(filename) % User has NOT entered a name

                            msg = 'Please enter a valid file name';
                            uialert(app.OMZer_Vm_UIFigure,msg,'Warning','Icon','error')

                        else

                            % Export map data
                            mapName = app.exportMapName;
                            mapDataTitle = app.exportMapDataTitle;
                            mapData = app.exportMapData;
                            mapDataWithTitle = cell( 2, length(mapDataTitle) );
                            mapDataWithTitle(1,:) = app.exportMapDataTitle;
                            mapDataWithTitle(2,:) = app.exportMapData;

                            acquisitionFrequency_Hz_Unit = app.acqFreq;
                            backgroundImage = app.bgImageNomGroup{app.cameraSource};
                            cameraID = app.cameraSource;
                            mapPlotTitle = app.MapTitleLabel.Text;
                            meanValue_ms_Unit = app.MeanmsEditField.Value;
                            medianValue_ms_Unit = app.MedianmsEditField.Value;
                            SDValue_ms_Unit = app.SDmsEditField.Value;

                            if isequal( app.ActLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Act map

                                msg = [ 'Export the ', app.ActLabel.Text, ' Map' ];
                                progressBar = uiprogressdlg(app.OMZer_Vm_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');

                                mapData{10} = app.actMapPlot.CData;
                                mapUserComment = mapData{12};

                            elseif isequal( app.RepLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Rep map

                                msg = [ 'Export the ', app.RepLabel.Text, ' Map' ];
                                progressBar = uiprogressdlg(app.OMZer_Vm_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');

                                mapData{11} = app.repMapPlot.CData;
                                mapUserComment = mapData{13};

                            elseif isequal( app.APDLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % APD map

                                msg = [ 'Export the ', app.APDLabel.Text, ' Map' ];
                                progressBar = uiprogressdlg(app.OMZer_Vm_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');

                                mapData{12} = app.APDMapPlot.CData;
                                mapUserComment = mapData{14};

                            elseif isequal( app.RTLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % RT map

                                msg = [ 'Export the ', app.RTLabel.Text, ' Map' ];
                                progressBar = uiprogressdlg(app.OMZer_Vm_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');

                                mapData{13} = app.RTMapPlot.CData;
                                mapUserComment = mapData{15};
                            end

                            fullSaveName = strcat(saveDir, '/', filename, '.mat'); % At this moment, 'fullSaveName' is a 'cell'.
                            fullSaveName = fullSaveName{1}; % Now 'fullSaveName' is a 'character
                            save(fullSaveName, ...
                                'mapName', ...
                                'mapDataTitle', ...
                                'mapData', ...
                                'mapDataWithTitle', ...
                                'mapUserComment', ...
                                'acquisitionFrequency_Hz_Unit', ...
                                'backgroundImage', ...
                                'cameraID', ...
                                'mapPlotTitle', ...
                                'meanValue_ms_Unit', ...
                                'medianValue_ms_Unit', ...
                                'SDValue_ms_Unit');

                            close(progressBar)
                        end
                    end

                    figure(app.OMZer_Vm_UIFigure) % Set this uifigure at the top level


                case optionItem{2} % Export Display Pixel (.xlsx File)

                    app.OMZer_Vm_ExportDisplayPixel = OMZer_Vm_ExportDisplayPixel(app);


                case optionItem{3} % Export Map ROI (.mat File)

                    % Get valid saving directory and file name
                    saveDir = uigetdir;

                    if saveDir == 0 % User has NOT selected file directory.

                        msg = 'Please select a valid saving directory';
                        uialert(app.OMZer_Vm_UIFigure,msg,'Warning','Icon','error')

                    else

                        prompt = {'Enter filename to save (without extension)...   Please do not use \/:*?"<>|'};
                        dlgtitle = 'Save to a .mat file';
                        dfinput = { [ app.fileName, '_Vm_', app.exportMapName, '_Map_ROI' ] };
                        dims = [1,100];
                        filename = inputdlg(prompt,dlgtitle,dims,dfinput);

                        if isempty(filename) % User has NOT entered a name

                            msg = 'Please enter a valid file name';
                            uialert(app.OMZer_Vm_UIFigure,msg,'Warning','Icon','error')

                        else

                            % Export map data
                            if isequal( app.ActLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Act map

                                msg = [ 'Export the ', app.ActLabel.Text, ' Map ROI' ];
                                progressBar = uiprogressdlg(app.OMZer_Vm_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');

                                matrix = app.actMapPlot.CData;

                            elseif isequal( app.RepLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Rep map

                                msg = [ 'Export the ', app.RepLabel.Text, ' Map ROI' ];
                                progressBar = uiprogressdlg(app.OMZer_Vm_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');

                                matrix = app.repMapPlot.CData;

                            elseif isequal( app.APDLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % APD map

                                msg = [ 'Export the ', app.APDLabel.Text, ' Map ROI' ];
                                progressBar = uiprogressdlg(app.OMZer_Vm_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');

                                matrix = app.APDMapPlot.CData;

                            elseif isequal( app.RTLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % RT map

                                msg = [ 'Export the ', app.RTLabel.Text, ' Map ROI' ];
                                progressBar = uiprogressdlg(app.OMZer_Vm_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');

                                matrix = app.RTMapPlot.CData;
                            end

                            mapRegion = ~isnan(matrix); % 1 (or 0) - pixel has (does not have) signals
                            mapRegion = double(mapRegion);
                            mapRegion( mapRegion == 0 ) = NaN; % 1 (or NaN) - pixel has (does not have) signals

                            bgImageNomRGB = app.bgImageNomGroup{app.cameraSource};
                            mask = mapRegion;

                            fullSaveName = strcat(saveDir, '/', filename, '.mat'); % At this moment, 'fullSaveName' is a 'cell'.
                            fullSaveName = fullSaveName{1}; % Now 'fullSaveName' is a 'character
                            save(fullSaveName, 'mask', 'bgImageNomRGB');

                            close(progressBar)
                        end
                    end

                    figure(app.OMZer_Vm_UIFigure) % Set this uifigure at the top level
            end

        end

        % Image clicked function: Image_ExportFigure
        function Image_ExportFigureClicked(app, event)

            showImageIconAct(app, app.Image_ExportFigure)

            app.OMZer_Vm_ExportFigure = OMZer_Vm_ExportFigure(app);
            
        end

        % Value changed function: RTEndDropDown, RTStartDropDown
        function RTStartEndDropDownValueChanged(app, event)

            if str2double(app.RTStartDropDown.Value) >= str2double(app.RTEndDropDown.Value)

                msg = 'Start level should be lower than end level';
                uialert(app.OMZer_Vm_UIFigure, msg,'Warning','Icon','error')

                app.RTStartDropDown.Value = app.setup.VmMapCalSetup{3,2};
                app.RTEndDropDown.Value = app.setup.VmMapCalSetup{4,2};
            end

        end

        % Value changed function: MapModeDropDown
        function MapModeDropDownValueChanged(app, event)

            if isequal( app.MapModeDropDown.Value, 'Single')

                app.Image_MapMode.ImageSource = 'Icon Single.png';

            elseif isequal( app.MapModeDropDown.Value, 'Ensemble')

                app.Image_MapMode.ImageSource = 'Icon Multiple.png';
            end

        end

        % Image clicked function: Image_ROIDock
        function Image_ROIDockClicked(app, event)

            showImageIconAct(app, app.Image_ROIDock)

            msg = 'Choose what you want to do';
            msgTitle = 'Warning';
            optionItem = { 'Load a Saved ROI', 'Display the ROI in Dock', 'Cancel' };
            selection = uiconfirm( app.OMZer_Vm_UIFigure, msg, msgTitle, ...
                'Options', optionItem, ...
                'DefaultOption', 1, ...
                'CancelOption', 3 ); % uiconfirm returns the selection as a character vector

            switch selection

                case optionItem{1} % Load a Saved ROI

                    [file,path] = uigetfile('*.mat');

                    if ~ischar(file)

                        msg = 'Only choose one ".mat" file that has a ROI';
                        uialert(app.OMZer_Vm_UIFigure, msg,'Warning','Icon','error')

                        % Reset
                        app.dockMapCalculationROI = [];

                    else

                        msg = 'Loading the ROI';
                        progressBar = uiprogressdlg(app.OMZer_Vm_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');

                        maskData = load( [path,file] ); % 'maskData' is a structure
                        app.dockMapCalculationROI = maskData.mask;

                        close(progressBar)
                    end

                    figure(app.OMZer_Vm_UIFigure) % Set this uifigure at the top level

                case optionItem{2} % Display the ROI in Dock

                    if isempty(app.dockMapCalculationROI)

                        msg = 'You have not loaded a ROI into the dock' ;
                        uialert(app.OMZer_Vm_UIFigure, msg,'Warning','Icon','error')

                    else

                        figure
                        imagesc(app.dockMapCalculationROI)
                    end
            end

        end

        % Image clicked function: Image_RegionalMap
        function Image_RegionalMapClicked(app, event)

            showImageIconAct(app, app.Image_RegionalMap)

            % (1) Check maxMapNumber
            if ( isequal( app.ActLabel.FontColor, [ 1.00, 0.00, 0.00 ] )  &&  ( app.actSaveToID > app.maxMapNumber ) )  ||  ...
                    ( isequal( app.RepLabel.FontColor, [ 1.00, 0.00, 0.00 ] )  &&  ( app.repSaveToID > app.maxMapNumber ) )  ||  ...
                    ( isequal( app.APDLabel.FontColor, [ 1.00, 0.00, 0.00 ] )  &&  ( app.APDSaveToID > app.maxMapNumber ) )  ||  ...
                    ( isequal( app.RTLabel.FontColor, [ 1.00, 0.00, 0.00 ] )  &&  ( app.RTSaveToID > app.maxMapNumber ) )

                msg = [ 'Up to ', num2str(app.maxMapNumber), ' map files can be stored. Delete old ones before new calculation' ];
                uialert(app.OMZer_Vm_UIFigure, msg,'Warning','Icon','error')

                % Reset
                app.callFromRegionalButton = 0;
                app.mapCalculationROI = [];
                return
            end

            % (2) Define the region of interets (ROI)
            msg = 'Choose a method to define the region of interet (ROI)';
            msgTitle = 'Warning';
            optionItem = {'Draw on the Background Image', 'Load a Saved ROI', 'Use ROI in the ROI Dock', 'Cancel'};
            cancelItem = 4;
            selection = uiconfirm( app.OMZer_Vm_UIFigure,msg,msgTitle, 'Options',optionItem, 'DefaultOption',1, 'CancelOption',cancelItem ); % When the user selects an option, uiconfirm returns their selection as a character vector.

            switch selection

                case optionItem{1} % Draw on the Background Image

                    screenSize = get( groot, 'ScreenSize' );
                    fObject = figure;
                    fObject.Position = [ 1, 20, 0.9*screenSize(4), 0.9*screenSize(4) ];

                    % Prepare Background Axis
                    axMap = axes( 'Parent', fObject );
                    axMap.OuterPosition = [ 0, 0, 1, 1 ];
                    axMap.Box = 'on';
                    axMap.YDir = 'Reverse';
                    axMap.XLim = [ 1, app.cmosDataColNum ];
                    axMap.YLim = [ 1, app.cmosDataRowNum ];
                    axMap.PlotBoxAspectRatio = [ 1, 1, 1 ];

                    % Plot Background
                    fp = imagesc( axMap, app.bgImageNomGroup{app.cameraSource} ); % 'fp - figure plot'

                    % Get ROI
                    roiObject = drawpolygon(axMap);

                    if ishandle(roiObject) == 0 % The user close the figure directly withou drawing any roi

                        msg = 'You have not truly set the region of interest (ROI). Draw a region and double click on it to set the ROI.';
                        uialert(app.OMZer_Vm_UIFigure, msg,'Warning','Icon','error')

                        % Reset
                        app.callFromRegionalButton = 0;
                        app.mapCalculationROI = [];
                        figure(app.OMZer_Vm_UIFigure) % Set this uifigure at the top level

                    else

                        wait(roiObject); % If the user draws a roi, he should double click on the roi to truly set it up
                        if ishandle(roiObject) == 0 % Although the user draws a roi, he does not double click on the roi to truly set it up

                            msg = 'You have not truly set the region of interest (ROI). Draw a region and double click on it to set the ROI.';
                            uialert(app.OMZer_Vm_UIFigure, msg,'Warning','Icon','error')

                            % Reset
                            app.callFromRegionalButton = 0;
                            app.mapCalculationROI = [];
                            figure(app.OMZer_Vm_UIFigure) % Set this uifigure at the top level

                        else

                            % The user has truly set a region of interest
                            app.mapCalculationROI = createMask(roiObject,fp);
                            if ishandle(fObject) == 1
                                close(fObject)
                            end
                            app.mapCalculationROI = double(app.mapCalculationROI);
                            app.mapCalculationROI(app.mapCalculationROI == 0) = NaN; %  1 (or NaN) - pixel inside (or outside) mask

                            figure(app.OMZer_Vm_UIFigure) % Set this uifigure at the top level

                            app.callFromRegionalButton = 1;
                            Image_GlobalMapClicked(app, event)
                        end
                    end

                case optionItem{2} % Load a Saved ROI

                    [file,path] = uigetfile('*.mat');

                    if ~ischar(file)

                        msg = 'Only choose one ".mat" file that has a ROI';
                        uialert(app.OMZer_Vm_UIFigure, msg,'Warning','Icon','error')

                        % Reset
                        app.callFromRegionalButton = 0;
                        app.mapCalculationROI = [];
                        figure(app.OMZer_Vm_UIFigure) % Set this uifigure at the top level

                    else

                        msg = 'Loading the ROI';
                        progressBar = uiprogressdlg(app.OMZer_Vm_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');

                        maskData = load( [path,file] ); % 'Data' is a structure
                        app.mapCalculationROI = maskData.mask;

                        figure(app.OMZer_Vm_UIFigure) % Set this uifigure at the top level
                        close(progressBar)

                        app.callFromRegionalButton = 1;
                        Image_GlobalMapClicked(app, event)
                    end

                case optionItem{3} % Use ROI in the ROI Dock

                    if isempty(app.dockMapCalculationROI)

                        msg = 'You have not loaded a ROI into the dock' ;
                        uialert(app.OMZer_Vm_UIFigure, msg,'Warning','Icon','error')

                        % Reset
                        app.callFromRegionalButton = 0;
                        app.mapCalculationROI = [];
                        figure(app.OMZer_Vm_UIFigure) % Set this uifigure at the top level

                    else

                        app.mapCalculationROI = app.dockMapCalculationROI;

                        figure(app.OMZer_Vm_UIFigure) % Set this uifigure at the top level

                        app.callFromRegionalButton = 1;
                        Image_GlobalMapClicked(app, event)
                    end
            end

        end

        % Image clicked function: Image_GlobalMap
        function Image_GlobalMapClicked(app, event)

            showImageIconAct(app, app.Image_GlobalMap)

            % (1) Check maxMapNumber
            if ( isequal( app.ActLabel.FontColor, [ 1.00, 0.00, 0.00 ] )  &&  ( app.actSaveToID > app.maxMapNumber ) )  ||  ...
                    ( isequal( app.RepLabel.FontColor, [ 1.00, 0.00, 0.00 ] )  &&  ( app.repSaveToID > app.maxMapNumber ) )  ||  ...
                    ( isequal( app.APDLabel.FontColor, [ 1.00, 0.00, 0.00 ] )  &&  ( app.APDSaveToID > app.maxMapNumber ) )  ||  ...
                    ( isequal( app.RTLabel.FontColor, [ 1.00, 0.00, 0.00 ] )  &&  ( app.RTSaveToID > app.maxMapNumber ) )

                msg = [ 'Up to ', num2str(app.maxMapNumber), ' map files can be stored. Delete old ones before new calculation' ];
                uialert(app.OMZer_Vm_UIFigure, msg,'Warning','Icon','error')

                % Reset
                app.callFromRegionalButton = 0;
                app.mapCalculationROI = [];
                return
            end


            % (2) Check global or regional map calculation
            if app.callFromRegionalButton == 1
                areaLabel = 'Regional';
            else
                app.mapCalculationROI = ones( app.cmosDataRowNum, app.cmosDataColNum );
                areaLabel = 'Global';
            end


            % (3) Single map calculation
            if isequal( app.MapModeDropDown.Value, 'Single' )

                if isempty(app.oneBeatWindowStartIndex)  ||  isempty(app.oneBeatWindowEndIndex) % No single beat set up by the user yet

                    msg = 'Choose a single beat before new calculation';
                    uialert(app.OMZer_Vm_UIFigure, msg,'Warning','Icon','error')

                    Image_SignalClicked(app, 'clicked')

                else

                    if isequal( app.ActLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Act map

                        msg = [ 'Calculate the ', areaLabel, ' ', app.MapModeDropDown.Value, ' ', app.ActLabel.Text, ' Map' ];
                        progressBar = uiprogressdlg(app.OMZer_Vm_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');

                        [ dataSectionTime, ...
                            dataSection, ...
                            locsAct, ...
                            actMatrix ] = calculateAct( app.acqFreq, app.cmosDataGroup{app.cameraSource}, app.oneBeatWindowStartIndex, app.oneBeatWindowEndIndex, app.mapCalculationROI, app.actDefinition);

                        pksNum = [];
                        mapUserComment = [ 'Activation definition - ', app.actDefinition ];

                        saveActMap(app, app.oneBeatWindowStartIndex, app.oneBeatWindowEndIndex, areaLabel, app.MapModeDropDown.Value, dataSectionTime, dataSection, locsAct, actMatrix, pksNum, mapUserComment)
                        app.actSaveToID = app.actSaveToID + 1;
                        prepareMapCalculation(app, app.Image_Act)

                        close(progressBar)

                    elseif isequal( app.RepLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Rep map

                        msg = [ 'Calculate the ', areaLabel, ' ', app.MapModeDropDown.Value, ' ', app.RepLabel.Text, ' Map' ];
                        progressBar = uiprogressdlg(app.OMZer_Vm_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');

                        [ dataSectionTime, ...
                            dataSection, ...
                            locsRep, ...
                            repMatrix ] = calculateRep( app.acqFreq, app.cmosDataGroup{app.cameraSource}, app.oneBeatWindowStartIndex, app.oneBeatWindowEndIndex, app.mapCalculationROI, (str2double(app.RepDropDown.Value)/100) );

                        pksNum = [];
                        mapUserComment = '';

                        saveRepMap(app, app.oneBeatWindowStartIndex, app.oneBeatWindowEndIndex, areaLabel, app.MapModeDropDown.Value, dataSectionTime, dataSection, locsRep, repMatrix, pksNum, mapUserComment, (str2double(app.RepDropDown.Value)/100))
                        app.repSaveToID = app.repSaveToID + 1;
                        prepareMapCalculation(app, app.Image_Rep)

                        close(progressBar)

                    elseif isequal( app.APDLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % APD map

                        msg = [ 'Calculate the ', areaLabel, ' ', app.MapModeDropDown.Value, ' ', app.APDLabel.Text, ' Map' ];
                        progressBar = uiprogressdlg(app.OMZer_Vm_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');

                        [ dataSectionTime, ...
                            dataSection, ...
                            locsAct, ...
                            locsRep, ...
                            durationMatrix ] = calculateDuration( app.acqFreq, app.cmosDataGroup{app.cameraSource}, app.oneBeatWindowStartIndex, app.oneBeatWindowEndIndex, app.mapCalculationROI, (str2double(app.APDDropDown.Value)/100), app.actDefinition );

                        pksNum = [];
                        mapUserComment = [ 'Activation definition - ', app.actDefinition ];

                        saveAPDMap(app, app.oneBeatWindowStartIndex, app.oneBeatWindowEndIndex, areaLabel, app.MapModeDropDown.Value, dataSectionTime, dataSection, locsAct, locsRep, durationMatrix, pksNum, mapUserComment, (str2double(app.APDDropDown.Value)/100))
                        app.APDSaveToID = app.APDSaveToID + 1;
                        prepareMapCalculation(app, app.Image_APD)

                        close(progressBar)

                    elseif isequal( app.RTLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % RT map

                        msg = [ 'Calculate the ', areaLabel, ' ', app.MapModeDropDown.Value, ' ', app.RTLabel.Text, ' Map' ];
                        progressBar = uiprogressdlg(app.OMZer_Vm_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');

                        [ dataSectionTime, ...
                            dataSection, ...
                            locsStart, ...
                            locsEnd, ...
                            durationMatrix ] = calculateRT( app.acqFreq, app.cmosDataGroup{app.cameraSource}, app.oneBeatWindowStartIndex, app.oneBeatWindowEndIndex, app.mapCalculationROI, (str2double(app.RTStartDropDown.Value)/100), (str2double(app.RTEndDropDown.Value)/100) );

                        pksNum = [];
                        mapUserComment = '';

                        saveRTMap(app, app.oneBeatWindowStartIndex, app.oneBeatWindowEndIndex, areaLabel, app.MapModeDropDown.Value, dataSectionTime, dataSection, locsStart, locsEnd, durationMatrix, pksNum, mapUserComment, (str2double(app.RTStartDropDown.Value)/100), (str2double(app.RTEndDropDown.Value)/100))
                        app.RTSaveToID = app.RTSaveToID + 1;
                        prepareMapCalculation(app, app.Image_RT)

                        close(progressBar)
                    end
                end

            end


            % (4) Ensemble map calculation
            if isequal( app.MapModeDropDown.Value, 'Ensemble' )

                msg = 'Choose the method to generate ensemble signals';
                msgTitle = 'Warning';
                optionItem = { 'One Beat & Pacing', 'One Beat & Peak Detection', 'Multi Beats', 'Cancel' };
                selection = uiconfirm( app.OMZer_Vm_UIFigure, msg, msgTitle, ...
                    'Options', optionItem, ...
                    'DefaultOption', 1, ...
                    'CancelOption', 4 ); % uiconfirm returns the selection as a character vector

                % (4.1) Choose different ensemble methods
                switch selection

                    case optionItem{1} % One Beat & Pacing

                        if isempty(app.oneBeatWindowStartIndex)  ||  isempty(app.oneBeatWindowEndIndex) % No single beat set up by the user yet

                            msg = 'Choose a single beat before new calculation';
                            uialert(app.OMZer_Vm_UIFigure, msg,'Warning','Icon','error')
                            Image_SignalClicked(app, 'clicked')
                            selection = 'Cancel';

                        else

                            if isequal( app.pacing1Enable, 'off' )  &&  isequal( app.pacing2Enable, 'off' )

                                msg = 'There are no pacing signals at all';
                                uialert(app.OMZer_Vm_UIFigure, msg,'Warning','Icon','error')
                                selection = 'Cancel';

                            elseif isequal( app.pacing1Enable, 'on' )  &&  isequal( app.pacing2Enable, 'on' )

                                msg = 'Choose the pacing signals';
                                msgTitle = 'Warning';
                                optionItem = { 'One Beat & Pacing 1', 'One Beat & Pacing 2', 'Cancel' };
                                selection = uiconfirm( app.OMZer_Vm_UIFigure, msg, msgTitle, ...
                                    'Options', optionItem, ...
                                    'DefaultOption', 1, ...
                                    'CancelOption', 3);

                            elseif isequal( app.pacing1Enable, 'on' )  &&  isequal( app.pacing2Enable, 'off' )

                                selection = 'One Beat & Pacing 1';

                            elseif isequal( app.pacing1Enable, 'off' )  &&  isequal( app.pacing2Enable, 'on' )

                                selection = 'One Beat & Pacing 2';
                            end
                        end

                    case optionItem{2} % One Beat & Peak Detection

                        if isempty(app.oneBeatWindowStartIndex)  ||  isempty(app.oneBeatWindowEndIndex) % No single beat set up by the user yet

                            msg = 'Choose a single beat before new calculation';
                            uialert(app.OMZer_Vm_UIFigure, msg,'Warning','Icon','error')
                            Image_SignalClicked(app, 'clicked')
                            selection = 'Cancel';

                        else
                            selection = 'One Beat & Peak Detection';
                        end

                    case optionItem{3} % Multi Beats

                        if isempty(app.multiBeatsWindowStartIndex)  ||  isempty(app.multiBeatsWindowEndIndex) % No multi beats set up by the user yet

                            msg = 'Choose multi beats before new calculation';
                            uialert(app.OMZer_Vm_UIFigure, msg,'Warning','Icon','error')
                            Image_SignalClicked(app, 'clicked')
                            selection = 'Cancel';

                        else
                            selection = 'Multi Beats';
                        end
                end

                % Start calculation
                if ~isequal( selection, 'Cancel') % selection = 'One Beat & Pacing 1' or 'One Beat & Pacing 2' or 'One Beat & Peak Detection' or 'Multi Beats'

                    msg = 'Generate Ensemble Signals';
                    progressBar = uiprogressdlg(app.OMZer_Vm_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');

                    % (4.2) Preparation before generating ensemble signals
                    if isequal( selection, 'One Beat & Pacing 1' )

                        [ anchorIndex, beatStart_to_anchor_length, beatEnd_to_anchor_length, specialOneBeatWindowStartIndex, specialOneBeatWindowEndIndex ] = calculateAnchorIndex( selection, app.pacing1, app.signalTime, app.oneBeatWindowStartIndex, app.oneBeatWindowEndIndex );

                    elseif isequal( selection, 'One Beat & Pacing 2' )

                        [ anchorIndex, beatStart_to_anchor_length, beatEnd_to_anchor_length, specialOneBeatWindowStartIndex, specialOneBeatWindowEndIndex ] = calculateAnchorIndex( selection, app.pacing2, app.signalTime, app.oneBeatWindowStartIndex, app.oneBeatWindowEndIndex );

                    elseif isequal( selection, 'One Beat & Peak Detection' )

                        row = app.markerXY(1,2); % Y
                        col = app.markerXY(1,1); % X
                        [ anchorIndex, beatStart_to_anchor_length, beatEnd_to_anchor_length, specialOneBeatWindowStartIndex, specialOneBeatWindowEndIndex ] = calculateAnchorIndex( selection, str2double(app.MinPeakHeightDropDown.Value), app.cmosDataGroup{app.cameraSource}, row, col, app.signalTime, app.oneBeatWindowStartIndex, app.oneBeatWindowEndIndex );

                    elseif isequal( selection, 'Multi Beats' )

                        row = app.markerXY(1,2); % Y
                        col = app.markerXY(1,1); % X
                        [ anchorIndex, beatStart_to_anchor_length, beatEnd_to_anchor_length, specialOneBeatWindowStartIndex, specialOneBeatWindowEndIndex ] = calculateAnchorIndex( selection, app.cmosDataGroup{app.cameraSource}, row, col, app.signalTime, app.multiBeatsWindowStartIndex, app.multiBeatsWindowEndIndex );
                    end

                    % (4.3) Generate ensemble signals
                    if prod(~isnan( anchorIndex )) == 1

                        [ ensembleSignal, ensembleSignalLength, pksIndex, pksNumber ] = calculateEnsemble( app.cmosDataGroup{app.cameraSource}, anchorIndex, beatStart_to_anchor_length, beatEnd_to_anchor_length, specialOneBeatWindowStartIndex, specialOneBeatWindowEndIndex, str2double(app.MinPeakHeightDropDown.Value) );

                    else

                        ensembleSignal = NaN;
                        msg = 'No anchor index can be found to generate ensemble signals';
                        uialert(app.OMZer_Vm_UIFigure, msg,'Warning','Icon','error')
                    end
                    close(progressBar)

                    % (4.4) Map calculation
                    if sum( ~isnan(ensembleSignal(:)) ) >= 1

                        if isequal( app.ActLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Act map

                            msg = [ 'Calculate the ', areaLabel, ' ', app.MapModeDropDown.Value, ' ', app.ActLabel.Text, ' Map' ];
                            progressBar = uiprogressdlg(app.OMZer_Vm_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');

                            [ dataSectionTime, ...
                                dataSection, ...
                                locsAct, ...
                                actMatrix ] = calculateAct( app.acqFreq, ensembleSignal, 1, ensembleSignalLength, app.mapCalculationROI, app.actDefinition);

                            pksNum = pksNumber;
                            mapUserComment = { [ 'Activation definition - ', app.actDefinition ]; [ 'Ensemble method - ', selection ] };

                            if isequal( selection, 'Multi Beats' )

                                storeBeatWindowStartIndex = app.multiBeatsWindowStartIndex;
                                storeBeatWindowEndIndex = app.multiBeatsWindowEndIndex;
                                mapUserComment = generateMultiBeatsMapUserComment(app, mapUserComment, app.signalTime(storeBeatWindowStartIndex), app.signalTime(storeBeatWindowEndIndex));

                            else

                                storeBeatWindowStartIndex = app.oneBeatWindowStartIndex;
                                storeBeatWindowEndIndex = app.oneBeatWindowEndIndex;
                            end

                            saveActMap(app, storeBeatWindowStartIndex, storeBeatWindowEndIndex, areaLabel, app.MapModeDropDown.Value, dataSectionTime, dataSection, locsAct, actMatrix, pksNum, mapUserComment)
                            app.actSaveToID = app.actSaveToID + 1;
                            prepareMapCalculation(app, app.Image_Act)

                            close(progressBar)

                        elseif isequal( app.RepLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Rep map

                            msg = [ 'Calculate the ', areaLabel, ' ', app.MapModeDropDown.Value, ' ', app.RepLabel.Text, ' Map' ];
                            progressBar = uiprogressdlg(app.OMZer_Vm_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');

                            [ dataSectionTime, ...
                                dataSection, ...
                                locsRep, ...
                                repMatrix ] = calculateRep( app.acqFreq, ensembleSignal, 1, ensembleSignalLength, app.mapCalculationROI, (str2double(app.RepDropDown.Value)/100) );

                            pksNum = pksNumber;
                            mapUserComment = { [ 'Ensemble method - ', selection ] };

                            if isequal( selection, 'Multi Beats' )

                                storeBeatWindowStartIndex = app.multiBeatsWindowStartIndex;
                                storeBeatWindowEndIndex = app.multiBeatsWindowEndIndex;
                                mapUserComment = generateMultiBeatsMapUserComment(app, mapUserComment, app.signalTime(storeBeatWindowStartIndex), app.signalTime(storeBeatWindowEndIndex));

                            else

                                storeBeatWindowStartIndex = app.oneBeatWindowStartIndex;
                                storeBeatWindowEndIndex = app.oneBeatWindowEndIndex;
                            end

                            saveRepMap(app, storeBeatWindowStartIndex, storeBeatWindowEndIndex, areaLabel, app.MapModeDropDown.Value, dataSectionTime, dataSection, locsRep, repMatrix, pksNum, mapUserComment, (str2double(app.RepDropDown.Value)/100))
                            app.repSaveToID = app.repSaveToID + 1;
                            prepareMapCalculation(app, app.Image_Rep)

                            close(progressBar)

                        elseif isequal( app.APDLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % APD map

                            msg = [ 'Calculate the ', areaLabel, ' ', app.MapModeDropDown.Value, ' ', app.APDLabel.Text, ' Map' ];
                            progressBar = uiprogressdlg(app.OMZer_Vm_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');

                            [ dataSectionTime, ...
                                dataSection, ...
                                locsAct, ...
                                locsRep, ...
                                durationMatrix ] = calculateDuration( app.acqFreq, ensembleSignal, 1, ensembleSignalLength, app.mapCalculationROI, (str2double(app.APDDropDown.Value)/100), app.actDefinition );

                            pksNum = pksNumber;
                            mapUserComment = { [ 'Activation definition - ', app.actDefinition ]; [ 'Ensemble method - ', selection ] };

                            if isequal( selection, 'Multi Beats' )

                                storeBeatWindowStartIndex = app.multiBeatsWindowStartIndex;
                                storeBeatWindowEndIndex = app.multiBeatsWindowEndIndex;
                                mapUserComment = generateMultiBeatsMapUserComment(app, mapUserComment, app.signalTime(storeBeatWindowStartIndex), app.signalTime(storeBeatWindowEndIndex));

                            else

                                storeBeatWindowStartIndex = app.oneBeatWindowStartIndex;
                                storeBeatWindowEndIndex = app.oneBeatWindowEndIndex;
                            end

                            saveAPDMap(app, storeBeatWindowStartIndex, storeBeatWindowEndIndex, areaLabel, app.MapModeDropDown.Value, dataSectionTime, dataSection, locsAct, locsRep, durationMatrix, pksNum, mapUserComment, (str2double(app.APDDropDown.Value)/100))
                            app.APDSaveToID = app.APDSaveToID + 1;
                            prepareMapCalculation(app, app.Image_APD)

                            close(progressBar)

                        elseif isequal( app.RTLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % RT map

                            msg = [ 'Calculate the ', areaLabel, ' ', app.MapModeDropDown.Value, ' ', app.RTLabel.Text, ' Map' ];
                            progressBar = uiprogressdlg(app.OMZer_Vm_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');

                            [ dataSectionTime, ...
                                dataSection, ...
                                locsStart, ...
                                locsEnd, ...
                                durationMatrix ] = calculateRT( app.acqFreq, ensembleSignal, 1, ensembleSignalLength, app.mapCalculationROI, (str2double(app.RTStartDropDown.Value)/100), (str2double(app.RTEndDropDown.Value)/100) );

                            pksNum =  pksNumber;
                            mapUserComment = { [ 'Ensemble method - ', selection ] };

                            if isequal( selection, 'Multi Beats' )

                                storeBeatWindowStartIndex = app.multiBeatsWindowStartIndex;
                                storeBeatWindowEndIndex = app.multiBeatsWindowEndIndex;
                                mapUserComment = generateMultiBeatsMapUserComment(app, mapUserComment, app.signalTime(storeBeatWindowStartIndex), app.signalTime(storeBeatWindowEndIndex));

                            else

                                storeBeatWindowStartIndex = app.oneBeatWindowStartIndex;
                                storeBeatWindowEndIndex = app.oneBeatWindowEndIndex;
                            end

                            saveRTMap(app, storeBeatWindowStartIndex, storeBeatWindowEndIndex, areaLabel, app.MapModeDropDown.Value, dataSectionTime, dataSection, locsStart, locsEnd, durationMatrix, pksNum, mapUserComment, (str2double(app.RTStartDropDown.Value)/100), (str2double(app.RTEndDropDown.Value)/100))
                            app.RTSaveToID = app.RTSaveToID + 1;
                            prepareMapCalculation(app, app.Image_RT)

                            close(progressBar)
                        end
                    end

                end
            end


            % (5) Reset
            app.callFromRegionalButton = 0;
            app.mapCalculationROI = [];

            figure(app.OMZer_Vm_UIFigure) % Set this uifigure at the top level

        end

        % Value changed function: LBmsSpinner
        function LBmsSpinnerValueChanged(app, event)

            checkMapID = str2double(app.MapListBox.Value);

            % (1) Initial bound check
            if app.LBmsSpinner.Value >= app.UBmsSpinner.Value

                msg = 'Lower bound value should be smaller than upper bound value';
                uialert(app.OMZer_Vm_UIFigure, msg,'Warning','Icon','error')

                app.LBmsSpinner.Value = min(app.LBmsSpinner.Limits);
                app.UBmsSpinner.Value = max(app.UBmsSpinner.Limits);
            end

            % (2) Update data
            if isequal( app.ActLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Act map

                matrix = app.actMapData{1,10,checkMapID};
                checkLBUBAdjustment(app, matrix)
                app.actMapData{1,5,checkMapID} = app.LBmsSpinner.Value;
                app.actMapData{1,6,checkMapID} = app.UBmsSpinner.Value;

            elseif isequal( app.RepLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Rep map

                matrix = app.repMapData{1,11,checkMapID};
                checkLBUBAdjustment(app, matrix)
                app.repMapData{1,6,checkMapID} = app.LBmsSpinner.Value;
                app.repMapData{1,7,checkMapID} = app.UBmsSpinner.Value;

            elseif isequal( app.APDLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % APD map

                matrix = app.APDMapData{1,12,checkMapID};
                checkLBUBAdjustment(app, matrix)
                app.APDMapData{1,6,checkMapID} = app.LBmsSpinner.Value;
                app.APDMapData{1,7,checkMapID} = app.UBmsSpinner.Value;

            elseif isequal( app.RTLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % RT map

                matrix = app.RTMapData{1,13,checkMapID};
                checkLBUBAdjustment(app, matrix)
                app.RTMapData{1,7,checkMapID} = app.LBmsSpinner.Value;
                app.RTMapData{1,8,checkMapID} = app.UBmsSpinner.Value;
            end

            % (3) Update map plot
            MapListBoxValueChanged(app, event)

        end

        % Value changing function: LBmsSpinner
        function LBmsSpinnerValueChanging(app, event)

            if ischar(event.Value)
                app.LBmsSpinner.Value = str2double(event.Value);
            else
                app.LBmsSpinner.Value = event.Value;
            end

            LBmsSpinnerValueChanged(app, event)

        end

        % Value changed function: UBmsSpinner
        function UBmsSpinnerValueChanged(app, event)

            checkMapID = str2double(app.MapListBox.Value);

            % (1) Initial bound check
            if app.LBmsSpinner.Value >= app.UBmsSpinner.Value

                msg = 'Lower bound value should be smaller than upper bound value';
                uialert(app.OMZer_Vm_UIFigure, msg,'Warning','Icon','error')

                app.LBmsSpinner.Value = min(app.LBmsSpinner.Limits);
                app.UBmsSpinner.Value = max(app.UBmsSpinner.Limits);
            end

            % (2) Update data
            if isequal( app.ActLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Act map

                matrix = app.actMapData{1,10,checkMapID};
                checkLBUBAdjustment(app, matrix)
                app.actMapData{1,5,checkMapID} = app.LBmsSpinner.Value;
                app.actMapData{1,6,checkMapID} = app.UBmsSpinner.Value;

            elseif isequal( app.RepLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Rep map

                matrix = app.repMapData{1,11,checkMapID};
                checkLBUBAdjustment(app, matrix)
                app.repMapData{1,6,checkMapID} = app.LBmsSpinner.Value;
                app.repMapData{1,7,checkMapID} = app.UBmsSpinner.Value;

            elseif isequal( app.APDLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % APD map

                matrix = app.APDMapData{1,12,checkMapID};
                checkLBUBAdjustment(app, matrix)
                app.APDMapData{1,6,checkMapID} = app.LBmsSpinner.Value;
                app.APDMapData{1,7,checkMapID} = app.UBmsSpinner.Value;

            elseif isequal( app.RTLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % RT map

                matrix = app.RTMapData{1,13,checkMapID};
                checkLBUBAdjustment(app, matrix)
                app.RTMapData{1,7,checkMapID} = app.LBmsSpinner.Value;
                app.RTMapData{1,8,checkMapID} = app.UBmsSpinner.Value;
            end

            % (3) Update map plot
            MapListBoxValueChanged(app, event)

        end

        % Value changing function: UBmsSpinner
        function UBmsSpinnerValueChanging(app, event)

            if ischar(event.Value)
                app.UBmsSpinner.Value = str2double(event.Value);
            else
                app.UBmsSpinner.Value = event.Value;
            end

            UBmsSpinnerValueChanged(app, event);

        end

        % Value changed function: MapListBox
        function MapListBoxValueChanged(app, event)

            % (1) Update ribbon menu and calculation components
            app.Image_ExportData.Enable = 'on';
            app.Image_ExportFigure.Enable = 'on';

            app.Image_RegionalMap.Enable = 'off';
            app.Image_GlobalMap.Enable = 'off';

            % (2) Unload the previous map
            % (2.1) Reset statistic result
            resetStatisticResult(app)
            app.LBmsSpinner.Enable = 'on';
            app.UBmsSpinner.Enable = 'on';

            % (2.2) Reset signal window information
            app.WinStartsecEditField.Value = Inf;
            app.WinEndsecEditField.Value = Inf;

            % (2.3) Clear displayed map and signal plot
            app.MapTitleLabel.Text = 'Map Title';
            cla(app.MapAxis)
            cla(app.colorBarAxes)
            cla(app.MapHistAxis)
            imagesc( app.MapAxis, app.bgImageNomGroup{app.cameraSource} );

            % (2.4) Clear check signal
            if ~isempty(app.checkSigMarkerPlot)

                clearCheckSigPlot(app)

                app.CheckSigX_scrn.Value = 1;
                app.CheckSigY_scrn.Value = 1;
                app.CheckSigX_scrn.Editable = 'off';
                app.CheckSigY_scrn.Editable = 'off';
                app.CheckSigX_scrn.BackgroundColor = [1.00, 1.00, 1.00]; % White
                app.CheckSigY_scrn.BackgroundColor = [1.00, 1.00, 1.00];
            end

            % (2.5) Clear map comment
            app.UserCommentTextArea.Value = {''};

            % (3) Load new map data
            % (3.1) Enable map display components
            app.Image_ResetBound.Enable = 'on';
            app.Image_UnloadMap.Enable = 'on';
            app.Image_DeleteMap.Enable = 'on';

            app.Image_DisplayPixel.Enable = 'on';
            app.Image_ClearPixel.Enable = 'on';

            app.UserCommentTextArea.Enable = 'on';

            % (3.2) Load data
            checkMapID = str2double(app.MapListBox.Value);

            if isequal( app.ActLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Act map

                clearActMapPlot(app)

                thisWinStartIndex = app.actMapData{1,1,checkMapID};
                thisWindEndIndex = app.actMapData{1,2,checkMapID};

                AreaMode = app.actMapData{1,3,checkMapID};
                APMode = app.actMapData{1,4,checkMapID};

                LB = app.actMapData{1,5,checkMapID};
                UB = app.actMapData{1,6,checkMapID};
                matrix = app.actMapData{1,10,checkMapID};

                pksNum = app.actMapData{1,11,checkMapID};

                if ~isempty( app.actMapData{1,12,checkMapID} )
                    app.UserCommentTextArea.Value = app.actMapData{1,12,checkMapID};
                end

                app.MapTitleLabel.Text = [ AreaMode, ' ', APMode, ' Activation Map' ];

                app.actMapPlot = plotMap(app, app.actMapPlot, matrix, LB, UB);
                app.actColorBarPlot = plotMapColorBar(app, app.actColorBarPlot, matrix, LB, UB);
                app.actHistPlot = plotMapHist(app, app.actHistPlot, matrix, LB, UB);

                updateStatisticResult(app, matrix, LB, UB)
                updateSignalWindowInformation(app, thisWinStartIndex, thisWindEndIndex)

                % if ~isempty(pksNum)
                %     plotEnsemblePksNum(app, app.ActEnsemblePksNumPlot, pksNum)
                % end

                % For export data
                app.exportMapName = app.ActLabel.Text;
                app.exportMapDataTitle = app.actMapDataTitle;
                app.exportMapData = app.actMapData(:, :, checkMapID);

            elseif isequal( app.RepLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Rep map

                clearRepMapPlot(app)

                thisWinStartIndex = app.repMapData{1,1,checkMapID};
                thisWindEndIndex = app.repMapData{1,2,checkMapID};

                AreaMode = app.repMapData{1,3,checkMapID};
                APMode = app.repMapData{1,4,checkMapID};
                RepLevel = app.repMapData{1,5,checkMapID};

                LB = app.repMapData{1,6,checkMapID};
                UB = app.repMapData{1,7,checkMapID};
                matrix = app.repMapData{1,11,checkMapID};

                pksNum = app.repMapData{1,12,checkMapID};

                if ~isempty( app.repMapData{1,13,checkMapID} )
                    app.UserCommentTextArea.Value = app.repMapData{1,13,checkMapID};
                end

                app.MapTitleLabel.Text = [ AreaMode, ' ', APMode, ' Repolarisation ', num2str(RepLevel*100), ' Map' ];

                app.repMapPlot = plotMap(app, app.repMapPlot, matrix, LB, UB);
                app.repColorBarPlot = plotMapColorBar(app, app.repColorBarPlot, matrix, LB, UB);
                app.repHistPlot = plotMapHist(app, app.repHistPlot, matrix, LB, UB);

                updateStatisticResult(app, matrix, LB, UB)
                updateSignalWindowInformation(app, thisWinStartIndex, thisWindEndIndex)

                % if ~isempty(pksNum)
                %     plotEnsemblePksNum(app, app.RepEnsemblePksNumPlot, pksNum)
                % end

                % For export data
                app.exportMapName = app.RepLabel.Text;
                app.exportMapDataTitle = app.repMapDataTitle;
                app.exportMapData = app.repMapData(:, :, checkMapID);

            elseif isequal( app.APDLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % APD map

                clearAPDMapPlot(app)

                thisWinStartIndex = app.APDMapData{1,1,checkMapID};
                thisWindEndIndex = app.APDMapData{1,2,checkMapID};

                AreaMode = app.APDMapData{1,3,checkMapID};
                APMode = app.APDMapData{1,4,checkMapID};

                APDLevel = app.APDMapData{1,5,checkMapID};

                LB = app.APDMapData{1,6,checkMapID};
                UB = app.APDMapData{1,7,checkMapID};
                matrix = app.APDMapData{1,12,checkMapID};

                pksNum = app.APDMapData{1,13,checkMapID};

                if ~isempty( app.APDMapData{1,14,checkMapID} )
                    app.UserCommentTextArea.Value = app.APDMapData{1,14,checkMapID};
                end

                app.MapTitleLabel.Text = [ AreaMode, ' ', APMode, ' APD ', num2str(APDLevel*100), ' Map' ];

                app.APDMapPlot = plotMap(app, app.APDMapPlot, matrix, LB, UB);
                app.APDColorBarPlot = plotMapColorBar(app, app.APDColorBarPlot, matrix, LB, UB);
                app.APDHistPlot = plotMapHist(app, app.APDHistPlot, matrix, LB, UB);

                updateStatisticResult(app, matrix, LB, UB)
                updateSignalWindowInformation(app, thisWinStartIndex, thisWindEndIndex)

                % if ~isempty(pksNum)
                %     plotEnsemblePksNum(app, app.APDEnsemblePksNumPlot, pksNum)
                % end

                % For export data
                app.exportMapName = app.APDLabel.Text;
                app.exportMapDataTitle = app.APDMapDataTitle;
                app.exportMapData = app.APDMapData(:, :, checkMapID);

            elseif isequal( app.RTLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % RT map

                clearRTMapPlot(app)

                thisWinStartIndex = app.RTMapData{1,1,checkMapID};
                thisWindEndIndex = app.RTMapData{1,2,checkMapID};

                AreaMode = app.RTMapData{1,3,checkMapID};
                APMode = app.RTMapData{1,4,checkMapID};

                startLevel = app.RTMapData{1,5,checkMapID};
                endLevel = app.RTMapData{1,6,checkMapID};

                LB = app.RTMapData{1,7,checkMapID};
                UB = app.RTMapData{1,8,checkMapID};
                matrix = app.RTMapData{1,13,checkMapID};

                pksNum = app.RTMapData{1,14,checkMapID};

                if ~isempty( app.RTMapData{1,15,checkMapID} )
                    app.UserCommentTextArea.Value = app.RTMapData{1,15,checkMapID};
                end

                app.MapTitleLabel.Text = [ AreaMode, ' ', APMode, ' Rise Time ', num2str(startLevel*100), '-', num2str(endLevel*100), ' Map' ];

                app.RTMapPlot = plotMap(app, app.RTMapPlot, matrix, LB, UB);
                app.RTColorBarPlot = plotMapColorBar(app, app.RTColorBarPlot, matrix, LB, UB);
                app.RTHistPlot = plotMapHist(app, app.RTHistPlot, matrix, LB, UB);

                updateStatisticResult(app, matrix, LB, UB)
                updateSignalWindowInformation(app, thisWinStartIndex, thisWindEndIndex)

                % if ~isempty(pksNum)
                %     plotEnsemblePksNum(app, app.RTEnsemblePksNumPlot, pksNum)
                % end

                % For export data
                app.exportMapName = app.RTLabel.Text;
                app.exportMapDataTitle = app.RTMapDataTitle;
                app.exportMapData = app.RTMapData(:, :, checkMapID);
            end

        end

        % Image clicked function: Image_ResetBound
        function Image_ResetBoundClicked(app, event)

            showImageIconAct(app, app.Image_ResetBound)

            checkMapID = str2double(app.MapListBox.Value);

            if isequal( app.ActLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Act map

                matrix = app.actMapData{1,10,checkMapID};
                matrix = matrix - nanmin(matrix(:));

                app.actMapData{1,5,checkMapID} = nanmin( matrix(:) );
                app.actMapData{1,6,checkMapID} = nanmax( matrix(:) );

            elseif isequal( app.RepLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Rep map

                matrix = app.repMapData{1,11,checkMapID};
                matrix = matrix - nanmin(matrix(:));

                app.repMapData{1,6,checkMapID} = nanmin( matrix(:) );
                app.repMapData{1,7,checkMapID} = nanmax( matrix(:) );

            elseif isequal( app.APDLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % APD map

                matrix = app.APDMapData{1,12,checkMapID};

                app.APDMapData{1,6,checkMapID} = nanmin( matrix(:) );
                app.APDMapData{1,7,checkMapID} = nanmax( matrix(:) );

            elseif isequal( app.RTLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % RT map

                matrix = app.RTMapData{1,13,checkMapID};

                app.RTMapData{1,7,checkMapID} = nanmin( matrix(:) );
                app.RTMapData{1,8,checkMapID} = nanmax( matrix(:) );
            end

            MapListBoxValueChanged(app, event)

        end

        % Image clicked function: Image_UnloadMap
        function Image_UnloadMapClicked(app, event)

            showImageIconAct(app, app.Image_UnloadMap)

            % (1) Update ribbon menu and calculation components
            app.Image_ExportData.Enable = 'off';
            app.Image_ExportFigure.Enable = 'off';

            app.Image_RegionalMap.Enable = 'on';
            app.Image_GlobalMap.Enable = 'on';

            % (2) Unload the previous map
            % (2.1) Reset statistic result
            resetStatisticResult(app)
            app.LBmsSpinner.Enable = 'off';
            app.UBmsSpinner.Enable = 'off';

            % (2.2) Reset signal window information
            app.WinStartsecEditField.Value = Inf;
            app.WinEndsecEditField.Value = Inf;

            % (2.3) Clear displayed map and signal plot
            app.MapTitleLabel.Text = 'Map Title';
            cla(app.MapAxis)
            cla(app.colorBarAxes)
            cla(app.MapHistAxis)
            imagesc( app.MapAxis, app.bgImageNomGroup{app.cameraSource} );

            % (2.4) Clear check signal
            if ~isempty(app.checkSigMarkerPlot)

                clearCheckSigPlot(app)

                app.CheckSigX_scrn.Value = 1;
                app.CheckSigY_scrn.Value = 1;
                app.CheckSigX_scrn.Editable = 'off';
                app.CheckSigY_scrn.Editable = 'off';
                app.CheckSigX_scrn.BackgroundColor = [1.00, 1.00, 1.00]; % White
                app.CheckSigY_scrn.BackgroundColor = [1.00, 1.00, 1.00];
            end

            % (3) Disable map display components
            app.Image_ResetBound.Enable = 'off';
            app.Image_UnloadMap.Enable = 'off';
            app.Image_DeleteMap.Enable = 'off';

            app.Image_DisplayPixel.Enable = 'off';
            app.Image_ClearPixel.Enable = 'off';

            app.UserCommentTextArea.Enable = 'off';
            app.UserCommentTextArea.Value = {''};

            % (4) Uncheck the MapListBox
            app.MapListBox.Value = {};

            % (5) For export data
            app.exportMapName = [];
            app.exportMapDataTitle = [];
            app.exportMapData = [];

        end

        % Image clicked function: Image_DeleteMap
        function Image_DeleteMapClicked(app, event)

            showImageIconAct(app, app.Image_DeleteMap)

            checkMapID = str2double(app.MapListBox.Value);

            if isequal( app.ActLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Act map

                % (1) Delete data
                parameterNumber = length( app.actMapDataTitle );
                dataTemporary = cell(1, parameterNumber, app.maxMapNumber);
                app.actMapData(:,:, checkMapID) = [];

                dataTemporary(:,:, 1:app.maxMapNumber-1) = app.actMapData;
                app.actMapData = dataTemporary;

                % (2) Update Save to ID
                app.actSaveToID = app.actSaveToID - 1;

                % (3) Update 'MapListBox'
                updateMapListBox(app, app.Image_Act)

            elseif isequal( app.RepLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Rep map

                % (1) Delete data
                parameterNumber = length( app.repMapDataTitle );
                dataTemporary = cell(1, parameterNumber, app.maxMapNumber);
                app.repMapData(:,:, checkMapID) = [];

                dataTemporary(:,:, 1:app.maxMapNumber-1) = app.repMapData;
                app.repMapData = dataTemporary;

                % (2) Update Save to ID
                app.repSaveToID = app.repSaveToID - 1;

                % (3) Update 'MapListBox'
                updateMapListBox(app, app.Image_Rep)

            elseif isequal( app.APDLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % APD map

                % (1) Delete data
                parameterNumber = length( app.APDMapDataTitle );
                dataTemporary = cell(1, parameterNumber, app.maxMapNumber);
                app.APDMapData(:,:, checkMapID) = [];

                dataTemporary(:,:, 1:app.maxMapNumber-1) = app.APDMapData;
                app.APDMapData = dataTemporary;

                % (2) Update Save to File ID
                app.APDSaveToID = app.APDSaveToID - 1;

                % (3) Update 'MapListBox'
                updateMapListBox(app, app.Image_APD)

            elseif isequal( app.RTLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % RT map

                % (1) Delete data
                parameterNumber = length( app.RTMapDataTitle );
                dataTemporary = cell(1, parameterNumber, app.maxMapNumber);
                app.RTMapData(:,:, checkMapID) = [];

                dataTemporary(:,:, 1:app.maxMapNumber-1) = app.RTMapData;
                app.RTMapData = dataTemporary;

                % (2) Update Save to ID
                app.RTSaveToID = app.RTSaveToID - 1;

                % (3) Update 'MapListBox'
                updateMapListBox(app, app.Image_RT)
            end

            Image_UnloadMapClicked(app, 'ImageClicked')

        end

        % Image clicked function: Image_DisplayPixel
        function Image_DisplayPixelClicked(app, event)

            showImageIconAct(app, app.Image_DisplayPixel)

            % (1) Update components
            app.Image_DisplayPixel.Enable = 'off';

            app.CheckSigX_scrn.Editable = 'on';
            app.CheckSigY_scrn.Editable = 'on';
            app.CheckSigX_scrn.BackgroundColor = [1.00, 1.00, 0.00]; % Yellow
            app.CheckSigY_scrn.BackgroundColor = [1.00, 1.00, 0.00];

            % (2) Plot the initial marker
            checkSigColor = [0.00, 0.45, 0.74];

            app.checkSigMarkerXY = [ round(0.5*app.cmosDataRowNum), round(0.5*app.cmosDataColNum) ];
            app.CheckSigX_scrn.Value = app.checkSigMarkerXY(1,1);
            app.CheckSigY_scrn.Value = app.checkSigMarkerXY(1,2);

            % (2.1) Marker plot
            delete(app.checkSigMarkerPlot)
            app.checkSigMarkerPlot = updateCheckSigMarker(app, checkSigColor);

            % (2.2) Marker Highlight Plot
            delete(app.checkSigMarkerHighlightPlot)
            app.checkSigMarkerHighlightPlot = updateCheckSigMarkerHighlight(app);

            % (3) Get the data
            checkMapID = str2double(app.MapListBox.Value);

            if isequal( app.ActLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Act map

                app.checkSigDataSectionTime = app.actMapData{1,7,checkMapID};
                app.checkSigDataSection = app.actMapData{1,8,checkMapID};
                app.checkSigLocsAct = app.actMapData{1,9,checkMapID};

                app.checkSigMapData = app.actMapData{1,10,checkMapID};
                app.checkSigLB = app.actMapData{1,5,checkMapID};
                app.checkSigUB = app.actMapData{1,6,checkMapID};
                app.checkSigMapData( app.checkSigMapData < app.checkSigLB  |  app.checkSigMapData > app.checkSigUB ) = NaN;

                delete(app.checkSigPlot)
                app.checkSigPlot = updateCheckSig(app, app.checkSigDataSectionTime, app.checkSigDataSection, checkSigColor);

                delete(app.checkSigActPointPlot)
                app.checkSigActPointPlot = updateCheckSigActPoints(app, app.checkSigDataSectionTime, app.checkSigDataSection);

                delete(app.checkSigHistIndicatingLinePlot)
                app.checkSigHistIndicatingLinePlot = updatecheckSigHistIndicatingLine(app, app.checkSigMapData);

            elseif isequal( app.RepLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Rep map

                app.checkSigDataSectionTime = app.repMapData{1,8,checkMapID};
                app.checkSigDataSection = app.repMapData{1,9,checkMapID};
                app.checkSigLocsRep = app.repMapData{1,10,checkMapID};

                app.checkSigMapData = app.repMapData{1,11,checkMapID};
                app.checkSigLB = app.repMapData{1,6,checkMapID};
                app.checkSigUB = app.repMapData{1,7,checkMapID};
                app.checkSigMapData( app.checkSigMapData < app.checkSigLB  |  app.checkSigMapData > app.checkSigUB ) = NaN;

                delete(app.checkSigPlot)
                app.checkSigPlot = updateCheckSig(app, app.checkSigDataSectionTime, app.checkSigDataSection, checkSigColor);

                delete(app.checkSigRepPointPlot)
                app.checkSigRepPointPlot = updateCheckSigRepPoints(app, app.checkSigDataSectionTime, app.checkSigDataSection);

                delete(app.checkSigHistIndicatingLinePlot)
                app.checkSigHistIndicatingLinePlot = updatecheckSigHistIndicatingLine(app, app.checkSigMapData);

            elseif isequal( app.APDLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % APD map

                app.checkSigDataSectionTime = app.APDMapData{1,8,checkMapID};
                app.checkSigDataSection = app.APDMapData{1,9,checkMapID};
                app.checkSigLocsAct = app.APDMapData{1,10,checkMapID};
                app.checkSigLocsRep = app.APDMapData{1,11,checkMapID};

                app.checkSigMapData = app.APDMapData{1,12,checkMapID};
                app.checkSigLB = app.APDMapData{1,6,checkMapID};
                app.checkSigUB = app.APDMapData{1,7,checkMapID};
                app.checkSigMapData( app.checkSigMapData < app.checkSigLB  |  app.checkSigMapData > app.checkSigUB ) = NaN;

                delete(app.checkSigPlot)
                app.checkSigPlot = updateCheckSig(app, app.checkSigDataSectionTime, app.checkSigDataSection, checkSigColor);

                delete(app.checkSigActPointPlot)
                app.checkSigActPointPlot = updateCheckSigActPoints(app, app.checkSigDataSectionTime, app.checkSigDataSection);

                delete(app.checkSigRepPointPlot)
                app.checkSigRepPointPlot = updateCheckSigRepPoints(app, app.checkSigDataSectionTime, app.checkSigDataSection);

                delete(app.checkSigHistIndicatingLinePlot)
                app.checkSigHistIndicatingLinePlot = updatecheckSigHistIndicatingLine(app, app.checkSigMapData);

            elseif isequal( app.RTLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % RT map

                app.checkSigDataSectionTime = app.RTMapData{1,9,checkMapID};
                app.checkSigDataSection = app.RTMapData{1,10,checkMapID};
                app.checkSigLocsStart = app.RTMapData{1,11,checkMapID};
                app.checkSigLocsEnd = app.RTMapData{1,12,checkMapID};

                app.checkSigMapData = app.RTMapData{1,13,checkMapID};
                app.checkSigLB = app.RTMapData{1,7,checkMapID};
                app.checkSigUB = app.RTMapData{1,8,checkMapID};
                app.checkSigMapData( app.checkSigMapData < app.checkSigLB  |  app.checkSigMapData > app.checkSigUB ) = NaN;

                delete(app.checkSigPlot)
                app.checkSigPlot = updateCheckSig(app, app.checkSigDataSectionTime, app.checkSigDataSection, checkSigColor);

                delete(app.checkSigStartPointPlot)
                app.checkSigStartPointPlot = updateCheckSigStartPoints(app, app.checkSigDataSectionTime, app.checkSigDataSection);

                delete(app.checkSigEndPointPlot)
                app.checkSigEndPointPlot = updateCheckSigEndPoints(app, app.checkSigDataSectionTime, app.checkSigDataSection);

                delete(app.checkSigHistIndicatingLinePlot)
                app.checkSigHistIndicatingLinePlot = updatecheckSigHistIndicatingLine(app, app.checkSigMapData);
            end

        end

        % Image clicked function: Image_ClearPixel
        function Image_ClearPixelClicked(app, event)

            showImageIconAct(app, app.Image_ClearPixel)

            if isempty( app.checkSigMarkerPlot )

                msg = 'Display the signal first';
                uialert(app.OMZer_Vm_UIFigure, msg,'Warning','Icon','error')

            else

                app.Image_DisplayPixel.Enable = 'on';

                app.CheckSigX_scrn.Editable = 'off';
                app.CheckSigY_scrn.Editable = 'off';
                app.CheckSigX_scrn.BackgroundColor = [1.00, 1.00, 1.00]; % White
                app.CheckSigY_scrn.BackgroundColor = [1.00, 1.00, 1.00];

                clearCheckSigPlot(app)
            end

        end

        % Value changed function: UserCommentTextArea
        function UserCommentTextAreaValueChanged(app, event)

            checkMapID = str2double(app.MapListBox.Value);

            if isequal( app.ActLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Act map

                app.actMapData{1,12, checkMapID} = app.UserCommentTextArea.Value;

            elseif isequal( app.RepLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Rep map

                app.repMapData{1,13, checkMapID} = app.UserCommentTextArea.Value;

            elseif isequal( app.APDLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % APD map

                app.APDMapData{1,14, checkMapID} = app.UserCommentTextArea.Value;

            elseif isequal( app.RTLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % RT map

                app.RTMapData{1,15, checkMapID} = app.UserCommentTextArea.Value;
            end

        end

        % Value changed function: CheckSigX_scrn, CheckSigY_scrn
        function CheckSigXY_scrnValueChanged(app, event)

            app.checkSigMarkerXY(1,1) = app.CheckSigX_scrn.Value;
            app.checkSigMarkerXY(1,2) = app.CheckSigY_scrn.Value;

            checkSigColor = [0.00, 0.45, 0.74];

            % (1.1) Marker plot
            delete(app.checkSigMarkerPlot)
            app.checkSigMarkerPlot = updateCheckSigMarker(app, checkSigColor);

            % (1.2) Marker Highlight Plot
            delete(app.checkSigMarkerHighlightPlot)
            app.checkSigMarkerHighlightPlot = updateCheckSigMarkerHighlight(app);

            % (2) Update the signal
            if isequal( app.ActLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Act map

                delete(app.checkSigPlot)
                app.checkSigPlot = updateCheckSig(app, app.checkSigDataSectionTime, app.checkSigDataSection, checkSigColor);

                delete(app.checkSigActPointPlot)
                app.checkSigActPointPlot = updateCheckSigActPoints(app, app.checkSigDataSectionTime, app.checkSigDataSection);

                delete(app.checkSigHistIndicatingLinePlot)
                app.checkSigHistIndicatingLinePlot = updatecheckSigHistIndicatingLine(app, app.checkSigMapData);

            elseif isequal( app.RepLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Rep map

                delete(app.checkSigPlot)
                app.checkSigPlot = updateCheckSig(app, app.checkSigDataSectionTime, app.checkSigDataSection, checkSigColor);

                delete(app.checkSigRepPointPlot)
                app.checkSigRepPointPlot = updateCheckSigRepPoints(app, app.checkSigDataSectionTime, app.checkSigDataSection);

                delete(app.checkSigHistIndicatingLinePlot)
                app.checkSigHistIndicatingLinePlot = updatecheckSigHistIndicatingLine(app, app.checkSigMapData);

            elseif isequal( app.APDLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % APD map

                delete(app.checkSigPlot)
                app.checkSigPlot = updateCheckSig(app, app.checkSigDataSectionTime, app.checkSigDataSection, checkSigColor);

                delete(app.checkSigActPointPlot)
                app.checkSigActPointPlot = updateCheckSigActPoints(app, app.checkSigDataSectionTime, app.checkSigDataSection);

                delete(app.checkSigRepPointPlot)
                app.checkSigRepPointPlot = updateCheckSigRepPoints(app, app.checkSigDataSectionTime, app.checkSigDataSection);

                delete(app.checkSigHistIndicatingLinePlot)
                app.checkSigHistIndicatingLinePlot = updatecheckSigHistIndicatingLine(app, app.checkSigMapData);

            elseif isequal( app.RTLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % RT map

                delete(app.checkSigPlot)
                app.checkSigPlot = updateCheckSig(app, app.checkSigDataSectionTime, app.checkSigDataSection, checkSigColor);

                delete(app.checkSigStartPointPlot)
                app.checkSigStartPointPlot = updateCheckSigStartPoints(app, app.checkSigDataSectionTime, app.checkSigDataSection);

                delete(app.checkSigEndPointPlot)
                app.checkSigEndPointPlot = updateCheckSigEndPoints(app, app.checkSigDataSectionTime, app.checkSigDataSection);

                delete(app.checkSigHistIndicatingLinePlot)
                app.checkSigHistIndicatingLinePlot = updatecheckSigHistIndicatingLine(app, app.checkSigMapData);
            end

        end

        % Window button down function: OMZer_Vm_UIFigure
        function OMZer_Vm_UIFigureWindowButtonDown(app, event)

            if isequal(app.Image_DisplayPixel.Enable, 'off')  &&  isequal(app.Image_ClearPixel.Enable, 'on')

                app.mouseMotion = app.OMZer_Vm_UIFigure.SelectionType; % The result can be 'normal', 'extend', 'alt', 'open'

                if isequal(app.mouseMotion,'normal') % 'normal' means single-click any mouse button.

                    app.mouseXY = app.OMZer_Vm_UIFigure.CurrentPoint; % Returns (x, y) coordinates of the mouse pointer, measured from the lower-left corner of the figure.
                    app.mapAxisLocs = app.MapAxis.InnerPosition; % Returns a 1-by-4 row vector -  [left bottom width height].

                    if ( app.mouseXY(1,1) >= app.mapAxisLocs(1,1) )  ...
                            &&  ( app.mouseXY(1,1) <= app.mapAxisLocs(1,1) + app.mapAxisLocs(1,3) )  ...
                            &&  ( app.mouseXY(1,2) >= app.mapAxisLocs(1,2) )  ...
                            &&  ( app.mouseXY(1,2) <= app.mapAxisLocs(1,2) + app.mapAxisLocs(1,4) )

                        updateMouseXY(app, app.mapAxisLocs);

                        if ( abs(app.mouseXY(1,1) - app.checkSigMarkerXY(1,1)) <= 5 )  &&  ( abs(app.mouseXY(1,2) - app.checkSigMarkerXY(1,2)) <= 5 ) % Find which marker is within 5 pixels of the mouse location

                            app.checkSigMouseDragID = 1;
                        end
                    end
                end
            end

        end

        % Window button motion function: OMZer_Vm_UIFigure
        function OMZer_Vm_UIFigureWindowButtonMotion(app, event)

            if ~isempty(app.checkSigMouseDragID)  &&  isequal(app.Image_DisplayPixel.Enable, 'off')  &&  isequal(app.Image_ClearPixel.Enable, 'on')

                app.mouseXY = app.OMZer_Vm_UIFigure.CurrentPoint; % Returns (x, y) coordinates of the mouse pointer, measured from the lower-left corner of the figure.
                app.mapAxisLocs = app.MapAxis.InnerPosition; % Returns a 1-by-4 row vector -  [left bottom width height].

                if ( app.mouseXY(1,1) >= app.mapAxisLocs(1,1) )  ...
                        &&  ( app.mouseXY(1,1) <= app.mapAxisLocs(1,1) + app.mapAxisLocs(1,3) )  ...
                        &&  ( app.mouseXY(1,2) >= app.mapAxisLocs(1,2) )  ...
                        &&  ( app.mouseXY(1,2) <= app.mapAxisLocs(1,2) + app.mapAxisLocs(1,4) )

                    updateMouseXY(app, app.mapAxisLocs);

                    app.checkSigMarkerXY(1,1) = app.mouseXY(1,1);
                    app.checkSigMarkerXY(1,2) = app.mouseXY(1,2);

                    app.CheckSigX_scrn.Value = app.checkSigMarkerXY(1,1);
                    app.CheckSigY_scrn.Value = app.checkSigMarkerXY(1,2);

                    CheckSigXY_scrnValueChanged(app, event)
                end
            end

        end

        % Window button up function: OMZer_Vm_UIFigure
        function OMZer_Vm_UIFigureWindowButtonUp(app, event)

            app.checkSigMouseDragID = [];

        end

        % Window key press function: OMZer_Vm_UIFigure
        function OMZer_Vm_UIFigureWindowKeyPress(app, event)
            
            % key = event.Key;
            
            app.keyPressHistory{app.keyPressID} = event.Key;
            app.keyPressID = app.keyPressID + 1;

            % (1) Save file callback
            if length( app.keyPressHistory ) == 2

                if ( ismac == 1 )  &&  isequal( app.keyPressHistory{1}, 'meta' )  &&  isequal( app.keyPressHistory{2}, 's' )  &&  ( isequal( app.Image_Save.Enable, 'on' ) )

                    Image_SaveClicked(app, 'clicked')

                elseif ( ispc == 1 )  &&  isequal( app.keyPressHistory{1}, 'control' )  &&  isequal( app.keyPressHistory{2}, 's' )  &&  ( isequal( app.Image_Save.Enable, 'on' ) )

                    Image_SaveClicked(app, 'clicked')
                end
            end

            % (2) Open folder callback
            if length( app.keyPressHistory ) == 2

                if ( ismac == 1 )  &&  isequal( app.keyPressHistory{1}, 'meta' )  &&  isequal( app.keyPressHistory{2}, 'o' )

                    Image_OpenFolderClicked(app, 'clicked')

                elseif ( ispc == 1 )  &&  isequal( app.keyPressHistory{1}, 'control' )  &&  isequal( app.keyPressHistory{2}, 'o' )

                    Image_OpenFolderClicked(app, 'clicked')
                end
            end
            
        end

        % Window key release function: OMZer_Vm_UIFigure
        function OMZer_Vm_UIFigureWindowKeyRelease(app, event)
            
            % key = event.Key;
            
            app.keyPressID = 1;
            app.keyPressHistory = {};

        end

        % Close request function: OMZer_Vm_UIFigure
        function OMZer_Vm_UIFigureCloseRequest(app, event)

            % (1) Set mainApp at the top level
            figure(app.mainAppUIFigure)

            % (2) Close children app
            if isobject(app.OMZer_Vm_Signal)
                delete(app.OMZer_Vm_Signal)
            end

            if isobject(app.OMZer_Vm_ExportDisplayPixel)
                delete(app.OMZer_Vm_ExportDisplayPixel)
            end
            
            if isobject(app.OMZer_Vm_ExportFigure)
                delete(app.OMZer_Vm_ExportFigure)
            end

            if isobject(app.OMZer_Vm_CV)
                delete(app.OMZer_Vm_CV)
            end

            % (3) Update the mainApp
            app.mainApp.OMZer_Vm = [];

            delete(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OMZer_Vm_UIFigure = uifigure('Visible', 'off');
            app.OMZer_Vm_UIFigure.Name = 'OMZer Vm';
            app.OMZer_Vm_UIFigure.CloseRequestFcn = createCallbackFcn(app, @OMZer_Vm_UIFigureCloseRequest, true);
            app.OMZer_Vm_UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @OMZer_Vm_UIFigureWindowButtonDown, true);
            app.OMZer_Vm_UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @OMZer_Vm_UIFigureWindowButtonUp, true);
            app.OMZer_Vm_UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @OMZer_Vm_UIFigureWindowButtonMotion, true);
            app.OMZer_Vm_UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @OMZer_Vm_UIFigureWindowKeyPress, true);
            app.OMZer_Vm_UIFigure.WindowKeyReleaseFcn = createCallbackFcn(app, @OMZer_Vm_UIFigureWindowKeyRelease, true);
            app.colorBarAxes = uiaxes(app.OMZer_Vm_UIFigure);
            title(app.colorBarAxes, 'ms')
            app.colorBarAxes.XTick = [];
            app.MapAxis = uiaxes(app.OMZer_Vm_UIFigure);
            app.MapAxis.XLim = [1 100];
            app.MapAxis.YLim = [1 100];
            app.MapAxis.YDir = 'reverse';
            app.MapAxis.XTick = [];
            app.MapAxis.YTick = [];
            app.MapAxis.ZTick = [];
            app.checkSigAxis = uiaxes(app.OMZer_Vm_UIFigure);
            xlabel(app.checkSigAxis, 'Time (sec)')
            app.MapHistAxis = uiaxes(app.OMZer_Vm_UIFigure);
            xlabel(app.MapHistAxis, 'Time (ms)')
            ylabel(app.MapHistAxis, 'Counts')
            app.MapHistAxis.ZTick = [];
            app.TabGroup = uitabgroup(app.OMZer_Vm_UIFigure);
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.Image_Act = uiimage(app.AnalysisTab);
            app.Image_Act.ImageClickedFcn = createCallbackFcn(app, @Image_ActClicked, true);
            app.Image_Act.ImageSource = 'Icon A.png';
            app.ActLabel = uilabel(app.AnalysisTab);
            app.ActLabel.Text = 'Act';
            app.Image_Rep = uiimage(app.AnalysisTab);
            app.Image_Rep.ImageClickedFcn = createCallbackFcn(app, @Image_RepClicked, true);
            app.Image_Rep.ImageSource = 'Icon R.png';
            app.RepLabel = uilabel(app.AnalysisTab);
            app.RepLabel.Text = 'Rep';
            app.Image_APD = uiimage(app.AnalysisTab);
            app.Image_APD.ImageClickedFcn = createCallbackFcn(app, @Image_APDClicked, true);
            app.Image_APD.ImageSource = 'Icon A.png';
            app.APDLabel = uilabel(app.AnalysisTab);
            app.APDLabel.Text = 'APD';
            app.Image_RT = uiimage(app.AnalysisTab);
            app.Image_RT.ImageClickedFcn = createCallbackFcn(app, @Image_RTClicked, true);
            app.Image_RT.ImageSource = 'Icon R.png';
            app.RTLabel = uilabel(app.AnalysisTab);
            app.RTLabel.Text = 'RT';
            app.Image_Signal = uiimage(app.AnalysisTab);
            app.Image_Signal.ImageClickedFcn = createCallbackFcn(app, @Image_SignalClicked, true);
            app.Image_Signal.ImageSource = 'Icon Signal.png';
            app.SignalLabel = uilabel(app.AnalysisTab);
            app.SignalLabel.Text = 'Signal';
            app.Image_CV = uiimage(app.AnalysisTab);
            app.Image_CV.ImageClickedFcn = createCallbackFcn(app, @Image_CVClicked, true);
            app.Image_CV.ImageSource = 'Icon C.png';
            app.CVLabel = uilabel(app.AnalysisTab);
            app.CVLabel.Text = 'CV';
            app.ToolsTab = uitab(app.TabGroup);
            app.ToolsTab.Title = 'Tools';
            app.Image_Save = uiimage(app.ToolsTab);
            app.Image_Save.ImageClickedFcn = createCallbackFcn(app, @Image_SaveClicked, true);
            app.Image_Save.ImageSource = 'Icon Save.png';
            app.SaveLabel = uilabel(app.ToolsTab);
            app.SaveLabel.Text = 'Save';
            app.Image_ExportData = uiimage(app.ToolsTab);
            app.Image_ExportData.ImageClickedFcn = createCallbackFcn(app, @Image_ExportDataClicked, true);
            app.Image_ExportData.Enable = 'off';
            app.Image_ExportData.ImageSource = 'Icon Export.png';
            app.ExportDataLabel = uilabel(app.ToolsTab);
            app.ExportDataLabel.Text = 'Export Data';
            app.Image_ExportFigure = uiimage(app.ToolsTab);
            app.Image_ExportFigure.ImageClickedFcn = createCallbackFcn(app, @Image_ExportFigureClicked, true);
            app.Image_ExportFigure.Enable = 'off';
            app.Image_ExportFigure.ImageSource = 'Icon Export Figure.png';
            app.ExportFigureLabel = uilabel(app.ToolsTab);
            app.ExportFigureLabel.Text = 'Export Figure';
            app.PreferenceTab = uitab(app.TabGroup);
            app.PreferenceTab.Title = 'Preference';
            app.RepDropDownLabel = uilabel(app.OMZer_Vm_UIFigure);
            app.RepDropDownLabel.Text = '% Rep';
            app.RepDropDown = uidropdown(app.OMZer_Vm_UIFigure);
            app.RepDropDown.Items = {'10', '20', '30', '40', '50', '60', '70', '80', '90'};
            app.RepDropDown.Value = '80';
            app.APDDropDownLabel = uilabel(app.OMZer_Vm_UIFigure);
            app.APDDropDownLabel.Text = '% APD';
            app.APDDropDown = uidropdown(app.OMZer_Vm_UIFigure);
            app.APDDropDown.Items = {'10', '20', '30', '40', '50', '60', '70', '80', '90'};
            app.APDDropDown.Value = '80';
            app.RTStartDropDownLabel = uilabel(app.OMZer_Vm_UIFigure);
            app.RTStartDropDownLabel.Text = '% RT Start';
            app.RTStartDropDown = uidropdown(app.OMZer_Vm_UIFigure);
            app.RTStartDropDown.Items = {'10', '20', '30', '40', '50', '60', '70', '80', '90'};
            app.RTStartDropDown.ValueChangedFcn = createCallbackFcn(app, @RTStartEndDropDownValueChanged, true);
            app.RTStartDropDown.Value = '20';
            app.RTEndDropDownLabel = uilabel(app.OMZer_Vm_UIFigure);
            app.RTEndDropDownLabel.Text = '% RT End';
            app.RTEndDropDown = uidropdown(app.OMZer_Vm_UIFigure);
            app.RTEndDropDown.Items = {'20', '30', '40', '50', '60', '70', '80', '90', '100'};
            app.RTEndDropDown.ValueChangedFcn = createCallbackFcn(app, @RTStartEndDropDownValueChanged, true);
            app.RTEndDropDown.Value = '80';
            app.Image_RegionalMap = uiimage(app.OMZer_Vm_UIFigure);
            app.Image_RegionalMap.ImageClickedFcn = createCallbackFcn(app, @Image_RegionalMapClicked, true);
            app.Image_RegionalMap.ImageSource = 'Icon Regional Map.png';
            app.RegionalMapLabel = uilabel(app.OMZer_Vm_UIFigure);
            app.RegionalMapLabel.Text = 'Regional Map';
            app.Image_GlobalMap = uiimage(app.OMZer_Vm_UIFigure);
            app.Image_GlobalMap.ImageClickedFcn = createCallbackFcn(app, @Image_GlobalMapClicked, true);
            app.Image_GlobalMap.ImageSource = 'Icon Global Map.png';
            app.GlobalMapLabel = uilabel(app.OMZer_Vm_UIFigure);
            app.GlobalMapLabel.Text = 'Global Map';
            app.MapModeDropDown = uidropdown(app.OMZer_Vm_UIFigure);
            app.MapModeDropDown.Items = {'Single', 'Ensemble'};
            app.MapModeDropDown.ValueChangedFcn = createCallbackFcn(app, @MapModeDropDownValueChanged, true);
            app.MapModeDropDown.Value = 'Single';
            app.Image_MapMode = uiimage(app.OMZer_Vm_UIFigure);
            app.Image_MapMode.Tooltip = {'(This is NOT a button)'};
            app.Image_MapMode.ImageSource = 'Icon Single.png';
            app.MapListBoxLabel = uilabel(app.OMZer_Vm_UIFigure);
            app.MapListBoxLabel.Text = 'Map';
            app.MapListBox = uilistbox(app.OMZer_Vm_UIFigure);
            app.MapListBox.Items = {};
            app.MapListBox.ValueChangedFcn = createCallbackFcn(app, @MapListBoxValueChanged, true);
            app.MapListBox.Value = {};
            app.MeanmsEditFieldLabel = uilabel(app.OMZer_Vm_UIFigure);
            app.MeanmsEditFieldLabel.Text = 'Mean (ms)';
            app.MeanmsEditField = uieditfield(app.OMZer_Vm_UIFigure, 'numeric');
            app.MeanmsEditField.Editable = 'off';
            app.MedianmsEditFieldLabel = uilabel(app.OMZer_Vm_UIFigure);
            app.MedianmsEditFieldLabel.Text = 'Median (ms)';
            app.MedianmsEditField = uieditfield(app.OMZer_Vm_UIFigure, 'numeric');
            app.MedianmsEditField.Editable = 'off';
            app.SDmsEditFieldLabel = uilabel(app.OMZer_Vm_UIFigure);
            app.SDmsEditFieldLabel.Text = 'SD (ms)';
            app.SDmsEditField = uieditfield(app.OMZer_Vm_UIFigure, 'numeric');
            app.SDmsEditField.Editable = 'off';
            app.WinStartsecEditFieldLabel = uilabel(app.OMZer_Vm_UIFigure);
            app.WinStartsecEditFieldLabel.Text = 'Win Start (sec)';
            app.WinStartsecEditField = uieditfield(app.OMZer_Vm_UIFigure, 'numeric');
            app.WinStartsecEditField.Limits = [0 Inf];
            app.WinStartsecEditField.Editable = 'off';
            app.WinStartsecEditField.Value = Inf;
            app.WinEndsecEditFieldLabel = uilabel(app.OMZer_Vm_UIFigure);
            app.WinEndsecEditFieldLabel.Text = 'Win End (sec)';
            app.WinEndsecEditField = uieditfield(app.OMZer_Vm_UIFigure, 'numeric');
            app.WinEndsecEditField.Limits = [0 Inf];
            app.WinEndsecEditField.Editable = 'off';
            app.WinEndsecEditField.Value = Inf;
            app.LBmsSpinnerLabel = uilabel(app.OMZer_Vm_UIFigure);
            app.LBmsSpinnerLabel.Text = 'LB (ms)';
            app.LBmsSpinner = uispinner(app.OMZer_Vm_UIFigure);
            app.LBmsSpinner.Step = 0.5;
            app.LBmsSpinner.ValueChangingFcn = createCallbackFcn(app, @LBmsSpinnerValueChanging, true);
            app.LBmsSpinner.ValueChangedFcn = createCallbackFcn(app, @LBmsSpinnerValueChanged, true);
            app.LBmsSpinner.Enable = 'off';
            app.UBmsSpinnerLabel = uilabel(app.OMZer_Vm_UIFigure);
            app.UBmsSpinnerLabel.Text = 'UB (ms)';
            app.UBmsSpinner = uispinner(app.OMZer_Vm_UIFigure);
            app.UBmsSpinner.Step = 0.5;
            app.UBmsSpinner.ValueChangingFcn = createCallbackFcn(app, @UBmsSpinnerValueChanging, true);
            app.UBmsSpinner.ValueChangedFcn = createCallbackFcn(app, @UBmsSpinnerValueChanged, true);
            app.UBmsSpinner.Enable = 'off';
            app.Image_ResetBound = uiimage(app.OMZer_Vm_UIFigure);
            app.Image_ResetBound.ImageClickedFcn = createCallbackFcn(app, @Image_ResetBoundClicked, true);
            app.Image_ResetBound.Enable = 'off';
            app.Image_ResetBound.ImageSource = 'Icon Undo.png';
            app.ResetBoundLabel = uilabel(app.OMZer_Vm_UIFigure);
            app.ResetBoundLabel.Text = 'Reset Bound';
            app.Image_DeleteMap = uiimage(app.OMZer_Vm_UIFigure);
            app.Image_DeleteMap.ImageClickedFcn = createCallbackFcn(app, @Image_DeleteMapClicked, true);
            app.Image_DeleteMap.Enable = 'off';
            app.Image_DeleteMap.ImageSource = 'Icon Delete.png';
            app.DeleteMapLabel = uilabel(app.OMZer_Vm_UIFigure);
            app.DeleteMapLabel.Text = 'Delete Map';
            app.Image_UnloadMap = uiimage(app.OMZer_Vm_UIFigure);
            app.Image_UnloadMap.ImageClickedFcn = createCallbackFcn(app, @Image_UnloadMapClicked, true);
            app.Image_UnloadMap.Enable = 'off';
            app.Image_UnloadMap.ImageSource = 'Icon Unload Map.png';
            app.UnloadMapLabel = uilabel(app.OMZer_Vm_UIFigure);
            app.UnloadMapLabel.Text = 'Unload Map';
            app.MinPeakHeightDropDownLabel = uilabel(app.OMZer_Vm_UIFigure);
            app.MinPeakHeightDropDownLabel.Text = 'Min Peak Height';
            app.MinPeakHeightDropDown = uidropdown(app.OMZer_Vm_UIFigure);
            app.MinPeakHeightDropDown.Items = {'0.1', '0.2', '0.3', '0.4', '0.5', '0.6', '0.7', '0.8', '0.9'};
            app.MinPeakHeightDropDown.Value = '0.6';
            app.MapTitleLabel = uilabel(app.OMZer_Vm_UIFigure);
            app.MapTitleLabel.Text = 'Map Title';
            app.Image_DisplayPixel = uiimage(app.OMZer_Vm_UIFigure);
            app.Image_DisplayPixel.ImageClickedFcn = createCallbackFcn(app, @Image_DisplayPixelClicked, true);
            app.Image_DisplayPixel.Enable = 'off';
            app.Image_DisplayPixel.ImageSource = 'Icon Display Pixel.png';
            app.DisplayPixelLabel = uilabel(app.OMZer_Vm_UIFigure);
            app.DisplayPixelLabel.Text = 'Display Pixel';
            app.CheckSigXYLabel = uilabel(app.OMZer_Vm_UIFigure);
            app.CheckSigXYLabel.Tooltip = {'X (red) and Y (blue) coordinate of the marker. Please be noted that X is column and Y is row to locate an element in a matrix in MATLAB.'};
            app.CheckSigXYLabel.Text = 'X,Y';
            app.CheckSigY_scrn = uieditfield(app.OMZer_Vm_UIFigure, 'numeric');
            app.CheckSigY_scrn.Limits = [0 100];
            app.CheckSigY_scrn.ValueDisplayFormat = '%.0f';
            app.CheckSigY_scrn.ValueChangedFcn = createCallbackFcn(app, @CheckSigXY_scrnValueChanged, true);
            app.CheckSigY_scrn.Editable = 'off';
            app.CheckSigY_scrn.Tooltip = {'Y coordinate of the 1st marker (Note: row in a matlab matrix)'};
            app.CheckSigX_scrn = uieditfield(app.OMZer_Vm_UIFigure, 'numeric');
            app.CheckSigX_scrn.Limits = [0 100];
            app.CheckSigX_scrn.ValueDisplayFormat = '%.0f';
            app.CheckSigX_scrn.ValueChangedFcn = createCallbackFcn(app, @CheckSigXY_scrnValueChanged, true);
            app.CheckSigX_scrn.Editable = 'off';
            app.CheckSigX_scrn.Tooltip = {'X coordinate of the 1st marker (Note: column in a matlab matrix)'};
            app.Image_ClearPixel = uiimage(app.OMZer_Vm_UIFigure);
            app.Image_ClearPixel.ImageClickedFcn = createCallbackFcn(app, @Image_ClearPixelClicked, true);
            app.Image_ClearPixel.Enable = 'off';
            app.Image_ClearPixel.ImageSource = 'Icon Clear Pixel.png';
            app.ClearPixelLabel = uilabel(app.OMZer_Vm_UIFigure);
            app.ClearPixelLabel.Text = 'Clear Pixel';
            app.UserCommentTextAreaLabel = uilabel(app.OMZer_Vm_UIFigure);
            app.UserCommentTextAreaLabel.Text = 'User Comment';
            app.UserCommentTextArea = uitextarea(app.OMZer_Vm_UIFigure);
            app.UserCommentTextArea.ValueChangedFcn = createCallbackFcn(app, @UserCommentTextAreaValueChanged, true);
            app.UserCommentTextArea.Enable = 'off';
            app.UserCommentTextArea.Tooltip = {''};
            app.Image_ActivatedCamera = uiimage(app.OMZer_Vm_UIFigure);
            app.Image_ActivatedCamera.Tooltip = {'(This is NOT a button) Tell the user from which camera the signals are'};
            app.Image_ActivatedCamera.ImageSource = 'Icon Activated Camera.png';
            app.CameraNALabel = uilabel(app.OMZer_Vm_UIFigure);
            app.CameraNALabel.Text = 'Camera N/A';
            app.Image_ROIDock = uiimage(app.OMZer_Vm_UIFigure);
            app.Image_ROIDock.ImageClickedFcn = createCallbackFcn(app, @Image_ROIDockClicked, true);
            app.Image_ROIDock.ImageSource = 'Icon Collection.png';
            app.ROIDockLabel = uilabel(app.OMZer_Vm_UIFigure);
            app.ROIDockLabel.Text = 'ROI Dock';
            % Show the figure after all components are created
            app.OMZer_Vm_UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OMZer_Vm(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.OMZer_Vm_UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.OMZer_Vm_UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OMZer_Vm_UIFigure)
        end
    end
end