classdef OMZer_VmCaLinked < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OMZer_VmCaLinked_UIFigure   matlab.ui.Figure
        TheLBandUBareusedtodefinewholeareaofinterestLabel  matlab.ui.control.Label
        AreaForStatisticsDeltaCamera1Camera2ButtonGroup  matlab.ui.container.ButtonGroup
        PositiveDeltaAreaButton     matlab.ui.control.RadioButton
        NegativeDeltaAreaButton     matlab.ui.control.RadioButton
        WholeAreaButton             matlab.ui.control.RadioButton
        UserCommentTextArea         matlab.ui.control.TextArea
        UserCommentTextAreaLabel    matlab.ui.control.Label
        MinPeakHeightDropDown       matlab.ui.control.DropDown
        MinPeakHeightDropDownLabel  matlab.ui.control.Label
        UBmsSpinner                 matlab.ui.control.Spinner
        UBmsSpinnerLabel            matlab.ui.control.Label
        LBmsSpinner                 matlab.ui.control.Spinner
        LBmsSpinnerLabel            matlab.ui.control.Label
        WinEndsecEditField          matlab.ui.control.NumericEditField
        WinEndsecEditFieldLabel     matlab.ui.control.Label
        WinStartsecEditField        matlab.ui.control.NumericEditField
        WinStartsecEditFieldLabel   matlab.ui.control.Label
        SDmsEditField               matlab.ui.control.NumericEditField
        SDmsEditFieldLabel          matlab.ui.control.Label
        MedianmsEditField           matlab.ui.control.NumericEditField
        MedianmsEditFieldLabel      matlab.ui.control.Label
        MeanmsEditField             matlab.ui.control.NumericEditField
        MeanmsEditFieldLabel        matlab.ui.control.Label
        MapListBox                  matlab.ui.control.ListBox
        MapListBoxLabel             matlab.ui.control.Label
        Image_MapMode               matlab.ui.control.Image
        MapModeDropDown             matlab.ui.control.DropDown
        DurationDropDown            matlab.ui.control.DropDown
        DurationDropDownLabel       matlab.ui.control.Label
        RepDropDown                 matlab.ui.control.DropDown
        RepDropDownLabel            matlab.ui.control.Label
        ROIDockLabel                matlab.ui.control.Label
        Image_ROIDock               matlab.ui.control.Image
        Camera1solidlineblueCamera2DashdottedlineredLabel  matlab.ui.control.Label
        Image_ActivatedCamera       matlab.ui.control.Image
        ClearPixelLabel             matlab.ui.control.Label
        Image_ClearPixel            matlab.ui.control.Image
        CheckSigX_scrn              matlab.ui.control.NumericEditField
        CheckSigY_scrn              matlab.ui.control.NumericEditField
        CheckSigXYLabel             matlab.ui.control.Label
        DisplayPixelLabel           matlab.ui.control.Label
        Image_DisplayPixel          matlab.ui.control.Image
        MapTitleLabel               matlab.ui.control.Label
        UnloadMapLabel              matlab.ui.control.Label
        Image_UnloadMap             matlab.ui.control.Image
        DeleteMapLabel              matlab.ui.control.Label
        Image_DeleteMap             matlab.ui.control.Image
        ResetBoundLabel             matlab.ui.control.Label
        Image_ResetBound            matlab.ui.control.Image
        GlobalMapLabel              matlab.ui.control.Label
        Image_GlobalMap             matlab.ui.control.Image
        RegionalMapLabel            matlab.ui.control.Label
        Image_RegionalMap           matlab.ui.control.Image
        TabGroup                    matlab.ui.container.TabGroup
        AnalysisTab                 matlab.ui.container.Tab
        SignalLabel                 matlab.ui.control.Label
        Image_Signal                matlab.ui.control.Image
        DurationDeltaLabel          matlab.ui.control.Label
        Image_DurationDelta         matlab.ui.control.Image
        RepDeltaLabel               matlab.ui.control.Label
        Image_RepDelta              matlab.ui.control.Image
        ActDeltaLabel               matlab.ui.control.Label
        Image_ActDelta              matlab.ui.control.Image
        ToolsTab                    matlab.ui.container.Tab
        ExportFigureLabel           matlab.ui.control.Label
        Image_ExportFigure          matlab.ui.control.Image
        ExportDataLabel             matlab.ui.control.Label
        Image_ExportData            matlab.ui.control.Image
        SaveLabel                   matlab.ui.control.Label
        Image_Save                  matlab.ui.control.Image
        PreferenceTab               matlab.ui.container.Tab
        MapHistAxis                 matlab.ui.control.UIAxes
        checkSigAxis                matlab.ui.control.UIAxes
        MapAxis                     matlab.ui.control.UIAxes
        colorBarAxes                matlab.ui.control.UIAxes
    end


    properties (Access = public)

        % The Parent App --------------------
        mainApp
        mainAppUIFigure


        % The Children App --------------------
        OMZer_VmCaLinked_Signal
        OMZer_VmCaLinked_ExportDisplayPixel


        % The Current App --------------------
        dirName % File directory
        fileName % Filename without path and extension

        setup % User preference
        userAlphaValue

        imageIconActColor = [0.80, 0.80, 0.80]; % The activation color when an image is clicked

        ColorID
        Color1 = [0.00,0.45,0.74];
        Color2 = [0.93,0.69,0.13];
        Color3 = [0.64,0.08,0.18];
        Color4 = [0.72,0.27,1.00];
        Color5 = [0.47,0.67,0.19];
        Color6 = [0.89,0.47,0.58];

        lWidth = 2; % Line width

        maxMapNumber = 10; % The max number of map that can be saved

        data
        acqFreq
        markerXY
        signalTime
        cmosDataGroup
        bgImageNomGroup % Gray colour RGB version of background image
        cameraSource % '1 (or 2)' - data from camera 1 (or 2)

        pacing1 % Pacing signal recorded by channel 1
        pacing2 % Pacing signal recorded by channel 2
        pacingTime % Time points to plot pacing signal
        pacing1Enable
        pacing2Enable

        mapAxisLocs

        mouseXY = []; % This is a 1-by-2 row vector that stores mouse "X" and "Y" coordinate
        mouseMotion = []; % The result can be 'normal', 'extend', 'alt', 'open'

        cmosDataRowNum % The numer of rows in cmosData
        cmosDataColNum % The numer of columns in cmosData

        actDefinition = 'max_dv_dt'; % The definition of activation
        dockMapCalculationROI
        mapCalculationROI
        callFromRegionalButton = 0; % '1 (or 0)' - regional (or global)

        % (0) For signal window selection
        oneBeatWindowStartIndex
        oneBeatWindowEndIndex

        multiBeatsWindowStartIndex
        multiBeatsWindowEndIndex

        % (1) For Act
        actLinkedMapPlot
        actLinkedHistPlot
        actLinkedColorBarPlot

        actLinkedSaveToID = 1; % The ID of map to be saved
        actLinkedMapDataTitle = { '(1) Win Start Index', '(2) Win End Index', ...
            '(3) Area Mode', '(4) AP Mode', ...
            '(5) LB', '(6) UB', ...
            '(7) Data Section Time', ...
            '(8) Data Section Camera 1', '(9) Data Section Camera 2'...
            '(10) Locs Act Camera 1', '(11) Locs Act Camera 2', ...
            '(12) Act Delta (Camera 1 Minus Camera 2) Matrix', ...
            '(13) Ensemble Peaks Number', ...
            '(14) Map User Comment'};
        actLinkedMapData

        % (2) For Rep
        repLinkedMapPlot
        repLinkedHistPlot
        repLinkedColorBarPlot

        repLinkedSaveToID = 1;
        repLinkedMapDataTitle = { '(1) Win Start Index', '(2) Win End Index', ...
            '(3) Area Mode', '(4) AP Mode', ...
            '(5) Rep Level', ...
            '(6) LB', '(7) UB', ...
            '(8) Data Section Time', ...
            '(9) Data Section Camera 1', '(10) Data Section Camera 2', ...
            '(11) Locs Rep Camera 1', '(12) Locs Rep Camera 2', ...
            '(13) Rep Delta (Camera 1 Minus Camera 2) Matrix', ...
            '(14) Ensemble Peaks Number', ...
            '(15) Map User Comment'};
        repLinkedMapData

        % (3) For Duration
        durationLinkedMapPlot
        durationLinkedHistPlot
        durationLinkedColorBarPlot

        durationLinkedSaveToID = 1;
        durationLinkedMapDataTitle = { '(1) Win Start Index', '(2) Win End Index', ...
            '(3) Area Mode', '(4) AP Mode', ...
            '(5) Duration Level', ...
            '(6) LB', '(7) UB', ...
            '(8) Data Section Time', ...
            '(9) Data Section Camera 1', '(10) Data Section Camera 2', ...
            '(11) Locs Act Camera 1', '(12) Locs Act Camera 2', ...
            '(13) Locs Rep Camera 1', '(14) Locs Rep Camera 2', ...
            '(15) Duration Matrix Camera 1', '(16) Duration Matrix Camera 2', ...
            '(17) Duration Delta (Camera 1 Minus Camera 2) Matrix', ...
            '(18) Ensemble Peaks Number', ...
            '(19) Map User Comment'};
        durationLinkedMapData

        % (4) For Check Signal
        checkSigMarkerXY
        checkSigMouseDragID

        checkSigDataSectionTime

        checkSigDataSectionCamera1
        checkSigDataSectionCamera2

        checkSigLocsActCamera1
        checkSigLocsActCamera2

        checkSigLocsRepCamera1
        checkSigLocsRepCamera2

        checkSigMapData
        checkSigLB
        checkSigUB

        checkSigMarkerPlot
        checkSigMarkerHighlightPlot

        checkSigPlotCamera1
        checkSigPlotCamera2

        checkSigActPointPlotCamera1
        checkSigActPointPlotCamera2

        checkSigRepPointPlotCamera1
        checkSigRepPointPlotCamera2

        checkSigHistIndicatingLinePlot

        % (5) For export
        exportMapName
        exportMapDataTitle
        exportMapData
    end


    methods (Access = public)


        function callByMainApp_OMZer_VmCaLinked_UIFigureCloseRequest(app)

            OMZer_VmCaLinked_UIFigureCloseRequest(app, [])
        end


        function showImageIconAct(app, imageIconName)

            imageIconName.BackgroundColor = app.imageIconActColor; %[0.80, 0.80, 0.80];
            pause(0.05)
            imageIconName.BackgroundColor = 'none';
        end


        function setColourMap(app,name)

            colormap(app.MapAxis, name);
            colormap(app.colorBarAxes, name);
        end


        function setAxisLimit(app, xMaxLimit, yMaxLimit) % Set the x-y limit of all axes

            app.MapAxis.XLim = [1, xMaxLimit];
            app.MapAxis.YLim = [1, yMaxLimit];

            app.CheckSigX_scrn.Value = 1;
            app.CheckSigX_scrn.Limits = [1, xMaxLimit];
            app.CheckSigY_scrn.Value = 1;
            app.CheckSigY_scrn.Limits = [1, yMaxLimit];
        end


        function updateMouseXY(app, axisLosInfo)

            %             app.mouseXY(1,1) = ( app.mouseXY(1,1) - axisLosInfo(1,1) ) * app.cmosDataColNum / axisLosInfo(1,3);
            %             app.mouseXY(1,1) = round( app.mouseXY(1,1) ); % Transfer relative mouse location inside bgImageAxis1 to absolute location inside a 100x100 matrix
            %             if app.mouseXY(1,1) < 1
            %                 app.mouseXY(1,1) = 1; % As a result, there won't appear (0,y) element requirement in a 100x100 matrix
            %             end
            %             if app.mouseXY(1,1) > app.cmosDataColNum
            %                 app.mouseXY(1,1) = app.cmosDataColNum;
            %             end
            %
            %
            %             app.mouseXY(1,2) = axisLosInfo.CurrentPoint(1,2);

            %             app.mouseXY(1,2) = ( (axisLosInfo(1,2) + axisLosInfo(1,4)) - app.mouseXY(1,2) ) * app.cmosDataRowNum / axisLosInfo(1,4);
            %             app.mouseXY(1,2) = round( app.mouseXY(1,2) ); % Transfer relative mouse location inside bgImageAxis1 to absolute location inside a 100x100 matrix
            %             if app.mouseXY(1,2) < 1
            %                 app.mouseXY(1,2) = 1; % As a result, there won't appear (x,0) element requirement in a 100x100 matrix
            %             end
            %             if app.mouseXY(1,2) > app.cmosDataRowNum
            %                 app.mouseXY(1,2) = app.cmosDataRowNum;
            %             end


            app.mouseXY(1,1) = app.MapAxis.CurrentPoint(1,1);
            app.mouseXY(1,2) = app.MapAxis.CurrentPoint(1,2);


            app.mouseXY(1,1) = round( app.mouseXY(1,1) ); % Transfer relative mouse location inside bgImageAxis1 to absolute location inside a 100x100 matrix
            if app.mouseXY(1,1) < 1
                app.mouseXY(1,1) = 1; % As a result, there won't appear (0,y) element requirement in a 100x100 matrix
            end
            if app.mouseXY(1,1) > app.cmosDataColNum
                app.mouseXY(1,1) = app.cmosDataColNum;
            end

            app.mouseXY(1,2) = round( app.mouseXY(1,2) ); % Transfer relative mouse location inside bgImageAxis1 to absolute location inside a 100x100 matrix
            if app.mouseXY(1,2) < 1
                app.mouseXY(1,2) = 1; % As a result, there won't appear (x,0) element requirement in a 100x100 matrix
            end
            if app.mouseXY(1,2) > app.cmosDataRowNum
                app.mouseXY(1,2) = app.cmosDataRowNum;
            end
        end


        function loadSavedMap(app)

            field = { 'VmCaLinkedMeasurement' };
            TF = sum( isfield( app.data, field ) ); % 1 (or 0) - exist (or does not exist)

            if TF == 1 % Exist

                VmCaLinkedMap = app.data.VmCaLinkedMeasurement{ 1, 2 };

                % (1) Act
                app.actLinkedMapData = VmCaLinkedMap{1,3};
                mapNum = 0;
                for ID = 1 : app.maxMapNumber
                    mapNum = mapNum + ( ~isempty( app.actLinkedMapData{1,1,ID} ) );
                end
                app.actLinkedSaveToID = mapNum + 1;

                % (2) Rep
                app.repLinkedMapData = VmCaLinkedMap{2,3};
                mapNum = 0;
                for ID = 1 : app.maxMapNumber
                    mapNum = mapNum + ( ~isempty( app.repLinkedMapData{1,1,ID} ) );
                end
                app.repLinkedSaveToID = mapNum + 1;

                % (3) Duration
                app.durationLinkedMapData = VmCaLinkedMap{3,3};
                mapNum = 0;
                for ID = 1 : app.maxMapNumber
                    mapNum = mapNum + ( ~isempty( app.durationLinkedMapData{1,1,ID} ) );
                end
                app.durationLinkedSaveToID = mapNum + 1;

            else % Does not exist

                % (1) Act
                parameterNumber = length( app.actLinkedMapDataTitle );
                app.actLinkedMapData = cell(1, parameterNumber, app.maxMapNumber);

                % (2) Rep
                parameterNumber = length( app.repLinkedMapDataTitle );
                app.repLinkedMapData = cell(1, parameterNumber, app.maxMapNumber);

                % (3) Duration
                parameterNumber = length( app.durationLinkedMapDataTitle );
                app.durationLinkedMapData = cell(1, parameterNumber, app.maxMapNumber);
            end
        end


        function updateMapListBox(app, mapIconName)

            mapNum = 0;

            if isequal( mapIconName, app.Image_ActDelta )
                for ID = 1 : app.maxMapNumber
                    mapNum = mapNum + ( ~isempty( app.actLinkedMapData{1,1,ID} ) );
                end

            elseif isequal( mapIconName, app.Image_RepDelta )
                for ID = 1 : app.maxMapNumber
                    mapNum = mapNum + ( ~isempty( app.repLinkedMapData{1,1,ID} ) );
                end

            elseif isequal( mapIconName, app.Image_DurationDelta )
                for ID = 1 : app.maxMapNumber
                    mapNum = mapNum + ( ~isempty( app.durationLinkedMapData{1,1,ID} ) );
                end
            end

            % Update MapListBox
            app.MapListBox.Items = {};
            if mapNum ~= 0
                for ID = 1 : mapNum
                    app.MapListBox.Items{ID} = num2str(ID);
                end
            end
        end


        function clearActLinkedMapPlot(app)

            delete(app.actLinkedMapPlot)
            app.actLinkedMapPlot = [];

            delete(app.actLinkedColorBarPlot)
            app.actLinkedColorBarPlot = [];

            delete(app.actLinkedHistPlot)
            app.actLinkedHistPlot = [];
        end


        function clearRepLinkedMapPlot(app)

            delete(app.repLinkedMapPlot)
            app.repLinkedMapPlot = [];

            delete(app.repLinkedColorBarPlot)
            app.repLinkedColorBarPlot = [];

            delete(app.repLinkedHistPlot)
            app.repLinkedHistPlot = [];
        end


        function clearDurationLinkedMapPlot(app)

            delete(app.durationLinkedMapPlot)
            app.durationLinkedMapPlot = [];

            delete(app.durationLinkedColorBarPlot)
            app.durationLinkedColorBarPlot = [];

            delete(app.durationLinkedHistPlot)
            app.durationLinkedHistPlot = [];
        end


        function clearCheckSigPlot(app)

            app.checkSigMarkerXY = [];
            app.CheckSigX_scrn.Value = 1;
            app.CheckSigY_scrn.Value = 1;

            app.checkSigDataSectionTime = [];
            
            app.checkSigDataSectionCamera1 = [];
            app.checkSigDataSectionCamera2 = [];
            
            app.checkSigLocsActCamera1 = [];
            app.checkSigLocsActCamera2 = [];
            
            app.checkSigLocsRepCamera1 = [];
            app.checkSigLocsRepCamera2 = [];
            
            app.checkSigMapData = [];
            app.checkSigLB = [];
            app.checkSigUB = [];

            delete(app.checkSigMarkerPlot)
            delete(app.checkSigMarkerHighlightPlot)
            
            delete(app.checkSigPlotCamera1)
            delete(app.checkSigPlotCamera2)
            
            delete(app.checkSigActPointPlotCamera1)
            delete(app.checkSigActPointPlotCamera2)
            
            delete(app.checkSigRepPointPlotCamera1)
            delete(app.checkSigRepPointPlotCamera2)
            
            delete(app.checkSigHistIndicatingLinePlot)

            app.checkSigMarkerPlot = [];
            app.checkSigMarkerHighlightPlot = [];
            
            app.checkSigPlotCamera1 = [];
            app.checkSigPlotCamera2 = [];
            
            app.checkSigActPointPlotCamera1 = [];
            app.checkSigActPointPlotCamera2 = [];
            
            app.checkSigRepPointPlotCamera1 = [];
            app.checkSigRepPointPlotCamera2 = [];
            
            app.checkSigHistIndicatingLinePlot = [];
        end


        function resetStatisticResult(app)

            app.MeanmsEditField.Value = 0;
            app.MedianmsEditField.Value = 0;
            app.SDmsEditField.Value = 0;

            app.LBmsSpinner.Limits = [ -Inf, Inf ];
            app.LBmsSpinner.Value = 0;
            app.UBmsSpinner.Limits = [ -Inf, Inf ];
            app.UBmsSpinner.Value = 0;
        end


        function prepareMapCalculation(app, mapIconName)

            % (1) Update ribbon menu and calculation components
            app.Image_ExportData.Enable = 'off';
            app.Image_ExportFigure.Enable = 'off';

            app.ActDeltaLabel.FontColor = [ 0.00, 0.00, 0.00 ]; % Black
            app.RepDeltaLabel.FontColor = [ 0.00, 0.00, 0.00 ];
            app.DurationDeltaLabel.FontColor = [ 0.00, 0.00, 0.00 ];

            app.RepDropDown.Enable = 'off';
            app.DurationDropDown.Enable = 'off';

            if isequal( mapIconName, app.Image_ActDelta )
                app.ActDeltaLabel.FontColor = [ 1.00, 0.00, 0.00 ]; % Red

            elseif isequal( mapIconName, app.Image_RepDelta )
                app.RepDeltaLabel.FontColor = [ 1.00, 0.00, 0.00 ];
                app.RepDropDown.Enable = 'on';

            elseif isequal( mapIconName, app.Image_DurationDelta )
                app.DurationDeltaLabel.FontColor = [ 1.00, 0.00, 0.00 ];
                app.DurationDropDown.Enable = 'on';
            end

            app.Image_RegionalMap.Enable = 'on';
            app.Image_GlobalMap.Enable = 'on';

            % (2) Unload the previous map
            if ~isempty(app.MapListBox.Value)

                % (2.1) Reset statistic result
                resetStatisticResult(app)
                app.LBmsSpinner.Enable = 'off';
                app.UBmsSpinner.Enable = 'off';

                % (2.2) Reset signal window information
                app.WinStartsecEditField.Value = Inf;
                app.WinEndsecEditField.Value = Inf;

                % (2.3) Clear displayed map and signal plot
                app.MapTitleLabel.Text = 'Map Title';
                cla(app.MapAxis)
                cla(app.colorBarAxes)
                cla(app.MapHistAxis)
                imagesc( app.MapAxis, app.bgImageNomGroup{app.cameraSource} );

                clearActLinkedMapPlot(app)
                clearRepLinkedMapPlot(app)
                clearDurationLinkedMapPlot(app)

                % (2.4) Clear check signal
                app.Image_DisplayPixel.Enable = 'off';
                app.Image_ClearPixel.Enable = 'off';
                if ~isempty(app.checkSigMarkerPlot)

                    clearCheckSigPlot(app)

                    app.CheckSigX_scrn.Value = 1;
                    app.CheckSigY_scrn.Value = 1;
                    app.CheckSigX_scrn.Editable = 'off';
                    app.CheckSigY_scrn.Editable = 'off';
                    app.CheckSigX_scrn.BackgroundColor = [1.00, 1.00, 1.00]; % White
                    app.CheckSigY_scrn.BackgroundColor = [1.00, 1.00, 1.00];
                end

                % (2.5) Deselect the 'MapListBox'
                app.MapListBox.Value = {};
                app.Image_ResetBound.Enable = 'off';
                app.Image_UnloadMap.Enable = 'off';
                app.Image_DeleteMap.Enable = 'off';

                % (2.6) Clear map user comment
                app.UserCommentTextArea.Enable = 'off';
                app.UserCommentTextArea.Value = {''};
            end

            % (3) Update 'MapListBox'
            updateMapListBox(app, mapIconName)

            % (4) For export data
            app.exportMapName = [];
            app.exportMapDataTitle = [];
            app.exportMapData = [];
        end


        function checkLBUBAdjustment(app, matrix)

            mapTemporary = matrix;

            mapTemporaryCheck = mapTemporary;
            mapTemporaryCheck( mapTemporaryCheck < app.LBmsSpinner.Value  |  mapTemporaryCheck > app.UBmsSpinner.Value ) = NaN;

            if ~isnan( min(mapTemporaryCheck(:)) )  &&  ~isnan( max(mapTemporaryCheck(:)) )  &&  isequal( min(mapTemporaryCheck(:)), max(mapTemporaryCheck(:)) )

                msg = 'Although you specify different bounds for min and max, the min value and max value in the resulting range are actually the same. Please adjust the range.';
                uialert(app.OMZer_VmCaLinked_UIFigure, msg,'Warning','Icon','error')

                app.LBmsSpinner.Value = min(app.LBmsSpinner.Limits);
                app.UBmsSpinner.Value = max(app.UBmsSpinner.Limits);
            end
        end


        function newPlotObject = plotMap(app, plotObject, matrix, LB, UB)

            matrix( matrix < LB  |  matrix > UB ) = NaN;

            hold(app.MapAxis,'on')
            delete(plotObject)

            % newPlotObject = imagesc(app.MapAxis, matrix);
            % newPlotObject.AlphaData = ~isnan(matrix) * app.userAlphaValue; % Make the pixel with NaN transparent

            newPlotObject = pcolor( app.MapAxis, matrix );
            newPlotObject.FaceColor = 'interp';
            newPlotObject.EdgeColor = 'none';
            newPlotObject.AlphaData = ~isnan(matrix) * app.userAlphaValue; % Make the pixel with NaN transparent

            if nanmin(matrix(:)) == nanmax(matrix(:))
                caxis(app.MapAxis, [ nanmin(matrix(:)), nanmax(matrix(:))+1 ]);
            else
                caxis(app.MapAxis, [ nanmin(matrix(:)), nanmax(matrix(:)) ]);
            end
            hold(app.MapAxis,'off')
        end


        function newPlotObject = plotMapColorBar(app, plotObject, matrix, LB, UB)

            matrix( matrix < LB  |  matrix > UB ) = NaN;

            matrixColumnVersion = reshape( matrix, [],1 );
            matrixColumnVersion( isnan(matrixColumnVersion) ) = [];
            matrixColumnVersion = unique(matrixColumnVersion);

            % (1) Plot the color bar
            delete(plotObject)
            newPlotObject = imagesc(app.colorBarAxes, matrixColumnVersion);
            yticks(app.colorBarAxes, 'auto')
            app.colorBarAxes.YLim = [ 1, length(matrixColumnVersion) ];

            % (2) Manually create y tick label
            if length(app.colorBarAxes.YTick) <= length(matrixColumnVersion)

                app.colorBarAxes.YTick( mod(app.colorBarAxes.YTick , 1) ~= 0 ) = []; % Only keep integer tick

            else
                app.colorBarAxes.YTick = [ 1 : length(matrixColumnVersion) ];
            end

            colorBarLabel = cell( 1, length(app.colorBarAxes.YTick) );
            for ID = 1 : length(app.colorBarAxes.YTick)
                colorBarLabel{ID} = num2str( matrixColumnVersion(app.colorBarAxes.YTick(ID)) );
            end
            app.colorBarAxes.YTickLabel = colorBarLabel;
        end


        function newPlotObject = plotMapHist(app, plotObject, matrix, LB, UB)

            matrix( matrix < LB  |  matrix > UB ) = NaN;

            histColumnVersion = reshape(matrix, [],1);
            histColumnVersion( isnan(histColumnVersion) ) = []; % Delete NaN because it will also be counted into 'data length'
            binNum = round( sqrt( length(histColumnVersion) ) ); % Specifies the number of bins (= square root of the number of observations)

            hold(app.MapHistAxis,'on')
            delete(plotObject)

            %            newPlotObject = histogram(app.MapHistAxis, histColumnVersion, binNum, 'FaceColor',[0.00, 0.45, 0.74], 'FaceAlpha',1);

            newPlotObject = histogram(app.MapHistAxis, histColumnVersion, 'FaceColor',[0.00, 0.45, 0.74], 'FaceAlpha',1);


            %             % Colourful Histgtam (Nedd code update)
            %             if isequal( app.Setup.VmMapCalSetup{13,2}, 'Multiple' )
            %
            %                 newPlotObject.FaceAlpha = 0;
            %
            %                 histBinEdge = newPlotObject.BinEdges;
            %                 eval( [ 'colorLevel = ', app.userColourMap, '(', num2str(binNum), ');' ] )
            %                 app.colorHistPlot = cell(binNum,1);
            %
            %                 for ID = 1 : binNum
            %                     app.colorHistPlot{ID} = histogram( app.MapHistAxis, HistCol( HistCol >= histBinEdge(ID) & HistCol < histBinEdge(ID+1) ), histBinEdge, 'FaceColor',colorLevel(ID,:), 'FaceAlpha',1 );
            %                 end
            %             end

            hold(app.MapHistAxis,'off')

            if min(histColumnVersion) == max(histColumnVersion)
                xlim( app.MapHistAxis, [min(histColumnVersion), max(histColumnVersion)+1] );
            else
                xlim( app.MapHistAxis, [min(histColumnVersion), max(histColumnVersion)] );
            end

            app.MapHistAxis.YLim = [ 0, max(newPlotObject.BinCounts) ];
        end


        function updateStatisticResult(app, matrix, LB, UB)

            % (1) The "LB" and "UB" are for the whole area. Use these two values to set region of interest
            if nanmin(matrix(:)) == nanmax(matrix(:))

                app.LBmsSpinner.Limits = [ nanmin(matrix(:)), nanmax(matrix(:))+1 ];
                app.UBmsSpinner.Limits = [ nanmin(matrix(:)), nanmax(matrix(:))+1 ];

            else
                app.LBmsSpinner.Limits = [ nanmin(matrix(:)), nanmax(matrix(:)) ];
                app.UBmsSpinner.Limits = [ nanmin(matrix(:)), nanmax(matrix(:)) ];
            end

            app.LBmsSpinner.Value = LB;
            app.UBmsSpinner.Value = UB;

            mapTemporary = matrix;
            mapTemporary( mapTemporary < LB  |  mapTemporary > UB ) = NaN;
            
            % (2) Further set the region of interest for statistics calculation
            if isequal( app.AreaForStatisticsDeltaCamera1Camera2ButtonGroup.SelectedObject, app.WholeAreaButton )

                % Do nothing. Calculate statistics including "0", "negative", and "positive" area
                
            elseif isequal( app.AreaForStatisticsDeltaCamera1Camera2ButtonGroup.SelectedObject, app.NegativeDeltaAreaButton )
                
                mapTemporary( mapTemporary >= 0 ) = NaN;
                
            elseif isequal( app.AreaForStatisticsDeltaCamera1Camera2ButtonGroup.SelectedObject, app.PositiveDeltaAreaButton )
                
                mapTemporary( mapTemporary <= 0 ) = NaN;
            end
            
            % (3) Calculate statistics
            if sum( sum( ~isnan(mapTemporary) ) ) == 0

                msg = [ 'There is no real value in "', app.AreaForStatisticsDeltaCamera1Camera2ButtonGroup.SelectedObject.Text, '" for statistics calculation'  ];
                uialert(app.OMZer_VmCaLinked_UIFigure, msg,'Warning','Icon','error')
                
                app.MeanmsEditField.Value = Inf;
                app.MedianmsEditField.Value = Inf;
                app.SDmsEditField.Value = Inf;
            else
                
                app.MeanmsEditField.Value = nanmean( mapTemporary(:) );
                app.MedianmsEditField.Value = nanmedian( mapTemporary(:) );
                app.SDmsEditField.Value = nanstd( mapTemporary(:) );
            end
        end


        function updateSignalWindowInformation(app, thisWinStartIndex, thisWinEndIndex)

            if ( prod( ~isnan(thisWinStartIndex(:)) ) == 1 )  &&  ( prod( ~isnan(thisWinEndIndex(:)) ) == 1 )

                if length(thisWinStartIndex) == 1  &&  length(thisWinEndIndex) == 1

                    app.WinStartsecEditField.Value = app.signalTime(thisWinStartIndex);
                    app.WinEndsecEditField.Value = app.signalTime(thisWinEndIndex);
                end
            end
        end


        function plotObject = updateCheckSigMarker(app, markerColor)

            X = app.checkSigMarkerXY(1,1);
            Y = app.checkSigMarkerXY(1,2);

            plotObject = line(app.MapAxis, X, Y, 'Marker','square', 'MarkerSize',10, 'MarkerEdgeColor','white', 'MarkerFaceColor', markerColor);
        end


        function plotObject = updateCheckSigMarkerHighlight(app)

            X = app.checkSigMarkerXY(1,1);
            Y = app.checkSigMarkerXY(1,2);

            plotObject = line(app.MapAxis, X, Y, 'Marker','o', 'LineWidth',4, 'MarkerSize',30, 'MarkerEdgeColor','white', 'MarkerFaceColor', 'none');
        end


        function [ plotObjectCamera1, plotObjectCamera2 ] = updateCheckSig(app, dataTime, dataCamera1, dataCamera2, lineColor)

            row = app.checkSigMarkerXY(1,2);
            col = app.checkSigMarkerXY(1,1);

            lineColorCamera1 = lineColor;
            lineColorCamera2 = [ 1.00, 0.00, 0.00 ]; % Red
            
            plotObjectCamera1 = line( app.checkSigAxis, dataTime, squeeze(dataCamera1(row,col,:)), 'LineStyle','-', 'LineWidth',app.lWidth, 'color', lineColorCamera1 );
            plotObjectCamera2 = line( app.checkSigAxis, dataTime, squeeze(dataCamera2(row,col,:)), 'LineStyle','-.', 'LineWidth',app.lWidth, 'color', lineColorCamera2 );
            
            xlim( app.checkSigAxis, [ dataTime(1), dataTime(end) ] );
        end


        function plotObject = updatecheckSigHistIndicatingLine(app, dataMap)

            row = app.checkSigMarkerXY(1,2);
            col = app.checkSigMarkerXY(1,1);

            if ~isnan( dataMap(row,col) )

                valueLabel = num2str(dataMap(row,col));

                plotObject = xline( app.MapHistAxis, dataMap(row,col), 'LineWidth',app.lWidth, 'color','Red' );
                plotObject.Label = valueLabel;
                plotObject.FontSize = 20;
                plotObject.FontWeight = 'bold';

                delta = app.MapHistAxis.XLim(2) - app.MapHistAxis.XLim(1);
                if dataMap(row,col) <= app.MapHistAxis.XLim(1) + 0.1*delta
                    plotObject.LabelHorizontalAlignment = 'right';

                elseif dataMap(row,col) >= app.MapHistAxis.XLim(2) - 0.1*delta
                    plotObject.LabelHorizontalAlignment = 'left';

                else
                    plotObject.LabelHorizontalAlignment = 'center';
                end

            else
                plotObject = [];
            end
        end


        function [ plotObjectCamera1, plotObjectCamera2 ] = updateCheckSigActPoints(app, dataTime, dataCamera1, dataCamera2)

            row = app.checkSigMarkerXY(1,2);
            col = app.checkSigMarkerXY(1,1);

            IDCamera1 = app.checkSigLocsActCamera1( row, col );
            if ~isnan(IDCamera1)
                plotObjectCamera1 = line( app.checkSigAxis, dataTime(IDCamera1), dataCamera1(row,col,IDCamera1), 'Marker','o', 'MarkerSize',10, 'MarkerEdgeColor','black', 'MarkerFaceColor','black' );
            else
                plotObjectCamera1 = [];
            end
            
            IDCamera2 = app.checkSigLocsActCamera2( row, col );
            if ~isnan(IDCamera2)
                plotObjectCamera2 = line( app.checkSigAxis, dataTime(IDCamera2), dataCamera2(row,col,IDCamera2), 'Marker','o', 'MarkerSize',10, 'MarkerEdgeColor','black', 'MarkerFaceColor','black' );
            else
                plotObjectCamera2 = [];
            end
        end


        function [ plotObjectCamera1, plotObjectCamera2 ] = updateCheckSigRepPoints(app, dataTime, dataCamera1, dataCamera2)

            row = app.checkSigMarkerXY(1,2);
            col = app.checkSigMarkerXY(1,1);

            IDCamera1 = app.checkSigLocsRepCamera1( row, col );
            if ~isnan(IDCamera1)
                plotObjectCamera1 = line( app.checkSigAxis, dataTime(IDCamera1), dataCamera1(row,col,IDCamera1), 'Marker','d', 'MarkerSize',10, 'MarkerEdgeColor','black', 'MarkerFaceColor','black' );
            else
                plotObjectCamera1 = [];
            end
            
            IDCamera2 = app.checkSigLocsRepCamera2( row, col );
            if ~isnan(IDCamera2)
                plotObjectCamera2 = line( app.checkSigAxis, dataTime(IDCamera2), dataCamera2(row,col,IDCamera2), 'Marker','d', 'MarkerSize',10, 'MarkerEdgeColor','black', 'MarkerFaceColor','black' );
            else
                plotObjectCamera2 = [];
            end
        end


        function outputUserComment = generateMultiBeatsMapUserComment(app, inputUserComment, windowStartTime, windowEndTime)

            if iscolumn(windowStartTime) % Unit in 'sec'
                windowStartTime_row = windowStartTime';
            else
                windowStartTime_row = windowStartTime;
            end

            if iscolumn(windowEndTime) % Unit in 'sec'
                windowEndTime_row = windowEndTime';
            else
                windowEndTime_row = windowEndTime;
            end

            windowStartTimeText = 'Multi Beats window start (sec) at ';
            for ID = 1 : length(windowStartTime_row)
                windowStartTimeText = [ windowStartTimeText, num2str( windowStartTime_row(ID) ) ];
                if ID ~= length(windowStartTime_row)
                    windowStartTimeText = [windowStartTimeText, ', '];
                end
            end

            windowEndTimeText = 'Multi Beats window end (sec) at ';
            for ID = 1 : length(windowEndTime_row)
                windowEndTimeText = [ windowEndTimeText, num2str( windowEndTime_row(ID) ) ];
                if ID ~= length(windowEndTime_row)
                    windowEndTimeText = [windowEndTimeText, ', '];
                end
            end

            outputUserComment = inputUserComment;
            outputUserComment{ length(outputUserComment)+1, 1 } = windowStartTimeText;
            outputUserComment{ length(outputUserComment)+1, 1 } = windowEndTimeText;
        end


        function saveActLinkedMap(app, winStartIndex, winEndIndex, areaLabel, mapMode, dataSectionTime, dataSectionCamera1, dataSectionCamera2, locsActCamera1, locsActCamera2, actDeltaMatrix, pksNum, mapUserComment)

            app.actLinkedMapData{1, 1, app.actLinkedSaveToID} = winStartIndex;
            app.actLinkedMapData{1, 2, app.actLinkedSaveToID} = winEndIndex;
            app.actLinkedMapData{1, 3, app.actLinkedSaveToID} = areaLabel;
            app.actLinkedMapData{1, 4, app.actLinkedSaveToID} = mapMode;
            app.actLinkedMapData{1, 5, app.actLinkedSaveToID} = nanmin( actDeltaMatrix(:) );
            app.actLinkedMapData{1, 6, app.actLinkedSaveToID} = nanmax( actDeltaMatrix(:) );
            app.actLinkedMapData{1, 7, app.actLinkedSaveToID} = dataSectionTime;
            
            app.actLinkedMapData{1, 8, app.actLinkedSaveToID} = dataSectionCamera1;
            app.actLinkedMapData{1, 9, app.actLinkedSaveToID} = dataSectionCamera2;
            
            app.actLinkedMapData{1, 10, app.actLinkedSaveToID} = locsActCamera1;
            app.actLinkedMapData{1, 11, app.actLinkedSaveToID} = locsActCamera2;
            
            app.actLinkedMapData{1, 12, app.actLinkedSaveToID} = actDeltaMatrix;
            
            app.actLinkedMapData{1, 13, app.actLinkedSaveToID} = pksNum;
            app.actLinkedMapData{1, 14, app.actLinkedSaveToID} = mapUserComment;
        end


        function saveRepLinkedMap(app, winStartIndex, winEndIndex, areaLabel, mapMode, dataSectionTime, dataSectionCamera1, dataSectionCamera2, locsRepCamera1, locsRepCamera2, repDeltaMatrix, pksNum, mapUserComment, repLevel)
            
            app.repLinkedMapData{1, 1, app.repLinkedSaveToID} = winStartIndex;
            app.repLinkedMapData{1, 2, app.repLinkedSaveToID} = winEndIndex;
            app.repLinkedMapData{1, 3, app.repLinkedSaveToID} = areaLabel;
            app.repLinkedMapData{1, 4, app.repLinkedSaveToID} = mapMode;
            app.repLinkedMapData{1, 5, app.repLinkedSaveToID} = repLevel;
            app.repLinkedMapData{1, 6, app.repLinkedSaveToID} = nanmin( repDeltaMatrix(:) );
            app.repLinkedMapData{1, 7, app.repLinkedSaveToID} = nanmax( repDeltaMatrix(:) );
            app.repLinkedMapData{1, 8, app.repLinkedSaveToID} = dataSectionTime;
            
            app.repLinkedMapData{1, 9, app.repLinkedSaveToID} = dataSectionCamera1;
            app.repLinkedMapData{1, 10, app.repLinkedSaveToID} = dataSectionCamera2;
            
            app.repLinkedMapData{1, 11, app.repLinkedSaveToID} = locsRepCamera1;
            app.repLinkedMapData{1, 12, app.repLinkedSaveToID} = locsRepCamera2;
            
            app.repLinkedMapData{1, 13, app.repLinkedSaveToID} = repDeltaMatrix;
            
            app.repLinkedMapData{1, 14, app.repLinkedSaveToID} = pksNum;
            app.repLinkedMapData{1, 15, app.repLinkedSaveToID} = mapUserComment;
        end


        function saveDurationLinkedMap(app, winStartIndex, winEndIndex, areaLabel, mapMode, dataSectionTime, dataSectionCamera1, dataSectionCamera2, locsActCamera1, locsActCamera2, locsRepCamera1, locsRepCamera2, durationMatrixCamera1, durationMatrixCamera2, durationDeltaMatrix, pksNum, mapUserComment, repLevel )
                        
            app.durationLinkedMapData{1, 1, app.durationLinkedSaveToID} = winStartIndex;
            app.durationLinkedMapData{1, 2, app.durationLinkedSaveToID} = winEndIndex;
            app.durationLinkedMapData{1, 3, app.durationLinkedSaveToID} = areaLabel;
            app.durationLinkedMapData{1, 4, app.durationLinkedSaveToID} = mapMode;
            app.durationLinkedMapData{1, 5, app.durationLinkedSaveToID} = repLevel;
            app.durationLinkedMapData{1, 6, app.durationLinkedSaveToID} = nanmin( durationDeltaMatrix(:) );
            app.durationLinkedMapData{1, 7, app.durationLinkedSaveToID} = nanmax( durationDeltaMatrix(:) );
            app.durationLinkedMapData{1, 8, app.durationLinkedSaveToID} = dataSectionTime;
            
            app.durationLinkedMapData{1, 9, app.durationLinkedSaveToID} = dataSectionCamera1;
            app.durationLinkedMapData{1, 10, app.durationLinkedSaveToID} = dataSectionCamera2;
            
            app.durationLinkedMapData{1, 11, app.durationLinkedSaveToID} = locsActCamera1;
            app.durationLinkedMapData{1, 12, app.durationLinkedSaveToID} = locsActCamera2;
            
            app.durationLinkedMapData{1, 13, app.durationLinkedSaveToID} = locsRepCamera1;
            app.durationLinkedMapData{1, 14, app.durationLinkedSaveToID} = locsRepCamera2;
            
            app.durationLinkedMapData{1, 15, app.durationLinkedSaveToID} = durationMatrixCamera1;
            app.durationLinkedMapData{1, 16, app.durationLinkedSaveToID} = durationMatrixCamera2;
            
            app.durationLinkedMapData{1, 17, app.durationLinkedSaveToID} = durationDeltaMatrix;
            
            app.durationLinkedMapData{1, 18, app.durationLinkedSaveToID} = pksNum;
            app.durationLinkedMapData{1, 19, app.durationLinkedSaveToID} = mapUserComment;
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, OMZer)

            msg = 'Configuring files';
            progressBar = uiprogressdlg(app.OMZer_VmCaLinked_UIFigure, 'Title','Initialising', 'Message',msg, 'Indeterminate','on');
            drawnow

            % (1) User set up
            app.setup = load([pwd,'/OMZer_Setup']);
            setColourMap(app, app.setup.userColourMap);
            app.userAlphaValue = 1.0; % The default alpha value
            app.ColorID = {app.Color1, app.Color2, app.Color3, app.Color4, app.Color5, app.Color6}; % Store 6 color

            % (2) Get information from the mainApp
            app.mainApp = OMZer;
            app.mainAppUIFigure = app.mainApp.OMZer_UIFigure;

            app.dirName = app.mainApp.dirName;
            app.fileName = app.mainApp.fileName;

            app.data = app.mainApp.data;
            app.acqFreq = app.mainApp.acqFreq;
            app.markerXY = app.mainApp.markerXY;
            app.signalTime = app.mainApp.signalTime;
            app.cmosDataGroup = app.mainApp.cmosDataGroup;
            app.bgImageNomGroup = app.mainApp.bgImageNomGroup;
            app.cameraSource = app.mainApp.activeCamera;

            app.pacing1 = app.mainApp.pacing1;
            app.pacing2 = app.mainApp.pacing2;
            app.pacingTime = app.mainApp.pacingTime;
            app.pacing1Enable = app.mainApp.pacing1CheckBox.Enable;
            app.pacing2Enable = app.mainApp.pacing2CheckBox.Enable;

            % (3) Diable Toolbar
            % https://www.mathworks.com/help/matlab/ref/axtoolbar.html?searchHighlight=axtoolbar&s_tid=doc_srchtitle
            try
                disableDefaultInteractivity(app.colorBarAxes);
                disableDefaultInteractivity(app.MapAxis);
                disableDefaultInteractivity(app.MapHistAxis);
                disableDefaultInteractivity(app.checkSigAxis);

                axtoolbar(app.colorBarAxes);
                axtoolbar(app.MapAxis);
                axtoolbar(app.MapHistAxis);
                axtoolbar(app.checkSigAxis);

            catch
                % Do nothing
            end

            % (4) Plot background image
            [app.cmosDataRowNum, app.cmosDataColNum, ~] = size( app.cmosDataGroup{app.cameraSource} );
            setAxisLimit(app, app.cmosDataColNum, app.cmosDataRowNum);
            imagesc( app.MapAxis, app.bgImageNomGroup{app.cameraSource} );

            % (5) Load saved map
            loadSavedMap(app)

            % (6) Other
            Image_ActDeltaClicked(app, 'ImageClicked')

            close(progressBar)

        end

        % Image clicked function: Image_Signal
        function Image_SignalClicked(app, event)

            showImageIconAct(app, app.Image_Signal)
            app.OMZer_VmCaLinked_Signal = OMZer_VmCaLinked_Signal(app);

        end

        % Image clicked function: Image_ActDelta
        function Image_ActDeltaClicked(app, event)

            showImageIconAct(app, app.Image_ActDelta)
            prepareMapCalculation(app, app.Image_ActDelta)

        end

        % Image clicked function: Image_RepDelta
        function Image_RepDeltaClicked(app, event)

            showImageIconAct(app, app.Image_RepDelta)
            prepareMapCalculation(app, app.Image_RepDelta)

        end

        % Image clicked function: Image_DurationDelta
        function Image_DurationDeltaClicked(app, event)

            showImageIconAct(app, app.Image_DurationDelta)
            prepareMapCalculation(app, app.Image_DurationDelta)

        end

        % Image clicked function: Image_Save
        function Image_SaveClicked(app, event)

            showImageIconAct(app, app.Image_Save)

            msg = 'Saving the file';
            progressBar = uiprogressdlg(app.OMZer_VmCaLinked_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');

            % (1) Update the data
            VmCaLinkedMap = cell(3,3);

            VmCaLinkedMap{1,1} = 'Act Linked';
            VmCaLinkedMap{2,1} = 'Rep Linked';
            VmCaLinkedMap{3,1} = 'Duration Linked';
            
            VmCaLinkedMap{1,2} = app.actLinkedMapDataTitle;
            VmCaLinkedMap{1,3} = app.actLinkedMapData;

            VmCaLinkedMap{2,2} = app.repLinkedMapDataTitle;
            VmCaLinkedMap{2,3} = app.repLinkedMapData;

            VmCaLinkedMap{3,2} = app.durationLinkedMapDataTitle;
            VmCaLinkedMap{3,3} = app.durationLinkedMapData;
            
            VmCaLinkedMeasurement = { 'Camera 1 Minus Camera 2', VmCaLinkedMap };
            
            % (2) Save the data
            newFileName = [app.dirName, '/', app.fileName, '.mat'];

            save(newFileName, 'VmCaLinkedMeasurement', '-append');
            
            app.mainApp.data = load( newFileName ); % Since data changed, need to re-load and update data in the main app
            app.data = app.mainApp.data;

            close(progressBar)

        end

        % Image clicked function: Image_ExportData
        function Image_ExportDataClicked(app, event)

            showImageIconAct(app, app.Image_ExportData)

            msg = 'Choose the data you want to export';
            msgTitle = 'Warning';
            optionItem = { 'Export Map Data (.mat File)', 'Export Display Pixel (.mat File)', 'Export Map ROI (.mat File)', 'Cancel' };
            selection = uiconfirm( app.OMZer_VmCaLinked_UIFigure, msg, msgTitle, ...
                'Options', optionItem, ...
                'DefaultOption', 1, ...
                'CancelOption', 4 ); % uiconfirm returns the selection as a character vector

            switch selection

                case optionItem{1} % Export Map Data (.mat File)

                    % Get valid saving directory and file name
                    saveDir = uigetdir;

                    if saveDir == 0 % User has NOT selected file directory.

                        msg = 'Please select a valid saving directory';
                        uialert(app.OMZer_VmCaLinked_UIFigure,msg,'Warning','Icon','error')

                    else

                        prompt = {'Enter filename to save (without extension)...   Please do not use \/:*?"<>|'};
                        dlgtitle = 'Save to a .mat file';
                        dfinput = { [ app.fileName, '_Vm_', app.exportMapName, '_Data' ] };
                        dims = [1,100];
                        filename = inputdlg(prompt,dlgtitle,dims,dfinput);

                        if isempty(filename) % User has NOT entered a name

                            msg = 'Please enter a valid file name';
                            uialert(app.OMZer_VmCaLinked_UIFigure,msg,'Warning','Icon','error')

                        else

                            % Export map data
                            mapName = app.exportMapName;
                            mapDataTitle = app.exportMapDataTitle;
                            mapData = app.exportMapData;
                            mapDataWithTitle = cell( 2, length(mapDataTitle) );
                            mapDataWithTitle(1,:) = app.exportMapDataTitle;
                            mapDataWithTitle(2,:) = app.exportMapData;

                            acquisitionFrequency_Hz_Unit = app.acqFreq;
                            backgroundImage = app.bgImageNomGroup{app.cameraSource};
                            cameraID = app.cameraSource;
                            mapPlotTitle = app.MapTitleLabel.Text;
                            
                            originalSelectedObject = app.AreaForStatisticsDeltaCamera1Camera2ButtonGroup.SelectedObject;
                            
                            % Export map data - whole area statistics
                            app.AreaForStatisticsDeltaCamera1Camera2ButtonGroup.SelectedObject = app.WholeAreaButton;
                            AreaForStatisticsDeltaCamera1Camera2ButtonGroupSelectionChanged(app, event)
                            meanValue_WholeArea_ms_Unit = app.MeanmsEditField.Value;
                            medianValue_WholeArea_ms_Unit = app.MedianmsEditField.Value;
                            SDValue_WholeArea_ms_Unit = app.SDmsEditField.Value;

                            % Export map data - negative delta area statistics
                            app.AreaForStatisticsDeltaCamera1Camera2ButtonGroup.SelectedObject = app.NegativeDeltaAreaButton;
                            AreaForStatisticsDeltaCamera1Camera2ButtonGroupSelectionChanged(app, event)
                            meanValue_NegativeDeltaArea_ms_Unit = app.MeanmsEditField.Value;
                            medianValue_NegativeDeltaArea_ms_Unit = app.MedianmsEditField.Value;
                            SDValue_NegativeDeltaArea_ms_Unit = app.SDmsEditField.Value;
                            
                            % Export map data - positive delta area statistics
                            app.AreaForStatisticsDeltaCamera1Camera2ButtonGroup.SelectedObject = app.PositiveDeltaAreaButton;
                            AreaForStatisticsDeltaCamera1Camera2ButtonGroupSelectionChanged(app, event)
                            meanValue_PositiveDeltaArea_ms_Unit = app.MeanmsEditField.Value;
                            medianValue_PositiveDeltaArea_ms_Unit = app.MedianmsEditField.Value;
                            SDValue_PositiveDeltaArea_ms_Unit = app.SDmsEditField.Value;
                            
                            app.AreaForStatisticsDeltaCamera1Camera2ButtonGroup.SelectedObject = originalSelectedObject;
                            AreaForStatisticsDeltaCamera1Camera2ButtonGroupSelectionChanged(app, event)
                            
                            if isequal( app.ActDeltaLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Act map

                                msg = [ 'Export the ', app.ActDeltaLabel.Text, ' Map' ];
                                progressBar = uiprogressdlg(app.OMZer_VmCaLinked_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');

                                mapData{12} = app.actLinkedMapPlot.CData;
                                mapUserComment = mapData{14};

                            elseif isequal( app.RepDeltaLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Rep map

                                msg = [ 'Export the ', app.RepDeltaLabel.Text, ' Map' ];
                                progressBar = uiprogressdlg(app.OMZer_VmCaLinked_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');

                                mapData{13} = app.repLinkedMapPlot.CData;
                                mapUserComment = mapData{15};

                            elseif isequal( app.DurationDeltaLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Duration map

                                msg = [ 'Export the ', app.DurationDeltaLabel.Text, ' Map' ];
                                progressBar = uiprogressdlg(app.OMZer_VmCaLinked_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');

                                mapData{17} = app.durationLinkedMapPlot.CData;
                                mapUserComment = mapData{19};
                            end

                            fullSaveName = strcat(saveDir, '/', filename, '.mat'); % At this moment, 'fullSaveName' is a 'cell'.
                            fullSaveName = fullSaveName{1}; % Now 'fullSaveName' is a 'character
                            save(fullSaveName, ...
                                'mapName', ...
                                'mapDataTitle', ...
                                'mapData', ...
                                'mapDataWithTitle', ...
                                'mapUserComment', ...
                                'acquisitionFrequency_Hz_Unit', ...
                                'backgroundImage', ...
                                'cameraID', ...
                                'mapPlotTitle', ...
                                'meanValue_WholeArea_ms_Unit', ...
                                'medianValue_WholeArea_ms_Unit', ...
                                'SDValue_WholeArea_ms_Unit', ...
                                'meanValue_NegativeDeltaArea_ms_Unit', ...
                                'medianValue_NegativeDeltaArea_ms_Unit', ...
                                'SDValue_NegativeDeltaArea_ms_Unit', ...
                                'meanValue_PositiveDeltaArea_ms_Unit', ...
                                'medianValue_PositiveDeltaArea_ms_Unit', ...
                                'SDValue_PositiveDeltaArea_ms_Unit');

                            close(progressBar)
                        end
                    end

                    figure(app.OMZer_VmCaLinked_UIFigure) % Set this uifigure at the top level


                case optionItem{2} % Export Display Pixel (.xlsx File)

                    app.OMZer_VmCaLinked_ExportDisplayPixel = OMZer_VmCaLinked_ExportDisplayPixel(app);


                case optionItem{3} % Export Map ROI (.mat File)

                    % Get valid saving directory and file name
                    saveDir = uigetdir;

                    if saveDir == 0 % User has NOT selected file directory.

                        msg = 'Please select a valid saving directory';
                        uialert(app.OMZer_VmCaLinked_UIFigure,msg,'Warning','Icon','error')

                    else

                        prompt = {'Enter filename to save (without extension)...   Please do not use \/:*?"<>|'};
                        dlgtitle = 'Save to a .mat file';
                        dfinput = { [ app.fileName, '_Vm_', app.exportMapName, '_Map_ROI' ] };
                        dims = [1,100];
                        filename = inputdlg(prompt,dlgtitle,dims,dfinput);

                        if isempty(filename) % User has NOT entered a name

                            msg = 'Please enter a valid file name';
                            uialert(app.OMZer_VmCaLinked_UIFigure,msg,'Warning','Icon','error')

                        else

                            % Export map data
                            if isequal( app.ActDeltaLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Act map

                                msg = [ 'Export the ', app.ActDeltaLabel.Text, ' Map ROI' ];
                                progressBar = uiprogressdlg(app.OMZer_VmCaLinked_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');

                                matrix = app.actLinkedMapPlot.CData;

                            elseif isequal( app.RepDeltaLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Rep map

                                msg = [ 'Export the ', app.RepDeltaLabel.Text, ' Map ROI' ];
                                progressBar = uiprogressdlg(app.OMZer_VmCaLinked_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');

                                matrix = app.repLinkedMapPlot.CData;

                            elseif isequal( app.DurationDeltaLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Duration map

                                msg = [ 'Export the ', app.DurationDeltaLabel.Text, ' Map ROI' ];
                                progressBar = uiprogressdlg(app.OMZer_VmCaLinked_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');

                                matrix = app.durationLinkedMapPlot.CData;
                            end

                            mapRegion = ~isnan(matrix); % 1 (or 0) - pixel has (does not have) signals
                            mapRegion = double(mapRegion);
                            mapRegion( mapRegion == 0 ) = NaN; % 1 (or NaN) - pixel has (does not have) signals

                            bgImageNomRGB = app.bgImageNomGroup{app.cameraSource};
                            mask = mapRegion;

                            fullSaveName = strcat(saveDir, '/', filename, '.mat'); % At this moment, 'fullSaveName' is a 'cell'.
                            fullSaveName = fullSaveName{1}; % Now 'fullSaveName' is a 'character
                            save(fullSaveName, 'mask', 'bgImageNomRGB');

                            close(progressBar)
                        end
                    end

                    figure(app.OMZer_VmCaLinked_UIFigure) % Set this uifigure at the top level
            end

        end

        % Image clicked function: Image_ExportFigure
        function Image_ExportFigureClicked(app, event)

            showImageIconAct(app, app.Image_ExportFigure)

            
        end

        % Selection changed function: 
        % AreaForStatisticsDeltaCamera1Camera2ButtonGroup
        function AreaForStatisticsDeltaCamera1Camera2ButtonGroupSelectionChanged(app, event)

            if ~isequal( app.MapListBox.Value, {} )

                % Load data
                checkMapID = str2double(app.MapListBox.Value);

                if isequal( app.ActDeltaLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Act map

                    LB = app.actLinkedMapData{1,5,checkMapID};
                    UB = app.actLinkedMapData{1,6,checkMapID};
                    matrix = app.actLinkedMapData{1,12,checkMapID};

                elseif isequal( app.RepDeltaLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Rep map

                    LB = app.repLinkedMapData{1,6,checkMapID};
                    UB = app.repLinkedMapData{1,7,checkMapID};
                    matrix = app.repLinkedMapData{1,13,checkMapID};

                elseif isequal( app.DurationDeltaLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Duration map

                    LB = app.durationLinkedMapData{1,6,checkMapID};
                    UB = app.durationLinkedMapData{1,7,checkMapID};
                    matrix = app.durationLinkedMapData{1,17,checkMapID};
                end
                
                updateStatisticResult(app, matrix, LB, UB)
            end

        end

        % Value changed function: MapModeDropDown
        function MapModeDropDownValueChanged(app, event)
           
            if isequal( app.MapModeDropDown.Value, 'Single')

                app.Image_MapMode.ImageSource = 'Icon Single.png';

            elseif isequal( app.MapModeDropDown.Value, 'Ensemble')

                app.Image_MapMode.ImageSource = 'Icon Multiple.png';
            end

        end

        % Image clicked function: Image_ROIDock
        function Image_ROIDockClicked(app, event)

            showImageIconAct(app, app.Image_ROIDock)

            msg = 'Choose what you want to do';
            msgTitle = 'Warning';
            optionItem = { 'Load a Saved ROI', 'Display the ROI in Dock', 'Cancel' };
            selection = uiconfirm( app.OMZer_VmCaLinked_UIFigure, msg, msgTitle, ...
                'Options', optionItem, ...
                'DefaultOption', 1, ...
                'CancelOption', 3 ); % uiconfirm returns the selection as a character vector

            switch selection

                case optionItem{1} % Load a Saved ROI

                    [file,path] = uigetfile('*.mat');

                    if ~ischar(file)

                        msg = 'Only choose one ".mat" file that has a ROI';
                        uialert(app.OMZer_VmCaLinked_UIFigure, msg,'Warning','Icon','error')

                        % Reset
                        app.dockMapCalculationROI = [];

                    else

                        msg = 'Loading the ROI';
                        progressBar = uiprogressdlg(app.OMZer_VmCaLinked_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');

                        maskData = load( [path,file] ); % 'maskData' is a structure
                        app.dockMapCalculationROI = maskData.mask;

                        close(progressBar)
                    end

                    figure(app.OMZer_VmCaLinked_UIFigure) % Set this uifigure at the top level

                case optionItem{2} % Display the ROI in Dock

                    if isempty(app.dockMapCalculationROI)

                        msg = 'You have not loaded a ROI into the dock' ;
                        uialert(app.OMZer_VmCaLinked_UIFigure, msg,'Warning','Icon','error')

                    else

                        figure
                        imagesc(app.dockMapCalculationROI)
                    end
            end

        end

        % Image clicked function: Image_RegionalMap
        function Image_RegionalMapClicked(app, event)

            showImageIconAct(app, app.Image_RegionalMap)

            % (1) Check maxMapNumber
            if ( isequal( app.ActDeltaLabel.FontColor, [ 1.00, 0.00, 0.00 ] )  &&  ( app.actLinkedSaveToID > app.maxMapNumber ) )  ||  ...
                    ( isequal( app.RepDeltaLabel.FontColor, [ 1.00, 0.00, 0.00 ] )  &&  ( app.repLinkedSaveToID > app.maxMapNumber ) )  ||  ...
                    ( isequal( app.DurationDeltaLabel.FontColor, [ 1.00, 0.00, 0.00 ] )  &&  ( app.durationLinkedSaveToID > app.maxMapNumber ) )

                msg = [ 'Up to ', num2str(app.maxMapNumber), ' map files can be stored. Delete old ones before new calculation' ];
                uialert(app.OMZer_VmCaLinked_UIFigure, msg,'Warning','Icon','error')

                % Reset
                app.callFromRegionalButton = 0;
                app.mapCalculationROI = [];
                return
            end

            % (2) Define the region of interets (ROI)
            msg = 'Choose a method to define the region of interet (ROI)';
            msgTitle = 'Warning';
            optionItem = {'Draw on the Background Image', 'Load a Saved ROI', 'Use ROI in the ROI Dock', 'Cancel'};
            cancelItem = 4;
            selection = uiconfirm( app.OMZer_VmCaLinked_UIFigure,msg,msgTitle, 'Options',optionItem, 'DefaultOption',1, 'CancelOption',cancelItem ); % When the user selects an option, uiconfirm returns their selection as a character vector.

            switch selection

                case optionItem{1} % Draw on the Background Image

                    screenSize = get( groot, 'ScreenSize' );
                    fObject = figure;
                    fObject.Position = [ 1, 20, 0.9*screenSize(4), 0.9*screenSize(4) ];

                    % Prepare Background Axis
                    axMap = axes( 'Parent', fObject );
                    axMap.OuterPosition = [ 0, 0, 1, 1 ];
                    axMap.Box = 'on';
                    axMap.YDir = 'Reverse';
                    axMap.XLim = [ 1, app.cmosDataColNum ];
                    axMap.YLim = [ 1, app.cmosDataRowNum ];
                    axMap.PlotBoxAspectRatio = [ 1, 1, 1 ];

                    % Plot Background
                    fp = imagesc( axMap, app.bgImageNomGroup{app.cameraSource} ); % 'fp - figure plot'

                    % Get ROI
                    roiObject = drawpolygon(axMap);

                    if ishandle(roiObject) == 0 % The user close the figure directly withou drawing any roi

                        msg = 'You have not truly set the region of interest (ROI). Draw a region and double click on it to set the ROI.';
                        uialert(app.OMZer_VmCaLinked_UIFigure, msg,'Warning','Icon','error')

                        % Reset
                        app.callFromRegionalButton = 0;
                        app.mapCalculationROI = [];
                        figure(app.OMZer_VmCaLinked_UIFigure) % Set this uifigure at the top level

                    else

                        wait(roiObject); % If the user draws a roi, he should double click on the roi to truly set it up
                        if ishandle(roiObject) == 0 % Although the user draws a roi, he does not double click on the roi to truly set it up

                            msg = 'You have not truly set the region of interest (ROI). Draw a region and double click on it to set the ROI.';
                            uialert(app.OMZer_VmCaLinked_UIFigure, msg,'Warning','Icon','error')

                            % Reset
                            app.callFromRegionalButton = 0;
                            app.mapCalculationROI = [];
                            figure(app.OMZer_VmCaLinked_UIFigure) % Set this uifigure at the top level

                        else

                            % The user has truly set a region of interest
                            app.mapCalculationROI = createMask(roiObject,fp);
                            if ishandle(fObject) == 1
                                close(fObject)
                            end
                            app.mapCalculationROI = double(app.mapCalculationROI);
                            app.mapCalculationROI(app.mapCalculationROI == 0) = NaN; %  1 (or NaN) - pixel inside (or outside) mask

                            figure(app.OMZer_VmCaLinked_UIFigure) % Set this uifigure at the top level

                            app.callFromRegionalButton = 1;
                            Image_GlobalMapClicked(app, event)
                        end
                    end

                case optionItem{2} % Load a Saved ROI

                    [file,path] = uigetfile('*.mat');

                    if ~ischar(file)

                        msg = 'Only choose one ".mat" file that has a ROI';
                        uialert(app.OMZer_VmCaLinked_UIFigure, msg,'Warning','Icon','error')

                        % Reset
                        app.callFromRegionalButton = 0;
                        app.mapCalculationROI = [];
                        figure(app.OMZer_VmCaLinked_UIFigure) % Set this uifigure at the top level

                    else

                        msg = 'Loading the ROI';
                        progressBar = uiprogressdlg(app.OMZer_VmCaLinked_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');

                        maskData = load( [path,file] ); % 'Data' is a structure
                        app.mapCalculationROI = maskData.mask;

                        figure(app.OMZer_VmCaLinked_UIFigure) % Set this uifigure at the top level
                        close(progressBar)

                        app.callFromRegionalButton = 1;
                        Image_GlobalMapClicked(app, event)
                    end

                case optionItem{3} % Use ROI in the ROI Dock

                    if isempty(app.dockMapCalculationROI)

                        msg = 'You have not loaded a ROI into the dock' ;
                        uialert(app.OMZer_VmCaLinked_UIFigure, msg,'Warning','Icon','error')

                        % Reset
                        app.callFromRegionalButton = 0;
                        app.mapCalculationROI = [];
                        figure(app.OMZer_VmCaLinked_UIFigure) % Set this uifigure at the top level

                    else

                        app.mapCalculationROI = app.dockMapCalculationROI;

                        figure(app.OMZer_VmCaLinked_UIFigure) % Set this uifigure at the top level

                        app.callFromRegionalButton = 1;
                        Image_GlobalMapClicked(app, event)
                    end
            end

        end

        % Image clicked function: Image_GlobalMap
        function Image_GlobalMapClicked(app, event)

            showImageIconAct(app, app.Image_GlobalMap)

            % (1) Check maxMapNumber
            if ( isequal( app.ActDeltaLabel.FontColor, [ 1.00, 0.00, 0.00 ] )  &&  ( app.actLinkedSaveToID > app.maxMapNumber ) )  ||  ...
                    ( isequal( app.RepDeltaLabel.FontColor, [ 1.00, 0.00, 0.00 ] )  &&  ( app.repLinkedSaveToID > app.maxMapNumber ) )  ||  ...
                    ( isequal( app.DurationDeltaLabel.FontColor, [ 1.00, 0.00, 0.00 ] )  &&  ( app.durationLinkedSaveToID > app.maxMapNumber ) )

                msg = [ 'Up to ', num2str(app.maxMapNumber), ' map files can be stored. Delete old ones before new calculation' ];
                uialert(app.OMZer_VmCaLinked_UIFigure, msg,'Warning','Icon','error')

                % Reset
                app.callFromRegionalButton = 0;
                app.mapCalculationROI = [];
                return
            end


            % (2) Check global or regional map calculation
            if app.callFromRegionalButton == 1
                areaLabel = 'Regional';
            else
                app.mapCalculationROI = ones( app.cmosDataRowNum, app.cmosDataColNum );
                areaLabel = 'Global';
            end


            % (3) Single map calculation
            if isequal( app.MapModeDropDown.Value, 'Single' )

                if isempty(app.oneBeatWindowStartIndex)  ||  isempty(app.oneBeatWindowEndIndex) % No single beat set up by the user yet

                    msg = 'Choose a single beat before new calculation';
                    uialert(app.OMZer_VmCaLinked_UIFigure, msg,'Warning','Icon','error')

                    Image_SignalClicked(app, 'clicked')

                else

                    if isequal( app.ActDeltaLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Act map

                        msg = [ 'Calculate the ', areaLabel, ' ', app.MapModeDropDown.Value, ' ', app.ActDeltaLabel.Text, ' Map' ];
                        progressBar = uiprogressdlg(app.OMZer_VmCaLinked_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');

                        [ dataSectionTime, ...
                            dataSectionCamera1, ...
                            dataSectionCamera2, ...
                            locsActCamera1, ...
                            locsActCamera2, ...
                            actDeltaMatrix ] = calculateActDelta( app.acqFreq, app.cmosDataGroup{1}, app.cmosDataGroup{2}, app.oneBeatWindowStartIndex, app.oneBeatWindowEndIndex, app.mapCalculationROI, app.actDefinition);

                        pksNum = [];
                        mapUserComment = [ 'Activation definition - ', app.actDefinition ];

                        saveActLinkedMap(app, app.oneBeatWindowStartIndex, app.oneBeatWindowEndIndex, areaLabel, app.MapModeDropDown.Value, dataSectionTime, dataSectionCamera1, dataSectionCamera2, locsActCamera1, locsActCamera2, actDeltaMatrix, pksNum, mapUserComment)
                        app.actLinkedSaveToID = app.actLinkedSaveToID + 1;
                        prepareMapCalculation(app, app.Image_ActDelta)

                        close(progressBar)

                    elseif isequal( app.RepDeltaLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Rep map

                        msg = [ 'Calculate the ', areaLabel, ' ', app.MapModeDropDown.Value, ' ', app.RepDeltaLabel.Text, ' Map' ];
                        progressBar = uiprogressdlg(app.OMZer_VmCaLinked_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');

                        [ dataSectionTime, ...
                            dataSectionCamera1, ...
                            dataSectionCamera2, ...
                            locsRepCamera1, ...
                            locsRepCamera2, ...
                            repDeltaMatrix ] = calculateRepDelta( app.acqFreq, app.cmosDataGroup{1}, app.cmosDataGroup{2}, app.oneBeatWindowStartIndex, app.oneBeatWindowEndIndex, app.mapCalculationROI, (str2double(app.RepDropDown.Value)/100) );

                        pksNum = [];
                        mapUserComment = '';

                        saveRepLinkedMap(app, app.oneBeatWindowStartIndex, app.oneBeatWindowEndIndex, areaLabel, app.MapModeDropDown.Value, dataSectionTime, dataSectionCamera1, dataSectionCamera2, locsRepCamera1, locsRepCamera2, repDeltaMatrix, pksNum, mapUserComment, (str2double(app.RepDropDown.Value)/100))
                        app.repLinkedSaveToID = app.repLinkedSaveToID + 1;
                        prepareMapCalculation(app, app.Image_RepDelta)

                        close(progressBar)

                    elseif isequal( app.DurationDeltaLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Duration map

                        msg = [ 'Calculate the ', areaLabel, ' ', app.MapModeDropDown.Value, ' ', app.DurationDeltaLabel.Text, ' Map' ];
                        progressBar = uiprogressdlg(app.OMZer_VmCaLinked_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');

                        [ dataSectionTime, ...
                            dataSectionCamera1, ...
                            dataSectionCamera2, ...
                            locsActCamera1, ...
                            locsActCamera2, ...
                            locsRepCamera1, ...
                            locsRepCamera2, ...
                            durationMatrixCamera1, ...
                            durationMatrixCamera2, ...
                            durationDeltaMatrix ] = calculateDurationDelta( app.acqFreq, app.cmosDataGroup{1}, app.cmosDataGroup{2}, app.oneBeatWindowStartIndex, app.oneBeatWindowEndIndex, app.mapCalculationROI, (str2double(app.DurationDropDown.Value)/100), app.actDefinition );

                        pksNum = [];
                        mapUserComment = [ 'Activation definition - ', app.actDefinition ];

                        saveDurationLinkedMap(app, app.oneBeatWindowStartIndex, app.oneBeatWindowEndIndex, areaLabel, app.MapModeDropDown.Value, dataSectionTime, dataSectionCamera1, dataSectionCamera2, locsActCamera1, locsActCamera2, locsRepCamera1, locsRepCamera2, durationMatrixCamera1, durationMatrixCamera2, durationDeltaMatrix, pksNum, mapUserComment, (str2double(app.DurationDropDown.Value)/100))
                        app.durationLinkedSaveToID = app.durationLinkedSaveToID + 1;
                        prepareMapCalculation(app, app.Image_DurationDelta)

                        close(progressBar)
                    end
                end

            end


            % (4) Ensemble map calculation
            if isequal( app.MapModeDropDown.Value, 'Ensemble' )

                msg = 'Choose the method to generate ensemble signals';
                msgTitle = 'Warning';
                optionItem = { 'One Beat & Pacing', 'One Beat & Peak Detection', 'Multi Beats', 'Cancel' };
                selection = uiconfirm( app.OMZer_VmCaLinked_UIFigure, msg, msgTitle, ...
                    'Options', optionItem, ...
                    'DefaultOption', 1, ...
                    'CancelOption', 4 ); % uiconfirm returns the selection as a character vector

                % (4.1) Choose different ensemble methods
                switch selection

                    case optionItem{1} % One Beat & Pacing

                        if isempty(app.oneBeatWindowStartIndex)  ||  isempty(app.oneBeatWindowEndIndex) % No single beat set up by the user yet

                            msg = 'Choose a single beat before new calculation';
                            uialert(app.OMZer_VmCaLinked_UIFigure, msg,'Warning','Icon','error')
                            Image_SignalClicked(app, 'clicked')
                            selection = 'Cancel';

                        else

                            if isequal( app.pacing1Enable, 'off' )  &&  isequal( app.pacing2Enable, 'off' )

                                msg = 'There are no pacing signals at all';
                                uialert(app.OMZer_VmCaLinked_UIFigure, msg,'Warning','Icon','error')
                                selection = 'Cancel';

                            elseif isequal( app.pacing1Enable, 'on' )  &&  isequal( app.pacing2Enable, 'on' )

                                msg = 'Choose the pacing signals';
                                msgTitle = 'Warning';
                                optionItem = { 'One Beat & Pacing 1', 'One Beat & Pacing 2', 'Cancel' };
                                selection = uiconfirm( app.OMZer_VmCaLinked_UIFigure, msg, msgTitle, ...
                                    'Options', optionItem, ...
                                    'DefaultOption', 1, ...
                                    'CancelOption', 3);

                            elseif isequal( app.pacing1Enable, 'on' )  &&  isequal( app.pacing2Enable, 'off' )

                                selection = 'One Beat & Pacing 1';

                            elseif isequal( app.pacing1Enable, 'off' )  &&  isequal( app.pacing2Enable, 'on' )

                                selection = 'One Beat & Pacing 2';
                            end
                        end

                    case optionItem{2} % One Beat & Peak Detection

                        if isempty(app.oneBeatWindowStartIndex)  ||  isempty(app.oneBeatWindowEndIndex) % No single beat set up by the user yet

                            msg = 'Choose a single beat before new calculation';
                            uialert(app.OMZer_VmCaLinked_UIFigure, msg,'Warning','Icon','error')
                            Image_SignalClicked(app, 'clicked')
                            selection = 'Cancel';

                        else
                            selection = 'One Beat & Peak Detection';
                        end

                    case optionItem{3} % Multi Beats

                        if isempty(app.multiBeatsWindowStartIndex)  ||  isempty(app.multiBeatsWindowEndIndex) % No multi beats set up by the user yet

                            msg = 'Choose multi beats before new calculation';
                            uialert(app.OMZer_VmCaLinked_UIFigure, msg,'Warning','Icon','error')
                            Image_SignalClicked(app, 'clicked')
                            selection = 'Cancel';

                        else
                            selection = 'Multi Beats';
                        end
                end

                % Start calculation
                if ~isequal( selection, 'Cancel') % selection = 'One Beat & Pacing 1' or 'One Beat & Pacing 2' or 'One Beat & Peak Detection' or 'Multi Beats'

                    msg = 'Generate Ensemble Signals';
                    progressBar = uiprogressdlg(app.OMZer_VmCaLinked_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');

                    % (4.2) Preparation before generating ensemble signals
                    if isequal( selection, 'One Beat & Pacing 1' )

                        [ anchorIndeCamera1, beatStart_to_anchor_lengthCamera1, beatEnd_to_anchor_lengthCamera1, specialOneBeatWindowStartIndexCamera1, specialOneBeatWindowEndIndexCamera1 ] = calculateAnchorIndex( selection, app.pacing1, app.signalTime, app.oneBeatWindowStartIndex, app.oneBeatWindowEndIndex );
                        [ anchorIndeCamera2, beatStart_to_anchor_lengthCamera2, beatEnd_to_anchor_lengthCamera2, specialOneBeatWindowStartIndexCamera2, specialOneBeatWindowEndIndexCamera2 ] = calculateAnchorIndex( selection, app.pacing1, app.signalTime, app.oneBeatWindowStartIndex, app.oneBeatWindowEndIndex );
                        
                    elseif isequal( selection, 'One Beat & Pacing 2' )

                        [ anchorIndeCamera1, beatStart_to_anchor_lengthCamera1, beatEnd_to_anchor_lengthCamera1, specialOneBeatWindowStartIndexCamera1, specialOneBeatWindowEndIndexCamera1 ] = calculateAnchorIndex( selection, app.pacing2, app.signalTime, app.oneBeatWindowStartIndex, app.oneBeatWindowEndIndex );
                        [ anchorIndeCamera2, beatStart_to_anchor_lengthCamera2, beatEnd_to_anchor_lengthCamera2, specialOneBeatWindowStartIndexCamera2, specialOneBeatWindowEndIndexCamera2 ] = calculateAnchorIndex( selection, app.pacing2, app.signalTime, app.oneBeatWindowStartIndex, app.oneBeatWindowEndIndex );

                    elseif isequal( selection, 'One Beat & Peak Detection' )

                        row = app.markerXY(1,2); % Y
                        col = app.markerXY(1,1); % X
                        [ anchorIndeCamera1, beatStart_to_anchor_lengthCamera1, beatEnd_to_anchor_lengthCamera1, specialOneBeatWindowStartIndexCamera1, specialOneBeatWindowEndIndexCamera1 ] = calculateAnchorIndex( selection, str2double(app.MinPeakHeightDropDown.Value), app.cmosDataGroup{1}, row, col, app.signalTime, app.oneBeatWindowStartIndex, app.oneBeatWindowEndIndex );
                        [ anchorIndeCamera2, beatStart_to_anchor_lengthCamera2, beatEnd_to_anchor_lengthCamera2, specialOneBeatWindowStartIndexCamera2, specialOneBeatWindowEndIndexCamera2 ] = calculateAnchorIndex( selection, str2double(app.MinPeakHeightDropDown.Value), app.cmosDataGroup{2}, row, col, app.signalTime, app.oneBeatWindowStartIndex, app.oneBeatWindowEndIndex );

                    elseif isequal( selection, 'Multi Beats' )

                        row = app.markerXY(1,2); % Y
                        col = app.markerXY(1,1); % X
                        [ anchorIndeCamera1, beatStart_to_anchor_lengthCamera1, beatEnd_to_anchor_lengthCamera1, specialOneBeatWindowStartIndexCamera1, specialOneBeatWindowEndIndexCamera1 ] = calculateAnchorIndex( selection, app.cmosDataGroup{1}, row, col, app.signalTime, app.multiBeatsWindowStartIndex, app.multiBeatsWindowEndIndex );
                        [ anchorIndeCamera2, beatStart_to_anchor_lengthCamera2, beatEnd_to_anchor_lengthCamera2, specialOneBeatWindowStartIndexCamera2, specialOneBeatWindowEndIndexCamera2 ] = calculateAnchorIndex( selection, app.cmosDataGroup{2}, row, col, app.signalTime, app.multiBeatsWindowStartIndex, app.multiBeatsWindowEndIndex );
                    end

                    % (4.3) Generate ensemble signals
                    if ( prod(~isnan( anchorIndeCamera1 )) == 1 )  &&  ( prod(~isnan( anchorIndeCamera2 )) == 1 )

                        [ ensembleSignalCamera1, ensembleSignalLengthCamera1, pksIndexCamera1, pksNumberCamera1 ] = calculateEnsemble( app.cmosDataGroup{1}, anchorIndeCamera1, beatStart_to_anchor_lengthCamera1, beatEnd_to_anchor_lengthCamera1, specialOneBeatWindowStartIndexCamera1, specialOneBeatWindowEndIndexCamera1, str2double(app.MinPeakHeightDropDown.Value) );
                        [ ensembleSignalCamera2, ensembleSignalLengthCamera2, pksIndexCamera2, pksNumberCamera2 ] = calculateEnsemble( app.cmosDataGroup{2}, anchorIndeCamera2, beatStart_to_anchor_lengthCamera2, beatEnd_to_anchor_lengthCamera2, specialOneBeatWindowStartIndexCamera2, specialOneBeatWindowEndIndexCamera2, str2double(app.MinPeakHeightDropDown.Value) );

                    elseif ( prod(~isnan( anchorIndeCamera1 )) == 0 )  &&  ( prod(~isnan( anchorIndeCamera2 )) == 1 )
                        
                        ensembleSignalCamera1 = NaN;
                        ensembleSignalCamera2 = NaN;
                        msg = 'All the cameras must have ensemble signals in order to continue map calculation, but no anchor index can be found to generate ensemble signals for camera 1';
                        uialert(app.OMZer_VmCaLinked_UIFigure, msg,'Warning','Icon','error')
                        
                    elseif ( prod(~isnan( anchorIndeCamera1 )) == 1 )  &&  ( prod(~isnan( anchorIndeCamera2 )) == 0 )
                        
                        ensembleSignalCamera1 = NaN;
                        ensembleSignalCamera2 = NaN;
                        msg = 'All the cameras must have ensemble signals in order to continue map calculation, but no anchor index can be found to generate ensemble signals for camera 2';
                        uialert(app.OMZer_VmCaLinked_UIFigure, msg,'Warning','Icon','error')
                        
                    else

                        ensembleSignalCamera1 = NaN;
                        ensembleSignalCamera2 = NaN;
                        msg = 'All the cameras must have ensemble signals in order to continue map calculation, but no anchor index can be found to generate ensemble signals for camera 1 and camera 2';
                        uialert(app.OMZer_VmCaLinked_UIFigure, msg,'Warning','Icon','error')
                    end
                    close(progressBar)

                    % (4.4) Map calculation
                    if ( sum( ~isnan(ensembleSignalCamera1(:)) ) >= 1 )  &&  ( sum( ~isnan(ensembleSignalCamera2(:)) ) >= 1 )  &&  ( ensembleSignalLengthCamera1 == ensembleSignalLengthCamera2 )

                        ensembleSignalLength = ensembleSignalLengthCamera1;
                        
                        if isequal( app.ActDeltaLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Act map

                            msg = [ 'Calculate the ', areaLabel, ' ', app.MapModeDropDown.Value, ' ', app.ActDeltaLabel.Text, ' Map' ];
                            progressBar = uiprogressdlg(app.OMZer_VmCaLinked_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');

                            [ dataSectionTime, ...
                            dataSectionCamera1, ...
                            dataSectionCamera2, ...
                            locsActCamera1, ...
                            locsActCamera2, ...
                            actDeltaMatrix ] = calculateActDelta( app.acqFreq, ensembleSignalCamera1, ensembleSignalCamera2, 1, ensembleSignalLength, app.mapCalculationROI, app.actDefinition);

                            pksNum = [];
                            mapUserComment = { [ 'Activation definition - ', app.actDefinition ]; [ 'Ensemble method - ', selection ] };

                            if isequal( selection, 'Multi Beats' )

                                storeBeatWindowStartIndex = app.multiBeatsWindowStartIndex;
                                storeBeatWindowEndIndex = app.multiBeatsWindowEndIndex;
                                mapUserComment = generateMultiBeatsMapUserComment(app, mapUserComment, app.signalTime(storeBeatWindowStartIndex), app.signalTime(storeBeatWindowEndIndex));

                            else

                                storeBeatWindowStartIndex = app.oneBeatWindowStartIndex;
                                storeBeatWindowEndIndex = app.oneBeatWindowEndIndex;
                            end

                            saveActLinkedMap(app, storeBeatWindowStartIndex, storeBeatWindowEndIndex, areaLabel, app.MapModeDropDown.Value, dataSectionTime, dataSectionCamera1, dataSectionCamera2, locsActCamera1, locsActCamera2, actDeltaMatrix, pksNum, mapUserComment)
                            app.actLinkedSaveToID = app.actLinkedSaveToID + 1;
                            prepareMapCalculation(app, app.Image_ActDelta)

                            close(progressBar)

                        elseif isequal( app.RepDeltaLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Rep map

                            msg = [ 'Calculate the ', areaLabel, ' ', app.MapModeDropDown.Value, ' ', app.RepDeltaLabel.Text, ' Map' ];
                            progressBar = uiprogressdlg(app.OMZer_VmCaLinked_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');

                            [ dataSectionTime, ...
                            dataSectionCamera1, ...
                            dataSectionCamera2, ...
                            locsRepCamera1, ...
                            locsRepCamera2, ...
                            repDeltaMatrix ] = calculateRepDelta( app.acqFreq, ensembleSignalCamera1, ensembleSignalCamera2, 1, ensembleSignalLength, app.mapCalculationROI, (str2double(app.RepDropDown.Value)/100) );

                            pksNum = [];
                            mapUserComment = { [ 'Ensemble method - ', selection ] };

                            if isequal( selection, 'Multi Beats' )

                                storeBeatWindowStartIndex = app.multiBeatsWindowStartIndex;
                                storeBeatWindowEndIndex = app.multiBeatsWindowEndIndex;
                                mapUserComment = generateMultiBeatsMapUserComment(app, mapUserComment, app.signalTime(storeBeatWindowStartIndex), app.signalTime(storeBeatWindowEndIndex));

                            else

                                storeBeatWindowStartIndex = app.oneBeatWindowStartIndex;
                                storeBeatWindowEndIndex = app.oneBeatWindowEndIndex;
                            end

                            saveRepLinkedMap(app, storeBeatWindowStartIndex, storeBeatWindowEndIndex, areaLabel, app.MapModeDropDown.Value, dataSectionTime, dataSectionCamera1, dataSectionCamera2, locsRepCamera1, locsRepCamera2, repDeltaMatrix, pksNum, mapUserComment, (str2double(app.RepDropDown.Value)/100))
                            app.repLinkedSaveToID = app.repLinkedSaveToID + 1;
                            prepareMapCalculation(app, app.Image_RepDelta)

                            close(progressBar)

                        elseif isequal( app.DurationDeltaLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Duration map

                            msg = [ 'Calculate the ', areaLabel, ' ', app.MapModeDropDown.Value, ' ', app.DurationDeltaLabel.Text, ' Map' ];
                            progressBar = uiprogressdlg(app.OMZer_VmCaLinked_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');

                            [ dataSectionTime, ...
                                dataSectionCamera1, ...
                                dataSectionCamera2, ...
                                locsActCamera1, ...
                                locsActCamera2, ...
                                locsRepCamera1, ...
                                locsRepCamera2, ...
                                durationMatrixCamera1, ...
                                durationMatrixCamera2, ...
                                durationDeltaMatrix ] = calculateDurationDelta( app.acqFreq, ensembleSignalCamera1, ensembleSignalCamera2, 1, ensembleSignalLength, app.mapCalculationROI, (str2double(app.DurationDropDown.Value)/100), app.actDefinition );

                            pksNum = [];
                            mapUserComment = { [ 'Activation definition - ', app.actDefinition ]; [ 'Ensemble method - ', selection ] };

                            if isequal( selection, 'Multi Beats' )

                                storeBeatWindowStartIndex = app.multiBeatsWindowStartIndex;
                                storeBeatWindowEndIndex = app.multiBeatsWindowEndIndex;
                                mapUserComment = generateMultiBeatsMapUserComment(app, mapUserComment, app.signalTime(storeBeatWindowStartIndex), app.signalTime(storeBeatWindowEndIndex));

                            else

                                storeBeatWindowStartIndex = app.oneBeatWindowStartIndex;
                                storeBeatWindowEndIndex = app.oneBeatWindowEndIndex;
                            end

                            saveDurationLinkedMap(app, storeBeatWindowStartIndex, storeBeatWindowEndIndex, areaLabel, app.MapModeDropDown.Value, dataSectionTime, dataSectionCamera1, dataSectionCamera2, locsActCamera1, locsActCamera2, locsRepCamera1, locsRepCamera2, durationMatrixCamera1, durationMatrixCamera2, durationDeltaMatrix, pksNum, mapUserComment, (str2double(app.DurationDropDown.Value)/100))
                            app.durationLinkedSaveToID = app.durationLinkedSaveToID + 1;
                            prepareMapCalculation(app, app.Image_DurationDelta)

                            close(progressBar)
                        end
                    end

                end
            end


            % (5) Reset
            app.callFromRegionalButton = 0;
            app.mapCalculationROI = [];

            figure(app.OMZer_VmCaLinked_UIFigure) % Set this uifigure at the top level

        end

        % Value changed function: LBmsSpinner
        function LBmsSpinnerValueChanged(app, event)
            
            checkMapID = str2double(app.MapListBox.Value);

            % (1) Initial bound check
            if app.LBmsSpinner.Value >= app.UBmsSpinner.Value

                msg = 'Lower bound value should be smaller than upper bound value';
                uialert(app.OMZer_VmCaLinked_UIFigure, msg,'Warning','Icon','error')

                app.LBmsSpinner.Value = min(app.LBmsSpinner.Limits);
                app.UBmsSpinner.Value = max(app.UBmsSpinner.Limits);
            end

            % (2) Update data
            if isequal( app.ActDeltaLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Act map

                matrix = app.actLinkedMapData{1,12,checkMapID};
                checkLBUBAdjustment(app, matrix)
                app.actLinkedMapData{1,5,checkMapID} = app.LBmsSpinner.Value;
                app.actLinkedMapData{1,6,checkMapID} = app.UBmsSpinner.Value;

            elseif isequal( app.RepDeltaLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Rep map

                matrix = app.repLinkedMapData{1,13,checkMapID};
                checkLBUBAdjustment(app, matrix)
                app.repLinkedMapData{1,6,checkMapID} = app.LBmsSpinner.Value;
                app.repLinkedMapData{1,7,checkMapID} = app.UBmsSpinner.Value;

            elseif isequal( app.DurationDeltaLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Duration map

                matrix = app.durationLinkedMapData{1,17,checkMapID};
                checkLBUBAdjustment(app, matrix)
                app.durationLinkedMapData{1,6,checkMapID} = app.LBmsSpinner.Value;
                app.durationLinkedMapData{1,7,checkMapID} = app.UBmsSpinner.Value;
            end

            % (3) Update map plot
            MapListBoxValueChanged(app, event)

        end

        % Value changing function: LBmsSpinner
        function LBmsSpinnerValueChanging(app, event)
            
            if ischar(event.Value)
                app.LBmsSpinner.Value = str2double(event.Value);
            else
                app.LBmsSpinner.Value = event.Value;
            end

            LBmsSpinnerValueChanged(app, event)

        end

        % Value changed function: UBmsSpinner
        function UBmsSpinnerValueChanged(app, event)
            
            checkMapID = str2double(app.MapListBox.Value);

            % (1) Initial bound check
            if app.LBmsSpinner.Value >= app.UBmsSpinner.Value

                msg = 'Lower bound value should be smaller than upper bound value';
                uialert(app.OMZer_VmCaLinked_UIFigure, msg,'Warning','Icon','error')

                app.LBmsSpinner.Value = min(app.LBmsSpinner.Limits);
                app.UBmsSpinner.Value = max(app.UBmsSpinner.Limits);
            end

            % (2) Update data
            if isequal( app.ActDeltaLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Act map

                matrix = app.actLinkedMapData{1,12,checkMapID};
                checkLBUBAdjustment(app, matrix)
                app.actLinkedMapData{1,5,checkMapID} = app.LBmsSpinner.Value;
                app.actLinkedMapData{1,6,checkMapID} = app.UBmsSpinner.Value;

            elseif isequal( app.RepDeltaLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Rep map

                matrix = app.repLinkedMapData{1,13,checkMapID};
                checkLBUBAdjustment(app, matrix)
                app.repLinkedMapData{1,6,checkMapID} = app.LBmsSpinner.Value;
                app.repLinkedMapData{1,7,checkMapID} = app.UBmsSpinner.Value;

            elseif isequal( app.DurationDeltaLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Duration map

                matrix = app.durationLinkedMapData{1,15,checkMapID};
                checkLBUBAdjustment(app, matrix)
                app.durationLinkedMapData{1,6,checkMapID} = app.LBmsSpinner.Value;
                app.durationLinkedMapData{1,7,checkMapID} = app.UBmsSpinner.Value;
            end

            % (3) Update map plot
            MapListBoxValueChanged(app, event)

        end

        % Value changing function: UBmsSpinner
        function UBmsSpinnerValueChanging(app, event)
            
            if ischar(event.Value)
                app.UBmsSpinner.Value = str2double(event.Value);
            else
                app.UBmsSpinner.Value = event.Value;
            end

            UBmsSpinnerValueChanged(app, event);

        end

        % Value changed function: MapListBox
        function MapListBoxValueChanged(app, event)
            
            % (1) Update ribbon menu and calculation components
            app.Image_ExportData.Enable = 'on';
            app.Image_ExportFigure.Enable = 'on';

            app.Image_RegionalMap.Enable = 'off';
            app.Image_GlobalMap.Enable = 'off';

            % (2) Unload the previous map
            % (2.1) Reset statistic result
            resetStatisticResult(app)
            app.LBmsSpinner.Enable = 'on';
            app.UBmsSpinner.Enable = 'on';

            % (2.2) Reset signal window information
            app.WinStartsecEditField.Value = Inf;
            app.WinEndsecEditField.Value = Inf;

            % (2.3) Clear displayed map and signal plot
            app.MapTitleLabel.Text = 'Map Title';
            cla(app.MapAxis)
            cla(app.colorBarAxes)
            cla(app.MapHistAxis)
            imagesc( app.MapAxis, app.bgImageNomGroup{app.cameraSource} );

            % (2.4) Clear check signal
            if ~isempty(app.checkSigMarkerPlot)

                clearCheckSigPlot(app)

                app.CheckSigX_scrn.Value = 1;
                app.CheckSigY_scrn.Value = 1;
                app.CheckSigX_scrn.Editable = 'off';
                app.CheckSigY_scrn.Editable = 'off';
                app.CheckSigX_scrn.BackgroundColor = [1.00, 1.00, 1.00]; % White
                app.CheckSigY_scrn.BackgroundColor = [1.00, 1.00, 1.00];
            end

            % (2.5) Clear map comment
            app.UserCommentTextArea.Value = {''};

            % (3) Load new map data
            % (3.1) Enable map display components
            app.Image_ResetBound.Enable = 'on';
            app.Image_UnloadMap.Enable = 'on';
            app.Image_DeleteMap.Enable = 'on';

            app.Image_DisplayPixel.Enable = 'on';
            app.Image_ClearPixel.Enable = 'on';

            app.UserCommentTextArea.Enable = 'on';

            % (3.2) Load data
            checkMapID = str2double(app.MapListBox.Value);
            
            if isequal( app.ActDeltaLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Act map

                clearActLinkedMapPlot(app)

                thisWinStartIndex = app.actLinkedMapData{1,1,checkMapID};
                thisWindEndIndex = app.actLinkedMapData{1,2,checkMapID};

                AreaMode = app.actLinkedMapData{1,3,checkMapID};
                APMode = app.actLinkedMapData{1,4,checkMapID};

                LB = app.actLinkedMapData{1,5,checkMapID};
                UB = app.actLinkedMapData{1,6,checkMapID};             
                matrix = app.actLinkedMapData{1,12,checkMapID};

                pksNum = app.actLinkedMapData{1,13,checkMapID};

                if ~isempty( app.actLinkedMapData{1,14,checkMapID} )
                    app.UserCommentTextArea.Value = app.actLinkedMapData{1,14,checkMapID};
                end

                app.MapTitleLabel.Text = [ AreaMode, ' ', APMode, ' Activation Delta (Camera 1 - Camera 2) Map' ];

                app.actLinkedMapPlot = plotMap(app, app.actLinkedMapPlot, matrix, LB, UB);
                app.actLinkedColorBarPlot = plotMapColorBar(app, app.actLinkedColorBarPlot, matrix, LB, UB);
                app.actLinkedHistPlot = plotMapHist(app, app.actLinkedHistPlot, matrix, LB, UB);

                updateStatisticResult(app, matrix, LB, UB)
                updateSignalWindowInformation(app, thisWinStartIndex, thisWindEndIndex)

                % if ~isempty(pksNum)
                %     plotEnsemblePksNum(app, app.ActEnsemblePksNumPlot, pksNum)
                % end

                % For export data
                app.exportMapName = app.ActDeltaLabel.Text;
                app.exportMapDataTitle = app.actLinkedMapDataTitle;
                app.exportMapData = app.actLinkedMapData(:, :, checkMapID);

            elseif isequal( app.RepDeltaLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Rep map

                clearRepLinkedMapPlot(app)

                thisWinStartIndex = app.repLinkedMapData{1,1,checkMapID};
                thisWindEndIndex = app.repLinkedMapData{1,2,checkMapID};

                AreaMode = app.repLinkedMapData{1,3,checkMapID};
                APMode = app.repLinkedMapData{1,4,checkMapID};
                RepLevel = app.repLinkedMapData{1,5,checkMapID};

                LB = app.repLinkedMapData{1,6,checkMapID};
                UB = app.repLinkedMapData{1,7,checkMapID};
                matrix = app.repLinkedMapData{1,13,checkMapID};

                pksNum = app.repLinkedMapData{1,14,checkMapID};

                if ~isempty( app.repLinkedMapData{1,15,checkMapID} )
                    app.UserCommentTextArea.Value = app.repLinkedMapData{1,15,checkMapID};
                end

                app.MapTitleLabel.Text = [ AreaMode, ' ', APMode, ' Repolarisation ', num2str(RepLevel*100), ' Delta (Camera 1 - Camera 2) Map' ];

                app.repLinkedMapPlot = plotMap(app, app.repLinkedMapPlot, matrix, LB, UB);
                app.repLinkedColorBarPlot = plotMapColorBar(app, app.repLinkedColorBarPlot, matrix, LB, UB);
                app.repLinkedHistPlot = plotMapHist(app, app.repLinkedHistPlot, matrix, LB, UB);

                updateStatisticResult(app, matrix, LB, UB)
                updateSignalWindowInformation(app, thisWinStartIndex, thisWindEndIndex)

                % if ~isempty(pksNum)
                %     plotEnsemblePksNum(app, app.RepEnsemblePksNumPlot, pksNum)
                % end

                % For export data
                app.exportMapName = app.RepDeltaLabel.Text;
                app.exportMapDataTitle = app.repLinkedMapDataTitle;
                app.exportMapData = app.repLinkedMapData(:, :, checkMapID);

            elseif isequal( app.DurationDeltaLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Duration map

                clearDurationLinkedMapPlot(app)

                thisWinStartIndex = app.durationLinkedMapData{1,1,checkMapID};
                thisWindEndIndex = app.durationLinkedMapData{1,2,checkMapID};

                AreaMode = app.durationLinkedMapData{1,3,checkMapID};
                APMode = app.durationLinkedMapData{1,4,checkMapID};

                durationLevel = app.durationLinkedMapData{1,5,checkMapID};

                LB = app.durationLinkedMapData{1,6,checkMapID};
                UB = app.durationLinkedMapData{1,7,checkMapID};
                matrix = app.durationLinkedMapData{1,17,checkMapID};

                pksNum = app.durationLinkedMapData{1,18,checkMapID};

                if ~isempty( app.durationLinkedMapData{1,19,checkMapID} )
                    app.UserCommentTextArea.Value = app.durationLinkedMapData{1,19,checkMapID};
                end

                app.MapTitleLabel.Text = [ AreaMode, ' ', APMode, ' Duration ', num2str(durationLevel*100), ' Delta (Camera 1 - Camera 2) Map' ];

                app.durationLinkedMapPlot = plotMap(app, app.durationLinkedMapPlot, matrix, LB, UB);
                app.durationLinkedColorBarPlot = plotMapColorBar(app, app.durationLinkedColorBarPlot, matrix, LB, UB);
                app.durationLinkedHistPlot = plotMapHist(app, app.durationLinkedHistPlot, matrix, LB, UB);

                updateStatisticResult(app, matrix, LB, UB)
                updateSignalWindowInformation(app, thisWinStartIndex, thisWindEndIndex)

                % if ~isempty(pksNum)
                %     plotEnsemblePksNum(app, app.APDEnsemblePksNumPlot, pksNum)
                % end

                % For export data
                app.exportMapName = app.DurationDeltaLabel.Text;
                app.exportMapDataTitle = app.durationLinkedMapDataTitle;
                app.exportMapData = app.durationLinkedMapData(:, :, checkMapID);
            end
            
        end

        % Image clicked function: Image_ResetBound
        function Image_ResetBoundClicked(app, event)

            showImageIconAct(app, app.Image_ResetBound)

            checkMapID = str2double(app.MapListBox.Value);

            if isequal( app.ActDeltaLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Act map

                matrix = app.actLinkedMapData{1,12,checkMapID};

                app.actLinkedMapData{1,5,checkMapID} = nanmin( matrix(:) );
                app.actLinkedMapData{1,6,checkMapID} = nanmax( matrix(:) );

            elseif isequal( app.RepDeltaLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Rep map

                matrix = app.repLinkedMapData{1,13,checkMapID};

                app.repLinkedMapData{1,6,checkMapID} = nanmin( matrix(:) );
                app.repLinkedMapData{1,7,checkMapID} = nanmax( matrix(:) );

            elseif isequal( app.DurationDeltaLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Duration map

                matrix = app.durationLinkedMapData{1,17,checkMapID};

                app.durationLinkedMapData{1,6,checkMapID} = nanmin( matrix(:) );
                app.durationLinkedMapData{1,7,checkMapID} = nanmax( matrix(:) );
            end

            MapListBoxValueChanged(app, event)

        end

        % Image clicked function: Image_UnloadMap
        function Image_UnloadMapClicked(app, event)

            showImageIconAct(app, app.Image_UnloadMap)

            % (1) Update ribbon menu and calculation components
            app.Image_ExportData.Enable = 'off';
            app.Image_ExportFigure.Enable = 'off';

            app.Image_RegionalMap.Enable = 'on';
            app.Image_GlobalMap.Enable = 'on';

            % (2) Unload the previous map
            % (2.1) Reset statistic result
            resetStatisticResult(app)
            app.LBmsSpinner.Enable = 'off';
            app.UBmsSpinner.Enable = 'off';

            % (2.2) Reset signal window information
            app.WinStartsecEditField.Value = Inf;
            app.WinEndsecEditField.Value = Inf;

            % (2.3) Clear displayed map and signal plot
            app.MapTitleLabel.Text = 'Map Title';
            cla(app.MapAxis)
            cla(app.colorBarAxes)
            cla(app.MapHistAxis)
            imagesc( app.MapAxis, app.bgImageNomGroup{app.cameraSource} );

            % (2.4) Clear check signal
            if ~isempty(app.checkSigMarkerPlot)

                clearCheckSigPlot(app)

                app.CheckSigX_scrn.Value = 1;
                app.CheckSigY_scrn.Value = 1;
                app.CheckSigX_scrn.Editable = 'off';
                app.CheckSigY_scrn.Editable = 'off';
                app.CheckSigX_scrn.BackgroundColor = [1.00, 1.00, 1.00]; % White
                app.CheckSigY_scrn.BackgroundColor = [1.00, 1.00, 1.00];
            end

            % (3) Disable map display components
            app.Image_ResetBound.Enable = 'off';
            app.Image_UnloadMap.Enable = 'off';
            app.Image_DeleteMap.Enable = 'off';

            app.Image_DisplayPixel.Enable = 'off';
            app.Image_ClearPixel.Enable = 'off';

            app.UserCommentTextArea.Enable = 'off';
            app.UserCommentTextArea.Value = {''};

            % (4) Uncheck the MapListBox
            app.MapListBox.Value = {};

            % (5) For export data
            app.exportMapName = [];
            app.exportMapDataTitle = [];
            app.exportMapData = [];

        end

        % Image clicked function: Image_DeleteMap
        function Image_DeleteMapClicked(app, event)

            showImageIconAct(app, app.Image_DeleteMap)

            checkMapID = str2double(app.MapListBox.Value);

            if isequal( app.ActDeltaLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Act map

                % (1) Delete data
                parameterNumber = length( app.actLinkedMapDataTitle );
                dataTemporary = cell(1, parameterNumber, app.maxMapNumber);
                app.actLinkedMapData(:,:, checkMapID) = [];

                dataTemporary(:,:, 1:app.maxMapNumber-1) = app.actLinkedMapData;
                app.actLinkedMapData = dataTemporary;

                % (2) Update Save to ID
                app.actLinkedSaveToID = app.actLinkedSaveToID - 1;

                % (3) Update 'MapListBox'
                updateMapListBox(app, app.Image_ActDelta)

            elseif isequal( app.RepDeltaLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Rep map

                % (1) Delete data
                parameterNumber = length( app.repLinkedMapDataTitle );
                dataTemporary = cell(1, parameterNumber, app.maxMapNumber);
                app.repLinkedMapData(:,:, checkMapID) = [];

                dataTemporary(:,:, 1:app.maxMapNumber-1) = app.repLinkedMapData;
                app.repLinkedMapData = dataTemporary;

                % (2) Update Save to ID
                app.repLinkedSaveToID = app.repLinkedSaveToID - 1;

                % (3) Update 'MapListBox'
                updateMapListBox(app, app.Image_RepDelta)

            elseif isequal( app.DurationDeltaLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Duration map

                % (1) Delete data
                parameterNumber = length( app.durationLinkedMapDataTitle );
                dataTemporary = cell(1, parameterNumber, app.maxMapNumber);
                app.durationLinkedMapData(:,:, checkMapID) = [];

                dataTemporary(:,:, 1:app.maxMapNumber-1) = app.durationLinkedMapData;
                app.durationLinkedMapData = dataTemporary;

                % (2) Update Save to File ID
                app.durationLinkedSaveToID = app.durationLinkedSaveToID - 1;

                % (3) Update 'MapListBox'
                updateMapListBox(app, app.Image_DurationDelta)
            end

            Image_UnloadMapClicked(app, 'ImageClicked')

        end

        % Image clicked function: Image_DisplayPixel
        function Image_DisplayPixelClicked(app, event)

            showImageIconAct(app, app.Image_DisplayPixel)

            % (1) Update components
            app.Image_DisplayPixel.Enable = 'off';

            app.CheckSigX_scrn.Editable = 'on';
            app.CheckSigY_scrn.Editable = 'on';
            app.CheckSigX_scrn.BackgroundColor = [1.00, 1.00, 0.00]; % Yellow
            app.CheckSigY_scrn.BackgroundColor = [1.00, 1.00, 0.00];

            % (2) Plot the initial marker
            checkSigColor = [0.00, 0.45, 0.74];

            app.checkSigMarkerXY = [ round(0.5*app.cmosDataRowNum), round(0.5*app.cmosDataColNum) ];
            app.CheckSigX_scrn.Value = app.checkSigMarkerXY(1,1);
            app.CheckSigY_scrn.Value = app.checkSigMarkerXY(1,2);

            % (2.1) Marker plot
            delete(app.checkSigMarkerPlot)
            app.checkSigMarkerPlot = updateCheckSigMarker(app, checkSigColor);

            % (2.2) Marker Highlight Plot
            delete(app.checkSigMarkerHighlightPlot)
            app.checkSigMarkerHighlightPlot = updateCheckSigMarkerHighlight(app);

            % (3) Get the data
            checkMapID = str2double(app.MapListBox.Value);

            if isequal( app.ActDeltaLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Act map

                app.checkSigDataSectionTime = app.actLinkedMapData{1,7,checkMapID};
                
                app.checkSigDataSectionCamera1 = app.actLinkedMapData{1,8,checkMapID};
                app.checkSigDataSectionCamera2 = app.actLinkedMapData{1,9,checkMapID};
                
                app.checkSigLocsActCamera1 = app.actLinkedMapData{1,10,checkMapID};
                app.checkSigLocsActCamera2 = app.actLinkedMapData{1,11,checkMapID};

                app.checkSigMapData = app.actLinkedMapData{1,12,checkMapID};
                app.checkSigLB = app.actLinkedMapData{1,5,checkMapID};
                app.checkSigUB = app.actLinkedMapData{1,6,checkMapID};
                app.checkSigMapData( app.checkSigMapData < app.checkSigLB  |  app.checkSigMapData > app.checkSigUB ) = NaN;

                delete(app.checkSigPlotCamera1)
                delete(app.checkSigPlotCamera2)
                [ app.checkSigPlotCamera1, app.checkSigPlotCamera2 ] = updateCheckSig(app, app.checkSigDataSectionTime, app.checkSigDataSectionCamera1, app.checkSigDataSectionCamera2, checkSigColor);

                delete(app.checkSigActPointPlotCamera1)
                delete(app.checkSigActPointPlotCamera2)
                [ app.checkSigActPointPlotCamera1, app.checkSigActPointPlotCamera2 ] = updateCheckSigActPoints(app, app.checkSigDataSectionTime, app.checkSigDataSectionCamera1, app.checkSigDataSectionCamera2);
                
                delete(app.checkSigHistIndicatingLinePlot)
                app.checkSigHistIndicatingLinePlot = updatecheckSigHistIndicatingLine(app, app.checkSigMapData);
                
            elseif isequal( app.RepDeltaLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Rep map

                app.checkSigDataSectionTime = app.repLinkedMapData{1,8,checkMapID};
                
                app.checkSigDataSectionCamera1 = app.repLinkedMapData{1,9,checkMapID};
                app.checkSigDataSectionCamera2 = app.repLinkedMapData{1,10,checkMapID};
                
                app.checkSigLocsRepCamera1 = app.repLinkedMapData{1,11,checkMapID};
                app.checkSigLocsRepCamera2 = app.repLinkedMapData{1,12,checkMapID};

                app.checkSigMapData = app.repLinkedMapData{1,13,checkMapID};
                app.checkSigLB = app.repLinkedMapData{1,6,checkMapID};
                app.checkSigUB = app.repLinkedMapData{1,7,checkMapID};
                app.checkSigMapData( app.checkSigMapData < app.checkSigLB  |  app.checkSigMapData > app.checkSigUB ) = NaN;

                delete(app.checkSigPlotCamera1)
                delete(app.checkSigPlotCamera2)
                [ app.checkSigPlotCamera1, app.checkSigPlotCamera2 ] = updateCheckSig(app, app.checkSigDataSectionTime, app.checkSigDataSectionCamera1, app.checkSigDataSectionCamera2, checkSigColor);

                delete(app.checkSigRepPointPlotCamera1)
                delete(app.checkSigRepPointPlotCamera2)
                [ app.checkSigRepPointPlotCamera1, app.checkSigRepPointPlotCamera2 ] = updateCheckSigRepPoints(app, app.checkSigDataSectionTime, app.checkSigDataSectionCamera1, app.checkSigDataSectionCamera2);

                delete(app.checkSigHistIndicatingLinePlot)
                app.checkSigHistIndicatingLinePlot = updatecheckSigHistIndicatingLine(app, app.checkSigMapData);

            elseif isequal( app.DurationDeltaLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Duration map

                app.checkSigDataSectionTime = app.durationLinkedMapData{1,8,checkMapID};
                
                app.checkSigDataSectionCamera1 = app.durationLinkedMapData{1,9,checkMapID};
                app.checkSigDataSectionCamera2 = app.durationLinkedMapData{1,10,checkMapID};
                
                app.checkSigLocsActCamera1 = app.durationLinkedMapData{1,11,checkMapID};
                app.checkSigLocsActCamera2 = app.durationLinkedMapData{1,12,checkMapID};
                
                app.checkSigLocsRepCamera1 = app.durationLinkedMapData{1,13,checkMapID};
                app.checkSigLocsRepCamera2 = app.durationLinkedMapData{1,14,checkMapID};

                app.checkSigMapData = app.durationLinkedMapData{1,17,checkMapID};
                app.checkSigLB = app.durationLinkedMapData{1,6,checkMapID};
                app.checkSigUB = app.durationLinkedMapData{1,7,checkMapID};
                app.checkSigMapData( app.checkSigMapData < app.checkSigLB  |  app.checkSigMapData > app.checkSigUB ) = NaN;

                delete(app.checkSigPlotCamera1)
                delete(app.checkSigPlotCamera2)
                [ app.checkSigPlotCamera1, app.checkSigPlotCamera2 ] = updateCheckSig(app, app.checkSigDataSectionTime, app.checkSigDataSectionCamera1, app.checkSigDataSectionCamera2, checkSigColor);

                delete(app.checkSigActPointPlotCamera1)
                delete(app.checkSigActPointPlotCamera2)
                [ app.checkSigActPointPlotCamera1, app.checkSigActPointPlotCamera2 ] = updateCheckSigActPoints(app, app.checkSigDataSectionTime, app.checkSigDataSectionCamera1, app.checkSigDataSectionCamera2);
                
                delete(app.checkSigRepPointPlotCamera1)
                delete(app.checkSigRepPointPlotCamera2)
                [ app.checkSigRepPointPlotCamera1, app.checkSigRepPointPlotCamera2 ] = updateCheckSigRepPoints(app, app.checkSigDataSectionTime, app.checkSigDataSectionCamera1, app.checkSigDataSectionCamera2);

                delete(app.checkSigHistIndicatingLinePlot)
                app.checkSigHistIndicatingLinePlot = updatecheckSigHistIndicatingLine(app, app.checkSigMapData);
            end
            
        end

        % Image clicked function: Image_ClearPixel
        function Image_ClearPixelClicked(app, event)

            showImageIconAct(app, app.Image_ClearPixel)

            if isempty( app.checkSigMarkerPlot )

                msg = 'Display the signal first';
                uialert(app.OMZer_VmCaLinked_UIFigure, msg,'Warning','Icon','error')

            else

                app.Image_DisplayPixel.Enable = 'on';

                app.CheckSigX_scrn.Editable = 'off';
                app.CheckSigY_scrn.Editable = 'off';
                app.CheckSigX_scrn.BackgroundColor = [1.00, 1.00, 1.00]; % White
                app.CheckSigY_scrn.BackgroundColor = [1.00, 1.00, 1.00];

                clearCheckSigPlot(app)
            end

        end

        % Value changed function: UserCommentTextArea
        function UserCommentTextAreaValueChanged(app, event)
            
            checkMapID = str2double(app.MapListBox.Value);

            if isequal( app.ActDeltaLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Act map

                app.actLinkedMapData{1,14,checkMapID} = app.UserCommentTextArea.Value;
                
            elseif isequal( app.RepDeltaLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Rep map

                app.repLinkedMapData{1,15,checkMapID} = app.UserCommentTextArea.Value;

            elseif isequal( app.DurationDeltaLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Duration map

                app.durationLinkedMapData{1,19,checkMapID} = app.UserCommentTextArea.Value;
            end

        end

        % Value changed function: CheckSigX_scrn, CheckSigY_scrn
        function CheckSigXY_scrnValueChanged(app, event)
            
            app.checkSigMarkerXY(1,1) = app.CheckSigX_scrn.Value;
            app.checkSigMarkerXY(1,2) = app.CheckSigY_scrn.Value;

            checkSigColor = [0.00, 0.45, 0.74];

            % (1.1) Marker plot
            delete(app.checkSigMarkerPlot)
            app.checkSigMarkerPlot = updateCheckSigMarker(app, checkSigColor);

            % (1.2) Marker Highlight Plot
            delete(app.checkSigMarkerHighlightPlot)
            app.checkSigMarkerHighlightPlot = updateCheckSigMarkerHighlight(app);

            % (2) Update the signal
            if isequal( app.ActDeltaLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Act map

                delete(app.checkSigPlotCamera1)
                delete(app.checkSigPlotCamera2)
                [ app.checkSigPlotCamera1, app.checkSigPlotCamera2 ] = updateCheckSig(app, app.checkSigDataSectionTime, app.checkSigDataSectionCamera1, app.checkSigDataSectionCamera2, checkSigColor);

                delete(app.checkSigActPointPlotCamera1)
                delete(app.checkSigActPointPlotCamera2)
                [ app.checkSigActPointPlotCamera1, app.checkSigActPointPlotCamera2 ] = updateCheckSigActPoints(app, app.checkSigDataSectionTime, app.checkSigDataSectionCamera1, app.checkSigDataSectionCamera2);
                
                delete(app.checkSigHistIndicatingLinePlot)
                app.checkSigHistIndicatingLinePlot = updatecheckSigHistIndicatingLine(app, app.checkSigMapData);
                
            elseif isequal( app.RepDeltaLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Rep map

                delete(app.checkSigPlotCamera1)
                delete(app.checkSigPlotCamera2)
                [ app.checkSigPlotCamera1, app.checkSigPlotCamera2 ] = updateCheckSig(app, app.checkSigDataSectionTime, app.checkSigDataSectionCamera1, app.checkSigDataSectionCamera2, checkSigColor);

                delete(app.checkSigRepPointPlotCamera1)
                delete(app.checkSigRepPointPlotCamera2)
                [ app.checkSigRepPointPlotCamera1, app.checkSigRepPointPlotCamera2 ] = updateCheckSigRepPoints(app, app.checkSigDataSectionTime, app.checkSigDataSectionCamera1, app.checkSigDataSectionCamera2);

                delete(app.checkSigHistIndicatingLinePlot)
                app.checkSigHistIndicatingLinePlot = updatecheckSigHistIndicatingLine(app, app.checkSigMapData);

            elseif isequal( app.DurationDeltaLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Duration map

                delete(app.checkSigPlotCamera1)
                delete(app.checkSigPlotCamera2)
                [ app.checkSigPlotCamera1, app.checkSigPlotCamera2 ] = updateCheckSig(app, app.checkSigDataSectionTime, app.checkSigDataSectionCamera1, app.checkSigDataSectionCamera2, checkSigColor);

                delete(app.checkSigActPointPlotCamera1)
                delete(app.checkSigActPointPlotCamera2)
                [ app.checkSigActPointPlotCamera1, app.checkSigActPointPlotCamera2 ] = updateCheckSigActPoints(app, app.checkSigDataSectionTime, app.checkSigDataSectionCamera1, app.checkSigDataSectionCamera2);
                
                delete(app.checkSigRepPointPlotCamera1)
                delete(app.checkSigRepPointPlotCamera2)
                [ app.checkSigRepPointPlotCamera1, app.checkSigRepPointPlotCamera2 ] = updateCheckSigRepPoints(app, app.checkSigDataSectionTime, app.checkSigDataSectionCamera1, app.checkSigDataSectionCamera2);

                delete(app.checkSigHistIndicatingLinePlot)
                app.checkSigHistIndicatingLinePlot = updatecheckSigHistIndicatingLine(app, app.checkSigMapData);
            end

        end

        % Window button down function: OMZer_VmCaLinked_UIFigure
        function OMZer_VmCaLinked_UIFigureWindowButtonDown(app, event)

            if isequal(app.Image_DisplayPixel.Enable, 'off')  &&  isequal(app.Image_ClearPixel.Enable, 'on')

                app.mouseMotion = app.OMZer_VmCaLinked_UIFigure.SelectionType; % The result can be 'normal', 'extend', 'alt', 'open'

                if isequal(app.mouseMotion,'normal') % 'normal' means single-click any mouse button.

                    app.mouseXY = app.OMZer_VmCaLinked_UIFigure.CurrentPoint; % Returns (x, y) coordinates of the mouse pointer, measured from the lower-left corner of the figure.
                    app.mapAxisLocs = app.MapAxis.InnerPosition; % Returns a 1-by-4 row vector -  [left bottom width height].

                    if ( app.mouseXY(1,1) >= app.mapAxisLocs(1,1) )  ...
                            &&  ( app.mouseXY(1,1) <= app.mapAxisLocs(1,1) + app.mapAxisLocs(1,3) )  ...
                            &&  ( app.mouseXY(1,2) >= app.mapAxisLocs(1,2) )  ...
                            &&  ( app.mouseXY(1,2) <= app.mapAxisLocs(1,2) + app.mapAxisLocs(1,4) )

                        updateMouseXY(app, app.mapAxisLocs);

                        if ( abs(app.mouseXY(1,1) - app.checkSigMarkerXY(1,1)) <= 5 )  &&  ( abs(app.mouseXY(1,2) - app.checkSigMarkerXY(1,2)) <= 5 ) % Find which marker is within 5 pixels of the mouse location

                            app.checkSigMouseDragID = 1;
                        end
                    end
                end
            end

        end

        % Window button motion function: OMZer_VmCaLinked_UIFigure
        function OMZer_VmCaLinked_UIFigureWindowButtonMotion(app, event)

            if ~isempty(app.checkSigMouseDragID)  &&  isequal(app.Image_DisplayPixel.Enable, 'off')  &&  isequal(app.Image_ClearPixel.Enable, 'on')

                app.mouseXY = app.OMZer_VmCaLinked_UIFigure.CurrentPoint; % Returns (x, y) coordinates of the mouse pointer, measured from the lower-left corner of the figure.
                app.mapAxisLocs = app.MapAxis.InnerPosition; % Returns a 1-by-4 row vector -  [left bottom width height].

                if ( app.mouseXY(1,1) >= app.mapAxisLocs(1,1) )  ...
                        &&  ( app.mouseXY(1,1) <= app.mapAxisLocs(1,1) + app.mapAxisLocs(1,3) )  ...
                        &&  ( app.mouseXY(1,2) >= app.mapAxisLocs(1,2) )  ...
                        &&  ( app.mouseXY(1,2) <= app.mapAxisLocs(1,2) + app.mapAxisLocs(1,4) )

                    updateMouseXY(app, app.mapAxisLocs);

                    app.checkSigMarkerXY(1,1) = app.mouseXY(1,1);
                    app.checkSigMarkerXY(1,2) = app.mouseXY(1,2);

                    app.CheckSigX_scrn.Value = app.checkSigMarkerXY(1,1);
                    app.CheckSigY_scrn.Value = app.checkSigMarkerXY(1,2);

                    CheckSigXY_scrnValueChanged(app, event)
                end
            end

        end

        % Window button up function: OMZer_VmCaLinked_UIFigure
        function OMZer_VmCaLinked_UIFigureWindowButtonUp(app, event)

            app.checkSigMouseDragID = [];

        end

        % Close request function: OMZer_VmCaLinked_UIFigure
        function OMZer_VmCaLinked_UIFigureCloseRequest(app, event)

            % (1) Set mainApp at the top level
            figure(app.mainAppUIFigure)

            % (2) Close children app
            if isobject(app.OMZer_VmCaLinked_Signal)
                delete(app.OMZer_VmCaLinked_Signal)
            end
            
            if isobject(app.OMZer_VmCaLinked_ExportDisplayPixel)
                delete(app.OMZer_VmCaLinked_ExportDisplayPixel)
            end

            % (3) Update the mainApp
            app.mainApp.OMZer_VmCaLinked = [];

            delete(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OMZer_VmCaLinked_UIFigure = uifigure('Visible', 'off');
            app.OMZer_VmCaLinked_UIFigure.Name = 'OMZer Vm-Ca Linked Analysis';
            app.OMZer_VmCaLinked_UIFigure.Icon = 'Icons Wedding Ring.png';
            app.OMZer_VmCaLinked_UIFigure.CloseRequestFcn = createCallbackFcn(app, @OMZer_VmCaLinked_UIFigureCloseRequest, true);
            app.OMZer_VmCaLinked_UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @OMZer_VmCaLinked_UIFigureWindowButtonDown, true);
            app.OMZer_VmCaLinked_UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @OMZer_VmCaLinked_UIFigureWindowButtonUp, true);
            app.OMZer_VmCaLinked_UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @OMZer_VmCaLinked_UIFigureWindowButtonMotion, true);
            app.colorBarAxes = uiaxes(app.OMZer_VmCaLinked_UIFigure);
            title(app.colorBarAxes, 'ms')
            app.colorBarAxes.XTick = [];
            app.MapAxis = uiaxes(app.OMZer_VmCaLinked_UIFigure);
            app.MapAxis.XLim = [1 100];
            app.MapAxis.YLim = [1 100];
            app.MapAxis.YDir = 'reverse';
            app.MapAxis.XTick = [];
            app.MapAxis.YTick = [];
            app.MapAxis.ZTick = [];
            app.checkSigAxis = uiaxes(app.OMZer_VmCaLinked_UIFigure);
            xlabel(app.checkSigAxis, 'Time (sec)')
            app.MapHistAxis = uiaxes(app.OMZer_VmCaLinked_UIFigure);
            xlabel(app.MapHistAxis, 'Time (ms)')
            ylabel(app.MapHistAxis, 'Counts')
            app.MapHistAxis.ZTick = [];
            app.TabGroup = uitabgroup(app.OMZer_VmCaLinked_UIFigure);
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.Image_ActDelta = uiimage(app.AnalysisTab);
            app.Image_ActDelta.ImageClickedFcn = createCallbackFcn(app, @Image_ActDeltaClicked, true);
            app.Image_ActDelta.ImageSource = 'Icon A.png';
            app.ActDeltaLabel = uilabel(app.AnalysisTab);
            app.ActDeltaLabel.Text = 'Act Delta';
            app.Image_RepDelta = uiimage(app.AnalysisTab);
            app.Image_RepDelta.ImageClickedFcn = createCallbackFcn(app, @Image_RepDeltaClicked, true);
            app.Image_RepDelta.ImageSource = 'Icon R.png';
            app.RepDeltaLabel = uilabel(app.AnalysisTab);
            app.RepDeltaLabel.Text = 'Rep Delta';
            app.Image_DurationDelta = uiimage(app.AnalysisTab);
            app.Image_DurationDelta.ImageClickedFcn = createCallbackFcn(app, @Image_DurationDeltaClicked, true);
            app.Image_DurationDelta.ImageSource = 'Icon D.png';
            app.DurationDeltaLabel = uilabel(app.AnalysisTab);
            app.DurationDeltaLabel.Text = 'Duration Delta';
            app.Image_Signal = uiimage(app.AnalysisTab);
            app.Image_Signal.ImageClickedFcn = createCallbackFcn(app, @Image_SignalClicked, true);
            app.Image_Signal.ImageSource = 'Icon Signal.png';
            app.SignalLabel = uilabel(app.AnalysisTab);
            app.SignalLabel.Text = 'Signal';
            app.ToolsTab = uitab(app.TabGroup);
            app.ToolsTab.Title = 'Tools';
            app.Image_Save = uiimage(app.ToolsTab);
            app.Image_Save.ImageClickedFcn = createCallbackFcn(app, @Image_SaveClicked, true);
            app.Image_Save.ImageSource = 'Icon Save.png';
            app.SaveLabel = uilabel(app.ToolsTab);
            app.SaveLabel.Text = 'Save';
            app.Image_ExportData = uiimage(app.ToolsTab);
            app.Image_ExportData.ImageClickedFcn = createCallbackFcn(app, @Image_ExportDataClicked, true);
            app.Image_ExportData.Enable = 'off';
            app.Image_ExportData.ImageSource = 'Icon Export.png';
            app.ExportDataLabel = uilabel(app.ToolsTab);
            app.ExportDataLabel.Text = 'Export Data';
            app.Image_ExportFigure = uiimage(app.ToolsTab);
            app.Image_ExportFigure.ImageClickedFcn = createCallbackFcn(app, @Image_ExportFigureClicked, true);
            app.Image_ExportFigure.Enable = 'off';
            app.Image_ExportFigure.ImageSource = 'Icon Export Figure.png';
            app.ExportFigureLabel = uilabel(app.ToolsTab);
            app.ExportFigureLabel.Text = 'Export Figure';
            app.PreferenceTab = uitab(app.TabGroup);
            app.PreferenceTab.Title = 'Preference';
            app.Image_RegionalMap = uiimage(app.OMZer_VmCaLinked_UIFigure);
            app.Image_RegionalMap.ImageClickedFcn = createCallbackFcn(app, @Image_RegionalMapClicked, true);
            app.Image_RegionalMap.ImageSource = 'Icon Regional Map.png';
            app.RegionalMapLabel = uilabel(app.OMZer_VmCaLinked_UIFigure);
            app.RegionalMapLabel.Text = 'Regional Map';
            app.Image_GlobalMap = uiimage(app.OMZer_VmCaLinked_UIFigure);
            app.Image_GlobalMap.ImageClickedFcn = createCallbackFcn(app, @Image_GlobalMapClicked, true);
            app.Image_GlobalMap.ImageSource = 'Icon Global Map.png';
            app.GlobalMapLabel = uilabel(app.OMZer_VmCaLinked_UIFigure);
            app.GlobalMapLabel.Text = 'Global Map';
            app.Image_ResetBound = uiimage(app.OMZer_VmCaLinked_UIFigure);
            app.Image_ResetBound.ImageClickedFcn = createCallbackFcn(app, @Image_ResetBoundClicked, true);
            app.Image_ResetBound.Enable = 'off';
            app.Image_ResetBound.ImageSource = 'Icon Undo.png';
            app.ResetBoundLabel = uilabel(app.OMZer_VmCaLinked_UIFigure);
            app.ResetBoundLabel.Text = 'Reset Bound';
            app.Image_DeleteMap = uiimage(app.OMZer_VmCaLinked_UIFigure);
            app.Image_DeleteMap.ImageClickedFcn = createCallbackFcn(app, @Image_DeleteMapClicked, true);
            app.Image_DeleteMap.Enable = 'off';
            app.Image_DeleteMap.ImageSource = 'Icon Delete.png';
            app.DeleteMapLabel = uilabel(app.OMZer_VmCaLinked_UIFigure);
            app.DeleteMapLabel.Text = 'Delete Map';
            app.Image_UnloadMap = uiimage(app.OMZer_VmCaLinked_UIFigure);
            app.Image_UnloadMap.ImageClickedFcn = createCallbackFcn(app, @Image_UnloadMapClicked, true);
            app.Image_UnloadMap.Enable = 'off';
            app.Image_UnloadMap.ImageSource = 'Icon Unload Map.png';
            app.UnloadMapLabel = uilabel(app.OMZer_VmCaLinked_UIFigure);
            app.UnloadMapLabel.Text = 'Unload Map';
            app.MapTitleLabel = uilabel(app.OMZer_VmCaLinked_UIFigure);
            app.MapTitleLabel.Text = 'Map Title';
            app.Image_DisplayPixel = uiimage(app.OMZer_VmCaLinked_UIFigure);
            app.Image_DisplayPixel.ImageClickedFcn = createCallbackFcn(app, @Image_DisplayPixelClicked, true);
            app.Image_DisplayPixel.Enable = 'off';
            app.Image_DisplayPixel.ImageSource = 'Icon Display Pixel.png';
            app.DisplayPixelLabel = uilabel(app.OMZer_VmCaLinked_UIFigure);
            app.DisplayPixelLabel.Text = 'Display Pixel';
            app.CheckSigXYLabel = uilabel(app.OMZer_VmCaLinked_UIFigure);
            app.CheckSigXYLabel.Tooltip = {'X (red) and Y (blue) coordinate of the marker. Please be noted that X is column and Y is row to locate an element in a matrix in MATLAB.'};
            app.CheckSigXYLabel.Text = 'X,Y';
            app.CheckSigY_scrn = uieditfield(app.OMZer_VmCaLinked_UIFigure, 'numeric');
            app.CheckSigY_scrn.Limits = [0 100];
            app.CheckSigY_scrn.ValueDisplayFormat = '%.0f';
            app.CheckSigY_scrn.ValueChangedFcn = createCallbackFcn(app, @CheckSigXY_scrnValueChanged, true);
            app.CheckSigY_scrn.Editable = 'off';
            app.CheckSigY_scrn.Tooltip = {'Y coordinate of the 1st marker (Note: row in a matlab matrix)'};
            app.CheckSigX_scrn = uieditfield(app.OMZer_VmCaLinked_UIFigure, 'numeric');
            app.CheckSigX_scrn.Limits = [0 100];
            app.CheckSigX_scrn.ValueDisplayFormat = '%.0f';
            app.CheckSigX_scrn.ValueChangedFcn = createCallbackFcn(app, @CheckSigXY_scrnValueChanged, true);
            app.CheckSigX_scrn.Editable = 'off';
            app.CheckSigX_scrn.Tooltip = {'X coordinate of the 1st marker (Note: column in a matlab matrix)'};
            app.Image_ClearPixel = uiimage(app.OMZer_VmCaLinked_UIFigure);
            app.Image_ClearPixel.ImageClickedFcn = createCallbackFcn(app, @Image_ClearPixelClicked, true);
            app.Image_ClearPixel.Enable = 'off';
            app.Image_ClearPixel.ImageSource = 'Icon Clear Pixel.png';
            app.ClearPixelLabel = uilabel(app.OMZer_VmCaLinked_UIFigure);
            app.ClearPixelLabel.Text = 'Clear Pixel';
            app.Image_ActivatedCamera = uiimage(app.OMZer_VmCaLinked_UIFigure);
            app.Image_ActivatedCamera.Tooltip = {'(This is NOT a button) Tell the user from which camera the signals are'};
            app.Image_ActivatedCamera.ImageSource = 'Icon Activated Camera.png';
            app.Camera1solidlineblueCamera2DashdottedlineredLabel = uilabel(app.OMZer_VmCaLinked_UIFigure);
            app.Camera1solidlineblueCamera2DashdottedlineredLabel.Text = {'Camera 1 - solid line (blue)'; ''; 'Camera 2 - Dash-dotted line (red)'};
            app.Image_ROIDock = uiimage(app.OMZer_VmCaLinked_UIFigure);
            app.Image_ROIDock.ImageClickedFcn = createCallbackFcn(app, @Image_ROIDockClicked, true);
            app.Image_ROIDock.ImageSource = 'Icon Collection.png';
            app.ROIDockLabel = uilabel(app.OMZer_VmCaLinked_UIFigure);
            app.ROIDockLabel.Text = 'ROI Dock';
            app.RepDropDownLabel = uilabel(app.OMZer_VmCaLinked_UIFigure);
            app.RepDropDownLabel.Text = '% Rep';
            app.RepDropDown = uidropdown(app.OMZer_VmCaLinked_UIFigure);
            app.RepDropDown.Items = {'10', '20', '30', '40', '50', '60', '70', '80', '90'};
            app.RepDropDown.Value = '80';
            app.DurationDropDownLabel = uilabel(app.OMZer_VmCaLinked_UIFigure);
            app.DurationDropDownLabel.Text = '% Duration';
            app.DurationDropDown = uidropdown(app.OMZer_VmCaLinked_UIFigure);
            app.DurationDropDown.Items = {'10', '20', '30', '40', '50', '60', '70', '80', '90'};
            app.DurationDropDown.Value = '80';
            app.MapModeDropDown = uidropdown(app.OMZer_VmCaLinked_UIFigure);
            app.MapModeDropDown.Items = {'Single', 'Ensemble'};
            app.MapModeDropDown.ValueChangedFcn = createCallbackFcn(app, @MapModeDropDownValueChanged, true);
            app.MapModeDropDown.Value = 'Single';
            app.Image_MapMode = uiimage(app.OMZer_VmCaLinked_UIFigure);
            app.Image_MapMode.Tooltip = {'(This is NOT a button)'};
            app.Image_MapMode.ImageSource = 'Icon Single.png';
            app.MapListBoxLabel = uilabel(app.OMZer_VmCaLinked_UIFigure);
            app.MapListBoxLabel.Text = 'Map';
            app.MapListBox = uilistbox(app.OMZer_VmCaLinked_UIFigure);
            app.MapListBox.Items = {};
            app.MapListBox.ValueChangedFcn = createCallbackFcn(app, @MapListBoxValueChanged, true);
            app.MapListBox.Value = {};
            app.MeanmsEditFieldLabel = uilabel(app.OMZer_VmCaLinked_UIFigure);
            app.MeanmsEditFieldLabel.Text = 'Mean (ms)';
            app.MeanmsEditField = uieditfield(app.OMZer_VmCaLinked_UIFigure, 'numeric');
            app.MeanmsEditField.Editable = 'off';
            app.MedianmsEditFieldLabel = uilabel(app.OMZer_VmCaLinked_UIFigure);
            app.MedianmsEditFieldLabel.Text = 'Median (ms)';
            app.MedianmsEditField = uieditfield(app.OMZer_VmCaLinked_UIFigure, 'numeric');
            app.MedianmsEditField.Editable = 'off';
            app.SDmsEditFieldLabel = uilabel(app.OMZer_VmCaLinked_UIFigure);
            app.SDmsEditFieldLabel.Text = 'SD (ms)';
            app.SDmsEditField = uieditfield(app.OMZer_VmCaLinked_UIFigure, 'numeric');
            app.SDmsEditField.Editable = 'off';
            app.WinStartsecEditFieldLabel = uilabel(app.OMZer_VmCaLinked_UIFigure);
            app.WinStartsecEditFieldLabel.Text = 'Win Start (sec)';
            app.WinStartsecEditField = uieditfield(app.OMZer_VmCaLinked_UIFigure, 'numeric');
            app.WinStartsecEditField.Limits = [0 Inf];
            app.WinStartsecEditField.Editable = 'off';
            app.WinStartsecEditField.Value = Inf;
            app.WinEndsecEditFieldLabel = uilabel(app.OMZer_VmCaLinked_UIFigure);
            app.WinEndsecEditFieldLabel.Text = 'Win End (sec)';
            app.WinEndsecEditField = uieditfield(app.OMZer_VmCaLinked_UIFigure, 'numeric');
            app.WinEndsecEditField.Limits = [0 Inf];
            app.WinEndsecEditField.Editable = 'off';
            app.WinEndsecEditField.Value = Inf;
            app.LBmsSpinnerLabel = uilabel(app.OMZer_VmCaLinked_UIFigure);
            app.LBmsSpinnerLabel.Text = 'LB (ms)';
            app.LBmsSpinner = uispinner(app.OMZer_VmCaLinked_UIFigure);
            app.LBmsSpinner.Step = 0.5;
            app.LBmsSpinner.ValueChangingFcn = createCallbackFcn(app, @LBmsSpinnerValueChanging, true);
            app.LBmsSpinner.ValueChangedFcn = createCallbackFcn(app, @LBmsSpinnerValueChanged, true);
            app.LBmsSpinner.Enable = 'off';
            app.UBmsSpinnerLabel = uilabel(app.OMZer_VmCaLinked_UIFigure);
            app.UBmsSpinnerLabel.Text = 'UB (ms)';
            app.UBmsSpinner = uispinner(app.OMZer_VmCaLinked_UIFigure);
            app.UBmsSpinner.Step = 0.5;
            app.UBmsSpinner.ValueChangingFcn = createCallbackFcn(app, @UBmsSpinnerValueChanging, true);
            app.UBmsSpinner.ValueChangedFcn = createCallbackFcn(app, @UBmsSpinnerValueChanged, true);
            app.UBmsSpinner.Enable = 'off';
            app.MinPeakHeightDropDownLabel = uilabel(app.OMZer_VmCaLinked_UIFigure);
            app.MinPeakHeightDropDownLabel.Text = 'Min Peak Height';
            app.MinPeakHeightDropDown = uidropdown(app.OMZer_VmCaLinked_UIFigure);
            app.MinPeakHeightDropDown.Items = {'0.1', '0.2', '0.3', '0.4', '0.5', '0.6', '0.7', '0.8', '0.9'};
            app.MinPeakHeightDropDown.Value = '0.6';
            app.UserCommentTextAreaLabel = uilabel(app.OMZer_VmCaLinked_UIFigure);
            app.UserCommentTextAreaLabel.Text = 'User Comment';
            app.UserCommentTextArea = uitextarea(app.OMZer_VmCaLinked_UIFigure);
            app.UserCommentTextArea.ValueChangedFcn = createCallbackFcn(app, @UserCommentTextAreaValueChanged, true);
            app.UserCommentTextArea.Enable = 'off';
            app.UserCommentTextArea.Tooltip = {''};
            app.AreaForStatisticsDeltaCamera1Camera2ButtonGroup = uibuttongroup(app.OMZer_VmCaLinked_UIFigure);
            app.AreaForStatisticsDeltaCamera1Camera2ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AreaForStatisticsDeltaCamera1Camera2ButtonGroupSelectionChanged, true);
            app.AreaForStatisticsDeltaCamera1Camera2ButtonGroup.Title = 'Area For Statistics (Delta = Camera 1 - Camera 2)';
            app.WholeAreaButton = uiradiobutton(app.AreaForStatisticsDeltaCamera1Camera2ButtonGroup);
            app.WholeAreaButton.Text = 'Whole Area';
            app.WholeAreaButton.Value = true;
            app.NegativeDeltaAreaButton = uiradiobutton(app.AreaForStatisticsDeltaCamera1Camera2ButtonGroup);
            app.NegativeDeltaAreaButton.Text = 'Negative Delta Area';
            app.PositiveDeltaAreaButton = uiradiobutton(app.AreaForStatisticsDeltaCamera1Camera2ButtonGroup);
            app.PositiveDeltaAreaButton.Text = 'Positive Delta Area';
            app.TheLBandUBareusedtodefinewholeareaofinterestLabel = uilabel(app.OMZer_VmCaLinked_UIFigure);
            app.TheLBandUBareusedtodefinewholeareaofinterestLabel.Text = 'The "LB" and "UB" are used to define "whole area of interest"';
            % Show the figure after all components are created
            app.OMZer_VmCaLinked_UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OMZer_VmCaLinked(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.OMZer_VmCaLinked_UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.OMZer_VmCaLinked_UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OMZer_VmCaLinked_UIFigure)
        end
    end
end