classdef OMZer_VmCaLinked_ExportDisplayPixel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OMZer_VmCaLinked_ExportDisplayPixel_UIFigure  matlab.ui.Figure
        Camera1solidlineblueCamera2DashdottedlineredLabel  matlab.ui.control.Label
        Image_ActivatedCamera  matlab.ui.control.Image
        PixelListBox           matlab.ui.control.ListBox
        PixelListBoxLabel      matlab.ui.control.Label
        MapTitleLabel          matlab.ui.control.Label
        ExportLabel            matlab.ui.control.Label
        Image_Export           matlab.ui.control.Image
        AddIntoPixelLabel      matlab.ui.control.Label
        Image_AddIntoPixel     matlab.ui.control.Image
        DeletefromPixelLabel   matlab.ui.control.Label
        Image_DeletefromPixel  matlab.ui.control.Image
        CheckSigX_scrn         matlab.ui.control.NumericEditField
        CheckSigY_scrn         matlab.ui.control.NumericEditField
        CheckSigXYLabel        matlab.ui.control.Label
        MapHistAxis            matlab.ui.control.UIAxes
        MapAxis                matlab.ui.control.UIAxes
        colorBarAxes           matlab.ui.control.UIAxes
        checkSigAxis           matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        
        % The Parent App --------------------
        mainApp
        mainAppUIFigure


        % The Current App --------------------
        dirName % File directory
        fileName % Filename without path and extension

        setup % User preference
        userAlphaValue

        imageIconActColor = [0.80, 0.80, 0.80]; % The activation color when an image is clicked

        lWidth = 2; % Line width

        data
        acqFreq
        markerXY
        signalTime
        cmosDataGroup
        bgImageNomGroup % Gray colour RGB version of background image
        cameraSource % '1 (or 2)' - data from camera 1 (or 2)

        mapAxisLocs

        mouseXY = []; % This is a 1-by-2 row vector that stores mouse "X" and "Y" coordinate
        mouseMotion = []; % The result can be 'normal', 'extend', 'alt', 'open'

        cmosDataRowNum % The numer of rows in cmosData
        cmosDataColNum % The numer of columns in cmosData

        % (0)
        exportMapName
        exportMapDataTitle
        exportMapData

        % (1) For Act
        actLinkedMapPlot
        actLinkedHistPlot
        actLinkedColorBarPlot

        % (2) For Rep
        repLinkedMapPlot
        repLinkedHistPlot
        repLinkedColorBarPlot

        % (3) For Duration
        durationLinkedMapPlot
        durationLinkedHistPlot
        durationLinkedColorBarPlot

        % (4) For Check Signal
        checkSigMarkerXY
        checkSigMouseDragID

        checkSigDataSectionTime

        checkSigDataSectionCamera1
        checkSigDataSectionCamera2

        checkSigLocsActCamera1
        checkSigLocsActCamera2

        checkSigLocsRepCamera1
        checkSigLocsRepCamera2

        checkSigMapData
        checkSigLB
        checkSigUB

        checkSigMarkerPlot
        checkSigMarkerHighlightPlot

        checkSigPlotCamera1
        checkSigPlotCamera2

        checkSigActPointPlotCamera1
        checkSigActPointPlotCamera2

        checkSigRepPointPlotCamera1
        checkSigRepPointPlotCamera2

        checkSigHistIndicatingLinePlot
        
        
        % (5) For export pixel
        pixelSaveToID = 1;
        exportPixelX
        exportPixelY
    end


    methods (Access = public)
        
        
        function showImageIconAct(app, imageIconName)

            imageIconName.BackgroundColor = app.imageIconActColor; %[0.80, 0.80, 0.80];
            pause(0.05)
            imageIconName.BackgroundColor = 'none';
        end


        function setColourMap(app,name)

            colormap(app.MapAxis, name);
            colormap(app.colorBarAxes, name);
        end


        function setAxisLimit(app, xMaxLimit, yMaxLimit) % Set the x-y limit of all axes

            app.MapAxis.XLim = [1, xMaxLimit];
            app.MapAxis.YLim = [1, yMaxLimit];

            app.CheckSigX_scrn.Value = 1;
            app.CheckSigX_scrn.Limits = [1, xMaxLimit];
            app.CheckSigY_scrn.Value = 1;
            app.CheckSigY_scrn.Limits = [1, yMaxLimit];
        end


        function updateMouseXY(app, axisLosInfo)

            %             app.mouseXY(1,1) = ( app.mouseXY(1,1) - axisLosInfo(1,1) ) * app.cmosDataColNum / axisLosInfo(1,3);
            %             app.mouseXY(1,1) = round( app.mouseXY(1,1) ); % Transfer relative mouse location inside bgImageAxis1 to absolute location inside a 100x100 matrix
            %             if app.mouseXY(1,1) < 1
            %                 app.mouseXY(1,1) = 1; % As a result, there won't appear (0,y) element requirement in a 100x100 matrix
            %             end
            %             if app.mouseXY(1,1) > app.cmosDataColNum
            %                 app.mouseXY(1,1) = app.cmosDataColNum;
            %             end
            %
            %
            %             app.mouseXY(1,2) = axisLosInfo.CurrentPoint(1,2);

            %             app.mouseXY(1,2) = ( (axisLosInfo(1,2) + axisLosInfo(1,4)) - app.mouseXY(1,2) ) * app.cmosDataRowNum / axisLosInfo(1,4);
            %             app.mouseXY(1,2) = round( app.mouseXY(1,2) ); % Transfer relative mouse location inside bgImageAxis1 to absolute location inside a 100x100 matrix
            %             if app.mouseXY(1,2) < 1
            %                 app.mouseXY(1,2) = 1; % As a result, there won't appear (x,0) element requirement in a 100x100 matrix
            %             end
            %             if app.mouseXY(1,2) > app.cmosDataRowNum
            %                 app.mouseXY(1,2) = app.cmosDataRowNum;
            %             end


            app.mouseXY(1,1) = app.MapAxis.CurrentPoint(1,1);
            app.mouseXY(1,2) = app.MapAxis.CurrentPoint(1,2);


            app.mouseXY(1,1) = round( app.mouseXY(1,1) ); % Transfer relative mouse location inside bgImageAxis1 to absolute location inside a 100x100 matrix
            if app.mouseXY(1,1) < 1
                app.mouseXY(1,1) = 1; % As a result, there won't appear (0,y) element requirement in a 100x100 matrix
            end
            if app.mouseXY(1,1) > app.cmosDataColNum
                app.mouseXY(1,1) = app.cmosDataColNum;
            end

            app.mouseXY(1,2) = round( app.mouseXY(1,2) ); % Transfer relative mouse location inside bgImageAxis1 to absolute location inside a 100x100 matrix
            if app.mouseXY(1,2) < 1
                app.mouseXY(1,2) = 1; % As a result, there won't appear (x,0) element requirement in a 100x100 matrix
            end
            if app.mouseXY(1,2) > app.cmosDataRowNum
                app.mouseXY(1,2) = app.cmosDataRowNum;
            end
        end


        function updatePixelListBox(app)

            pixelNum = length( app.exportPixelX );

            app.PixelListBox.Items = {};
            if pixelNum ~= 0
                for ID = 1 : pixelNum
                    app.PixelListBox.Items{ID} = num2str(ID);
                end
            end
        end


        function newPlotObject = plotMap(app, plotObject, matrix, LB, UB)

            matrix( matrix < LB  |  matrix > UB ) = NaN;

            hold(app.MapAxis,'on')
            delete(plotObject)

            % newPlotObject = imagesc(app.MapAxis, matrix);
            % newPlotObject.AlphaData = ~isnan(matrix) * app.userAlphaValue; % Make the pixel with NaN transparent

            newPlotObject = pcolor( app.MapAxis, matrix );
            newPlotObject.FaceColor = 'interp';
            newPlotObject.EdgeColor = 'none';
            newPlotObject.AlphaData = ~isnan(matrix) * app.userAlphaValue; % Make the pixel with NaN transparent

            if nanmin(matrix(:)) == nanmax(matrix(:))
                caxis(app.MapAxis, [ nanmin(matrix(:)), nanmax(matrix(:))+1 ]);
            else
                caxis(app.MapAxis, [ nanmin(matrix(:)), nanmax(matrix(:)) ]);
            end
            hold(app.MapAxis,'off')
        end


        function newPlotObject = plotMapColorBar(app, plotObject, matrix, LB, UB)

            matrix( matrix < LB  |  matrix > UB ) = NaN;

            matrixColumnVersion = reshape( matrix, [],1 );
            matrixColumnVersion( isnan(matrixColumnVersion) ) = [];
            matrixColumnVersion = unique(matrixColumnVersion);

            % (1) Plot the color bar
            delete(plotObject)
            newPlotObject = imagesc(app.colorBarAxes, matrixColumnVersion);
            yticks(app.colorBarAxes, 'auto')
            app.colorBarAxes.YLim = [ 1, length(matrixColumnVersion) ];

            % (2) Manually create y tick label
            if length(app.colorBarAxes.YTick) <= length(matrixColumnVersion)

                app.colorBarAxes.YTick( mod(app.colorBarAxes.YTick , 1) ~= 0 ) = []; % Only keep integer tick

            else
                app.colorBarAxes.YTick = [ 1 : length(matrixColumnVersion) ];
            end

            colorBarLabel = cell( 1, length(app.colorBarAxes.YTick) );
            for ID = 1 : length(app.colorBarAxes.YTick)
                colorBarLabel{ID} = num2str( matrixColumnVersion(app.colorBarAxes.YTick(ID)) );
            end
            app.colorBarAxes.YTickLabel = colorBarLabel;
        end


        function newPlotObject = plotMapHist(app, plotObject, matrix, LB, UB)

            matrix( matrix < LB  |  matrix > UB ) = NaN;

            histColumnVersion = reshape(matrix, [],1);
            histColumnVersion( isnan(histColumnVersion) ) = []; % Delete NaN because it will also be counted into 'data length'
            binNum = round( sqrt( length(histColumnVersion) ) ); % Specifies the number of bins (= square root of the number of observations)

            hold(app.MapHistAxis,'on')
            delete(plotObject)

            %            newPlotObject = histogram(app.MapHistAxis, histColumnVersion, binNum, 'FaceColor',[0.00, 0.45, 0.74], 'FaceAlpha',1);

            newPlotObject = histogram(app.MapHistAxis, histColumnVersion, 'FaceColor',[0.00, 0.45, 0.74], 'FaceAlpha',1);


            %             % Colourful Histgtam (Nedd code update)
            %             if isequal( app.Setup.VmMapCalSetup{13,2}, 'Multiple' )
            %
            %                 newPlotObject.FaceAlpha = 0;
            %
            %                 histBinEdge = newPlotObject.BinEdges;
            %                 eval( [ 'colorLevel = ', app.userColourMap, '(', num2str(binNum), ');' ] )
            %                 app.colorHistPlot = cell(binNum,1);
            %
            %                 for ID = 1 : binNum
            %                     app.colorHistPlot{ID} = histogram( app.MapHistAxis, HistCol( HistCol >= histBinEdge(ID) & HistCol < histBinEdge(ID+1) ), histBinEdge, 'FaceColor',colorLevel(ID,:), 'FaceAlpha',1 );
            %                 end
            %             end

            hold(app.MapHistAxis,'off')

            if min(histColumnVersion) == max(histColumnVersion)
                xlim( app.MapHistAxis, [min(histColumnVersion), max(histColumnVersion)+1] );
            else
                xlim( app.MapHistAxis, [min(histColumnVersion), max(histColumnVersion)] );
            end

            app.MapHistAxis.YLim = [ 0, max(newPlotObject.BinCounts) ];
        end


        function plotObject = updateCheckSigMarker(app, markerColor)

            X = app.checkSigMarkerXY(1,1);
            Y = app.checkSigMarkerXY(1,2);

            plotObject = line(app.MapAxis, X, Y, 'Marker','square', 'MarkerSize',10, 'MarkerEdgeColor','white', 'MarkerFaceColor', markerColor);
        end


        function plotObject = updateCheckSigMarkerHighlight(app)

            X = app.checkSigMarkerXY(1,1);
            Y = app.checkSigMarkerXY(1,2);

            plotObject = line(app.MapAxis, X, Y, 'Marker','o', 'LineWidth',4, 'MarkerSize',30, 'MarkerEdgeColor','white', 'MarkerFaceColor', 'none');
        end


        function [ plotObjectCamera1, plotObjectCamera2 ] = updateCheckSig(app, dataTime, dataCamera1, dataCamera2, lineColor)

            row = app.checkSigMarkerXY(1,2);
            col = app.checkSigMarkerXY(1,1);

            lineColorCamera1 = lineColor;
            lineColorCamera2 = [ 1.00, 0.00, 0.00 ]; % Red
            
            plotObjectCamera1 = line( app.checkSigAxis, dataTime, squeeze(dataCamera1(row,col,:)), 'LineStyle','-', 'LineWidth',app.lWidth, 'color', lineColorCamera1 );
            plotObjectCamera2 = line( app.checkSigAxis, dataTime, squeeze(dataCamera2(row,col,:)), 'LineStyle','-.', 'LineWidth',app.lWidth, 'color', lineColorCamera2 );
            
            xlim( app.checkSigAxis, [ dataTime(1), dataTime(end) ] );
        end


        function plotObject = updatecheckSigHistIndicatingLine(app, dataMap)

            row = app.checkSigMarkerXY(1,2);
            col = app.checkSigMarkerXY(1,1);

            if ~isnan( dataMap(row,col) )

                valueLabel = num2str(dataMap(row,col));

                plotObject = xline( app.MapHistAxis, dataMap(row,col), 'LineWidth',app.lWidth, 'color','Red' );
                plotObject.Label = valueLabel;
                plotObject.FontSize = 20;
                plotObject.FontWeight = 'bold';

                delta = app.MapHistAxis.XLim(2) - app.MapHistAxis.XLim(1);
                if dataMap(row,col) <= app.MapHistAxis.XLim(1) + 0.1*delta
                    plotObject.LabelHorizontalAlignment = 'right';

                elseif dataMap(row,col) >= app.MapHistAxis.XLim(2) - 0.1*delta
                    plotObject.LabelHorizontalAlignment = 'left';

                else
                    plotObject.LabelHorizontalAlignment = 'center';
                end

            else
                plotObject = [];
            end
        end


        function [ plotObjectCamera1, plotObjectCamera2 ] = updateCheckSigActPoints(app, dataTime, dataCamera1, dataCamera2)

            row = app.checkSigMarkerXY(1,2);
            col = app.checkSigMarkerXY(1,1);

            IDCamera1 = app.checkSigLocsActCamera1( row, col );
            if ~isnan(IDCamera1)
                plotObjectCamera1 = line( app.checkSigAxis, dataTime(IDCamera1), dataCamera1(row,col,IDCamera1), 'Marker','o', 'MarkerSize',10, 'MarkerEdgeColor','black', 'MarkerFaceColor','black' );
            else
                plotObjectCamera1 = [];
            end
            
            IDCamera2 = app.checkSigLocsActCamera2( row, col );
            if ~isnan(IDCamera2)
                plotObjectCamera2 = line( app.checkSigAxis, dataTime(IDCamera2), dataCamera2(row,col,IDCamera2), 'Marker','o', 'MarkerSize',10, 'MarkerEdgeColor','black', 'MarkerFaceColor','black' );
            else
                plotObjectCamera2 = [];
            end
        end


        function [ plotObjectCamera1, plotObjectCamera2 ] = updateCheckSigRepPoints(app, dataTime, dataCamera1, dataCamera2)

            row = app.checkSigMarkerXY(1,2);
            col = app.checkSigMarkerXY(1,1);

            IDCamera1 = app.checkSigLocsRepCamera1( row, col );
            if ~isnan(IDCamera1)
                plotObjectCamera1 = line( app.checkSigAxis, dataTime(IDCamera1), dataCamera1(row,col,IDCamera1), 'Marker','d', 'MarkerSize',10, 'MarkerEdgeColor','black', 'MarkerFaceColor','black' );
            else
                plotObjectCamera1 = [];
            end
            
            IDCamera2 = app.checkSigLocsRepCamera2( row, col );
            if ~isnan(IDCamera2)
                plotObjectCamera2 = line( app.checkSigAxis, dataTime(IDCamera2), dataCamera2(row,col,IDCamera2), 'Marker','d', 'MarkerSize',10, 'MarkerEdgeColor','black', 'MarkerFaceColor','black' );
            else
                plotObjectCamera2 = [];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, OMZer_VmCaLinked)
            
            msg = 'Configuring files';
            progressBar = uiprogressdlg(app.OMZer_VmCaLinked_ExportDisplayPixel_UIFigure, 'Title','Initialising', 'Message',msg, 'Indeterminate','on');
            drawnow

            % (1) User set up
            app.setup = load([pwd,'/OMZer_Setup']);
            setColourMap(app, app.setup.userColourMap);
            app.userAlphaValue = 1.0; % The default alpha value

            % (2) Get information from the mainApp
            app.mainApp = OMZer_VmCaLinked;
            app.mainAppUIFigure = app.mainApp.OMZer_VmCaLinked_UIFigure;

            app.dirName = app.mainApp.dirName;
            app.fileName = app.mainApp.fileName;

            app.data = app.mainApp.data;
            app.acqFreq = app.mainApp.acqFreq;
            app.markerXY = app.mainApp.markerXY;
            app.signalTime = app.mainApp.signalTime;
            app.cmosDataGroup = app.mainApp.cmosDataGroup;
            app.bgImageNomGroup = app.mainApp.bgImageNomGroup;
            app.cameraSource = app.mainApp.cameraSource;

            app.exportMapName = app.mainApp.exportMapName;
            app.exportMapDataTitle = app.mainApp.exportMapDataTitle;
            app.exportMapData = app.mainApp.exportMapData;

            % (3) Diable Toolbar
            % https://www.mathworks.com/help/matlab/ref/axtoolbar.html?searchHighlight=axtoolbar&s_tid=doc_srchtitle
            try
                disableDefaultInteractivity(app.colorBarAxes);
                disableDefaultInteractivity(app.MapAxis);
                disableDefaultInteractivity(app.MapHistAxis);
                disableDefaultInteractivity(app.checkSigAxis);

                axtoolbar(app.colorBarAxes);
                axtoolbar(app.MapAxis);
                axtoolbar(app.MapHistAxis);
                axtoolbar(app.checkSigAxis);

            catch
                % Do nothing
            end

            % (4.1) Plot background image
            [app.cmosDataRowNum, app.cmosDataColNum, ~] = size( app.cmosDataGroup{app.cameraSource} );
            setAxisLimit(app, app.cmosDataColNum, app.cmosDataRowNum);
            imagesc(  app.MapAxis, app.bgImageNomGroup{app.cameraSource} );

            % (4.2) Plot map
            app.MapTitleLabel.Text = app.mainApp.MapTitleLabel.Text;

            if isequal( app.exportMapName, app.mainApp.ActDeltaLabel.Text ) % Act map

                app.checkSigDataSectionTime = app.exportMapData{7};
                
                app.checkSigDataSectionCamera1 = app.exportMapData{8};
                app.checkSigDataSectionCamera2 = app.exportMapData{9};
                
                app.checkSigLocsActCamera1 = app.exportMapData{10};
                app.checkSigLocsActCamera2 = app.exportMapData{11};

                app.checkSigMapData = app.exportMapData{12};
                app.checkSigLB = app.exportMapData{5};
                app.checkSigUB = app.exportMapData{6};
                app.checkSigMapData( app.checkSigMapData < app.checkSigLB  |  app.checkSigMapData > app.checkSigUB ) = NaN;

                app.actLinkedMapPlot = plotMap(app, app.actLinkedMapPlot, app.checkSigMapData, app.checkSigLB, app.checkSigUB);
                app.actLinkedColorBarPlot = plotMapColorBar(app, app.actLinkedColorBarPlot, app.checkSigMapData, app.checkSigLB, app.checkSigUB);
                app.actLinkedHistPlot = plotMapHist(app, app.actLinkedHistPlot, app.checkSigMapData, app.checkSigLB, app.checkSigUB);
                
            elseif isequal( app.exportMapName, app.mainApp.RepDeltaLabel.Text ) % Rep map

                app.checkSigDataSectionTime = app.exportMapData{8};
                
                app.checkSigDataSectionCamera1 = app.exportMapData{9};
                app.checkSigDataSectionCamera2 = app.exportMapData{10};
                
                app.checkSigLocsRepCamera1 = app.exportMapData{11};
                app.checkSigLocsRepCamera2 = app.exportMapData{12};

                app.checkSigMapData = app.exportMapData{13};
                app.checkSigLB = app.exportMapData{6};
                app.checkSigUB = app.exportMapData{7};
                app.checkSigMapData( app.checkSigMapData < app.checkSigLB  |  app.checkSigMapData > app.checkSigUB ) = NaN;
                
                app.repLinkedMapPlot = plotMap(app, app.repLinkedMapPlot, app.checkSigMapData, app.checkSigLB, app.checkSigUB);
                app.repLinkedColorBarPlot = plotMapColorBar(app, app.repLinkedColorBarPlot, app.checkSigMapData, app.checkSigLB, app.checkSigUB);
                app.repLinkedHistPlot = plotMapHist(app, app.repLinkedHistPlot, app.checkSigMapData, app.checkSigLB, app.checkSigUB);

            elseif isequal( app.exportMapName, app.mainApp.DurationDeltaLabel.Text ) % Duration map

                app.checkSigDataSectionTime = app.exportMapData{8};
                
                app.checkSigDataSectionCamera1 = app.exportMapData{9};
                app.checkSigDataSectionCamera2 = app.exportMapData{10};
                
                app.checkSigLocsActCamera1 = app.exportMapData{11};
                app.checkSigLocsActCamera2 = app.exportMapData{12};
                
                app.checkSigLocsRepCamera1 = app.exportMapData{13};
                app.checkSigLocsRepCamera2 = app.exportMapData{14};

                app.checkSigMapData = app.exportMapData{17};
                app.checkSigLB = app.exportMapData{6};
                app.checkSigUB = app.exportMapData{7};
                app.checkSigMapData( app.checkSigMapData < app.checkSigLB  |  app.checkSigMapData > app.checkSigUB ) = NaN;
                
                app.durationLinkedMapPlot = plotMap(app, app.durationLinkedMapPlot, app.checkSigMapData, app.checkSigLB, app.checkSigUB);
                app.durationLinkedColorBarPlot = plotMapColorBar(app, app.durationLinkedColorBarPlot, app.checkSigMapData, app.checkSigLB, app.checkSigUB);
                app.durationLinkedHistPlot = plotMapHist(app, app.durationLinkedHistPlot, app.checkSigMapData, app.checkSigLB, app.checkSigUB);
            end

            % (5) Plot initial marker
            app.checkSigMarkerXY = [ round(0.5*app.cmosDataRowNum), round(0.5*app.cmosDataColNum) ];
            app.CheckSigX_scrn.Value = app.checkSigMarkerXY(1,1);
            app.CheckSigY_scrn.Value = app.checkSigMarkerXY(1,2);

            CheckSigXY_scrnValueChanged(app)

            close(progressBar)

        end

        % Value changed function: PixelListBox
        function PixelListBoxValueChanged(app, event)
            
            app.CheckSigX_scrn.Value = app.exportPixelX( str2double(app.PixelListBox.Value) );
            app.CheckSigY_scrn.Value = app.exportPixelY( str2double(app.PixelListBox.Value) );

            CheckSigXY_scrnValueChanged(app, event)

        end

        % Image clicked function: Image_AddIntoPixel
        function Image_AddIntoPixelClicked(app, event)
            
            showImageIconAct(app, app.Image_AddIntoPixel)

            app.exportPixelX(app.pixelSaveToID) = app.CheckSigX_scrn.Value;
            app.exportPixelY(app.pixelSaveToID) = app.CheckSigY_scrn.Value;

            app.PixelListBox.Value = {};
            updatePixelListBox(app)

            app.pixelSaveToID = app.pixelSaveToID + 1;

        end

        % Image clicked function: Image_DeletefromPixel
        function Image_DeletefromPixelClicked(app, event)
            
            showImageIconAct(app, app.Image_DeletefromPixel)

            if isempty( app.PixelListBox.Value )

                msg = 'Choose one pixel from the "export list" to delete';
                uialert(app.OMZer_VmCaLinked_ExportDisplayPixel_UIFigure, msg,'Warning','Icon','error')

            else

                app.exportPixelX( str2double(app.PixelListBox.Value) ) = [];
                app.exportPixelY( str2double(app.PixelListBox.Value) ) = [];

                app.PixelListBox.Value = {};
                updatePixelListBox(app)

                app.pixelSaveToID = app.pixelSaveToID - 1;

                app.checkSigMarkerXY = [ round(0.5*app.cmosDataRowNum), round(0.5*app.cmosDataColNum) ];
                app.CheckSigX_scrn.Value = app.checkSigMarkerXY(1,1);
                app.CheckSigY_scrn.Value = app.checkSigMarkerXY(1,2);

                CheckSigXY_scrnValueChanged(app, event)
            end

        end

        % Image clicked function: Image_Export
        function Image_ExportClicked(app, event)
            
            showImageIconAct(app, app.Image_Export)

            if isempty( app.exportPixelX )

                msg = 'There is no pixel to be exported';
                uialert(app.OMZer_VmCaLinked_ExportDisplayPixel_UIFigure, msg,'Warning','Icon','error')

            else

                % Get valid saving directory and file name
                saveDir = uigetdir;

                if saveDir == 0 % User has NOT selected file directory.

                    msg = 'Please select a valid saving directory';
                    uialert(app.OMZer_VmCaLinked_ExportDisplayPixel_UIFigure,msg,'Warning','Icon','error')

                else

                    prompt = {'Enter filename to save (without extension)...   Please do not use \/:*?"<>|'};
                    dlgtitle = 'Save to a .mat file';
                    dfinput = { [ app.fileName, '_Vm_', app.exportMapName, '_Pixel_Data' ] };
                    dims = [1,100];
                    filename = inputdlg(prompt,dlgtitle,dims,dfinput);

                    if isempty(filename) % User has NOT entered a name

                        msg = 'Please enter a valid file name';
                        uialert(app.OMZer_VmCaLinked_ExportDisplayPixel_UIFigure,msg,'Warning','Icon','error')

                    else

                        mapPlotTitle = app.MapTitleLabel.Text;

                        acquisitionFrequency_Hz_Unit = app.acqFreq;

                        pixelTime_sec_Unit = app.checkSigDataSectionTime;
                        if isrow(pixelTime_sec_Unit)
                            pixelTime_sec_Unit = pixelTime_sec_Unit';
                        end

                        pixelX = app.exportPixelX;
                        if isrow(pixelX)
                            pixelX = pixelX';
                        end

                        pixelY = app.exportPixelY;
                        if isrow(pixelY)
                            pixelY = pixelY';
                        end

                        pixelSignalCamera1 = NaN( length(app.checkSigDataSectionTime), length(app.exportPixelX) );
                        pixelSignalCamera2 = NaN( length(app.checkSigDataSectionTime), length(app.exportPixelX) );
                        for ID = 1 : length(app.exportPixelX)

                            rowID = app.exportPixelY(ID);
                            colID = app.exportPixelX(ID);
                            
                            sigCamera1 = squeeze( app.checkSigDataSectionCamera1( rowID, colID, : ) );
                            sigCamera2 = squeeze( app.checkSigDataSectionCamera2( rowID, colID, : ) );

                            if isrow(sigCamera1)
                                sigCamera1 = sigCamera1';
                            end
                            pixelSignalCamera1(:,ID) = sigCamera1; % Each column is a pixel
                            
                            if isrow(sigCamera2)
                                sigCamera2 = sigCamera2';
                            end
                            pixelSignalCamera2(:,ID) = sigCamera2; % Each column is a pixel
                        end
                        
                        backgroundImage = app.bgImageNomGroup{app.cameraSource};

                        msg = [ 'Export the ', app.exportMapName, ' Pixel Data' ];
                        progressBar = uiprogressdlg(app.OMZer_VmCaLinked_ExportDisplayPixel_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');

                        fullSaveName = strcat(saveDir, '/', filename, '.mat'); % At this moment, 'fullSaveName' is a 'cell'.
                        fullSaveName = fullSaveName{1}; % Now 'fullSaveName' is a 'character
                        save(fullSaveName, ...
                            'mapPlotTitle', ...
                            'acquisitionFrequency_Hz_Unit', ...
                            'pixelTime_sec_Unit', ...
                            'pixelX', ...
                            'pixelY', ...
                            'pixelSignalCamera1', ...
                            'pixelSignalCamera2', ...
                            'backgroundImage');

                        if isequal( app.exportMapName, app.mainApp.ActDeltaLabel.Text ) % Act map

                            locsActCamera1 = NaN( length(app.exportPixelX), 1 );
                            locsActCamera2 = NaN( length(app.exportPixelX), 1 );
                            actDelta_Camera1_Minus_Camera2_ms_Unit = NaN( length(app.exportPixelX), 1 );
                            for ID = 1 : length(app.exportPixelX)

                                rowID = app.exportPixelY(ID);
                                colID = app.exportPixelX(ID);
                                locsActCamera1(ID,1) = app.exportMapData{10}( rowID, colID );
                                locsActCamera2(ID,1) = app.exportMapData{11}( rowID, colID );
                                actDelta_Camera1_Minus_Camera2_ms_Unit(ID,1) = app.exportMapData{12}( rowID, colID );
                            end

                            LB = app.exportMapData{5};
                            UB = app.exportMapData{6};
                            actDeltaMap_Camera1_Minus_Camera2 = app.exportMapData{12};
                            actDeltaMap_Camera1_Minus_Camera2( actDeltaMap_Camera1_Minus_Camera2 < LB  |  actDeltaMap_Camera1_Minus_Camera2 > UB ) = NaN;

                            mapUserComment = app.exportMapData{14};

                            save(fullSaveName, ...
                                'locsActCamera1', ...
                                'locsActCamera2', ...
                                'actDelta_Camera1_Minus_Camera2_ms_Unit', ...
                                'actDeltaMap_Camera1_Minus_Camera2', ...
                                'mapUserComment', ...
                                '-append');

                        elseif isequal( app.exportMapName, app.mainApp.RepDeltaLabel.Text ) % Rep map

                            locsRepCamera1 = NaN( length(app.exportPixelX), 1 );
                            locsRepCamera2 = NaN( length(app.exportPixelX), 1 );
                            repDelta_Camera1_Minus_Camera2_ms_Unit = NaN( length(app.exportPixelX), 1 );
                            for ID = 1 : length(app.exportPixelX)

                                rowID = app.exportPixelY(ID);
                                colID = app.exportPixelX(ID);
                                locsRepCamera1(ID,1) = app.exportMapData{11}( rowID, colID );
                                locsRepCamera2(ID,1) = app.exportMapData{12}( rowID, colID );
                                repDelta_Camera1_Minus_Camera2_ms_Unit(ID,1) = app.exportMapData{13}( rowID, colID );
                            end

                            LB = app.exportMapData{6};
                            UB = app.exportMapData{7};
                            repDeltaMap_Camera1_Minus_Camera2 = app.exportMapData{13};
                            repDeltaMap_Camera1_Minus_Camera2( repDeltaMap_Camera1_Minus_Camera2 < LB  |  repDeltaMap_Camera1_Minus_Camera2 > UB ) = NaN;

                            mapUserComment = app.exportMapData{15};

                            save(fullSaveName, ...
                                'locsRepCamera1', ...
                                'locsRepCamera2', ...
                                'repDelta_Camera1_Minus_Camera2_ms_Unit', ...
                                'repDeltaMap_Camera1_Minus_Camera2', ...
                                'mapUserComment', ...
                                '-append');

                        elseif isequal( app.exportMapName, app.mainApp.DurationDeltaLabel.Text ) % Duration map

                            locsActCamera1 = NaN( length(app.exportPixelX), 1 );
                            locsActCamera2 = NaN( length(app.exportPixelX), 1 );
                            locsRepCamera1 = NaN( length(app.exportPixelX), 1 );
                            locsRepCamera2 = NaN( length(app.exportPixelX), 1 );
                            DurationCamera1_ms_Unit = NaN( length(app.exportPixelX), 1 );
                            DurationCamera2_ms_Unit = NaN( length(app.exportPixelX), 1 );
                            DurationDelta_Camera1_Minus_Camera2_ms_Unit = NaN( length(app.exportPixelX), 1 );
                            for ID = 1 : length(app.exportPixelX)

                                rowID = app.exportPixelY(ID);
                                colID = app.exportPixelX(ID);
                                locsActCamera1(ID,1) = app.exportMapData{11}( rowID, colID );
                                locsActCamera2(ID,1) = app.exportMapData{12}( rowID, colID );
                                locsRepCamera1(ID,1) = app.exportMapData{13}( rowID, colID );
                                locsRepCamera2(ID,1) = app.exportMapData{14}( rowID, colID );
                                DurationCamera1_ms_Unit(ID,1) = app.exportMapData{15}( rowID, colID );
                                DurationCamera2_ms_Unit(ID,1) = app.exportMapData{16}( rowID, colID );
                                DurationDelta_Camera1_Minus_Camera2_ms_Unit(ID,1) = app.exportMapData{17}( rowID, colID );
                            end

                            LB = app.exportMapData{6};
                            UB = app.exportMapData{7};
                            DurationDeltaMap_Camera1_Minus_Camera2 = app.exportMapData{17};
                            DurationDeltaMap_Camera1_Minus_Camera2( DurationDeltaMap_Camera1_Minus_Camera2 < LB  |  DurationDeltaMap_Camera1_Minus_Camera2 > UB ) = NaN;

                            mapUserComment = app.exportMapData{19};

                            save(fullSaveName, ...
                                'locsActCamera1', ...
                                'locsActCamera2', ...
                                'locsRepCamera1', ...
                                'locsRepCamera2', ...
                                'DurationCamera1_ms_Unit', ...
                                'DurationCamera2_ms_Unit', ...
                                'DurationDelta_Camera1_Minus_Camera2_ms_Unit', ...
                                'DurationDeltaMap_Camera1_Minus_Camera2', ...
                                'mapUserComment', ...
                                '-append');
                        end

                        close(progressBar)
                    end
                end
            end

            figure(app.OMZer_VmCaLinked_ExportDisplayPixel_UIFigure)

        end

        % Value changed function: CheckSigX_scrn, CheckSigY_scrn
        function CheckSigXY_scrnValueChanged(app, event)
            
            app.checkSigMarkerXY(1,1) = app.CheckSigX_scrn.Value;
            app.checkSigMarkerXY(1,2) = app.CheckSigY_scrn.Value;

            checkSigColor = [0.00, 0.45, 0.74];

            % (1.1) Marker plot
            delete(app.checkSigMarkerPlot)
            app.checkSigMarkerPlot = updateCheckSigMarker(app, checkSigColor);

            % (1.2) Marker Highlight Plot
            delete(app.checkSigMarkerHighlightPlot)
            app.checkSigMarkerHighlightPlot = updateCheckSigMarkerHighlight(app);

            % (2) Update the signal
            if isequal( app.exportMapName, app.mainApp.ActDeltaLabel.Text ) % Act map

                delete(app.checkSigPlotCamera1)
                delete(app.checkSigPlotCamera2)
                [ app.checkSigPlotCamera1, app.checkSigPlotCamera2 ] = updateCheckSig(app, app.checkSigDataSectionTime, app.checkSigDataSectionCamera1, app.checkSigDataSectionCamera2, checkSigColor);

                delete(app.checkSigActPointPlotCamera1)
                delete(app.checkSigActPointPlotCamera2)
                [ app.checkSigActPointPlotCamera1, app.checkSigActPointPlotCamera2 ] = updateCheckSigActPoints(app, app.checkSigDataSectionTime, app.checkSigDataSectionCamera1, app.checkSigDataSectionCamera2);
                
                delete(app.checkSigHistIndicatingLinePlot)
                app.checkSigHistIndicatingLinePlot = updatecheckSigHistIndicatingLine(app, app.checkSigMapData);
                
            elseif isequal( app.exportMapName, app.mainApp.RepDeltaLabel.Text ) % Rep map

                delete(app.checkSigPlotCamera1)
                delete(app.checkSigPlotCamera2)
                [ app.checkSigPlotCamera1, app.checkSigPlotCamera2 ] = updateCheckSig(app, app.checkSigDataSectionTime, app.checkSigDataSectionCamera1, app.checkSigDataSectionCamera2, checkSigColor);

                delete(app.checkSigRepPointPlotCamera1)
                delete(app.checkSigRepPointPlotCamera2)
                [ app.checkSigRepPointPlotCamera1, app.checkSigRepPointPlotCamera2 ] = updateCheckSigRepPoints(app, app.checkSigDataSectionTime, app.checkSigDataSectionCamera1, app.checkSigDataSectionCamera2);

                delete(app.checkSigHistIndicatingLinePlot)
                app.checkSigHistIndicatingLinePlot = updatecheckSigHistIndicatingLine(app, app.checkSigMapData);

            elseif isequal( app.exportMapName, app.mainApp.DurationDeltaLabel.Text ) % Duration map

                delete(app.checkSigPlotCamera1)
                delete(app.checkSigPlotCamera2)
                [ app.checkSigPlotCamera1, app.checkSigPlotCamera2 ] = updateCheckSig(app, app.checkSigDataSectionTime, app.checkSigDataSectionCamera1, app.checkSigDataSectionCamera2, checkSigColor);

                delete(app.checkSigActPointPlotCamera1)
                delete(app.checkSigActPointPlotCamera2)
                [ app.checkSigActPointPlotCamera1, app.checkSigActPointPlotCamera2 ] = updateCheckSigActPoints(app, app.checkSigDataSectionTime, app.checkSigDataSectionCamera1, app.checkSigDataSectionCamera2);
                
                delete(app.checkSigRepPointPlotCamera1)
                delete(app.checkSigRepPointPlotCamera2)
                [ app.checkSigRepPointPlotCamera1, app.checkSigRepPointPlotCamera2 ] = updateCheckSigRepPoints(app, app.checkSigDataSectionTime, app.checkSigDataSectionCamera1, app.checkSigDataSectionCamera2);

                delete(app.checkSigHistIndicatingLinePlot)
                app.checkSigHistIndicatingLinePlot = updatecheckSigHistIndicatingLine(app, app.checkSigMapData);
            end

        end

        % Window button down function: 
        % OMZer_VmCaLinked_ExportDisplayPixel_UIFigure
        function OMZer_VmCaLinked_ExportDisplayPixel_UIFigureWindowButtonDown(app, event)
            
            app.mouseMotion = app.OMZer_VmCaLinked_ExportDisplayPixel_UIFigure.SelectionType; % The result can be 'normal', 'extend', 'alt', 'open'

            if isequal(app.mouseMotion,'normal') % 'normal' means single-click any mouse button.

                app.mouseXY = app.OMZer_VmCaLinked_ExportDisplayPixel_UIFigure.CurrentPoint; % Returns (x, y) coordinates of the mouse pointer, measured from the lower-left corner of the figure.
                app.mapAxisLocs = app.MapAxis.InnerPosition; % Returns a 1-by-4 row vector -  [left bottom width height].

                if ( app.mouseXY(1,1) >= app.mapAxisLocs(1,1) )  ...
                        &&  ( app.mouseXY(1,1) <= app.mapAxisLocs(1,1) + app.mapAxisLocs(1,3) )  ...
                        &&  ( app.mouseXY(1,2) >= app.mapAxisLocs(1,2) )  ...
                        &&  ( app.mouseXY(1,2) <= app.mapAxisLocs(1,2) + app.mapAxisLocs(1,4) )

                    updateMouseXY(app, app.mapAxisLocs);

                    if ( abs(app.mouseXY(1,1) - app.checkSigMarkerXY(1,1)) <= 5 )  &&  ( abs(app.mouseXY(1,2) - app.checkSigMarkerXY(1,2)) <= 5 ) % Find which marker is within 5 pixels of the mouse location

                        app.checkSigMouseDragID = 1;
                    end
                end
            end

        end

        % Window button motion function: 
        % OMZer_VmCaLinked_ExportDisplayPixel_UIFigure
        function OMZer_VmCaLinked_ExportDisplayPixel_UIFigureWindowButtonMotion(app, event)
            
            if ~isempty(app.checkSigMouseDragID)

                app.mouseXY = app.OMZer_VmCaLinked_ExportDisplayPixel_UIFigure.CurrentPoint; % Returns (x, y) coordinates of the mouse pointer, measured from the lower-left corner of the figure.
                app.mapAxisLocs = app.MapAxis.InnerPosition; % Returns a 1-by-4 row vector -  [left bottom width height].

                if ( app.mouseXY(1,1) >= app.mapAxisLocs(1,1) )  ...
                        &&  ( app.mouseXY(1,1) <= app.mapAxisLocs(1,1) + app.mapAxisLocs(1,3) )  ...
                        &&  ( app.mouseXY(1,2) >= app.mapAxisLocs(1,2) )  ...
                        &&  ( app.mouseXY(1,2) <= app.mapAxisLocs(1,2) + app.mapAxisLocs(1,4) )

                    updateMouseXY(app, app.mapAxisLocs);

                    app.checkSigMarkerXY(1,1) = app.mouseXY(1,1);
                    app.checkSigMarkerXY(1,2) = app.mouseXY(1,2);

                    app.CheckSigX_scrn.Value = app.checkSigMarkerXY(1,1);
                    app.CheckSigY_scrn.Value = app.checkSigMarkerXY(1,2);

                    CheckSigXY_scrnValueChanged(app, event)
                end
            end

        end

        % Window button up function: 
        % OMZer_VmCaLinked_ExportDisplayPixel_UIFigure
        function OMZer_VmCaLinked_ExportDisplayPixel_UIFigureWindowButtonUp(app, event)
            
            app.checkSigMouseDragID = [];

        end

        % Close request function: 
        % OMZer_VmCaLinked_ExportDisplayPixel_UIFigure
        function OMZer_VmCaLinked_ExportDisplayPixel_UIFigureCloseRequest(app, event)
            
            % (1) Set mainApp at the top level
            figure(app.mainAppUIFigure)

            % (2) Update the mainApp
            app.mainApp.OMZer_VmCaLinked_ExportDisplayPixel = [];

            delete(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OMZer_VmCaLinked_ExportDisplayPixel_UIFigure = uifigure('Visible', 'off');
            app.OMZer_VmCaLinked_ExportDisplayPixel_UIFigure.Name = 'OMZer Vm-Ca Linked Export Display Pixel';
            app.OMZer_VmCaLinked_ExportDisplayPixel_UIFigure.CloseRequestFcn = createCallbackFcn(app, @OMZer_VmCaLinked_ExportDisplayPixel_UIFigureCloseRequest, true);
            app.OMZer_VmCaLinked_ExportDisplayPixel_UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @OMZer_VmCaLinked_ExportDisplayPixel_UIFigureWindowButtonDown, true);
            app.OMZer_VmCaLinked_ExportDisplayPixel_UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @OMZer_VmCaLinked_ExportDisplayPixel_UIFigureWindowButtonUp, true);
            app.OMZer_VmCaLinked_ExportDisplayPixel_UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @OMZer_VmCaLinked_ExportDisplayPixel_UIFigureWindowButtonMotion, true);
            app.checkSigAxis = uiaxes(app.OMZer_VmCaLinked_ExportDisplayPixel_UIFigure);
            xlabel(app.checkSigAxis, 'Time (sec)')
            app.colorBarAxes = uiaxes(app.OMZer_VmCaLinked_ExportDisplayPixel_UIFigure);
            title(app.colorBarAxes, 'ms')
            app.colorBarAxes.XTick = [];
            app.MapAxis = uiaxes(app.OMZer_VmCaLinked_ExportDisplayPixel_UIFigure);
            app.MapAxis.XLim = [1 100];
            app.MapAxis.YLim = [1 100];
            app.MapAxis.YDir = 'reverse';
            app.MapAxis.XTick = [];
            app.MapAxis.YTick = [];
            app.MapAxis.ZTick = [];
            app.MapHistAxis = uiaxes(app.OMZer_VmCaLinked_ExportDisplayPixel_UIFigure);
            xlabel(app.MapHistAxis, 'Time (ms)')
            ylabel(app.MapHistAxis, 'Counts')
            app.MapHistAxis.ZTick = [];
            app.CheckSigXYLabel = uilabel(app.OMZer_VmCaLinked_ExportDisplayPixel_UIFigure);
            app.CheckSigXYLabel.Tooltip = {'X (red) and Y (blue) coordinate of the marker. Please be noted that X is column and Y is row to locate an element in a matrix in MATLAB.'};
            app.CheckSigXYLabel.Text = 'X,Y';
            app.CheckSigY_scrn = uieditfield(app.OMZer_VmCaLinked_ExportDisplayPixel_UIFigure, 'numeric');
            app.CheckSigY_scrn.Limits = [0 100];
            app.CheckSigY_scrn.ValueDisplayFormat = '%.0f';
            app.CheckSigY_scrn.ValueChangedFcn = createCallbackFcn(app, @CheckSigXY_scrnValueChanged, true);
            app.CheckSigY_scrn.Tooltip = {'Y coordinate of the 1st marker (Note: row in a matlab matrix)'};
            app.CheckSigX_scrn = uieditfield(app.OMZer_VmCaLinked_ExportDisplayPixel_UIFigure, 'numeric');
            app.CheckSigX_scrn.Limits = [0 100];
            app.CheckSigX_scrn.ValueDisplayFormat = '%.0f';
            app.CheckSigX_scrn.ValueChangedFcn = createCallbackFcn(app, @CheckSigXY_scrnValueChanged, true);
            app.CheckSigX_scrn.Tooltip = {'X coordinate of the 1st marker (Note: column in a matlab matrix)'};
            app.Image_DeletefromPixel = uiimage(app.OMZer_VmCaLinked_ExportDisplayPixel_UIFigure);
            app.Image_DeletefromPixel.ImageClickedFcn = createCallbackFcn(app, @Image_DeletefromPixelClicked, true);
            app.Image_DeletefromPixel.ImageSource = 'Icon Delete.png';
            app.DeletefromPixelLabel = uilabel(app.OMZer_VmCaLinked_ExportDisplayPixel_UIFigure);
            app.DeletefromPixelLabel.Text = 'Delete from Pixel';
            app.Image_AddIntoPixel = uiimage(app.OMZer_VmCaLinked_ExportDisplayPixel_UIFigure);
            app.Image_AddIntoPixel.ImageClickedFcn = createCallbackFcn(app, @Image_AddIntoPixelClicked, true);
            app.Image_AddIntoPixel.ImageSource = 'Icon Plus.png';
            app.AddIntoPixelLabel = uilabel(app.OMZer_VmCaLinked_ExportDisplayPixel_UIFigure);
            app.AddIntoPixelLabel.Text = 'Add Into Pixel';
            app.Image_Export = uiimage(app.OMZer_VmCaLinked_ExportDisplayPixel_UIFigure);
            app.Image_Export.ImageClickedFcn = createCallbackFcn(app, @Image_ExportClicked, true);
            app.Image_Export.ImageSource = 'Icon Export.png';
            app.ExportLabel = uilabel(app.OMZer_VmCaLinked_ExportDisplayPixel_UIFigure);
            app.ExportLabel.Text = 'Export';
            app.MapTitleLabel = uilabel(app.OMZer_VmCaLinked_ExportDisplayPixel_UIFigure);
            app.MapTitleLabel.Text = 'Map Title';
            app.PixelListBoxLabel = uilabel(app.OMZer_VmCaLinked_ExportDisplayPixel_UIFigure);
            app.PixelListBoxLabel.Text = 'Pixel';
            app.PixelListBox = uilistbox(app.OMZer_VmCaLinked_ExportDisplayPixel_UIFigure);
            app.PixelListBox.Items = {};
            app.PixelListBox.ValueChangedFcn = createCallbackFcn(app, @PixelListBoxValueChanged, true);
            app.PixelListBox.Value = {};
            app.Image_ActivatedCamera = uiimage(app.OMZer_VmCaLinked_ExportDisplayPixel_UIFigure);
            app.Image_ActivatedCamera.Tooltip = {'(This is NOT a button) Tell the user from which camera the signals are'};
            app.Image_ActivatedCamera.ImageSource = 'Icon Activated Camera.png';
            app.Camera1solidlineblueCamera2DashdottedlineredLabel = uilabel(app.OMZer_VmCaLinked_ExportDisplayPixel_UIFigure);
            app.Camera1solidlineblueCamera2DashdottedlineredLabel.Text = {'Camera 1 - solid line (blue)'; ''; 'Camera 2 - Dash-dotted line (red)'};
            % Show the figure after all components are created
            app.OMZer_VmCaLinked_ExportDisplayPixel_UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OMZer_VmCaLinked_ExportDisplayPixel(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.OMZer_VmCaLinked_ExportDisplayPixel_UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.OMZer_VmCaLinked_ExportDisplayPixel_UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OMZer_VmCaLinked_ExportDisplayPixel_UIFigure)
        end
    end
end