classdef OMZer_VmCaLinked_Signal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OMZer_VmCaLinked_Signal_UIFigure  matlab.ui.Figure
        TypeEndsecEditField         matlab.ui.control.NumericEditField
        TypeEndsecEditFieldLabel    matlab.ui.control.Label
        TypeStartsecEditField       matlab.ui.control.NumericEditField
        TypeStartsecEditFieldLabel  matlab.ui.control.Label
        WinEndsecEditField          matlab.ui.control.NumericEditField
        WinEndsecEditFieldLabel     matlab.ui.control.Label
        WinStartsecEditField        matlab.ui.control.NumericEditField
        WinStartsecEditFieldLabel   matlab.ui.control.Label
        MultiBeatsListBox           matlab.ui.control.ListBox
        MultiBeatsListBoxLabel      matlab.ui.control.Label
        ViewEndsecSpinner           matlab.ui.control.Spinner
        ViewEndsecSpinnerLabel      matlab.ui.control.Label
        ViewStartsecSpinner         matlab.ui.control.Spinner
        ViewStartsecSpinnerLabel    matlab.ui.control.Label
        AddIntoMultiBeatsLabel      matlab.ui.control.Label
        Image_AddIntoMultiBeats     matlab.ui.control.Image
        ButtonGroup                 matlab.ui.container.ButtonGroup
        MultiBeatsButton            matlab.ui.control.RadioButton
        OneBeatButton               matlab.ui.control.RadioButton
        DeletefromMultiBeatsLabel   matlab.ui.control.Label
        Image_DeletefromMultiBeats  matlab.ui.control.Image
        pacing2CheckBox             matlab.ui.control.CheckBox
        pacing1CheckBox             matlab.ui.control.CheckBox
        Camera1solidlineCamera2DashdottedlineLabel  matlab.ui.control.Label
        Image_ActivatedCamera       matlab.ui.control.Image
        RestoreViewLabel            matlab.ui.control.Label
        Image_RestoreView           matlab.ui.control.Image
        X_scrn6                     matlab.ui.control.NumericEditField
        Y_scrn6                     matlab.ui.control.NumericEditField
        X_scrn5                     matlab.ui.control.NumericEditField
        Y_scrn5                     matlab.ui.control.NumericEditField
        X_scrn4                     matlab.ui.control.NumericEditField
        Y_scrn4                     matlab.ui.control.NumericEditField
        X_scrn3                     matlab.ui.control.NumericEditField
        Y_scrn3                     matlab.ui.control.NumericEditField
        X_scrn2                     matlab.ui.control.NumericEditField
        Y_scrn2                     matlab.ui.control.NumericEditField
        X_scrn1                     matlab.ui.control.NumericEditField
        Y_scrn1                     matlab.ui.control.NumericEditField
        XYLabel                     matlab.ui.control.Label
        scrn6                       matlab.ui.control.UIAxes
        scrn5                       matlab.ui.control.UIAxes
        scrn4                       matlab.ui.control.UIAxes
        scrn3                       matlab.ui.control.UIAxes
        scrn2                       matlab.ui.control.UIAxes
        scrn1                       matlab.ui.control.UIAxes
    end


    properties (Access = public)

        % The Parent App --------------------
        mainApp
        mainAppUIFigure


        % The Children App --------------------


        % The Current App --------------------
        setup % User preference

        imageIconActColor = [0.80, 0.80, 0.80]; % The activation color when an image is clicked

        ColorID
        Color1 = [0.00,0.45,0.74];
        Color2 = [0.93,0.69,0.13];
        Color3 = [0.64,0.08,0.18];
        Color4 = [0.72,0.27,1.00];
        Color5 = [0.47,0.67,0.19];
        Color6 = [0.89,0.47,0.58];

        lWidth = 2; % Line width

        dirName % File directory
        fileName % Filename without path and extension

        data
        acqFreq
        markerXY
        signalTime
        cmosDataGroup
        bgImageNomGroup % Gray colour RGB version of background image
        cameraSource % '1 (or 2)' - data from camera 1 (or 2)

        pacing1 % Pacing signal recorded by channel 1
        pacing2 % Pacing signal recorded by channel 2
        pacingTime % Time points to plot pacing signal
        pacingPlot = cell(2,6); % Row 1 (or 2) - pacing 1 (or 2)

        scrnObject
        Xs
        Ys

        % Beat selection
        multiBeatsSaveToID = 1; % The ID of beat to be saved

        oneBeatWindowStartIndex
        oneBeatWindowEndIndex

        multiBeatsWindowStartIndex
        multiBeatsWindowEndIndex

        typeWindowSelectionPatchColor = [0.89, 0.47, 0.58]; % Pink
        oneBeatFromMultiBeatsListPatchColor = [0.47, 0.67, 0.19] % Green

        typeWindowSelectionPatchPlot = cell(1,6); % 6 patches on 6 scrn axises
        oneBeatFromMultiBeatsListPatchPlot = cell(1,6);
    end


    methods (Access = public)

        function showImageIconAct(app, imageIconName)

            imageIconName.BackgroundColor = app.imageIconActColor; %[0.80, 0.80, 0.80];
            pause(0.05)
            imageIconName.BackgroundColor = 'none';
        end


        function updateTypeWindowSelectionPatch(app)

            xLeft = app.signalTime(app.oneBeatWindowStartIndex);
            xRight = app.signalTime(app.oneBeatWindowEndIndex);
            yBottom = app.scrn1.YLim(1);
            yTop = app.scrn1.YLim(2);

            for ID = 1 : size(app.markerXY,1)

                delete(app.typeWindowSelectionPatchPlot{ID})
                app.typeWindowSelectionPatchPlot{ID} = patch( app.scrnObject{ID}, [xLeft, xRight, xRight, xLeft], [yBottom, yBottom, yTop, yTop], app.typeWindowSelectionPatchColor, 'FaceAlpha',0.3, 'EdgeColor','none');
            end
        end


        function updateOneBeatFromMultiBeatsListPatch(app, winStartIndex, winEndIndex)

            xLeft = app.signalTime(winStartIndex);
            xRight = app.signalTime(winEndIndex);
            yBottom = app.scrn1.YLim(1);
            yTop = app.scrn1.YLim(2);

            for ID = 1 : size(app.markerXY,1)

                delete(app.oneBeatFromMultiBeatsListPatchPlot{ID})
                app.oneBeatFromMultiBeatsListPatchPlot{ID} = patch( app.scrnObject{ID}, [xLeft, xRight, xRight, xLeft], [yBottom, yBottom, yTop, yTop], app.oneBeatFromMultiBeatsListPatchColor, 'FaceAlpha',0.3, 'EdgeColor','none');
            end
        end


        function updateMultiBeatsListBox(app)

            app.MultiBeatsListBox.Value = {};
            app.MultiBeatsListBox.Items = {};

            for ID = 1 : length( app.multiBeatsWindowStartIndex )
                app.MultiBeatsListBox.Items{ID} = num2str(ID);
            end

            for ID = 1 : size(app.markerXY,1)
                delete(app.oneBeatFromMultiBeatsListPatchPlot{ID})
                app.oneBeatFromMultiBeatsListPatchPlot{ID} = [];
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, OMZer_VmCaLinked)

            msg = 'Configuring files';
            progressBar = uiprogressdlg(app.OMZer_VmCaLinked_Signal_UIFigure, 'Title','Initialising', 'Message',msg, 'Indeterminate','on');
            drawnow

            % (1) Get information from the mainApp
            app.mainApp = OMZer_VmCaLinked;
            app.mainAppUIFigure = app.mainApp.OMZer_VmCaLinked_UIFigure;

            app.dirName = app.mainApp.dirName;
            app.fileName = app.mainApp.fileName;

            app.data = app.mainApp.data;
            app.acqFreq = app.mainApp.acqFreq;
            app.markerXY = app.mainApp.markerXY;
            app.signalTime = app.mainApp.signalTime;
            app.cmosDataGroup = app.mainApp.cmosDataGroup;
            app.bgImageNomGroup = app.mainApp.bgImageNomGroup;
            app.cameraSource = app.mainApp.cameraSource;

            app.pacing1 = app.mainApp.pacing1;
            app.pacing2 = app.mainApp.pacing2;
            app.pacingTime = app.mainApp.pacingTime;
            app.pacing1CheckBox.Enable = app.mainApp.pacing1Enable;
            app.pacing2CheckBox.Enable = app.mainApp.pacing2Enable;

            % (2) Plot all the signal traces if available
            % (2.1) Create components
            app.scrnObject = {app.scrn1, app.scrn2, app.scrn3, app.scrn4, app.scrn5, app.scrn6}; % Store 6 scrn handles in the cell 'scrnList'
            app.Xs = {app.X_scrn1, app.X_scrn2, app.X_scrn3, app.X_scrn4, app.X_scrn5, app.X_scrn6};
            app.Ys = {app.Y_scrn1, app.Y_scrn2, app.Y_scrn3, app.Y_scrn4, app.Y_scrn5, app.Y_scrn6};
            app.ColorID = {app.Color1, app.Color2, app.Color3, app.Color4, app.Color5, app.Color6}; % Store 6 color

            try
                for ID = 1 : 6
                    disableDefaultInteractivity( app.scrnObject{ID} );
                    axtoolbar( app.scrnObject{ID} );
                end

            catch
                % Do nothing
            end

            % (2.2) Set view limits
            app.ViewStartsecSpinner.Limits = [ 0, app.signalTime(end) ];
            app.ViewEndsecSpinner.Limits = [ 0, app.signalTime(end) ];

            app.ViewStartsecSpinner.Value = min(app.ViewStartsecSpinner.Limits);
            app.ViewEndsecSpinner.Value = max(app.ViewEndsecSpinner.Limits);

            for ID = 1:6
                xlim( app.scrnObject{ID}, [app.ViewStartsecSpinner.Value, app.ViewEndsecSpinner.Value] )
            end

            % (2.3) Plot signals
            for ID = 1:size(app.markerXY,1)

                row = app.markerXY(ID,2);
                col = app.markerXY(ID,1);

                sigCamera1 = squeeze( app.cmosDataGroup{1}(row,col,:) );
                sigCamera2 = squeeze( app.cmosDataGroup{2}(row,col,:) );

                line( app.scrnObject{ID}, app.signalTime, sigCamera1, 'LineStyle','-', 'LineWidth',app.lWidth, 'color',app.ColorID{ID} );
                line( app.scrnObject{ID}, app.signalTime, sigCamera2, 'LineStyle','-.', 'LineWidth',app.lWidth, 'color',app.ColorID{ID} );

                app.Xs{ID}.Value = app.markerXY(ID,1);
                app.Ys{ID}.Value = app.markerXY(ID,2);
            end

            % (3) Show default 'one beat' window selection
            app.TypeStartsecEditField.Limits = [ 0, app.signalTime(end) ];
            app.TypeEndsecEditField.Limits = [ 0, app.signalTime(end) ];

            % (3.1) Get the one beat window if exists
            app.oneBeatWindowStartIndex = app.mainApp.oneBeatWindowStartIndex;
            app.oneBeatWindowEndIndex = app.mainApp.oneBeatWindowEndIndex;

            % (3.2) Get the multi beats window if exists
            app.multiBeatsWindowStartIndex = app.mainApp.multiBeatsWindowStartIndex;
            app.multiBeatsWindowEndIndex = app.mainApp.multiBeatsWindowEndIndex;
            updateMultiBeatsListBox(app)
            app.multiBeatsSaveToID = length( app.multiBeatsWindowStartIndex ) + 1;

            % (3.3) Show default value
            if ~isempty(app.oneBeatWindowStartIndex)  &&  ~isempty(app.oneBeatWindowEndIndex)

                app.TypeStartsecEditField.Value = app.signalTime(app.oneBeatWindowStartIndex);
                app.TypeEndsecEditField.Value = app.signalTime(app.oneBeatWindowEndIndex);

            else
                app.TypeStartsecEditField.Value = 0.05 * app.signalTime(end);
                app.TypeEndsecEditField.Value = 2 * 0.05 * app.signalTime(end);
            end

            TypeStartEndsecEditFieldValueChanged(app)

            close(progressBar)

        end

        % Value changed function: pacing1CheckBox
        function pacing1CheckBoxValueChanged(app, event)

            for ID = 1:size(app.markerXY,1)
                delete(app.pacingPlot{1,ID})
            end

            if app.pacing1CheckBox.Value == 1
                for ID = 1:size(app.markerXY,1)
                    hold(app.scrnObject{ID},'on')
                    app.pacingPlot{1,ID} = stem(app.scrnObject{ID}, app.pacingTime, app.pacing1, 'LineWidth',2, 'color','black', 'Marker','none');
                    hold(app.scrnObject{ID},'off')
                end
            end

        end

        % Value changed function: pacing2CheckBox
        function pacing2CheckBoxValueChanged(app, event)

            for ID = 1:size(app.markerXY,1)
                delete(app.pacingPlot{2,ID})
            end

            if app.pacing2CheckBox.Value == 1
                for ID = 1:size(app.markerXY,1)
                    hold(app.scrnObject{ID},'on')
                    app.pacingPlot{2,ID} = stem(app.scrnObject{ID}, app.pacingTime, app.pacing2, 'LineWidth',2, 'color','black', 'Marker','none');
                    hold(app.scrnObject{ID},'off')
                end
            end

        end

        % Value changed function: ViewStartsecSpinner
        function ViewStartsecSpinnerValueChanged(app, event)

            if app.ViewStartsecSpinner.Value >= app.ViewEndsecSpinner.Value

                msg = 'Start time must be eariler than end time';
                uialert(app.OMZer_VmCaLinked_Signal_UIFigure,msg,'Warning','Icon','error')

                app.ViewStartsecSpinner.Value = 0;
                app.ViewEndsecSpinner.Value = app.signalTime(end);
            end

            for ID = 1:6
                xlim( app.scrnObject{ID}, [ app.ViewStartsecSpinner.Value, app.ViewEndsecSpinner.Value ] )
            end
        end

        % Value changing function: ViewStartsecSpinner
        function ViewStartsecSpinnerValueChanging(app, event)

            if ischar(event.Value)
                app.ViewStartsecSpinner.Value = str2double(event.Value);
            else
                app.ViewStartsecSpinner.Value = event.Value;
            end

            ViewStartsecSpinnerValueChanged(app, event)

        end

        % Value changed function: ViewEndsecSpinner
        function ViewEndsecSpinnerValueChanged(app, event)

            if app.ViewStartsecSpinner.Value >= app.ViewEndsecSpinner.Value

                msg = 'Start time must be eariler than end time';
                uialert(app.OMZer_VmCaLinked_Signal_UIFigure,msg,'Warning','Icon','error')

                app.ViewStartsecSpinner.Value = 0;
                app.ViewEndsecSpinner.Value = app.signalTime(end);
            end

            for ID = 1:6
                xlim( app.scrnObject{ID}, [ app.ViewStartsecSpinner.Value, app.ViewEndsecSpinner.Value ] )
            end

        end

        % Value changing function: ViewEndsecSpinner
        function ViewEndsecSpinnerValueChanging(app, event)

            if ischar(event.Value)
                app.ViewEndsecSpinner.Value = str2double(event.Value);
            else
                app.ViewEndsecSpinner.Value = event.Value;
            end

            ViewEndsecSpinnerValueChanged(app, event)

        end

        % Image clicked function: Image_RestoreView
        function Image_RestoreViewClicked(app, event)

            showImageIconAct(app, app.Image_RestoreView)

            app.ViewStartsecSpinner.Value = 0;
            app.ViewEndsecSpinner.Value = app.signalTime(end);

            for ID = 1:6
                xlim( app.scrnObject{ID}, [ min(app.ViewStartsecSpinner.Limits), max(app.ViewEndsecSpinner.Limits) ] )
            end

        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)

            if app.ButtonGroup.SelectedObject == app.OneBeatButton

                app.MultiBeatsListBox.Enable = 'off';
                app.Image_AddIntoMultiBeats.Enable = 'off';
                app.Image_DeletefromMultiBeats.Enable = 'off';

            elseif app.ButtonGroup.SelectedObject == app.MultiBeatsButton

                app.MultiBeatsListBox.Enable = 'on';
                app.Image_AddIntoMultiBeats.Enable = 'on';
                app.Image_DeletefromMultiBeats.Enable = 'on';
            end

            if ~isempty(app.oneBeatWindowStartIndex)  &&  ~isempty(app.oneBeatWindowEndIndex)

                app.TypeStartsecEditField.Value = app.signalTime(app.oneBeatWindowStartIndex);
                app.TypeEndsecEditField.Value = app.signalTime(app.oneBeatWindowEndIndex);

            else
                app.TypeStartsecEditField.Value = 0.05 * app.signalTime(end);
                app.TypeEndsecEditField.Value = 2 * 0.05 * app.signalTime(end);
            end

            TypeStartEndsecEditFieldValueChanged(app, event)

            updateMultiBeatsListBox(app)

        end

        % Value changed function: TypeEndsecEditField, 
        % TypeStartsecEditField
        function TypeStartEndsecEditFieldValueChanged(app, event)

            if app.TypeStartsecEditField.Value >= app.TypeEndsecEditField.Value

                msg = 'Start time must be eariler than end time';
                uialert(app.OMZer_VmCaLinked_Signal_UIFigure, msg,'Warning','Icon','error')

                app.TypeStartsecEditField.Value = 0.05 * app.signalTime(end);
                app.TypeEndsecEditField.Value = 2 * 0.05 * app.signalTime(end);
            end

            % (1) Find the window index
            app.oneBeatWindowStartIndex = find( app.signalTime >= app.TypeStartsecEditField.Value, 1,'first' );
            app.oneBeatWindowEndIndex = find( app.signalTime <= app.TypeEndsecEditField.Value, 1,'last' );

            app.mainApp.oneBeatWindowStartIndex = app.oneBeatWindowStartIndex;
            app.mainApp.oneBeatWindowEndIndex = app.oneBeatWindowEndIndex;

            % (2) Update the time display based on window index
            app.TypeStartsecEditField.Value = app.signalTime(app.oneBeatWindowStartIndex);
            app.TypeEndsecEditField.Value = app.signalTime(app.oneBeatWindowEndIndex);

            app.WinStartsecEditField.Value = app.signalTime(app.oneBeatWindowStartIndex);
            app.WinEndsecEditField.Value = app.signalTime(app.oneBeatWindowEndIndex);

            % (3) Update patch plot
            updateTypeWindowSelectionPatch(app)

        end

        % Value changed function: MultiBeatsListBox
        function MultiBeatsListBoxValueChanged(app, event)
            
            ID = str2double(app.MultiBeatsListBox.Value);

            winStartIndex = app.multiBeatsWindowStartIndex(ID);
            winEndIndex = app.multiBeatsWindowEndIndex(ID);

            updateOneBeatFromMultiBeatsListPatch(app, winStartIndex, winEndIndex)

        end

        % Image clicked function: Image_AddIntoMultiBeats
        function Image_AddIntoMultiBeatsClicked(app, event)

            showImageIconAct(app, app.Image_AddIntoMultiBeats)

            app.multiBeatsWindowStartIndex(app.multiBeatsSaveToID) = app.oneBeatWindowStartIndex;
            app.multiBeatsWindowEndIndex(app.multiBeatsSaveToID) = app.oneBeatWindowEndIndex;

            app.mainApp.multiBeatsWindowStartIndex = app.multiBeatsWindowStartIndex;
            app.mainApp.multiBeatsWindowEndIndex = app.multiBeatsWindowEndIndex;

            updateMultiBeatsListBox(app)

            app.multiBeatsSaveToID = app.multiBeatsSaveToID + 1;

        end

        % Image clicked function: Image_DeletefromMultiBeats
        function Image_DeletefromMultiBeatsClicked(app, event)

            showImageIconAct(app, app.Image_DeletefromMultiBeats)

            if isempty( app.MultiBeatsListBox.Value )

                msg = 'Choose one beat from the list to delete';
                uialert(app.OMZer_VmCaLinked_Signal_UIFigure, msg,'Warning','Icon','error')

            else
                ID = str2double(app.MultiBeatsListBox.Value);

                app.multiBeatsWindowStartIndex(ID) = [];
                app.multiBeatsWindowEndIndex(ID) = [];

                app.mainApp.multiBeatsWindowStartIndex = app.multiBeatsWindowStartIndex;
                app.mainApp.multiBeatsWindowEndIndex = app.multiBeatsWindowEndIndex;

                updateMultiBeatsListBox(app)

                app.multiBeatsSaveToID = app.multiBeatsSaveToID - 1;
            end

        end

        % Close request function: OMZer_VmCaLinked_Signal_UIFigure
        function OMZer_VmCaLinked_Signal_UIFigureCloseRequest(app, event)

            % (1) Set mainApp at the top level
            figure(app.mainAppUIFigure)

            % (2) Update the mainApp
            app.mainApp.OMZer_VmCaLinked_Signal = [];

            delete(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OMZer_VmCaLinked_Signal_UIFigure = uifigure('Visible', 'off');
            app.OMZer_VmCaLinked_Signal_UIFigure.Name = 'OMZer Vm-Ca Linked Signal';
            app.OMZer_VmCaLinked_Signal_UIFigure.Icon = 'Icons Wedding Ring.png';
            app.OMZer_VmCaLinked_Signal_UIFigure.CloseRequestFcn = createCallbackFcn(app, @OMZer_VmCaLinked_Signal_UIFigureCloseRequest, true);
            app.scrn1 = uiaxes(app.OMZer_VmCaLinked_Signal_UIFigure);
            app.scrn2 = uiaxes(app.OMZer_VmCaLinked_Signal_UIFigure);
            app.scrn3 = uiaxes(app.OMZer_VmCaLinked_Signal_UIFigure);
            app.scrn4 = uiaxes(app.OMZer_VmCaLinked_Signal_UIFigure);
            app.scrn5 = uiaxes(app.OMZer_VmCaLinked_Signal_UIFigure);
            app.scrn6 = uiaxes(app.OMZer_VmCaLinked_Signal_UIFigure);
            app.XYLabel = uilabel(app.OMZer_VmCaLinked_Signal_UIFigure);
            app.XYLabel.Tooltip = {'X (red) and Y (blue) coordinate of the marker. Please be noted that X is column and Y is row to locate an element in a matrix in MATLAB.'};
            app.XYLabel.Text = 'X,Y';
            app.Y_scrn1 = uieditfield(app.OMZer_VmCaLinked_Signal_UIFigure, 'numeric');
            app.Y_scrn1.Limits = [0 100];
            app.Y_scrn1.ValueDisplayFormat = '%.0f';
            app.Y_scrn1.Editable = 'off';
            app.Y_scrn1.Tooltip = {'Y coordinate of the 1st marker (Note: row in a matlab matrix)'};
            app.X_scrn1 = uieditfield(app.OMZer_VmCaLinked_Signal_UIFigure, 'numeric');
            app.X_scrn1.Limits = [0 100];
            app.X_scrn1.ValueDisplayFormat = '%.0f';
            app.X_scrn1.Editable = 'off';
            app.X_scrn1.Tooltip = {'X coordinate of the 1st marker (Note: column in a matlab matrix)'};
            app.Y_scrn2 = uieditfield(app.OMZer_VmCaLinked_Signal_UIFigure, 'numeric');
            app.Y_scrn2.Limits = [0 100];
            app.Y_scrn2.ValueDisplayFormat = '%.0f';
            app.Y_scrn2.Editable = 'off';
            app.Y_scrn2.Tooltip = {'Y coordinate of the 1st marker (Note: row in a matlab matrix)'};
            app.X_scrn2 = uieditfield(app.OMZer_VmCaLinked_Signal_UIFigure, 'numeric');
            app.X_scrn2.Limits = [0 100];
            app.X_scrn2.ValueDisplayFormat = '%.0f';
            app.X_scrn2.Editable = 'off';
            app.X_scrn2.Tooltip = {'X coordinate of the 1st marker (Note: column in a matlab matrix)'};
            app.Y_scrn3 = uieditfield(app.OMZer_VmCaLinked_Signal_UIFigure, 'numeric');
            app.Y_scrn3.Limits = [0 100];
            app.Y_scrn3.ValueDisplayFormat = '%.0f';
            app.Y_scrn3.Editable = 'off';
            app.Y_scrn3.Tooltip = {'Y coordinate of the 1st marker (Note: row in a matlab matrix)'};
            app.X_scrn3 = uieditfield(app.OMZer_VmCaLinked_Signal_UIFigure, 'numeric');
            app.X_scrn3.Limits = [0 100];
            app.X_scrn3.ValueDisplayFormat = '%.0f';
            app.X_scrn3.Editable = 'off';
            app.X_scrn3.Tooltip = {'X coordinate of the 1st marker (Note: column in a matlab matrix)'};
            app.Y_scrn4 = uieditfield(app.OMZer_VmCaLinked_Signal_UIFigure, 'numeric');
            app.Y_scrn4.Limits = [0 100];
            app.Y_scrn4.ValueDisplayFormat = '%.0f';
            app.Y_scrn4.Editable = 'off';
            app.Y_scrn4.Tooltip = {'Y coordinate of the 1st marker (Note: row in a matlab matrix)'};
            app.X_scrn4 = uieditfield(app.OMZer_VmCaLinked_Signal_UIFigure, 'numeric');
            app.X_scrn4.Limits = [0 100];
            app.X_scrn4.ValueDisplayFormat = '%.0f';
            app.X_scrn4.Editable = 'off';
            app.X_scrn4.Tooltip = {'X coordinate of the 1st marker (Note: column in a matlab matrix)'};
            app.Y_scrn5 = uieditfield(app.OMZer_VmCaLinked_Signal_UIFigure, 'numeric');
            app.Y_scrn5.Limits = [0 100];
            app.Y_scrn5.ValueDisplayFormat = '%.0f';
            app.Y_scrn5.Editable = 'off';
            app.Y_scrn5.Tooltip = {'Y coordinate of the 1st marker (Note: row in a matlab matrix)'};
            app.X_scrn5 = uieditfield(app.OMZer_VmCaLinked_Signal_UIFigure, 'numeric');
            app.X_scrn5.Limits = [0 100];
            app.X_scrn5.ValueDisplayFormat = '%.0f';
            app.X_scrn5.Editable = 'off';
            app.X_scrn5.Tooltip = {'X coordinate of the 1st marker (Note: column in a matlab matrix)'};
            app.Y_scrn6 = uieditfield(app.OMZer_VmCaLinked_Signal_UIFigure, 'numeric');
            app.Y_scrn6.Limits = [0 100];
            app.Y_scrn6.ValueDisplayFormat = '%.0f';
            app.Y_scrn6.Editable = 'off';
            app.Y_scrn6.Tooltip = {'Y coordinate of the 1st marker (Note: row in a matlab matrix)'};
            app.X_scrn6 = uieditfield(app.OMZer_VmCaLinked_Signal_UIFigure, 'numeric');
            app.X_scrn6.Limits = [0 100];
            app.X_scrn6.ValueDisplayFormat = '%.0f';
            app.X_scrn6.Editable = 'off';
            app.X_scrn6.Tooltip = {'X coordinate of the 1st marker (Note: column in a matlab matrix)'};
            app.Image_RestoreView = uiimage(app.OMZer_VmCaLinked_Signal_UIFigure);
            app.Image_RestoreView.ImageClickedFcn = createCallbackFcn(app, @Image_RestoreViewClicked, true);
            app.Image_RestoreView.ImageSource = 'Icon Restore View.png';
            app.RestoreViewLabel = uilabel(app.OMZer_VmCaLinked_Signal_UIFigure);
            app.RestoreViewLabel.Text = 'Restore View';
            app.Image_ActivatedCamera = uiimage(app.OMZer_VmCaLinked_Signal_UIFigure);
            app.Image_ActivatedCamera.Tooltip = {'(This is NOT a button) Tell the user from which camera are the signals being displayed now'};
            app.Image_ActivatedCamera.ImageSource = 'Icon Activated Camera.png';
            app.Camera1solidlineCamera2DashdottedlineLabel = uilabel(app.OMZer_VmCaLinked_Signal_UIFigure);
            app.Camera1solidlineCamera2DashdottedlineLabel.Text = {'Camera 1 - solid line'; ''; 'Camera 2 - Dash-dotted line'};
            app.pacing1CheckBox = uicheckbox(app.OMZer_VmCaLinked_Signal_UIFigure);
            app.pacing1CheckBox.ValueChangedFcn = createCallbackFcn(app, @pacing1CheckBoxValueChanged, true);
            app.pacing1CheckBox.Enable = 'off';
            app.pacing1CheckBox.Tooltip = {'Pacing signal stored in analog 1 channel'};
            app.pacing1CheckBox.Text = 'Pacing 1';
            app.pacing2CheckBox = uicheckbox(app.OMZer_VmCaLinked_Signal_UIFigure);
            app.pacing2CheckBox.ValueChangedFcn = createCallbackFcn(app, @pacing2CheckBoxValueChanged, true);
            app.pacing2CheckBox.Enable = 'off';
            app.pacing2CheckBox.Tooltip = {'Pacing signal stored in analog 2 channel'};
            app.pacing2CheckBox.Text = 'Pacing 2';
            app.Image_DeletefromMultiBeats = uiimage(app.OMZer_VmCaLinked_Signal_UIFigure);
            app.Image_DeletefromMultiBeats.ImageClickedFcn = createCallbackFcn(app, @Image_DeletefromMultiBeatsClicked, true);
            app.Image_DeletefromMultiBeats.Enable = 'off';
            app.Image_DeletefromMultiBeats.ImageSource = 'Icon Delete.png';
            app.DeletefromMultiBeatsLabel = uilabel(app.OMZer_VmCaLinked_Signal_UIFigure);
            app.DeletefromMultiBeatsLabel.Text = 'Delete from Multi Beats';
            app.ButtonGroup = uibuttongroup(app.OMZer_VmCaLinked_Signal_UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.OneBeatButton = uiradiobutton(app.ButtonGroup);
            app.OneBeatButton.Text = 'One Beat';
            app.OneBeatButton.Value = true;
            app.MultiBeatsButton = uiradiobutton(app.ButtonGroup);
            app.MultiBeatsButton.Text = 'Multi Beats';
            app.Image_AddIntoMultiBeats = uiimage(app.OMZer_VmCaLinked_Signal_UIFigure);
            app.Image_AddIntoMultiBeats.ImageClickedFcn = createCallbackFcn(app, @Image_AddIntoMultiBeatsClicked, true);
            app.Image_AddIntoMultiBeats.Enable = 'off';
            app.Image_AddIntoMultiBeats.ImageSource = 'Icon Plus.png';
            app.AddIntoMultiBeatsLabel = uilabel(app.OMZer_VmCaLinked_Signal_UIFigure);
            app.AddIntoMultiBeatsLabel.Text = 'Add Into Multi Beats';
            app.ViewStartsecSpinnerLabel = uilabel(app.OMZer_VmCaLinked_Signal_UIFigure);
            app.ViewStartsecSpinnerLabel.Text = 'View Start (sec)';
            app.ViewStartsecSpinner = uispinner(app.OMZer_VmCaLinked_Signal_UIFigure);
            app.ViewStartsecSpinner.Step = 0.05;
            app.ViewStartsecSpinner.ValueChangingFcn = createCallbackFcn(app, @ViewStartsecSpinnerValueChanging, true);
            app.ViewStartsecSpinner.Limits = [0 Inf];
            app.ViewStartsecSpinner.ValueChangedFcn = createCallbackFcn(app, @ViewStartsecSpinnerValueChanged, true);
            app.ViewEndsecSpinnerLabel = uilabel(app.OMZer_VmCaLinked_Signal_UIFigure);
            app.ViewEndsecSpinnerLabel.Text = 'View End (sec)';
            app.ViewEndsecSpinner = uispinner(app.OMZer_VmCaLinked_Signal_UIFigure);
            app.ViewEndsecSpinner.Step = 0.05;
            app.ViewEndsecSpinner.ValueChangingFcn = createCallbackFcn(app, @ViewEndsecSpinnerValueChanging, true);
            app.ViewEndsecSpinner.Limits = [0 Inf];
            app.ViewEndsecSpinner.ValueChangedFcn = createCallbackFcn(app, @ViewEndsecSpinnerValueChanged, true);
            app.ViewEndsecSpinner.Value = Inf;
            app.MultiBeatsListBoxLabel = uilabel(app.OMZer_VmCaLinked_Signal_UIFigure);
            app.MultiBeatsListBoxLabel.Text = 'Multi Beats';
            app.MultiBeatsListBox = uilistbox(app.OMZer_VmCaLinked_Signal_UIFigure);
            app.MultiBeatsListBox.Items = {};
            app.MultiBeatsListBox.ValueChangedFcn = createCallbackFcn(app, @MultiBeatsListBoxValueChanged, true);
            app.MultiBeatsListBox.Enable = 'off';
            app.MultiBeatsListBox.Value = {};
            app.WinStartsecEditFieldLabel = uilabel(app.OMZer_VmCaLinked_Signal_UIFigure);
            app.WinStartsecEditFieldLabel.Text = 'Win Start (sec)';
            app.WinStartsecEditField = uieditfield(app.OMZer_VmCaLinked_Signal_UIFigure, 'numeric');
            app.WinStartsecEditField.Limits = [0 Inf];
            app.WinStartsecEditField.Editable = 'off';
            app.WinStartsecEditField.Value = Inf;
            app.WinEndsecEditFieldLabel = uilabel(app.OMZer_VmCaLinked_Signal_UIFigure);
            app.WinEndsecEditFieldLabel.Text = 'Win End (sec)';
            app.WinEndsecEditField = uieditfield(app.OMZer_VmCaLinked_Signal_UIFigure, 'numeric');
            app.WinEndsecEditField.Limits = [0 Inf];
            app.WinEndsecEditField.Editable = 'off';
            app.WinEndsecEditField.Value = Inf;
            app.TypeStartsecEditFieldLabel = uilabel(app.OMZer_VmCaLinked_Signal_UIFigure);
            app.TypeStartsecEditFieldLabel.Text = 'Type Start (sec)';
            app.TypeStartsecEditField = uieditfield(app.OMZer_VmCaLinked_Signal_UIFigure, 'numeric');
            app.TypeStartsecEditField.Limits = [0 Inf];
            app.TypeStartsecEditField.ValueChangedFcn = createCallbackFcn(app, @TypeStartEndsecEditFieldValueChanged, true);
            app.TypeEndsecEditFieldLabel = uilabel(app.OMZer_VmCaLinked_Signal_UIFigure);
            app.TypeEndsecEditFieldLabel.Text = 'Type End (sec)';
            app.TypeEndsecEditField = uieditfield(app.OMZer_VmCaLinked_Signal_UIFigure, 'numeric');
            app.TypeEndsecEditField.Limits = [0 Inf];
            app.TypeEndsecEditField.ValueChangedFcn = createCallbackFcn(app, @TypeStartEndsecEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.OMZer_VmCaLinked_Signal_UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OMZer_VmCaLinked_Signal(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.OMZer_VmCaLinked_Signal_UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.OMZer_VmCaLinked_Signal_UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OMZer_VmCaLinked_Signal_UIFigure)
        end
    end
end