classdef OMZer_Vm_CV < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OMZer_Vm_CV_UIFigure           matlab.ui.Figure
        UseMouseScrollWheeltoAdjustMapColorTransparencyLabel  matlab.ui.control.Label
        CheckSigX_scrn                 matlab.ui.control.NumericEditField
        CheckSigY_scrn                 matlab.ui.control.NumericEditField
        CheckSigXYLabel                matlab.ui.control.Label
        ClearPixelLabel                matlab.ui.control.Label
        Image_ClearPixel               matlab.ui.control.Image
        DisplayPixelLabel              matlab.ui.control.Label
        Image_DisplayPixel             matlab.ui.control.Image
        VectorSpeedEditField           matlab.ui.control.NumericEditField
        VectorSpeedEditFieldLabel      matlab.ui.control.Label
        VectorAngelDegreeEditField     matlab.ui.control.NumericEditField
        VectorAngelDegreeEditFieldLabel  matlab.ui.control.Label
        UndoLabel                      matlab.ui.control.Label
        Image_Undo                     matlab.ui.control.Image
        AddVectorsLabel                matlab.ui.control.Label
        Image_AddVectors               matlab.ui.control.Image
        DeleteVectorsLabel             matlab.ui.control.Label
        Image_DeleteVectors            matlab.ui.control.Image
        AnglewithinDegreeEditField     matlab.ui.control.NumericEditField
        AnglewithinDegreeEditFieldLabel  matlab.ui.control.Label
        DistancewithinPixelEditField   matlab.ui.control.NumericEditField
        DistancewithinPixelEditFieldLabel  matlab.ui.control.Label
        VectorMembersEditField         matlab.ui.control.NumericEditField
        VectorMembersEditFieldLabel    matlab.ui.control.Label
        LineAngleDegreeEditField       matlab.ui.control.NumericEditField
        LineAngleDegreeEditFieldLabel  matlab.ui.control.Label
        DirectingLineLabel             matlab.ui.control.Label
        Image_DirectingLine            matlab.ui.control.Image
        PolarPanel                     matlab.ui.container.Panel
        MapTitleLabel                  matlab.ui.control.Label
        UnloadMapLabel                 matlab.ui.control.Label
        Image_UnloadMap                matlab.ui.control.Image
        DeleteMapLabel                 matlab.ui.control.Label
        Image_DeleteMap                matlab.ui.control.Image
        CalculateLabel                 matlab.ui.control.Label
        Image_Calculate                matlab.ui.control.Image
        UnitDropDown                   matlab.ui.control.DropDown
        UnitDropDownLabel              matlab.ui.control.Label
        SDEditField                    matlab.ui.control.NumericEditField
        SDEditFieldLabel               matlab.ui.control.Label
        MedianEditField                matlab.ui.control.NumericEditField
        MedianEditFieldLabel           matlab.ui.control.Label
        MeanEditField                  matlab.ui.control.NumericEditField
        MeanEditFieldLabel             matlab.ui.control.Label
        UserCommentTextArea            matlab.ui.control.TextArea
        UserCommentTextAreaLabel       matlab.ui.control.Label
        MapListBox                     matlab.ui.control.ListBox
        MapListBoxLabel                matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        AnalysisTab                    matlab.ui.container.Tab
        DefaultViewLabel               matlab.ui.control.Label
        Image_DefaultView              matlab.ui.control.Image
        VectorColorDropDown            matlab.ui.control.DropDown
        VectorColorDropDownLabel       matlab.ui.control.Label
        VectorLengthDropDown           matlab.ui.control.DropDown
        VectorLengthDropDownLabel      matlab.ui.control.Label
        CVVectorLabel                  matlab.ui.control.Label
        Image_CVVector                 matlab.ui.control.Image
        YResmmpixelEditField           matlab.ui.control.NumericEditField
        YResmmpixelEditFieldLabel      matlab.ui.control.Label
        XResmmpixelEditField           matlab.ui.control.NumericEditField
        XResmmpixelEditFieldLabel      matlab.ui.control.Label
        ToolsTab                       matlab.ui.container.Tab
        ExportFigureLabel              matlab.ui.control.Label
        Image_ExportFigure             matlab.ui.control.Image
        ExportDataLabel                matlab.ui.control.Label
        Image_ExportData               matlab.ui.control.Image
        SaveLabel                      matlab.ui.control.Label
        Image_Save                     matlab.ui.control.Image
        PreferenceTab                  matlab.ui.container.Tab
        colorBarAxes                   matlab.ui.control.UIAxes
        MapAxis                        matlab.ui.control.UIAxes
    end


    properties (Access = public)

        % The Parent App --------------------
        mainApp
        mainAppUIFigure


        % The Current App --------------------
        dirName % File directory
        fileName % Filename without path and extension

        setup % User preference
        userAlphaValue

        imageIconActColor = [0.80, 0.80, 0.80]; % The activation color when an image is clicked

        lWidth = 2; % Line width

        maxMapNumber = 10; % The max number of map that can be saved

        data
        acqFreq
        cmosDataGroup
        bgImageNomGroup % Gray colour RGB version of background image
        cameraSource % '1 (or 2)' - data from camera 1 (or 2)

        mapAxisLocs

        mouseXY = []; % This is a 1-by-2 row vector that stores mouse "X" and "Y" coordinate
        mouseMotion = []; % The result can be 'normal', 'extend', 'alt', 'open'

        cmosDataRowNum % The numer of rows in cmosData
        cmosDataColNum % The numer of columns in cmosData

        % (1) The Act Map From mainApp For CV Calculation
        sourceActMapData % The act map data from the mainApp

        % (2) For CV
        CVSaveToID = 1;

        CVMapDataTitle = { '(1) Win Start Index', '(2) Win End Index', ...
            '(3) Area Mode', '(4) AP Mode', ...
            '(5) Act Matrix', ...
            '(6) CVVectorMatrix', '(7) CVVectorAngleMatrix', '(8) CVVectorSpeedMatrix', ...
            '(9) CVDirectingLineStartXY', '(10) CVDirectingLineEndXY', ...
            '(11) CVDirectingLineSubVectorMatrix', ...
            '(12) LineAngleDegree', '(13) VectorMembers', ...
            '(14) DistancewithinPixel', '(15) AnglewithinDegree', ...
            '(16) Data Section Time', '(17) Data Section', ...
            '(18) Locs Act', ...
            '(19) Ensemble Peaks Number', ...
            '(20) Map User Comment'};
        CVMapData

        CVVectorAddDeleteID = 1;
        CVVectorAddDeletePlot % The plot for user to add or delete vectors

        pax % Polarplot axis

        CVDirectingLineStartXY
        CVDirectingLineEndXY
        CVDirectingLinePlot % The plot of the propagation line
        CVDirectingLineSubVectorPlot % Plot of CV vectors in the user-defined region

        CVVectorBgImagePlot % The background image beneath conduction velocity vector plot
        actMapPlot % The activation map image beneath conduction velocity vector plot
        actColorBarPlot
        CVVectorMapPlot % To store conduction velocity vector map

        CVPolarPlot
        CVUserSelectedVectorPolarPlot % The plot on the polaraxis about the angle of all the vectors user want

        checkSigMarkerXY
        checkSigMouseDragID
        checkSigMarkerPlot % The marker in CV vector plot - to check CV angle and speed
        checkSigMarkerHighlightPlot


        CVVectorMatrix % To store conduction velocity vector information (i.e., direction, speed) of each pixel. Data is in the form of complex number (real - x component, image - y component)
        CVVectorAngleMatrix % The anlge of each vector （unit in degree)
        CVVectorSpeedMatrix % The magnitude (i.e., speed) of each vector (unit in mm/msec)

        CVDirectingLineSubVectorMatrix % The CV vectors in the user-defined region

        % (3) For export
        exportMapName
        exportMapDataTitle
        exportMapData
        
        % (4) For window key press and release callback
        keyPressHistory
        keyPressID = 1;
    end


    methods (Access = public)

        function showImageIconAct(app, imageIconName)

            imageIconName.BackgroundColor = app.imageIconActColor; %[0.80, 0.80, 0.80];
            pause(0.05)
            imageIconName.BackgroundColor = 'none';
        end


        function adjustMapTransparency(app)

            if ~isempty(app.actMapPlot)
                app.actMapPlot.AlphaData = ~isnan(app.actMapPlot.CData) * app.userAlphaValue;
            end
        end


        function setColourMap(app,name)

            colormap(app.MapAxis, name);
            colormap(app.colorBarAxes, name);
        end


        function setAxisLimit(app, xMaxLimit, yMaxLimit) % Set the x-y limit of all axes

            app.MapAxis.XLim = [1, xMaxLimit];
            app.MapAxis.YLim = [1, yMaxLimit];

            app.CheckSigX_scrn.Value = 1;
            app.CheckSigX_scrn.Limits = [1, xMaxLimit];
            app.CheckSigY_scrn.Value = 1;
            app.CheckSigY_scrn.Limits = [1, yMaxLimit];
        end


        function updateMouseXY(app, axisLosInfo)

            %             app.mouseXY(1,1) = ( app.mouseXY(1,1) - axisLosInfo(1,1) ) * app.cmosDataColNum / axisLosInfo(1,3);
            %             app.mouseXY(1,1) = round( app.mouseXY(1,1) ); % Transfer relative mouse location inside bgImageAxis1 to absolute location inside a 100x100 matrix
            %             if app.mouseXY(1,1) < 1
            %                 app.mouseXY(1,1) = 1; % As a result, there won't appear (0,y) element requirement in a 100x100 matrix
            %             end
            %             if app.mouseXY(1,1) > app.cmosDataColNum
            %                 app.mouseXY(1,1) = app.cmosDataColNum;
            %             end
            %
            %
            %             app.mouseXY(1,2) = axisLosInfo.CurrentPoint(1,2);

            %             app.mouseXY(1,2) = ( (axisLosInfo(1,2) + axisLosInfo(1,4)) - app.mouseXY(1,2) ) * app.cmosDataRowNum / axisLosInfo(1,4);
            %             app.mouseXY(1,2) = round( app.mouseXY(1,2) ); % Transfer relative mouse location inside bgImageAxis1 to absolute location inside a 100x100 matrix
            %             if app.mouseXY(1,2) < 1
            %                 app.mouseXY(1,2) = 1; % As a result, there won't appear (x,0) element requirement in a 100x100 matrix
            %             end
            %             if app.mouseXY(1,2) > app.cmosDataRowNum
            %                 app.mouseXY(1,2) = app.cmosDataRowNum;
            %             end


            app.mouseXY(1,1) = app.MapAxis.CurrentPoint(1,1);
            app.mouseXY(1,2) = app.MapAxis.CurrentPoint(1,2);


            app.mouseXY(1,1) = round( app.mouseXY(1,1) ); % Transfer relative mouse location inside bgImageAxis1 to absolute location inside a 100x100 matrix
            if app.mouseXY(1,1) < 1
                app.mouseXY(1,1) = 1; % As a result, there won't appear (0,y) element requirement in a 100x100 matrix
            end
            if app.mouseXY(1,1) > app.cmosDataColNum
                app.mouseXY(1,1) = app.cmosDataColNum;
            end

            app.mouseXY(1,2) = round( app.mouseXY(1,2) ); % Transfer relative mouse location inside bgImageAxis1 to absolute location inside a 100x100 matrix
            if app.mouseXY(1,2) < 1
                app.mouseXY(1,2) = 1; % As a result, there won't appear (x,0) element requirement in a 100x100 matrix
            end
            if app.mouseXY(1,2) > app.cmosDataRowNum
                app.mouseXY(1,2) = app.cmosDataRowNum;
            end
        end


        function loadSavedMap(app)

            VmMap = app.data.VmMeasurement{ app.cameraSource, 2 };

            % (1) CV
            app.CVMapData = VmMap{5,3};
            mapNum = 0;
            for ID = 1 : app.maxMapNumber
                mapNum = mapNum + ( ~isempty( app.CVMapData{1,1,ID} ) );
            end
            app.CVSaveToID = mapNum + 1;
        end


        function updateMapListBox(app)

            mapNum = 0;
            for ID = 1 : app.maxMapNumber
                mapNum = mapNum + ( ~isempty( app.CVMapData{1,1,ID} ) );
            end

            % Update MapListBox
            app.MapListBox.Items = {};
            if mapNum ~= 0
                for ID = 1 : mapNum
                    app.MapListBox.Items{ID} = num2str(ID);
                end
            end
        end


        function clearActMapPlot(app)

            delete(app.actMapPlot)
            app.actMapPlot = [];

            delete(app.actColorBarPlot)
            app.actColorBarPlot = [];
        end


        function clearCheckSigPlot(app)

            app.checkSigMarkerXY = [];
            app.CheckSigX_scrn.Value = 1;
            app.CheckSigY_scrn.Value = 1;

            delete(app.checkSigMarkerPlot)
            delete(app.checkSigMarkerHighlightPlot)

            app.checkSigMarkerPlot = [];
            app.checkSigMarkerHighlightPlot = [];

            app.VectorAngelDegreeEditField.Value = Inf;
            app.VectorSpeedEditField.Value = Inf;
        end


        function resetStatisticResult(app)

            app.MeanEditField.Value = 0;
            app.MedianEditField.Value = 0;
            app.SDEditField.Value = 0;
        end


        function newPlotObject = plotMap(app, plotObject, matrix)

            hold(app.MapAxis,'on')
            delete(plotObject)

            newPlotObject = imagesc(app.MapAxis, matrix);
            newPlotObject.AlphaData = ~isnan(matrix) * app.userAlphaValue; % Make the pixel with NaN transparent

            % newPlotObject = pcolor( app.MapAxis, matrix );
            % newPlotObject.FaceAlpha = 'interp';
            % newPlotObject.FaceColor = 'interp'; %'interp';
            % newPlotObject.EdgeColor = 'none';
            % newPlotObject.AlphaData = ~isnan(matrix) * app.userAlphaValue; % Make the pixel with NaN transparent


            if nanmin(matrix(:)) == nanmax(matrix(:))
                caxis(app.MapAxis, [ nanmin(matrix(:)), nanmax(matrix(:))+1 ]);
            else
                caxis(app.MapAxis, [ nanmin(matrix(:)), nanmax(matrix(:)) ]);
            end
            hold(app.MapAxis,'off')
        end


        function newPlotObject = plotMapColorBar(app, plotObject, matrix)

            matrix = matrix - nanmin( matrix(:) ); % Shift the time bar such that Act (or Rep) Map always starts from 0

            matrixColumnVersion = reshape( matrix, [],1 );
            matrixColumnVersion( isnan(matrixColumnVersion) ) = [];
            matrixColumnVersion = unique(matrixColumnVersion);

            % (1) Plot the color bar
            delete(plotObject)
            newPlotObject = imagesc(app.colorBarAxes, matrixColumnVersion);
            yticks(app.colorBarAxes, 'auto')
            app.colorBarAxes.YLim = [ 1, length(matrixColumnVersion) ];

            % (2) Manually create y tick label
            if length(app.colorBarAxes.YTick) <= length(matrixColumnVersion)

                app.colorBarAxes.YTick( mod(app.colorBarAxes.YTick , 1) ~= 0  ) = []; % Only keep integer tick

            else
                app.colorBarAxes.YTick = [ 1 : length(matrixColumnVersion) ];
            end

            colorBarLabel = cell( 1, length(app.colorBarAxes.YTick) );
            for ID = 1 : length(app.colorBarAxes.YTick)
                colorBarLabel{ID} = num2str( matrixColumnVersion(app.colorBarAxes.YTick(ID)) );
            end
            app.colorBarAxes.YTickLabel = colorBarLabel;
        end


        function newPlotObject = plotCVVectorMap(app, plotObject, matrix, vectorLength)

            hold(app.MapAxis, 'on')

            delete(plotObject);

            [xQ, yQ] = meshgrid( 1:app.cmosDataColNum, 1:app.cmosDataRowNum );
            newPlotObject = quiver( app.MapAxis, xQ, yQ, real(matrix), imag(matrix) );

            newPlotObject.Color = app.VectorColorDropDown.Value;
            newPlotObject.LineWidth = 2;
            newPlotObject.MaxHeadSize = 0.5;
            newPlotObject.Marker = 'none';
            newPlotObject.MarkerSize = 2;
            newPlotObject.AutoScale = 'on';
            newPlotObject.AutoScaleFactor = vectorLength;

            hold(app.MapAxis, 'off')
        end


        function clearCVMapPlot(app)

            delete(app.actMapPlot)
            app.actMapPlot = [];

            delete(app.actColorBarPlot)
            app.actColorBarPlot = [];

            delete(app.CVVectorMapPlot)
            app.CVVectorMapPlot = [];

            delete(app.CVPolarPlot)
            app.CVPolarPlot = [];

            delete(app.CVUserSelectedVectorPolarPlot)
            app.CVUserSelectedVectorPolarPlot = [];

            delete(app.CVDirectingLinePlot)
            app.CVDirectingLinePlot = [];

            delete(app.CVDirectingLineSubVectorPlot)
            app.CVDirectingLineSubVectorPlot = [];

            app.CVVectorMatrix = [];
            app.CVVectorAngleMatrix = [];
            app.CVVectorSpeedMatrix = [];

            app.CVDirectingLineSubVectorMatrix = [];

            app.MapTitleLabel.Text = 'Map Title';
        end


        function plotObject = updateCheckSigMarker(app, markerColor)

            X = app.checkSigMarkerXY(1,1);
            Y = app.checkSigMarkerXY(1,2);

            plotObject = line(app.MapAxis, X, Y, 'Marker','square', 'MarkerSize',10, 'MarkerEdgeColor','white', 'MarkerFaceColor', markerColor);
        end


        function plotObject = updateCheckSigMarkerHighlight(app)

            X = app.checkSigMarkerXY(1,1);
            Y = app.checkSigMarkerXY(1,2);

            plotObject = line( app.MapAxis, X, Y, 'Marker','o', 'LineWidth',4, 'MarkerSize',30, 'MarkerEdgeColor','white', 'MarkerFaceColor', 'none');
        end


        function updateCheckCVVectorInfo(app)

            row = app.checkSigMarkerXY(1,2);
            col = app.checkSigMarkerXY(1,1);

            % (1) Update angle
            if ~isnan( app.CVVectorAngleMatrix(row,col) )

                app.VectorAngelDegreeEditField.Value = app.CVVectorAngleMatrix(row,col);

            else
                app.VectorAngelDegreeEditField.Value = Inf;
            end

            % (2) Update speed
            % Convert speed unit: 1 mm/msec == 1000 mm/sec == 100 cm/sec == 1 m/sec
            % The unit of 'app.CVVectorSpeedMatrix' is 'mm/msec'
            if isequal(app.UnitDropDown.Value, 'mm/msec')
                vectorSpeedUnitConverted = app.CVVectorSpeedMatrix;

            elseif isequal(app.UnitDropDown.Value, 'mm/sec')
                vectorSpeedUnitConverted = 1000 * app.CVVectorSpeedMatrix;

            elseif isequal(app.UnitDropDown.Value, 'cm/sec')
                vectorSpeedUnitConverted = 100 * app.CVVectorSpeedMatrix;

            elseif isequal(app.UnitDropDown.Value, 'm/sec')
                vectorSpeedUnitConverted = app.CVVectorSpeedMatrix;
            end

            if ~isnan( vectorSpeedUnitConverted(row,col) )

                app.VectorSpeedEditField.Value = vectorSpeedUnitConverted(row,col);
            else
                app.VectorSpeedEditField.Value = Inf;
            end
        end


        function newPlotObject = plotCVUserSelectedVectorPolarPlot(app, plotObject)

            thetaLB = app.LineAngleDegreeEditField.Value - app.AnglewithinDegreeEditField.Value;
            thetaUB = app.LineAngleDegreeEditField.Value + app.AnglewithinDegreeEditField.Value;
            theta = ( thetaLB : thetaUB );

            num = length( theta );
            rho = ones(num,1) * max(app.CVPolarPlot.RData);
            rho(1) = 0;
            rho(end) = 0;

            theta = deg2rad(theta); % Unit - radian

            delete(plotObject)
            hold(app.pax, 'on')
            newPlotObject = polarplot(app.pax, theta, rho, '-', 'LineWidth',app.lWidth, 'Color','red' );
            hold(app.pax, 'off')
        end


        function directingLineSubVectorMatrix = searchCVDirectingLineSubVector(app)

            directingLineSubVectorMatrix = nan( size(app.CVVectorMatrix) );


            XsPoint = app.CVDirectingLineStartXY(1,1);
            YsPoint = app.CVDirectingLineStartXY(1,2);

            XePoint = app.CVDirectingLineEndXY(1,1);
            YePoint = app.CVDirectingLineEndXY(1,2);

            angleThresh = app.AnglewithinDegreeEditField.Value; % 'angle threshold' - unit in degrees
            distThresh = app.DistancewithinPixelEditField.Value; % 'distance threshold' - unit in pixels

            lineAngle = rad2deg( atan2( YePoint-YsPoint, XePoint-XsPoint ) ); % This gives angle (in degree) if 'Y-axis' direction is normal;
            if isequal( app.MapAxis.YDir, 'reverse')
                lineAngle = -1 * lineAngle; % Account for flip in y-direction
            end
            lineAngle = round(lineAngle);

            % (1) Generate a rectangle in which query points will be checked if they fall into the user defined region
            minR = min(YsPoint, YePoint);
            minC = min(XsPoint, XePoint);
            if (minR - distThresh < 1)
                minR = distThresh + 1;
            end
            if (minC - distThresh < 1)
                minC = distThresh + 1;
            end

            maxR = max(YsPoint, YePoint);
            maxC = max(XsPoint, XePoint);
            if (maxR + distThresh > app.cmosDataRowNum)
                maxR = app.cmosDataRowNum - distThresh;
            end
            if (maxC + distThresh > app.cmosDataColNum)
                maxC = app.cmosDataColNum - distThresh;
            end

            % (2) Distance and angle
            for rowID = minR - distThresh  :  maxR + distThresh % Row (i.e., y) of the query point
                for colID = minC - distThresh  :  maxC + distThresh % Column (i.e., x) of the query point

                    % A triangle
                    A = sqrt( (colID-XsPoint)^2 + (rowID-YsPoint)^2 ); % The length of sideA
                    B = sqrt( (colID-XePoint)^2 + (rowID-YePoint)^2 ); % The length of sideB
                    C = sqrt( (XsPoint-XePoint)^2 + (YsPoint-YePoint)^2 ); % The length of sideC

                    % Use 'dot product' of two vectors to check it they form an 'actue - cos>0' / 'obtuse - cos<0' angle
                    cosAC = dot( [(colID-XsPoint),(rowID-YsPoint)], [(XePoint-XsPoint),(YePoint-YsPoint)] ) / (A*C); % Cosine of angle between sideA and sideC
                    cosBC = dot( [(colID-XePoint),(rowID-YePoint)], [(XsPoint-XePoint),(YsPoint-YePoint)] ) / (B*C); % Cosine of angle between sideB and sideC

                    if cosAC <= 0  &&  cosBC > 0
                        dist = A; % 'dist - distance'

                    elseif cosAC > 0  &&  cosBC <= 0
                        dist = B;

                    elseif cosAC > 0  &&  cosBC > 0
                        dist = sqrt( A^2 - (A*cosAC)^2 ); % dist is also = sqrt( B^2 - (B*cosBC)^2 )
                    else
                        dist = NaN;
                    end
                    dist = round(dist,3);

                    % Calculate the angle bwtween a vector and the user-defiend propagation line
                    angleLineVector = abs( app.CVVectorAngleMatrix(rowID,colID) - lineAngle );
                    if angleLineVector > 180
                        angleLineVector = 360 - angleLineVector;
                    end

                    % Check threshold
                    if ~isnan(dist)  &&  ( dist <= distThresh )   &&   ( angleLineVector <= angleThresh )
                        directingLineSubVectorMatrix(rowID,colID) = app.CVVectorMatrix(rowID,colID);
                    end
                end
            end

            app.LineAngleDegreeEditField.Value = lineAngle;
        end


        function newPlotObject = updateCVDirectingLineSubVectorPlot(app, plotObject) % Being called each time the user adds/deletes a specific vector

            % (1) Clear Add / Delete Vector Plot
            if ~isempty( app.CVVectorAddDeletePlot )
                for ID = 1 : length(app.CVVectorAddDeletePlot)
                    delete( app.CVVectorAddDeletePlot{ID} )
                end
                app.CVVectorAddDeletePlot = [];
                app.CVVectorAddDeleteID = 1;
            end

            % (2) Update directling line subvector plot
            delete(plotObject)
            plotObject = [];

            hold(app.MapAxis, 'on')
            [r,c] = find( ~isnan( abs(app.CVDirectingLineSubVectorMatrix) ) == 1 );
            if isempty(r)  &&  isempty(c)

                msg = 'Although you draw the directing line, no vectors can be found based on the current setup. Please adjust it.';
                uialert(app.OMZer_Vm_CV_UIFigure, msg,'Warning','Icon','error')

                newPlotObject = [];

            else

                newPlotObject = line( app.MapAxis, c,r, 'LineStyle','none', 'LineWidth',app.lWidth, 'Marker','o');
                newPlotObject.MarkerSize = 6;
                newPlotObject.MarkerEdgeColor = 'Red';
                newPlotObject.MarkerFaceColor = 'None';
            end
            hold(app.MapAxis, 'off')
        end


        function enableComponentsForAddDeleteVectors(app, state)

            % Ribbon menu
            app.Image_Save.Enable = state;
            app.Image_ExportData.Enable = state;
            app.Image_ExportFigure.Enable = state;

            % Components
            app.Image_DirectingLine.Enable = state;
            app.Image_Undo.Enable = state;
            app.DistancewithinPixelEditField.Enable = state;
            app.AnglewithinDegreeEditField.Enable = state;

            app.MapListBox.Enable = state;
            app.UnitDropDown.Enable = state;
            app.Image_Calculate.Enable = state;
            app.Image_UnloadMap.Enable = state;
            app.Image_DeleteMap.Enable = state;
            app.UserCommentTextArea.Enable = state;

            app.Image_DisplayPixel.Enable = state;
            app.Image_ClearPixel.Enable = state;
            app.CheckSigX_scrn.Enable = state;
            app.CheckSigY_scrn.Enable = state;

            if isequal( state, 'on' )  &&  ~isempty(app.checkSigMarkerPlot)
                app.Image_DisplayPixel.Enable = 'off';
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, OMZer_Vm)

            msg = 'Configuring files';
            progressBar = uiprogressdlg(app.OMZer_Vm_CV_UIFigure, 'Title','Initialising', 'Message',msg, 'Indeterminate','on');
            drawnow

            % (1.1) User set up
            app.setup = load([pwd,'/OMZer_Setup']);
            setColourMap(app, app.setup.userColourMap);
            app.userAlphaValue = 1.0; % The default alpha value

            % (1.2) Set default pixel resolution
            app.XResmmpixelEditField.Value = app.setup.VmMapCalSetup{5,2};
            app.YResmmpixelEditField.Value = app.setup.VmMapCalSetup{6,2};

            % (1.3) Set default directing line sub vector choice and CV unit
            app.DistancewithinPixelEditField.Value = app.setup.VmMapCalSetup{7,2};
            app.AnglewithinDegreeEditField.Value = app.setup.VmMapCalSetup{8,2};
            app.UnitDropDown.Value = app.setup.VmMapCalSetup{9,2};

            % (1.4) Set default CV vector plot display choice
            app.VectorLengthDropDown.Value = app.setup.VmMapCalSetup{10,2};

            % (2) Get information from the mainApp
            app.mainApp = OMZer_Vm;
            app.mainAppUIFigure = app.mainApp.OMZer_Vm_UIFigure;

            app.dirName = app.mainApp.dirName;
            app.fileName = app.mainApp.fileName;

            app.data = app.mainApp.data;
            app.acqFreq = app.mainApp.acqFreq;
            app.cmosDataGroup = app.mainApp.cmosDataGroup;
            app.bgImageNomGroup = app.mainApp.bgImageNomGroup;
            app.cameraSource = app.mainApp.cameraSource;

            if isequal( app.mainApp.ActLabel.FontColor, [ 1.00, 0.00, 0.00 ] )  &&  ( ~isempty(app.mainApp.MapListBox.Value) ) % Act map

                checkMapID = str2double(app.mainApp.MapListBox.Value);
                app.sourceActMapData = app.mainApp.actMapData(:, :, checkMapID);
            end

            % (3) Disable figure toolbox
            try
                disableDefaultInteractivity(app.MapAxis);

                axtoolbar(app.MapAxis, {'datacursor', 'pan', 'zoomin', 'zoomout'});

            catch
                % Do nothing
            end

            % (4.1) Plot background image
            [app.cmosDataRowNum, app.cmosDataColNum, ~] = size( app.cmosDataGroup{app.cameraSource} );
            setAxisLimit(app, app.cmosDataColNum, app.cmosDataRowNum);
            imagesc( app.MapAxis, app.bgImageNomGroup{app.cameraSource} );

            % (4.2) Plot act image if available
            if ~isempty(app.sourceActMapData)

                AreaMode = app.sourceActMapData{3};
                APMode = app.sourceActMapData{4};

                LB = app.sourceActMapData{5};
                UB = app.sourceActMapData{6};
                matrix = app.sourceActMapData{10};
                matrix( matrix < LB  |  matrix > UB ) = NaN;

                app.MapTitleLabel.Text = [ AreaMode, ' ', APMode, ' Activation Map' ];

                app.actMapPlot = plotMap(app, app.actMapPlot, matrix);
                app.actColorBarPlot = plotMapColorBar(app, app.actColorBarPlot, matrix);
            end

            % (5) Load saved map
            loadSavedMap(app)
            updateMapListBox(app)

            % (6) Setup polaraxis
            app.pax = polaraxes(app.PolarPanel);
            app.pax.ThetaLim = [-180, 180];
            app.pax.FontSize = 11;
            app.pax.FontWeight = 'bold';

            close(progressBar)

        end

        % Image clicked function: Image_CVVector
        function Image_CVVectorClicked(app, event)

            showImageIconAct(app, app.Image_CVVector)

            if isempty( app.sourceActMapData )

                msg = 'In order to calculate CV vector, (1) close this app, (2) choose an "Act Map" in the "OMZer Vm App", (3) click "CV" in the "Analysis Menu" to re-open this app';
                uialert(app.OMZer_Vm_CV_UIFigure,msg,'Warning','Icon','error')

            else

                if app.CVSaveToID > app.maxMapNumber

                    msg = [ 'Up to ', num2str(app.maxMapNumber), ' map files can be stored. Delete old ones before new calculation' ];
                    uialert(app.OMZer_Vm_CV_UIFigure, msg,'Warning','Icon','error')

                else

                    msg = 'Calculating the CV vector map';
                    progressBar = uiprogressdlg(app.OMZer_Vm_CV_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');

                    % (1) Calculate CV vector matrix
                    actMatrix = app.sourceActMapData{10}; % The activation time (unit in 'msec')
                    LB = app.sourceActMapData{5};
                    UB = app.sourceActMapData{6};

                    actMatrix( actMatrix < LB  |  actMatrix > UB ) = NaN;
                    actMatrix = actMatrix - nanmin( actMatrix(:) ); % Shift the time bar such that Act Map always starts from 0

                    app.CVVectorMatrix = calculateCVVector( actMatrix, app.XResmmpixelEditField.Value, app.YResmmpixelEditField.Value );

                    % (2) Calculate the angle and magnitude of each vector
                    app.CVVectorAngleMatrix = nan( size(app.CVVectorMatrix) ); % Unit in degree
                    app.CVVectorSpeedMatrix = nan( size(app.CVVectorMatrix) ); % Unit in mm/msec

                    for rowID = 1 : size(app.CVVectorMatrix,1)
                        for colID = 1 : size(app.CVVectorMatrix,2)

                            if ~isnan( app.CVVectorMatrix(rowID,colID) ) % For example, ~isnan(3+4i) == 1

                                app.CVVectorAngleMatrix(rowID,colID) = rad2deg( angle( app.CVVectorMatrix(rowID,colID) ) ); % Unit in 'degree'. Ranges in [-180,180] (When the elements of z are non-negative real numbers, angle(z) returns 0. When the elements of z are negative real numbers, angle(z) returns π.)
                                if isequal( app.MapAxis.YDir, 'reverse')
                                    app.CVVectorAngleMatrix(rowID,colID) = -1 * app.CVVectorAngleMatrix(rowID,colID); % Account for flip in y-direction
                                end
                                app.CVVectorAngleMatrix(rowID,colID) = round( app.CVVectorAngleMatrix(rowID,colID) ); % Unit in 'degree'. Ranges in [-180,180]

                                app.CVVectorSpeedMatrix(rowID,colID) = abs( app.CVVectorMatrix(rowID,colID) ); % Unit in 'mm/msec'
                            end
                        end
                    end

                    % (3) Store CV data
                    app.CVMapData{1, 1, app.CVSaveToID} = app.sourceActMapData{1}; % 'Win Start Index'
                    app.CVMapData{1, 2, app.CVSaveToID} = app.sourceActMapData{2}; % 'Win End Index'
                    app.CVMapData{1, 3, app.CVSaveToID} = app.sourceActMapData{3}; % AreaMode
                    app.CVMapData{1, 4, app.CVSaveToID} =  app.sourceActMapData{4}; % APMode
                    app.CVMapData{1, 5, app.CVSaveToID} = actMatrix; % The activation map
                    app.CVMapData{1, 6, app.CVSaveToID} = app.CVVectorMatrix;
                    app.CVMapData{1, 7, app.CVSaveToID} = app.CVVectorAngleMatrix;
                    app.CVMapData{1, 8, app.CVSaveToID} = app.CVVectorSpeedMatrix;
                    app.CVMapData{1, 9, app.CVSaveToID} = []; % 'CVDirectingLineStartXY'
                    app.CVMapData{1, 10, app.CVSaveToID} = []; % 'CVDirectingLineEndXY'
                    app.CVMapData{1, 11, app.CVSaveToID} = []; % 'CVDirectingLineSubVectorMatrix'
                    app.CVMapData{1, 12, app.CVSaveToID} = []; % 'LineAngleDegree'
                    app.CVMapData{1, 13, app.CVSaveToID} = []; % 'VectorMembers'
                    app.CVMapData{1, 14, app.CVSaveToID} = []; % 'DistancewithinPixel'
                    app.CVMapData{1, 15, app.CVSaveToID} = []; % 'AnglewithinDegree'
                    app.CVMapData{1, 16, app.CVSaveToID} = app.sourceActMapData{7}; % 'Data Section Time'
                    app.CVMapData{1, 17, app.CVSaveToID} = app.sourceActMapData{8}; % 'Data Section'
                    app.CVMapData{1, 18, app.CVSaveToID} = app.sourceActMapData{9}; % 'Locs Act'
                    app.CVMapData{1, 19, app.CVSaveToID} = app.sourceActMapData{11}; % 'Ensemble Peaks Number'
                    app.CVMapData{1, 20, app.CVSaveToID} = app.sourceActMapData{12}; % Map User Comment

                    % (4) Update Save to File ID
                    app.CVSaveToID = app.CVSaveToID + 1;

                    % (5) Update map list box
                    updateMapListBox(app)

                    % (6) Clear
                    app.CVVectorMatrix = [];
                    app.CVVectorAngleMatrix = [];
                    app.CVVectorSpeedMatrix = [];

                    close(progressBar)
                end
            end

        end

        % Value changed function: VectorLengthDropDown
        function VectorLengthDropDownValueChanged(app, event)

            if ~isempty( app.CVVectorMapPlot )

                app.CVVectorMapPlot.AutoScaleFactor = str2double(app.VectorLengthDropDown.Value);
            end

        end

        % Value changed function: VectorColorDropDown
        function VectorColorDropDownValueChanged(app, event)

            if ~isempty( app.CVVectorMapPlot )

                app.CVVectorMapPlot.Color = app.VectorColorDropDown.Value;
            end

        end

        % Image clicked function: Image_DefaultView
        function Image_DefaultViewClicked(app, event)

            showImageIconAct(app, app.Image_DefaultView)

            xlim(app.MapAxis, [1,app.cmosDataColNum]);
            ylim(app.MapAxis, [1,app.cmosDataRowNum]);

        end

        % Image clicked function: Image_Save
        function Image_SaveClicked(app, event)

            showImageIconAct(app, app.Image_Save)

            msg = 'Saving the file';
            progressBar = uiprogressdlg(app.OMZer_Vm_CV_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');

            % (1) Update the data
            VmMeasurement = app.data.VmMeasurement;
            old_VmMap = VmMeasurement{ app.cameraSource, 2 };
            new_VmMap = old_VmMap;

            new_VmMap{5,3} = app.CVMapData;

            VmMeasurement{ app.cameraSource, 2 } = new_VmMap;

            % (2) Save the data
            newFileName = [app.dirName, '/', app.fileName, '.mat'];

            save(newFileName, 'VmMeasurement', '-append');

            app.mainApp.mainApp.data = load( newFileName ); % OMZer
            app.mainApp.data = app.mainApp.mainApp.data; % OMZer_Vm - Since data changed, need to re-load and update data in the main app
            app.data = app.mainApp.data;

            close(progressBar)

        end

        % Image clicked function: Image_ExportData
        function Image_ExportDataClicked(app, event)

            showImageIconAct(app, app.Image_ExportData)

            msg = 'Choose the data you want to export';
            msgTitle = 'Warning';
            optionItem = { 'Export Map Data (.mat File)', 'Cancel' };
            selection = uiconfirm( app.OMZer_Vm_CV_UIFigure, msg, msgTitle, ...
                'Options', optionItem, ...
                'DefaultOption', 1, ...
                'CancelOption', 2 ); % uiconfirm returns the selection as a character vector

            switch selection

                case optionItem{1} % Export Map Data (.mat File)

                    % Get valid saving directory and file name
                    saveDir = uigetdir;

                    if saveDir == 0 % User has NOT selected file directory.

                        msg = 'Please select a valid saving directory';
                        uialert(app.OMZer_Vm_CV_UIFigure,msg,'Warning','Icon','error')

                    else

                        prompt = {'Enter filename to save (without extension)...   Please do not use \/:*?"<>|'};
                        dlgtitle = 'Save to a .mat file';
                        dfinput = { [ app.fileName, '_Vm_', app.exportMapName, '_Data' ] };
                        dims = [1,100];
                        filename = inputdlg(prompt,dlgtitle,dims,dfinput);

                        if isempty(filename) % User has NOT entered a name

                            msg = 'Please enter a valid file name';
                            uialert(app.OMZer_Vm_CV_UIFigure,msg,'Warning','Icon','error')

                        else

                            % Export map data
                            mapName = app.exportMapName;
                            mapDataTitle = app.exportMapDataTitle;
                            mapData = app.exportMapData;
                            mapDataWithTitle = cell( 2, length(mapDataTitle) );
                            mapDataWithTitle(1,:) = app.exportMapDataTitle;
                            mapDataWithTitle(2,:) = app.exportMapData;

                            mapUserComment = mapData{20};

                            acquisitionFrequency_Hz_Unit = app.acqFreq;
                            backgroundImage = app.bgImageNomGroup{app.cameraSource};
                            cameraID = app.cameraSource;
                            mapPlotTitle = app.MapTitleLabel.Text;
                            meanValue = app.MeanEditField.Value;
                            medianValue = app.MedianEditField.Value;
                            SDValue = app.SDEditField.Value;
                            speed_Unit = app.UnitDropDown.Value;

                            polarplot_ThetaData = app.CVPolarPlot.ThetaData;
                            polarplot_RData = app.CVPolarPlot.RData;

                            msg = [ 'Export the ', app.exportMapName, ' Map' ];
                            progressBar = uiprogressdlg(app.OMZer_Vm_CV_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');


                            fullSaveName = strcat(saveDir, '/', filename, '.mat'); % At this moment, 'fullSaveName' is a 'cell'.
                            fullSaveName = fullSaveName{1}; % Now 'fullSaveName' is a 'character
                            save(fullSaveName, ...
                                'mapName', ...
                                'mapDataTitle', ...
                                'mapData', ...
                                'mapDataWithTitle', ...
                                'mapUserComment', ...
                                'acquisitionFrequency_Hz_Unit', ...
                                'backgroundImage', ...
                                'cameraID', ...
                                'mapPlotTitle', ...
                                'meanValue', ...
                                'medianValue', ...
                                'SDValue', ...
                                'speed_Unit', ...
                                'polarplot_ThetaData', ...
                                'polarplot_RData');

                            close(progressBar)
                        end
                    end

                    figure(app.OMZer_Vm_CV_UIFigure) % Set this uifigure at the top level
            end

        end

        % Image clicked function: Image_ExportFigure
        function Image_ExportFigureClicked(app, event)

            showImageIconAct(app, app.Image_ExportFigure)

        end

        % Image clicked function: Image_DirectingLine
        function Image_DirectingLineClicked(app, event)

            showImageIconAct(app, app.Image_DirectingLine)

            checkMapID = str2double( app.MapListBox.Value );

            % (1) Clear statistics result (i.e., need re-calculation)
            resetStatisticResult(app)

            % (2) Clear previous directing line plot
            app.CVDirectingLineStartXY = [];
            app.CVDirectingLineEndXY = [];

            delete(app.CVDirectingLinePlot)
            app.CVDirectingLinePlot = [];

            delete(app.CVUserSelectedVectorPolarPlot) % On polaraxis
            app.CVUserSelectedVectorPolarPlot = [];

            delete(app.CVDirectingLineSubVectorPlot) % On CVAxis
            app.CVDirectingLineSubVectorPlot = [];

            app.CVDirectingLineSubVectorMatrix = [];

            app.LineAngleDegreeEditField.Value = 0;
            app.VectorMembersEditField.Value = 0;

            % (3.1) Pop up a figure
            screenSize = get( groot, 'ScreenSize' );

            f = figure;
            f.Position = [ 1, 20, 0.9*screenSize(4), 0.9*screenSize(4) ];
            set(gca,'Ydir','reverse');
            colormap(app.setup.userColourMap)
            hold on

            % (3.2) Plot background image
            imagesc( gca, app.bgImageNomGroup{app.cameraSource} );

            % (3.3) Plot activation map
            matrix = app.CVMapData{1,5,checkMapID}; % The activation map matrix
            actFP = imagesc(gca, matrix); % 'actFP' - activation figure plot
            actFP.AlphaData = ~isnan(matrix) * app.userAlphaValue; % Make the pixel with NaN transparent

            % (3.4) Plot CV Vectors
            [xQ, yQ] = meshgrid( 1:app.cmosDataColNum, 1:app.cmosDataRowNum );
            vectorFP = quiver( gca, xQ, yQ, real(app.CVVectorMatrix), imag(app.CVVectorMatrix) );
            vectorFP.Color = app.VectorColorDropDown.Value;
            vectorFP.LineWidth = app.CVVectorMapPlot.LineWidth;
            vectorFP.MaxHeadSize = app.CVVectorMapPlot.MaxHeadSize;
            vectorFP.Marker = app.CVVectorMapPlot.Marker;
            vectorFP.MarkerSize = app.CVVectorMapPlot.MarkerSize;
            vectorFP.AutoScale = 'on';
            vectorFP.AutoScaleFactor = str2double(app.VectorLengthDropDown.Value);


            %             % Test - Plot Polar Grid
            %             centerX = round( 0.5 * app.cmosDataColNum );
            %             centerY = round( 0.5 * app.cmosDataRowNum );
            %
            %             radius_positive = abs( max( app.cmosDataColNum, app.cmosDataRowNum ) );
            %             radius_negative = -1 * radius_positive;
            %
            %             for angleDegree = [ 0 : 30 : 360 ]
            %
            %                 x1 = round( centerX + (radius_positive * cos(angleDegree)) );
            %                 y1 = round( centerY + (radius_positive * sin(angleDegree)) );
            %
            %                 x2 = round( centerX + (radius_negative * cos(angleDegree)) );
            %                 y2 = round( centerY + (radius_negative * sin(angleDegree)) );
            %
            %                 plot([x1 x2],[y1 y2], 'Color', 'White', 'LineWidth',2)
            %             end


            % (3.5) Set figure x- and y- axis limit
            xlim(gca, [1 app.cmosDataColNum]);
            ylim(gca, [1 app.cmosDataRowNum]);
            grid on
            grid minor
            hold off

            % (4.1) Let the user draw a line
            roiLine = drawline(gca);

            if ishandle(roiLine) == 0 % The user close the figure directly withou drawing any roi

                msg = 'You have not truly draw a line. Draw a line and double click on it to set it.';
                uialert(app.OMZer_Vm_CV_UIFigure, msg,'Warning','Icon','error')

                MapListBoxValueChanged(app, event)

            else

                wait(roiLine); % If the user draws a roi, he should double click on the roi to truly set it up
                if ishandle(roiLine) == 0 % Although the user draws a roi, he does not double click on the roi to truly set it up

                    msg = 'You have not truly draw a line. Draw a line and double click on it to set it.';
                    uialert(app.OMZer_Vm_CV_UIFigure, msg,'Warning','Icon','error')

                    MapListBoxValueChanged(app, event)

                else % The user has truly set a region of interest

                    % Start point
                    XsPoint = round( roiLine.Position(1,1) ); % X-coordinate of line start
                    YsPoint = round( roiLine.Position(1,2) ); % Y-coordinate of line start
                    app.CVDirectingLineStartXY(1,1) = XsPoint;
                    app.CVDirectingLineStartXY(1,2) = YsPoint;

                    % End point
                    XePoint = round( roiLine.Position(2,1) ); % X-coordinate of line end
                    YePoint = round( roiLine.Position(2,2) ); % X-coordinate of line end
                    app.CVDirectingLineEndXY(1,1) = XePoint;
                    app.CVDirectingLineEndXY(1,2) = YePoint;

                    % Close the figure
                    delete(roiLine);
                    if ishandle(f) == 1
                        close(f)
                    end

                    % (4.2) Update line in the app
                    app.CVDirectingLinePlot = line(app.MapAxis, [XsPoint,XePoint], [YsPoint,YePoint], 'LineStyle','-', 'LineWidth',2.5, 'Color',[0.64,0.08,0.18]);
                    app.CVDirectingLinePlot.Marker = 'Pentagram';
                    app.CVDirectingLinePlot.MarkerSize = 10;
                    app.CVDirectingLinePlot.MarkerEdgeColor = 'White';
                    app.CVDirectingLinePlot.MarkerFaceColor = [0.64, 0.08, 0.18];

                    % (5.1) Find vectors within user-defiend area and update plot
                    app.CVDirectingLineSubVectorMatrix = searchCVDirectingLineSubVector(app);

                    % (5.2) Update the polar plot
                    app.CVUserSelectedVectorPolarPlot = plotCVUserSelectedVectorPolarPlot(app, app.CVUserSelectedVectorPolarPlot);

                    % (5.3) Update directing line subvector matrix
                    app.VectorMembersEditField.Value = sum( ~isnan(app.CVDirectingLineSubVectorMatrix(:)) );

                    hold(app.MapAxis, 'on')
                    [r,c] = find( ~isnan( abs(app.CVDirectingLineSubVectorMatrix) ) == 1 );
                    if isempty(r)  &&  isempty(c)

                        msg = 'Although you draw the directing line, no vectors can be found based on the current setup. Please adjust it.';
                        uialert(app.OMZer_Vm_CV_UIFigure, msg,'Warning','Icon','error')

                    else

                        app.CVDirectingLineSubVectorPlot = line( app.MapAxis, c,r, 'LineStyle','none', 'LineWidth',app.lWidth, 'Marker','o');
                        app.CVDirectingLineSubVectorPlot.MarkerSize = 6;
                        app.CVDirectingLineSubVectorPlot.MarkerEdgeColor = 'Red';
                        app.CVDirectingLineSubVectorPlot.MarkerFaceColor = 'None';
                    end
                    hold(app.MapAxis, 'off')

                    % (6) Update CV data
                    app.CVMapData{1, 9, checkMapID} = app.CVDirectingLineStartXY;
                    app.CVMapData{1, 10, checkMapID} = app.CVDirectingLineEndXY;
                    app.CVMapData{1, 11, checkMapID} = app.CVDirectingLineSubVectorMatrix;
                    app.CVMapData{1, 12, checkMapID} = app.LineAngleDegreeEditField.Value;
                    app.CVMapData{1, 13, checkMapID} = app.VectorMembersEditField.Value;
                    app.CVMapData{1, 14, checkMapID} = app.DistancewithinPixelEditField.Value;
                    app.CVMapData{1, 15, checkMapID} = app.AnglewithinDegreeEditField.Value;
                end
            end

            figure(app.OMZer_Vm_CV_UIFigure) % Set this uifigure at the top level

        end

        % Image clicked function: Image_AddVectors, 
        % Image_DeleteVectors
        function Image_AddDeleteVectorsClicked(app, event)

            if isempty(app.CVDirectingLineSubVectorMatrix)

                msg = 'Set the directing line and choose vectors to be calculated first';
                uialert(app.OMZer_Vm_CV_UIFigure, msg,'Warning','Icon','error')

                app.AddVectorsLabel.FontColor = [ 0.00, 0.00, 0.00 ]; % Black
                app.DeleteVectorsLabel.FontColor = [ 0.00, 0.00, 0.00 ]; % Black

            else

                if isequal( event.Source, app.Image_AddVectors ) % Add vectors

                    showImageIconAct(app, app.Image_AddVectors)

                    if isequal( app.AddVectorsLabel.FontColor, [ 0.00, 0.00, 0.00 ] ) % Black

                        app.AddVectorsLabel.FontColor = [ 1.00, 0.00, 0.00 ]; % Red
                        app.DeleteVectorsLabel.FontColor = [ 0.00, 0.00, 0.00 ]; % Black

                    elseif isequal( app.AddVectorsLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Red

                        app.AddVectorsLabel.FontColor = [ 0.00, 0.00, 0.00 ]; % Black
                    end

                elseif isequal( event.Source, app.Image_DeleteVectors ) % Delete vectors

                    showImageIconAct(app, app.Image_DeleteVectors)

                    if isequal( app.DeleteVectorsLabel.FontColor, [ 0.00, 0.00, 0.00 ] ) % Black

                        app.DeleteVectorsLabel.FontColor = [ 1.00, 0.00, 0.00 ]; % Red
                        app.AddVectorsLabel.FontColor = [ 0.00, 0.00, 0.00 ]; % Black

                    elseif isequal( app.DeleteVectorsLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Red

                        app.DeleteVectorsLabel.FontColor = [ 0.00, 0.00, 0.00 ]; % Black
                    end
                end

                if isequal( app.AddVectorsLabel.FontColor, [ 1.00, 0.00, 0.00 ] )  ||  isequal( app.DeleteVectorsLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Red

                    enableComponentsForAddDeleteVectors(app, 'off')

                else

                    enableComponentsForAddDeleteVectors(app, 'on')
                end

                % (1) Update directling line subvector plot
                app.CVDirectingLineSubVectorPlot = updateCVDirectingLineSubVectorPlot(app, app.CVDirectingLineSubVectorPlot);

                % (2) Update CV data
                checkMapID = str2double( app.MapListBox.Value );

                app.VectorMembersEditField.Value = sum( ~isnan(app.CVDirectingLineSubVectorMatrix(:)) );

                app.CVMapData{1, 11, checkMapID} = app.CVDirectingLineSubVectorMatrix;
                app.CVMapData{1, 13, checkMapID} = app.VectorMembersEditField.Value;

                % (3) For export data
                app.exportMapData = app.CVMapData(:, :, checkMapID);

                % (4) Update calculation
                Image_CalculateClicked(app, event)
            end

        end

        % Image clicked function: Image_Undo
        function Image_UndoClicked(app, event)

            showImageIconAct(app, app.Image_Undo)

            [r,c] = find( ~isnan( abs(app.CVDirectingLineSubVectorMatrix) ) == 1 );

            if isempty(app.CVDirectingLineSubVectorPlot)  ||  ( isempty(r)  &&  isempty(c) )

                msg = 'Set the directing line and choose vectors to be calculated first';
                uialert(app.OMZer_Vm_CV_UIFigure, msg,'Warning','Icon','error')

            else

                % (1) Clear statistics result (i.e., need re-calculation)
                resetStatisticResult(app)

                % (2) Clear old directing line sub vector matrix
                delete(app.CVDirectingLineSubVectorPlot)
                app.CVDirectingLineSubVectorPlot = [];

                if ~isempty( app.CVVectorAddDeletePlot )
                    for ID = 1 : length(app.CVVectorAddDeletePlot)
                        delete( app.CVVectorAddDeletePlot{ID} )
                    end
                    app.CVVectorAddDeletePlot = [];
                    app.CVVectorAddDeleteID = 1;
                end

                % (3) Find vectors within user-defiend area and update plot
                app.CVDirectingLineSubVectorMatrix = searchCVDirectingLineSubVector(app);

                app.VectorMembersEditField.Value = sum( ~isnan(app.CVDirectingLineSubVectorMatrix(:)) );

                hold(app.MapAxis, 'on')
                [r,c] = find( ~isnan( abs(app.CVDirectingLineSubVectorMatrix) ) == 1 );
                if isempty(r)  &&  isempty(c)

                    msg = 'Although you draw the directing line, no vectors can be found based on the current setup. Please adjust it.';
                    uialert(app.OMZer_Vm_CV_UIFigure, msg,'Warning','Icon','error')

                else

                    app.CVDirectingLineSubVectorPlot = line( app.MapAxis, c,r, 'LineStyle','none', 'LineWidth',app.lWidth, 'Marker','o');
                    app.CVDirectingLineSubVectorPlot.MarkerSize = 6;
                    app.CVDirectingLineSubVectorPlot.MarkerEdgeColor = 'Red';
                    app.CVDirectingLineSubVectorPlot.MarkerFaceColor = 'None';

                    Image_CalculateClicked(app, event)
                end
                hold(app.MapAxis, 'off')

                % (4) Update CV data
                checkMapID = str2double( app.MapListBox.Value );

                app.CVMapData{1, 11, checkMapID} = app.CVDirectingLineSubVectorMatrix;
                app.CVMapData{1, 13, checkMapID} = app.VectorMembersEditField.Value;
                app.CVMapData{1, 14, checkMapID} = app.DistancewithinPixelEditField.Value;
                app.CVMapData{1, 15, checkMapID} = app.AnglewithinDegreeEditField.Value;

                % (5) For export data
                app.exportMapData = app.CVMapData(:, :, checkMapID);
            end

        end

        % Value changed function: AnglewithinDegreeEditField, 
        % DistancewithinPixelEditField
        function DistancewithinPixel_AnglewithinDegree_EditFieldValueChanged(app, event)

            if ~isempty(app.CVDirectingLinePlot)

                % (1) Update polar plot
                app.CVUserSelectedVectorPolarPlot = plotCVUserSelectedVectorPolarPlot(app, app.CVUserSelectedVectorPolarPlot);

                % (2) Clear statistics result (i.e., need re-calculation)
                resetStatisticResult(app)

                % (3) Clear old directing line sub vector matrix
                delete(app.CVDirectingLineSubVectorPlot)
                app.CVDirectingLineSubVectorPlot = [];

                if ~isempty( app.CVVectorAddDeletePlot )
                    for ID = 1 : length(app.CVVectorAddDeletePlot)
                        delete( app.CVVectorAddDeletePlot{ID} )
                    end
                    app.CVVectorAddDeletePlot = [];
                    app.CVVectorAddDeleteID = 1;
                end

                % (4) Find vectors within user-defiend area and update plot
                app.CVDirectingLineSubVectorMatrix = searchCVDirectingLineSubVector(app);

                app.VectorMembersEditField.Value = sum( ~isnan(app.CVDirectingLineSubVectorMatrix(:)) );

                hold(app.MapAxis, 'on')
                [r,c] = find( ~isnan( abs(app.CVDirectingLineSubVectorMatrix) ) == 1 );
                if isempty(r)  &&  isempty(c)

                    msg = 'Although you draw the directing line, no vectors can be found based on the current setup. Please adjust it.';
                    uialert(app.OMZer_Vm_CV_UIFigure, msg,'Warning','Icon','error')

                else

                    app.CVDirectingLineSubVectorPlot = line( app.MapAxis, c,r, 'LineStyle','none', 'LineWidth',app.lWidth, 'Marker','o');
                    app.CVDirectingLineSubVectorPlot.MarkerSize = 6;
                    app.CVDirectingLineSubVectorPlot.MarkerEdgeColor = 'Red';
                    app.CVDirectingLineSubVectorPlot.MarkerFaceColor = 'None';

                    Image_CalculateClicked(app, event)
                end
                hold(app.MapAxis, 'off')

                % (5) Update CV data
                checkMapID = str2double( app.MapListBox.Value );

                app.CVMapData{1, 11, checkMapID} = app.CVDirectingLineSubVectorMatrix;
                app.CVMapData{1, 13, checkMapID} = app.VectorMembersEditField.Value;
                app.CVMapData{1, 14, checkMapID} = app.DistancewithinPixelEditField.Value;
                app.CVMapData{1, 15, checkMapID} = app.AnglewithinDegreeEditField.Value;

                % (6) For export data
                app.exportMapData = app.CVMapData(:, :, checkMapID);
            end

        end

        % Value changed function: MapListBox
        function MapListBoxValueChanged(app, event)

            % (1) Update ribbon menu and calculation components
            app.Image_ExportData.Enable = 'on';
            app.Image_ExportFigure.Enable = 'on';

            app.XResmmpixelEditField.Enable = 'off';
            app.YResmmpixelEditField.Enable = 'off';
            app.Image_CVVector.Enable = 'off';

            app.Image_DirectingLine.Enable = 'on';
            app.Image_AddVectors.Enable = 'on';
            app.Image_DeleteVectors.Enable = 'on';
            app.Image_Undo.Enable = 'on';

            % (2) Unload the previous map
            % (2.1) Reset statistic result
            resetStatisticResult(app)

            % (2.2) Reset directing line and directing line sub vector information
            app.LineAngleDegreeEditField.Value = 0;
            app.VectorMembersEditField.Value = 0;

            app.CVDirectingLineStartXY = [];
            app.CVDirectingLineEndXY = [];
            app.CVDirectingLinePlot = [];

            app.CVDirectingLineSubVectorMatrix = [];
            app.CVDirectingLineSubVectorPlot = [];

            app.CVUserSelectedVectorPolarPlot = [];

            app.CVVectorAddDeletePlot = [];
            app.CVVectorAddDeleteID = 1;

            % (2.3) Clear displayed map and signal plot
            app.MapTitleLabel.Text = 'Map Title';
            cla(app.MapAxis)
            cla(app.colorBarAxes)
            imagesc( app.MapAxis, app.bgImageNomGroup{app.cameraSource} );

            % (2.4) Clear check signal
            if ~isempty(app.checkSigMarkerPlot)

                clearCheckSigPlot(app)

                app.CheckSigX_scrn.Value = 1;
                app.CheckSigY_scrn.Value = 1;
                app.CheckSigX_scrn.Editable = 'off';
                app.CheckSigY_scrn.Editable = 'off';
                app.CheckSigX_scrn.BackgroundColor = [1.00, 1.00, 1.00]; % White
                app.CheckSigY_scrn.BackgroundColor = [1.00, 1.00, 1.00];
            end

            % (2.5) Clear map comment
            app.UserCommentTextArea.Value = {''};
            
            % (2.6) Reset view
            Image_DefaultViewClicked(app, event)

            % (3) Load new map data
            % (3.1) Enable map display components
            app.Image_Calculate.Enable = 'on';
            app.Image_UnloadMap.Enable = 'on';
            app.Image_DeleteMap.Enable = 'on';

            app.Image_DisplayPixel.Enable = 'on';
            app.Image_ClearPixel.Enable = 'on';

            app.UserCommentTextArea.Enable = 'on';

            % (3.2) Load data
            checkMapID = str2double(app.MapListBox.Value);

            clearActMapPlot(app)

            AreaMode = app.CVMapData{1,3,checkMapID};
            APMode = app.CVMapData{1,4,checkMapID};

            matrix = app.CVMapData{1,5,checkMapID}; % Act matrix

            if ~isempty( app.CVMapData{1,20,checkMapID} )
                app.UserCommentTextArea.Value = app.CVMapData{1,20,checkMapID};
            end

            app.MapTitleLabel.Text = [ AreaMode, ' ', APMode, ' Activation Map' ];

            % (3.3) Plot act map
            app.actMapPlot = plotMap(app, app.actMapPlot, matrix);
            app.actColorBarPlot = plotMapColorBar(app, app.actColorBarPlot, matrix);

            app.CVVectorMatrix = app.CVMapData{1,6,checkMapID};
            app.CVVectorAngleMatrix = app.CVMapData{1,7,checkMapID};
            app.CVVectorSpeedMatrix = app.CVMapData{1,8,checkMapID};

            % (3.4) Convert speed unit: 1 mm/msec == 1000 mm/sec == 100 cm/sec == 1 m/sec
            if isequal(app.UnitDropDown.Value, 'mm/msec')
                vectorSpeedUnitConverted = app.CVVectorSpeedMatrix;

            elseif isequal(app.UnitDropDown.Value, 'mm/sec')
                vectorSpeedUnitConverted = 1000 * app.CVVectorSpeedMatrix;

            elseif isequal(app.UnitDropDown.Value, 'cm/sec')
                vectorSpeedUnitConverted = 100 * app.CVVectorSpeedMatrix;

            elseif isequal(app.UnitDropDown.Value, 'm/sec')
                vectorSpeedUnitConverted = app.CVVectorSpeedMatrix;
            end

            % (3.5) Plot the polar plot
            % (3.5.1) https://www.mathworks.com/help/matlab/creating_guis/graphics-support-in-app-designer.html
            % (3.5.2) https://www.mathworks.com/help/matlab/ref/polarplot.html#namevaluepairarguments
            % (3.5.3) https://www.mathworks.com/help/matlab/ref/rlim.html
            % (3.5.4) https://www.mathworks.com/help/matlab/ref/thetalim.html
            % (3.5.5) https://www.mathworks.com/matlabcentral/answers/521133-polar-plot-in-app-designer-edit-not-possible-april-2020
            degree = (-180 : 180-1);
            num = length( degree );
            theta = nan(num,1); % Angle
            rho = nan(num,1); % Speed

            for ID = 1 : num
                if sum( app.CVVectorAngleMatrix(:) == degree(ID) ) ~= 0

                    theta(ID) = degree(ID); % Unit - degree
                    rho(ID) = mean( mean( vectorSpeedUnitConverted( app.CVVectorAngleMatrix == degree(ID) ) ) );
                end
            end
            theta = deg2rad(theta); % Unit - radian

            app.CVPolarPlot = polarplot(app.pax, theta, rho, '.-' );
            app.CVPolarPlot.LineWidth = 2;
            app.CVPolarPlot.MarkerSize = 10;

            app.pax.RMinorGrid = 'on';
            app.pax.ThetaLim = [-180, 180];
            app.pax.FontSize = 11;
            app.pax.FontWeight = 'bold';

            % (3.6) Plot CV vector map
            app.CVVectorMapPlot = plotCVVectorMap(app, app.CVVectorMapPlot, app.CVVectorMatrix, str2double(app.VectorLengthDropDown.Value));

            % (4) Load user selected directing line and directing line sub vectors if possible
            if ~isempty( app.CVMapData{1,9,checkMapID} )  &&  ~isempty( app.CVMapData{1,10,checkMapID} )

                app.CVDirectingLineStartXY = app.CVMapData{1,9,checkMapID};
                app.CVDirectingLineEndXY = app.CVMapData{1,10,checkMapID};
                app.CVDirectingLineSubVectorMatrix = app.CVMapData{1,11,checkMapID};

                app.LineAngleDegreeEditField.Value = app.CVMapData{1,12,checkMapID};
                app.VectorMembersEditField.Value = app.CVMapData{1,13,checkMapID};
                app.DistancewithinPixelEditField.Value = app.CVMapData{1,14,checkMapID};
                app.AnglewithinDegreeEditField.Value = app.CVMapData{1,15,checkMapID};

                % (4.1) Update directing line plot
                XsPoint = app.CVDirectingLineStartXY(1,1);
                YsPoint = app.CVDirectingLineStartXY(1,2);

                XePoint = app.CVDirectingLineEndXY(1,1);
                YePoint = app.CVDirectingLineEndXY(1,2);

                app.CVDirectingLinePlot = line(app.MapAxis, [XsPoint,XePoint], [YsPoint,YePoint], 'LineStyle','-', 'LineWidth',2.5, 'Color',[0.64,0.08,0.18]);
                app.CVDirectingLinePlot.Marker = 'Pentagram';
                app.CVDirectingLinePlot.MarkerSize = 10;
                app.CVDirectingLinePlot.MarkerEdgeColor = 'White';
                app.CVDirectingLinePlot.MarkerFaceColor = [0.64,0.08,0.18];

                app.CVUserSelectedVectorPolarPlot = plotCVUserSelectedVectorPolarPlot(app, app.CVUserSelectedVectorPolarPlot);

                % (4.2) Update directing line sub vectors plot
                hold(app.MapAxis, 'on')

                [r,c] = find( ~isnan( abs(app.CVDirectingLineSubVectorMatrix) ) == 1 );

                if isempty(r)  &&  isempty(c)

                    msg = 'Although you draw the directing line, no vectors can be found based on the current setup. Please adjust it.';
                    uialert(app.OMZer_Vm_CV_UIFigure, msg,'Warning','Icon','error')

                else

                    app.CVDirectingLineSubVectorPlot = line( app.MapAxis, c,r, 'LineStyle','none', 'LineWidth',app.lWidth, 'Marker','o');
                    app.CVDirectingLineSubVectorPlot.MarkerSize = 6;
                    app.CVDirectingLineSubVectorPlot.MarkerEdgeColor = 'Red';
                    app.CVDirectingLineSubVectorPlot.MarkerFaceColor = 'None';

                    Image_CalculateClicked(app, event) % Since sirecting line sub vectors exist, do calculation
                end

                hold(app.MapAxis, 'off')
            end

            % (6) For export data
            app.exportMapName = app.mainApp.CVLabel.Text;
            app.exportMapDataTitle = app.CVMapDataTitle;
            app.exportMapData = app.CVMapData(:, :, checkMapID);

        end

        % Value changed function: UnitDropDown
        function UnitDropDownValueChanged(app, event)

            [r,c] = find( ~isnan( abs(app.CVDirectingLineSubVectorMatrix) ) == 1 );

            if ~isempty(app.CVDirectingLineSubVectorPlot)  &&  ~isempty(r)  &&  ~isempty(c)

                Image_CalculateClicked(app, event)

                if isequal(app.Image_DisplayPixel.Enable, 'off')  &&  isequal(app.Image_ClearPixel.Enable, 'on' )

                    updateCheckCVVectorInfo(app)
                end
            end

        end

        % Image clicked function: Image_Calculate
        function Image_CalculateClicked(app, event)

            showImageIconAct(app, app.Image_Calculate)

            [r,c] = find( ~isnan( abs(app.CVDirectingLineSubVectorMatrix) ) == 1 );

            if isempty(app.CVDirectingLineSubVectorPlot)  ||  ( isempty(r)  &&  isempty(c) )

                msg = 'Set the directing line and choose vectors to be calculated first';
                uialert(app.OMZer_Vm_CV_UIFigure, msg,'Warning','Icon','error')

            else

                % (1) Convert speed unit: 1 mm/msec == 1000 mm/sec == 100 cm/sec == 1 m/sec
                meanSpeed = nanmean( abs(app.CVDirectingLineSubVectorMatrix(:)) ); % Unit in 'mm/msec'
                medianSpeed = nanmedian( abs(app.CVDirectingLineSubVectorMatrix(:)) ); % Unit in 'mm/msec
                SDSpeed = nanstd( abs(app.CVDirectingLineSubVectorMatrix(:)) ); % Unit in 'mm/msec

                if isequal(app.UnitDropDown.Value, 'mm/msec')

                    meanSpeedUnitConverted = meanSpeed;
                    medianSpeedUnitConverted = medianSpeed;
                    SDSpeedUnitConverted = SDSpeed;

                    vectorSpeedUnitConverted = app.CVVectorSpeedMatrix; % For polar plot

                elseif isequal(app.UnitDropDown.Value, 'mm/sec')

                    meanSpeedUnitConverted = 1000 * meanSpeed;
                    medianSpeedUnitConverted = 1000 * medianSpeed;
                    SDSpeedUnitConverted = 1000 * SDSpeed;

                    vectorSpeedUnitConverted = 1000 * app.CVVectorSpeedMatrix; % For polar plot

                elseif isequal(app.UnitDropDown.Value, 'cm/sec')

                    meanSpeedUnitConverted = 100 * meanSpeed;
                    medianSpeedUnitConverted = 100 * medianSpeed;
                    SDSpeedUnitConverted = 100 * SDSpeed;

                    vectorSpeedUnitConverted = 100 * app.CVVectorSpeedMatrix; % For polar plot

                elseif isequal(app.UnitDropDown.Value, 'm/sec')

                    meanSpeedUnitConverted = meanSpeed;
                    medianSpeedUnitConverted = medianSpeed;
                    SDSpeedUnitConverted = SDSpeed;

                    vectorSpeedUnitConverted = app.CVVectorSpeedMatrix; % For polar plot
                end

                app.MeanEditField.Value = meanSpeedUnitConverted;
                app.MedianEditField.Value = medianSpeedUnitConverted;
                app.SDEditField.Value = SDSpeedUnitConverted;

                % (2) Update Polar Plot Unit
                delete(app.CVPolarPlot)

                degree = (-180 : 180-1);
                num = length( degree );
                theta = nan(num,1);
                rho = nan(num,1);

                for ID = 1 : num

                    if sum( app.CVVectorAngleMatrix(:) == degree(ID) ) ~= 0

                        theta(ID) = degree(ID); % Unit - degree
                        rho(ID) = mean( mean( vectorSpeedUnitConverted( app.CVVectorAngleMatrix == degree(ID) ) ) );
                    end
                end

                theta = deg2rad(theta); % Unit - radian

                app.CVPolarPlot = polarplot(app.pax, theta, rho, '.-' );
                app.CVPolarPlot.LineWidth = 1.5;
                app.CVPolarPlot.MarkerSize = 10;

                app.pax.RMinorGrid = 'on';
                app.pax.ThetaLim = [-180, 180];
                app.pax.FontWeight = 'bold';

                app.CVUserSelectedVectorPolarPlot = plotCVUserSelectedVectorPolarPlot(app, app.CVUserSelectedVectorPolarPlot);
            end

        end

        % Image clicked function: Image_UnloadMap
        function Image_UnloadMapClicked(app, event)

            showImageIconAct(app, app.Image_UnloadMap)

            % (1) Update ribbon menu and calculation components
            app.Image_ExportData.Enable = 'off';
            app.Image_ExportFigure.Enable = 'off';

            app.XResmmpixelEditField.Enable = 'on';
            app.YResmmpixelEditField.Enable = 'on';
            app.Image_CVVector.Enable = 'on';

            app.Image_DirectingLine.Enable = 'off';
            app.Image_AddVectors.Enable = 'off';
            app.Image_DeleteVectors.Enable = 'off';
            app.Image_Undo.Enable = 'off';

            % (2) Unload the previous map
            % (2.1) Reset statistic result
            resetStatisticResult(app)

            % (2.2) Reset directing line and directing line sub vector information
            app.LineAngleDegreeEditField.Value = 0;
            app.VectorMembersEditField.Value = 0;

            app.CVDirectingLineStartXY = [];
            app.CVDirectingLineEndXY = [];
            app.CVDirectingLinePlot = [];

            app.CVDirectingLineSubVectorMatrix = [];
            app.CVDirectingLineSubVectorPlot = [];

            app.CVUserSelectedVectorPolarPlot = [];

            app.CVVectorAddDeletePlot = [];
            app.CVVectorAddDeleteID = 1;

            % (2.3) Clear displayed map and signal plot
            app.MapTitleLabel.Text = 'Map Title';
            cla(app.MapAxis)
            cla(app.colorBarAxes)
            imagesc( app.MapAxis, app.bgImageNomGroup{app.cameraSource} );

            % (2.4) Clear check signal
            if ~isempty(app.checkSigMarkerPlot)

                clearCheckSigPlot(app)

                app.CheckSigX_scrn.Value = 1;
                app.CheckSigY_scrn.Value = 1;
                app.CheckSigX_scrn.Editable = 'off';
                app.CheckSigY_scrn.Editable = 'off';
                app.CheckSigX_scrn.BackgroundColor = [1.00, 1.00, 1.00]; % White
                app.CheckSigY_scrn.BackgroundColor = [1.00, 1.00, 1.00];
            end

            % (3) Disable map display components
            app.Image_Calculate.Enable = 'off';
            app.Image_UnloadMap.Enable = 'off';
            app.Image_DeleteMap.Enable = 'off';

            app.Image_DisplayPixel.Enable = 'off';
            app.Image_ClearPixel.Enable = 'off';

            app.UserCommentTextArea.Enable = 'off';
            app.UserCommentTextArea.Value = {''};

            app.CVVectorMatrix = [];
            app.CVVectorAngleMatrix = [];
            app.CVVectorSpeedMatrix = [];

            % (4) Uncheck the MapListBox
            app.MapListBox.Value = {};

            % (5) For export data
            app.exportMapName = [];
            app.exportMapDataTitle = [];
            app.exportMapData = [];

        end

        % Image clicked function: Image_DeleteMap
        function Image_DeleteMapClicked(app, event)

            showImageIconAct(app, app.Image_DeleteMap)

            checkMapID = str2double(app.MapListBox.Value);

            % (1) Delete data
            parameterNumber = length( app.CVMapDataTitle );
            dataTemporary = cell(1, parameterNumber, app.maxMapNumber);
            app.CVMapData(:,:, checkMapID) = [];

            dataTemporary(:,:, 1:app.maxMapNumber-1) = app.CVMapData;
            app.CVMapData = dataTemporary;

            % (2) Update Save to ID
            app.CVSaveToID = app.CVSaveToID - 1;

            % (3) Update 'MapListBox'
            updateMapListBox(app)

            Image_UnloadMapClicked(app, 'ImageClicked')

        end

        % Image clicked function: Image_DisplayPixel
        function Image_DisplayPixelClicked(app, event)

            showImageIconAct(app, app.Image_DisplayPixel)

            % (1) Update components
            app.Image_DisplayPixel.Enable = 'off';

            app.CheckSigX_scrn.Editable = 'on';
            app.CheckSigY_scrn.Editable = 'on';
            app.CheckSigX_scrn.BackgroundColor = [1.00, 1.00, 0.00]; % Yellow
            app.CheckSigY_scrn.BackgroundColor = [1.00, 1.00, 0.00];

            % (2) Plot the initial marker
            checkSigColor = [0.00, 0.45, 0.74];

            app.checkSigMarkerXY = [ round(0.5*app.cmosDataRowNum), round(0.5*app.cmosDataColNum) ];
            app.CheckSigX_scrn.Value = app.checkSigMarkerXY(1,1);
            app.CheckSigY_scrn.Value = app.checkSigMarkerXY(1,2);

            % (2.1) Marker plot
            delete(app.checkSigMarkerPlot)
            app.checkSigMarkerPlot = updateCheckSigMarker(app, checkSigColor);

            % (2.2) Marker Highlight Plot
            delete(app.checkSigMarkerHighlightPlot)
            app.checkSigMarkerHighlightPlot = updateCheckSigMarkerHighlight(app);

            % (3) Get the data
            updateCheckCVVectorInfo(app)

        end

        % Image clicked function: Image_ClearPixel
        function Image_ClearPixelClicked(app, event)

            showImageIconAct(app, app.Image_ClearPixel)

            if isempty( app.checkSigMarkerPlot )

                msg = 'Display the signal first';
                uialert(app.OMZer_Vm_CV_UIFigure, msg,'Warning','Icon','error')

            else

                app.Image_DisplayPixel.Enable = 'on';

                app.CheckSigX_scrn.Editable = 'off';
                app.CheckSigY_scrn.Editable = 'off';
                app.CheckSigX_scrn.BackgroundColor = [1.00, 1.00, 1.00]; % White
                app.CheckSigY_scrn.BackgroundColor = [1.00, 1.00, 1.00];

                clearCheckSigPlot(app)
            end

        end

        % Value changed function: UserCommentTextArea
        function UserCommentTextAreaValueChanged(app, event)

            checkMapID = str2double(app.MapListBox.Value);

            app.CVMapData{1,20,checkMapID} = app.UserCommentTextArea.Value;

        end

        % Window scroll wheel function: OMZer_Vm_CV_UIFigure
        function OMZer_Vm_CV_UIFigureWindowScrollWheel(app, event)

            verticalScrollAmount = event.VerticalScrollAmount;
            verticalScrollCount = event.VerticalScrollCount;

            app.mouseXY = app.OMZer_Vm_CV_UIFigure.CurrentPoint; % Returns (x, y) coordinates of the mouse pointer, measured from the lower-left corner of the figure.
            app.mapAxisLocs = app.MapAxis.InnerPosition;

            if ( app.mouseXY(1,1) >= app.mapAxisLocs(1,1) )  ...
                    &&  ( app.mouseXY(1,1) <= app.mapAxisLocs(1,1) + app.mapAxisLocs(1,3) )  ...
                    &&  ( app.mouseXY(1,2) >= app.mapAxisLocs(1,2) )  ...
                    &&  ( app.mouseXY(1,2) <= app.mapAxisLocs(1,2) + app.mapAxisLocs(1,4) )

                if verticalScrollCount > 0

                    app.userAlphaValue = app.userAlphaValue + 0.1;

                    if app.userAlphaValue >= 1
                        app.userAlphaValue = 1;
                    end

                elseif verticalScrollCount < 0

                    app.userAlphaValue = app.userAlphaValue - 0.1;

                    if app.userAlphaValue <= 0
                        app.userAlphaValue = 0;
                    end
                end

                adjustMapTransparency(app)
            end

        end

        % Window button down function: OMZer_Vm_CV_UIFigure
        function OMZer_Vm_CV_UIFigureWindowButtonDown(app, event)

            app.mouseMotion = app.OMZer_Vm_CV_UIFigure.SelectionType; % The result can be 'normal', 'extend', 'alt', 'open'

            if isequal(app.mouseMotion,'normal') % 'normal' means single-click any mouse button.

                app.mouseXY = app.OMZer_Vm_CV_UIFigure.CurrentPoint; % Returns (x, y) coordinates of the mouse pointer, measured from the lower-left corner of the figure.
                app.mapAxisLocs = app.MapAxis.InnerPosition; % Returns a 1-by-4 row vector -  [left bottom width height].

                if ( app.mouseXY(1,1) >= app.mapAxisLocs(1,1) )  ...
                        &&  ( app.mouseXY(1,1) <= app.mapAxisLocs(1,1) + app.mapAxisLocs(1,3) )  ...
                        &&  ( app.mouseXY(1,2) >= app.mapAxisLocs(1,2) )  ...
                        &&  ( app.mouseXY(1,2) <= app.mapAxisLocs(1,2) + app.mapAxisLocs(1,4) )

                    % (1) Add a CV vector
                    if ( ~isempty(app.CVDirectingLineSubVectorMatrix) )  &&  isequal( app.AddVectorsLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Red

                        X = round( app.MapAxis.CurrentPoint(1,1) ); % X is column in matlab matrix
                        if X < 1
                            X = 1;
                        elseif X > app.cmosDataColNum
                            X = app.cmosDataColNum;
                        end
                        Y = round( app.MapAxis.CurrentPoint(1,2) ); % Y is row in matlab matrix
                        if Y < 1
                            Y = 1;
                        elseif Y > app.cmosDataRowNum
                            Y = app.cmosDataRowNum;
                        end

                        app.CVDirectingLineSubVectorMatrix(Y,X) = app.CVVectorMatrix(Y,X);

                        % Plot the user-defined vector
                        app.CVVectorAddDeletePlot{ app.CVVectorAddDeleteID } = line( app.MapAxis, X,Y, 'Marker','Hexagram', 'MarkerSize',8, 'MarkerEdgeColor','red', 'MarkerFaceColor','yellow');
                        app.CVVectorAddDeleteID = app.CVVectorAddDeleteID + 1;


                        % (2) Delete a vector
                    elseif ( ~isempty(app.CVDirectingLineSubVectorMatrix) )  &&  isequal( app.DeleteVectorsLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Red

                        X = round( app.MapAxis.CurrentPoint(1,1) ); % X is column in matlab matrix
                        if X < 1
                            X = 1;
                        elseif X > app.cmosDataColNum
                            X = app.cmosDataColNum;
                        end
                        Y = round( app.MapAxis.CurrentPoint(1,2) ); % Y is row in matlab matrix
                        if Y < 1
                            Y = 1;
                        elseif Y > app.cmosDataRowNum
                            Y = app.cmosDataRowNum;
                        end

                        app.CVDirectingLineSubVectorMatrix(Y,X) = NaN+0i;

                        % Plot the user-defined vector
                        app.CVVectorAddDeletePlot{ app.CVVectorAddDeleteID } = line( app.MapAxis, X,Y, 'Marker','Square', 'MarkerSize',8, 'MarkerEdgeColor','red', 'MarkerFaceColor','yellow');
                        app.CVVectorAddDeleteID = app.CVVectorAddDeleteID + 1;


                    elseif isequal(app.Image_DisplayPixel.Enable, 'off')  &&  isequal(app.Image_ClearPixel.Enable, 'on')

                        % (3) Check signal
                        updateMouseXY(app, app.mapAxisLocs);

                        if ( abs(app.mouseXY(1,1) - app.checkSigMarkerXY(1,1)) <= 5 )  &&  ( abs(app.mouseXY(1,2) - app.checkSigMarkerXY(1,2)) <= 5 ) % Find which marker is within 5 pixels of the mouse location

                            app.checkSigMouseDragID = 1;
                        end

                    end

                end
            end

        end

        % Window button motion function: OMZer_Vm_CV_UIFigure
        function OMZer_Vm_CV_UIFigureWindowButtonMotion(app, event)

            if ~isempty(app.checkSigMouseDragID)  &&  isequal(app.Image_DisplayPixel.Enable, 'off')  &&  isequal(app.Image_ClearPixel.Enable, 'on')

                app.mouseXY = app.OMZer_Vm_CV_UIFigure.CurrentPoint; % Returns (x, y) coordinates of the mouse pointer, measured from the lower-left corner of the figure.
                app.mapAxisLocs = app.MapAxis.InnerPosition; % Returns a 1-by-4 row vector -  [left bottom width height].

                if ( app.mouseXY(1,1) >= app.mapAxisLocs(1,1) )  ...
                        &&  ( app.mouseXY(1,1) <= app.mapAxisLocs(1,1) + app.mapAxisLocs(1,3) )  ...
                        &&  ( app.mouseXY(1,2) >= app.mapAxisLocs(1,2) )  ...
                        &&  ( app.mouseXY(1,2) <= app.mapAxisLocs(1,2) + app.mapAxisLocs(1,4) )

                    updateMouseXY(app, app.mapAxisLocs);

                    app.checkSigMarkerXY(1,1) = app.mouseXY(1,1);
                    app.checkSigMarkerXY(1,2) = app.mouseXY(1,2);

                    app.CheckSigX_scrn.Value = app.checkSigMarkerXY(1,1);
                    app.CheckSigY_scrn.Value = app.checkSigMarkerXY(1,2);

                    CheckSigXY_scrnValueChanged(app, event)
                end
            end

        end

        % Window button up function: OMZer_Vm_CV_UIFigure
        function OMZer_Vm_CV_UIFigureWindowButtonUp(app, event)

            app.checkSigMouseDragID = [];

        end

        % Value changed function: CheckSigX_scrn, CheckSigY_scrn
        function CheckSigXY_scrnValueChanged(app, event)

            app.checkSigMarkerXY(1,1) = app.CheckSigX_scrn.Value;
            app.checkSigMarkerXY(1,2) = app.CheckSigY_scrn.Value;

            checkSigColor = [0.00, 0.45, 0.74];

            % (1) Marker plot
            delete(app.checkSigMarkerPlot)
            app.checkSigMarkerPlot = updateCheckSigMarker(app, checkSigColor);

            % (2) Marker Highlight Plot
            delete(app.checkSigMarkerHighlightPlot)
            app.checkSigMarkerHighlightPlot = updateCheckSigMarkerHighlight(app);

            % (3) Get the data
            updateCheckCVVectorInfo(app)

        end

        % Window key press function: OMZer_Vm_CV_UIFigure
        function OMZer_Vm_CV_UIFigureWindowKeyPress(app, event)
            
            % key = event.Key;
            
            app.keyPressHistory{app.keyPressID} = event.Key;
            app.keyPressID = app.keyPressID + 1;

            % (1) Save file callback
            if length( app.keyPressHistory ) == 2

                if ( ismac == 1 )  &&  isequal( app.keyPressHistory{1}, 'meta' )  &&  isequal( app.keyPressHistory{2}, 's' )  &&  ( isequal( app.Image_Save.Enable, 'on' ) )

                    Image_SaveClicked(app, 'clicked')

                elseif ( ispc == 1 )  &&  isequal( app.keyPressHistory{1}, 'control' )  &&  isequal( app.keyPressHistory{2}, 's' )  &&  ( isequal( app.Image_Save.Enable, 'on' ) )

                    Image_SaveClicked(app, 'clicked')
                end
            end

            % (2) Open folder callback
            if length( app.keyPressHistory ) == 2

                if ( ismac == 1 )  &&  isequal( app.keyPressHistory{1}, 'meta' )  &&  isequal( app.keyPressHistory{2}, 'o' )

                    Image_OpenFolderClicked(app, 'clicked')

                elseif ( ispc == 1 )  &&  isequal( app.keyPressHistory{1}, 'control' )  &&  isequal( app.keyPressHistory{2}, 'o' )

                    Image_OpenFolderClicked(app, 'clicked')
                end
            end
            
        end

        % Window key release function: OMZer_Vm_CV_UIFigure
        function OMZer_Vm_CV_UIFigureWindowKeyRelease(app, event)
            
            % key = event.Key;
            
            app.keyPressID = 1;
            app.keyPressHistory = {};

        end

        % Close request function: OMZer_Vm_CV_UIFigure
        function OMZer_Vm_CV_UIFigureCloseRequest(app, event)

            % (1) Set mainApp at the top level
            figure(app.mainAppUIFigure)

            % (2) Update the mainApp
            app.mainApp.OMZer_Vm_CV = [];

            delete(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OMZer_Vm_CV_UIFigure = uifigure('Visible', 'off');
            app.OMZer_Vm_CV_UIFigure.Name = 'OMZer Vm CV';
            app.OMZer_Vm_CV_UIFigure.CloseRequestFcn = createCallbackFcn(app, @OMZer_Vm_CV_UIFigureCloseRequest, true);
            app.OMZer_Vm_CV_UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @OMZer_Vm_CV_UIFigureWindowButtonDown, true);
            app.OMZer_Vm_CV_UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @OMZer_Vm_CV_UIFigureWindowButtonUp, true);
            app.OMZer_Vm_CV_UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @OMZer_Vm_CV_UIFigureWindowButtonMotion, true);
            app.OMZer_Vm_CV_UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @OMZer_Vm_CV_UIFigureWindowScrollWheel, true);
            app.OMZer_Vm_CV_UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @OMZer_Vm_CV_UIFigureWindowKeyPress, true);
            app.OMZer_Vm_CV_UIFigure.WindowKeyReleaseFcn = createCallbackFcn(app, @OMZer_Vm_CV_UIFigureWindowKeyRelease, true);
            app.MapAxis = uiaxes(app.OMZer_Vm_CV_UIFigure);
            app.MapAxis.XLim = [1 100];
            app.MapAxis.YLim = [1 100];
            app.MapAxis.YDir = 'reverse';
            app.MapAxis.XTick = [];
            app.MapAxis.YTick = [];
            app.MapAxis.ZTick = [];
            app.colorBarAxes = uiaxes(app.OMZer_Vm_CV_UIFigure);
            title(app.colorBarAxes, 'ms')
            app.colorBarAxes.XTick = [];
            app.TabGroup = uitabgroup(app.OMZer_Vm_CV_UIFigure);
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.XResmmpixelEditFieldLabel = uilabel(app.AnalysisTab);
            app.XResmmpixelEditFieldLabel.Text = 'X Res (mm/pixel)';
            app.XResmmpixelEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.YResmmpixelEditFieldLabel = uilabel(app.AnalysisTab);
            app.YResmmpixelEditFieldLabel.Text = 'Y Res (mm/pixel)';
            app.YResmmpixelEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.Image_CVVector = uiimage(app.AnalysisTab);
            app.Image_CVVector.ImageClickedFcn = createCallbackFcn(app, @Image_CVVectorClicked, true);
            app.Image_CVVector.ImageSource = 'Icon Vector.png';
            app.CVVectorLabel = uilabel(app.AnalysisTab);
            app.CVVectorLabel.Text = 'CV Vector';
            app.VectorLengthDropDownLabel = uilabel(app.AnalysisTab);
            app.VectorLengthDropDownLabel.Text = 'Vector Length';
            app.VectorLengthDropDown = uidropdown(app.AnalysisTab);
            app.VectorLengthDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15'};
            app.VectorLengthDropDown.ValueChangedFcn = createCallbackFcn(app, @VectorLengthDropDownValueChanged, true);
            app.VectorLengthDropDown.Value = '2';
            app.VectorColorDropDownLabel = uilabel(app.AnalysisTab);
            app.VectorColorDropDownLabel.Text = 'Vector Color';
            app.VectorColorDropDown = uidropdown(app.AnalysisTab);
            app.VectorColorDropDown.Items = {'White', 'Black', 'Red', 'Blue', 'Yellow'};
            app.VectorColorDropDown.ValueChangedFcn = createCallbackFcn(app, @VectorColorDropDownValueChanged, true);
            app.VectorColorDropDown.Value = 'White';
            app.Image_DefaultView = uiimage(app.AnalysisTab);
            app.Image_DefaultView.ImageClickedFcn = createCallbackFcn(app, @Image_DefaultViewClicked, true);
            app.Image_DefaultView.ImageSource = 'Icon View.png';
            app.DefaultViewLabel = uilabel(app.AnalysisTab);
            app.DefaultViewLabel.Text = 'Default View';
            app.ToolsTab = uitab(app.TabGroup);
            app.ToolsTab.Title = 'Tools';
            app.Image_Save = uiimage(app.ToolsTab);
            app.Image_Save.ImageClickedFcn = createCallbackFcn(app, @Image_SaveClicked, true);
            app.Image_Save.ImageSource = 'Icon Save.png';
            app.SaveLabel = uilabel(app.ToolsTab);
            app.SaveLabel.Text = 'Save';
            app.Image_ExportData = uiimage(app.ToolsTab);
            app.Image_ExportData.ImageClickedFcn = createCallbackFcn(app, @Image_ExportDataClicked, true);
            app.Image_ExportData.Enable = 'off';
            app.Image_ExportData.ImageSource = 'Icon Export.png';
            app.ExportDataLabel = uilabel(app.ToolsTab);
            app.ExportDataLabel.Text = 'Export Data';
            app.Image_ExportFigure = uiimage(app.ToolsTab);
            app.Image_ExportFigure.ImageClickedFcn = createCallbackFcn(app, @Image_ExportFigureClicked, true);
            app.Image_ExportFigure.Enable = 'off';
            app.Image_ExportFigure.ImageSource = 'Icon Export Figure.png';
            app.ExportFigureLabel = uilabel(app.ToolsTab);
            app.ExportFigureLabel.Text = 'Export Figure';
            app.PreferenceTab = uitab(app.TabGroup);
            app.PreferenceTab.Title = 'Preference';
            app.MapListBoxLabel = uilabel(app.OMZer_Vm_CV_UIFigure);
            app.MapListBoxLabel.Text = 'Map';
            app.MapListBox = uilistbox(app.OMZer_Vm_CV_UIFigure);
            app.MapListBox.Items = {};
            app.MapListBox.ValueChangedFcn = createCallbackFcn(app, @MapListBoxValueChanged, true);
            app.MapListBox.Value = {};
            app.UserCommentTextAreaLabel = uilabel(app.OMZer_Vm_CV_UIFigure);
            app.UserCommentTextAreaLabel.Text = 'User Comment';
            app.UserCommentTextArea = uitextarea(app.OMZer_Vm_CV_UIFigure);
            app.UserCommentTextArea.ValueChangedFcn = createCallbackFcn(app, @UserCommentTextAreaValueChanged, true);
            app.UserCommentTextArea.Enable = 'off';
            app.UserCommentTextArea.Tooltip = {''};
            app.MeanEditFieldLabel = uilabel(app.OMZer_Vm_CV_UIFigure);
            app.MeanEditFieldLabel.Text = 'Mean';
            app.MeanEditField = uieditfield(app.OMZer_Vm_CV_UIFigure, 'numeric');
            app.MeanEditField.Editable = 'off';
            app.MedianEditFieldLabel = uilabel(app.OMZer_Vm_CV_UIFigure);
            app.MedianEditFieldLabel.Text = 'Median';
            app.MedianEditField = uieditfield(app.OMZer_Vm_CV_UIFigure, 'numeric');
            app.MedianEditField.Editable = 'off';
            app.SDEditFieldLabel = uilabel(app.OMZer_Vm_CV_UIFigure);
            app.SDEditFieldLabel.Text = 'SD';
            app.SDEditField = uieditfield(app.OMZer_Vm_CV_UIFigure, 'numeric');
            app.SDEditField.Editable = 'off';
            app.UnitDropDownLabel = uilabel(app.OMZer_Vm_CV_UIFigure);
            app.UnitDropDownLabel.Text = 'Unit';
            app.UnitDropDown = uidropdown(app.OMZer_Vm_CV_UIFigure);
            app.UnitDropDown.Items = {'mm/msec', 'mm/sec', 'cm/sec', 'm/sec'};
            app.UnitDropDown.ValueChangedFcn = createCallbackFcn(app, @UnitDropDownValueChanged, true);
            app.UnitDropDown.Value = 'm/sec';
            app.Image_Calculate = uiimage(app.OMZer_Vm_CV_UIFigure);
            app.Image_Calculate.ImageClickedFcn = createCallbackFcn(app, @Image_CalculateClicked, true);
            app.Image_Calculate.Enable = 'off';
            app.Image_Calculate.ImageSource = 'Icon Do.png';
            app.CalculateLabel = uilabel(app.OMZer_Vm_CV_UIFigure);
            app.CalculateLabel.Text = 'Calculate';
            app.Image_DeleteMap = uiimage(app.OMZer_Vm_CV_UIFigure);
            app.Image_DeleteMap.ImageClickedFcn = createCallbackFcn(app, @Image_DeleteMapClicked, true);
            app.Image_DeleteMap.Enable = 'off';
            app.Image_DeleteMap.ImageSource = 'Icon Delete.png';
            app.DeleteMapLabel = uilabel(app.OMZer_Vm_CV_UIFigure);
            app.DeleteMapLabel.Text = 'Delete Map';
            app.Image_UnloadMap = uiimage(app.OMZer_Vm_CV_UIFigure);
            app.Image_UnloadMap.ImageClickedFcn = createCallbackFcn(app, @Image_UnloadMapClicked, true);
            app.Image_UnloadMap.Enable = 'off';
            app.Image_UnloadMap.ImageSource = 'Icon Unload Map.png';
            app.UnloadMapLabel = uilabel(app.OMZer_Vm_CV_UIFigure);
            app.UnloadMapLabel.Text = 'Unload Map';
            app.MapTitleLabel = uilabel(app.OMZer_Vm_CV_UIFigure);
            app.MapTitleLabel.Text = 'Map Title';
            app.PolarPanel = uipanel(app.OMZer_Vm_CV_UIFigure);
            app.PolarPanel.Title = 'Polar';
            app.Image_DirectingLine = uiimage(app.OMZer_Vm_CV_UIFigure);
            app.Image_DirectingLine.ImageClickedFcn = createCallbackFcn(app, @Image_DirectingLineClicked, true);
            app.Image_DirectingLine.Enable = 'off';
            app.Image_DirectingLine.ImageSource = 'Icon Directing Line.png';
            app.DirectingLineLabel = uilabel(app.OMZer_Vm_CV_UIFigure);
            app.DirectingLineLabel.Text = 'Directing Line';
            app.LineAngleDegreeEditFieldLabel = uilabel(app.OMZer_Vm_CV_UIFigure);
            app.LineAngleDegreeEditFieldLabel.Text = 'Line Angle (Degree)';
            app.LineAngleDegreeEditField = uieditfield(app.OMZer_Vm_CV_UIFigure, 'numeric');
            app.LineAngleDegreeEditField.Editable = 'off';
            app.VectorMembersEditFieldLabel = uilabel(app.OMZer_Vm_CV_UIFigure);
            app.VectorMembersEditFieldLabel.Text = '# Vector Members';
            app.VectorMembersEditField = uieditfield(app.OMZer_Vm_CV_UIFigure, 'numeric');
            app.VectorMembersEditField.Limits = [0 Inf];
            app.VectorMembersEditField.Editable = 'off';
            app.DistancewithinPixelEditFieldLabel = uilabel(app.OMZer_Vm_CV_UIFigure);
            app.DistancewithinPixelEditFieldLabel.Text = 'Distance within +/- (Pixel)';
            app.DistancewithinPixelEditField = uieditfield(app.OMZer_Vm_CV_UIFigure, 'numeric');
            app.DistancewithinPixelEditField.Limits = [0 Inf];
            app.DistancewithinPixelEditField.ValueDisplayFormat = '%.0f';
            app.DistancewithinPixelEditField.ValueChangedFcn = createCallbackFcn(app, @DistancewithinPixel_AnglewithinDegree_EditFieldValueChanged, true);
            app.DistancewithinPixelEditField.Value = 5;
            app.AnglewithinDegreeEditFieldLabel = uilabel(app.OMZer_Vm_CV_UIFigure);
            app.AnglewithinDegreeEditFieldLabel.Text = 'Angle within +/- (Degree)';
            app.AnglewithinDegreeEditField = uieditfield(app.OMZer_Vm_CV_UIFigure, 'numeric');
            app.AnglewithinDegreeEditField.Limits = [0 Inf];
            app.AnglewithinDegreeEditField.ValueDisplayFormat = '%.0f';
            app.AnglewithinDegreeEditField.ValueChangedFcn = createCallbackFcn(app, @DistancewithinPixel_AnglewithinDegree_EditFieldValueChanged, true);
            app.AnglewithinDegreeEditField.Value = 8;
            app.Image_DeleteVectors = uiimage(app.OMZer_Vm_CV_UIFigure);
            app.Image_DeleteVectors.ImageClickedFcn = createCallbackFcn(app, @Image_AddDeleteVectorsClicked, true);
            app.Image_DeleteVectors.Enable = 'off';
            app.Image_DeleteVectors.ImageSource = 'Icon Minus.png';
            app.DeleteVectorsLabel = uilabel(app.OMZer_Vm_CV_UIFigure);
            app.DeleteVectorsLabel.Text = 'Delete Vectors';
            app.Image_AddVectors = uiimage(app.OMZer_Vm_CV_UIFigure);
            app.Image_AddVectors.ImageClickedFcn = createCallbackFcn(app, @Image_AddDeleteVectorsClicked, true);
            app.Image_AddVectors.Enable = 'off';
            app.Image_AddVectors.ImageSource = 'Icon Plus.png';
            app.AddVectorsLabel = uilabel(app.OMZer_Vm_CV_UIFigure);
            app.AddVectorsLabel.Text = 'Add Vectors';
            app.Image_Undo = uiimage(app.OMZer_Vm_CV_UIFigure);
            app.Image_Undo.ImageClickedFcn = createCallbackFcn(app, @Image_UndoClicked, true);
            app.Image_Undo.Enable = 'off';
            app.Image_Undo.ImageSource = 'Icon Undo.png';
            app.UndoLabel = uilabel(app.OMZer_Vm_CV_UIFigure);
            app.UndoLabel.Text = 'Undo';
            app.VectorAngelDegreeEditFieldLabel = uilabel(app.OMZer_Vm_CV_UIFigure);
            app.VectorAngelDegreeEditFieldLabel.Text = 'Vector Angel (Degree)';
            app.VectorAngelDegreeEditField = uieditfield(app.OMZer_Vm_CV_UIFigure, 'numeric');
            app.VectorAngelDegreeEditField.Editable = 'off';
            app.VectorAngelDegreeEditField.Value = Inf;
            app.VectorSpeedEditFieldLabel = uilabel(app.OMZer_Vm_CV_UIFigure);
            app.VectorSpeedEditFieldLabel.Text = 'Vector Speed';
            app.VectorSpeedEditField = uieditfield(app.OMZer_Vm_CV_UIFigure, 'numeric');
            app.VectorSpeedEditField.Limits = [0 Inf];
            app.VectorSpeedEditField.Editable = 'off';
            app.VectorSpeedEditField.Value = Inf;
            app.Image_DisplayPixel = uiimage(app.OMZer_Vm_CV_UIFigure);
            app.Image_DisplayPixel.ImageClickedFcn = createCallbackFcn(app, @Image_DisplayPixelClicked, true);
            app.Image_DisplayPixel.Enable = 'off';
            app.Image_DisplayPixel.ImageSource = 'Icon Display Pixel.png';
            app.DisplayPixelLabel = uilabel(app.OMZer_Vm_CV_UIFigure);
            app.DisplayPixelLabel.Text = 'Display Pixel';
            app.Image_ClearPixel = uiimage(app.OMZer_Vm_CV_UIFigure);
            app.Image_ClearPixel.ImageClickedFcn = createCallbackFcn(app, @Image_ClearPixelClicked, true);
            app.Image_ClearPixel.Enable = 'off';
            app.Image_ClearPixel.ImageSource = 'Icon Clear Pixel.png';
            app.ClearPixelLabel = uilabel(app.OMZer_Vm_CV_UIFigure);
            app.ClearPixelLabel.Text = 'Clear Pixel';
            app.CheckSigXYLabel = uilabel(app.OMZer_Vm_CV_UIFigure);
            app.CheckSigXYLabel.Tooltip = {'X (red) and Y (blue) coordinate of the marker. Please be noted that X is column and Y is row to locate an element in a matrix in MATLAB.'};
            app.CheckSigXYLabel.Text = 'X,Y';
            app.CheckSigY_scrn = uieditfield(app.OMZer_Vm_CV_UIFigure, 'numeric');
            app.CheckSigY_scrn.Limits = [0 100];
            app.CheckSigY_scrn.ValueDisplayFormat = '%.0f';
            app.CheckSigY_scrn.ValueChangedFcn = createCallbackFcn(app, @CheckSigXY_scrnValueChanged, true);
            app.CheckSigY_scrn.Editable = 'off';
            app.CheckSigY_scrn.Tooltip = {'Y coordinate of the 1st marker (Note: row in a matlab matrix)'};
            app.CheckSigX_scrn = uieditfield(app.OMZer_Vm_CV_UIFigure, 'numeric');
            app.CheckSigX_scrn.Limits = [0 100];
            app.CheckSigX_scrn.ValueDisplayFormat = '%.0f';
            app.CheckSigX_scrn.ValueChangedFcn = createCallbackFcn(app, @CheckSigXY_scrnValueChanged, true);
            app.CheckSigX_scrn.Editable = 'off';
            app.CheckSigX_scrn.Tooltip = {'X coordinate of the 1st marker (Note: column in a matlab matrix)'};
            app.UseMouseScrollWheeltoAdjustMapColorTransparencyLabel = uilabel(app.OMZer_Vm_CV_UIFigure);
            app.UseMouseScrollWheeltoAdjustMapColorTransparencyLabel.Text = 'Use Mouse Scroll Wheel to Adjust Map Color Transparency';
            % Show the figure after all components are created
            app.OMZer_Vm_CV_UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OMZer_Vm_CV(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.OMZer_Vm_CV_UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.OMZer_Vm_CV_UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OMZer_Vm_CV_UIFigure)
        end
    end
end