classdef OMZer_Vm_ExportFigure < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OMZer_Vm_ExportFigure_UIFigure  matlab.ui.Figure
        UserCommentTextArea             matlab.ui.control.TextArea
        UserCommentTextAreaLabel        matlab.ui.control.Label
        MapTitleLabel                   matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        ToolsTab                        matlab.ui.container.Tab
        CautionScalebarlengthis1mmLabel  matlab.ui.control.Label
        ExportLabel                     matlab.ui.control.Label
        Image_Export                    matlab.ui.control.Image
        ColorBarRangeTab                matlab.ui.container.Tab
        ScaleBarTab                     matlab.ui.container.Tab
        CautionScalebarlengthis1mmLabel_2  matlab.ui.control.Label
        ScaleBarSizeSpinner             matlab.ui.control.Spinner
        ScaleBarSizeSpinnerLabel        matlab.ui.control.Label
        FieldofViewmmSpinner            matlab.ui.control.Spinner
        FieldofViewmmSpinnerLabel       matlab.ui.control.Label
        ScaleBarColorLabel              matlab.ui.control.Label
        Image_ScaleBarColor             matlab.ui.control.Image
        ScaleBarCheckBox                matlab.ui.control.CheckBox
        MarkerTab                       matlab.ui.container.Tab
        DeleteAMarkerLabel              matlab.ui.control.Label
        AddAMarkerLabel                 matlab.ui.control.Label
        Image_DeleteAMarker             matlab.ui.control.Image
        Image_AddAMarker                matlab.ui.control.Image
        CautionExportsignallengthisthesameasviewlengthLabel  matlab.ui.control.Label
        MarkerSizeSpinner               matlab.ui.control.Spinner
        MarkerSizeSpinnerLabel          matlab.ui.control.Label
        FigureTab                       matlab.ui.container.Tab
        CautionBoxLineWidthonlyworkswhenFigureBoxischeckedLabel  matlab.ui.control.Label
        BoxLineWidthSpinner             matlab.ui.control.Spinner
        BoxLineWidthSpinnerLabel        matlab.ui.control.Label
        FigureBoxCheckBox               matlab.ui.control.CheckBox
        OnlyShowForegroundCheckBox      matlab.ui.control.CheckBox
        ImageSmoothTab                  matlab.ui.container.Tab
        ViewEndsecSpinner               matlab.ui.control.Spinner
        ViewEndsecSpinnerLabel          matlab.ui.control.Label
        ViewStartsecSpinner             matlab.ui.control.Spinner
        ViewStartsecSpinnerLabel        matlab.ui.control.Label
        RestoreViewLabel                matlab.ui.control.Label
        Image_RestoreView               matlab.ui.control.Image
        X_scrn6                         matlab.ui.control.NumericEditField
        Y_scrn6                         matlab.ui.control.NumericEditField
        X_scrn5                         matlab.ui.control.NumericEditField
        Y_scrn5                         matlab.ui.control.NumericEditField
        X_scrn4                         matlab.ui.control.NumericEditField
        Y_scrn4                         matlab.ui.control.NumericEditField
        X_scrn3                         matlab.ui.control.NumericEditField
        Y_scrn3                         matlab.ui.control.NumericEditField
        X_scrn2                         matlab.ui.control.NumericEditField
        Y_scrn2                         matlab.ui.control.NumericEditField
        X_scrn1                         matlab.ui.control.NumericEditField
        Y_scrn1                         matlab.ui.control.NumericEditField
        XYLabel                         matlab.ui.control.Label
        MapHistAxis                     matlab.ui.control.UIAxes
        MapAxis                         matlab.ui.control.UIAxes
        colorBarAxes                    matlab.ui.control.UIAxes
        scrn6                           matlab.ui.control.UIAxes
        scrn5                           matlab.ui.control.UIAxes
        scrn4                           matlab.ui.control.UIAxes
        scrn3                           matlab.ui.control.UIAxes
        scrn2                           matlab.ui.control.UIAxes
        scrn1                           matlab.ui.control.UIAxes
    end


    properties (Access = public)

        % The Parent App --------------------
        mainApp
        mainAppUIFigure


        % The Current App --------------------
        dirName % File directory
        fileName % Filename without path and extension

        setup % User preference
        userAlphaValue

        imageIconActColor = [0.80, 0.80, 0.80]; % The activation color when an image is clicked

        lWidth = 2; % Line width

        data
        acqFreq
        signalTime
        cmosDataGroup
        bgImageNomGroup % Gray colour RGB version of background image

        cameraSource % '1 (or 2)' - data from camera 1 (or 2)

        mapAxisLocs

        maxMarkerNumber = 6;

        clickCount = 1; % Record how many times the mouse double-click in imageAxis1 and imageAxis2
        mouseMotion
        scaleBarDragID
        mouseXY
        scrnObject % To store 6 scrn axis objects
        Xs % The x coordinate edit box
        Ys % The y coordinate edit box

        cmosDataRowNum % The numer of rows in cmosData
        cmosDataColNum % The numer of columns in cmosData

        maskPlotObject

        scaleBarPlotObject
        scaleBarPixelNum
        scaleBarColor = [1, 1, 1]; % White

        ColorID
        Color1 = [0.00,0.45,0.74];
        Color2 = [0.93,0.69,0.13];
        Color3 = [0.64,0.08,0.18];
        Color4 = [0.72,0.27,1.00];
        Color5 = [0.47,0.67,0.19];
        Color6 = [0.89,0.47,0.58];

        % (1) For Act
        actMapDataTitle
        actMapData

        % (2) For Rep
        repMapDataTitle
        repMapData

        % (3) For APD
        APDMapDataTitle
        APDMapData

        % (4) For RT
        RTMapDataTitle
        RTMapData

        % (5) For Check Signal
        checkSigMarkerXY
        checkSigMouseDragID

        checkSigDataSectionTime
        checkSigDataSection
        checkSigLocsAct
        checkSigLocsRep
        checkSigLocsStart
        checkSigLocsEnd

        checkSigMapData
        checkSigLB
        checkSigUB

        checkSigMarkerPlotObject = cell(1,6);
        checkSigPlotObject = cell(1,6);

        checkSigActPointPlotObject = cell(1,6);
        checkSigRepPointPlotObject = cell(1,6);
        checkSigStartPointPlotObject = cell(1,6);
        checkSigEndPointPlotObject = cell(1,6);


        % For export
        exportBgImage

        exportMapPlot
        exportColorBarPlot
        exportHistPlot

        pixelSaveToID = 1;
        exportPixelX
        exportPixelY
    end


    methods (Access = public)


        function showImageIconAct(app, imageIconName)

            imageIconName.BackgroundColor = app.imageIconActColor; %[0.80, 0.80, 0.80];
            pause(0.05)
            imageIconName.BackgroundColor = 'none';
        end


        function setColourMap(app,name)

            colormap(app.MapAxis, name);
            colormap(app.colorBarAxes, name);
        end


        function setAxisLimit(app, xMaxLimit, yMaxLimit) % Set the x-y limit of all axes

            app.MapAxis.XLim = [1, xMaxLimit];
            app.MapAxis.YLim = [1, yMaxLimit];
        end


        function setScrnLimit(app, LB, UB)

            app.ViewStartsecSpinner.Limits = [ LB, UB ];
            app.ViewEndsecSpinner.Limits = [ LB, UB ];

            app.ViewStartsecSpinner.Value = LB;
            app.ViewEndsecSpinner.Value = UB;

            for ID = 1:6
                xlim( app.scrnObject{ID}, [ app.ViewStartsecSpinner.Value, app.ViewEndsecSpinner.Value ] )
            end
        end


        function newPlotObject = plotMap(app, plotObject, matrix, LB, UB)

            matrix( matrix < LB  |  matrix > UB ) = NaN;

            if isequal( app.mainApp.ActLabel.FontColor, [ 1.00, 0.00, 0.00 ] )  ...
                    ||  isequal( app.mainApp.RepLabel.FontColor, [ 1.00, 0.00, 0.00 ] )

                matrix = matrix - nanmin( matrix(:) ); % Shift the time bar such that Act (or Rep) Map always starts from 0
            end

            hold(app.MapAxis,'on')
            delete(plotObject)

            % newPlotObject = imagesc(app.MapAxis, matrix);
            % newPlotObject.AlphaData = ~isnan(matrix) * app.userAlphaValue; % Make the pixel with NaN transparent

            %{
            newPlotObject = pcolor( app.MapAxis, matrix );
            newPlotObject.FaceColor = 'interp';
            newPlotObject.EdgeColor = 'none';
            newPlotObject.AlphaData = ~isnan(matrix) * app.userAlphaValue; % Make the pixel with NaN transparent
            %}
            
            newPlotObject = contourf( app.MapAxis, matrix );
            
            
            if nanmin(matrix(:)) == nanmax(matrix(:))
                caxis(app.MapAxis, [ nanmin(matrix(:)), nanmax(matrix(:))+1 ]);
            else
                caxis(app.MapAxis, [ nanmin(matrix(:)), nanmax(matrix(:)) ]);
            end
            hold(app.MapAxis,'off')
        end


        function newPlotObject = plotMapColorBar(app, plotObject, matrix, LB, UB)

            matrix( matrix < LB  |  matrix > UB ) = NaN;

            if isequal( app.mainApp.ActLabel.FontColor, [ 1.00, 0.00, 0.00 ] )  ...
                    ||  isequal( app.mainApp.RepLabel.FontColor, [ 1.00, 0.00, 0.00 ] )

                matrix = matrix - nanmin( matrix(:) ); % Shift the time bar such that Act (or Rep) Map always starts from 0
            end

            matrixColumnVersion = reshape( matrix, [],1 );
            matrixColumnVersion( isnan(matrixColumnVersion) ) = [];
            matrixColumnVersion = unique(matrixColumnVersion);

            % (1) Plot the color bar
            delete(plotObject)
            newPlotObject = imagesc(app.colorBarAxes, matrixColumnVersion);
            yticks(app.colorBarAxes, 'auto')
            app.colorBarAxes.YLim = [ 1, length(matrixColumnVersion) ];

            % (2) Manually create y tick label
            if length(app.colorBarAxes.YTick) <= length(matrixColumnVersion)

                app.colorBarAxes.YTick( mod(app.colorBarAxes.YTick , 1) ~= 0 ) = []; % Only keep integer tick

            else
                app.colorBarAxes.YTick = [ 1 : length(matrixColumnVersion) ];
            end

            colorBarLabel = cell( 1, length(app.colorBarAxes.YTick) );
            for ID = 1 : length(app.colorBarAxes.YTick)
                colorBarLabel{ID} = num2str( matrixColumnVersion(app.colorBarAxes.YTick(ID)) );
            end
            app.colorBarAxes.YTickLabel = colorBarLabel;
        end


        function newPlotObject = plotMapHist(app, plotObject, matrix, LB, UB)

            matrix( matrix < LB  |  matrix > UB ) = NaN;

            histColumnVersion = reshape(matrix, [],1);
            histColumnVersion( isnan(histColumnVersion) ) = []; % Delete NaN because it will also be counted into 'data length'
            binNum = round( sqrt( length(histColumnVersion) ) ); % Specifies the number of bins (= square root of the number of observations)

            hold(app.MapHistAxis,'on')
            delete(plotObject)

            %            newPlotObject = histogram(app.MapHistAxis, histColumnVersion, binNum, 'FaceColor',[0.00, 0.45, 0.74], 'FaceAlpha',1);

            newPlotObject = histogram(app.MapHistAxis, histColumnVersion, 'FaceColor',[0.00, 0.45, 0.74], 'FaceAlpha',1);


            %             % Colourful Histgtam (Nedd code update)
            %             if isequal( app.Setup.VmMapCalSetup{13,2}, 'Multiple' )
            %
            %                 newPlotObject.FaceAlpha = 0;
            %
            %                 histBinEdge = newPlotObject.BinEdges;
            %                 eval( [ 'colorLevel = ', app.userColourMap, '(', num2str(binNum), ');' ] )
            %                 app.colorHistPlot = cell(binNum,1);
            %
            %                 for ID = 1 : binNum
            %                     app.colorHistPlot{ID} = histogram( app.MapHistAxis, HistCol( HistCol >= histBinEdge(ID) & HistCol < histBinEdge(ID+1) ), histBinEdge, 'FaceColor',colorLevel(ID,:), 'FaceAlpha',1 );
            %                 end
            %             end

            hold(app.MapHistAxis,'off')

            if min(histColumnVersion) == max(histColumnVersion)
                xlim( app.MapHistAxis, [min(histColumnVersion), max(histColumnVersion)+1] );
            else
                xlim( app.MapHistAxis, [min(histColumnVersion), max(histColumnVersion)] );
            end

            app.MapHistAxis.YLim = [ 0, max(newPlotObject.BinCounts) ];
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, OMZer_Vm)

            msg = 'Configuring files';
            progressBar = uiprogressdlg(app.OMZer_Vm_ExportFigure_UIFigure, 'Title','Initialising', 'Message',msg, 'Indeterminate','on');
            drawnow

            % (1) User set up
            app.setup = load([pwd,'/OMZer_Setup']);
            setColourMap(app, app.setup.userColourMap);
            app.userAlphaValue = 1.0; % The default alpha value

            % (2) Get information from the mainApp
            app.mainApp = OMZer_Vm;
            app.mainAppUIFigure = app.mainApp.OMZer_Vm_UIFigure;

            app.dirName = app.mainApp.dirName;
            app.fileName = app.mainApp.fileName;

            app.data = app.mainApp.data;
            app.acqFreq = app.mainApp.acqFreq;
            app.signalTime = app.mainApp.signalTime;
            app.cmosDataGroup = app.mainApp.cmosDataGroup;
            app.bgImageNomGroup = app.mainApp.bgImageNomGroup;
            app.cameraSource = app.mainApp.cameraSource;

            app.actMapDataTitle = app.mainApp.actMapDataTitle;
            app.actMapData = app.mainApp.actMapData;

            app.repMapDataTitle = app.mainApp.repMapDataTitle;
            app.repMapData = app.mainApp.repMapData;

            app.APDMapDataTitle = app.mainApp.APDMapDataTitle;
            app.APDMapData = app.mainApp.APDMapData;

            app.RTMapDataTitle = app.mainApp.RTMapDataTitle;
            app.RTMapData = app.mainApp.RTMapData;

            % (3) Create components collection
            app.scrnObject = {app.scrn1, app.scrn2, app.scrn3, app.scrn4, app.scrn5, app.scrn6};
            app.Xs = {app.X_scrn1, app.X_scrn2, app.X_scrn3, app.X_scrn4, app.X_scrn5, app.X_scrn6};
            app.Ys = {app.Y_scrn1, app.Y_scrn2, app.Y_scrn3, app.Y_scrn4, app.Y_scrn5, app.Y_scrn6};
            app.ColorID = {app.Color1, app.Color2, app.Color3, app.Color4, app.Color5, app.Color6};

            % (4) Diable Toolbar
            % https://www.mathworks.com/help/matlab/ref/axtoolbar.html?searchHighlight=axtoolbar&s_tid=doc_srchtitle
            try
                disableDefaultInteractivity(app.colorBarAxes);
                disableDefaultInteractivity(app.MapAxis);
                disableDefaultInteractivity(app.MapHistAxis);

                axtoolbar(app.colorBarAxes);
                axtoolbar(app.MapAxis);
                axtoolbar(app.MapHistAxis);

                for ID = 1 : 6
                    disableDefaultInteractivity( app.scrnObject{ID} );
                    axtoolbar( app.scrnObject{ID} );
                end

            catch
                % Do nothing
            end

            % (5) Plot background image
            [app.cmosDataRowNum, app.cmosDataColNum, ~] = size( app.cmosDataGroup{app.cameraSource} );
            setAxisLimit(app, app.cmosDataColNum, app.cmosDataRowNum);

            imagesc( app.MapAxis, app.bgImageNomGroup{app.cameraSource} );

            app.exportBgImage = app.bgImageNomGroup{app.cameraSource};

            % (6) Plot map
            checkMapID = str2double(app.mainApp.MapListBox.Value);

            if isequal( app.mainApp.ActLabel.FontColor, [ 1.00, 0.00, 0.00 ] ) % Act map

                AreaMode = app.actMapData{1,3,checkMapID};
                APMode = app.actMapData{1,4,checkMapID};

                app.checkSigLB = app.actMapData{1,5,checkMapID};
                app.checkSigUB = app.actMapData{1,6,checkMapID};
                app.checkSigMapData = app.actMapData{1,10,checkMapID};
                app.checkSigMapData( app.checkSigMapData < app.checkSigLB  |  app.checkSigMapData > app.checkSigUB ) = NaN;

                app.checkSigDataSectionTime = app.actMapData{1,7,checkMapID};
                app.checkSigDataSection = app.actMapData{1,8,checkMapID};
                app.checkSigLocsAct = app.actMapData{1,9,checkMapID};

                LB = 0;
                UB = app.checkSigDataSectionTime(end);
                setScrnLimit(app, LB, UB)

                if ~isempty( app.actMapData{1,12,checkMapID} )
                    app.UserCommentTextArea.Value = app.actMapData{1,12,checkMapID};
                end

                app.MapTitleLabel.Text = [ AreaMode, ' ', APMode, ' Activation Map' ];

                app.exportMapPlot = plotMap(app, app.exportMapPlot, app.checkSigMapData, app.checkSigLB, app.checkSigUB);
                app.exportColorBarPlot = plotMapColorBar(app, app.exportColorBarPlot, app.checkSigMapData, app.checkSigLB, app.checkSigUB);
                app.exportHistPlot = plotMapHist(app, app.exportHistPlot, app.checkSigMapData, app.checkSigLB, app.checkSigUB);


                % For export data
                %app.exportMapName = app.ActLabel.Text;
                %app.exportMapDataTitle = app.actMapDataTitle;
                %app.exportMapData = app.actMapData(:, :, checkMapID);
            end


            close(progressBar)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OMZer_Vm_ExportFigure_UIFigure = uifigure('Visible', 'off');
            app.OMZer_Vm_ExportFigure_UIFigure.Name = 'OMZer Vm Export Figure';
            app.scrn1 = uiaxes(app.OMZer_Vm_ExportFigure_UIFigure);
            app.scrn2 = uiaxes(app.OMZer_Vm_ExportFigure_UIFigure);
            app.scrn3 = uiaxes(app.OMZer_Vm_ExportFigure_UIFigure);
            app.scrn4 = uiaxes(app.OMZer_Vm_ExportFigure_UIFigure);
            app.scrn5 = uiaxes(app.OMZer_Vm_ExportFigure_UIFigure);
            app.scrn6 = uiaxes(app.OMZer_Vm_ExportFigure_UIFigure);
            app.colorBarAxes = uiaxes(app.OMZer_Vm_ExportFigure_UIFigure);
            title(app.colorBarAxes, 'ms')
            app.colorBarAxes.XTick = [];
            app.MapAxis = uiaxes(app.OMZer_Vm_ExportFigure_UIFigure);
            app.MapAxis.XLim = [1 100];
            app.MapAxis.YLim = [1 100];
            app.MapAxis.YDir = 'reverse';
            app.MapAxis.XTick = [];
            app.MapAxis.YTick = [];
            app.MapAxis.ZTick = [];
            app.MapHistAxis = uiaxes(app.OMZer_Vm_ExportFigure_UIFigure);
            xlabel(app.MapHistAxis, 'Time (ms)')
            ylabel(app.MapHistAxis, 'Counts')
            app.MapHistAxis.ZTick = [];
            app.XYLabel = uilabel(app.OMZer_Vm_ExportFigure_UIFigure);
            app.XYLabel.Tooltip = {'X (red) and Y (blue) coordinate of the marker. Please be noted that X is column and Y is row to locate an element in a matrix in MATLAB.'};
            app.XYLabel.Text = 'X,Y';
            app.Y_scrn1 = uieditfield(app.OMZer_Vm_ExportFigure_UIFigure, 'numeric');
            app.Y_scrn1.Limits = [0 100];
            app.Y_scrn1.ValueDisplayFormat = '%.0f';
            app.Y_scrn1.Editable = 'off';
            app.Y_scrn1.Tooltip = {'Y coordinate of the 1st marker (Note: row in a matlab matrix)'};
            app.X_scrn1 = uieditfield(app.OMZer_Vm_ExportFigure_UIFigure, 'numeric');
            app.X_scrn1.Limits = [0 100];
            app.X_scrn1.ValueDisplayFormat = '%.0f';
            app.X_scrn1.Editable = 'off';
            app.X_scrn1.Tooltip = {'X coordinate of the 1st marker (Note: column in a matlab matrix)'};
            app.Y_scrn2 = uieditfield(app.OMZer_Vm_ExportFigure_UIFigure, 'numeric');
            app.Y_scrn2.Limits = [0 100];
            app.Y_scrn2.ValueDisplayFormat = '%.0f';
            app.Y_scrn2.Editable = 'off';
            app.Y_scrn2.Tooltip = {'Y coordinate of the 1st marker (Note: row in a matlab matrix)'};
            app.X_scrn2 = uieditfield(app.OMZer_Vm_ExportFigure_UIFigure, 'numeric');
            app.X_scrn2.Limits = [0 100];
            app.X_scrn2.ValueDisplayFormat = '%.0f';
            app.X_scrn2.Editable = 'off';
            app.X_scrn2.Tooltip = {'X coordinate of the 1st marker (Note: column in a matlab matrix)'};
            app.Y_scrn3 = uieditfield(app.OMZer_Vm_ExportFigure_UIFigure, 'numeric');
            app.Y_scrn3.Limits = [0 100];
            app.Y_scrn3.ValueDisplayFormat = '%.0f';
            app.Y_scrn3.Editable = 'off';
            app.Y_scrn3.Tooltip = {'Y coordinate of the 1st marker (Note: row in a matlab matrix)'};
            app.X_scrn3 = uieditfield(app.OMZer_Vm_ExportFigure_UIFigure, 'numeric');
            app.X_scrn3.Limits = [0 100];
            app.X_scrn3.ValueDisplayFormat = '%.0f';
            app.X_scrn3.Editable = 'off';
            app.X_scrn3.Tooltip = {'X coordinate of the 1st marker (Note: column in a matlab matrix)'};
            app.Y_scrn4 = uieditfield(app.OMZer_Vm_ExportFigure_UIFigure, 'numeric');
            app.Y_scrn4.Limits = [0 100];
            app.Y_scrn4.ValueDisplayFormat = '%.0f';
            app.Y_scrn4.Editable = 'off';
            app.Y_scrn4.Tooltip = {'Y coordinate of the 1st marker (Note: row in a matlab matrix)'};
            app.X_scrn4 = uieditfield(app.OMZer_Vm_ExportFigure_UIFigure, 'numeric');
            app.X_scrn4.Limits = [0 100];
            app.X_scrn4.ValueDisplayFormat = '%.0f';
            app.X_scrn4.Editable = 'off';
            app.X_scrn4.Tooltip = {'X coordinate of the 1st marker (Note: column in a matlab matrix)'};
            app.Y_scrn5 = uieditfield(app.OMZer_Vm_ExportFigure_UIFigure, 'numeric');
            app.Y_scrn5.Limits = [0 100];
            app.Y_scrn5.ValueDisplayFormat = '%.0f';
            app.Y_scrn5.Editable = 'off';
            app.Y_scrn5.Tooltip = {'Y coordinate of the 1st marker (Note: row in a matlab matrix)'};
            app.X_scrn5 = uieditfield(app.OMZer_Vm_ExportFigure_UIFigure, 'numeric');
            app.X_scrn5.Limits = [0 100];
            app.X_scrn5.ValueDisplayFormat = '%.0f';
            app.X_scrn5.Editable = 'off';
            app.X_scrn5.Tooltip = {'X coordinate of the 1st marker (Note: column in a matlab matrix)'};
            app.Y_scrn6 = uieditfield(app.OMZer_Vm_ExportFigure_UIFigure, 'numeric');
            app.Y_scrn6.Limits = [0 100];
            app.Y_scrn6.ValueDisplayFormat = '%.0f';
            app.Y_scrn6.Editable = 'off';
            app.Y_scrn6.Tooltip = {'Y coordinate of the 1st marker (Note: row in a matlab matrix)'};
            app.X_scrn6 = uieditfield(app.OMZer_Vm_ExportFigure_UIFigure, 'numeric');
            app.X_scrn6.Limits = [0 100];
            app.X_scrn6.ValueDisplayFormat = '%.0f';
            app.X_scrn6.Editable = 'off';
            app.X_scrn6.Tooltip = {'X coordinate of the 1st marker (Note: column in a matlab matrix)'};
            app.Image_RestoreView = uiimage(app.OMZer_Vm_ExportFigure_UIFigure);
            app.Image_RestoreView.ImageSource = 'Icon Restore View.png';
            app.RestoreViewLabel = uilabel(app.OMZer_Vm_ExportFigure_UIFigure);
            app.RestoreViewLabel.Text = 'Restore View';
            app.ViewStartsecSpinnerLabel = uilabel(app.OMZer_Vm_ExportFigure_UIFigure);
            app.ViewStartsecSpinnerLabel.Text = 'View Start (sec)';
            app.ViewStartsecSpinner = uispinner(app.OMZer_Vm_ExportFigure_UIFigure);
            app.ViewStartsecSpinner.Step = 0.05;
            app.ViewStartsecSpinner.Limits = [0 Inf];
            app.ViewEndsecSpinnerLabel = uilabel(app.OMZer_Vm_ExportFigure_UIFigure);
            app.ViewEndsecSpinnerLabel.Text = 'View End (sec)';
            app.ViewEndsecSpinner = uispinner(app.OMZer_Vm_ExportFigure_UIFigure);
            app.ViewEndsecSpinner.Step = 0.05;
            app.ViewEndsecSpinner.Limits = [0 Inf];
            app.ViewEndsecSpinner.Value = Inf;
            app.TabGroup = uitabgroup(app.OMZer_Vm_ExportFigure_UIFigure);
            app.ToolsTab = uitab(app.TabGroup);
            app.ToolsTab.Title = 'Tools';
            app.Image_Export = uiimage(app.ToolsTab);
            app.Image_Export.ImageSource = 'Icon Export.png';
            app.ExportLabel = uilabel(app.ToolsTab);
            app.ExportLabel.Text = 'Export';
            app.CautionScalebarlengthis1mmLabel = uilabel(app.ToolsTab);
            app.CautionScalebarlengthis1mmLabel.Text = 'Caution: If signals are plotted, signals data will be exported as a .mat file';
            app.ColorBarRangeTab = uitab(app.TabGroup);
            app.ColorBarRangeTab.Title = 'ColorBar Range';
            app.ScaleBarTab = uitab(app.TabGroup);
            app.ScaleBarTab.Title = 'Scale Bar';
            app.ScaleBarCheckBox = uicheckbox(app.ScaleBarTab);
            app.ScaleBarCheckBox.Text = 'Scale Bar';
            app.Image_ScaleBarColor = uiimage(app.ScaleBarTab);
            app.Image_ScaleBarColor.ImageSource = 'Icon Color.png';
            app.ScaleBarColorLabel = uilabel(app.ScaleBarTab);
            app.ScaleBarColorLabel.Text = 'Scale Bar Color';
            app.FieldofViewmmSpinnerLabel = uilabel(app.ScaleBarTab);
            app.FieldofViewmmSpinnerLabel.Text = 'Field of View (mm)';
            app.FieldofViewmmSpinner = uispinner(app.ScaleBarTab);
            app.FieldofViewmmSpinner.Limits = [1 Inf];
            app.FieldofViewmmSpinner.ValueDisplayFormat = '%.0f';
            app.FieldofViewmmSpinner.Value = 15;
            app.ScaleBarSizeSpinnerLabel = uilabel(app.ScaleBarTab);
            app.ScaleBarSizeSpinnerLabel.Text = 'Scale Bar Size';
            app.ScaleBarSizeSpinner = uispinner(app.ScaleBarTab);
            app.ScaleBarSizeSpinner.Limits = [1 Inf];
            app.ScaleBarSizeSpinner.ValueDisplayFormat = '%.0f';
            app.ScaleBarSizeSpinner.Value = 10;
            app.CautionScalebarlengthis1mmLabel_2 = uilabel(app.ScaleBarTab);
            app.CautionScalebarlengthis1mmLabel_2.Text = 'Caution: Scale bar length is 1 mm';
            app.MarkerTab = uitab(app.TabGroup);
            app.MarkerTab.Title = 'Marker';
            app.MarkerSizeSpinnerLabel = uilabel(app.MarkerTab);
            app.MarkerSizeSpinnerLabel.Text = 'Marker Size';
            app.MarkerSizeSpinner = uispinner(app.MarkerTab);
            app.MarkerSizeSpinner.Limits = [10 Inf];
            app.MarkerSizeSpinner.ValueDisplayFormat = '%.0f';
            app.MarkerSizeSpinner.Value = 10;
            app.CautionExportsignallengthisthesameasviewlengthLabel = uilabel(app.MarkerTab);
            app.CautionExportsignallengthisthesameasviewlengthLabel.Text = 'Caution: Export signal length is the same as view length';
            app.Image_AddAMarker = uiimage(app.MarkerTab);
            app.Image_AddAMarker.ImageSource = 'Icon Plus.png';
            app.Image_DeleteAMarker = uiimage(app.MarkerTab);
            app.Image_DeleteAMarker.ImageSource = 'Icon Delete.png';
            app.AddAMarkerLabel = uilabel(app.MarkerTab);
            app.AddAMarkerLabel.Text = 'Add A Marker';
            app.DeleteAMarkerLabel = uilabel(app.MarkerTab);
            app.DeleteAMarkerLabel.Text = 'Delete A Marker';
            app.FigureTab = uitab(app.TabGroup);
            app.FigureTab.Title = 'Figure';
            app.OnlyShowForegroundCheckBox = uicheckbox(app.FigureTab);
            app.OnlyShowForegroundCheckBox.Text = 'Only Show Foreground';
            app.FigureBoxCheckBox = uicheckbox(app.FigureTab);
            app.FigureBoxCheckBox.Text = 'Figure Box';
            app.BoxLineWidthSpinnerLabel = uilabel(app.FigureTab);
            app.BoxLineWidthSpinnerLabel.Text = 'Box LineWidth';
            app.BoxLineWidthSpinner = uispinner(app.FigureTab);
            app.BoxLineWidthSpinner.Limits = [1 Inf];
            app.BoxLineWidthSpinner.ValueDisplayFormat = '%.0f';
            app.BoxLineWidthSpinner.Value = 1;
            app.CautionBoxLineWidthonlyworkswhenFigureBoxischeckedLabel = uilabel(app.FigureTab);
            app.CautionBoxLineWidthonlyworkswhenFigureBoxischeckedLabel.Text = 'Caution: "Box LineWidth" only works when "Figure Box" is checked; click "Export" to see the effect';
            app.ImageSmoothTab = uitab(app.TabGroup);
            app.ImageSmoothTab.Title = 'Image Smooth';
            app.MapTitleLabel = uilabel(app.OMZer_Vm_ExportFigure_UIFigure);
            app.MapTitleLabel.Text = 'Map Title';
            app.UserCommentTextAreaLabel = uilabel(app.OMZer_Vm_ExportFigure_UIFigure);
            app.UserCommentTextAreaLabel.Text = 'User Comment';
            app.UserCommentTextArea = uitextarea(app.OMZer_Vm_ExportFigure_UIFigure);
            app.UserCommentTextArea.Editable = 'off';
            app.UserCommentTextArea.Tooltip = {''};
            % Show the figure after all components are created
            app.OMZer_Vm_ExportFigure_UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OMZer_Vm_ExportFigure(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.OMZer_Vm_ExportFigure_UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.OMZer_Vm_ExportFigure_UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OMZer_Vm_ExportFigure_UIFigure)
        end
    end
end