classdef OMZer_Vm_Phase < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OMZer_Vm_Phase_UIFigure   matlab.ui.Figure
        FluorescenceSignalLabel   matlab.ui.control.Label
        ExportDataLabel           matlab.ui.control.Label
        Image_ExportData          matlab.ui.control.Image
        Wavefrontswhiteplotlinesaredefinedastheisophaselinesalong2Label  matlab.ui.control.Label
        RadianLabel               matlab.ui.control.Label
        MoviesecSpinner           matlab.ui.control.Spinner
        MoviesecSpinnerLabel      matlab.ui.control.Label
        RestoreViewLabel          matlab.ui.control.Label
        Image_RestoreView         matlab.ui.control.Image
        MapTitleLabel             matlab.ui.control.Label
        CameraNALabel             matlab.ui.control.Label
        Image_ActivatedCamera     matlab.ui.control.Image
        ClearPixelLabel           matlab.ui.control.Label
        Image_ClearPixel          matlab.ui.control.Image
        DisplayPixelLabel         matlab.ui.control.Label
        Image_DisplayPixel        matlab.ui.control.Image
        MovieSlider               matlab.ui.control.Slider
        CautionPhaseCalculationlengthisthesameasviewlengthLabel  matlab.ui.control.Label
        GlobalMapLabel            matlab.ui.control.Label
        Image_GlobalMap           matlab.ui.control.Image
        RegionalMapLabel          matlab.ui.control.Label
        Image_RegionalMap         matlab.ui.control.Image
        ViewEndsecSpinner         matlab.ui.control.Spinner
        ViewEndsecSpinnerLabel    matlab.ui.control.Label
        ViewStartsecSpinner       matlab.ui.control.Spinner
        ViewStartsecSpinnerLabel  matlab.ui.control.Label
        CheckSigX_scrn            matlab.ui.control.NumericEditField
        CheckSigY_scrn            matlab.ui.control.NumericEditField
        XYLabel                   matlab.ui.control.Label
        colorBarAxes              matlab.ui.control.UIAxes
        checkPhaseAxis            matlab.ui.control.UIAxes
        checkSigAxis              matlab.ui.control.UIAxes
        MapAxis                   matlab.ui.control.UIAxes
    end


    properties (Access = public)

        % The Parent App --------------------
        mainApp
        mainAppUIFigure


        % The Current App --------------------
        dirName % File directory
        fileName % Filename without path and extension

        setup % User preference
        userAlphaValue

        imageIconActColor = [0.80, 0.80, 0.80]; % The activation color when an image is clicked


        lWidth = 2; % Line width
        
        wavefrontLW = 4.5; % Wavefront line width

        bgFrame = cell(1,1); % 'background fram' - store background image
        imFrame = cell(1,1); % 'image Frame' - store phase map frame
        imFrameAlpha = cell(1,1); % The transparency of imFrame
        timeIndicators = cell(2,1); % The plot of movie playing sweep bar. Row 1 (or 2) for signal (or phase) axis
        wavefrontFrame % The plot of wavefronts
        currentFrameID % The current frame


        maxMapNumber = 10; % The max number of map that can be saved

        data
        acqFreq

        signalTime
        cmosDataGroup
        bgImageNomGroup % Gray colour RGB version of background image
        cameraSource % '1 (or 2)' - data from camera 1 (or 2)

        pacing1 % Pacing signal recorded by channel 1
        pacing2 % Pacing signal recorded by channel 2
        pacingTime % Time points to plot pacing signal
        pacing1Enable
        pacing2Enable

        scrnObject % To store 6 scrn axis objects

        mapAxisLocs

        mouseXY = []; % This is a 1-by-2 row vector that stores mouse "X" and "Y" coordinate
        mouseMotion = []; % The result can be 'normal', 'extend', 'alt', 'open'

        cmosDataRowNum % The numer of rows in cmosData
        cmosDataColNum % The numer of columns in cmosData

        mapCalculationROI
        callFromRegionalButton = 0; % '1 (or 0)' - regional (or global)

        % For Phase Analysis
        phasePlot
        phaseColorBarPlot

        phaseSaveToID = 1; % The ID of map to be saved
        phaseAnalysisDataTitle = { '(1) Area Mode' ...
            '(2) Data Section Time', '(3) Data Section', ...
            '(4) Phase Map Data Section', '(5) Hilbert Data section', ...
            '(6) Wavefront Data Section Title', '(7) Wavefront Data Section', ...
            '(8) Read Me'};
        phaseAnalysisData

        % For Check Signal
        checkSigMarkerXY
        checkSigMouseDragID

        checkSigDataSectionTime
        checkSigDataSection % A 3D matrix
        checkPhaseMapDataSection % A 3D matrix

        checkWavefrontData

        checkSigMarkerPlot
        checkSigMarkerHighlightPlot

        checkSigPlot
        checkPhasePlot
    end


    methods (Access = public)

        function callByMainApp_OMZer_Vm_Phase_UIFigureCloseRequest(app)

            OMZer_Vm_Phase_UIFigureCloseRequest(app, [])
        end


        function showImageIconAct(app, imageIconName)

            imageIconName.BackgroundColor = app.imageIconActColor; %[0.80, 0.80, 0.80];
            pause(0.05)
            imageIconName.BackgroundColor = 'none';
        end


        function setColourMap(app,name)

            colormap(app.MapAxis, name);
            colormap(app.colorBarAxes, name);
        end


        function setAxisLimit(app, xMaxLimit, yMaxLimit) % Set the x-y limit of all axes

            app.MapAxis.XLim = [1, xMaxLimit];
            app.MapAxis.YLim = [1, yMaxLimit];

            app.CheckSigX_scrn.Value = 1;
            app.CheckSigX_scrn.Limits = [1, xMaxLimit];
            app.CheckSigY_scrn.Value = 1;
            app.CheckSigY_scrn.Limits = [1, yMaxLimit];
        end


        function setScrnLimit(app, LB, UB, state)

            app.Image_RestoreView.Enable = state;

            app.ViewStartsecSpinner.Enable = state;
            app.ViewEndsecSpinner.Enable = state;

            app.ViewStartsecSpinner.Limits = [ LB, UB ];
            app.ViewEndsecSpinner.Limits = [ LB, UB ];

            app.ViewStartsecSpinner.Value = LB;
            app.ViewEndsecSpinner.Value = UB;

            xlim( app.checkSigAxis, [ app.ViewStartsecSpinner.Value, app.ViewEndsecSpinner.Value ] )
            xlim( app.checkPhaseAxis, [ app.ViewStartsecSpinner.Value, app.ViewEndsecSpinner.Value ] )
        end


        function savePhaseAnalysisData(app, areaLabel, dataSectionTime, dataSection, phaseMapDataSection, hilbertDataSection, wavefrontDataSectionTitle, wavefrontDataSection, readMe)

            app.phaseAnalysisData{1, 1, app.phaseSaveToID} = areaLabel;
            app.phaseAnalysisData{1, 2, app.phaseSaveToID} = dataSectionTime;
            app.phaseAnalysisData{1, 3, app.phaseSaveToID} = dataSection;
            app.phaseAnalysisData{1, 4, app.phaseSaveToID} = phaseMapDataSection;
            app.phaseAnalysisData{1, 5, app.phaseSaveToID} = hilbertDataSection;
            app.phaseAnalysisData{1, 6, app.phaseSaveToID} = wavefrontDataSectionTitle;
            app.phaseAnalysisData{1, 7, app.phaseSaveToID} = wavefrontDataSection;
            app.phaseAnalysisData{1, 8, app.phaseSaveToID} = readMe;
        end


        function plotObject = updateCheckSigMarker(app, markerColor)

            X = app.checkSigMarkerXY(1,1);
            Y = app.checkSigMarkerXY(1,2);

            plotObject = line(app.MapAxis, X, Y, 'Marker','square', 'MarkerSize',10, 'MarkerEdgeColor','white', 'MarkerFaceColor', markerColor);
        end


        function plotObject = updateCheckSigMarkerHighlight(app)

            X = app.checkSigMarkerXY(1,1);
            Y = app.checkSigMarkerXY(1,2);

            plotObject = line( app.MapAxis, X, Y, 'Marker','o', 'LineWidth',4, 'MarkerSize',30, 'MarkerEdgeColor','white', 'MarkerFaceColor', 'none');
        end


        function plotObject = updateCheckSig(app, dataTime, data, lineColor)

            row = app.checkSigMarkerXY(1,2);
            col = app.checkSigMarkerXY(1,1);

            plotObject = line( app.checkSigAxis, dataTime, squeeze(data(row,col,:)), 'LineWidth',app.lWidth, 'color', lineColor );
        end


        function plotObject = updateCheckPhase(app, dataTime, data, lineColor)

            row = app.checkSigMarkerXY(1,2);
            col = app.checkSigMarkerXY(1,1);

            plotObject = line( app.checkPhaseAxis, dataTime, squeeze(data(row,col,:)), 'LineWidth',app.lWidth, 'color', lineColor );
        end


        function clearCheckSigPlot(app)

            app.checkSigMarkerXY = [];
            app.CheckSigX_scrn.Value = 1;
            app.CheckSigY_scrn.Value = 1;

            app.checkSigDataSectionTime = [];
            app.checkSigDataSection = [];
            app.checkPhaseMapDataSection = [];

            delete(app.checkSigMarkerPlot)
            delete(app.checkSigMarkerHighlightPlot)
            delete(app.checkSigPlot)
            delete(app.checkPhasePlot)

            app.checkSigMarkerPlot = [];
            app.checkSigMarkerHighlightPlot = [];
            app.checkSigPlot = [];
            app.checkPhasePlot = [];
        end


        function updateMouseXY(app, axisLosInfo)

            %             app.mouseXY(1,1) = ( app.mouseXY(1,1) - axisLosInfo(1,1) ) * app.cmosDataColNum / axisLosInfo(1,3);
            %             app.mouseXY(1,1) = round( app.mouseXY(1,1) ); % Transfer relative mouse location inside bgImageAxis1 to absolute location inside a 100x100 matrix
            %             if app.mouseXY(1,1) < 1
            %                 app.mouseXY(1,1) = 1; % As a result, there won't appear (0,y) element requirement in a 100x100 matrix
            %             end
            %             if app.mouseXY(1,1) > app.cmosDataColNum
            %                 app.mouseXY(1,1) = app.cmosDataColNum;
            %             end
            %
            %
            %             app.mouseXY(1,2) = axisLosInfo.CurrentPoint(1,2);

            %             app.mouseXY(1,2) = ( (axisLosInfo(1,2) + axisLosInfo(1,4)) - app.mouseXY(1,2) ) * app.cmosDataRowNum / axisLosInfo(1,4);
            %             app.mouseXY(1,2) = round( app.mouseXY(1,2) ); % Transfer relative mouse location inside bgImageAxis1 to absolute location inside a 100x100 matrix
            %             if app.mouseXY(1,2) < 1
            %                 app.mouseXY(1,2) = 1; % As a result, there won't appear (x,0) element requirement in a 100x100 matrix
            %             end
            %             if app.mouseXY(1,2) > app.cmosDataRowNum
            %                 app.mouseXY(1,2) = app.cmosDataRowNum;
            %             end


            app.mouseXY(1,1) = app.MapAxis.CurrentPoint(1,1);
            app.mouseXY(1,2) = app.MapAxis.CurrentPoint(1,2);


            app.mouseXY(1,1) = round( app.mouseXY(1,1) ); % Transfer relative mouse location inside bgImageAxis1 to absolute location inside a 100x100 matrix
            if app.mouseXY(1,1) < 1
                app.mouseXY(1,1) = 1; % As a result, there won't appear (0,y) element requirement in a 100x100 matrix
            end
            if app.mouseXY(1,1) > app.cmosDataColNum
                app.mouseXY(1,1) = app.cmosDataColNum;
            end

            app.mouseXY(1,2) = round( app.mouseXY(1,2) ); % Transfer relative mouse location inside bgImageAxis1 to absolute location inside a 100x100 matrix
            if app.mouseXY(1,2) < 1
                app.mouseXY(1,2) = 1; % As a result, there won't appear (x,0) element requirement in a 100x100 matrix
            end
            if app.mouseXY(1,2) > app.cmosDataRowNum
                app.mouseXY(1,2) = app.cmosDataRowNum;
            end
        end


        function setupCameraFrameInfo(app)

            app.currentFrameID = 0;

            hold(app.MapAxis,'on')
            app.bgFrame{1,1} = imagesc( app.MapAxis, app.bgImageNomGroup{app.cameraSource} );
            %app.imFrame{1,1} = imagesc( app.MapAxis, app.bgImageNomGroup{app.cameraSource} );

            %             app.imFrame{1,1} = pcolor( app.MapAxis, app.bgImageNomAdjustGroup{1} );
            %             app.imFrame{1,1}.FaceColor = 'interp';
            %             app.imFrame{1,1}.EdgeColor = 'none';
            %             app.imFrame{1,1}.CData = app.bgImageNomGroup{1};

            hold(app.MapAxis,'off')
            %app.imFrameAlpha{1,1} = ~isnan( app.cmosDataGroup{app.cameraSource}(:,:,1) ); % The transparency of imFrame
        end


        function setupPhaseFrameInfo(app, phaseMap)

            hold(app.MapAxis,'on')

            app.imFrame{1,1} = imagesc( app.MapAxis, phaseMap(:,:,1) );
            app.imFrame{1,1}.AlphaData = ~isnan( phaseMap(:,:,1) );

            hold(app.MapAxis,'off')
        end


        function updateVideoFrame(app, phaseMap)

            if app.currentFrameID ~= 0 % Display colourful image

                app.imFrame{1,1}.CData = phaseMap(:,:,app.currentFrameID);
                app.imFrame{1,1}.AlphaData = ~isnan( phaseMap(:,:,app.currentFrameID) );
                drawnow

            else % Display background image

                app.imFrame{1,1}.CData = app.bgImageNomGroup{app.cameraSource};
                drawnow
            end
        end


        function updateWavefrontFrame(app)

            % Clear previous wavefront plot
            if ~isempty( app.wavefrontFrame )

                for ID = 1 : length( app.wavefrontFrame )

                    delete( app.wavefrontFrame{ID} )
                end
            end

            if app.currentFrameID ~= 0 % Display colourful image

                if ~isempty( app.checkWavefrontData )

                    wavefrontData = app.checkWavefrontData{ app.currentFrameID };

                    if iscell( wavefrontData )

                        app.wavefrontFrame = cell( size( wavefrontData, 1 ), 1 );

                        for ID = 1 : size( wavefrontData, 1 )

                            wavefront_X = wavefrontData{ ID,4 };
                            wavefront_Y = wavefrontData{ ID,5 };

                            app.wavefrontFrame{ ID } = line( app.MapAxis, wavefront_X, wavefront_Y, 'Color','White', 'LineWidth',app.wavefrontLW );
                        end
                    end
                end

            else % Display background image

                if ~isempty( app.wavefrontFrame )

                    for ID = 1 : length( app.wavefrontFrame )

                        delete( app.wavefrontFrame{ID} )
                    end
                end
            end
        end


        function updateSweepBar(app) % If the current frame is a "bgimage" frame, then it will delete all the sweepbar previously plotted.

            if app.currentFrameID ~= 0 % Display colourful image
                for ID = 1 : 2
                    app.timeIndicators{ID,1}.Value = app.currentFrameID / app.acqFreq;
                end

            else % Display background image
                for ID = 1 : 2
                    app.timeIndicators{ID,1}.Value = -1;
                end
            end
        end


        function newPlotObject = plotMapColorBar(app, plotObject)

            matrixColumnVersion = [ -pi : pi/(2^8) : pi ]';
            matrixColumnVersion = sort( matrixColumnVersion, 'descend' );

            ID_pi_minus = find( matrixColumnVersion == -pi );
            ID_pi_half_minus = find( matrixColumnVersion == -pi/2 );
            ID_zero = find( matrixColumnVersion == 0 );
            ID_pi_half = find( matrixColumnVersion == pi/2 );
            ID_pi = find( matrixColumnVersion == pi );

            delete(plotObject)
            newPlotObject = imagesc( app.colorBarAxes, matrixColumnVersion );

            app.colorBarAxes.YLim = [ 1, length(matrixColumnVersion) ];
            
            % app.colorBarAxes.YTick = [ ID_pi_minus, ID_pi_half_minus, ID_zero, ID_pi_half, ID_pi ];
            % app.colorBarAxes.YTickLabel = { '-\pi', '-\pi/2', '0', '\pi/2', '\pi' };
            
            app.colorBarAxes.YTick = [ ID_pi, ID_pi_half, ID_zero, ID_pi_half_minus, ID_pi_minus ];
            app.colorBarAxes.YTickLabel = { '\pi', '\pi/2', '0', '-\pi/2', '-\pi' };
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, OMZer)

            msg = 'Configuring files';
            progressBar = uiprogressdlg(app.OMZer_Vm_Phase_UIFigure, 'Title','Initialising', 'Message',msg, 'Indeterminate','on');
            drawnow

            % (1) User set up
            app.setup = load([pwd,'/OMZer_Setup']);
            setColourMap(app, app.setup.userColourMap);
            app.userAlphaValue = 1.0; % The default alpha value

            % (2) Create components collection
            app.scrnObject = {app.checkSigAxis, app.checkPhaseAxis};

            app.checkPhaseAxis.YLim = [ -pi, pi ];
            app.checkPhaseAxis.YTick = [ -pi, -pi/2, 0, pi/2, pi ];
            app.checkPhaseAxis.YTickLabel = { '-\pi', '-\pi/2', '0', '\pi/2', '\pi' };

            % (3) Get information from the mainApp
            app.mainApp = OMZer;
            app.mainAppUIFigure = app.mainApp.OMZer_UIFigure;

            app.dirName = app.mainApp.dirName;
            app.fileName = app.mainApp.fileName;

            app.data = app.mainApp.data;
            app.acqFreq = app.mainApp.acqFreq;
            app.signalTime = app.mainApp.signalTime;
            app.cmosDataGroup = app.mainApp.cmosDataGroup;
            app.bgImageNomGroup = app.mainApp.bgImageNomGroup;
            app.cameraSource = app.mainApp.activeCamera;

            app.pacing1 = app.mainApp.pacing1;
            app.pacing2 = app.mainApp.pacing2;
            app.pacingTime = app.mainApp.pacingTime;
            app.pacing1Enable = app.mainApp.pacing1CheckBox.Enable;
            app.pacing2Enable = app.mainApp.pacing2CheckBox.Enable;

            app.CameraNALabel.Text = [ 'Camera ', num2str(app.cameraSource) ];

            % (4) Set movie slider bar and time indicator sweep bar
            LB = 0;
            UB = app.signalTime(end);
            setScrnLimit(app, LB, UB, 'on')

            app.MoviesecSpinner.Limits = [ 0, app.signalTime(end) ];
            app.MoviesecSpinner.Value = 0;
            app.MoviesecSpinner.Step = 1 / app.acqFreq;
            digitsNumberAfterDecimalPoint = num2str( round( abs( log10( 1 / app.acqFreq ) ) ) );
            app.MoviesecSpinner.ValueDisplayFormat = [ '%.', digitsNumberAfterDecimalPoint, 'f' ];

            app.MovieSlider.Limits = [ 0, length(app.signalTime)-1 ];
            app.MovieSlider.Value = 0;

            for ID = 1:2
                app.timeIndicators{ID,1} = xline(app.scrnObject{ID}, -1, 'Color','red', 'LineWidth',app.lWidth);
            end

            % (5) Diable Toolbar
            % https://www.mathworks.com/help/matlab/ref/axtoolbar.html?searchHighlight=axtoolbar&s_tid=doc_srchtitle
            try
                disableDefaultInteractivity(app.colorBarAxes);
                disableDefaultInteractivity(app.MapAxis);
                disableDefaultInteractivity(app.checkSigAxis);
                disableDefaultInteractivity(app.checkPhaseAxis);

                axtoolbar(app.colorBarAxes);
                axtoolbar(app.MapAxis);
                axtoolbar(app.checkSigAxis);
                axtoolbar(app.checkPhaseAxis);

            catch
                % Do nothing
            end

            % (6) Plot background image
            [app.cmosDataRowNum, app.cmosDataColNum, ~] = size( app.cmosDataGroup{app.cameraSource} );
            setAxisLimit(app, app.cmosDataColNum, app.cmosDataRowNum);

            setupCameraFrameInfo(app)

            close(progressBar)

        end

        % Image clicked function: Image_RegionalMap
        function Image_RegionalMapClicked(app, event)

            showImageIconAct(app, app.Image_RegionalMap)

            % (2) Define the region of interets (ROI)
            msg = 'Choose a method to define the region of interet (ROI)';
            msgTitle = 'Warning';
            optionItem = {'Draw on the Background Image', 'Load a Saved ROI', 'Cancel'};
            cancelItem = 3;
            selection = uiconfirm( app.OMZer_Vm_Phase_UIFigure,msg,msgTitle, 'Options',optionItem, 'DefaultOption',1, 'CancelOption',cancelItem ); % When the user selects an option, uiconfirm returns their selection as a character vector.

            switch selection

                case optionItem{1} % Draw on the Background Image

                    screenSize = get( groot, 'ScreenSize' );
                    fObject = figure;
                    fObject.Position = [ 1, 20, 0.9*screenSize(4), 0.9*screenSize(4) ];

                    % Prepare Background Axis
                    axMap = axes( 'Parent', fObject );
                    axMap.OuterPosition = [ 0, 0, 1, 1 ];
                    axMap.Box = 'on';
                    axMap.YDir = 'Reverse';
                    axMap.XLim = [ 1, app.cmosDataColNum ];
                    axMap.YLim = [ 1, app.cmosDataRowNum ];
                    axMap.PlotBoxAspectRatio = [ 1, 1, 1 ];

                    % Plot Background
                    fp = imagesc( axMap, app.bgImageNomGroup{app.cameraSource} ); % 'fp - figure plot'

                    % Get ROI
                    roiObject = drawpolygon(axMap);

                    if ishandle(roiObject) == 0 % The user close the figure directly withou drawing any roi

                        msg = 'You have not truly set the region of interest (ROI). Draw a region and double click on it to set the ROI.';
                        uialert(app.OMZer_Vm_Phase_UIFigure, msg,'Warning','Icon','error')

                        % Reset
                        app.callFromRegionalButton = 0;
                        app.mapCalculationROI = [];
                        figure(app.OMZer_Vm_Phase_UIFigure) % Set this uifigure at the top level

                    else

                        wait(roiObject); % If the user draws a roi, he should double click on the roi to truly set it up
                        if ishandle(roiObject) == 0 % Although the user draws a roi, he does not double click on the roi to truly set it up

                            msg = 'You have not truly set the region of interest (ROI). Draw a region and double click on it to set the ROI.';
                            uialert(app.OMZer_Vm_Phase_UIFigure, msg,'Warning','Icon','error')

                            % Reset
                            app.callFromRegionalButton = 0;
                            app.mapCalculationROI = [];
                            figure(app.OMZer_Vm_Phase_UIFigure) % Set this uifigure at the top level

                        else

                            % The user has truly set a region of interest
                            app.mapCalculationROI = createMask(roiObject,fp);
                            if ishandle(fObject) == 1
                                close(fObject)
                            end
                            app.mapCalculationROI = double(app.mapCalculationROI);
                            app.mapCalculationROI(app.mapCalculationROI == 0) = NaN; %  1 (or NaN) - pixel inside (or outside) mask

                            figure(app.OMZer_Vm_Phase_UIFigure) % Set this uifigure at the top level

                            app.callFromRegionalButton = 1;
                            Image_GlobalMapClicked(app, event)
                        end
                    end

                case optionItem{2} % Load a Saved ROI

                    [file,path] = uigetfile('*.mat');

                    if ~ischar(file)

                        msg = 'Only choose one ".mat" file that has a ROI';
                        uialert(app.OMZer_Vm_Phase_UIFigure, msg,'Warning','Icon','error')

                        % Reset
                        app.callFromRegionalButton = 0;
                        app.mapCalculationROI = [];
                        figure(app.OMZer_Vm_Phase_UIFigure) % Set this uifigure at the top level

                    else

                        msg = 'Loading the ROI';
                        progressBar = uiprogressdlg(app.OMZer_Vm_Phase_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');

                        maskData = load( [path,file] ); % 'Data' is a structure
                        app.mapCalculationROI = maskData.mask;

                        figure(app.OMZer_Vm_Phase_UIFigure) % Set this uifigure at the top level
                        close(progressBar)

                        app.callFromRegionalButton = 1;
                        Image_GlobalMapClicked(app, event)
                    end
            end

        end

        % Image clicked function: Image_GlobalMap
        function Image_GlobalMapClicked(app, event)

            showImageIconAct(app, app.Image_GlobalMap)

            % (1) Check global or regional map calculation
            if app.callFromRegionalButton == 1
                areaLabel = 'Regional';
            else
                app.mapCalculationROI = ones( app.cmosDataRowNum, app.cmosDataColNum );
                areaLabel = 'Global';
            end


            % (2) Phase map calculation
            msg = [ 'Calculate the ', areaLabel, ' Phase Map' ];
            progressBar = uiprogressdlg(app.OMZer_Vm_Phase_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');

            [ dataSectionTime, ...
                dataSection, ...
                phaseMapDataSection, ...
                hilbertDataSection, ...
                wavefrontDataSectionTitle, ...
                wavefrontDataSection, ...
                readMe ] = calculatePhase( app.acqFreq, app.cmosDataGroup{app.cameraSource}, app.mapCalculationROI );

            app.MapTitleLabel.Text = [ areaLabel, ' Phase Map' ];

            savePhaseAnalysisData(app, areaLabel, dataSectionTime, dataSection, phaseMapDataSection, hilbertDataSection, wavefrontDataSectionTitle, wavefrontDataSection, readMe)

            % (3) Reset plot
            app.MoviesecSpinner.Value = 0;
            MoviesecSpinnerValueChanged(app, event)

            clearCheckSigPlot(app)
            app.Image_DisplayPixel.Enable = 'on';
            
            cla( app.MapAxis )
            cla( app.colorBarAxes )

            % (4) Set phase frame
            setupCameraFrameInfo(app)

            app.checkPhaseMapDataSection = app.phaseAnalysisData{ 1, 4, 1 }; % A 3D matrix
            setupPhaseFrameInfo(app, app.checkPhaseMapDataSection)

            updateVideoFrame(app, app.checkPhaseMapDataSection)

            % (5) Set colorbar
            app.phaseColorBarPlot = plotMapColorBar(app, app.phaseColorBarPlot);

            % (6) set wavefront frame
            app.checkWavefrontData = app.phaseAnalysisData{ 1, 7, 1 }; % A cell
            updateWavefrontFrame(app)

            close(progressBar)

        end

        % Image clicked function: Image_ExportData
        function Image_ExportDataClicked(app, event)

            showImageIconAct(app, app.Image_ExportData)

            if isempty( app.phaseAnalysisData )

                msg = 'Conduct phase analysis before exporting data';
                uialert(app.OMZer_Vm_Phase_UIFigure,msg,'Warning','Icon','error')
                
                return
            end

            msg = 'Choose the data you want to export';
            msgTitle = 'Warning';
            optionItem = { 'Export Map Data (.mat File)', 'Export Display Pixel (.mat File)', 'Cancel' };
            selection = uiconfirm( app.OMZer_Vm_Phase_UIFigure, msg, msgTitle, ...
                'Options', optionItem, ...
                'DefaultOption', 1, ...
                'CancelOption', 3 ); % uiconfirm returns the selection as a character vector

            switch selection

                case optionItem{1} % Export Map Data (.mat File)

                    % Get valid saving directory and file name
                    saveDir = uigetdir;

                    if saveDir == 0 % User has NOT selected file directory.

                        msg = 'Please select a valid saving directory';
                        uialert(app.OMZer_Vm_Phase_UIFigure,msg,'Warning','Icon','error')

                    else

                        prompt = {'Enter filename to save (without extension)...   Please do not use \/:*?"<>|'};
                        dlgtitle = 'Save to a .mat file';
                        dfinput = { [ app.fileName, '_Vm_Phase_Anlysis_Data' ] };
                        dims = [1,100];
                        filename = inputdlg(prompt,dlgtitle,dims,dfinput);

                        if isempty(filename) % User has NOT entered a name

                            msg = 'Please enter a valid file name';
                            uialert(app.OMZer_Vm_Phase_UIFigure,msg,'Warning','Icon','error')

                        else

                            % Export map data
                            areaLabel = app.phaseAnalysisData{1, 1, 1};
                            mapDataTitle = [ areaLabel, '_Vm_Phase_Analysis_Data' ];

                            dataSectionTime = app.phaseAnalysisData{1, 2, 1};
                            dataSection = app.phaseAnalysisData{1, 3, 1};
                            phaseMapDataSection = app.phaseAnalysisData{1, 4, 1};
                            hilbertDataSection = app.phaseAnalysisData{1, 5, 1};

                            wavefrontDataSectionTitle = app.phaseAnalysisData{1, 6, 1};
                            wavefrontDataSection = app.phaseAnalysisData{1, 7, 1};

                            readMe = app.phaseAnalysisData{1, 8, 1};

                            acquisitionFrequency_Hz_Unit = app.acqFreq;
                            backgroundImage = app.bgImageNomGroup{app.cameraSource};
                            cameraID = app.cameraSource;

                            msg = [ 'Export the ', areaLabel, ' Vm Phase Analysis Data' ];
                            progressBar = uiprogressdlg(app.OMZer_Vm_Phase_UIFigure, 'Title','Processing', 'Message',msg, 'Indeterminate','on');

                            fullSaveName = strcat(saveDir, '/', filename, '.mat'); % At this moment, 'fullSaveName' is a 'cell'.
                            fullSaveName = fullSaveName{1}; % Now 'fullSaveName' is a 'character
                            save(fullSaveName, ...
                                'mapDataTitle', ...
                                'dataSectionTime', ...
                                'dataSection', ...
                                'phaseMapDataSection', ...
                                'hilbertDataSection', ...
                                'wavefrontDataSectionTitle', ...
                                'wavefrontDataSection', ...
                                'readMe', ...
                                'acquisitionFrequency_Hz_Unit', ...
                                'backgroundImage', ...
                                'cameraID');

                            close(progressBar)
                        end
                    end

                    figure(app.OMZer_Vm_Phase_UIFigure) % Set this uifigure at the top level


                case optionItem{2} % Export Display Pixel (.xlsx File)

                    msg = 'This function is not working yet';
                    uialert(app.OMZer_Vm_Phase_UIFigure,msg,'Warning','Icon','error')


                    % app.OMZer_Vm_ExportDisplayPixel = OMZer_Vm_ExportDisplayPixel(app);

                    figure(app.OMZer_Vm_Phase_UIFigure) % Set this uifigure at the top level
            end

        end

        % Image clicked function: Image_RestoreView
        function Image_RestoreViewClicked(app, event)

            showImageIconAct(app, app.Image_RestoreView)

            app.ViewStartsecSpinner.Value = 0;
            app.ViewEndsecSpinner.Value = app.signalTime(end);

            for ID = 1:2
                xlim( app.scrnObject{ID}, [ min(app.ViewStartsecSpinner.Limits), max(app.ViewEndsecSpinner.Limits) ] )
            end

        end

        % Value changed function: ViewStartsecSpinner
        function ViewStartsecSpinnerValueChanged(app, event)

            if app.ViewStartsecSpinner.Value >= app.ViewEndsecSpinner.Value

                msg = 'Start time must be eariler than end time';
                uialert(app.OMZer_Vm_Phase_UIFigure,msg,'Warning','Icon','error')

                app.ViewStartsecSpinner.Value = 0;
                app.ViewEndsecSpinner.Value = app.signalTime(end);
            end

            for ID = 1:2
                xlim( app.scrnObject{ID}, [ app.ViewStartsecSpinner.Value, app.ViewEndsecSpinner.Value ] )
            end

        end

        % Value changing function: ViewStartsecSpinner
        function ViewStartsecSpinnerValueChanging(app, event)

            if ischar(event.Value)
                app.ViewStartsecSpinner.Value = str2double(event.Value);
            else
                app.ViewStartsecSpinner.Value = event.Value;
            end

            ViewStartsecSpinnerValueChanged(app, event)

        end

        % Value changed function: ViewEndsecSpinner
        function ViewEndsecSpinnerValueChanged(app, event)

            if app.ViewStartsecSpinner.Value >= app.ViewEndsecSpinner.Value

                msg = 'Start time must be eariler than end time';
                uialert(app.OMZer_Vm_Phase_UIFigure,msg,'Warning','Icon','error')

                app.ViewStartsecSpinner.Value = 0;
                app.ViewEndsecSpinner.Value = app.signalTime(end);
            end

            for ID = 1:2
                xlim( app.scrnObject{ID}, [ app.ViewStartsecSpinner.Value, app.ViewEndsecSpinner.Value ] )
            end

        end

        % Value changing function: ViewEndsecSpinner
        function ViewEndsecSpinnerValueChanging(app, event)

            if ischar(event.Value)
                app.ViewEndsecSpinner.Value = str2double(event.Value);
            else
                app.ViewEndsecSpinner.Value = event.Value;
            end

            ViewEndsecSpinnerValueChanged(app, event)

        end

        % Image clicked function: Image_DisplayPixel
        function Image_DisplayPixelClicked(app, event)

            showImageIconAct(app, app.Image_DisplayPixel)

            if isempty( app.phaseAnalysisData )

                msg = 'Conduct phase analysis before checking the pixel signal';
                uialert(app.OMZer_Vm_Phase_UIFigure,msg,'Warning','Icon','error')

            else

                % (1) Update components
                app.Image_DisplayPixel.Enable = 'off';

                app.CheckSigX_scrn.Editable = 'on';
                app.CheckSigY_scrn.Editable = 'on';
                app.CheckSigX_scrn.BackgroundColor = [1.00, 1.00, 0.00]; % Yellow
                app.CheckSigY_scrn.BackgroundColor = [1.00, 1.00, 0.00];

                % (2) Plot the initial marker
                checkSigColor = [0.00, 0.45, 0.74];

                app.checkSigMarkerXY = [ round(0.5*app.cmosDataRowNum), round(0.5*app.cmosDataColNum) ];
                app.CheckSigX_scrn.Value = app.checkSigMarkerXY(1,1);
                app.CheckSigY_scrn.Value = app.checkSigMarkerXY(1,2);

                % (2.1) Marker plot
                delete(app.checkSigMarkerPlot)
                app.checkSigMarkerPlot = updateCheckSigMarker(app, checkSigColor);

                % (2.2) Marker Highlight Plot
                delete(app.checkSigMarkerHighlightPlot)
                app.checkSigMarkerHighlightPlot = updateCheckSigMarkerHighlight(app);

                % (3) Get the data
                app.checkSigDataSectionTime = app.phaseAnalysisData{ 1, 2, 1 };
                app.checkSigDataSection = app.phaseAnalysisData{ 1, 3, 1 }; % A 3D matrix

                app.checkPhaseMapDataSection = app.phaseAnalysisData{ 1, 4, 1 }; % A 3D matrix

                delete(app.checkSigPlot)
                app.checkSigPlot = updateCheckSig(app, app.checkSigDataSectionTime, app.checkSigDataSection, checkSigColor);

                delete(app.checkPhasePlot)
                app.checkPhasePlot = updateCheckPhase(app, app.checkSigDataSectionTime, app.checkPhaseMapDataSection, checkSigColor);
            end

        end

        % Image clicked function: Image_ClearPixel
        function Image_ClearPixelClicked(app, event)

            showImageIconAct(app, app.ClearPixelLabel)

            if isempty( app.checkSigMarkerPlot )

                msg = 'Display the signal first';
                uialert(app.OMZer_Vm_Phase_UIFigure, msg,'Warning','Icon','error')

            else

                app.Image_DisplayPixel.Enable = 'on';

                app.CheckSigX_scrn.Editable = 'off';
                app.CheckSigY_scrn.Editable = 'off';
                app.CheckSigX_scrn.BackgroundColor = [1.00, 1.00, 1.00]; % White
                app.CheckSigY_scrn.BackgroundColor = [1.00, 1.00, 1.00];

                clearCheckSigPlot(app)
            end

        end

        % Value changed function: CheckSigX_scrn, CheckSigY_scrn
        function CheckSigXY_scrnValueChanged(app, event)

            app.checkSigMarkerXY(1,1) = app.CheckSigX_scrn.Value;
            app.checkSigMarkerXY(1,2) = app.CheckSigY_scrn.Value;

            checkSigColor = [0.00, 0.45, 0.74];

            % (1.1) Marker plot
            delete(app.checkSigMarkerPlot)
            app.checkSigMarkerPlot = updateCheckSigMarker(app, checkSigColor);

            % (1.2) Marker Highlight Plot
            delete(app.checkSigMarkerHighlightPlot)
            app.checkSigMarkerHighlightPlot = updateCheckSigMarkerHighlight(app);

            % (2) Update the signal
            delete(app.checkSigPlot)
            app.checkSigPlot = updateCheckSig(app, app.checkSigDataSectionTime, app.checkSigDataSection, checkSigColor);

            delete(app.checkPhasePlot)
            app.checkPhasePlot = updateCheckPhase(app, app.checkSigDataSectionTime, app.checkPhaseMapDataSection, checkSigColor);

        end

        % Value changed function: MoviesecSpinner
        function MoviesecSpinnerValueChanged(app, event)

            app.currentFrameID = round(app.MoviesecSpinner.Value * app.acqFreq);
            app.MovieSlider.Value = app.currentFrameID;

            if ~isempty( app.phaseAnalysisData )

                updateVideoFrame(app, app.checkPhaseMapDataSection);
            end

            updateSweepBar(app);

            updateWavefrontFrame(app);

        end

        % Value changing function: MoviesecSpinner
        function MoviesecSpinnerValueChanging(app, event)

            if ischar(event.Value)
                app.MoviesecSpinner.Value = str2double(event.Value);
            else
                app.MoviesecSpinner.Value = event.Value;
            end

            MoviesecSpinnerValueChanged(app, event)

        end

        % Value changed function: MovieSlider
        function MovieSliderValueChanged(app, event)

            app.currentFrameID = round(app.MovieSlider.Value);

            app.MoviesecSpinner.Value = app.currentFrameID / app.acqFreq;

            if ~isempty( app.phaseAnalysisData )

                updateVideoFrame(app, app.checkPhaseMapDataSection);
            end

            updateSweepBar(app);

            updateWavefrontFrame(app);

        end

        % Value changing function: MovieSlider
        function MovieSliderValueChanging(app, event)

            app.currentFrameID = round(event.Value);

            app.MoviesecSpinner.Value = app.currentFrameID / app.acqFreq;

            if ~isempty( app.phaseAnalysisData )

                updateVideoFrame(app, app.checkPhaseMapDataSection);
            end

            updateSweepBar(app);

            updateWavefrontFrame(app);

        end

        % Window button down function: OMZer_Vm_Phase_UIFigure
        function OMZer_Vm_Phase_UIFigureWindowButtonDown(app, event)

            if isequal(app.Image_DisplayPixel.Enable, 'off')  &&  isequal(app.Image_ClearPixel.Enable, 'on')

                app.mouseMotion = app.OMZer_Vm_Phase_UIFigure.SelectionType; % The result can be 'normal', 'extend', 'alt', 'open'

                if isequal(app.mouseMotion,'normal') % 'normal' means single-click any mouse button.

                    app.mouseXY = app.OMZer_Vm_Phase_UIFigure.CurrentPoint; % Returns (x, y) coordinates of the mouse pointer, measured from the lower-left corner of the figure.
                    app.mapAxisLocs = app.MapAxis.InnerPosition; % Returns a 1-by-4 row vector -  [left bottom width height].

                    if ( app.mouseXY(1,1) >= app.mapAxisLocs(1,1) )  ...
                            &&  ( app.mouseXY(1,1) <= app.mapAxisLocs(1,1) + app.mapAxisLocs(1,3) )  ...
                            &&  ( app.mouseXY(1,2) >= app.mapAxisLocs(1,2) )  ...
                            &&  ( app.mouseXY(1,2) <= app.mapAxisLocs(1,2) + app.mapAxisLocs(1,4) )

                        updateMouseXY(app, app.mapAxisLocs);

                        if ( abs(app.mouseXY(1,1) - app.checkSigMarkerXY(1,1)) <= 5 )  &&  ( abs(app.mouseXY(1,2) - app.checkSigMarkerXY(1,2)) <= 5 ) % Find which marker is within 5 pixels of the mouse location

                            app.checkSigMouseDragID = 1;
                        end
                    end
                end
            end

        end

        % Window button motion function: OMZer_Vm_Phase_UIFigure
        function OMZer_Vm_Phase_UIFigureWindowButtonMotion(app, event)

            if ~isempty(app.checkSigMouseDragID)  &&  isequal(app.Image_DisplayPixel.Enable, 'off')  &&  isequal(app.Image_ClearPixel.Enable, 'on')

                app.mouseXY = app.OMZer_Vm_Phase_UIFigure.CurrentPoint; % Returns (x, y) coordinates of the mouse pointer, measured from the lower-left corner of the figure.
                app.mapAxisLocs = app.MapAxis.InnerPosition; % Returns a 1-by-4 row vector -  [left bottom width height].

                if ( app.mouseXY(1,1) >= app.mapAxisLocs(1,1) )  ...
                        &&  ( app.mouseXY(1,1) <= app.mapAxisLocs(1,1) + app.mapAxisLocs(1,3) )  ...
                        &&  ( app.mouseXY(1,2) >= app.mapAxisLocs(1,2) )  ...
                        &&  ( app.mouseXY(1,2) <= app.mapAxisLocs(1,2) + app.mapAxisLocs(1,4) )

                    updateMouseXY(app, app.mapAxisLocs);

                    app.checkSigMarkerXY(1,1) = app.mouseXY(1,1);
                    app.checkSigMarkerXY(1,2) = app.mouseXY(1,2);

                    app.CheckSigX_scrn.Value = app.checkSigMarkerXY(1,1);
                    app.CheckSigY_scrn.Value = app.checkSigMarkerXY(1,2);

                    CheckSigXY_scrnValueChanged(app, event)
                end
            end

        end

        % Window button up function: OMZer_Vm_Phase_UIFigure
        function OMZer_Vm_Phase_UIFigureWindowButtonUp(app, event)

            app.checkSigMouseDragID = [];

        end

        % Close request function: OMZer_Vm_Phase_UIFigure
        function OMZer_Vm_Phase_UIFigureCloseRequest(app, event)

            delete(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OMZer_Vm_Phase_UIFigure = uifigure('Visible', 'off');
            app.OMZer_Vm_Phase_UIFigure.Name = 'OMZer Vm Phase Analysis';
            app.OMZer_Vm_Phase_UIFigure.CloseRequestFcn = createCallbackFcn(app, @OMZer_Vm_Phase_UIFigureCloseRequest, true);
            app.OMZer_Vm_Phase_UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @OMZer_Vm_Phase_UIFigureWindowButtonDown, true);
            app.OMZer_Vm_Phase_UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @OMZer_Vm_Phase_UIFigureWindowButtonUp, true);
            app.OMZer_Vm_Phase_UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @OMZer_Vm_Phase_UIFigureWindowButtonMotion, true);
            app.MapAxis = uiaxes(app.OMZer_Vm_Phase_UIFigure);
            app.MapAxis.XLim = [1 100];
            app.MapAxis.YLim = [1 100];
            app.MapAxis.YDir = 'reverse';
            app.MapAxis.XTick = [];
            app.MapAxis.YTick = [];
            app.MapAxis.ZTick = [];
            app.checkSigAxis = uiaxes(app.OMZer_Vm_Phase_UIFigure);
            app.checkPhaseAxis = uiaxes(app.OMZer_Vm_Phase_UIFigure);
            app.colorBarAxes = uiaxes(app.OMZer_Vm_Phase_UIFigure);
            title(app.colorBarAxes, 'Radian (θ)')
            app.colorBarAxes.XTick = [];
            app.XYLabel = uilabel(app.OMZer_Vm_Phase_UIFigure);
            app.XYLabel.Tooltip = {'X (red) and Y (blue) coordinate of the marker. Please be noted that X is column and Y is row to locate an element in a matrix in MATLAB.'};
            app.XYLabel.Text = 'X,Y';
            app.CheckSigY_scrn = uieditfield(app.OMZer_Vm_Phase_UIFigure, 'numeric');
            app.CheckSigY_scrn.Limits = [0 100];
            app.CheckSigY_scrn.ValueDisplayFormat = '%.0f';
            app.CheckSigY_scrn.ValueChangedFcn = createCallbackFcn(app, @CheckSigXY_scrnValueChanged, true);
            app.CheckSigY_scrn.Editable = 'off';
            app.CheckSigY_scrn.Tooltip = {'Y coordinate of the 1st marker (Note: row in a matlab matrix)'};
            app.CheckSigX_scrn = uieditfield(app.OMZer_Vm_Phase_UIFigure, 'numeric');
            app.CheckSigX_scrn.Limits = [0 100];
            app.CheckSigX_scrn.ValueDisplayFormat = '%.0f';
            app.CheckSigX_scrn.ValueChangedFcn = createCallbackFcn(app, @CheckSigXY_scrnValueChanged, true);
            app.CheckSigX_scrn.Editable = 'off';
            app.CheckSigX_scrn.Tooltip = {'X coordinate of the 1st marker (Note: column in a matlab matrix)'};
            app.ViewStartsecSpinnerLabel = uilabel(app.OMZer_Vm_Phase_UIFigure);
            app.ViewStartsecSpinnerLabel.Text = 'View Start (sec)';
            app.ViewStartsecSpinner = uispinner(app.OMZer_Vm_Phase_UIFigure);
            app.ViewStartsecSpinner.Step = 0.05;
            app.ViewStartsecSpinner.ValueChangingFcn = createCallbackFcn(app, @ViewStartsecSpinnerValueChanging, true);
            app.ViewStartsecSpinner.Limits = [0 Inf];
            app.ViewStartsecSpinner.ValueChangedFcn = createCallbackFcn(app, @ViewStartsecSpinnerValueChanged, true);
            app.ViewEndsecSpinnerLabel = uilabel(app.OMZer_Vm_Phase_UIFigure);
            app.ViewEndsecSpinnerLabel.Text = 'View End (sec)';
            app.ViewEndsecSpinner = uispinner(app.OMZer_Vm_Phase_UIFigure);
            app.ViewEndsecSpinner.Step = 0.05;
            app.ViewEndsecSpinner.ValueChangingFcn = createCallbackFcn(app, @ViewEndsecSpinnerValueChanging, true);
            app.ViewEndsecSpinner.Limits = [0 Inf];
            app.ViewEndsecSpinner.ValueChangedFcn = createCallbackFcn(app, @ViewEndsecSpinnerValueChanged, true);
            app.ViewEndsecSpinner.Value = Inf;
            app.Image_RegionalMap = uiimage(app.OMZer_Vm_Phase_UIFigure);
            app.Image_RegionalMap.ImageClickedFcn = createCallbackFcn(app, @Image_RegionalMapClicked, true);
            app.Image_RegionalMap.ImageSource = 'Icon Regional Map.png';
            app.RegionalMapLabel = uilabel(app.OMZer_Vm_Phase_UIFigure);
            app.RegionalMapLabel.Text = 'Regional Map';
            app.Image_GlobalMap = uiimage(app.OMZer_Vm_Phase_UIFigure);
            app.Image_GlobalMap.ImageClickedFcn = createCallbackFcn(app, @Image_GlobalMapClicked, true);
            app.Image_GlobalMap.ImageSource = 'Icon Global Map.png';
            app.GlobalMapLabel = uilabel(app.OMZer_Vm_Phase_UIFigure);
            app.GlobalMapLabel.Text = 'Global Map';
            app.CautionPhaseCalculationlengthisthesameasviewlengthLabel = uilabel(app.OMZer_Vm_Phase_UIFigure);
            app.CautionPhaseCalculationlengthisthesameasviewlengthLabel.Text = 'Caution: Phase analysis will be applied to the entire signal segment. The longer tha recording file is, the longer the calculation will be.';
            app.MovieSlider = uislider(app.OMZer_Vm_Phase_UIFigure);
            app.MovieSlider.MajorTicks = [];
            app.MovieSlider.MajorTickLabels = {''};
            app.MovieSlider.ValueChangedFcn = createCallbackFcn(app, @MovieSliderValueChanged, true);
            app.MovieSlider.ValueChangingFcn = createCallbackFcn(app, @MovieSliderValueChanging, true);
            app.MovieSlider.MinorTicks = [];
            app.Image_DisplayPixel = uiimage(app.OMZer_Vm_Phase_UIFigure);
            app.Image_DisplayPixel.ImageClickedFcn = createCallbackFcn(app, @Image_DisplayPixelClicked, true);
            app.Image_DisplayPixel.ImageSource = 'Icon Display Pixel.png';
            app.DisplayPixelLabel = uilabel(app.OMZer_Vm_Phase_UIFigure);
            app.DisplayPixelLabel.Text = 'Display Pixel';
            app.Image_ClearPixel = uiimage(app.OMZer_Vm_Phase_UIFigure);
            app.Image_ClearPixel.ImageClickedFcn = createCallbackFcn(app, @Image_ClearPixelClicked, true);
            app.Image_ClearPixel.ImageSource = 'Icon Clear Pixel.png';
            app.ClearPixelLabel = uilabel(app.OMZer_Vm_Phase_UIFigure);
            app.ClearPixelLabel.Text = 'Clear Pixel';
            app.Image_ActivatedCamera = uiimage(app.OMZer_Vm_Phase_UIFigure);
            app.Image_ActivatedCamera.Tooltip = {'(This is NOT a button) Tell the user from which camera the signals are'};
            app.Image_ActivatedCamera.ImageSource = 'Icon Activated Camera.png';
            app.CameraNALabel = uilabel(app.OMZer_Vm_Phase_UIFigure);
            app.CameraNALabel.Text = 'Camera N/A';
            app.MapTitleLabel = uilabel(app.OMZer_Vm_Phase_UIFigure);
            app.MapTitleLabel.Text = 'Map Title';
            app.Image_RestoreView = uiimage(app.OMZer_Vm_Phase_UIFigure);
            app.Image_RestoreView.ImageClickedFcn = createCallbackFcn(app, @Image_RestoreViewClicked, true);
            app.Image_RestoreView.ImageSource = 'Icon Restore View.png';
            app.RestoreViewLabel = uilabel(app.OMZer_Vm_Phase_UIFigure);
            app.RestoreViewLabel.Text = 'Restore View';
            app.MoviesecSpinnerLabel = uilabel(app.OMZer_Vm_Phase_UIFigure);
            app.MoviesecSpinnerLabel.Text = 'Movie (sec)';
            app.MoviesecSpinner = uispinner(app.OMZer_Vm_Phase_UIFigure);
            app.MoviesecSpinner.Step = 0.0005;
            app.MoviesecSpinner.ValueChangingFcn = createCallbackFcn(app, @MoviesecSpinnerValueChanging, true);
            app.MoviesecSpinner.Limits = [0 Inf];
            app.MoviesecSpinner.ValueDisplayFormat = '%.4f';
            app.MoviesecSpinner.ValueChangedFcn = createCallbackFcn(app, @MoviesecSpinnerValueChanged, true);
            app.RadianLabel = uilabel(app.OMZer_Vm_Phase_UIFigure);
            app.RadianLabel.Text = 'Radian (θ)';
            app.Wavefrontswhiteplotlinesaredefinedastheisophaselinesalong2Label = uilabel(app.OMZer_Vm_Phase_UIFigure);
            app.Wavefrontswhiteplotlinesaredefinedastheisophaselinesalong2Label.Text = 'Wavefronts (white plot lines) are defined as the isophase lines along φ = π/2.';
            app.Image_ExportData = uiimage(app.OMZer_Vm_Phase_UIFigure);
            app.Image_ExportData.ImageClickedFcn = createCallbackFcn(app, @Image_ExportDataClicked, true);
            app.Image_ExportData.ImageSource = 'Icon Export.png';
            app.ExportDataLabel = uilabel(app.OMZer_Vm_Phase_UIFigure);
            app.ExportDataLabel.Text = 'Export Data';
            app.FluorescenceSignalLabel = uilabel(app.OMZer_Vm_Phase_UIFigure);
            app.FluorescenceSignalLabel.Text = 'Fluorescence Signal';
            % Show the figure after all components are created
            app.OMZer_Vm_Phase_UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OMZer_Vm_Phase(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.OMZer_Vm_Phase_UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.OMZer_Vm_Phase_UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OMZer_Vm_Phase_UIFigure)
        end
    end
end